classdef CustomerRelevantProperties < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainScreenCRP                  matlab.ui.Figure
        Step2CustomerRelevantPropertiesofAutonomousVehicleConceptsLabel  matlab.ui.control.Label
        TUMlogo                        matlab.ui.control.Image
        MainMenuPanel                  matlab.ui.container.Panel
        DataOptionsPanel               matlab.ui.container.Panel
        LoadButton                     matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        ManipulateButton               matlab.ui.control.StateButton
        ProceedtoTechnicalPropertiesButton  matlab.ui.control.Button
        VehicleConceptsSelectionPanel  matlab.ui.container.Panel
        CheckBox1                      matlab.ui.control.CheckBox
        VCLamp1                        matlab.ui.control.Lamp
        VehicleConcept1Label           matlab.ui.control.Label
        CheckBox2                      matlab.ui.control.CheckBox
        VCLamp2                        matlab.ui.control.Lamp
        VehicleConcept2Label           matlab.ui.control.Label
        CheckBox3                      matlab.ui.control.CheckBox
        VCLamp3                        matlab.ui.control.Lamp
        VehicleConcept3Label           matlab.ui.control.Label
        CheckBox4                      matlab.ui.control.CheckBox
        VCLamp4                        matlab.ui.control.Lamp
        VehicleConcept4Label           matlab.ui.control.Label
        CheckBox5                      matlab.ui.control.CheckBox
        VCLamp5                        matlab.ui.control.Lamp
        VehicleConcept5Label           matlab.ui.control.Label
        CheckBox6                      matlab.ui.control.CheckBox
        VCLamp6                        matlab.ui.control.Lamp
        VehicleConcept6Label           matlab.ui.control.Label
        CheckBox7                      matlab.ui.control.CheckBox
        VCLamp7                        matlab.ui.control.Lamp
        VehicleConcept7Label           matlab.ui.control.Label
        CheckBox8                      matlab.ui.control.CheckBox
        VCLamp8                        matlab.ui.control.Lamp
        VehicleConcept8Label           matlab.ui.control.Label
        CheckBox9                      matlab.ui.control.CheckBox
        VCLamp9                        matlab.ui.control.Lamp
        VehicleConcept9Label           matlab.ui.control.Label
        CheckBox10                     matlab.ui.control.CheckBox
        VCLamp10                       matlab.ui.control.Lamp
        VehicleConcept10Label          matlab.ui.control.Label
        CheckBox11                     matlab.ui.control.CheckBox
        VCLamp11                       matlab.ui.control.Lamp
        VehicleConcept11Label          matlab.ui.control.Label
        CheckBox12                     matlab.ui.control.CheckBox
        VCLamp12                       matlab.ui.control.Lamp
        VehicleConcept12Label          matlab.ui.control.Label
        CheckBox13                     matlab.ui.control.CheckBox
        VCLamp13                       matlab.ui.control.Lamp
        VehicleConcept13Label          matlab.ui.control.Label
        CheckBox14                     matlab.ui.control.CheckBox
        VCLamp14                       matlab.ui.control.Lamp
        VehicleConcept14Label          matlab.ui.control.Label
        TickAllButton                  matlab.ui.control.Button
        UpdateButton                   matlab.ui.control.Button
        DisplayOptionsPanel            matlab.ui.container.Panel
        DerivativeVCButton             matlab.ui.control.Button
        AllVCGraphsButton              matlab.ui.control.Button
        AllVCGraphsPanel               matlab.ui.container.Panel
        DynamicsGraphPanel             matlab.ui.container.Panel
        DynamicsGraph                  matlab.ui.control.UIAxes
        ErgonomicsGraphPanel           matlab.ui.container.Panel
        ErgonomicsGraph                matlab.ui.control.UIAxes
        SecurityGraphPanel             matlab.ui.container.Panel
        SecurityGraph                  matlab.ui.control.UIAxes
        UsabilityGraphPanel            matlab.ui.container.Panel
        UsabilityGraph                 matlab.ui.control.UIAxes
        DrivingStyleGraphPanel         matlab.ui.container.Panel
        DrivingStyleGraph              matlab.ui.control.UIAxes
        LuxuryGraphPanel               matlab.ui.container.Panel
        LuxuryGraph                    matlab.ui.control.UIAxes
        HomeButton                     matlab.ui.control.Button
        DerivativeVCPanel              matlab.ui.container.Panel
        VehicleConceptsGraphsPanel     matlab.ui.container.Panel
        GotoParametersButton           matlab.ui.control.Button
        VehicleConceptsGraph           matlab.ui.control.UIAxes
        DerivativesPanel               matlab.ui.container.Panel
        ButtonGroupDerivatives         matlab.ui.container.ButtonGroup
        SecondaryActivitiesButton      matlab.ui.control.ToggleButton
        CharacterMobilityButton        matlab.ui.control.ToggleButton
        DerivativesGraph               matlab.ui.control.UIAxes
        ButtonGroupVC                  matlab.ui.container.ButtonGroup
        DynamicsButton                 matlab.ui.control.ToggleButton
        ErgonomicsButton               matlab.ui.control.ToggleButton
        SecurityButton                 matlab.ui.control.ToggleButton
        UsabilityButton                matlab.ui.control.ToggleButton
        DrivingStyleButton             matlab.ui.control.ToggleButton
        LuxuryButton                   matlab.ui.control.ToggleButton
        VehicleConceptsParameterPanel  matlab.ui.container.Panel
        VehicleConceptLabel            matlab.ui.control.Label
        VehicleConceptDropDown         matlab.ui.control.DropDown
        DynamicsPanel                  matlab.ui.container.Panel
        AxialDynamicsSpinner           matlab.ui.control.Spinner
        AxialDynamicsSpinnerLabel      matlab.ui.control.Label
        LateralDynamicsSpinner         matlab.ui.control.Spinner
        LateralDynamicsSpinnerLabel    matlab.ui.control.Label
        VerticalDynamicsSpinner        matlab.ui.control.Spinner
        VerticalDynamicsSpinnerLabel   matlab.ui.control.Label
        ManeuverabilitySpinner         matlab.ui.control.Spinner
        ManeuverabilitySpinnerLabel    matlab.ui.control.Label
        ErgonomicsPanel                matlab.ui.container.Panel
        BoardingComfortSpinner         matlab.ui.control.Spinner
        BoardingComfortSpinnerLabel    matlab.ui.control.Label
        BoardingTimeSpinner            matlab.ui.control.Spinner
        BoardingTimeSpinnerLabel       matlab.ui.control.Label
        LegRoomSpinner                 matlab.ui.control.Spinner
        LegRoomSpinnerLabel            matlab.ui.control.Label
        ShouldersRoomSpinner           matlab.ui.control.Spinner
        ShouldersRoomSpinnerLabel      matlab.ui.control.Label
        HeadRoomSpinner                matlab.ui.control.Spinner
        HeadRoomSpinnerLabel           matlab.ui.control.Label
        InteriorAcousticsSpinner       matlab.ui.control.Spinner
        InteriorAcousticsSpinnerLabel  matlab.ui.control.Label
        SecurityPanel                  matlab.ui.container.Panel
        ExternalCommunicationSpinner   matlab.ui.control.Spinner
        ExternalCommunicationSpinnerLabel  matlab.ui.control.Label
        InteriorRecognitionSpinner     matlab.ui.control.Spinner
        InteriorRecognitionSpinnerLabel  matlab.ui.control.Label
        PassiveSafetySpinner           matlab.ui.control.Spinner
        PassiveSafetySpinnerLabel      matlab.ui.control.Label
        QualityofAutomationSpinner     matlab.ui.control.Spinner
        QualityofAutomationLabel       matlab.ui.control.Label
        ActiveSafetySpinner            matlab.ui.control.Spinner
        ActiveSafetySpinnerLabel       matlab.ui.control.Label
        UsabilityPanel                 matlab.ui.container.Panel
        BadRoadCapabilitySpinner       matlab.ui.control.Spinner
        BadRoadCapabilitySpinner_2Label  matlab.ui.control.Label
        LuggageSpaceSpinner            matlab.ui.control.Spinner
        LuggageSpaceSpinnerLabel       matlab.ui.control.Label
        RadiusofMovementSpinner        matlab.ui.control.Spinner
        RadiusofMovementSpinnerLabel   matlab.ui.control.Label
        CostsSpinner                   matlab.ui.control.Spinner
        CostsSpinnerLabel              matlab.ui.control.Label
        EcologySpinner                 matlab.ui.control.Spinner
        EcologySpinnerLabel            matlab.ui.control.Label
        PassengersSpinner              matlab.ui.control.Spinner
        PassengersSpinnerLabel         matlab.ui.control.Label
        DrivingStylePanel              matlab.ui.container.Panel
        DSComfortSpinner               matlab.ui.control.Spinner
        DSComfortSpinnerLabel          matlab.ui.control.Label
        DSSafetySpinner                matlab.ui.control.Spinner
        DSSafetySpinnerLabel           matlab.ui.control.Label
        DSTimePotentialSpinner         matlab.ui.control.Spinner
        DSTimePotentialSpinnerLabel    matlab.ui.control.Label
        DSConsumptionSpinner           matlab.ui.control.Spinner
        DSConsumptionSpinnerLabel      matlab.ui.control.Label
        GotoGraphsButton               matlab.ui.control.Button
        LuxuryPanel                    matlab.ui.container.Panel
        BuiltInInfotainmentSpinner     matlab.ui.control.Spinner
        BuiltInInfotainmentSpinnerLabel  matlab.ui.control.Label
        InfoIndividualizationSpinner   matlab.ui.control.Spinner
        InfoIndividualizationSpinnerLabel  matlab.ui.control.Label
        ExteriorDesignSpinner          matlab.ui.control.Spinner
        ExteriorDesignSpinnerLabel     matlab.ui.control.Label
    end

    %% General Information:
    
    % Designed by David Fischer, Ferdinand Schockenhoff (FTM, Techical University of Munich)
    %---------------------------------------
    % Created on July 2021
    % Last update: 2021-08-20
    %---------------------------------------
    % Version: Matlab2020b
    %---------------------------------------
    % Description: GUI of the process step "Customer-Relevant Properties"
    %---------------------------------------
    % Input: 'derivativesCRP','derivativeSuggestionCRP'
    %---------------------------------------
    % Output: TBD
    % Start of the app TechnicalProperties
    
    properties (Access = private)
        oldpath = addpath('99_General_Elements\') % Needed for first build up of app (TUM image)
        
        %imported input data from vehicle provision app
        vehicleConceptsCRP;             %Customer relevant properties for each vehicle concept
        vehicleConceptsCRPBackup;       %Backup
        vehicleConceptsCRPCustom;       %Customer relevant properties for vehcile concpet customized by the appuser
        derivativeSuggestion;           %derivative to satisfy the users' needs
        derivativeTypes;                %Types of the Derivatives
        ColorDerivatives = [[249 186 0]./255; [0 124 48]./255;  [105 8 90]./255; [227 114 34]./255; [162 173 0]./255; [152 198 234]./255; [218 215 203]./255;...
                 [255 220 0]./255; [103 154 29]./255; [156 13 22]./255; [214 76 19]./255; [50 119 138]./255; [100 160 200]./255; [15 27 9]./255;]; %TUM Colors for Derivatives/ Vehicle Concepts
        
        %Used parameters
        oldVCNumber = 1;
    end
    
    methods (Access = private)
        %__________________________________________________________________
        %Evaluating the color of the lamps
        function colorEvaluator(app,number,LampName,checkboxName)
            value = app.(checkboxName).Value;
            derivativesCol = app.ColorDerivatives;
            if value == true
                app.(LampName).Color = derivativesCol(number,:);
            else
                app.(LampName).Color = 'white';
            end
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Determinates the visible Vehicles for main view
        function checkBoxesEnabler(app,lampname,labelname,checkboxname,maxfields)
            %Enable the user check boxes
            for i=1:maxfields
                fieldName = sprintf(lampname,i);
                app.(fieldName).Visible = 'on';
                labelName = sprintf(labelname,i);
                app.(labelName).Visible = 'on';
                checkBoxName = sprintf(checkboxname,i);
                app.(checkBoxName).Visible = 'on';
                app.(checkBoxName).Enable = 'on';
            end
            if maxfields < 14
                extendedNumber = maxfields + 1;
                for k=extendedNumber:14
                    fieldName = sprintf(lampname,k);
                    app.(fieldName).Visible = 'off';
                    labelName = sprintf(labelname,k);
                    app.(labelName).Visible = 'off';
                    checkBoxName = sprintf(checkboxname,k);
                    app.(checkBoxName).Visible = 'off';
                    app.(checkBoxName).Enable = 'off';
                end
            end
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Determinates a matrix with the ticked Vehicles for view
        function [tickMatrixVehicles] = checkBoxChecker(app)
            %Check the ticked Vehicles
            [vehicleNumber,~] = size(app.vehicleConceptsCRP);
            tickMatrixVehicles = zeros(vehicleNumber,1);
            for i=1:vehicleNumber
                checkBoxName = sprintf('CheckBox%d',i);
                if app.(checkBoxName).Value == true
                    tickMatrixVehicles(i) = 1;
                else
                    tickMatrixVehicles(i) = 0;
                end
            end
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Setting up the data for derivative graphs
        function [dataSec,lblSec,dataCharMob,lblCharMob,figure,leg] = derivativeGraphData(app,tickMatrixVehicles)
            [numberDerivatives,~] = size(app.derivativeSuggestion);
            
            %%Data for Secondary Activities Derivatives
            dataSecD = zeros(nnz(tickMatrixVehicles),9);
            flag = 1;
            for k=1:numberDerivatives
                if tickMatrixVehicles(k) == 1
                    dataSecD(flag,1:6) = app.derivativeSuggestion(k,2:7);
                    flag = flag + 1;
                end
            end
            dataSecD(:,7:9) = [dataSecD(:,6),dataSecD(:,6),dataSecD(:,6)]; %Adding the passengers for the view compatibility with UM
            dataSec = dataSecD';
            
            %%Label for Secondary Activities
            lblSec = {'Self-Driving'; 'Relax'; 'Work'; 'Sleep'; 'Load'; 'Passengers for Self-Driving'; sprintf('Passengers \nfor Relax'); ...
                sprintf('Passengers \nfor Work'); sprintf('Passengers \nfor Sleep')};
            
            %%Data for Character and Mobility Derivatives
            dataCharMobD = zeros(nnz(tickMatrixVehicles),6);
            flag2 = 1;
            for k=1:numberDerivatives
                if tickMatrixVehicles(k) == 1
                    dataCharMobD(flag2,:) = app.derivativeSuggestion(k,11:16);
                    flag2 = flag2 + 1;
                end
            end
            dataCharMob = dataCharMobD';

            %%Label for Character/Mobility
            lblCharMob = {sprintf('Willingness to Invest'); sprintf('Need for \n   Security/Safety'); 'DP Urban'; 'DP Rural'; 'DP Highway'; 'DP General Need'};
            
            %%Figure
            figure = app.DerivativesGraph;
            
            %%Legend entries
            numberTickedElements = nnz(tickMatrixVehicles);
            leg = cell(1, numberTickedElements);
            typeNames = app.derivativeTypes;
            flag3 = 1;
            for i=1:numberDerivatives
                if tickMatrixVehicles(i) == 1
                    typeName = typeNames(1,i);
                    leg{flag3}=sprintf('Derivative %d \n(%s)',i,typeName);
                    flag3 = flag3 + 1;
                end
            end
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Setting up the data for Vehicle Concept graphs
        function [data,lbl,figure,leg] = VCGraphData(app,tickMatrixVehicles,VCGraphType)
            [numberVC,~] = size(app.vehicleConceptsCRP);
            
            %%Data for Vehicle Concept
            switch VCGraphType
                case 'Dynamics'
                    [dataTemp] = getVCCRP(app,tickMatrixVehicles,1,4);
                case 'Ergonomics'
                    [dataTemp] = getVCCRP(app,tickMatrixVehicles,5,6);
                case 'Security'
                    [dataTemp] = getVCCRP(app,tickMatrixVehicles,11,5);
                case 'Usability'
                    [dataTemp] = getVCCRP(app,tickMatrixVehicles,16,6);
                case 'DrivingStyle'
                    [dataTemp] = getVCCRP(app,tickMatrixVehicles,22,4);
                case 'Luxury'
                    [dataTemp] = getVCCRP(app,tickMatrixVehicles,26,3);
            end
            data = dataTemp';
            
            %%Label for Secondary Activities
            switch VCGraphType
                case 'Dynamics'
                    lbl = {'Axial Dynamics';'Lateral Dynamics';'Vertical Dynamics';'Maneuverability'};
                case 'Ergonomics'
                    lbl = {'Boarding Comfort';'Boarding Time';'Leg Room';'Shoulders Room';'Head Room';'Interior Acoustics'};
                case 'Security'
                    lbl = {'Passive Safety';sprintf('External \nCommunication');sprintf('Interior \nRecognition');'Quality of Automation';...
                        'Active Safety'};
                case 'Usability'
                    lbl = {'Bad Road Capability';'Luggage Space';'Radius of Movement';'Costs';'Ecology';'Passengers'};
                case 'DrivingStyle'
                    lbl = {'DS Comfort';'DS Safety';'DS Time Potential';'DS Consumption'};
                case 'Luxury'
                    lbl = {'Built-In Infotainment';'Info Individualization';'Exterior Design'};
            end
            
            %%Figure
            figure = app.VehicleConceptsGraph;
            
            %%Legend entries
            numberTickedElements = nnz(tickMatrixVehicles);
            leg = cell(1, numberTickedElements);
            flag3 = 1;
            for i=1:numberVC
                if tickMatrixVehicles(i) == 1
                    leg{flag3}=sprintf('Vehicle %d',i);
                    flag3 = flag3 + 1;
                end
            end
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Shortcall to get the wanted data from VehicleConceptsCRP
        function [dataTemp] = getVCCRP(app,tickMatrixVehicles,startColumn,columnSize)
            [numberVC,~] = size(app.vehicleConceptsCRP);
            dataTemp = zeros(nnz(tickMatrixVehicles),columnSize);
            flag = 1;
            for k=1:numberVC
                if tickMatrixVehicles(k) == 1
                    dataTemp(flag,1:columnSize) = app.vehicleConceptsCRP(k,startColumn:startColumn+columnSize-1);
                    flag = flag + 1;
                end
            end
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Changes the fields in the DropDown Menu
        function dropdownSizeChanger(app,tickMatrixVehicles)
            [numberVC,~] = size(app.vehicleConceptsCRP);
            if numberVC == 0
                itemVector = '----';
            else
                itemVector = num2cell(zeros(nnz(tickMatrixVehicles),1));
            end
            
            flag = 1;
            for k=1:numberVC
                if tickMatrixVehicles(k) == 1
                    itemVector{flag} = sprintf('Vehicle %d',k);
                    flag = flag + 1;
                end
            end
            
            app.VehicleConceptDropDown.Items = itemVector;
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Fills parameters of spinners from VCCRPs
        function parameterFillUp(app)
            %Generate matrix with names of edit fields
            spinnerLabels = {'AxialDynamicsSpinner';'LateralDynamicsSpinner';'VerticalDynamicsSpinner';'ManeuverabilitySpinner';...
                'BoardingComfortSpinner';'BoardingTimeSpinner';'LegRoomSpinner';'ShouldersRoomSpinner';'HeadRoomSpinner';'InteriorAcousticsSpinner';...
                'PassiveSafetySpinner';'ExternalCommunicationSpinner';'InteriorRecognitionSpinner';'QualityofAutomationSpinner';'ActiveSafetySpinner';...
                'BadRoadCapabilitySpinner';'LuggageSpaceSpinner';'RadiusofMovementSpinner';'CostsSpinner';'EcologySpinner';'PassengersSpinner';...
                'DSComfortSpinner';'DSSafetySpinner';'DSTimePotentialSpinner';'DSConsumptionSpinner';...
                'BuiltInInfotainmentSpinner';'InfoIndividualizationSpinner';'ExteriorDesignSpinner'};
            
            %Get the number of the DropDown Field
            vehicleChoice = app.VehicleConceptDropDown.Value;
            if size(vehicleChoice,2) == 9
                vehicleNumber = str2double(vehicleChoice(1,9));
            elseif size(vehicleChoice,2) == 10
                vehicleNumber = str2double(vehicleChoice(1,9:10));
            else
                vehicleNumber = 0;
            end
            app.oldVCNumber = vehicleNumber;
            
            %Fill up the Spinners
            for k=1:28
                field = string(spinnerLabels(k));
                myValue = app.vehicleConceptsCRP(vehicleNumber,k);
                app.(field).Value = myValue;
            end
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Fills parameters of spinners from VCCRPs
        function parameterSave(app)
            %Generate matrix with names of edit fields
            spinnerLabels = {'AxialDynamicsSpinner';'LateralDynamicsSpinner';'VerticalDynamicsSpinner';'ManeuverabilitySpinner';...
                'BoardingComfortSpinner';'BoardingTimeSpinner';'LegRoomSpinner';'ShouldersRoomSpinner';'HeadRoomSpinner';'InteriorAcousticsSpinner';...
                'PassiveSafetySpinner';'ExternalCommunicationSpinner';'InteriorRecognitionSpinner';'QualityofAutomationSpinner';'ActiveSafetySpinner';...
                'BadRoadCapabilitySpinner';'LuggageSpaceSpinner';'RadiusofMovementSpinner';'CostsSpinner';'EcologySpinner';'PassengersSpinner';...
                'DSComfortSpinner';'DSSafetySpinner';'DSTimePotentialSpinner';'DSConsumptionSpinner';...
                'BuiltInInfotainmentSpinner';'InfoIndividualizationSpinner';'ExteriorDesignSpinner'};
            
            vehicleNumber = app.oldVCNumber;
            
            %Save the typed in parameters into the selected VC
            for k=1:28
                field = string(spinnerLabels(k));
                app.vehicleConceptsCRP(vehicleNumber,k) = app.(field).Value;
            end
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Draws the vehicle concept spider
        function drawVehicleConceptSpider(app)
            [tickMatrixVehicles] = checkBoxChecker(app);
            placement = [-1.3,1.3;-1.3,1.3];
            %%Update the Vehicle Concept View
            switch app.ButtonGroupVC.SelectedObject
                case app.DynamicsButton
                    [data,lbl,figureVC,legVC] = VCGraphData(app,tickMatrixVehicles,'Dynamics');
                case app.ErgonomicsButton
                    [data,lbl,figureVC,legVC] = VCGraphData(app,tickMatrixVehicles,'Ergonomics');
                case app.SecurityButton
                    [data,lbl,figureVC,legVC] = VCGraphData(app,tickMatrixVehicles,'Security');
                case app.UsabilityButton
                    [data,lbl,figureVC,legVC] = VCGraphData(app,tickMatrixVehicles,'Usability');
                case app.DrivingStyleButton
                    [data,lbl,figureVC,legVC] = VCGraphData(app,tickMatrixVehicles,'DrivingStyle');
                case app.LuxuryButton
                    [data,lbl,figureVC,legVC] = VCGraphData(app,tickMatrixVehicles,'Luxury');
            end
            plotSpiderGraph(app,data,lbl,figureVC,legVC,16,10,placement,0,0,tickMatrixVehicles,1)
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Plot function for spider graph
        function plotSpiderGraph(app,data,lbl,ax,leg,fontsize,fts_tm,placement,colorcode,dashtype,tickMatrixVehicles,additionalMark)
            % size segments and number of cases
            [r,s] = size(data);
            
            min_max_rng = [0 10];
            rng = repmat (min_max_rng, r, 1);
            
            %Clean up the initial plot
            legend(ax,'off'); reset(ax); cla(ax); hold(ax,'on'); ax.BackgroundColor = [1,1,1];
            
            % set to add plot
            set(ax,'nextplot','add');
            
            % clear figure and set limits
            set(ax,'visible','off'); set(ax,'color','w');        %makes 2D plot invisible and sets background color white
            set(ax,'xlim',placement(1,:),'ylim',placement(2,:)); axis(ax,'equal','manual') %Defines size of the plot
            
            ax.Visible = 'off'; set(ax,'color','w');
            
            % scale by range
            angw = linspace(0,2*pi,r+1)';
            % wrap data (close the last axis to the first)
            ang = angw(1:end-1); 
            angs = [ang(:); ang(1)];
            
            % make the plot
            % define the axis locations
            start_ax = [zeros(1,r)+0.5; sin(ang')+0.5];
            stop_ax = [zeros(1,r); cos(ang')];
            
            % Transformation of data
            start = [zeros(1,r); sin(ang')];
            A_xaxis = data .* start(2,:)';
            A_xaxis = [A_xaxis; A_xaxis(1,:)];
            A_xaxis = A_xaxis *(1/10)+0.5;
            
            stop = [zeros(1,r); cos(ang')];
            A_yaxis = data .* stop(2,:)';
            A_yaxis = [A_yaxis; A_yaxis(1,:)];
            A_yaxis = A_yaxis *(1/10);
            
            % plot the axes
            plot(ax,start_ax,stop_ax,'color','k','linestyle','-','linewidth', 1); hold(ax, 'on'); axis equal; % open figure...
            
            % plot axes markers
            inc = (1/5):(1/5):1; 
            tx = 0.05 * ones(1,5);
            
            %fontsize tick marks
            fonttm = fts_tm;
            
            %fontsize legend
            fontleg = fontsize-4;
            % loop each axis ang plot the line markers and labels
            % add axes
            for ii = 1:r
            	% plot tick lines
                plot(ax,[sin(angs(ii)) * inc; sin(angs(ii+1)) * inc] +0.5, ...
            		[(cos(angs(ii)) * inc); cos(angs(ii+1)) * inc],'color','k','linewidth', 0.75);
                
            	% label the tick marks
            	for jj = 1:5
                    temp = text(ax,sin(angs(ii)) * inc(jj) + cos(angs(ii)) * tx(jj)+0.5, ...
            			cos(angs(ii)) * inc(jj) - sin(angs(ii)) * tx(jj), ...
            			num2str(rd(app,rng(ii,1) + inc(jj)*diff(rng(ii,:)),-2)), ...
            			'fontsize',fonttm, 'FontName', 'Arial');
            		% flip the text alignment for lower axes
            		if angs(ii) >= pi
            			set(temp,'HorizontalAlignment','right');
            		end
            	end
            	% label each axis
            	temp = text(ax,sin(angs(ii)) * 1.1 + cos(angs(ii)) * 0+0.5, cos(angs(ii)) * 1.1 - sin(angs(ii)) * 0, ...
        			char(lbl(ii,:)),'fontsize',fontsize, 'FontName', 'Arial');
            	% flip the text alignment for right side axes
            	if angs(ii) >= pi
            		set(temp,'HorizontalAlignment','right');
            	end
            end
            
            %Draw additional custom lines
            if additionalMark == 1
                % Transformation of data
                dataMyLine = ones(1,s)*4;
                A_xaxis2 = dataMyLine .* start(2,:)';
                A_xaxis2 = [A_xaxis2; A_xaxis2(1,:)];
                A_xaxis2 = A_xaxis2 *(1/10)+0.5;
                A_yaxis2 = dataMyLine .* stop(2,:)';
                A_yaxis2 = [A_yaxis2; A_yaxis2(1,:)];
                A_yaxis2 = A_yaxis2 *(1/10);
                
                % Draw the line
                plot(ax,A_xaxis2,A_yaxis2,"Color",'red',"LineWidth",1.5)
            end
            
            %plot the data
            o = plot(ax,A_xaxis,A_yaxis);
            
            % define data case colors
            derivativesCol = app.ColorDerivatives;
            
            % define data case linestyles
            if dashtype == 0
                vehiclesLinsty = {'--'; '--'; '--'; '--'; '--'; '--';'--'; '--'; '--'; '--'; '--'; '--';'--'; '--'};
            elseif dashtype == 1
                vehiclesLinsty = {'-.';'-.';'-.';'-.';'-.';'-.';'-.';'-.';'-.';'-.';'-.';'-.';'-.';'-.'};
            end
            
            % set color
            switch colorcode
                %Derivatives and Vehicle Concepts
                case 0
                    %Creating vector with numbers of ticked vehicles
                    [numberDerivatives,~] = size(app.derivativeSuggestion);
                    tickedDerivatives = zeros(nnz(tickMatrixVehicles),1);
                    flag = 1;
                    for i=1:numberDerivatives
                        if tickMatrixVehicles(i) == 1
                            tickedDerivatives(flag) = i;
                            flag = flag + 1;
                        end
                    end
                    %Setting the color accordingly
                    flag2 = 1;
                    for ii=1:nnz(tickMatrixVehicles)
                        set(o(ii),'color',derivativesCol(tickedDerivatives(flag2),:), ...
                            'LineWidth', 3, 'LineStyle', vehiclesLinsty{tickedDerivatives(flag2)});
                        flag2 = flag2 + 1;
                    end
            end
            
            legend(o, leg,'Location','southoutside','Orientation',"horizontal",'NumColumns',4,'FontName', 'Arial', 'fontsize',fontleg);
            hold on
            close
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %quick round function (to specified decimal)
        function [v] = rd(~,v,dec)
            if nargin == 1; dec = 0; end
            
            v = v / 10^dec;
            v = round(v);
            v = v * 10^dec;
        end
        %__________________________________________________________________
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, derivativesCRP, derivativeSuggestionCRP, typeVector)
            %Adding necessary folders to path
            addpath('99_General_Elements\')
            addpath(genpath('05_Customer-relevant Properties\'))
            addpath(genpath('06_Simulation_CRP2TP\'))           
            addpath(genpath('07_Technical Properties\'))
            
            %Check if data in the workspace is available
            dataExistCheck = exist('derivativeSuggestionCRP',"var");
            if dataExistCheck == 0
                uiconfirm(app.MainScreenCRP,'App was loaded with no dataset. Please load manually a predefined dataset with the "Load" Button',...
                    'No dataset chosen','Options',{'Ok'},'Icon','warning');
                app.DerivativeVCPanel.Enable = 'off';
                app.ManipulateButton.Enable = 'off';
                app.ResetButton.Enable = 'off';
                app.DisplayOptionsPanel.Enable = 'off';
                app.VehicleConceptsSelectionPanel.Enable = 'off';
                app.ProceedtoTechnicalPropertiesButton.Enable = 'off';
            else
                %Defining the input parameters
                app.vehicleConceptsCRP = derivativesCRP;
                app.derivativeSuggestion = derivativeSuggestionCRP;
                app.derivativeTypes = typeVector;
                app.CheckBox1.Value = true;
                app.VCLamp1.Color = "#EFD4B4";
                UpdateButtonPushed(app)
            end
            
            %Making a Backup for input data
            app.vehicleConceptsCRPBackup = app.vehicleConceptsCRP;
            
            %Setting up the main view
            [vehicleNumber,~] = size(app.vehicleConceptsCRP);
            checkBoxesEnabler(app,'VCLamp%d','VehicleConcept%dLabel','CheckBox%d',vehicleNumber)
            app.DynamicsPanel.Enable = 'off';
            app.ErgonomicsPanel.Enable = 'off';
            app.SecurityPanel.Enable = 'off';
            app.UsabilityPanel.Enable = 'off';
            app.DrivingStylePanel.Enable = 'off';
            app.LuxuryPanel.Enable = 'off';
            app.SaveButton.Enable = 'off';
            app.VehicleConceptsParameterPanel.Visible = 'off';
            app.VehicleConceptsGraphsPanel.Visible = 'on';
            app.DerivativeVCPanel.Visible = 'on';
            app.AllVCGraphsPanel.Visible = 'off';
            
            %Opening the window maximized
            drawnow;
            app.MainScreenCRP.WindowState = 'maximized';
        end

        % Value changed function: CheckBox1, CheckBox10, 
        % CheckBox11, CheckBox12, CheckBox13, CheckBox14, 
        % CheckBox2, CheckBox3, CheckBox4, CheckBox5, CheckBox6, 
        % CheckBox7, CheckBox8, CheckBox9
        function CheckBox1ValueChanged(app, event)
            [vehicleNumber,~] = size(app.vehicleConceptsCRP);
            for i=1:vehicleNumber
                lampName = sprintf('VCLamp%d',i);
                checkboxName = sprintf('CheckBox%d',i);
                colorEvaluator(app,i,lampName,checkboxName)
            end
        end

        % Selection changed function: ButtonGroupDerivatives
        function ButtonGroupDerivativesSelectionChanged(app, event)
            [tickMatrixVehicles] = checkBoxChecker(app);
            
            %%Update the derivative View
            [dataSec,lblSec,dataCharMob,lblCharMob,figure,leg] = derivativeGraphData(app,tickMatrixVehicles);
            placement = [-1.3,1.3;-1.3,1.3];
            if app.SecondaryActivitiesButton.Value == true
                plotSpiderGraph(app,dataSec,lblSec,figure,leg,16,10,placement,0,1,tickMatrixVehicles,0)
            else
                plotSpiderGraph(app,dataCharMob,lblCharMob,figure,leg,16,10,placement,0,1,tickMatrixVehicles,0)
            end
        end

        % Selection changed function: ButtonGroupVC
        function ButtonGroupVCSelectionChanged(app, event)
            drawVehicleConceptSpider(app)
        end

        % Value changed function: VehicleConceptDropDown
        function VehicleConceptDropDownValueChanged(app, event)
            if app.ManipulateButton.Value == false
            	parameterFillUp(app)
            else
                parameterSave(app)
                parameterFillUp(app)
            end
        end

        % Value changed function: ManipulateButton
        function ManipulateButtonValueChanged(app, event)
            % Enable the possibilty to manipulate the values of the properties
            if app.ManipulateButton.Value == false
                fig = app.MainScreenCRP;
                selection = uiconfirm(fig,['Do you really want to end the manipulation mode? This will delete all your made Parameter changes.' ...
                    ' Consider saving your manipulated Parameters before.'],'Confirm manipulation mode change','Icon','warning');
                tf = strcmp(selection,'OK');
                if tf == true
                    app.DynamicsPanel.Enable = 'off';
                    app.ErgonomicsPanel.Enable = 'off';
                    app.SecurityPanel.Enable = 'off';
                    app.UsabilityPanel.Enable = 'off';
                    app.DrivingStylePanel.Enable = 'off';
                    app.LuxuryPanel.Enable = 'off';
                    app.SaveButton.Enable = 'off';
                    
                    app.vehicleConceptsCRP = app.vehicleConceptsCRPBackup;
                    [tickMatrixVehicles] = checkBoxChecker(app);
                    if nnz(tickMatrixVehicles) ~= 0
                        parameterFillUp(app)
                        UpdateButtonPushed(app, event)
                    end
                else
                    app.ManipulateButton.Value = true;
                end
            else
                %Enable the view
                app.VehicleConceptsParameterPanel.Visible = 'on';
                app.VehicleConceptsGraphsPanel.Visible = 'off';
                app.ButtonGroupVC.Visible = 'off';
                app.DerivativeVCPanel.Visible = 'on';
                app.AllVCGraphsPanel.Visible = 'off';
                
                %Enable the panels
                app.DynamicsPanel.Enable = 'on';
                app.ErgonomicsPanel.Enable = 'on';
                app.SecurityPanel.Enable = 'on';
                app.UsabilityPanel.Enable = 'on';
                app.DrivingStylePanel.Enable = 'on';
                app.LuxuryPanel.Enable = 'on';
                app.SaveButton.Enable = 'on';
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %Open the dialog box to save .mat file
            filename = uiputfile('*.mat','myScenario_CRP','05_Customer-relevant Properties\CRP_Saved_Scenarios\');
            if filename ~= 0
                parameterSave(app)
                derivativesCRP = app.vehicleConceptsCRP;
                derivativeSuggestionCRP = app.derivativeSuggestion;
                typeVector = app.derivativeTypes;
                saveName = append('05_Customer-relevant Properties\CRP_Saved_Scenarios\',filename);
                save(saveName,'derivativesCRP','derivativeSuggestionCRP','typeVector')
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            %Open the dialog box to choose .mat file
            filename = uigetfile('05_Customer-relevant Properties\CRP_Saved_Scenarios\','*.mat');
            if filename ~= 0
                load(filename,'derivativesCRP','derivativeSuggestionCRP','typeVector');
                
                %Filling the loaded data into parameter edit fields
                app.vehicleConceptsCRP = derivativesCRP;
                app.derivativeSuggestion = derivativeSuggestionCRP;
                app.derivativeTypes = typeVector;
                
                %Making a Backup for input data
                app.vehicleConceptsCRPBackup = app.vehicleConceptsCRP;
                
                %Enabling the view in case of non-parameter loaded start
                app.DerivativeVCPanel.Enable = 'on';
                app.ManipulateButton.Enable = 'on';
                app.ResetButton.Enable = 'on';
                app.DisplayOptionsPanel.Enable = 'on';
                app.VehicleConceptsSelectionPanel.Enable = 'on';
                app.ProceedtoTechnicalPropertiesButton.Enable = 'on';
                
                %Resetting view
                [vehicleNumber,~] = size(app.vehicleConceptsCRP);
                checkBoxesEnabler(app,'VCLamp%d','VehicleConcept%dLabel','CheckBox%d',vehicleNumber)
                app.DynamicsPanel.Enable = 'off';
                app.ErgonomicsPanel.Enable = 'off';
                app.SecurityPanel.Enable = 'off';
                app.UsabilityPanel.Enable = 'off';
                app.DrivingStylePanel.Enable = 'off';
                app.SaveButton.Enable = 'off';
                app.ManipulateButton.Value = false;
                
                %Untick all
                [vehicleNumber,~] = size(app.vehicleConceptsCRP);
                for i=1:vehicleNumber
                    boxName = sprintf('CheckBox%d',i);
                    app.(boxName).Value = false;
                    lampName = sprintf('VCLamp%d',i);
                    colorEvaluator(app,i,lampName,boxName)
                end
                % Tick all
                TickallButtonPushed(app, event);
                % Update the view
                UpdateButtonPushed(app, event)
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %Open a confirmation dialog box
            fig = app.MainScreenCRP;
            selection = uiconfirm(fig,['Do you really want to reset all parameters? This will delete all your made parameter changes.' ...
                ' Consider saving your manipulated parameters before.'],'Confirm parameter reset','Icon','warning');
            tf = strcmp(selection,'OK');
            %Execute only when the user agrees
            if tf == true
                %Resetting view
                app.DynamicsPanel.Enable = 'off';
                app.ErgonomicsPanel.Enable = 'off';
                app.SecurityPanel.Enable = 'off';
                app.UsabilityPanel.Enable = 'off';
                app.DrivingStylePanel.Enable = 'off';
                app.LuxuryPanel.Enable = 'off';
                app.SaveButton.Enable = 'off';
                app.VehicleConceptsParameterPanel.Visible = 'off';
                app.VehicleConceptsGraphsPanel.Visible = 'on';
                app.ButtonGroupVC.SelectedObject = app.DynamicsButton;
                app.ButtonGroupDerivatives.SelectedObject = app.SecondaryActivitiesButton;
                app.DerivativeVCPanel.Visible = 'on';
                app.AllVCGraphsPanel.Visible = 'off';
                
                app.ManipulateButton.Value = false;
                app.vehicleConceptsCRP = app.vehicleConceptsCRPBackup;
                %Updating the view with vehicle concept 1
                [vehicleNumber,~] = size(app.vehicleConceptsCRP);
                app.CheckBox1.Value = true;
                app.VCLamp1.Color = "#EFD4B4";
                for i=2:vehicleNumber
                    boxName = sprintf('CheckBox%d',i);
                    app.(boxName).Value = false;
                    lampName = sprintf('VCLamp%d',i);
                    app.(lampName).Color = 'white';
                end
                UpdateButtonPushed(app, event)
            end
        end

        % Button pushed function: TickAllButton
        function TickallButtonPushed(app, event)
            % Tick all Vehicles
            [tickMatrixVehicles] = checkBoxChecker(app);
            nonTickedVehicles = nnz(~tickMatrixVehicles);
            if nonTickedVehicles==0
                [vehicleNumber,~] = size(app.vehicleConceptsCRP);
                for i=1:vehicleNumber
                    boxName = sprintf('CheckBox%d',i);
                    app.(boxName).Value = false;
                    lampName = sprintf('VCLamp%d',i);
                    colorEvaluator(app,i,lampName,boxName)
                end
            else
                [vehicleNumber,~] = size(app.vehicleConceptsCRP);
                for i=1:vehicleNumber
                    boxName = sprintf('CheckBox%d',i);
                    app.(boxName).Value = true;
                    lampName = sprintf('VCLamp%d',i);
                    colorEvaluator(app,i,lampName,boxName)
                end
            end
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            % Upate the visualizations to show the choosen vehilces
            if app.DerivativeVCPanel.Visible == true
                %Update the derivative View
                [tickMatrixVehicles] = checkBoxChecker(app);
                [dataSec,lblSec,dataCharMob,lblCharMob,figure,leg] = derivativeGraphData(app,tickMatrixVehicles);
                placement = [-1.3,1.3;-1.3,1.3];
                if app.SecondaryActivitiesButton.Value == true
                    plotSpiderGraph(app,dataSec,lblSec,figure,leg,16,10,placement,0,1,tickMatrixVehicles,0)
                else
                    plotSpiderGraph(app,dataCharMob,lblCharMob,figure,leg,16,10,placement,0,1,tickMatrixVehicles,0)
                end
                
                %%Update the visible parameters
                dropdownSizeChanger(app,tickMatrixVehicles)
                if nnz(tickMatrixVehicles) ~= 0
                    if app.ManipulateButton.Value == false
                    	parameterFillUp(app)
                    else
                        parameterSave(app)
                        parameterFillUp(app)
                    end
                end
                
                %Update the Vehicle Concept View
                drawVehicleConceptSpider(app)
            else
                if app.ManipulateButton.Value == true
                    parameterSave(app)
                end
                [tickMatrixVehicles] = checkBoxChecker(app);
                placement = [-1.1,1.1;-1.1,1.1];
                %Plot Dynamics
                [data,lbl,~,legVC] = VCGraphData(app,tickMatrixVehicles,'Dynamics');
                plotSpiderGraph(app,data,lbl,app.DynamicsGraph,legVC,12,9,placement,0,0,tickMatrixVehicles,1)
                %Plot Ergonomics
                [data,lbl,~,legVC] = VCGraphData(app,tickMatrixVehicles,'Ergonomics');
                plotSpiderGraph(app,data,lbl,app.ErgonomicsGraph,legVC,12,9,placement,0,0,tickMatrixVehicles,1)
                %Plot Security
                [data,lbl,~,legVC] = VCGraphData(app,tickMatrixVehicles,'Security');
                plotSpiderGraph(app,data,lbl,app.SecurityGraph,legVC,12,9,placement,0,0,tickMatrixVehicles,1)
                %Plot Usability
                [data,lbl,~,legVC] = VCGraphData(app,tickMatrixVehicles,'Usability');
                plotSpiderGraph(app,data,lbl,app.UsabilityGraph,legVC,12,9,placement,0,0,tickMatrixVehicles,1)
                %Plot Driving Style
                [data,lbl,~,legVC] = VCGraphData(app,tickMatrixVehicles,'DrivingStyle');
                plotSpiderGraph(app,data,lbl,app.DrivingStyleGraph,legVC,12,9,placement,0,0,tickMatrixVehicles,1)
                %Plot Luxury
                [data,lbl,~,legVC] = VCGraphData(app,tickMatrixVehicles,'Luxury');
                plotSpiderGraph(app,data,lbl,app.LuxuryGraph,legVC,12,9,placement,0,0,tickMatrixVehicles,1)
            end
            
        end

        % Button pushed function: GotoParametersButton
        function GotoParametersButtonPushed(app, event)
            %Select the panel
            app.VehicleConceptsParameterPanel.Visible = 'on';
            app.VehicleConceptsGraphsPanel.Visible = 'off';
            app.ButtonGroupVC.Visible = 'off';
        end

        % Button pushed function: GotoGraphsButton
        function GotoGraphsButtonPushed(app, event)
            if app.ManipulateButton.Value == true
                parameterSave(app)
            end
            
            %Update the Vehicle Concept View
            drawVehicleConceptSpider(app)
            
            %Select the panel
            app.VehicleConceptsParameterPanel.Visible = 'off';
            app.VehicleConceptsGraphsPanel.Visible = 'on';
            app.ButtonGroupVC.Visible = 'on';
        end

        % Button pushed function: DerivativeVCButton
        function DerivativeVCButtonPushed(app, event)
            % Change the view to show the VP and VC
            app.DerivativeVCPanel.Visible = 'on';
            app.AllVCGraphsPanel.Visible = 'off';
            UpdateButtonPushed(app, event)
        end

        % Button pushed function: AllVCGraphsButton
        function AllVCGraphsButtonPushed(app, event)
            % Change the view to show all VCs
            app.DerivativeVCPanel.Visible = 'off';
            app.AllVCGraphsPanel.Visible = 'on';
            UpdateButtonPushed(app, event)        
        end

        % Button pushed function: ProceedtoTechnicalPropertiesButton
        function ProceedtoTechnicalPropertiesButtonPushed(app, event)
            %Open a confirmation dialog box
            fig = app.MainScreenCRP;
            selection = uiconfirm(fig,'Do you want to proceed and start the calculation?','Confirm calculation start','Icon','question');
            tf = strcmp(selection,'OK');
            %Execute only when the user agrees
            if tf == true
                
                CustomerRelevantPropertiesVC = app.vehicleConceptsCRP;
                Derivative = app.derivativeSuggestion;
                [~, TechnicalPropertiesVC, MinMaxTP, corrfunct] = CorrelationsCRP2TP(CustomerRelevantPropertiesVC);
                VehicleTypes = app.derivativeTypes;
                                
                %Save the results of the simulation if the user agrees
                selection2 = uiconfirm(fig,'Do you want to save the results?','Saving Results','Icon','question');
                tf2 = strcmp(selection2,'OK');
                if tf2 == true
                    filename = uiputfile('*.mat','myScenario_TP','07_Technical Properties\TP_Saved_Scenarios\');
                    if filename ~= 0
                        newName = append('07_Technical Properties\TP_Saved_Scenarios\',filename);
                        save(newName,'TechnicalPropertiesVC','CustomerRelevantPropertiesVC', 'Derivative','MinMaxTP', 'corrfunct' , 'VehicleTypes')
                    end
                end
                
                %open the next app (CR Properties) and transfer data
                selection3 = uiconfirm(fig,'Do you want to open the Technical Properties app?','Open Technical Properties app','Icon','question');
                tf3 = strcmp(selection3,'OK');
                if tf3 == true
                    TechnicalProperties(TechnicalPropertiesVC,CustomerRelevantPropertiesVC, Derivative, MinMaxTP, corrfunct, VehicleTypes)                    
                end
            end
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            %Open a confirmation dialog box
            fig = app.MainScreenCRP;
            message = sprintf(['Do you want to return to the Home screen? \n\n'...
                'Consider saving their current results. Otherwise it will be deleted.']);
            selection = uiconfirm(fig,message,'Confirm return to Home screen','Icon','question');
            tf = strcmp(selection,'OK');
            %Execute only when the user agrees
            if tf == true
                Start;
                delete(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainScreenCRP = uifigure('Visible', 'off');
            app.MainScreenCRP.Name = 'Customer-Relevant Properties';
            app.MainScreenCRP.Icon = 'FTMLogo.PNG';
            app.Step2CustomerRelevantPropertiesofAutonomousVehicleConceptsLabel = uilabel(app.MainScreenCRP);
            app.Step2CustomerRelevantPropertiesofAutonomousVehicleConceptsLabel.Text = '              Step 2: Customer-Relevant Properties of Autonomous, Shared and Electric Vehicle Concepts';
            app.TUMlogo = uiimage(app.MainScreenCRP);
            app.TUMlogo.ImageSource = '2015_Logo_TUM_neg_RGB.svg';
            app.MainMenuPanel = uipanel(app.MainScreenCRP);
            app.MainMenuPanel.Title = 'Main Menu';
            app.DataOptionsPanel = uipanel(app.MainMenuPanel);
            app.DataOptionsPanel.Title = 'Data Options';
            app.LoadButton = uibutton(app.DataOptionsPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Tooltip = {'Loads saved calcualtion or parameters from .mat file.'; 'Notice: Only previously saved files can be openend!'};
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.DataOptionsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tooltip = {'Saves the current parameters into a .mat file. This file can be opened later by the Load button.'};
            app.SaveButton.Text = 'Save';
            app.ResetButton = uibutton(app.DataOptionsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tooltip = {'Resets all parameters and sets display to the start view.'};
            app.ResetButton.Text = 'Reset';
            app.ManipulateButton = uibutton(app.DataOptionsPanel, 'state');
            app.ManipulateButton.ValueChangedFcn = createCallbackFcn(app, @ManipulateButtonValueChanged, true);
            app.ManipulateButton.Tooltip = {'Enables the manipulation of the Vehicle Concepts parameters.'};
            app.ManipulateButton.Text = 'Manipulate';
            app.ProceedtoTechnicalPropertiesButton = uibutton(app.MainMenuPanel, 'push');
            app.ProceedtoTechnicalPropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedtoTechnicalPropertiesButtonPushed, true);
            app.ProceedtoTechnicalPropertiesButton.Tooltip = {'Proceed to the "Technical Properties" GUI by starting the calculation of the Technical Properties for each selected Vehicle Concept.'};
            app.ProceedtoTechnicalPropertiesButton.Text = 'Proceed to Technical Properties';
            app.VehicleConceptsSelectionPanel = uipanel(app.MainMenuPanel);
            app.VehicleConceptsSelectionPanel.Title = 'Vehicle Concepts Selection';
            app.CheckBox1 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox1.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox1.Text = '';
            app.VCLamp1 = uilamp(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept1Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept1Label.Text = 'Vehicle Concept 1';
            app.CheckBox2 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox2.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox2.Text = '';
            app.VCLamp2 = uilamp(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept2Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept2Label.Text = 'Vehicle Concept 2';
            app.CheckBox3 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox3.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox3.Text = '';
            app.VCLamp3 = uilamp(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept3Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept3Label.Text = 'Vehicle Concept 3';
            app.CheckBox4 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox4.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox4.Text = '';
            app.VCLamp4 = uilamp(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept4Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept4Label.Text = 'Vehicle Concept 4';
            app.CheckBox5 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox5.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox5.Text = '';
            app.VCLamp5 = uilamp(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept5Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept5Label.Text = 'Vehicle Concept 5';
            app.CheckBox6 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox6.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox6.Text = '';
            app.VCLamp6 = uilamp(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept6Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept6Label.Text = 'Vehicle Concept 6';
            app.CheckBox7 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox7.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox7.Text = '';
            app.VCLamp7 = uilamp(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept7Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept7Label.Text = 'Vehicle Concept 7';
            app.CheckBox8 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox8.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox8.Text = '';
            app.VCLamp8 = uilamp(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept8Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept8Label.Text = 'Vehicle Concept 8';
            app.CheckBox9 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox9.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox9.Text = '';
            app.VCLamp9 = uilamp(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept9Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept9Label.Text = 'Vehicle Concept 9';
            app.CheckBox10 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox10.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox10.Text = '';
            app.VCLamp10 = uilamp(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept10Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept10Label.Text = 'Vehicle Concept 10';
            app.CheckBox11 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox11.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox11.Text = '';
            app.VCLamp11 = uilamp(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept11Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept11Label.Text = 'Vehicle Concept 11';
            app.CheckBox12 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox12.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox12.Text = '';
            app.VCLamp12 = uilamp(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept12Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept12Label.Text = 'Vehicle Concept 12';
            app.CheckBox13 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox13.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox13.Text = '';
            app.VCLamp13 = uilamp(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept13Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept13Label.Text = 'Vehicle Concept 13';
            app.CheckBox14 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox14.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox14.Text = '';
            app.VCLamp14 = uilamp(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept14Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept14Label.Text = 'Vehicle Concept 14';
            app.TickAllButton = uibutton(app.VehicleConceptsSelectionPanel, 'push');
            app.TickAllButton.ButtonPushedFcn = createCallbackFcn(app, @TickallButtonPushed, true);
            app.TickAllButton.Tooltip = {'Ticks all available Vehicle Concepts.'};
            app.TickAllButton.Text = 'Tick All';
            app.UpdateButton = uibutton(app.VehicleConceptsSelectionPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Tooltip = {'Updates all visible elements and spidergraphs.'};
            app.UpdateButton.Text = 'Update';
            app.DisplayOptionsPanel = uipanel(app.MainMenuPanel);
            app.DisplayOptionsPanel.Title = 'Display Options';
            app.DerivativeVCButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.DerivativeVCButton.ButtonPushedFcn = createCallbackFcn(app, @DerivativeVCButtonPushed, true);
            app.DerivativeVCButton.Tooltip = {'Shows only one spidergraph of the Customer-Relevant Properties next to the Derivative(s) fulfilled Mobility Needs.'; '(VC = Vehicle Concept)'};
            app.DerivativeVCButton.Text = 'Derivative/VC';
            app.AllVCGraphsButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.AllVCGraphsButton.ButtonPushedFcn = createCallbackFcn(app, @AllVCGraphsButtonPushed, true);
            app.AllVCGraphsButton.Tooltip = {'Shows all spidergraphs of the Customer-Relevant Properties in one view.'; '(VC = Vehicle Concept)'};
            app.AllVCGraphsButton.Text = 'All VC Graphs';
            app.AllVCGraphsPanel = uipanel(app.MainScreenCRP);
            app.AllVCGraphsPanel.Title = 'All Vehicle Concept(s) Graphs View';
            app.DynamicsGraphPanel = uipanel(app.AllVCGraphsPanel);
            app.DynamicsGraphPanel.Tooltip = {'The red line shows the minimal acceptable value of the Customer-Relevant Property. '};
            app.DynamicsGraphPanel.Title = 'Dynamics';
            app.DynamicsGraph = uiaxes(app.DynamicsGraphPanel);
            xlabel(app.DynamicsGraph, 'X')
            ylabel(app.DynamicsGraph, 'Y')
            zlabel(app.DynamicsGraph, 'Z')
            app.DynamicsGraph.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ErgonomicsGraphPanel = uipanel(app.AllVCGraphsPanel);
            app.ErgonomicsGraphPanel.Tooltip = {'The red line shows the minimal acceptable value of the Customer-Relevant Property. '};
            app.ErgonomicsGraphPanel.Title = 'Ergonomics';
            app.ErgonomicsGraph = uiaxes(app.ErgonomicsGraphPanel);
            xlabel(app.ErgonomicsGraph, 'X')
            ylabel(app.ErgonomicsGraph, 'Y')
            zlabel(app.ErgonomicsGraph, 'Z')
            app.ErgonomicsGraph.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.SecurityGraphPanel = uipanel(app.AllVCGraphsPanel);
            app.SecurityGraphPanel.Tooltip = {'The red line shows the minimal acceptable value of the Customer-Relevant Property. '};
            app.SecurityGraphPanel.Title = 'Security';
            app.SecurityGraph = uiaxes(app.SecurityGraphPanel);
            xlabel(app.SecurityGraph, 'X')
            ylabel(app.SecurityGraph, 'Y')
            zlabel(app.SecurityGraph, 'Z')
            app.SecurityGraph.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UsabilityGraphPanel = uipanel(app.AllVCGraphsPanel);
            app.UsabilityGraphPanel.Tooltip = {'The red line shows the minimal acceptable value of the Customer-Relevant Property. '};
            app.UsabilityGraphPanel.Title = 'Usability';
            app.UsabilityGraph = uiaxes(app.UsabilityGraphPanel);
            xlabel(app.UsabilityGraph, 'X')
            ylabel(app.UsabilityGraph, 'Y')
            zlabel(app.UsabilityGraph, 'Z')
            app.UsabilityGraph.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.DrivingStyleGraphPanel = uipanel(app.AllVCGraphsPanel);
            app.DrivingStyleGraphPanel.Tooltip = {'The red line shows the minimal acceptable value of the Customer-Relevant Property. '};
            app.DrivingStyleGraphPanel.Title = 'Driving Style';
            app.DrivingStyleGraph = uiaxes(app.DrivingStyleGraphPanel);
            xlabel(app.DrivingStyleGraph, 'X')
            ylabel(app.DrivingStyleGraph, 'Y')
            zlabel(app.DrivingStyleGraph, 'Z')
            app.DrivingStyleGraph.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.LuxuryGraphPanel = uipanel(app.AllVCGraphsPanel);
            app.LuxuryGraphPanel.Tooltip = {'The red line shows the minimal acceptable value of the Customer-Relevant Property. '};
            app.LuxuryGraphPanel.Title = 'Luxury';
            app.LuxuryGraph = uiaxes(app.LuxuryGraphPanel);
            xlabel(app.LuxuryGraph, 'X')
            ylabel(app.LuxuryGraph, 'Y')
            zlabel(app.LuxuryGraph, 'Z')
            app.LuxuryGraph.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.HomeButton = uibutton(app.MainScreenCRP, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Icon = 'Home.svg';
            app.HomeButton.Text = '';
            app.DerivativeVCPanel = uipanel(app.MainScreenCRP);
            app.DerivativeVCPanel.Title = 'Derivative/Vehicle Concept(s) View';
            app.VehicleConceptsGraphsPanel = uipanel(app.DerivativeVCPanel);
            app.VehicleConceptsGraphsPanel.Tooltip = {'The red line shows the minimal acceptable value of the Customer-Relevant Property. '};
            app.VehicleConceptsGraphsPanel.Title = 'Vehicle Concept(s) - Graphs';
            app.GotoParametersButton = uibutton(app.VehicleConceptsGraphsPanel, 'push');
            app.GotoParametersButton.ButtonPushedFcn = createCallbackFcn(app, @GotoParametersButtonPushed, true);
            app.GotoParametersButton.Tooltip = {'View the parameters of the Vehicle Concept.'};
            app.GotoParametersButton.Text = 'Go to Parameters';
            app.VehicleConceptsGraph = uiaxes(app.VehicleConceptsGraphsPanel);
            xlabel(app.VehicleConceptsGraph, 'X')
            ylabel(app.VehicleConceptsGraph, 'Y')
            zlabel(app.VehicleConceptsGraph, 'Z')
            app.DerivativesPanel = uipanel(app.DerivativeVCPanel);
            app.DerivativesPanel.Title = 'Derivative(s)';
            app.ButtonGroupDerivatives = uibuttongroup(app.DerivativesPanel);
            app.ButtonGroupDerivatives.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupDerivativesSelectionChanged, true);
            app.ButtonGroupDerivatives.Tooltip = {'Select between the two Mobility Needs spidergraphs.'};
            app.SecondaryActivitiesButton = uitogglebutton(app.ButtonGroupDerivatives);
            app.SecondaryActivitiesButton.Text = 'Secondary Activities';
            app.SecondaryActivitiesButton.Value = true;
            app.CharacterMobilityButton = uitogglebutton(app.ButtonGroupDerivatives);
            app.CharacterMobilityButton.Text = 'Character/Mobility';
            app.DerivativesGraph = uiaxes(app.DerivativesPanel);
            xlabel(app.DerivativesGraph, 'X')
            ylabel(app.DerivativesGraph, 'Y')
            zlabel(app.DerivativesGraph, 'Z')
            app.ButtonGroupVC = uibuttongroup(app.DerivativeVCPanel);
            app.ButtonGroupVC.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupVCSelectionChanged, true);
            app.ButtonGroupVC.Tooltip = {'Select the Customer-Relevant Properties category.'};
            app.DynamicsButton = uitogglebutton(app.ButtonGroupVC);
            app.DynamicsButton.Text = 'Dynamics';
            app.DynamicsButton.Value = true;
            app.ErgonomicsButton = uitogglebutton(app.ButtonGroupVC);
            app.ErgonomicsButton.Text = 'Ergonomics';
            app.SecurityButton = uitogglebutton(app.ButtonGroupVC);
            app.SecurityButton.Text = 'Security';
            app.UsabilityButton = uitogglebutton(app.ButtonGroupVC);
            app.UsabilityButton.Text = 'Usability';
            app.DrivingStyleButton = uitogglebutton(app.ButtonGroupVC);
            app.DrivingStyleButton.Text = 'Driving Style';
            app.LuxuryButton = uitogglebutton(app.ButtonGroupVC);
            app.LuxuryButton.Text = 'Luxury';
            app.VehicleConceptsParameterPanel = uipanel(app.DerivativeVCPanel);
            app.VehicleConceptsParameterPanel.Title = 'Vehicle Concept(s) - Parameter';
            app.VehicleConceptLabel = uilabel(app.VehicleConceptsParameterPanel);
            app.VehicleConceptLabel.Text = 'Vehicle Concept:';
            app.VehicleConceptDropDown = uidropdown(app.VehicleConceptsParameterPanel);
            app.VehicleConceptDropDown.Items = {'Vehicle 1'};
            app.VehicleConceptDropDown.ValueChangedFcn = createCallbackFcn(app, @VehicleConceptDropDownValueChanged, true);
            app.VehicleConceptDropDown.Tooltip = {'Current Vehicle Concept Customer-Relevant Properties.'};
            app.VehicleConceptDropDown.Value = 'Vehicle 1';
            app.DynamicsPanel = uipanel(app.VehicleConceptsParameterPanel);
            app.DynamicsPanel.Tooltip = {'Dynamic Properties of the Vehicle Concept.'};
            app.DynamicsPanel.Title = 'Dynamics';
            app.AxialDynamicsSpinner = uispinner(app.DynamicsPanel);
            app.AxialDynamicsSpinner.Limits = [4 10];
            app.AxialDynamicsSpinner.ValueDisplayFormat = '%.1f';
            app.AxialDynamicsSpinner.Value = 4;
            app.AxialDynamicsSpinnerLabel = uilabel(app.DynamicsPanel);
            app.AxialDynamicsSpinnerLabel.Text = 'Axial Dynamics';
            app.LateralDynamicsSpinner = uispinner(app.DynamicsPanel);
            app.LateralDynamicsSpinner.Limits = [4 10];
            app.LateralDynamicsSpinner.ValueDisplayFormat = '%.1f';
            app.LateralDynamicsSpinner.Value = 4;
            app.LateralDynamicsSpinnerLabel = uilabel(app.DynamicsPanel);
            app.LateralDynamicsSpinnerLabel.Text = 'Lateral Dynamics';
            app.VerticalDynamicsSpinner = uispinner(app.DynamicsPanel);
            app.VerticalDynamicsSpinner.Limits = [4 10];
            app.VerticalDynamicsSpinner.ValueDisplayFormat = '%.1f';
            app.VerticalDynamicsSpinner.Value = 4;
            app.VerticalDynamicsSpinnerLabel = uilabel(app.DynamicsPanel);
            app.VerticalDynamicsSpinnerLabel.Text = 'Vertical Dynamics';
            app.ManeuverabilitySpinner = uispinner(app.DynamicsPanel);
            app.ManeuverabilitySpinner.Limits = [4 10];
            app.ManeuverabilitySpinner.ValueDisplayFormat = '%.1f';
            app.ManeuverabilitySpinner.Value = 4;
            app.ManeuverabilitySpinnerLabel = uilabel(app.DynamicsPanel);
            app.ManeuverabilitySpinnerLabel.Text = 'Maneuverability';
            app.ErgonomicsPanel = uipanel(app.VehicleConceptsParameterPanel);
            app.ErgonomicsPanel.Tooltip = {'Ergonomic Properties of the Vehicle Concept.'};
            app.ErgonomicsPanel.Title = 'Ergonomics';
            app.BoardingComfortSpinner = uispinner(app.ErgonomicsPanel);
            app.BoardingComfortSpinner.Limits = [4 10];
            app.BoardingComfortSpinner.ValueDisplayFormat = '%.1f';
            app.BoardingComfortSpinner.Value = 4;
            app.BoardingComfortSpinnerLabel = uilabel(app.ErgonomicsPanel);
            app.BoardingComfortSpinnerLabel.Text = 'Boarding Comfort';
            app.BoardingTimeSpinner = uispinner(app.ErgonomicsPanel);
            app.BoardingTimeSpinner.Limits = [4 10];
            app.BoardingTimeSpinner.ValueDisplayFormat = '%.1f';
            app.BoardingTimeSpinner.Value = 4;
            app.BoardingTimeSpinnerLabel = uilabel(app.ErgonomicsPanel);
            app.BoardingTimeSpinnerLabel.Text = 'Boarding Time';
            app.LegRoomSpinner = uispinner(app.ErgonomicsPanel);
            app.LegRoomSpinner.Limits = [4 10];
            app.LegRoomSpinner.ValueDisplayFormat = '%.1f';
            app.LegRoomSpinner.Value = 4;
            app.LegRoomSpinnerLabel = uilabel(app.ErgonomicsPanel);
            app.LegRoomSpinnerLabel.Text = 'Leg Room';
            app.ShouldersRoomSpinner = uispinner(app.ErgonomicsPanel);
            app.ShouldersRoomSpinner.Limits = [4 10];
            app.ShouldersRoomSpinner.ValueDisplayFormat = '%.1f';
            app.ShouldersRoomSpinner.Value = 4;
            app.ShouldersRoomSpinnerLabel = uilabel(app.ErgonomicsPanel);
            app.ShouldersRoomSpinnerLabel.Text = 'Shoulders Room';
            app.HeadRoomSpinner = uispinner(app.ErgonomicsPanel);
            app.HeadRoomSpinner.Limits = [4 10];
            app.HeadRoomSpinner.ValueDisplayFormat = '%.1f';
            app.HeadRoomSpinner.Value = 4;
            app.HeadRoomSpinnerLabel = uilabel(app.ErgonomicsPanel);
            app.HeadRoomSpinnerLabel.Text = 'Head Room';
            app.InteriorAcousticsSpinner = uispinner(app.ErgonomicsPanel);
            app.InteriorAcousticsSpinner.Limits = [4 10];
            app.InteriorAcousticsSpinner.ValueDisplayFormat = '%.1f';
            app.InteriorAcousticsSpinner.Value = 4;
            app.InteriorAcousticsSpinnerLabel = uilabel(app.ErgonomicsPanel);
            app.InteriorAcousticsSpinnerLabel.Text = 'Interior Acoustics';
            app.SecurityPanel = uipanel(app.VehicleConceptsParameterPanel);
            app.SecurityPanel.Tooltip = {'Security Properties of the Vehicle Concept.'};
            app.SecurityPanel.Title = 'Security';
            app.ExternalCommunicationSpinner = uispinner(app.SecurityPanel);
            app.ExternalCommunicationSpinner.Limits = [4 10];
            app.ExternalCommunicationSpinner.ValueDisplayFormat = '%.1f';
            app.ExternalCommunicationSpinner.Value = 4;
            app.ExternalCommunicationSpinnerLabel = uilabel(app.SecurityPanel);
            app.ExternalCommunicationSpinnerLabel.Text = 'External Communication';
            app.InteriorRecognitionSpinner = uispinner(app.SecurityPanel);
            app.InteriorRecognitionSpinner.Limits = [4 10];
            app.InteriorRecognitionSpinner.ValueDisplayFormat = '%.1f';
            app.InteriorRecognitionSpinner.Value = 4;
            app.InteriorRecognitionSpinnerLabel = uilabel(app.SecurityPanel);
            app.InteriorRecognitionSpinnerLabel.Text = 'Interior Recognition';
            app.PassiveSafetySpinner = uispinner(app.SecurityPanel);
            app.PassiveSafetySpinner.Limits = [4 10];
            app.PassiveSafetySpinner.ValueDisplayFormat = '%.1f';
            app.PassiveSafetySpinner.Value = 4;
            app.PassiveSafetySpinnerLabel = uilabel(app.SecurityPanel);
            app.PassiveSafetySpinnerLabel.Text = 'Passive Safety';
            app.QualityofAutomationSpinner = uispinner(app.SecurityPanel);
            app.QualityofAutomationSpinner.Limits = [4 10];
            app.QualityofAutomationSpinner.ValueDisplayFormat = '%.1f';
            app.QualityofAutomationSpinner.Value = 4;
            app.QualityofAutomationLabel = uilabel(app.SecurityPanel);
            app.QualityofAutomationLabel.Text = 'Quality of Automation';
            app.ActiveSafetySpinner = uispinner(app.SecurityPanel);
            app.ActiveSafetySpinner.Limits = [4 10];
            app.ActiveSafetySpinner.ValueDisplayFormat = '%.1f';
            app.ActiveSafetySpinner.Value = 4;
            app.ActiveSafetySpinnerLabel = uilabel(app.SecurityPanel);
            app.ActiveSafetySpinnerLabel.Text = 'Active Safety';
            app.UsabilityPanel = uipanel(app.VehicleConceptsParameterPanel);
            app.UsabilityPanel.Tooltip = {'Usability Properties of the Vehicle Concept.'};
            app.UsabilityPanel.Title = 'Usability';
            app.BadRoadCapabilitySpinner = uispinner(app.UsabilityPanel);
            app.BadRoadCapabilitySpinner.Limits = [4 10];
            app.BadRoadCapabilitySpinner.ValueDisplayFormat = '%.1f';
            app.BadRoadCapabilitySpinner.Value = 4;
            app.BadRoadCapabilitySpinner_2Label = uilabel(app.UsabilityPanel);
            app.BadRoadCapabilitySpinner_2Label.Text = 'Bad Road Capability';
            app.LuggageSpaceSpinner = uispinner(app.UsabilityPanel);
            app.LuggageSpaceSpinner.Limits = [4 10];
            app.LuggageSpaceSpinner.ValueDisplayFormat = '%.1f';
            app.LuggageSpaceSpinner.Value = 4;
            app.LuggageSpaceSpinnerLabel = uilabel(app.UsabilityPanel);
            app.LuggageSpaceSpinnerLabel.Text = 'Luggage Space';
            app.RadiusofMovementSpinner = uispinner(app.UsabilityPanel);
            app.RadiusofMovementSpinner.Limits = [4 10];
            app.RadiusofMovementSpinner.ValueDisplayFormat = '%.1f';
            app.RadiusofMovementSpinner.Value = 4;
            app.RadiusofMovementSpinnerLabel = uilabel(app.UsabilityPanel);
            app.RadiusofMovementSpinnerLabel.Text = 'Radius of Movement';
            app.CostsSpinner = uispinner(app.UsabilityPanel);
            app.CostsSpinner.Limits = [4 10];
            app.CostsSpinner.ValueDisplayFormat = '%.1f';
            app.CostsSpinner.Value = 4;
            app.CostsSpinnerLabel = uilabel(app.UsabilityPanel);
            app.CostsSpinnerLabel.Text = 'Costs';
            app.EcologySpinner = uispinner(app.UsabilityPanel);
            app.EcologySpinner.Limits = [4 10];
            app.EcologySpinner.ValueDisplayFormat = '%.1f';
            app.EcologySpinner.Value = 4;
            app.EcologySpinnerLabel = uilabel(app.UsabilityPanel);
            app.EcologySpinnerLabel.Text = 'Ecology';
            app.PassengersSpinner = uispinner(app.UsabilityPanel);
            app.PassengersSpinner.Limits = [0 10];
            app.PassengersSpinner.ValueDisplayFormat = '%.0f';
            app.PassengersSpinnerLabel = uilabel(app.UsabilityPanel);
            app.PassengersSpinnerLabel.Text = 'Passengers';
            app.DrivingStylePanel = uipanel(app.VehicleConceptsParameterPanel);
            app.DrivingStylePanel.Tooltip = {'Driving Style Properties of the Vehicle Concept.'; '(DS = Driving Style)'};
            app.DrivingStylePanel.Title = 'Driving Style';
            app.DSComfortSpinner = uispinner(app.DrivingStylePanel);
            app.DSComfortSpinner.Limits = [4 10];
            app.DSComfortSpinner.ValueDisplayFormat = '%.1f';
            app.DSComfortSpinner.Value = 4;
            app.DSComfortSpinnerLabel = uilabel(app.DrivingStylePanel);
            app.DSComfortSpinnerLabel.Text = 'DS Comfort';
            app.DSSafetySpinner = uispinner(app.DrivingStylePanel);
            app.DSSafetySpinner.Limits = [4 10];
            app.DSSafetySpinner.ValueDisplayFormat = '%.1f';
            app.DSSafetySpinner.Value = 4;
            app.DSSafetySpinnerLabel = uilabel(app.DrivingStylePanel);
            app.DSSafetySpinnerLabel.Text = 'DS Safety';
            app.DSTimePotentialSpinner = uispinner(app.DrivingStylePanel);
            app.DSTimePotentialSpinner.Limits = [4 10];
            app.DSTimePotentialSpinner.ValueDisplayFormat = '%.1f';
            app.DSTimePotentialSpinner.Value = 4;
            app.DSTimePotentialSpinnerLabel = uilabel(app.DrivingStylePanel);
            app.DSTimePotentialSpinnerLabel.Text = 'DS Time Potential';
            app.DSConsumptionSpinner = uispinner(app.DrivingStylePanel);
            app.DSConsumptionSpinner.Limits = [4 10];
            app.DSConsumptionSpinner.ValueDisplayFormat = '%.1f';
            app.DSConsumptionSpinner.Value = 4;
            app.DSConsumptionSpinnerLabel = uilabel(app.DrivingStylePanel);
            app.DSConsumptionSpinnerLabel.Text = 'DS Consumption';
            app.GotoGraphsButton = uibutton(app.VehicleConceptsParameterPanel, 'push');
            app.GotoGraphsButton.ButtonPushedFcn = createCallbackFcn(app, @GotoGraphsButtonPushed, true);
            app.GotoGraphsButton.Tooltip = {'View the spidergraphs of the Vehicle Concept.'};
            app.GotoGraphsButton.Text = 'Go to Graphs';
            app.LuxuryPanel = uipanel(app.VehicleConceptsParameterPanel);
            app.LuxuryPanel.Tooltip = {'Luxury Properties of the Vehicle Concept.'};
            app.LuxuryPanel.Title = 'Luxury';
            app.BuiltInInfotainmentSpinner = uispinner(app.LuxuryPanel);
            app.BuiltInInfotainmentSpinner.Limits = [4 10];
            app.BuiltInInfotainmentSpinner.ValueDisplayFormat = '%.1f';
            app.BuiltInInfotainmentSpinner.Value = 4;
            app.BuiltInInfotainmentSpinnerLabel = uilabel(app.LuxuryPanel);
            app.BuiltInInfotainmentSpinnerLabel.Text = 'Built-In Infotainment';
            app.InfoIndividualizationSpinner = uispinner(app.LuxuryPanel);
            app.InfoIndividualizationSpinner.Limits = [4 10];
            app.InfoIndividualizationSpinner.ValueDisplayFormat = '%.1f';
            app.InfoIndividualizationSpinner.Value = 4;
            app.InfoIndividualizationSpinnerLabel = uilabel(app.LuxuryPanel);
            app.InfoIndividualizationSpinnerLabel.Text = 'Info Individualization';
            app.ExteriorDesignSpinner = uispinner(app.LuxuryPanel);
            app.ExteriorDesignSpinner.Limits = [4 10];
            app.ExteriorDesignSpinner.ValueDisplayFormat = '%.1f';
            app.ExteriorDesignSpinner.Value = 4;
            app.ExteriorDesignSpinnerLabel = uilabel(app.LuxuryPanel);
            app.ExteriorDesignSpinnerLabel.Text = 'Exterior Design';
            % Show the figure after all components are created
            app.MainScreenCRP.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CustomerRelevantProperties(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainScreenCRP)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainScreenCRP)
        end
    end
end