classdef DesignParameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainScreenDP                    matlab.ui.Figure
        Step4ComponentSelectionforAutonomousVehicleConceptsLabel  matlab.ui.control.Label
        MainMenuPanel                   matlab.ui.container.Panel
        DataOptionsPanel                matlab.ui.container.Panel
        LoadButton                      matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        ManipulateButton                matlab.ui.control.StateButton
        DisplayOptionsPanel             matlab.ui.container.Panel
        KeyParameterSelectionButton     matlab.ui.control.Button
        DetailedParameterSelectionButton  matlab.ui.control.Button
        ProceedtoVehicleConceptsButton  matlab.ui.control.Button
        VehicleConceptsSelectionPanel   matlab.ui.container.Panel
        CheckBox1                       matlab.ui.control.CheckBox
        CheckBox2                       matlab.ui.control.CheckBox
        CheckBox3                       matlab.ui.control.CheckBox
        CheckBox4                       matlab.ui.control.CheckBox
        CheckBox5                       matlab.ui.control.CheckBox
        CheckBox6                       matlab.ui.control.CheckBox
        CheckBox7                       matlab.ui.control.CheckBox
        CheckBox8                       matlab.ui.control.CheckBox
        CheckBox9                       matlab.ui.control.CheckBox
        CheckBox10                      matlab.ui.control.CheckBox
        CheckBox11                      matlab.ui.control.CheckBox
        CheckBox12                      matlab.ui.control.CheckBox
        CheckBox13                      matlab.ui.control.CheckBox
        CheckBox14                      matlab.ui.control.CheckBox
        VehicleConcept14Label           matlab.ui.control.Label
        VehicleConcept13Label           matlab.ui.control.Label
        VehicleConcept12Label           matlab.ui.control.Label
        VehicleConcept11Label           matlab.ui.control.Label
        VehicleConcept10Label           matlab.ui.control.Label
        VehicleConcept9Label            matlab.ui.control.Label
        VehicleConcept8Label            matlab.ui.control.Label
        VehicleConcept7Label            matlab.ui.control.Label
        VehicleConcept6Label            matlab.ui.control.Label
        VehicleConcept5Label            matlab.ui.control.Label
        VehicleConcept4Label            matlab.ui.control.Label
        VehicleConcept3Label            matlab.ui.control.Label
        VehicleConcept2Label            matlab.ui.control.Label
        VehicleConcept1Label            matlab.ui.control.Label
        VDP_ButtonGroup                 matlab.ui.container.ButtonGroup
        VDP_Button_1                    matlab.ui.control.ToggleButton
        VDP_Button_2                    matlab.ui.control.ToggleButton
        VDP_Button_3                    matlab.ui.control.ToggleButton
        VDP_Button_4                    matlab.ui.control.ToggleButton
        VDP_Button_5                    matlab.ui.control.ToggleButton
        VDP_Button_6                    matlab.ui.control.ToggleButton
        VDP_Button_7                    matlab.ui.control.ToggleButton
        VDP_Button_8                    matlab.ui.control.ToggleButton
        VDP_Button_9                    matlab.ui.control.ToggleButton
        VDP_Button_10                   matlab.ui.control.ToggleButton
        VDP_Button_11                   matlab.ui.control.ToggleButton
        VDP_Button_12                   matlab.ui.control.ToggleButton
        VDP_Button_13                   matlab.ui.control.ToggleButton
        VDP_Button_14                   matlab.ui.control.ToggleButton
        GobacktoappdefaultstatusButton  matlab.ui.control.Button
        Create3DModelButton             matlab.ui.control.Button
        TUMlogo                         matlab.ui.control.Image
        HomeButton                      matlab.ui.control.Button
        KeyDesignParameterPanel         matlab.ui.container.Panel
        VehicleConcept1Panel            matlab.ui.container.Panel
        SeatingLayoutDropDown_7Label_13  matlab.ui.control.Label
        input_seating_layout_1          matlab.ui.control.DropDown
        DriveTypeDropDown_7Label_13     matlab.ui.control.Label
        input_drive_type_1              matlab.ui.control.DropDown
        EngineTypeDropDown_7Label_13    matlab.ui.control.Label
        input_engine_type_1             matlab.ui.control.DropDown
        RearAxleTypeDropDown_7Label_13  matlab.ui.control.Label
        input_rear_axis_type_1          matlab.ui.control.DropDown
        BatteryCellTypeDropDown_7Label_13  matlab.ui.control.Label
        input_cell_type_1               matlab.ui.control.DropDown
        VehicleConcept2Panel            matlab.ui.container.Panel
        SeatingLayoutDropDown_7Label_2  matlab.ui.control.Label
        input_seating_layout_2          matlab.ui.control.DropDown
        DriveTypeDropDown_7Label_2      matlab.ui.control.Label
        input_drive_type_2              matlab.ui.control.DropDown
        EngineTypeDropDown_7Label_2     matlab.ui.control.Label
        input_engine_type_2             matlab.ui.control.DropDown
        RearAxleTypeDropDown_7Label_2   matlab.ui.control.Label
        input_rear_axis_type_2          matlab.ui.control.DropDown
        BatteryCellTypeDropDown_7Label_2  matlab.ui.control.Label
        input_cell_type_2               matlab.ui.control.DropDown
        VehicleConcept3Panel            matlab.ui.container.Panel
        SeatingLayoutDropDown_7Label_3  matlab.ui.control.Label
        input_seating_layout_3          matlab.ui.control.DropDown
        DriveTypeDropDown_7Label_3      matlab.ui.control.Label
        input_drive_type_3              matlab.ui.control.DropDown
        EngineTypeDropDown_7Label_3     matlab.ui.control.Label
        input_engine_type_3             matlab.ui.control.DropDown
        RearAxleTypeDropDown_7Label_3   matlab.ui.control.Label
        input_rear_axis_type_3          matlab.ui.control.DropDown
        BatteryCellTypeDropDown_7Label_3  matlab.ui.control.Label
        input_cell_type_3               matlab.ui.control.DropDown
        VehicleConcept4Panel            matlab.ui.container.Panel
        SeatingLayoutDropDown_7Label_4  matlab.ui.control.Label
        input_seating_layout_4          matlab.ui.control.DropDown
        DriveTypeDropDown_7Label_4      matlab.ui.control.Label
        input_drive_type_4              matlab.ui.control.DropDown
        EngineTypeDropDown_7Label_4     matlab.ui.control.Label
        input_engine_type_4             matlab.ui.control.DropDown
        RearAxleTypeDropDown_7Label_4   matlab.ui.control.Label
        input_rear_axis_type_4          matlab.ui.control.DropDown
        BatteryCellTypeDropDown_7Label_4  matlab.ui.control.Label
        input_cell_type_4               matlab.ui.control.DropDown
        VehicleConcept5Panel            matlab.ui.container.Panel
        SeatingLayoutDropDown_7Label_5  matlab.ui.control.Label
        input_seating_layout_5          matlab.ui.control.DropDown
        DriveTypeDropDown_7Label_5      matlab.ui.control.Label
        input_drive_type_5              matlab.ui.control.DropDown
        EngineTypeDropDown_7Label_5     matlab.ui.control.Label
        input_engine_type_5             matlab.ui.control.DropDown
        RearAxleTypeDropDown_7Label_5   matlab.ui.control.Label
        input_rear_axis_type_5          matlab.ui.control.DropDown
        BatteryCellTypeDropDown_7Label_5  matlab.ui.control.Label
        input_cell_type_5               matlab.ui.control.DropDown
        VehicleConcept6Panel            matlab.ui.container.Panel
        SeatingLayoutDropDown_7Label_6  matlab.ui.control.Label
        input_seating_layout_6          matlab.ui.control.DropDown
        DriveTypeDropDown_7Label_6      matlab.ui.control.Label
        input_drive_type_6              matlab.ui.control.DropDown
        EngineTypeDropDown_7Label_6     matlab.ui.control.Label
        input_engine_type_6             matlab.ui.control.DropDown
        RearAxleTypeDropDown_7Label_6   matlab.ui.control.Label
        input_rear_axis_type_6          matlab.ui.control.DropDown
        BatteryCellTypeDropDown_7Label_6  matlab.ui.control.Label
        input_cell_type_6               matlab.ui.control.DropDown
        VehicleConcept7Panel            matlab.ui.container.Panel
        SeatingLayoutDropDown_7Label_7  matlab.ui.control.Label
        input_seating_layout_7          matlab.ui.control.DropDown
        DriveTypeDropDown_7Label_7      matlab.ui.control.Label
        input_drive_type_7              matlab.ui.control.DropDown
        EngineTypeDropDown_7Label_7     matlab.ui.control.Label
        input_engine_type_7             matlab.ui.control.DropDown
        RearAxleTypeDropDown_7Label_7   matlab.ui.control.Label
        input_rear_axis_type_7          matlab.ui.control.DropDown
        BatteryCellTypeDropDown_7Label_7  matlab.ui.control.Label
        input_cell_type_7               matlab.ui.control.DropDown
        VehicleConcept8Panel            matlab.ui.container.Panel
        SeatingLayoutDropDown_7Label_8  matlab.ui.control.Label
        input_seating_layout_8          matlab.ui.control.DropDown
        DriveTypeDropDown_7Label_8      matlab.ui.control.Label
        input_drive_type_8              matlab.ui.control.DropDown
        EngineTypeDropDown_7Label_8     matlab.ui.control.Label
        input_engine_type_8             matlab.ui.control.DropDown
        RearAxleTypeDropDown_7Label_8   matlab.ui.control.Label
        input_rear_axis_type_8          matlab.ui.control.DropDown
        BatteryCellTypeDropDown_7Label_8  matlab.ui.control.Label
        input_cell_type_8               matlab.ui.control.DropDown
        VehicleConcept9Panel            matlab.ui.container.Panel
        SeatingLayoutDropDown_7Label_9  matlab.ui.control.Label
        input_seating_layout_9          matlab.ui.control.DropDown
        DriveTypeDropDown_7Label_9      matlab.ui.control.Label
        input_drive_type_9              matlab.ui.control.DropDown
        EngineTypeDropDown_7Label_9     matlab.ui.control.Label
        input_engine_type_9             matlab.ui.control.DropDown
        RearAxleTypeDropDown_7Label_9   matlab.ui.control.Label
        input_rear_axis_type_9          matlab.ui.control.DropDown
        BatteryCellTypeDropDown_7Label_9  matlab.ui.control.Label
        input_cell_type_9               matlab.ui.control.DropDown
        VehicleConcept10Panel           matlab.ui.container.Panel
        SeatingLayoutDropDown_7Label_10  matlab.ui.control.Label
        input_seating_layout_10         matlab.ui.control.DropDown
        DriveTypeDropDown_7Label_10     matlab.ui.control.Label
        input_drive_type_10             matlab.ui.control.DropDown
        EngineTypeDropDown_7Label_10    matlab.ui.control.Label
        input_engine_type_10            matlab.ui.control.DropDown
        RearAxleTypeDropDown_7Label_10  matlab.ui.control.Label
        input_rear_axis_type_10         matlab.ui.control.DropDown
        BatteryCellTypeDropDown_7Label_10  matlab.ui.control.Label
        input_cell_type_10              matlab.ui.control.DropDown
        VehicleConcept11Panel           matlab.ui.container.Panel
        SeatingLayoutDropDown_7Label_11  matlab.ui.control.Label
        input_seating_layout_11         matlab.ui.control.DropDown
        DriveTypeDropDown_7Label_11     matlab.ui.control.Label
        input_drive_type_11             matlab.ui.control.DropDown
        EngineTypeDropDown_7Label_11    matlab.ui.control.Label
        input_engine_type_11            matlab.ui.control.DropDown
        RearAxleTypeDropDown_7Label_11  matlab.ui.control.Label
        input_rear_axis_type_11         matlab.ui.control.DropDown
        BatteryCellTypeDropDown_7Label_11  matlab.ui.control.Label
        input_cell_type_11              matlab.ui.control.DropDown
        VehicleConcept12Panel           matlab.ui.container.Panel
        SeatingLayoutDropDown_7Label_12  matlab.ui.control.Label
        input_seating_layout_12         matlab.ui.control.DropDown
        DriveTypeDropDown_7Label_12     matlab.ui.control.Label
        input_drive_type_12             matlab.ui.control.DropDown
        EngineTypeDropDown_7Label_12    matlab.ui.control.Label
        input_engine_type_12            matlab.ui.control.DropDown
        RearAxleTypeDropDown_7Label_12  matlab.ui.control.Label
        input_rear_axis_type_12         matlab.ui.control.DropDown
        BatteryCellTypeDropDown_7Label_12  matlab.ui.control.Label
        input_cell_type_12              matlab.ui.control.DropDown
        VehicleConceptOriginPanel       matlab.ui.container.Panel
        SeatingLayoutDropDown_7Label    matlab.ui.control.Label
        input_seating_layout            matlab.ui.control.DropDown
        DriveTypeDropDown_7Label        matlab.ui.control.Label
        input_drive_type                matlab.ui.control.DropDown
        EngineTypeDropDown_7Label       matlab.ui.control.Label
        input_engine_type               matlab.ui.control.DropDown
        RearAxleTypeDropDown_7Label     matlab.ui.control.Label
        input_rear_axis_type            matlab.ui.control.DropDown
        BatteryCellTypeDropDown_7Label  matlab.ui.control.Label
        input_cell_type                 matlab.ui.control.DropDown
        DetailedDesignParameterPanel    matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        ComponentSelectionTab           matlab.ui.container.Tab
        BatteryPanel                    matlab.ui.container.Panel
        input_batterycooling_in_z       matlab.ui.control.CheckBox
        SmalloverlapLabel               matlab.ui.control.Label
        input_small_overlap             matlab.ui.control.DropDown
        SensorsPanel                    matlab.ui.container.Panel
        NumberofSonarsensorsSpinnerLabel  matlab.ui.control.Label
        input_sonar                     matlab.ui.control.Spinner
        NumberofRadarsensorsSpinnerLabel  matlab.ui.control.Label
        input_radar                     matlab.ui.control.Spinner
        NumberofSurroundCamerasSpinnerLabel  matlab.ui.control.Label
        input_surround_cameras          matlab.ui.control.Spinner
        NumberofFrontCamerasSpinnerLabel  matlab.ui.control.Label
        input_front_cameras             matlab.ui.control.Spinner
        input_c2x                       matlab.ui.control.CheckBox
        input_5g                        matlab.ui.control.CheckBox
        input_onboard_pc                matlab.ui.control.CheckBox
        input_gps_reciever              matlab.ui.control.CheckBox
        input_gps_correction            matlab.ui.control.CheckBox
        NumberofmechLIDARsensorsLabel   matlab.ui.control.Label
        input_mech_lidar                matlab.ui.control.Spinner
        NumberofmemsLIDARsensorsSpinnerLabel  matlab.ui.control.Label
        input_mems_lidar                matlab.ui.control.Spinner
        BodyTypeandMaterialsPanel       matlab.ui.container.Panel
        ClosuresmaterialDropDownLabel   matlab.ui.control.Label
        input_closures_material         matlab.ui.control.DropDown
        DoormaterialLabel               matlab.ui.control.Label
        input_door_material             matlab.ui.control.DropDown
        TailgatematerialLabel           matlab.ui.control.Label
        input_tailgate_material         matlab.ui.control.DropDown
        HoodmaterialLabel               matlab.ui.control.Label
        input_hood_material             matlab.ui.control.DropDown
        FendersmaterialLabel            matlab.ui.control.Label
        input_fenders_material          matlab.ui.control.DropDown
        NumberofdoorsLabel              matlab.ui.control.Label
        input_n_doors                   matlab.ui.control.Spinner
        FronttrunktypeLabel             matlab.ui.control.Label
        input_trunk_type_front          matlab.ui.control.DropDown
        ReartrunktypeLabel              matlab.ui.control.Label
        input_trunk_type_rear           matlab.ui.control.DropDown
        settings_conv                   matlab.ui.control.CheckBox
        settings_aut                    matlab.ui.control.CheckBox
        PowertrainPanel                 matlab.ui.container.Panel
        input_sbw                       matlab.ui.control.CheckBox
        input_emech_steering            matlab.ui.control.CheckBox
        input_emech_brakes              matlab.ui.control.CheckBox
        input_obc                       matlab.ui.control.CheckBox
        AdditionalFeaturesPanel         matlab.ui.container.Panel
        input_keyless_go                matlab.ui.control.CheckBox
        input_acc                       matlab.ui.control.CheckBox
        input_lks                       matlab.ui.control.CheckBox
        input_bsm                       matlab.ui.control.CheckBox
        input_park_assist               matlab.ui.control.CheckBox
        input_night_vision              matlab.ui.control.CheckBox
        input_trunk_assist              matlab.ui.control.CheckBox
        input_phone_connectivity        matlab.ui.control.CheckBox
        HeadlighttechnologyLabel        matlab.ui.control.Label
        input_headlight_tech            matlab.ui.control.DropDown
        input_seat_warmers              matlab.ui.control.CheckBox
        input_pneumatic_doors           matlab.ui.control.CheckBox
        input_window_lift               matlab.ui.control.CheckBox
        input_adjustable_seats          matlab.ui.control.CheckBox
        input_panorama_roof             matlab.ui.control.CheckBox
        input_sliding_roof              matlab.ui.control.CheckBox
        input_hvac                      matlab.ui.control.CheckBox
        input_tire_load                 matlab.ui.control.CheckBox
        input_spare_tire                matlab.ui.control.CheckBox
        InteriorPanel_Components        matlab.ui.container.Panel
        NumberofinteriordisplaysLabel   matlab.ui.control.Label
        input_displays                  matlab.ui.control.Spinner
        NumberofrowsLabel               matlab.ui.control.Label
        input_int_n_rows_copy           matlab.ui.control.Spinner
        SeatsthirdrowLabel_3            matlab.ui.control.Label
        input_seats_row_3_copy          matlab.ui.control.Spinner
        SeatsfirstrowLabel_3            matlab.ui.control.Label
        SeatssecondrowLabel_6           matlab.ui.control.Label
        input_seats_row_2_copy          matlab.ui.control.Spinner
        input_seats_row_1_copy          matlab.ui.control.Spinner
        ComponentParametersTab          matlab.ui.container.Tab
        SecurityPanel                   matlab.ui.container.Panel
        FreecrashlengthsidemmLabel      matlab.ui.control.Label
        input_free_crash_length_3       matlab.ui.control.Spinner
        FreecrashlengthrearmmLabel      matlab.ui.control.Label
        input_free_crash_length_2       matlab.ui.control.Spinner
        NumberofsideairbagsLabel        matlab.ui.control.Label
        input_side_airbags              matlab.ui.control.Spinner
        EnginePanel                     matlab.ui.container.Panel
        EngineanglefrontdegLabel        matlab.ui.control.Label
        input_engine_angle_front_min    matlab.ui.control.Spinner
        EngineanglereardegLabel         matlab.ui.control.Label
        input_engine_angle_rear_min     matlab.ui.control.Spinner
        TorqueratiofrontLabel           matlab.ui.control.Label
        input_torque_ratio_min          matlab.ui.control.Spinner
        AxlePanel                       matlab.ui.container.Panel
        AxleanglexydegLabel             matlab.ui.control.Label
        input_axle_angle_1_min          matlab.ui.control.Spinner
        AxleanglexzdegLabel             matlab.ui.control.Label
        input_axle_angle_2_min          matlab.ui.control.Spinner
        GearboxPanel                    matlab.ui.container.Panel
        GeartypeRearLabel               matlab.ui.control.Label
        input_gear_type_rear            matlab.ui.control.DropDown
        GeartypeFrontLabel              matlab.ui.control.Label
        input_gear_type_front           matlab.ui.control.DropDown
        Gearratio1frontLabel            matlab.ui.control.Label
        input_gear_ratio_1_front_min    matlab.ui.control.Spinner
        Gearratio1rearLabel             matlab.ui.control.Label
        input_gear_ratio_1_rear_min     matlab.ui.control.Spinner
        GearboxoptgoalLabel             matlab.ui.control.Label
        input_gearbox_opt               matlab.ui.control.DropDown
        input_two_speed                 matlab.ui.control.CheckBox
        Gearratio2frontLabel            matlab.ui.control.Label
        input_gear_ratio_2_front_min    matlab.ui.control.Spinner
        Gearratio2rearLabel             matlab.ui.control.Label
        input_gear_ratio_2_rear_min     matlab.ui.control.Spinner
        input_Gearboxlossmodel          matlab.ui.control.CheckBox
        OthersPanel                     matlab.ui.container.Panel
        TirediametermmLabel             matlab.ui.control.Label
        input_tire_diam_min             matlab.ui.control.Spinner
        input_tire_diam_max             matlab.ui.control.Spinner
        TrunkratiofrontrearLabel        matlab.ui.control.Label
        input_trunk_ratio_min           matlab.ui.control.Spinner
        input_trunk_ratio_max           matlab.ui.control.Spinner
        DistancewheelhousebumpermmLabel  matlab.ui.control.Label
        input_dist_wheelhouse2bumper_min  matlab.ui.control.Spinner
        input_dist_wheelhouse2bumper_max  matlab.ui.control.Spinner
        SteeringdistributionfrontLabel  matlab.ui.control.Label
        input_steering_ratio_min        matlab.ui.control.Spinner
        input_steering_ratio_max        matlab.ui.control.Spinner
        FrontwindowangleLabel           matlab.ui.control.Label
        input_window_angle_front_min    matlab.ui.control.Spinner
        RearwindowangleLabel            matlab.ui.control.Label
        input_window_angle_rear_min     matlab.ui.control.Spinner
        DistancewheelhousebumperFrontmmLabel  matlab.ui.control.Label
        input_dist_wheelhouse2bumper_f_min  matlab.ui.control.Spinner
        DistancewheelhousebumperRearmmLabel  matlab.ui.control.Label
        input_dist_wheelhouse2bumper_r_min  matlab.ui.control.Spinner
        input_dist_wheelhouse2bumper_f_max  matlab.ui.control.Spinner
        input_dist_wheelhouse2bumper_r_max  matlab.ui.control.Spinner
        input_window_angle_front_max    matlab.ui.control.Spinner
        input_window_angle_rear_max     matlab.ui.control.Spinner
        input_dist_int2fw_max           matlab.ui.control.Spinner
        input_dist_int2rw_max           matlab.ui.control.Spinner
        DistanceinteriortofrontwagonmmLabel  matlab.ui.control.Label
        input_dist_int2fw_min           matlab.ui.control.Spinner
        DistanceinteriortorearwagonmmLabel  matlab.ui.control.Label
        input_dist_int2rw_min           matlab.ui.control.Spinner
        input_engine_angle_front_max    matlab.ui.control.Spinner
        input_engine_angle_rear_max     matlab.ui.control.Spinner
        input_torque_ratio_max          matlab.ui.control.Spinner
        input_axle_angle_1_max          matlab.ui.control.Spinner
        input_axle_angle_2_max          matlab.ui.control.Spinner
        input_gear_ratio_2_rear_max     matlab.ui.control.Spinner
        input_gear_ratio_2_front_max    matlab.ui.control.Spinner
        input_gear_ratio_1_rear_max     matlab.ui.control.Spinner
        input_gear_ratio_1_front_max    matlab.ui.control.Spinner
        Panel_2                         matlab.ui.container.Panel
        SoftwarePanel                   matlab.ui.container.Panel
        input_eco_driving_check         matlab.ui.control.CheckBox
        SavingsinLabel                  matlab.ui.control.Label
        input_eco_driving_percentage    matlab.ui.control.Spinner
        ManufacturingandMaterialsPanel  matlab.ui.container.Panel
        EstimatedassemblytimehLabel     matlab.ui.control.Label
        input_assembly_time             matlab.ui.control.Spinner
        AluminiumpercentageBIWLabel     matlab.ui.control.Label
        input_biw_alu_perc              matlab.ui.control.Spinner
        InteriorParametersTab           matlab.ui.container.Tab
        FirstrowPanel                   matlab.ui.container.Panel
        input_int_double_ar_1           matlab.ui.control.CheckBox
        input_int_single_ar_between_1   matlab.ui.control.CheckBox
        input_int_single_ar_outside_1   matlab.ui.control.CheckBox
        UpperBodylengthmmLabel          matlab.ui.control.Label
        input_int_upperbody_length_1    matlab.ui.control.Spinner
        BackrestanglemaxdegLabel        matlab.ui.control.Label
        input_int_angle_br_max_1        matlab.ui.control.Spinner
        HeadroommmLabel                 matlab.ui.control.Label
        input_int_headroom_1            matlab.ui.control.Spinner
        SeatdepthmmLabel                matlab.ui.control.Label
        input_int_seat_depth_1          matlab.ui.control.Spinner
        SeatadjustmentxmmLabel          matlab.ui.control.Label
        input_int_seat_adjustment_x_1   matlab.ui.control.Spinner
        SeatheightmmLabel               matlab.ui.control.Label
        input_int_seat_height_1         matlab.ui.control.Spinner
        SeatadjustmentzmmLabel          matlab.ui.control.Label
        input_int_seat_adjustment_z_1   matlab.ui.control.Spinner
        FootlengthmmLabel               matlab.ui.control.Label
        input_int_foot_length_1         matlab.ui.control.Spinner
        LegroomadditionalmmLabel        matlab.ui.control.Label
        input_int_legroom_additional_1  matlab.ui.control.Spinner
        BackrestanglemindegLabel        matlab.ui.control.Label
        input_int_angle_br_min_1        matlab.ui.control.Spinner
        BackrestwidthmmLabel            matlab.ui.control.Label
        input_int_backrest_width_1      matlab.ui.control.Spinner
        SinglearmrestwidthmmLabel       matlab.ui.control.Label
        input_int_armrest_width_single_1  matlab.ui.control.Spinner
        GapbetweenseatsmmLabel          matlab.ui.control.Label
        input_int_seatgap_1             matlab.ui.control.Spinner
        BackrestthicknessmmLabel        matlab.ui.control.Label
        input_int_thickness_br_1        matlab.ui.control.Spinner
        SeatthicknessmmLabel            matlab.ui.control.Label
        input_int_thickness_seat_1      matlab.ui.control.Spinner
        BackrestlengthmmLabel_2         matlab.ui.control.Label
        input_int_backrest_length_1     matlab.ui.control.Spinner
        DoublearmrestwidthmmLabel       matlab.ui.control.Label
        input_int_armrest_width_double_1  matlab.ui.control.Spinner
        GaptowallmmLabel                matlab.ui.control.Label
        input_int_gaptowall_1           matlab.ui.control.Spinner
        SeatsfirstrowLabel              matlab.ui.control.Label
        input_seats_row_1               matlab.ui.control.Spinner
        ThirdrowPanel                   matlab.ui.container.Panel
        input_int_double_ar_3           matlab.ui.control.CheckBox
        input_int_single_ar_between_3   matlab.ui.control.CheckBox
        input_int_single_ar_outside_3   matlab.ui.control.CheckBox
        UpperBodylengthmmLabel_2        matlab.ui.control.Label
        input_int_upperbody_length_3    matlab.ui.control.Spinner
        BackrestanglemaxdegLabel_2      matlab.ui.control.Label
        input_int_angle_br_max_3        matlab.ui.control.Spinner
        HeadroommmLabel_2               matlab.ui.control.Label
        input_int_headroom_3            matlab.ui.control.Spinner
        SeatdepthmmLabel_2              matlab.ui.control.Label
        input_int_seat_depth_3          matlab.ui.control.Spinner
        SeatadjustmentxmmLabel_2        matlab.ui.control.Label
        input_int_seat_adjustment_x_3   matlab.ui.control.Spinner
        SeatheightmmLabel_2             matlab.ui.control.Label
        input_int_seat_height_3         matlab.ui.control.Spinner
        SeatadjustmentzmmLabel_2        matlab.ui.control.Label
        input_int_seat_adjustment_z_3   matlab.ui.control.Spinner
        FootlengthmmLabel_2             matlab.ui.control.Label
        input_int_foot_length_3         matlab.ui.control.Spinner
        LegroomadditionalmmLabel_2      matlab.ui.control.Label
        input_int_legroom_additional_3  matlab.ui.control.Spinner
        BackrestanglemindegLabel_2      matlab.ui.control.Label
        input_int_angle_br_min_3        matlab.ui.control.Spinner
        BackrestwidthmmLabel_2          matlab.ui.control.Label
        input_int_backrest_width_3      matlab.ui.control.Spinner
        SinglearmrestwidthmmLabel_2     matlab.ui.control.Label
        input_int_armrest_width_single_3  matlab.ui.control.Spinner
        GapbetweenseatsmmLabel_2        matlab.ui.control.Label
        input_int_seatgap_3             matlab.ui.control.Spinner
        BackrestthicknessmmLabel_2      matlab.ui.control.Label
        input_int_thickness_br_3        matlab.ui.control.Spinner
        SeatthicknessmmLabel_2          matlab.ui.control.Label
        input_int_thickness_seat_3      matlab.ui.control.Spinner
        BackrestlengthmmLabel_3         matlab.ui.control.Label
        input_int_backrest_length_3     matlab.ui.control.Spinner
        DoublearmrestwidthmmLabel_2     matlab.ui.control.Label
        input_int_armrest_width_double_3  matlab.ui.control.Spinner
        GaptowallmmLabel_2              matlab.ui.control.Label
        input_int_gaptowall_3           matlab.ui.control.Spinner
        SeatsthirdrowLabel_2            matlab.ui.control.Label
        input_seats_row_3               matlab.ui.control.Spinner
        SecondrowPanel                  matlab.ui.container.Panel
        input_int_double_ar_2           matlab.ui.control.CheckBox
        input_int_single_ar_between_2   matlab.ui.control.CheckBox
        input_int_single_ar_outside_2   matlab.ui.control.CheckBox
        UpperBodylengthmmLabel_3        matlab.ui.control.Label
        input_int_upperbody_length_2    matlab.ui.control.Spinner
        BackrestanglemaxdegLabel_3      matlab.ui.control.Label
        input_int_angle_br_max_2        matlab.ui.control.Spinner
        HeadroommmLabel_3               matlab.ui.control.Label
        input_int_headroom_2            matlab.ui.control.Spinner
        SeatdepthmmLabel_3              matlab.ui.control.Label
        input_int_seat_depth_2          matlab.ui.control.Spinner
        SeatadjustmentxmmLabel_3        matlab.ui.control.Label
        input_int_seat_adjustment_x_2   matlab.ui.control.Spinner
        SeatheightmmLabel_3             matlab.ui.control.Label
        input_int_seat_height_2         matlab.ui.control.Spinner
        SeatadjustmentzmmLabel_3        matlab.ui.control.Label
        input_int_seat_adjustment_z_2   matlab.ui.control.Spinner
        FootlengthmmLabel_3             matlab.ui.control.Label
        input_int_foot_length_2         matlab.ui.control.Spinner
        LegroomadditionalmmLabel_3      matlab.ui.control.Label
        input_int_legroom_additional_2  matlab.ui.control.Spinner
        BackrestanglemindegLabel_3      matlab.ui.control.Label
        input_int_angle_br_min_2        matlab.ui.control.Spinner
        BackrestwidthmmLabel_3          matlab.ui.control.Label
        input_int_backrest_width_2      matlab.ui.control.Spinner
        SinglearmrestwidthmmLabel_3     matlab.ui.control.Label
        input_int_armrest_width_single_2  matlab.ui.control.Spinner
        GapbetweenseatsmmLabel_3        matlab.ui.control.Label
        input_int_seatgap_2             matlab.ui.control.Spinner
        BackrestthicknessmmLabel_3      matlab.ui.control.Label
        input_int_thickness_br_2        matlab.ui.control.Spinner
        SeatthicknessmmLabel_3          matlab.ui.control.Label
        input_int_thickness_seat_2      matlab.ui.control.Spinner
        BackrestlengthmmLabel_4         matlab.ui.control.Label
        input_int_backrest_length_2     matlab.ui.control.Spinner
        DoublearmrestwidthmmLabel_3     matlab.ui.control.Label
        input_int_armrest_width_double_2  matlab.ui.control.Spinner
        GaptowallmmLabel_3              matlab.ui.control.Label
        input_int_gaptowall_2           matlab.ui.control.Spinner
        SeatssecondrowLabel_5           matlab.ui.control.Label
        input_seats_row_2               matlab.ui.control.Spinner
        NumberofrowsLabel_2             matlab.ui.control.Label
        input_int_n_rows                matlab.ui.control.Spinner
        GeneralPanel                    matlab.ui.container.Panel
        input_int_comfort               matlab.ui.control.CheckBox
        input_int_sleep                 matlab.ui.control.CheckBox
        input_int_ergonomic             matlab.ui.control.CheckBox
        input_int_work                  matlab.ui.control.CheckBox
        WalldistancemmLabel             matlab.ui.control.Label
        input_int_b2b_walldistance      matlab.ui.control.Spinner
        LegoverlapmmLabel               matlab.ui.control.Label
        input_int_x_overlap             matlab.ui.control.Spinner
        UseCaseLabel                    matlab.ui.control.Label
        input_int_usecase               matlab.ui.control.DropDown
        MinH30frontLabel                matlab.ui.control.Label
        input_minH30_front              matlab.ui.control.Spinner
        MinH30rearLabel                 matlab.ui.control.Label
        input_minH30_rear               matlab.ui.control.Spinner
        MininteriorheightmmLabel        matlab.ui.control.Label
        input_int_height_min            matlab.ui.control.Spinner
        MaxinteriorheightmmLabel        matlab.ui.control.Label
        input_int_height_max            matlab.ui.control.Spinner
        MaxH30frontLabel                matlab.ui.control.Label
        input_maxH30_front              matlab.ui.control.Spinner
        MaxH30rearLabel                 matlab.ui.control.Label
        input_maxH30_rear               matlab.ui.control.Spinner
        SeatingLayoutDropDown_7Label_14  matlab.ui.control.Label
        input_seating_layout_copy       matlab.ui.control.DropDown
        TechnicalPropertiesTab          matlab.ui.container.Tab
        TopspeedinkmhLabel              matlab.ui.control.Label
        input_maximal_speed             matlab.ui.control.Spinner
        Minimumaccelerationtimefrom0to100kmhinsLabel  matlab.ui.control.Label
        input_maximal_acceleration      matlab.ui.control.Spinner
        GroundclearanceLabel            matlab.ui.control.Label
        input_ground_clearance          matlab.ui.control.DropDown
        MinrangekmLabel                 matlab.ui.control.Label
        input_range                     matlab.ui.control.Spinner
        FreecrashlengthfrontmmLabel     matlab.ui.control.Label
        input_free_crash_length         matlab.ui.control.Spinner
        TurningdiametermLabel           matlab.ui.control.Label
        input_turning_diameter          matlab.ui.control.Spinner
        TotaltrunkvolumeLLabel          matlab.ui.control.Label
        input_trunk_volume              matlab.ui.control.Spinner
        CustomerrelevantPropertiesTab   matlab.ui.container.Tab
        DynamicsGraphPanel              matlab.ui.container.Panel
        DynamicsGraph                   matlab.ui.control.UIAxes
        ErgonomicsGraphPanel            matlab.ui.container.Panel
        ErgonomicsGraph                 matlab.ui.control.UIAxes
        SecurityGraphPanel              matlab.ui.container.Panel
        SecurityGraph                   matlab.ui.control.UIAxes
        UsabilityGraphPanel             matlab.ui.container.Panel
        UsabilityGraph                  matlab.ui.control.UIAxes
        DrivingStyleGraphPanel          matlab.ui.container.Panel
        DrivingStyleGraph               matlab.ui.control.UIAxes
        LuxuryGraphPanel                matlab.ui.container.Panel
        LuxuryGraph                     matlab.ui.control.UIAxes
        VisualizationOptionsTab         matlab.ui.container.Tab
        HVACPanel                       matlab.ui.container.Panel
        ScenarioLabel                   matlab.ui.control.Label
        input_weather_scenario          matlab.ui.control.DropDown
        AmbienttemperatureCLabel        matlab.ui.control.Label
        input_ambient_temp              matlab.ui.control.Spinner
        MassflowratekgminLabel          matlab.ui.control.Label
        input_massflow_rate             matlab.ui.control.Spinner
        HVACcalculationLabel            matlab.ui.control.Label
        HVAC_calc                       matlab.ui.control.CheckBox
        RecirculationpercentageLabel    matlab.ui.control.Label
        input_percentage_circair        matlab.ui.control.Spinner
        SettemperatureDropDownLabel     matlab.ui.control.Label
        input_temperature_set           matlab.ui.control.DropDown
        OptimizationParametersPanel     matlab.ui.container.Panel
        sing_veh                        matlab.ui.control.CheckBox
        PopulationSizeLabel             matlab.ui.control.Label
        input_pop_size                  matlab.ui.control.Spinner
        MaxgenerationLabel              matlab.ui.control.Label
        input_max_gen                   matlab.ui.control.Spinner
        input_parallel_comp             matlab.ui.control.CheckBox
        TimeframeDropDownLabel          matlab.ui.control.Label
        input_timeframe                 matlab.ui.control.DropDown
        Goal1Label                      matlab.ui.control.Label
        input_goal_1                    matlab.ui.control.DropDown
        Goal2Label                      matlab.ui.control.Label
        input_goal_2                    matlab.ui.control.DropDown
        input_scale_axis                matlab.ui.control.CheckBox
        DrivingcycleLabel               matlab.ui.control.Label
        input_driving_cycle             matlab.ui.control.DropDown
        input_H30Opt                    matlab.ui.control.CheckBox
        VisualizationOptionsPanel       matlab.ui.container.Panel
        plot_battery                    matlab.ui.control.CheckBox
        plot_manikin                    matlab.ui.control.CheckBox
        plot_boundary                   matlab.ui.control.CheckBox
        input_showwarning               matlab.ui.control.CheckBox
        plot_wheel_env                  matlab.ui.control.CheckBox
    end

    %% General Information:
    
    %----------------------------------------------------------------------
    % Designed by Marc Raudszus, Ferdinand Schockenhoff (FTM, Techical University of Munich)
    % Template and few parts of the code of David Fischer (FTM, Techical University of Munich)
    %---------------------------------------
    % Created on:   September 2021
    % Last update:  April 2022
    %---------------------------------------
    % Version:      Matlab2020b
    %---------------------------------------
    % Description:  GUI of the process step "Design Parameters"
    %---------------------------------------
    % Input:        - input_parameter_matrix (all necessary input parameters of the Vehicle Concepts for AuVeCoDe-tool)  
    %               => cell (n x m) n = number of inputs / m = number of vehicle concepts + 1
    %
    %               - currentCRP (current CRP values form previous steps)
    %               - CustomerRelevantPropertiesVC (CRP values form previous steps)
    %---------------------------------------
    % Output:       - VC_result: (all necessary parameters of the Vehicle Concepts for the Vehicle Concept Results App)
    %               => cell (n x m) n = number of parameters / m = number of vehicle concepts + 1
    %
    %               - VC_number: Vector of numbers from feasible VC (feasible in AuVeCoDe)
    %
    %               - input_parameter_matrix (same as input)
    %
    %               - currentCRP (current CRP values form previous steps)
    %               - CustomerRelevantPropertiesVC (CRP values form previous steps)
    % 
    %               Start of the app Vehicle Concept Results (VCR)
    %----------------------------------------------------------------------
    properties (Access = private)
        
        oldpath = addpath('99_General_Elements\') % Needed for first build up of app (TUM image)
        
    end
    %----------------------------------------------------------------------
    properties (Access = public)
        
        input_parameter_matrix_DP;          % Matrix with all input parameter for every Vehicle Concept in DP-App
        input_parameter_matrix_DP_backup;   % Backup input_parameter_matrix_DP not needed until now
        input_parameter_KDP_data = {'input_cell_type';'input_rear_axis_type';'input_engine_type';'input_drive_type';'input_seating_layout'}; % Input parameter in Key Design Parameter panel
        IPM_last_row;                       % Index of last row from input_parameter_matrix
        IPM_KDP_last_row;                   % Index of last row from input_parameter_KDP_data
        VC_number_tot;                      % Total number of Vehicle Concepts from previous step
        selected_VC;                        % Number of VC selected in button group (Vehicle Concepts Selection)
        VC_number_vec;                      % Vector with the number of all ticked VC
        VC_number_ticked;                   % Amount of ticked VC
        index_IPM_KDP;                      % Vector with indices of input_parameter_KDP_data strings in input_parameter_matrix_DP
        index_layout;                       % Index of the input 'seating_layout' in the input_parameter_matrix
        i_feasible;                         % Counting variable for usage in AuVeCoDe to name 3D-Model figures
        appstate;                           % State of DP-App before continuing to next step (0 = abort and go back to DP-App; 1 = continue to VCR-App)
        CRPofVCs;                           % Customer-relevant properties of the Vehicle Concepts
        CRP;                                % Customer-relevant properties of the Vehicle Concepts
        TPofVCs;                            % Technical Properties of the Vehicle Concepts
        Derivative;                         % Derivatives of the VCs
        ColorVC = [[249 186 0]./255; [0 124 48]./255;  [105 8 90]./255; [227 114 34]./255; [162 173 0]./255; [152 198 234]./255; [218 215 203]./255;...
                 [255 220 0]./255; [103 154 29]./255; [156 13 22]./255; [214 76 19]./255; [50 119 138]./255; [100 160 200]./255; [15 27 9]./255;]; %TUM Colors for Vehicle Concepts
        input_parameter_int_row_data = {'input_seats_row';'input_int_gaptowall';'input_int_armrest_width_double';'input_int_backrest_length';...
            'input_int_thickness_seat';'input_int_thickness_br';'input_int_seatgap';'input_int_armrest_width_single';'input_int_backrest_width';...
            'input_int_angle_br_min';'input_int_legroom_additional';'input_int_foot_length';'input_int_seat_adjustment_z';'input_int_seat_height';...
            'input_int_seat_adjustment_x';'input_int_seat_depth';'input_int_headroom';'input_int_angle_br_max';'input_int_upperbody_length';...
            'input_int_single_ar_outside';'input_int_single_ar_between';'input_int_double_ar'}; % Input parameter for seat row panels
        IRD_last_row;                       % Index of last row from input_parameter_int_row_data
        
        input_eco_test;
    
    end
    %----------------------------------------------------------------------
    methods (Access = private)
        %------------------------------------------------------------------
        % Update the values of input parameter matrix to values in app
        function update_input_parameters(app)
            
            % Check if selected VC is ticked and if so, update the origin inputs for Key Design Parameter (KDP) to the selected VC
            for i_VC_ticked = 1:app.VC_number_ticked
                if app.selected_VC == app.VC_number_vec(i_VC_ticked)
                    for row_IPM_KDP = 1:app.IPM_KDP_last_row
                        
                        %------------------------------------------------------------------
                        % For debugging purposes
                        % current_row = row_IPM_KDP
                        %------------------------------------------------------------------
                        
                        KDP_input_name = sprintf('%s_%0.0f',app.input_parameter_KDP_data{row_IPM_KDP,1},app.selected_VC);
                        app.(app.input_parameter_KDP_data{row_IPM_KDP,1}).Value = app.(KDP_input_name).Value;
                    end
                    break
                end
            end
            
            % Update all values of input parameter matrix to the app.input.-values (Values in App)
            for row_UIP = 1:app.IPM_last_row
                app.input_parameter_matrix_DP{row_UIP,(app.selected_VC + 1)} = app.(app.input_parameter_matrix_DP{row_UIP,1}).Value;
            end
            
            % Update the Key Design Parameter values of input parameter matrix to the KDP-app.input.-values
            for i_VC_ticked = 1:app.VC_number_ticked
                for row_IPM_KDP = 1:app.IPM_KDP_last_row
                    KDP_input_name = sprintf('%s_%0.0f',app.input_parameter_KDP_data{row_IPM_KDP,1},i_VC_ticked);
                    app.input_parameter_matrix_DP{app.index_IPM_KDP(row_IPM_KDP,1),(app.VC_number_vec(i_VC_ticked)+1)} = app.(KDP_input_name).Value;
                end
            end
        end
        %------------------------------------------------------------------
        % Fill the values in the app with the values from the input parameter matrix
        function fill_input_parameters(app)
            
            % Eliminate input limits to fill input data
            eliminate_input_limits(app)
            
            % Fill the app.input.-values (Values in App) with the values from the input parameter matrix
            for row_FIP = 1:app.IPM_last_row
                
                %------------------------------------------------------------------
                % For debugging purposes
                % current_row = row_FIP
                %------------------------------------------------------------------

                app.(app.input_parameter_matrix_DP{row_FIP,1}).Value = app.input_parameter_matrix_DP{row_FIP,(app.selected_VC + 1)};
                
            end
            
            % Fill the KDP-app.input.-values with the values from the KDP-input parameter matrix
            for i_VC_ticked = 1:app.VC_number_ticked
                for row_IPM_KDP = 1:app.IPM_KDP_last_row
                    KDP_input_name = sprintf('%s_%0.0f',app.input_parameter_KDP_data{row_IPM_KDP,1},i_VC_ticked);
                    app.(KDP_input_name).Value = app.input_parameter_matrix_DP{app.index_IPM_KDP(row_IPM_KDP,1),(app.VC_number_vec(i_VC_ticked)+1)};
                end
            end

            % Update input limits
            update_input_limits(app)
            
            % Fill the Copy-Inputs
            app.input_seating_layout_copy.Value = app.input_parameter_matrix_DP{app.index_layout,(app.selected_VC+1)};
            
            % Update interior input rules
            input_int_n_rowsValueChanged(app)
            
        end
        %------------------------------------------------------------------
        % Get parameters necessary to build layout of the Design Parameters App
        function get_layout_parameters(app)
            [IPM_rows, IPM_columns] = size(app.input_parameter_matrix_DP);
            app.VC_number_tot = IPM_columns - 1;  % Total number of vehicle concepts form previous step
            app.IPM_last_row = IPM_rows;
            [IPM_KDP_rows,~] = size(app.input_parameter_KDP_data);
            app.IPM_KDP_last_row = IPM_KDP_rows;
        end
        %------------------------------------------------------------------
        % Initialize the layout for the Design Parameters App
        function initialize_layout(app)
            
            % Vehicle Concepts Selection Panel initialization (make vehicle concepts visible)
            for i_VC_number = 1 : app.VC_number_tot
                VC_label_name = sprintf('VehicleConcept%0.0fLabel',i_VC_number);
                VC_checkbox_name = sprintf('CheckBox%0.0f',i_VC_number);
                VDP_button_name = sprintf('VDP_Button_%0.0f',i_VC_number);
                app.(VC_label_name).Visible = 'on';
                app.(VC_checkbox_name).Visible = 'on';
                app.(VC_checkbox_name).Enable = 'on';
                app.(VDP_button_name).Visible = 'on'; 
            end

            % Vehicle Concepts Selection Panel initialization (make vehicle concepts not visible)
            if app.VC_number_tot < 14
                for i_VC_number = (app.VC_number_tot + 1) : 14
                    VC_label_name = sprintf('VehicleConcept%0.0fLabel',i_VC_number);
                    VC_checkbox_name = sprintf('CheckBox%0.0f',i_VC_number);
                    VDP_button_name = sprintf('VDP_Button_%0.0f',i_VC_number);
                    app.(VC_label_name).Visible = 'off';
                    app.(VC_checkbox_name).Visible = 'off';
                    app.(VC_checkbox_name).Enable = 'off';
                    app.(VDP_button_name).Visible = 'off';
                end
            end
        end 
        %------------------------------------------------------------------
        % Enables all features in the Design Parameters App
        function enable_all(app)
            app.KeyDesignParameterPanel.Enable = 'on';
            app.DetailedDesignParameterPanel.Enable = 'on';
            app.GobacktoappdefaultstatusButton.Enable = 'on';
            app.VehicleConceptsSelectionPanel.Enable = 'on';
            app.ProceedtoVehicleConceptsButton.Enable = 'on';
            app.DisplayOptionsPanel.Enable = 'on';
            app.ManipulateButton.Enable = 'on';
            app.SaveButton.Enable = 'on';
            app.ResetButton.Enable = 'on';
            app.LoadButton.Enable = 'on'; 
        end
        %------------------------------------------------------------------
        % Disables all features in the Design Parameters App
        function disable_all(app)
            app.KeyDesignParameterPanel.Enable = 'off';
            app.DetailedDesignParameterPanel.Enable = 'off';
            app.GobacktoappdefaultstatusButton.Enable = 'off';
            app.VehicleConceptsSelectionPanel.Enable = 'off';
            app.ProceedtoVehicleConceptsButton.Enable = 'off';
            app.DisplayOptionsPanel.Enable = 'off';
            app.ManipulateButton.Enable = 'off';
            app.SaveButton.Enable = 'off';
            app.ResetButton.Enable = 'off';
            app.LoadButton.Enable = 'off'; 
        end
        %------------------------------------------------------------------
        % Determinates a matrix with the ticked Vehicles
        function [tickMatrixVehicles] = checkBoxChecker(app)
            
            % Check the ticked Vehicles
            tickMatrixVehicles = zeros(app.VC_number_tot,1);
            for i_VC_number = 1:app.VC_number_tot
                checkBoxName = sprintf('CheckBox%d',i_VC_number);
                if app.(checkBoxName).Value == true
                    tickMatrixVehicles(i_VC_number) = 1;
                else
                    tickMatrixVehicles(i_VC_number) = 0;
                end
            end
            
            % Create VC_number_vec (vector with every ticked VC and its number)
            app.VC_number_ticked = nnz(tickMatrixVehicles);
            app.VC_number_vec = zeros(app.VC_number_ticked,1);
            num = 1;
            for i_VC_number = 1:app.VC_number_tot
                if tickMatrixVehicles(i_VC_number) == 1
                    app.VC_number_vec(num) = i_VC_number;
                    num = num + 1;
                end
            end
        end
        %------------------------------------------------------------------
        % Creates the layout for the Key Design Parameter panel
        function create_layout_key_design_parameter(app)
            
            % Key Design Parameter Panel initialization (make vehicle concepts visible)
            for i_VC_number_ticked = 1 : app.VC_number_ticked
                VC_panel_name = sprintf('VehicleConcept%0.0fPanel',i_VC_number_ticked);
                VC_panel_title = sprintf('Vehicle Concept %0.0f',app.VC_number_vec(i_VC_number_ticked));
                app.(VC_panel_name).Title = VC_panel_title;
                app.(VC_panel_name).Visible = 'on';
                %app.(VC_panel_name).Enable = 'on';
                if i_VC_number_ticked > 12
                    % Not possible to get into this if-loop because of
                    % CheckBox1ValueChanged-function but "break" still
                    % active to avoid crash of App in worst case or bug
                    break
                end
            end
            
            % Key Design Parameter Panel initialization (make vehicle concepts not visible)
            if app.VC_number_ticked < 12
                for i_VC_number_ticked = (app.VC_number_ticked + 1) : 12
                    VC_panel_name = sprintf('VehicleConcept%0.0fPanel',i_VC_number_ticked);
                    app.(VC_panel_name).Visible = 'off';
                end
            end
        end
        %------------------------------------------------------------------
        % Find index of Key Design Parameter (KDP) data in input parameter matrix (IPM)
        function find_KDP_input(app)
            app.index_IPM_KDP = zeros(app.IPM_KDP_last_row,1);
            for i_index_IPM_KDP = 1:app.IPM_KDP_last_row
                app.index_IPM_KDP(i_index_IPM_KDP,1) = find(strcmp(app.input_parameter_matrix_DP(:,1),app.input_parameter_KDP_data(i_index_IPM_KDP,1)));
            end
        end
        %------------------------------------------------------------------
        % Ticks all Vehicle Concepts from previous step
        function tick_all_VC(app)
            for i_VC_ticked = 1 : app.VC_number_tot
                checkbox_name = sprintf('CheckBox%0.0f',i_VC_ticked);
                app.(checkbox_name).Value = true;             
            end
        end
        %------------------------------------------------------------------
        % Unticks all Vehicle Concepts
        function untick_all_VC(app)
            for i_VC_ticked = 1 : app.VC_number_tot
                checkbox_name = sprintf('CheckBox%0.0f',i_VC_ticked);
                app.(checkbox_name).Value = false;             
            end
        end
        %------------------------------------------------------------------
        % Disables all inputs
        function disable_all_inputs(app)
            for row_DAI = 1:app.IPM_last_row
                app.(app.input_parameter_matrix_DP{row_DAI,1}).Enable = 'off';
            end
            for row_DAI = 1:12
                panel_name = sprintf('VehicleConcept%0.0fPanel',row_DAI);
                app.(panel_name).Enable = 'off';
            end
            
            % Copied inputs need to be disabled separately
            app.input_int_n_rows_copy.Enable = 'off';
            app.input_seats_row_1_copy.Enable = 'off';
            app.input_seats_row_2_copy.Enable = 'off';
            app.input_seats_row_3_copy.Enable = 'off';
            app.input_seating_layout_copy.Enable = 'off';
        end
        %------------------------------------------------------------------
        % Enables all inputs
        function enable_all_inputs(app)
            for row_DAI = 1:app.IPM_last_row
                app.(app.input_parameter_matrix_DP{row_DAI,1}).Enable = 'on';
            end
            for row_DAI = 1:12
                panel_name = sprintf('VehicleConcept%0.0fPanel',row_DAI);
                app.(panel_name).Enable = 'on';
            end
            app.SaveButton.Enable = 'on';
            app.LoadButton.Enable = 'on';
            app.ManipulateButton.Enable = 'on';
            app.ResetButton.Enable = 'on';
            app.ProceedtoVehicleConceptsButton.Enable = 'on';
            app.KeyParameterSelectionButton.Enable = 'on';
            app.DetailedParameterSelectionButton.Enable = 'on';
            app.VehicleConceptsSelectionPanel.Enable = 'on';
            
            % Copied inputs need to be disabled separately
            app.input_int_n_rows_copy.Enable = 'on';
            app.input_seats_row_1_copy.Enable = 'on';
            app.input_seats_row_2_copy.Enable = 'on';
            app.input_seats_row_3_copy.Enable = 'on';
            app.input_seating_layout_copy.Enable = 'on';
        end
       
        
        %__________________________________________________________________
                        
        %__________________________________________________________________
        %Setting up the data for Vehicle Concept graphs
        function [data,lbl,leg] = VCGraphData(app,VCGraphType)
            [numberVC,~] = size(app.CRPofVCs);
            
            %%Data for Vehicle Concept
            switch VCGraphType
                case 'Dynamics'
                    [dataTemp] = app.CRPofVCs(:,1:4);
                case 'Ergonomics'
                    [dataTemp] = app.CRPofVCs(:,5:10);
                case 'Security'
                    [dataTemp] = app.CRPofVCs(:,11:15);
                case 'Usability'
                    [dataTemp] = app.CRPofVCs(:,16:21);
                case 'DrivingStyle'
                    [dataTemp] = app.CRPofVCs(:,22:25);
                case 'Luxury'
                    [dataTemp] = app.CRPofVCs(:,26:28);
            end
            data = dataTemp';
            
            %%Label for Secondary Activities
            switch VCGraphType
                case 'Dynamics'
                    lbl = {'Axial Dynamics';'Lateral Dynamics';'Vertical Dynamics';'Maneuverability'};
                case 'Ergonomics'
                    lbl = {'Boarding Comfort';'Boarding Time';'Leg Room';'Shoulders Room';'Head Room';'Interior Acoustics'};
                case 'Security'
                    lbl = {'Passive Safety';sprintf('External \nCommunication');sprintf('Interior \nRecognition');'Environmental Monitoring';...
                        'Active Safety'};
                case 'Usability'
                    lbl = {'Bad Road Capability';'Luggage Space';'Radius of Movement';'Costs';'Ecology';'Passengers'};
                case 'DrivingStyle'
                    lbl = {'DS Comfort';'DS Safety';'DS Time Potential';'DS Consumption'};
                case 'Luxury'
                    lbl = {'Built-In Infotainment';'Info Individualization';'Exterior Design'};
            end
                                   
            %%Legend entries
            leg = cell(1,numberVC);
            for i=1:numberVC
                leg{i}=sprintf('Vehicle %d',i); 
            end
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Plot function for spider graph
        function plotSpiderGraph(app,data,lbl,ax,leg,fontsize,fts_tm,placement,colorcode,dashtype,additionalMark)
            % size segments and number of cases
            [r,s] = size(data);
            
            min_max_rng = [0 10];
            rng = repmat (min_max_rng, r, 1);
            
            %Clean up the initial plot
            legend(ax,'off'); reset(ax); cla(ax); hold(ax,'on'); ax.BackgroundColor = [1,1,1];
            
            % set to add plot
            set(ax,'nextplot','add');
            
            % clear figure and set limits
            set(ax,'visible','off'); set(ax,'color','w');        %makes 2D plot invisible and sets background color white
            set(ax,'xlim',placement(1,:),'ylim',placement(2,:)); axis(ax,'equal','manual') %Defines size of the plot
            
            ax.Visible = 'off'; set(ax,'color','w');
            
            % scale by range
            angw = linspace(0,2*pi,r+1)';
            % wrap data (close the last axis to the first)
            ang = angw(1:end-1); 
            angs = [ang(:); ang(1)];
            
            % make the plot
            % define the axis locations
            start_ax = [zeros(1,r)+0.5; sin(ang')+0.5];
            stop_ax = [zeros(1,r); cos(ang')];
            
            % Transformation of data
            start = [zeros(1,r); sin(ang')];
            A_xaxis = data .* start(2,:)';
            A_xaxis = [A_xaxis; A_xaxis(1,:)];
            A_xaxis = A_xaxis *(1/10)+0.5;
            
            stop = [zeros(1,r); cos(ang')];
            A_yaxis = data .* stop(2,:)';
            A_yaxis = [A_yaxis; A_yaxis(1,:)];
            A_yaxis = A_yaxis *(1/10);
            
            % plot the axes
            plot(ax,start_ax,stop_ax,'color','k','linestyle','-','linewidth', 1); hold(ax, 'on'); axis equal; % open figure...
            
            % plot axes markers
            inc = (1/5):(1/5):1; 
            tx = 0.05 * ones(1,5);
            
            %fontsize tick marks
            fonttm = fts_tm;
            
            %fontsize legend
            fontleg = fontsize-4;
            % loop each axis ang plot the line markers and labels
            % add axes
            for ii = 1:r
            	% plot tick lines
                plot(ax,[sin(angs(ii)) * inc; sin(angs(ii+1)) * inc] +0.5, ...
            		[(cos(angs(ii)) * inc); cos(angs(ii+1)) * inc],'color','k','linewidth', 0.75);
                
            	% label the tick marks
            	for jj = 1:5
                    temp = text(ax,sin(angs(ii)) * inc(jj) + cos(angs(ii)) * tx(jj)+0.5, ...
            			cos(angs(ii)) * inc(jj) - sin(angs(ii)) * tx(jj), ...
            			num2str(rd(app,rng(ii,1) + inc(jj)*diff(rng(ii,:)),-2)), ...
            			'fontsize',fonttm, 'FontName', 'Arial');
            		% flip the text alignment for lower axes
            		if angs(ii) >= pi
            			set(temp,'HorizontalAlignment','right');
            		end
            	end
            	% label each axis
            	temp = text(ax,sin(angs(ii)) * 1.1 + cos(angs(ii)) * 0+0.5, cos(angs(ii)) * 1.1 - sin(angs(ii)) * 0, ...
        			char(lbl(ii,:)),'fontsize',fontsize, 'FontName', 'Arial');
            	% flip the text alignment for right side axes
            	if angs(ii) >= pi
            		set(temp,'HorizontalAlignment','right');
            	end
            end
            
            %Draw additional custom lines
            if additionalMark == 1
                % Transformation of data
                dataMyLine = ones(1,s)*4;
                A_xaxis2 = dataMyLine .* start(2,:)';
                A_xaxis2 = [A_xaxis2; A_xaxis2(1,:)];
                A_xaxis2 = A_xaxis2 *(1/10)+0.5;
                A_yaxis2 = dataMyLine .* stop(2,:)';
                A_yaxis2 = [A_yaxis2; A_yaxis2(1,:)];
                A_yaxis2 = A_yaxis2 *(1/10);
                
                % Draw the line
                plot(ax,A_xaxis2,A_yaxis2,"Color",'red',"LineWidth",1.5)
            end
            
            %plot the data
            o = plot(ax,A_xaxis,A_yaxis);
            
            % define data case colors
            CRPCol = app.ColorVC;
            
            % define data case linestyles
            if dashtype == 0
                vehiclesLinsty = {'--'; '--'; '--'; '--'; '--'; '--';'--'; '--'; '--'; '--'; '--'; '--';'--'; '--'};
            elseif dashtype == 1
                vehiclesLinsty = {'-.';'-.';'-.';'-.';'-.';'-.';'-.';'-.';'-.';'-.';'-.';'-.';'-.';'-.'};
            end
            
            % set color
            switch colorcode
                case 0                                       
                    %Setting the color for all VCs accordingly                   
                    for ii=1:s
                        set(o(ii),'Color',CRPCol(ii,:), ...
                            'LineWidth', 1, 'LineStyle', vehiclesLinsty{ii});                        
                    end
                    
                    % Highlight the selected VC (Color and Linewidth)
                    set(o(app.selected_VC),'Color',CRPCol(app.selected_VC,:), 'LineWidth', 3, 'LineStyle', '-');                 
            end
            
            legend(o, leg,'Location','southoutside','Orientation',"horizontal",'NumColumns',4,'FontName', 'Arial', 'fontsize',fontleg);
            hold on
            close
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %quick round function (to specified decimal)
        function [v] = rd(~,v,dec)
            if nargin == 1; dec = 0; end
            
            v = v / 10^dec;
            v = round(v);
            v = v * 10^dec;
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        function plotCRPs(app)
           % Update the CRP view
            placement = [-1.1,1.1;-1.1,1.1];
            %Plot Dynamics
            [data,lbl,legVC] = VCGraphData(app,'Dynamics');
            plotSpiderGraph(app,data,lbl,app.DynamicsGraph,legVC,12,9,placement,0,0,1)
            %Plot Ergonomics
            [data,lbl,legVC] = VCGraphData(app,'Ergonomics');
            plotSpiderGraph(app,data,lbl,app.ErgonomicsGraph,legVC,12,9,placement,0,0,1)
            %Plot Security
            [data,lbl,legVC] = VCGraphData(app,'Security');
            plotSpiderGraph(app,data,lbl,app.SecurityGraph,legVC,12,9,placement,0,0,1)
            %Plot Usability
            [data,lbl,legVC] = VCGraphData(app,'Usability');
            plotSpiderGraph(app,data,lbl,app.UsabilityGraph,legVC,12,9,placement,0,0,1)
            %Plot Driving Style
            [data,lbl,legVC] = VCGraphData(app,'DrivingStyle');
            plotSpiderGraph(app,data,lbl,app.DrivingStyleGraph,legVC,12,9,placement,0,0,1)
            %Plot Luxury
            [data,lbl,legVC] = VCGraphData(app,'Luxury');
            plotSpiderGraph(app,data,lbl,app.LuxuryGraph,legVC,12,9,placement,0,0,1)
        end           
        %__________________________________________________________________
        

        %------------------------------------------------------------------
        % This function eliminates the input limits for seating layout
        % in the Key Design Parameter to fill in the new data
        function eliminate_input_limits(app)
            for i_panel_KDP = 1:app.VC_number_tot
                seating_layout_input_name = sprintf('input_seating_layout_%0.0f',i_panel_KDP);
                app.(seating_layout_input_name).Items = {'Vis-a-Vis','Conventional','Single Row','Back-to-Back','Vis-à-Vis in front','Vis-à-Vis in back'};
            end   
            app.input_seating_layout_copy.Items = {'Vis-a-Vis','Conventional','Single Row','Back-to-Back','Vis-à-Vis in front','Vis-à-Vis in back'};
        end
        %------------------------------------------------------------------
        % This function updates the input limits for seating layout
        % in the Key Design Parameter
        function update_input_limits(app)
            for i_panel_KDP = 1:app.VC_number_ticked
                seating_layout_input_name = sprintf('input_seating_layout_%0.0f',i_panel_KDP);
                % Find index for input: number of rows
                index_nor = find(strcmp(app.input_parameter_matrix_DP(:,1),'input_int_n_rows'));
                switch_value =  app.input_parameter_matrix_DP{index_nor,(app.VC_number_vec(i_panel_KDP,1)+1)}; %#ok<FNDSB> 
                % Update input limits for given number of rows
                switch switch_value
                    case 1
                        app.(seating_layout_input_name).Items = {'Single Row'};
                        app.(seating_layout_input_name).Value = 'Single Row';
                    case 2
                        app.(seating_layout_input_name).Items = {'Vis-a-Vis','Conventional','Back-to-Back'};  
                    case 3
                        app.(seating_layout_input_name).Items = {'Conventional','Vis-à-Vis in front','Vis-à-Vis in back'};
                end
            end
        end
        %------------------------------------------------------------------
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, input_parameter_matrix, currentCRP, CustomerRelevantPropertiesVC, TechnicalPropertiesVC, Derivatives)
            % Adding necessary folders to path
            addpath(genpath('99_General_Elements\'))
            addpath(genpath('09_Design Parameters\'))
            addpath(genpath('10_Simulation_DP2VC\'))
            addpath(genpath('11_Vehicle Concept\'))
            
            % Check if data in the workspace is available
            dataExistCheck = exist('input_parameter_matrix',"var");
            if dataExistCheck == 0
                uiconfirm(app.MainScreenDP,'App was loaded with no dataset. Please load manually a predefined dataset with the "Load" Button',...
                    'No dataset chosen','Options',{'Ok'},'Icon','warning');
                disable_all(app);
                app.LoadButton.Enable = 'on';
            else
                % Defining the input parameters
                app.input_parameter_matrix_DP = input_parameter_matrix;
                app.input_parameter_matrix_DP_backup = input_parameter_matrix;
                app.CRPofVCs = currentCRP;
                app.CRP = CustomerRelevantPropertiesVC;
                app.TPofVCs = TechnicalPropertiesVC;
                app.Derivative = Derivatives;
                app.index_layout = find(strcmp(app.input_parameter_matrix_DP(:,1),'input_seating_layout'));
                
                % Select Vehicle Concept 1
                app.selected_VC = 1;
                
                %------------------------------------------------------------------
                % Startup functions
                % Get parameters necessary to build the layout at start
                get_layout_parameters(app);
                
                % Find the input-indices for the KDP-data (input-names in Key Design Parameter)
                find_KDP_input(app);
                
                % Tick all VC for standard view at start
                tick_all_VC(app);
                
                % Check which VC are ticked in the checkbox group
                checkBoxChecker(app);
                
                % Create the layout for KDP
                create_layout_key_design_parameter(app);
                
                % Initialize the layout of the Design Parameters App
                initialize_layout(app);
                
                % Fill the input parameters from input_parameter_matrix to values in DP-App
                fill_input_parameters(app);
                
                % Initialize the interior related behavir
                input_int_n_rowsValueChanged(app);
                                
                % Disable the inputs until user wants to manipulate
                disable_all_inputs(app);
                %------------------------------------------------------------------
            end

            % Activate Key Design Parameter Panel View
            KeyParameterSelectionButtonPushed(app);
            app.Create3DModelButton.Visible = 'off';
            app.appstate = 0;
            app.GobacktoappdefaultstatusButton.Visible = 'off';
            
            % Opening the window maximized
            drawnow;
            app.MainScreenDP.WindowState = 'maximized';
        end

        % Button pushed function: KeyParameterSelectionButton
        function KeyParameterSelectionButtonPushed(app, event)
            % Activates Key Design Parameter Panel View
            app.KeyDesignParameterPanel.Visible = 'on';
            app.DetailedDesignParameterPanel.Visible = 'off';
            app.VDP_ButtonGroup.Visible = 'off';
        end

        % Button pushed function: DetailedParameterSelectionButton
        function DetailedParameterSelectionButtonPushed(app, event)
            % Activates Detailed Design Parameter Panel View
            app.KeyDesignParameterPanel.Visible = 'off';
            app.DetailedDesignParameterPanel.Visible = 'on';
            app.VDP_ButtonGroup.Visible = 'on';
            update_input_parameters(app)
            fill_input_parameters(app)
            
            % Copy interior inputs based on current seating layout
            input_seats_row_2ValueChanged(app);
            update_input_parameters(app);
            fill_input_parameters(app);
        end

        % Button pushed function: ProceedtoVehicleConceptsButton
        function ProceedtoVehicleConceptsButtonPushed(app, event)
            % Update input parameter before starting the AuVeCoDe-Tool
            update_input_parameters(app);
          
            % Open a confirmation dialog box
            fig = app.MainScreenDP;
            selection = uiconfirm(fig,('Do you want to continue to the next step?'),'Confirm optimization start','Icon','question');
            confirm_continue = strcmp(selection,'OK');

            % Choose 6 VC for VehicleConceptsResults-App
            if confirm_continue == true
                % Disable all inputs
                disable_all_inputs(app);
                app.SaveButton.Enable = 'off';
                app.LoadButton.Enable = 'off';
                app.ManipulateButton.Enable = 'off';
                app.ResetButton.Enable = 'off';
                app.ProceedtoVehicleConceptsButton.Enable = 'off';
                app.ResetButton.Enable = 'on'; % Alpha version (delete in final tool)
                
                % Show App-reset button
                app.GobacktoappdefaultstatusButton.Visible = 'on';
                
                % Show all Vehicle Concepts
                tick_all_VC(app);
                checkBoxChecker(app);
                create_layout_key_design_parameter(app);
                
                % Untick all Vehicle Concepts
                untick_all_VC(app);
                
                % Choose up to 6 Vehicle Concepts
                fig = app.MainScreenDP;
                message = sprintf(['Please select up to six vehicle concepts you would like to simulate. \n\n' ...
                    'You can select the vehicle concepts by checking them in the vehicle concepts selection. \n\n' ...
                    'To start the simulation process click on the button: Create 3D-Model. \n\n' ...
                    'If you push this button without selecting any vehicle concept, the app will crash!!']);
                uialert(fig,message,'Vehicle selection info box','Icon','info');
                
                % Standard view
                KeyParameterSelectionButtonPushed(app);
                app.Create3DModelButton.Visible = 'on';
                
                % Activate appstate to VC selection process
                app.appstate = 1;
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Updates current changes and saves the input parameter matrix
            update_input_parameters(app);
            
            % Save function not implemented yet
            filename = uiputfile('*.mat','myScenario_DP','09_Design Parameters\DP_Saved_Scenarios\');
                if filename ~= 0
                    input_parameter_matrix = app.input_parameter_matrix_DP;
                    currentCRP = app.CRPofVCs;
                    CustomerRelevantPropertiesVC = app.CRP;
                    TechnicalPropertiesVC = app.TPofVCs;
                    Derivative = app.Derivative;
                    
                    newName = append('09_Design Parameters\DP_Saved_Scenarios\',filename);
                    save(newName,'input_parameter_matrix', 'currentCRP', 'CustomerRelevantPropertiesVC', 'TechnicalPropertiesVC', 'Derivative');
                end           
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Open the dialog box to choose .mat file
            filename = uigetfile('09_Design Parameters\DP_Saved_Scenarios\','*.mat');
            if filename ~= 0
                load(filename,'input_parameter_matrix', 'currentCRP', 'CustomerRelevantPropertiesVC', 'TechnicalPropertiesVC', 'Derivative');
                
                % Call StartUp-Function
                startupFcn(app, input_parameter_matrix, currentCRP, CustomerRelevantPropertiesVC, TechnicalPropertiesVC, Derivative);

                % Enabling the view in case of non-parameter loaded start
                enable_all(app);
            end
        end

        % Selection changed function: VDP_ButtonGroup
        function VDP_ButtonGroupSelectionChanged(app, event)
            % Activated when switching between VC in Detailed Design Parameter Selection
            % Data of selected (old) VC will be updated to input_parameter_matrix
            update_input_parameters(app)
            selectedButton = app.VDP_ButtonGroup.SelectedObject;
            for i_button = 1:app.VC_number_tot
                button_name = sprintf('VDP_Button_%0.0f', i_button);
                if selectedButton == app.(button_name)
                    app.selected_VC = i_button;
                    break
                end
            end
            % Data of selected (new) VC will be filled to input-values of DP-App
            fill_input_parameters(app);
            
            % Update inputs regarding number of seat rows
            input_int_n_rowsValueChanged(app);
            
            % Copy interior inputs based on current seating layout
            input_seats_row_2ValueChanged(app);
            update_input_parameters(app);
            
            % plot the CRPs of the VCs
            if app.TabGroup.SelectedTab == app.CustomerrelevantPropertiesTab
                plotCRPs(app);
            end
        end

        % Button pushed function: GobacktoappdefaultstatusButton
        function GobacktoappdefaultstatusButtonPushed(app, event)
            % Button resets app status in case of tool crash
            app.appstate = 0;
            enable_all_inputs(app);
            app.Create3DModelButton.Visible = 'off';
            app.GobacktoappdefaultstatusButton.Visible = 'off';
            fill_input_parameters(app);
        end

        % Button pushed function: Create3DModelButton
        function Create3DModelButtonPushed(app, event)
            
            % Open a confirmation dialog box
            fig = app.MainScreenDP;
            selection = uiconfirm(fig,('Do you want to start the simulation?'),'Confirm simulation start','Icon','question');
            confirm_simu = strcmp(selection,'OK');
                        
            % Preperation for simulation start
            if confirm_simu == true
                
                % Disable all inputs except the Reset-Button
                app.KeyParameterSelectionButton.Enable = 'off';
                app.DetailedParameterSelectionButton.Enable = 'off';
                app.VehicleConceptsSelectionPanel.Enable = 'off';
                
                % Update parameters before function to convert input to AuVeCoDe parameters
                update_input_parameters(app);
                
                % Fill parameters one last time before AuVeCoDe start
                checkBoxChecker(app);
                fill_input_parameters(app)
                
                % Delete existing 3D-Model Figures and ISO Views
                delete('11_Vehicle Concept\temp\01_3D_Model_Figures\*.fig');                
                
                % Create vector for the feasibility of the VC
                VC_feasibility = zeros(app.VC_number_ticked,1);
                
                % Create vector for the error messages of the VC
                VC_error_msg = cell(app.VC_number_ticked,1);
                
                app.i_feasible = 1; % Input variable for AuVeCoDe-tool to name 3D-Figures after the VC-Number
                
                %prepare VC_result cell for the for-loop
                VC_result = cell(27,(app.VC_number_ticked +1));
                
                % Iteration for simulation
                
                for iteration_AuVeCoDe = 1:app.VC_number_ticked
                    
                    % Fill the input parameters in App (for read-function in AuVeCoDe-tool)
                    for row_C3D = 1:app.IPM_last_row
                        app.(app.input_parameter_matrix_DP{row_C3D,1}).Value = ...
                            app.input_parameter_matrix_DP{row_C3D,(app.VC_number_vec(iteration_AuVeCoDe) + 1)};
                    end
                    
                    % Check for 'Vis-à-Vis in front' seating layout and swap values
                    % Needed for row input data of 2nd row (3rd row input
                    % data isnt used in AuVeCoDe)
                    if app.input_seating_layout.Value == "Vis-à-Vis in front"
                        [IP_ird_last_row,~] = size(app.input_parameter_int_row_data);
                        for i_ird = 1:IP_ird_last_row
                            int_input_name_2 = sprintf('%s_2',app.input_parameter_int_row_data{i_ird,1});
                            int_input_name_3 = sprintf('%s_3',app.input_parameter_int_row_data{i_ird,1});
                            app.(int_input_name_2).Value = app.(int_input_name_3).Value;
                        end
                    end
                    
                    % Create progress figure with progressing bar
                    progress_fig = uiprogressdlg(fig,'Title','Simulation in progress - Please Wait','Message','Simulating Vehicle Concepts...');
                    progress_message = sprintf('Simulating Vehicle Concept %0.0f...    (%0.0f/%0.0f)', ...
                        app.VC_number_vec(iteration_AuVeCoDe),iteration_AuVeCoDe,app.VC_number_ticked);
                    progress_fig.Message = progress_message;
                    % If-loop needed if only one VC is ticked
                    if app.VC_number_ticked > 1
                        progress_fig.Value = (iteration_AuVeCoDe - 1)/(app.VC_number_ticked - 1);
                    end
                    
                    %------------------------------------------------------------------
                    % AuVeCoDe-Tool:
                    % Author:                   Adrian König
                    % Last modification:        May 2022
                    
                    % Starts the simulation with the current input (AuVeCoDe)
                    if  app.sing_veh.Value==1 % Single vehicle is selected                        
                        pause(0.1); % pause required to update the GUI
                        Parameters=ConvertUserform(app); %starts conversion of app to Parameter struct
                        [vehicle,~,Msg]=Startfile_AuVeCoDe(Parameters,app); %STARTS SIMULATION
                        pause(0.1); % pause required to update the GUI
                        
                    else % optimization is selected (not possible in current GUI)
                        pause(0.1); % pause required to update the GUI
                        Parameters = ConvertUserform(app); %starts conversion of app to Parameter struct
                        [vehicle,~,Msg] = Startfile(Parameters,app); %STARTS SIMULATION
                        pause(0.1); % pause required to update the GUI
                    end
                    %------------------------------------------------------------------
                    
                    % If-loop for 3D-Model name in AuVeCoDe tool
                    if vehicle.feasible == 1
                        app.i_feasible = app.i_feasible + 1;
                    end
                    
                    % Feasibility vector filled with 0 or 1 (0 = VC not feasible; 1 = VC feasible)
                    VC_feasibility(iteration_AuVeCoDe) = vehicle.feasible;
                    
                    % Error message vector
                    VC_error_msg{iteration_AuVeCoDe} = Msg;
                                                           
                    % Result Matrix values
                    
                    if vehicle.feasible == 1
                        VC_result{3,(iteration_AuVeCoDe+1)} = round(vehicle.dimensions.GX.vehicle_length, 2);
                        VC_result{4,(iteration_AuVeCoDe+1)} = round(vehicle.dimensions.GY.vehicle_width, 2);
                        VC_result{5,(iteration_AuVeCoDe+1)} = round(vehicle.dimensions.GZ.vehicle_height, 2);
                        VC_result{6,(iteration_AuVeCoDe+1)} = round(vehicle.Cost.Manufacturing_Cost, 2); 
                        VC_result{7,(iteration_AuVeCoDe+1)} = round(vehicle.masses.vehicle_empty_weight, 2);
                        VC_result{8,(iteration_AuVeCoDe+1)} = round(vehicle.masses.vehicle_max_weight, 2); 
                        VC_result{9,(iteration_AuVeCoDe+1)} = round(vehicle.LDS.range, 2); 
                        VC_result{16,(iteration_AuVeCoDe+1)} = round(vehicle.LDS.sim_cons.consumption100km, 2);
                        VC_result{17,(iteration_AuVeCoDe+1)} = round(vehicle.Input.eco_driving, 2);
                        veh.(sprintf('vehicle%0.0f', iteration_AuVeCoDe)) = vehicle;
                        
                    end
                end
                
                close(progress_fig);
                
                                                              
                 %------------------------------------------------------------------
                % Code snippet for creation of VC_result matrix
                % n-columns:    n-Vehicle Concepts
                % 1st row:      VC number  
                % 2nd row:      VC feasible (0 = not feasible; 1 = feasible)
                % 3rd row: ...
                VC_result(:,1) = {'Vehicle Concept Number';'Vehicle feasible';'Vehicle length in mm';'Vehicle width in mm';'Vehicle height in mm';...
                    'Total costs in EUR';'Total weigth empty in kg';'Total Max weigth in kg';... 
                    'Range in km';'Storage Volume in l';'  ';'Driving Style: Comfort - Jerk in m/s^3';...
                    'Driving Style: Comfort - Acceleration in m/s^2';'Driving Style: Safety - Homeostatic Risk Perception';...
                    'Driving Style: Time Potential - Fastness';'Driving Style: Consumption per 100km in kWh';...
                    'Eco-Driving Savings in %';'  '; 'Disengagement per 100.000km'; '  ';...
                    'Averaged Sound Pressure Level in dB'; 'Screen Area per Seat in cm^2'; 'External Communication in CRP';...
                    'Interior Recognition in CRP'; 'Infotainment Individualization in CRP'; '  '; 'External Design in CRP'};
                VC_result(1,2:(app.VC_number_ticked + 1)) = num2cell(app.VC_number_vec);
                VC_result(2,2:(app.VC_number_ticked + 1)) = num2cell(VC_feasibility); 
                
                for ii = 1:app.VC_number_ticked
                    %Storage Volume
                    if app.Derivative(ii,6) <=5
                        VC_result{10,ii+1} = app.TPofVCs(app.VC_number_vec(ii), 17);
                    else
                        VC_result{10,ii+1} = app.TPofVCs(app.VC_number_vec(ii), 18);
                    end
                    % Driving Style
                    VC_result{12,ii+1} = app.TPofVCs(app.VC_number_vec(ii), 23); %Comfort - Jerk
                    VC_result{13,ii+1} = app.TPofVCs(app.VC_number_vec(ii), 24); %Comfort - Acceleration
                    VC_result{14,ii+1} = app.TPofVCs(app.VC_number_vec(ii), 25); %Safety - homeostatic risk perception
                    VC_result{15,ii+1} = app.TPofVCs(app.VC_number_vec(ii), 26); %Time Potential - Fastness
                    VC_result{19,ii+1} = app.TPofVCs(app.VC_number_vec(ii), 14); %Disengagement per 100.000km
                    VC_result{21,ii+1} = app.TPofVCs(app.VC_number_vec(ii), 12); %Averaged Sound Pressure Level
                    VC_result{22,ii+1} = app.TPofVCs(app.VC_number_vec(ii), 28); %Screen Area per Seat
                    VC_result{23,ii+1} = app.CRPofVCs(app.VC_number_vec(ii), 12); %External Communication
                    VC_result{24,ii+1} = app.CRPofVCs(app.VC_number_vec(ii), 13); %Interior Recognition
                    VC_result{25,ii+1} = app.CRPofVCs(app.VC_number_vec(ii), 27); %Infotainment Individualization
                    VC_result{27,ii+1} = app.CRPofVCs(app.VC_number_vec(ii), 28); %External Design
                    
                end
                
                
                %------------------------------------------------------------------
                
                % Check for feasibility
                VC_not_feasible = zeros(nnz(~VC_feasibility),1);
                VC_not_feasible_error_msg = cell(nnz(~VC_feasibility),1);
                VC_number = zeros(nnz(VC_feasibility),1);
                
                % Create vectors for feasible and non feasible VC with their number
                num_non_feasible = 1;
                num_feasible = 1;
                for i_VC_results = 1:app.VC_number_ticked
                    if VC_feasibility(i_VC_results) == 0
                        VC_not_feasible(num_non_feasible) = app.VC_number_vec(i_VC_results); % Vector with non-feasible VC
                        VC_not_feasible_error_msg{num_non_feasible} = VC_error_msg{i_VC_results}; % Cell with non-feasible VC error messages
                        num_non_feasible = num_non_feasible + 1;
                    elseif VC_feasibility(i_VC_results) == 1
                        VC_number(num_feasible) = app.VC_number_vec(i_VC_results); % Vector with feasible VC
                        num_feasible = num_feasible + 1;
                    end
                end
                
                % prepare futher data for saving and for VC App
                input_parameter_matrix_VC = app.input_parameter_matrix_DP;
                currentCRP = app.CRPofVCs;
                CustomerRelevantPropertiesVC = app.CRP;
                
                if num_non_feasible == 1
                    % All VC are feasible, no need to warn the user
                    VCR_state = 1; % State to start the next app
                else
                    % Some VC are not feasible, ask user to continue or to adjust parameters
                    msg_not_feasible_total = '';
                    msg_before = sprintf('The following Vehicle Concepts could not be simulated:\n\n');
                    for i_msg = 1:(num_non_feasible-1)
                        msg_not_feasible = sprintf('- Vehicle Concept %0.0f\nReason: %s\n\n',VC_not_feasible(i_msg),VC_not_feasible_error_msg{i_msg});
                        msg_not_feasible_total = append(msg_not_feasible_total,msg_not_feasible);
                    end
                    msg_after = sprintf(['The results for these Vehicle Concepts cannot be shown in the next App!\n\n' ...
                        'Do you nonetheless want to continue to the Vehicle Concept Results App ' ...
                        'or do you want to go back and adjust the parameters for these Vehicle Concepts?\n\n' ...
                        'If the reason for non simulated VC is "Loop Iteration Counter = Max. Iteration" consider increasing the turning diameter or decreasing the steering distribution.']);
                    msg_total = append(msg_before,msg_not_feasible_total,msg_after);
                    
                    fig_feasible = app.MainScreenDP;
                    selection = uiconfirm(fig_feasible,msg_total,'Some Vehicle Concepts are not feasible','Icon','warning', ...
                        'Options',{'Continue','Adjust parameters'},'DefaultOption',1);
                    
                    % User decision to continue or to abort and adjust parameters
                    switch selection
                        case 'Continue'
                            VCR_state = 1; % State to start the next app
                        case 'Adjust parameters'
                            VCR_state = 0; % State to abort and return to DP-App
                    end
                end

                if VCR_state == 1
                    % User decided to continue or all VC feasible
                    %Save the results of the simulation if the user agrees
                    selection2 = uiconfirm(fig,'Do you want to save the results?','Saving Results','Icon','question');
                    tf2 = strcmp(selection2,'OK');
                    if tf2 == true
                        filename = uiputfile('*.mat','myScenario_VC','11_Vehicle Concept\VC_Saved_Scenarios\');
                        if filename ~= 0
                            newName = append('11_Vehicle Concept\VC_Saved_Scenarios\',filename);
                            save(newName,'VC_result','VC_number', 'input_parameter_matrix_VC', 'currentCRP', 'CustomerRelevantPropertiesVC', 'veh')
                            
                            foldername = filename(1:end-4);
                            newFolder = append('11_Vehicle Concept\VC_Saved_Scenarios\3D_models\', foldername);
                            
                            if isfolder(newFolder)
                                delete(append(newFolder, '\*'));
                                                                
                            else                            
                                mkdir(newFolder);
                            end
                            
                            copyfile('11_Vehicle Concept\temp\01_3D_Model_Figures\*.fig', newFolder);                            
                        end
                        
                    end
           
                    % Open Vehicle Concept App
                    selection3 = uiconfirm(fig,'Do you want to open the Vehicle Concepts app?','Open Vehicle Concepts app','Icon','question');
                    tf3 = strcmp(selection3,'OK');
                    if tf3 == true
                        % Open Vehicle Concept Results App                        
                        VehicleConcepts(VC_result,VC_number,input_parameter_matrix_VC,currentCRP,CustomerRelevantPropertiesVC);                        
                    end
                end
                % Reset View
                app.appstate = 0;
                enable_all_inputs(app);
                app.Create3DModelButton.Visible = 'off';
                app.GobacktoappdefaultstatusButton.Visible = 'off';
                fill_input_parameters(app);
            end            
        end

        % Value changed function: CheckBox1, CheckBox10, 
        % CheckBox11, CheckBox12, CheckBox13, CheckBox14, 
        % CheckBox2, CheckBox3, CheckBox4, CheckBox5, CheckBox6, 
        % CheckBox7, CheckBox8, CheckBox9
        function CheckBox1ValueChanged(app, event)
            % This funtion is to ensure the user doesn't check more than the allowed number of boxes
            update_input_parameters(app)
            [tickMatrixVehicles] = checkBoxChecker(app);
            
            if app.appstate == 1 
                % Create warning message if more than 6 boxes are checked
                % (max allowed number of VC for Vehicle Concept Results App)
                
                if app.VC_number_ticked > 6
                    error_boxes = app.MainScreenDP;
                    uialert(error_boxes,'Please select a maximum of 6 Vehicle Concepts','Maxium number of computable Vehicle Concepts exceeded','Icon','warning');
%                 elseif app.VC_number_ticked == 0
%                     error_boxes = app.MainScreenDP;
%                     uialert(error_boxes,'Please select a maximum of 6 Vehicle Concepts','Maxium number of computable Vehicle Concepts exceeded','Icon','warning');
                
                end
                
                
                % Uncheck the 7th checked box
                num = 1;
                for i=1:app.VC_number_tot
                    if tickMatrixVehicles(i) == 1
                        if num > 6
                            checkboxName = sprintf('CheckBox%d',i);
                            app.(checkboxName).Value = false;
                        end
                        num = num +1;
                    end                
                end
                checkBoxChecker(app);
            elseif app.appstate == 0
                % Create warning message if more than 12 boxes are checked
                % (max allowed number of VC for the display in the Key Design Parameter Selection)

                if app.VC_number_ticked > 12
                    error_boxes = app.MainScreenDP;
                    uialert(error_boxes,'Please select a maximum of 12 Vehicle Concepts','Maxium number of displayable Vehicle Concepts exceeded','Icon','warning');
                end
               
                % Uncheck the 13th checked box
                num = 1;
                for i=1:app.VC_number_tot
                    if tickMatrixVehicles(i) == 1
                        if num > 12
                            checkboxName = sprintf('CheckBox%d',i);
                            app.(checkboxName).Value = false;
                        end
                        num = num +1;
                    end  
                
                end
                checkBoxChecker(app);
            end
            
            % Use this function to update the view and input-data (no need to press "Update"-button)
            create_layout_key_design_parameter(app);
            fill_input_parameters(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Reset input values (From start of app: original input_paramter_matrix)
            
            % Open a confirmation dialog box
            fig_reset = app.MainScreenDP;
            msg_reset = sprintf(['Do you really want to reset all parameters?\n' ...
                'This will delete all your made parameter changes.\n' ...
                'Consider saving your manipulated parameters before.']);
            selection_reset = uiconfirm(fig_reset,msg_reset,'Confirm parameter reset','Icon','warning');
            tf_reset = strcmp(selection_reset,'OK');
            
            % Execute only when the user agrees
            if tf_reset == true
                % Fill app with values from backup matrix
                app.input_parameter_matrix_DP = app.input_parameter_matrix_DP_backup;
                fill_input_parameters(app)
            end
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            %Open a confirmation dialog box
            fig = app.MainScreenDP;
            message = sprintf(['Do you want to return to the Home screen? \n\n'...
                'Consider saving their current results. Otherwise it will be deleted.']);
            selection = uiconfirm(fig,message,'Confirm return to Home screen','Icon','question');
            tf = strcmp(selection,'OK');
            % Execute only when the user agrees
            if tf == true
                Start;
                delete(app);
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            % plot the CRPs of the VCs
            selectedTab = app.TabGroup.SelectedTab;
            if selectedTab == app.CustomerrelevantPropertiesTab
                plotCRPs(app);
            end
        end

        % Value changed function: input_int_n_rows
        function input_int_n_rowsValueChanged(app, event)
            % Find VC panel number
            VC_panel_check = 0;
            for i_VC_ticked = 1:app.VC_number_ticked
                if app.VC_number_vec(i_VC_ticked) == app.selected_VC
                    seating_layout_input_name = sprintf('input_seating_layout_%0.0f',i_VC_ticked);
                    VC_panel_check = 1;
                    break
                else
                    VC_panel_check = 0;
                end
            end
            
            % Update seat row related inputs (View and items of interior panel)
            switch app.input_int_n_rows.Value
                case 1
                    % VC with one seat row: Change seating layout to Single Row
                    % Check if VC panel is ticked
                    if VC_panel_check == 1
                        % If it's ticked change items and value
                        app.(seating_layout_input_name).Items = {'Single Row'};
                        app.(seating_layout_input_name).Value = 'Single Row';
                    else
                        % If not update the IPM
                        %app.input_parameter_matrix_DP{app.index_layout,(app.selected_VC + 1)} = "Single Row";
                    end
                    app.input_seating_layout_copy.Items = {'Single Row'};
                    app.input_seating_layout_copy.Value = 'Single Row';
                    
                    % Visibility of row panels
                    app.SecondrowPanel.Enable = 'off';
                    app.ThirdrowPanel.Enable = 'off';
                                        
                    % Visibility of row inputs in DP-Panel
                    app.input_seats_row_2_copy.Enable = 'off';
                    app.input_seats_row_3_copy.Enable = 'off';
                case 2 
                    % Change seating layout options for VC with 2 seat rows
                    if VC_panel_check == 1
                        app.(seating_layout_input_name).Items = {'Vis-a-Vis','Conventional','Back-to-Back'};
                    else
                        %app.input_parameter_matrix_DP{app.index_layout,(app.selected_VC + 1)} = "Conventional";
                    end
                    app.input_seating_layout_copy.Items = {'Vis-a-Vis','Conventional','Back-to-Back'};
                    
                    
                    if app.ManipulateButton.Value == 1
                        % Visibility of row panels
                        app.SecondrowPanel.Enable = 'on';
                        app.ThirdrowPanel.Enable = 'off';
                                        
                        % Visibility of row inputs in DP-Panel
                        app.input_seats_row_2_copy.Enable = 'on';
                        app.input_seats_row_3_copy.Enable = 'off';                        
                    end
                    
                case 3
                    % Change seating layout options for VC with 3 seat rows
                    if VC_panel_check == 1
                        app.(seating_layout_input_name).Items = {'Conventional','Vis-à-Vis in front','Vis-à-Vis in back'};
                    else
                        %app.input_parameter_matrix_DP{app.index_layout,(app.selected_VC + 1)} = "Conventional";
                    end
                    app.input_seating_layout_copy.Items = {'Conventional','Vis-à-Vis in front','Vis-à-Vis in back'};
                    
                    if app.ManipulateButton.Value == 1
                        % Visibility of row panels
                        app.SecondrowPanel.Enable = 'on';
                        app.ThirdrowPanel.Enable = 'on';
                                        
                        % Visibility of row inputs in DP-Panel
                        app.input_seats_row_2_copy.Enable = 'on';
                        app.input_seats_row_3_copy.Enable = 'on';
                    end
            end
            
            % Copy seat row input into DP-Panel
            app.input_int_n_rows_copy.Value = app.input_int_n_rows.Value;
            
            % Update interior related inputs
            input_seats_row_2ValueChanged(app);
            update_input_parameters(app);
        end

        % Value changed function: input_int_n_rows_copy
        function input_int_n_rows_copyValueChanged(app, event)
            % Copy input data to interior panel
            app.input_int_n_rows.Value = app.input_int_n_rows_copy.Value;
            input_int_n_rowsValueChanged(app);
        end

        % Value changed function: input_int_angle_br_max_1, 
        % input_int_angle_br_min_1, 
        % input_int_armrest_width_double_1, 
        % input_int_armrest_width_single_1, 
        % input_int_backrest_length_1, input_int_backrest_width_1, 
        % input_int_double_ar_1, input_int_foot_length_1, 
        % input_int_gaptowall_1, input_int_headroom_1, 
        % input_int_legroom_additional_1, 
        % input_int_seat_adjustment_x_1, 
        % input_int_seat_adjustment_z_1, input_int_seat_depth_1, 
        % input_int_seat_height_1, input_int_seatgap_1, 
        % input_int_single_ar_between_1, 
        % input_int_single_ar_outside_1, input_int_thickness_br_1, 
        % input_int_thickness_seat_1, input_int_upperbody_length_1, 
        % input_seats_row_1
        function input_seats_row_1ValueChanged(app, event)
            % Function for changing values in first row panel
            
            % AuVeCoDe-Tool is built to create packages of vehicles with up
            % to 2 rows. A third row is implemented, but only in the
            % 3D-Plot. This is done by increasing the legroom where needed
            % and copying an existing row as the third row in the newly
            % created space. Because rows will be copied, it's not possible
            % to have 3 distinct rows with different inputs in this tool!
            % For every seating layout there are two rows, which need to
            % have the same parameters, because one row will be the product
            % of a copy from the other row.
            
            % For seating layout "Vis-à-Vis in front" the first row will be
            % copied and mirrored to be plotted as the second row in the
            % middle. 
            % => First and second row always need to have the same parameters
            
            % Get height of input_parameter_int_row_data vector
            [app.IRD_last_row,~] = size(app.input_parameter_int_row_data);

            % When changing inputs in the first row panel, copy them to the
            % second row panel
            if app.input_seating_layout_copy.Value == "Vis-à-Vis in front"
                for i_IRD = 1:app.IRD_last_row
                    input_int_name_1 = sprintf('%s_1',app.input_parameter_int_row_data{i_IRD});
                    input_int_name_2 = sprintf('%s_2',app.input_parameter_int_row_data{i_IRD});
                    app.(input_int_name_2).Value = app.(input_int_name_1).Value;
                end
            end
            
            % Copy number of seats input from interior panel to DP-panel
            app.input_seats_row_1_copy.Value = app.input_seats_row_1.Value;
            app.input_seats_row_2_copy.Value = app.input_seats_row_2.Value;
        end

        % Value changed function: input_int_angle_br_max_2, 
        % input_int_angle_br_min_2, 
        % input_int_armrest_width_double_2, 
        % input_int_armrest_width_single_2, 
        % input_int_backrest_length_2, input_int_backrest_width_2, 
        % input_int_double_ar_2, input_int_foot_length_2, 
        % input_int_gaptowall_2, input_int_headroom_2, 
        % input_int_legroom_additional_2, 
        % input_int_seat_adjustment_x_2, 
        % input_int_seat_adjustment_z_2, input_int_seat_depth_2, 
        % input_int_seat_height_2, input_int_seatgap_2, 
        % input_int_single_ar_between_2, 
        % input_int_single_ar_outside_2, input_int_thickness_br_2, 
        % input_int_thickness_seat_2, input_int_upperbody_length_2, 
        % input_seats_row_2
        function input_seats_row_2ValueChanged(app, event)
            % Function for changing values in second row panel
            
            % AuVeCoDe-Tool is built to create packages of vehicles with up
            % to 2 rows. A third row is implemented, but only in the
            % 3D-Plot. This is done by increasing the legroom where needed
            % and copying an existing row as the third row in the newly
            % created space. Because rows will be copied, it's not possible
            % to have 3 distinct rows with different inputs in this tool!
            % For every seating layout there are two rows, which need to
            % have the same parameters, because one row will be the product
            % of a copy from the other row.
            
            % For seating layout "Conventional" the second row will be
            % copied to be plotted as the third row in the middle.
            % => Second and third row always need to have the same parameters
            
            % For seating layout "Vis-à-Vis in front" the first row will be
            % copied and mirrored to be plotted as the second row in the
            % middle.
            % => First and second row always need to have the same parameters
            
            % For seating layout "Vis-à-Vis in back" the second row will be
            % copied and mirrored to be plotted as the third row in the
            % middle.
            % => Second and third row always need to have the same parameters
            
            % Get height of input_parameter_int_row_data vector
            [app.IRD_last_row,~] = size(app.input_parameter_int_row_data);

            switch app.input_seating_layout_copy.Value
                case 'Conventional'
                    % When changing inputs in the second row panel, copy them to the
                    % third row panel
                    
                    % Make sure the conventional VC has 3 seat rows
                    if app.input_int_n_rows.Value == 3
                        for i_IRD = 1:app.IRD_last_row
                            input_int_name_2 = sprintf('%s_2',app.input_parameter_int_row_data{i_IRD});
                            input_int_name_3 = sprintf('%s_3',app.input_parameter_int_row_data{i_IRD});
                            app.(input_int_name_3).Value = app.(input_int_name_2).Value;
                        end
                    end
                case 'Vis-à-Vis in front'
                    % When changing inputs in the second row panel, copy them to the
                    % first row panel
                    for i_IRD = 1:app.IRD_last_row
                        input_int_name_1 = sprintf('%s_1',app.input_parameter_int_row_data{i_IRD});
                        input_int_name_2 = sprintf('%s_2',app.input_parameter_int_row_data{i_IRD});
                        app.(input_int_name_1).Value = app.(input_int_name_2).Value;
                    end    
                case 'Vis-à-Vis in back'
                    % When changing inputs in the second row panel, copy them to the
                    % third row panel
                    for i_IRD = 1:app.IRD_last_row
                        input_int_name_2 = sprintf('%s_2',app.input_parameter_int_row_data{i_IRD});
                        input_int_name_3 = sprintf('%s_3',app.input_parameter_int_row_data{i_IRD});
                        app.(input_int_name_3).Value = app.(input_int_name_2).Value;
                    end
            end
            
            % Copy number of seats input from interior panel to DP-panel
            app.input_seats_row_1_copy.Value = app.input_seats_row_1.Value;
            app.input_seats_row_2_copy.Value = app.input_seats_row_2.Value;
            app.input_seats_row_3_copy.Value = app.input_seats_row_3.Value;
        end

        % Value changed function: input_int_angle_br_max_3, 
        % input_int_angle_br_min_3, 
        % input_int_armrest_width_double_3, 
        % input_int_armrest_width_single_3, 
        % input_int_backrest_length_3, input_int_backrest_width_3, 
        % input_int_double_ar_3, input_int_foot_length_3, 
        % input_int_gaptowall_3, input_int_headroom_3, 
        % input_int_legroom_additional_3, 
        % input_int_seat_adjustment_x_3, 
        % input_int_seat_adjustment_z_3, input_int_seat_depth_3, 
        % input_int_seat_height_3, input_int_seatgap_3, 
        % input_int_single_ar_between_3, 
        % input_int_single_ar_outside_3, input_int_thickness_br_3, 
        % input_int_thickness_seat_3, input_int_upperbody_length_3, 
        % input_seats_row_3
        function input_seats_row_3ValueChanged(app, event)
            % Fuction for changing values in third row panel
            
            % AuVeCoDe-Tool is built to create packages of vehicles with up
            % to 2 rows. A third row is implemented, but only in the
            % 3D-Plot. This is done by increasing the legroom where needed
            % and copying an existing row as the third row in the newly
            % created space. Because rows will be copied, it's not possible
            % to have 3 distinct rows with different inputs in this tool!
            % For every seating layout there are two rows, which need to
            % have the same parameters, because one row will be the product
            % of a copy from the other row.
            
            % For seating layout "Conventional" the second row will be
            % copied to be plotted as the third row in the middle.
            % => Second and third row always need to have the same parameters
            
            % For seating layout "Vis-à-Vis in back" the second row will be
            % copied and mirrored to be plotted as the third row in the
            % middle.
            % => Second and third row always need to have the same parameters
            
            % Get height of input_parameter_int_row_data vector
            [app.IRD_last_row,~] = size(app.input_parameter_int_row_data);

            switch app.input_seating_layout_copy.Value
                case 'Conventional'
                    % When changing inputs in the third row panel, copy them to the
                    % second row panel
                    
                    % Make sure the conventional VC has 3 seat rows
                    if app.input_int_n_rows.Value == 3
                        for i_IRD = 1:app.IRD_last_row
                            input_int_name_2 = sprintf('%s_2',app.input_parameter_int_row_data{i_IRD});
                            input_int_name_3 = sprintf('%s_3',app.input_parameter_int_row_data{i_IRD});
                            app.(input_int_name_2).Value = app.(input_int_name_3).Value;
                        end
                    end
                    
                case 'Vis-à-Vis in back'
                    % When changing inputs in the third row panel, copy them to the
                    % second row panel
                    for i_IRD = 1:app.IRD_last_row
                        input_int_name_2 = sprintf('%s_2',app.input_parameter_int_row_data{i_IRD});
                        input_int_name_3 = sprintf('%s_3',app.input_parameter_int_row_data{i_IRD});
                        app.(input_int_name_2).Value = app.(input_int_name_3).Value;
                    end
            end
            
            % Copy number of seats input from interior panel to DP-panel
            app.input_seats_row_1_copy.Value = app.input_seats_row_1.Value;
            app.input_seats_row_2_copy.Value = app.input_seats_row_2.Value;
            app.input_seats_row_3_copy.Value = app.input_seats_row_3.Value;
        end

        % Value changed function: input_seating_layout_copy
        function input_seating_layout_copyValueChanged(app, event)
            % Copy interior inputs based on current seating layout
            input_seats_row_2ValueChanged(app);
            
            % Copy input data for seating layout into Key Design Parameter
            % Selection view
            update_input_parameters(app);
            app.input_parameter_matrix_DP{app.index_layout,(app.selected_VC + 1)} = app.input_seating_layout_copy.Value;
            fill_input_parameters(app);
        end

        % Value changed function: input_seats_row_1_copy
        function input_seats_row_1_copyValueChanged(app, event)
            % Copy seat row values from DP-Panel into interior panel
            app.input_seats_row_1.Value = app.input_seats_row_1_copy.Value;
            input_seats_row_1ValueChanged(app);
        end

        % Callback function
        function input_seats_row_2_copyValueChanged(app, event)
            % Copy seat row values from DP-Panel into interior panel
            app.input_seats_row_2.Value = app.input_seats_row_2_copy.Value;
            input_seats_row_2ValueChanged(app);            
        end

        % Value changed function: input_seats_row_3_copy
        function input_seats_row_3_copyValueChanged(app, event)
            % Copy seat row values from DP-Panel into interior panel
            app.input_seats_row_3.Value = app.input_seats_row_3_copy.Value;
            input_seats_row_3ValueChanged(app);            
        end

        % Value changed function: ManipulateButton
        function ManipulateButtonValueChanged(app, event)
            value = app.ManipulateButton.Value;
            % If on, enable all inputs to be able to manipulate them
            if value == 1
                enable_all_inputs(app);
            else
                disable_all_inputs(app);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainScreenDP = uifigure('Visible', 'off');
            app.MainScreenDP.Name = 'Design Parameters';
            app.MainScreenDP.Icon = 'FTMLogo.PNG';
            app.Step4ComponentSelectionforAutonomousVehicleConceptsLabel = uilabel(app.MainScreenDP);
            app.Step4ComponentSelectionforAutonomousVehicleConceptsLabel.Text = '              Step 4: Design Parameters for Autonomous, Shared and Electric Vehicle Concepts';
            app.MainMenuPanel = uipanel(app.MainScreenDP);
            app.MainMenuPanel.Title = 'Main Menu';
            app.DataOptionsPanel = uipanel(app.MainMenuPanel);
            app.DataOptionsPanel.Title = 'Data Options';
            app.LoadButton = uibutton(app.DataOptionsPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Tooltip = {''};
            app.LoadButton.Text = 'Load';
            app.ResetButton = uibutton(app.DataOptionsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tooltip = {''};
            app.ResetButton.Text = 'Reset';
            app.SaveButton = uibutton(app.DataOptionsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tooltip = {''};
            app.SaveButton.Text = 'Save';
            app.ManipulateButton = uibutton(app.DataOptionsPanel, 'state');
            app.ManipulateButton.ValueChangedFcn = createCallbackFcn(app, @ManipulateButtonValueChanged, true);
            app.ManipulateButton.Text = 'Manipulate';
            app.DisplayOptionsPanel = uipanel(app.MainMenuPanel);
            app.DisplayOptionsPanel.Title = 'Display Options';
            app.KeyParameterSelectionButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.KeyParameterSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @KeyParameterSelectionButtonPushed, true);
            app.KeyParameterSelectionButton.Tooltip = {''};
            app.KeyParameterSelectionButton.Text = 'Key Parameter Selection ';
            app.DetailedParameterSelectionButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.DetailedParameterSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @DetailedParameterSelectionButtonPushed, true);
            app.DetailedParameterSelectionButton.Tooltip = {''};
            app.DetailedParameterSelectionButton.Text = 'Detailed Parameter Selection';
            app.ProceedtoVehicleConceptsButton = uibutton(app.MainMenuPanel, 'push');
            app.ProceedtoVehicleConceptsButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedtoVehicleConceptsButtonPushed, true);
            app.ProceedtoVehicleConceptsButton.Text = 'Proceed to Vehicle Concepts';
            app.VehicleConceptsSelectionPanel = uipanel(app.MainMenuPanel);
            app.VehicleConceptsSelectionPanel.Title = 'Vehicle Concepts Selection';
            app.CheckBox1 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox1.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox1.Text = '';
            app.CheckBox2 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox2.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox2.Text = '';
            app.CheckBox3 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox3.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox3.Text = '';
            app.CheckBox4 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox4.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox4.Text = '';
            app.CheckBox5 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox5.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox5.Text = '';
            app.CheckBox6 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox6.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox6.Text = '';
            app.CheckBox7 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox7.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox7.Text = '';
            app.CheckBox8 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox8.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox8.Text = '';
            app.CheckBox9 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox9.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox9.Text = '';
            app.CheckBox10 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox10.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox10.Text = '';
            app.CheckBox11 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox11.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox11.Text = '';
            app.CheckBox12 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox12.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox12.Text = '';
            app.CheckBox13 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox13.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox13.Text = '';
            app.CheckBox14 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox14.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox14.Text = '';
            app.VehicleConcept14Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept14Label.Text = 'Vehicle Concept 14';
            app.VehicleConcept13Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept13Label.Text = 'Vehicle Concept 13';
            app.VehicleConcept12Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept12Label.Text = 'Vehicle Concept 12';
            app.VehicleConcept11Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept11Label.Text = 'Vehicle Concept 11';
            app.VehicleConcept10Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept10Label.Text = 'Vehicle Concept 10';
            app.VehicleConcept9Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept9Label.Text = 'Vehicle Concept 9';
            app.VehicleConcept8Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept8Label.Text = 'Vehicle Concept 8';
            app.VehicleConcept7Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept7Label.Text = 'Vehicle Concept 7';
            app.VehicleConcept6Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept6Label.Text = 'Vehicle Concept 6';
            app.VehicleConcept5Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept5Label.Text = 'Vehicle Concept 5';
            app.VehicleConcept4Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept4Label.Text = 'Vehicle Concept 4';
            app.VehicleConcept3Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept3Label.Text = 'Vehicle Concept 3';
            app.VehicleConcept2Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept2Label.Text = 'Vehicle Concept 2';
            app.VehicleConcept1Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept1Label.Text = 'Vehicle Concept 1';
            app.VDP_ButtonGroup = uibuttongroup(app.VehicleConceptsSelectionPanel);
            app.VDP_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VDP_ButtonGroupSelectionChanged, true);
            app.VDP_Button_1 = uitogglebutton(app.VDP_ButtonGroup);
            app.VDP_Button_1.Text = '';
            app.VDP_Button_1.Value = true;
            app.VDP_Button_2 = uitogglebutton(app.VDP_ButtonGroup);
            app.VDP_Button_2.Text = '';
            app.VDP_Button_3 = uitogglebutton(app.VDP_ButtonGroup);
            app.VDP_Button_3.Text = '';
            app.VDP_Button_4 = uitogglebutton(app.VDP_ButtonGroup);
            app.VDP_Button_4.Text = '';
            app.VDP_Button_5 = uitogglebutton(app.VDP_ButtonGroup);
            app.VDP_Button_5.Text = '';
            app.VDP_Button_6 = uitogglebutton(app.VDP_ButtonGroup);
            app.VDP_Button_6.Text = '';
            app.VDP_Button_7 = uitogglebutton(app.VDP_ButtonGroup);
            app.VDP_Button_7.Text = '';
            app.VDP_Button_8 = uitogglebutton(app.VDP_ButtonGroup);
            app.VDP_Button_8.Text = '';
            app.VDP_Button_9 = uitogglebutton(app.VDP_ButtonGroup);
            app.VDP_Button_9.Text = '';
            app.VDP_Button_10 = uitogglebutton(app.VDP_ButtonGroup);
            app.VDP_Button_10.Text = '';
            app.VDP_Button_11 = uitogglebutton(app.VDP_ButtonGroup);
            app.VDP_Button_11.Text = '';
            app.VDP_Button_12 = uitogglebutton(app.VDP_ButtonGroup);
            app.VDP_Button_12.Text = '';
            app.VDP_Button_13 = uitogglebutton(app.VDP_ButtonGroup);
            app.VDP_Button_13.Text = '';
            app.VDP_Button_14 = uitogglebutton(app.VDP_ButtonGroup);
            app.VDP_Button_14.Text = '';
            app.GobacktoappdefaultstatusButton = uibutton(app.MainMenuPanel, 'push');
            app.GobacktoappdefaultstatusButton.ButtonPushedFcn = createCallbackFcn(app, @GobacktoappdefaultstatusButtonPushed, true);
            app.GobacktoappdefaultstatusButton.Tooltip = {'Updates all visible elements and spidergraphs.'};
            app.GobacktoappdefaultstatusButton.Text = 'Go back to app default status';
            app.Create3DModelButton = uibutton(app.MainMenuPanel, 'push');
            app.Create3DModelButton.ButtonPushedFcn = createCallbackFcn(app, @Create3DModelButtonPushed, true);
            app.Create3DModelButton.Text = 'Create 3D-Model';
            app.TUMlogo = uiimage(app.MainScreenDP);
            app.TUMlogo.ImageSource = '2015_Logo_TUM_neg_RGB.svg';
            app.HomeButton = uibutton(app.MainScreenDP, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Icon = 'Home.svg';
            app.HomeButton.Text = '';
            app.KeyDesignParameterPanel = uipanel(app.MainScreenDP);
            app.KeyDesignParameterPanel.Title = 'Key Design Parameter';
            app.VehicleConcept1Panel = uipanel(app.KeyDesignParameterPanel);
            app.VehicleConcept1Panel.Title = 'Vehicle Concept 1';
            app.SeatingLayoutDropDown_7Label_13 = uilabel(app.VehicleConcept1Panel);
            app.SeatingLayoutDropDown_7Label_13.Text = 'Seating Layout';
            app.input_seating_layout_1 = uidropdown(app.VehicleConcept1Panel);
            app.input_seating_layout_1.Items = {'Vis-a-Vis', 'Conventional', 'Single Row', 'Back-to-Back'};
            app.input_seating_layout_1.Value = 'Vis-a-Vis';
            app.DriveTypeDropDown_7Label_13 = uilabel(app.VehicleConcept1Panel);
            app.DriveTypeDropDown_7Label_13.Text = 'Drive Type';
            app.input_drive_type_1 = uidropdown(app.VehicleConcept1Panel);
            app.input_drive_type_1.Items = {'GM_X', 'X_GM', 'GM_GM', '2G2M_X', 'X_2G2M', '2G2M_GM', 'GM_2G2M', '2G2M_2G2M', 'All Combinations'};
            app.input_drive_type_1.Value = 'GM_X';
            app.EngineTypeDropDown_7Label_13 = uilabel(app.VehicleConcept1Panel);
            app.EngineTypeDropDown_7Label_13.Text = 'Engine Type';
            app.input_engine_type_1 = uidropdown(app.VehicleConcept1Panel);
            app.input_engine_type_1.Items = {'ASM', 'PSM', 'All Combinations'};
            app.input_engine_type_1.Value = 'ASM';
            app.RearAxleTypeDropDown_7Label_13 = uilabel(app.VehicleConcept1Panel);
            app.RearAxleTypeDropDown_7Label_13.Text = 'Rear Axle Type';
            app.input_rear_axis_type_1 = uidropdown(app.VehicleConcept1Panel);
            app.input_rear_axis_type_1.Items = {'torsion_beam', 'trapezoidal_link', 'sword_arm_link', 'five_link', 'All Combinations'};
            app.input_rear_axis_type_1.Value = 'torsion_beam';
            app.BatteryCellTypeDropDown_7Label_13 = uilabel(app.VehicleConcept1Panel);
            app.BatteryCellTypeDropDown_7Label_13.Text = 'Battery Cell Type';
            app.input_cell_type_1 = uidropdown(app.VehicleConcept1Panel);
            app.input_cell_type_1.Items = {'cylindrical', 'pouch', 'prismatic', 'All Combinations'};
            app.input_cell_type_1.Value = 'pouch';
            app.VehicleConcept2Panel = uipanel(app.KeyDesignParameterPanel);
            app.VehicleConcept2Panel.Title = 'Vehicle Concept 2:';
            app.SeatingLayoutDropDown_7Label_2 = uilabel(app.VehicleConcept2Panel);
            app.SeatingLayoutDropDown_7Label_2.Text = 'Seating Layout';
            app.input_seating_layout_2 = uidropdown(app.VehicleConcept2Panel);
            app.input_seating_layout_2.Items = {'Vis-a-Vis', 'Conventional', 'Single Row', 'Back-to-Back'};
            app.input_seating_layout_2.Value = 'Vis-a-Vis';
            app.DriveTypeDropDown_7Label_2 = uilabel(app.VehicleConcept2Panel);
            app.DriveTypeDropDown_7Label_2.Text = 'Drive Type';
            app.input_drive_type_2 = uidropdown(app.VehicleConcept2Panel);
            app.input_drive_type_2.Items = {'GM_X', 'X_GM', 'GM_GM', '2G2M_X', 'X_2G2M', '2G2M_GM', 'GM_2G2M', '2G2M_2G2M', 'All Combinations'};
            app.input_drive_type_2.Value = 'GM_X';
            app.EngineTypeDropDown_7Label_2 = uilabel(app.VehicleConcept2Panel);
            app.EngineTypeDropDown_7Label_2.Text = 'Engine Type';
            app.input_engine_type_2 = uidropdown(app.VehicleConcept2Panel);
            app.input_engine_type_2.Items = {'ASM', 'PSM', 'All Combinations'};
            app.input_engine_type_2.Value = 'ASM';
            app.RearAxleTypeDropDown_7Label_2 = uilabel(app.VehicleConcept2Panel);
            app.RearAxleTypeDropDown_7Label_2.Text = 'Rear Axle Type';
            app.input_rear_axis_type_2 = uidropdown(app.VehicleConcept2Panel);
            app.input_rear_axis_type_2.Items = {'torsion_beam', 'trapezoidal_link', 'sword_arm_link', 'five_link', 'All Combinations'};
            app.input_rear_axis_type_2.Value = 'torsion_beam';
            app.BatteryCellTypeDropDown_7Label_2 = uilabel(app.VehicleConcept2Panel);
            app.BatteryCellTypeDropDown_7Label_2.Text = 'Battery Cell Type';
            app.input_cell_type_2 = uidropdown(app.VehicleConcept2Panel);
            app.input_cell_type_2.Items = {'cylindrical', 'pouch', 'prismatic', 'All Combinations'};
            app.input_cell_type_2.Value = 'pouch';
            app.VehicleConcept3Panel = uipanel(app.KeyDesignParameterPanel);
            app.VehicleConcept3Panel.Title = 'Vehicle Concept 3:';
            app.SeatingLayoutDropDown_7Label_3 = uilabel(app.VehicleConcept3Panel);
            app.SeatingLayoutDropDown_7Label_3.Text = 'Seating Layout';
            app.input_seating_layout_3 = uidropdown(app.VehicleConcept3Panel);
            app.input_seating_layout_3.Items = {'Vis-a-Vis', 'Conventional', 'Single Row', 'Back-to-Back'};
            app.input_seating_layout_3.Value = 'Vis-a-Vis';
            app.DriveTypeDropDown_7Label_3 = uilabel(app.VehicleConcept3Panel);
            app.DriveTypeDropDown_7Label_3.Text = 'Drive Type';
            app.input_drive_type_3 = uidropdown(app.VehicleConcept3Panel);
            app.input_drive_type_3.Items = {'GM_X', 'X_GM', 'GM_GM', '2G2M_X', 'X_2G2M', '2G2M_GM', 'GM_2G2M', '2G2M_2G2M', 'All Combinations'};
            app.input_drive_type_3.Value = 'GM_X';
            app.EngineTypeDropDown_7Label_3 = uilabel(app.VehicleConcept3Panel);
            app.EngineTypeDropDown_7Label_3.Text = 'Engine Type';
            app.input_engine_type_3 = uidropdown(app.VehicleConcept3Panel);
            app.input_engine_type_3.Items = {'ASM', 'PSM', 'All Combinations'};
            app.input_engine_type_3.Value = 'ASM';
            app.RearAxleTypeDropDown_7Label_3 = uilabel(app.VehicleConcept3Panel);
            app.RearAxleTypeDropDown_7Label_3.Text = 'Rear Axle Type';
            app.input_rear_axis_type_3 = uidropdown(app.VehicleConcept3Panel);
            app.input_rear_axis_type_3.Items = {'torsion_beam', 'trapezoidal_link', 'sword_arm_link', 'five_link', 'All Combinations'};
            app.input_rear_axis_type_3.Value = 'torsion_beam';
            app.BatteryCellTypeDropDown_7Label_3 = uilabel(app.VehicleConcept3Panel);
            app.BatteryCellTypeDropDown_7Label_3.Text = 'Battery Cell Type';
            app.input_cell_type_3 = uidropdown(app.VehicleConcept3Panel);
            app.input_cell_type_3.Items = {'cylindrical', 'pouch', 'prismatic', 'All Combinations'};
            app.input_cell_type_3.Value = 'pouch';
            app.VehicleConcept4Panel = uipanel(app.KeyDesignParameterPanel);
            app.VehicleConcept4Panel.Title = 'Vehicle Concept 4:';
            app.SeatingLayoutDropDown_7Label_4 = uilabel(app.VehicleConcept4Panel);
            app.SeatingLayoutDropDown_7Label_4.Text = 'Seating Layout';
            app.input_seating_layout_4 = uidropdown(app.VehicleConcept4Panel);
            app.input_seating_layout_4.Items = {'Vis-a-Vis', 'Conventional', 'Single Row', 'Back-to-Back'};
            app.input_seating_layout_4.Value = 'Vis-a-Vis';
            app.DriveTypeDropDown_7Label_4 = uilabel(app.VehicleConcept4Panel);
            app.DriveTypeDropDown_7Label_4.Text = 'Drive Type';
            app.input_drive_type_4 = uidropdown(app.VehicleConcept4Panel);
            app.input_drive_type_4.Items = {'GM_X', 'X_GM', 'GM_GM', '2G2M_X', 'X_2G2M', '2G2M_GM', 'GM_2G2M', '2G2M_2G2M', 'All Combinations'};
            app.input_drive_type_4.Value = 'GM_X';
            app.EngineTypeDropDown_7Label_4 = uilabel(app.VehicleConcept4Panel);
            app.EngineTypeDropDown_7Label_4.Text = 'Engine Type';
            app.input_engine_type_4 = uidropdown(app.VehicleConcept4Panel);
            app.input_engine_type_4.Items = {'ASM', 'PSM', 'All Combinations'};
            app.input_engine_type_4.Value = 'ASM';
            app.RearAxleTypeDropDown_7Label_4 = uilabel(app.VehicleConcept4Panel);
            app.RearAxleTypeDropDown_7Label_4.Text = 'Rear Axle Type';
            app.input_rear_axis_type_4 = uidropdown(app.VehicleConcept4Panel);
            app.input_rear_axis_type_4.Items = {'torsion_beam', 'trapezoidal_link', 'sword_arm_link', 'five_link', 'All Combinations'};
            app.input_rear_axis_type_4.Value = 'torsion_beam';
            app.BatteryCellTypeDropDown_7Label_4 = uilabel(app.VehicleConcept4Panel);
            app.BatteryCellTypeDropDown_7Label_4.Text = 'Battery Cell Type';
            app.input_cell_type_4 = uidropdown(app.VehicleConcept4Panel);
            app.input_cell_type_4.Items = {'cylindrical', 'pouch', 'prismatic', 'All Combinations'};
            app.input_cell_type_4.Value = 'pouch';
            app.VehicleConcept5Panel = uipanel(app.KeyDesignParameterPanel);
            app.VehicleConcept5Panel.Title = 'Vehicle Concept 5:';
            app.SeatingLayoutDropDown_7Label_5 = uilabel(app.VehicleConcept5Panel);
            app.SeatingLayoutDropDown_7Label_5.Text = 'Seating Layout';
            app.input_seating_layout_5 = uidropdown(app.VehicleConcept5Panel);
            app.input_seating_layout_5.Items = {'Vis-a-Vis', 'Conventional', 'Single Row', 'Back-to-Back'};
            app.input_seating_layout_5.Value = 'Vis-a-Vis';
            app.DriveTypeDropDown_7Label_5 = uilabel(app.VehicleConcept5Panel);
            app.DriveTypeDropDown_7Label_5.Text = 'Drive Type';
            app.input_drive_type_5 = uidropdown(app.VehicleConcept5Panel);
            app.input_drive_type_5.Items = {'GM_X', 'X_GM', 'GM_GM', '2G2M_X', 'X_2G2M', '2G2M_GM', 'GM_2G2M', '2G2M_2G2M', 'All Combinations'};
            app.input_drive_type_5.Value = 'GM_X';
            app.EngineTypeDropDown_7Label_5 = uilabel(app.VehicleConcept5Panel);
            app.EngineTypeDropDown_7Label_5.Text = 'Engine Type';
            app.input_engine_type_5 = uidropdown(app.VehicleConcept5Panel);
            app.input_engine_type_5.Items = {'ASM', 'PSM', 'All Combinations'};
            app.input_engine_type_5.Value = 'ASM';
            app.RearAxleTypeDropDown_7Label_5 = uilabel(app.VehicleConcept5Panel);
            app.RearAxleTypeDropDown_7Label_5.Text = 'Rear Axle Type';
            app.input_rear_axis_type_5 = uidropdown(app.VehicleConcept5Panel);
            app.input_rear_axis_type_5.Items = {'torsion_beam', 'trapezoidal_link', 'sword_arm_link', 'five_link', 'All Combinations'};
            app.input_rear_axis_type_5.Value = 'torsion_beam';
            app.BatteryCellTypeDropDown_7Label_5 = uilabel(app.VehicleConcept5Panel);
            app.BatteryCellTypeDropDown_7Label_5.Text = 'Battery Cell Type';
            app.input_cell_type_5 = uidropdown(app.VehicleConcept5Panel);
            app.input_cell_type_5.Items = {'cylindrical', 'pouch', 'prismatic', 'All Combinations'};
            app.input_cell_type_5.Value = 'pouch';
            app.VehicleConcept6Panel = uipanel(app.KeyDesignParameterPanel);
            app.VehicleConcept6Panel.Title = 'Vehicle Concept 6:';
            app.SeatingLayoutDropDown_7Label_6 = uilabel(app.VehicleConcept6Panel);
            app.SeatingLayoutDropDown_7Label_6.Text = 'Seating Layout';
            app.input_seating_layout_6 = uidropdown(app.VehicleConcept6Panel);
            app.input_seating_layout_6.Items = {'Vis-a-Vis', 'Conventional', 'Single Row', 'Back-to-Back'};
            app.input_seating_layout_6.Value = 'Vis-a-Vis';
            app.DriveTypeDropDown_7Label_6 = uilabel(app.VehicleConcept6Panel);
            app.DriveTypeDropDown_7Label_6.Text = 'Drive Type';
            app.input_drive_type_6 = uidropdown(app.VehicleConcept6Panel);
            app.input_drive_type_6.Items = {'GM_X', 'X_GM', 'GM_GM', '2G2M_X', 'X_2G2M', '2G2M_GM', 'GM_2G2M', '2G2M_2G2M', 'All Combinations'};
            app.input_drive_type_6.Value = 'GM_X';
            app.EngineTypeDropDown_7Label_6 = uilabel(app.VehicleConcept6Panel);
            app.EngineTypeDropDown_7Label_6.Text = 'Engine Type';
            app.input_engine_type_6 = uidropdown(app.VehicleConcept6Panel);
            app.input_engine_type_6.Items = {'ASM', 'PSM', 'All Combinations'};
            app.input_engine_type_6.Value = 'ASM';
            app.RearAxleTypeDropDown_7Label_6 = uilabel(app.VehicleConcept6Panel);
            app.RearAxleTypeDropDown_7Label_6.Text = 'Rear Axle Type';
            app.input_rear_axis_type_6 = uidropdown(app.VehicleConcept6Panel);
            app.input_rear_axis_type_6.Items = {'torsion_beam', 'trapezoidal_link', 'sword_arm_link', 'five_link', 'All Combinations'};
            app.input_rear_axis_type_6.Value = 'torsion_beam';
            app.BatteryCellTypeDropDown_7Label_6 = uilabel(app.VehicleConcept6Panel);
            app.BatteryCellTypeDropDown_7Label_6.Text = 'Battery Cell Type';
            app.input_cell_type_6 = uidropdown(app.VehicleConcept6Panel);
            app.input_cell_type_6.Items = {'cylindrical', 'pouch', 'prismatic', 'All Combinations'};
            app.input_cell_type_6.Value = 'pouch';
            app.VehicleConcept7Panel = uipanel(app.KeyDesignParameterPanel);
            app.VehicleConcept7Panel.Title = 'Vehicle Concept 7:';
            app.SeatingLayoutDropDown_7Label_7 = uilabel(app.VehicleConcept7Panel);
            app.SeatingLayoutDropDown_7Label_7.Text = 'Seating Layout';
            app.input_seating_layout_7 = uidropdown(app.VehicleConcept7Panel);
            app.input_seating_layout_7.Items = {'Vis-a-Vis', 'Conventional', 'Single Row', 'Back-to-Back'};
            app.input_seating_layout_7.Value = 'Vis-a-Vis';
            app.DriveTypeDropDown_7Label_7 = uilabel(app.VehicleConcept7Panel);
            app.DriveTypeDropDown_7Label_7.Text = 'Drive Type';
            app.input_drive_type_7 = uidropdown(app.VehicleConcept7Panel);
            app.input_drive_type_7.Items = {'GM_X', 'X_GM', 'GM_GM', '2G2M_X', 'X_2G2M', '2G2M_GM', 'GM_2G2M', '2G2M_2G2M', 'All Combinations'};
            app.input_drive_type_7.Value = 'GM_X';
            app.EngineTypeDropDown_7Label_7 = uilabel(app.VehicleConcept7Panel);
            app.EngineTypeDropDown_7Label_7.Text = 'Engine Type';
            app.input_engine_type_7 = uidropdown(app.VehicleConcept7Panel);
            app.input_engine_type_7.Items = {'ASM', 'PSM', 'All Combinations'};
            app.input_engine_type_7.Value = 'ASM';
            app.RearAxleTypeDropDown_7Label_7 = uilabel(app.VehicleConcept7Panel);
            app.RearAxleTypeDropDown_7Label_7.Text = 'Rear Axle Type';
            app.input_rear_axis_type_7 = uidropdown(app.VehicleConcept7Panel);
            app.input_rear_axis_type_7.Items = {'torsion_beam', 'trapezoidal_link', 'sword_arm_link', 'five_link', 'All Combinations'};
            app.input_rear_axis_type_7.Value = 'torsion_beam';
            app.BatteryCellTypeDropDown_7Label_7 = uilabel(app.VehicleConcept7Panel);
            app.BatteryCellTypeDropDown_7Label_7.Text = 'Battery Cell Type';
            app.input_cell_type_7 = uidropdown(app.VehicleConcept7Panel);
            app.input_cell_type_7.Items = {'cylindrical', 'pouch', 'prismatic', 'All Combinations'};
            app.input_cell_type_7.Value = 'pouch';
            app.VehicleConcept8Panel = uipanel(app.KeyDesignParameterPanel);
            app.VehicleConcept8Panel.Title = 'Vehicle Concept 8:';
            app.SeatingLayoutDropDown_7Label_8 = uilabel(app.VehicleConcept8Panel);
            app.SeatingLayoutDropDown_7Label_8.Text = 'Seating Layout';
            app.input_seating_layout_8 = uidropdown(app.VehicleConcept8Panel);
            app.input_seating_layout_8.Items = {'Vis-a-Vis', 'Conventional', 'Single Row', 'Back-to-Back'};
            app.input_seating_layout_8.Value = 'Vis-a-Vis';
            app.DriveTypeDropDown_7Label_8 = uilabel(app.VehicleConcept8Panel);
            app.DriveTypeDropDown_7Label_8.Text = 'Drive Type';
            app.input_drive_type_8 = uidropdown(app.VehicleConcept8Panel);
            app.input_drive_type_8.Items = {'GM_X', 'X_GM', 'GM_GM', '2G2M_X', 'X_2G2M', '2G2M_GM', 'GM_2G2M', '2G2M_2G2M', 'All Combinations'};
            app.input_drive_type_8.Value = 'GM_X';
            app.EngineTypeDropDown_7Label_8 = uilabel(app.VehicleConcept8Panel);
            app.EngineTypeDropDown_7Label_8.Text = 'Engine Type';
            app.input_engine_type_8 = uidropdown(app.VehicleConcept8Panel);
            app.input_engine_type_8.Items = {'ASM', 'PSM', 'All Combinations'};
            app.input_engine_type_8.Value = 'ASM';
            app.RearAxleTypeDropDown_7Label_8 = uilabel(app.VehicleConcept8Panel);
            app.RearAxleTypeDropDown_7Label_8.Text = 'Rear Axle Type';
            app.input_rear_axis_type_8 = uidropdown(app.VehicleConcept8Panel);
            app.input_rear_axis_type_8.Items = {'torsion_beam', 'trapezoidal_link', 'sword_arm_link', 'five_link', 'All Combinations'};
            app.input_rear_axis_type_8.Value = 'torsion_beam';
            app.BatteryCellTypeDropDown_7Label_8 = uilabel(app.VehicleConcept8Panel);
            app.BatteryCellTypeDropDown_7Label_8.Text = 'Battery Cell Type';
            app.input_cell_type_8 = uidropdown(app.VehicleConcept8Panel);
            app.input_cell_type_8.Items = {'cylindrical', 'pouch', 'prismatic', 'All Combinations'};
            app.input_cell_type_8.Value = 'pouch';
            app.VehicleConcept9Panel = uipanel(app.KeyDesignParameterPanel);
            app.VehicleConcept9Panel.Title = 'Vehicle Concept 9:';
            app.SeatingLayoutDropDown_7Label_9 = uilabel(app.VehicleConcept9Panel);
            app.SeatingLayoutDropDown_7Label_9.Text = 'Seating Layout';
            app.input_seating_layout_9 = uidropdown(app.VehicleConcept9Panel);
            app.input_seating_layout_9.Items = {'Vis-a-Vis', 'Conventional', 'Single Row', 'Back-to-Back'};
            app.input_seating_layout_9.Value = 'Vis-a-Vis';
            app.DriveTypeDropDown_7Label_9 = uilabel(app.VehicleConcept9Panel);
            app.DriveTypeDropDown_7Label_9.Text = 'Drive Type';
            app.input_drive_type_9 = uidropdown(app.VehicleConcept9Panel);
            app.input_drive_type_9.Items = {'GM_X', 'X_GM', 'GM_GM', '2G2M_X', 'X_2G2M', '2G2M_GM', 'GM_2G2M', '2G2M_2G2M', 'All Combinations'};
            app.input_drive_type_9.Value = 'GM_X';
            app.EngineTypeDropDown_7Label_9 = uilabel(app.VehicleConcept9Panel);
            app.EngineTypeDropDown_7Label_9.Text = 'Engine Type';
            app.input_engine_type_9 = uidropdown(app.VehicleConcept9Panel);
            app.input_engine_type_9.Items = {'ASM', 'PSM', 'All Combinations'};
            app.input_engine_type_9.Value = 'ASM';
            app.RearAxleTypeDropDown_7Label_9 = uilabel(app.VehicleConcept9Panel);
            app.RearAxleTypeDropDown_7Label_9.Text = 'Rear Axle Type';
            app.input_rear_axis_type_9 = uidropdown(app.VehicleConcept9Panel);
            app.input_rear_axis_type_9.Items = {'torsion_beam', 'trapezoidal_link', 'sword_arm_link', 'five_link', 'All Combinations'};
            app.input_rear_axis_type_9.Value = 'torsion_beam';
            app.BatteryCellTypeDropDown_7Label_9 = uilabel(app.VehicleConcept9Panel);
            app.BatteryCellTypeDropDown_7Label_9.Text = 'Battery Cell Type';
            app.input_cell_type_9 = uidropdown(app.VehicleConcept9Panel);
            app.input_cell_type_9.Items = {'cylindrical', 'pouch', 'prismatic', 'All Combinations'};
            app.input_cell_type_9.Value = 'pouch';
            app.VehicleConcept10Panel = uipanel(app.KeyDesignParameterPanel);
            app.VehicleConcept10Panel.Title = 'Vehicle Concept 10:';
            app.SeatingLayoutDropDown_7Label_10 = uilabel(app.VehicleConcept10Panel);
            app.SeatingLayoutDropDown_7Label_10.Text = 'Seating Layout';
            app.input_seating_layout_10 = uidropdown(app.VehicleConcept10Panel);
            app.input_seating_layout_10.Items = {'Vis-a-Vis', 'Conventional', 'Single Row', 'Back-to-Back'};
            app.input_seating_layout_10.Value = 'Vis-a-Vis';
            app.DriveTypeDropDown_7Label_10 = uilabel(app.VehicleConcept10Panel);
            app.DriveTypeDropDown_7Label_10.Text = 'Drive Type';
            app.input_drive_type_10 = uidropdown(app.VehicleConcept10Panel);
            app.input_drive_type_10.Items = {'GM_X', 'X_GM', 'GM_GM', '2G2M_X', 'X_2G2M', '2G2M_GM', 'GM_2G2M', '2G2M_2G2M', 'All Combinations'};
            app.input_drive_type_10.Value = 'GM_X';
            app.EngineTypeDropDown_7Label_10 = uilabel(app.VehicleConcept10Panel);
            app.EngineTypeDropDown_7Label_10.Text = 'Engine Type';
            app.input_engine_type_10 = uidropdown(app.VehicleConcept10Panel);
            app.input_engine_type_10.Items = {'ASM', 'PSM', 'All Combinations'};
            app.input_engine_type_10.Value = 'ASM';
            app.RearAxleTypeDropDown_7Label_10 = uilabel(app.VehicleConcept10Panel);
            app.RearAxleTypeDropDown_7Label_10.Text = 'Rear Axle Type';
            app.input_rear_axis_type_10 = uidropdown(app.VehicleConcept10Panel);
            app.input_rear_axis_type_10.Items = {'torsion_beam', 'trapezoidal_link', 'sword_arm_link', 'five_link', 'All Combinations'};
            app.input_rear_axis_type_10.Value = 'torsion_beam';
            app.BatteryCellTypeDropDown_7Label_10 = uilabel(app.VehicleConcept10Panel);
            app.BatteryCellTypeDropDown_7Label_10.Text = 'Battery Cell Type';
            app.input_cell_type_10 = uidropdown(app.VehicleConcept10Panel);
            app.input_cell_type_10.Items = {'cylindrical', 'pouch', 'prismatic', 'All Combinations'};
            app.input_cell_type_10.Value = 'pouch';
            app.VehicleConcept11Panel = uipanel(app.KeyDesignParameterPanel);
            app.VehicleConcept11Panel.Title = 'Vehicle Concept 11:';
            app.SeatingLayoutDropDown_7Label_11 = uilabel(app.VehicleConcept11Panel);
            app.SeatingLayoutDropDown_7Label_11.Text = 'Seating Layout';
            app.input_seating_layout_11 = uidropdown(app.VehicleConcept11Panel);
            app.input_seating_layout_11.Items = {'Vis-a-Vis', 'Conventional', 'Single Row', 'Back-to-Back'};
            app.input_seating_layout_11.Value = 'Vis-a-Vis';
            app.DriveTypeDropDown_7Label_11 = uilabel(app.VehicleConcept11Panel);
            app.DriveTypeDropDown_7Label_11.Text = 'Drive Type';
            app.input_drive_type_11 = uidropdown(app.VehicleConcept11Panel);
            app.input_drive_type_11.Items = {'GM_X', 'X_GM', 'GM_GM', '2G2M_X', 'X_2G2M', '2G2M_GM', 'GM_2G2M', '2G2M_2G2M', 'All Combinations'};
            app.input_drive_type_11.Value = 'GM_X';
            app.EngineTypeDropDown_7Label_11 = uilabel(app.VehicleConcept11Panel);
            app.EngineTypeDropDown_7Label_11.Text = 'Engine Type';
            app.input_engine_type_11 = uidropdown(app.VehicleConcept11Panel);
            app.input_engine_type_11.Items = {'ASM', 'PSM', 'All Combinations'};
            app.input_engine_type_11.Value = 'ASM';
            app.RearAxleTypeDropDown_7Label_11 = uilabel(app.VehicleConcept11Panel);
            app.RearAxleTypeDropDown_7Label_11.Text = 'Rear Axle Type';
            app.input_rear_axis_type_11 = uidropdown(app.VehicleConcept11Panel);
            app.input_rear_axis_type_11.Items = {'torsion_beam', 'trapezoidal_link', 'sword_arm_link', 'five_link', 'All Combinations'};
            app.input_rear_axis_type_11.Value = 'torsion_beam';
            app.BatteryCellTypeDropDown_7Label_11 = uilabel(app.VehicleConcept11Panel);
            app.BatteryCellTypeDropDown_7Label_11.Text = 'Battery Cell Type';
            app.input_cell_type_11 = uidropdown(app.VehicleConcept11Panel);
            app.input_cell_type_11.Items = {'cylindrical', 'pouch', 'prismatic', 'All Combinations'};
            app.input_cell_type_11.Value = 'pouch';
            app.VehicleConcept12Panel = uipanel(app.KeyDesignParameterPanel);
            app.VehicleConcept12Panel.Title = 'Vehicle Concept 12:';
            app.SeatingLayoutDropDown_7Label_12 = uilabel(app.VehicleConcept12Panel);
            app.SeatingLayoutDropDown_7Label_12.Text = 'Seating Layout';
            app.input_seating_layout_12 = uidropdown(app.VehicleConcept12Panel);
            app.input_seating_layout_12.Items = {'Vis-a-Vis', 'Conventional', 'Single Row', 'Back-to-Back'};
            app.input_seating_layout_12.Value = 'Vis-a-Vis';
            app.DriveTypeDropDown_7Label_12 = uilabel(app.VehicleConcept12Panel);
            app.DriveTypeDropDown_7Label_12.Text = 'Drive Type';
            app.input_drive_type_12 = uidropdown(app.VehicleConcept12Panel);
            app.input_drive_type_12.Items = {'GM_X', 'X_GM', 'GM_GM', '2G2M_X', 'X_2G2M', '2G2M_GM', 'GM_2G2M', '2G2M_2G2M', 'All Combinations'};
            app.input_drive_type_12.Value = 'GM_X';
            app.EngineTypeDropDown_7Label_12 = uilabel(app.VehicleConcept12Panel);
            app.EngineTypeDropDown_7Label_12.Text = 'Engine Type';
            app.input_engine_type_12 = uidropdown(app.VehicleConcept12Panel);
            app.input_engine_type_12.Items = {'ASM', 'PSM', 'All Combinations'};
            app.input_engine_type_12.Value = 'ASM';
            app.RearAxleTypeDropDown_7Label_12 = uilabel(app.VehicleConcept12Panel);
            app.RearAxleTypeDropDown_7Label_12.Text = 'Rear Axle Type';
            app.input_rear_axis_type_12 = uidropdown(app.VehicleConcept12Panel);
            app.input_rear_axis_type_12.Items = {'torsion_beam', 'trapezoidal_link', 'sword_arm_link', 'five_link', 'All Combinations'};
            app.input_rear_axis_type_12.Value = 'torsion_beam';
            app.BatteryCellTypeDropDown_7Label_12 = uilabel(app.VehicleConcept12Panel);
            app.BatteryCellTypeDropDown_7Label_12.Text = 'Battery Cell Type';
            app.input_cell_type_12 = uidropdown(app.VehicleConcept12Panel);
            app.input_cell_type_12.Items = {'cylindrical', 'pouch', 'prismatic', 'All Combinations'};
            app.input_cell_type_12.Value = 'pouch';
            app.VehicleConceptOriginPanel = uipanel(app.KeyDesignParameterPanel);
            app.VehicleConceptOriginPanel.Enable = 'off';
            app.VehicleConceptOriginPanel.Title = 'Vehicle Concept 0';
            app.VehicleConceptOriginPanel.Visible = 'off';
            app.SeatingLayoutDropDown_7Label = uilabel(app.VehicleConceptOriginPanel);
            app.SeatingLayoutDropDown_7Label.Text = 'Seating Layout';
            app.input_seating_layout = uidropdown(app.VehicleConceptOriginPanel);
            app.input_seating_layout.Items = {'Vis-a-Vis', 'Conventional', 'Single Row', 'Back-to-Back', 'Vis-à-Vis in front', 'Vis-à-Vis in back'};
            app.input_seating_layout.Value = 'Vis-a-Vis';
            app.DriveTypeDropDown_7Label = uilabel(app.VehicleConceptOriginPanel);
            app.DriveTypeDropDown_7Label.Text = 'Drive Type';
            app.input_drive_type = uidropdown(app.VehicleConceptOriginPanel);
            app.input_drive_type.Items = {'GM_X', 'X_GM', 'GM_GM', '2G2M_X', 'X_2G2M', '2G2M_GM', 'GM_2G2M', '2G2M_2G2M', 'All Combinations'};
            app.input_drive_type.Value = 'GM_X';
            app.EngineTypeDropDown_7Label = uilabel(app.VehicleConceptOriginPanel);
            app.EngineTypeDropDown_7Label.Text = 'Engine Type';
            app.input_engine_type = uidropdown(app.VehicleConceptOriginPanel);
            app.input_engine_type.Items = {'ASM', 'PSM', 'All Combinations'};
            app.input_engine_type.Value = 'ASM';
            app.RearAxleTypeDropDown_7Label = uilabel(app.VehicleConceptOriginPanel);
            app.RearAxleTypeDropDown_7Label.Text = 'Rear Axle Type';
            app.input_rear_axis_type = uidropdown(app.VehicleConceptOriginPanel);
            app.input_rear_axis_type.Items = {'torsion_beam', 'trapezoidal_link', 'sword_arm_link', 'five_link', 'All Combinations'};
            app.input_rear_axis_type.Value = 'torsion_beam';
            app.BatteryCellTypeDropDown_7Label = uilabel(app.VehicleConceptOriginPanel);
            app.BatteryCellTypeDropDown_7Label.Text = 'Battery Cell Type';
            app.input_cell_type = uidropdown(app.VehicleConceptOriginPanel);
            app.input_cell_type.Items = {'cylindrical', 'pouch', 'prismatic', 'All Combinations'};
            app.input_cell_type.Value = 'pouch';
            app.DetailedDesignParameterPanel = uipanel(app.MainScreenDP);
            app.DetailedDesignParameterPanel.Title = 'Detailed Design Parameter';
            app.TabGroup = uitabgroup(app.DetailedDesignParameterPanel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ComponentSelectionTab = uitab(app.TabGroup);
            app.ComponentSelectionTab.Title = 'Component Selection';
            app.BatteryPanel = uipanel(app.ComponentSelectionTab);
            app.BatteryPanel.Title = 'Battery Settings';
            app.input_batterycooling_in_z = uicheckbox(app.BatteryPanel);
            app.input_batterycooling_in_z.Text = 'Cooling plate under battery';
            app.input_batterycooling_in_z.Value = true;
            app.SmalloverlapLabel = uilabel(app.BatteryPanel);
            app.SmalloverlapLabel.Text = 'Small overlap';
            app.input_small_overlap = uidropdown(app.BatteryPanel);
            app.input_small_overlap.Items = {'Yes', 'No', 'All Combinations'};
            app.input_small_overlap.Value = 'Yes';
            app.SensorsPanel = uipanel(app.ComponentSelectionTab);
            app.SensorsPanel.Title = 'Sensors';
            app.NumberofSonarsensorsSpinnerLabel = uilabel(app.SensorsPanel);
            app.NumberofSonarsensorsSpinnerLabel.Text = 'Number of Sonar sensors';
            app.input_sonar = uispinner(app.SensorsPanel);
            app.input_sonar.Limits = [0 50];
            app.input_sonar.Value = 8;
            app.NumberofRadarsensorsSpinnerLabel = uilabel(app.SensorsPanel);
            app.NumberofRadarsensorsSpinnerLabel.Text = 'Number of Radar sensors';
            app.input_radar = uispinner(app.SensorsPanel);
            app.input_radar.Limits = [0 30];
            app.input_radar.Value = 5;
            app.NumberofSurroundCamerasSpinnerLabel = uilabel(app.SensorsPanel);
            app.NumberofSurroundCamerasSpinnerLabel.Text = 'Number of Surround Cameras';
            app.input_surround_cameras = uispinner(app.SensorsPanel);
            app.input_surround_cameras.Limits = [0 30];
            app.input_surround_cameras.Value = 4;
            app.NumberofFrontCamerasSpinnerLabel = uilabel(app.SensorsPanel);
            app.NumberofFrontCamerasSpinnerLabel.Text = 'Number of Front Cameras';
            app.input_front_cameras = uispinner(app.SensorsPanel);
            app.input_front_cameras.Limits = [0 30];
            app.input_front_cameras.Value = 4;
            app.input_c2x = uicheckbox(app.SensorsPanel);
            app.input_c2x.Text = 'C2X Interface';
            app.input_c2x.Value = true;
            app.input_5g = uicheckbox(app.SensorsPanel);
            app.input_5g.Text = '5G compatibility';
            app.input_5g.Value = true;
            app.input_onboard_pc = uicheckbox(app.SensorsPanel);
            app.input_onboard_pc.Text = 'Onboard Computer';
            app.input_onboard_pc.Value = true;
            app.input_gps_reciever = uicheckbox(app.SensorsPanel);
            app.input_gps_reciever.Text = 'GPS reciever';
            app.input_gps_reciever.Value = true;
            app.input_gps_correction = uicheckbox(app.SensorsPanel);
            app.input_gps_correction.Text = 'GPS correction service';
            app.NumberofmechLIDARsensorsLabel = uilabel(app.SensorsPanel);
            app.NumberofmechLIDARsensorsLabel.Text = 'Number of mech. LIDAR sensors';
            app.input_mech_lidar = uispinner(app.SensorsPanel);
            app.input_mech_lidar.Limits = [0 20];
            app.input_mech_lidar.Value = 2;
            app.NumberofmemsLIDARsensorsSpinnerLabel = uilabel(app.SensorsPanel);
            app.NumberofmemsLIDARsensorsSpinnerLabel.Text = 'Number of mems. LIDAR sensors';
            app.input_mems_lidar = uispinner(app.SensorsPanel);
            app.input_mems_lidar.Limits = [0 20];
            app.BodyTypeandMaterialsPanel = uipanel(app.ComponentSelectionTab);
            app.BodyTypeandMaterialsPanel.Title = 'Body Type and Materials';
            app.ClosuresmaterialDropDownLabel = uilabel(app.BodyTypeandMaterialsPanel);
            app.ClosuresmaterialDropDownLabel.Text = 'Closures material';
            app.input_closures_material = uidropdown(app.BodyTypeandMaterialsPanel);
            app.input_closures_material.Items = {'Aluminium', 'Steel'};
            app.input_closures_material.Value = 'Aluminium';
            app.DoormaterialLabel = uilabel(app.BodyTypeandMaterialsPanel);
            app.DoormaterialLabel.Text = 'Door material';
            app.input_door_material = uidropdown(app.BodyTypeandMaterialsPanel);
            app.input_door_material.Items = {'Aluminium', 'Steel'};
            app.input_door_material.Value = 'Aluminium';
            app.TailgatematerialLabel = uilabel(app.BodyTypeandMaterialsPanel);
            app.TailgatematerialLabel.Text = 'Tailgate material';
            app.input_tailgate_material = uidropdown(app.BodyTypeandMaterialsPanel);
            app.input_tailgate_material.Items = {'Aluminium', 'Steel'};
            app.input_tailgate_material.Value = 'Aluminium';
            app.HoodmaterialLabel = uilabel(app.BodyTypeandMaterialsPanel);
            app.HoodmaterialLabel.Text = 'Hood material';
            app.input_hood_material = uidropdown(app.BodyTypeandMaterialsPanel);
            app.input_hood_material.Items = {'Aluminium', 'Steel'};
            app.input_hood_material.Value = 'Aluminium';
            app.FendersmaterialLabel = uilabel(app.BodyTypeandMaterialsPanel);
            app.FendersmaterialLabel.Text = 'Fenders material';
            app.input_fenders_material = uidropdown(app.BodyTypeandMaterialsPanel);
            app.input_fenders_material.Items = {'Aluminium', 'Steel'};
            app.input_fenders_material.Value = 'Aluminium';
            app.NumberofdoorsLabel = uilabel(app.BodyTypeandMaterialsPanel);
            app.NumberofdoorsLabel.Text = 'Number of doors';
            app.input_n_doors = uispinner(app.BodyTypeandMaterialsPanel);
            app.input_n_doors.Step = 2;
            app.input_n_doors.Limits = [0 4];
            app.input_n_doors.Value = 2;
            app.FronttrunktypeLabel = uilabel(app.BodyTypeandMaterialsPanel);
            app.FronttrunktypeLabel.Text = 'Front trunk type';
            app.input_trunk_type_front = uidropdown(app.BodyTypeandMaterialsPanel);
            app.input_trunk_type_front.Items = {'integrated', 'hooded'};
            app.input_trunk_type_front.Value = 'integrated';
            app.ReartrunktypeLabel = uilabel(app.BodyTypeandMaterialsPanel);
            app.ReartrunktypeLabel.Text = 'Rear trunk type';
            app.input_trunk_type_rear = uidropdown(app.BodyTypeandMaterialsPanel);
            app.input_trunk_type_rear.Items = {'integrated', 'hooded'};
            app.input_trunk_type_rear.Value = 'integrated';
            app.settings_conv = uicheckbox(app.BodyTypeandMaterialsPanel);
            app.settings_conv.Text = 'Set conventional vehicle settings';
            app.settings_aut = uicheckbox(app.BodyTypeandMaterialsPanel);
            app.settings_aut.Text = 'Set autonomous one-box vehicle settings';
            app.PowertrainPanel = uipanel(app.ComponentSelectionTab);
            app.PowertrainPanel.Title = 'Powertrain';
            app.input_sbw = uicheckbox(app.PowertrainPanel);
            app.input_sbw.Text = 'Steer-by-Wire';
            app.input_sbw.Value = true;
            app.input_emech_steering = uicheckbox(app.PowertrainPanel);
            app.input_emech_steering.Text = 'Electromech. Steering';
            app.input_emech_steering.Value = true;
            app.input_emech_brakes = uicheckbox(app.PowertrainPanel);
            app.input_emech_brakes.Text = 'Electromech. Braking';
            app.input_emech_brakes.Value = true;
            app.input_obc = uicheckbox(app.PowertrainPanel);
            app.input_obc.Text = 'On-board-Charger';
            app.input_obc.Value = true;
            app.AdditionalFeaturesPanel = uipanel(app.ComponentSelectionTab);
            app.AdditionalFeaturesPanel.Title = 'Additional Features';
            app.input_keyless_go = uicheckbox(app.AdditionalFeaturesPanel);
            app.input_keyless_go.Text = 'Keyless go';
            app.input_keyless_go.Value = true;
            app.input_acc = uicheckbox(app.AdditionalFeaturesPanel);
            app.input_acc.Text = 'ACC';
            app.input_acc.Value = true;
            app.input_lks = uicheckbox(app.AdditionalFeaturesPanel);
            app.input_lks.Text = 'LKS';
            app.input_lks.Value = true;
            app.input_bsm = uicheckbox(app.AdditionalFeaturesPanel);
            app.input_bsm.Text = 'BSM';
            app.input_bsm.Value = true;
            app.input_park_assist = uicheckbox(app.AdditionalFeaturesPanel);
            app.input_park_assist.Text = 'Park assist';
            app.input_park_assist.Value = true;
            app.input_night_vision = uicheckbox(app.AdditionalFeaturesPanel);
            app.input_night_vision.Text = 'Night Vision';
            app.input_trunk_assist = uicheckbox(app.AdditionalFeaturesPanel);
            app.input_trunk_assist.Text = 'Trunk assist';
            app.input_trunk_assist.Value = true;
            app.input_phone_connectivity = uicheckbox(app.AdditionalFeaturesPanel);
            app.input_phone_connectivity.Text = 'Phone connectivity';
            app.input_phone_connectivity.Value = true;
            app.HeadlighttechnologyLabel = uilabel(app.AdditionalFeaturesPanel);
            app.HeadlighttechnologyLabel.Text = 'Headlight technology';
            app.input_headlight_tech = uidropdown(app.AdditionalFeaturesPanel);
            app.input_headlight_tech.Items = {'LED', 'Xenon', 'Halogen'};
            app.input_headlight_tech.Value = 'LED';
            app.input_seat_warmers = uicheckbox(app.AdditionalFeaturesPanel);
            app.input_seat_warmers.Text = 'Seat warmers';
            app.input_seat_warmers.Value = true;
            app.input_pneumatic_doors = uicheckbox(app.AdditionalFeaturesPanel);
            app.input_pneumatic_doors.Text = 'Pneumatic doors';
            app.input_window_lift = uicheckbox(app.AdditionalFeaturesPanel);
            app.input_window_lift.Text = 'Electronic side window lift';
            app.input_window_lift.Value = true;
            app.input_adjustable_seats = uicheckbox(app.AdditionalFeaturesPanel);
            app.input_adjustable_seats.Text = 'Automatic adjustable seats';
            app.input_adjustable_seats.Value = true;
            app.input_panorama_roof = uicheckbox(app.AdditionalFeaturesPanel);
            app.input_panorama_roof.Text = 'Panorama roof';
            app.input_sliding_roof = uicheckbox(app.AdditionalFeaturesPanel);
            app.input_sliding_roof.Text = 'Sliding roof';
            app.input_hvac = uicheckbox(app.AdditionalFeaturesPanel);
            app.input_hvac.Text = 'HVAC';
            app.input_hvac.Value = true;
            app.input_tire_load = uicheckbox(app.AdditionalFeaturesPanel);
            app.input_tire_load.Text = 'Extra load tires';
            app.input_tire_load.Value = true;
            app.input_spare_tire = uicheckbox(app.AdditionalFeaturesPanel);
            app.input_spare_tire.Text = 'Spare tire';
            app.InteriorPanel_Components = uipanel(app.ComponentSelectionTab);
            app.InteriorPanel_Components.Title = 'Interior';
            app.NumberofinteriordisplaysLabel = uilabel(app.InteriorPanel_Components);
            app.NumberofinteriordisplaysLabel.Text = 'Number of interior displays';
            app.input_displays = uispinner(app.InteriorPanel_Components);
            app.input_displays.Limits = [0 10];
            app.input_displays.Value = 2;
            app.NumberofrowsLabel = uilabel(app.InteriorPanel_Components);
            app.NumberofrowsLabel.Text = 'Number of rows';
            app.input_int_n_rows_copy = uispinner(app.InteriorPanel_Components);
            app.input_int_n_rows_copy.Limits = [1 3];
            app.input_int_n_rows_copy.ValueChangedFcn = createCallbackFcn(app, @input_int_n_rows_copyValueChanged, true);
            app.input_int_n_rows_copy.Value = 2;
            app.SeatsthirdrowLabel_3 = uilabel(app.InteriorPanel_Components);
            app.SeatsthirdrowLabel_3.Text = 'Seats third row';
            app.input_seats_row_3_copy = uispinner(app.InteriorPanel_Components);
            app.input_seats_row_3_copy.Limits = [0 3];
            app.input_seats_row_3_copy.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_3_copyValueChanged, true);
            app.input_seats_row_3_copy.Value = 2;
            app.SeatsfirstrowLabel_3 = uilabel(app.InteriorPanel_Components);
            app.SeatsfirstrowLabel_3.Text = 'Seats first row';
            app.SeatssecondrowLabel_6 = uilabel(app.InteriorPanel_Components);
            app.SeatssecondrowLabel_6.Text = 'Seats second row';
            app.input_seats_row_2_copy = uispinner(app.InteriorPanel_Components);
            app.input_seats_row_2_copy.Limits = [0 3];
            app.input_seats_row_2_copy.Value = 2;
            app.input_seats_row_1_copy = uispinner(app.InteriorPanel_Components);
            app.input_seats_row_1_copy.Limits = [1 3];
            app.input_seats_row_1_copy.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_1_copyValueChanged, true);
            app.input_seats_row_1_copy.Value = 2;
            app.ComponentParametersTab = uitab(app.TabGroup);
            app.ComponentParametersTab.Title = 'Component Parameters';
            app.SecurityPanel = uipanel(app.ComponentParametersTab);
            app.SecurityPanel.Title = 'Security';
            app.FreecrashlengthsidemmLabel = uilabel(app.SecurityPanel);
            app.FreecrashlengthsidemmLabel.Text = 'Free crash length side [mm]';
            app.input_free_crash_length_3 = uispinner(app.SecurityPanel);
            app.input_free_crash_length_3.Step = 20;
            app.input_free_crash_length_3.Limits = [0 500];
            app.input_free_crash_length_3.Value = 250;
            app.FreecrashlengthrearmmLabel = uilabel(app.SecurityPanel);
            app.FreecrashlengthrearmmLabel.Text = 'Free crash length rear [mm]';
            app.input_free_crash_length_2 = uispinner(app.SecurityPanel);
            app.input_free_crash_length_2.Step = 50;
            app.input_free_crash_length_2.Limits = [0 1500];
            app.input_free_crash_length_2.Value = 400;
            app.NumberofsideairbagsLabel = uilabel(app.SecurityPanel);
            app.NumberofsideairbagsLabel.Text = 'Number of side airbags';
            app.input_side_airbags = uispinner(app.SecurityPanel);
            app.input_side_airbags.Limits = [0 20];
            app.input_side_airbags.Value = 2;
            app.EnginePanel = uipanel(app.ComponentParametersTab);
            app.EnginePanel.Title = 'Engine';
            app.EngineanglefrontdegLabel = uilabel(app.EnginePanel);
            app.EngineanglefrontdegLabel.Text = 'Engine angle front  [deg]';
            app.input_engine_angle_front_min = uispinner(app.EnginePanel);
            app.input_engine_angle_front_min.Step = 5;
            app.input_engine_angle_front_min.Limits = [0 180];
            app.EngineanglereardegLabel = uilabel(app.EnginePanel);
            app.EngineanglereardegLabel.Text = 'Engine angle rear  [deg]';
            app.input_engine_angle_rear_min = uispinner(app.EnginePanel);
            app.input_engine_angle_rear_min.Step = 5;
            app.input_engine_angle_rear_min.Limits = [0 180];
            app.TorqueratiofrontLabel = uilabel(app.EnginePanel);
            app.TorqueratiofrontLabel.Text = 'Torque ratio front [%]';
            app.input_torque_ratio_min = uispinner(app.EnginePanel);
            app.input_torque_ratio_min.Step = 5;
            app.input_torque_ratio_min.Limits = [0 180];
            app.input_torque_ratio_min.Value = 50;
            app.AxlePanel = uipanel(app.ComponentParametersTab);
            app.AxlePanel.Title = 'Axle';
            app.AxleanglexydegLabel = uilabel(app.AxlePanel);
            app.AxleanglexydegLabel.Text = 'Axle angle (xy) [deg]';
            app.input_axle_angle_1_min = uispinner(app.AxlePanel);
            app.input_axle_angle_1_min.Step = 0.5;
            app.input_axle_angle_1_min.Limits = [0 13];
            app.input_axle_angle_1_min.ValueDisplayFormat = '%.1f';
            app.input_axle_angle_1_min.Value = 7;
            app.AxleanglexzdegLabel = uilabel(app.AxlePanel);
            app.AxleanglexzdegLabel.Text = 'Axle angle (xz) [deg]';
            app.input_axle_angle_2_min = uispinner(app.AxlePanel);
            app.input_axle_angle_2_min.Step = 0.5;
            app.input_axle_angle_2_min.Limits = [0 13];
            app.input_axle_angle_2_min.ValueDisplayFormat = '%.1f';
            app.input_axle_angle_2_min.Value = 7;
            app.GearboxPanel = uipanel(app.ComponentParametersTab);
            app.GearboxPanel.Title = 'Gearbox';
            app.GeartypeRearLabel = uilabel(app.GearboxPanel);
            app.GeartypeRearLabel.Text = 'Gear type Rear';
            app.input_gear_type_rear = uidropdown(app.GearboxPanel);
            app.input_gear_type_rear.Items = {'Coaxial', 'Parallel', 'Coaxial-layshaft', 'All Combinations'};
            app.input_gear_type_rear.Value = 'Parallel';
            app.GeartypeFrontLabel = uilabel(app.GearboxPanel);
            app.GeartypeFrontLabel.Text = 'Gear type Front';
            app.input_gear_type_front = uidropdown(app.GearboxPanel);
            app.input_gear_type_front.Items = {'Coaxial', 'Parallel', 'Coaxial-layshaft', 'All Combinations'};
            app.input_gear_type_front.Value = 'Parallel';
            app.Gearratio1frontLabel = uilabel(app.GearboxPanel);
            app.Gearratio1frontLabel.Text = 'Gear ratio 1 front';
            app.input_gear_ratio_1_front_min = uispinner(app.GearboxPanel);
            app.input_gear_ratio_1_front_min.Step = 0.2;
            app.input_gear_ratio_1_front_min.Limits = [0 10];
            app.input_gear_ratio_1_front_min.ValueDisplayFormat = '%.1f';
            app.input_gear_ratio_1_front_min.Value = 10;
            app.Gearratio1rearLabel = uilabel(app.GearboxPanel);
            app.Gearratio1rearLabel.Text = 'Gear ratio 1 rear';
            app.input_gear_ratio_1_rear_min = uispinner(app.GearboxPanel);
            app.input_gear_ratio_1_rear_min.Step = 0.2;
            app.input_gear_ratio_1_rear_min.Limits = [0 10];
            app.input_gear_ratio_1_rear_min.ValueDisplayFormat = '%.1f';
            app.input_gear_ratio_1_rear_min.Value = 10;
            app.GearboxoptgoalLabel = uilabel(app.GearboxPanel);
            app.GearboxoptgoalLabel.Text = 'Gearbox opt. goal';
            app.input_gearbox_opt = uidropdown(app.GearboxPanel);
            app.input_gearbox_opt.Items = {'Length', 'Height', 'All Combinations'};
            app.input_gearbox_opt.Value = 'Length';
            app.input_two_speed = uicheckbox(app.GearboxPanel);
            app.input_two_speed.Text = 'Two speed gearbox?';
            app.Gearratio2frontLabel = uilabel(app.GearboxPanel);
            app.Gearratio2frontLabel.Text = 'Gear ratio 2 front';
            app.input_gear_ratio_2_front_min = uispinner(app.GearboxPanel);
            app.input_gear_ratio_2_front_min.Step = 0.2;
            app.input_gear_ratio_2_front_min.Limits = [0 10];
            app.input_gear_ratio_2_front_min.ValueDisplayFormat = '%.1f';
            app.input_gear_ratio_2_front_min.Value = 10;
            app.Gearratio2rearLabel = uilabel(app.GearboxPanel);
            app.Gearratio2rearLabel.Text = 'Gear ratio 2 rear';
            app.input_gear_ratio_2_rear_min = uispinner(app.GearboxPanel);
            app.input_gear_ratio_2_rear_min.Step = 0.2;
            app.input_gear_ratio_2_rear_min.Limits = [0 10];
            app.input_gear_ratio_2_rear_min.ValueDisplayFormat = '%.1f';
            app.input_gear_ratio_2_rear_min.Value = 10;
            app.input_Gearboxlossmodel = uicheckbox(app.GearboxPanel);
            app.input_Gearboxlossmodel.Text = 'Gearbox loss model';
            app.OthersPanel = uipanel(app.ComponentParametersTab);
            app.OthersPanel.Title = 'Others';
            app.TirediametermmLabel = uilabel(app.OthersPanel);
            app.TirediametermmLabel.Text = 'Tire diameter [mm]';
            app.input_tire_diam_min = uispinner(app.OthersPanel);
            app.input_tire_diam_min.Step = 20;
            app.input_tire_diam_min.Limits = [400 1000];
            app.input_tire_diam_min.Value = 700;
            app.input_tire_diam_max = uispinner(app.OthersPanel);
            app.input_tire_diam_max.Step = 20;
            app.input_tire_diam_max.Limits = [400 1000];
            app.input_tire_diam_max.Value = 700;
            app.TrunkratiofrontrearLabel = uilabel(app.OthersPanel);
            app.TrunkratiofrontrearLabel.Text = 'Trunk ratio front/rear [%]';
            app.input_trunk_ratio_min = uispinner(app.OthersPanel);
            app.input_trunk_ratio_min.Step = 5;
            app.input_trunk_ratio_min.Limits = [0 100];
            app.input_trunk_ratio_min.Value = 50;
            app.input_trunk_ratio_max = uispinner(app.OthersPanel);
            app.input_trunk_ratio_max.Step = 5;
            app.input_trunk_ratio_max.Limits = [0 100];
            app.input_trunk_ratio_max.Value = 50;
            app.DistancewheelhousebumpermmLabel = uilabel(app.OthersPanel);
            app.DistancewheelhousebumpermmLabel.Text = 'Distance wheelhouse-bumper [mm]';
            app.input_dist_wheelhouse2bumper_min = uispinner(app.OthersPanel);
            app.input_dist_wheelhouse2bumper_min.Step = 50;
            app.input_dist_wheelhouse2bumper_min.Limits = [0 800];
            app.input_dist_wheelhouse2bumper_min.Value = 200;
            app.input_dist_wheelhouse2bumper_max = uispinner(app.OthersPanel);
            app.input_dist_wheelhouse2bumper_max.Step = 50;
            app.input_dist_wheelhouse2bumper_max.Limits = [0 800];
            app.input_dist_wheelhouse2bumper_max.Value = 200;
            app.SteeringdistributionfrontLabel = uilabel(app.OthersPanel);
            app.SteeringdistributionfrontLabel.Text = 'Steering distribution front [%]';
            app.input_steering_ratio_min = uispinner(app.OthersPanel);
            app.input_steering_ratio_min.Step = 2;
            app.input_steering_ratio_min.Limits = [50 100];
            app.input_steering_ratio_min.Value = 70;
            app.input_steering_ratio_max = uispinner(app.OthersPanel);
            app.input_steering_ratio_max.Step = 2;
            app.input_steering_ratio_max.Limits = [50 100];
            app.input_steering_ratio_max.Value = 70;
            app.FrontwindowangleLabel = uilabel(app.OthersPanel);
            app.FrontwindowangleLabel.Text = 'Front window angle [°]';
            app.input_window_angle_front_min = uispinner(app.OthersPanel);
            app.input_window_angle_front_min.Limits = [0 85];
            app.input_window_angle_front_min.Value = 15;
            app.RearwindowangleLabel = uilabel(app.OthersPanel);
            app.RearwindowangleLabel.Text = 'Rear window angle [°]';
            app.input_window_angle_rear_min = uispinner(app.OthersPanel);
            app.input_window_angle_rear_min.Limits = [0 85];
            app.input_window_angle_rear_min.Value = 15;
            app.DistancewheelhousebumperFrontmmLabel = uilabel(app.OthersPanel);
            app.DistancewheelhousebumperFrontmmLabel.Text = 'Distance wheelhouse-bumper Front [mm]';
            app.input_dist_wheelhouse2bumper_f_min = uispinner(app.OthersPanel);
            app.input_dist_wheelhouse2bumper_f_min.Step = 50;
            app.input_dist_wheelhouse2bumper_f_min.Limits = [0 800];
            app.DistancewheelhousebumperRearmmLabel = uilabel(app.OthersPanel);
            app.DistancewheelhousebumperRearmmLabel.Text = 'Distance wheelhouse-bumper Rear [mm]';
            app.input_dist_wheelhouse2bumper_r_min = uispinner(app.OthersPanel);
            app.input_dist_wheelhouse2bumper_r_min.Step = 50;
            app.input_dist_wheelhouse2bumper_r_min.Limits = [0 800];
            app.input_dist_wheelhouse2bumper_f_max = uispinner(app.OthersPanel);
            app.input_dist_wheelhouse2bumper_f_max.Step = 50;
            app.input_dist_wheelhouse2bumper_f_max.Limits = [0 800];
            app.input_dist_wheelhouse2bumper_f_max.Value = 500;
            app.input_dist_wheelhouse2bumper_r_max = uispinner(app.OthersPanel);
            app.input_dist_wheelhouse2bumper_r_max.Step = 50;
            app.input_dist_wheelhouse2bumper_r_max.Limits = [0 800];
            app.input_dist_wheelhouse2bumper_r_max.Value = 500;
            app.input_window_angle_front_max = uispinner(app.OthersPanel);
            app.input_window_angle_front_max.Limits = [0 85];
            app.input_window_angle_front_max.Value = 50;
            app.input_window_angle_rear_max = uispinner(app.OthersPanel);
            app.input_window_angle_rear_max.Limits = [0 85];
            app.input_window_angle_rear_max.Value = 50;
            app.input_dist_int2fw_max = uispinner(app.OthersPanel);
            app.input_dist_int2fw_max.Step = 10;
            app.input_dist_int2fw_max.Limits = [0 500];
            app.input_dist_int2fw_max.Value = 50;
            app.input_dist_int2rw_max = uispinner(app.OthersPanel);
            app.input_dist_int2rw_max.Step = 10;
            app.input_dist_int2rw_max.Limits = [0 500];
            app.input_dist_int2rw_max.Value = 50;
            app.DistanceinteriortofrontwagonmmLabel = uilabel(app.OthersPanel);
            app.DistanceinteriortofrontwagonmmLabel.Text = 'Distance interior to front wagon [mm]';
            app.input_dist_int2fw_min = uispinner(app.OthersPanel);
            app.input_dist_int2fw_min.Step = 10;
            app.input_dist_int2fw_min.Limits = [0 500];
            app.DistanceinteriortorearwagonmmLabel = uilabel(app.OthersPanel);
            app.DistanceinteriortorearwagonmmLabel.Text = 'Distance interior to rear wagon [mm]';
            app.input_dist_int2rw_min = uispinner(app.OthersPanel);
            app.input_dist_int2rw_min.Step = 10;
            app.input_dist_int2rw_min.Limits = [0 500];
            app.input_engine_angle_front_max = uispinner(app.OthersPanel);
            app.input_engine_angle_front_max.Step = 5;
            app.input_engine_angle_front_max.Limits = [0 180];
            app.input_engine_angle_rear_max = uispinner(app.OthersPanel);
            app.input_engine_angle_rear_max.Step = 5;
            app.input_engine_angle_rear_max.Limits = [0 180];
            app.input_torque_ratio_max = uispinner(app.OthersPanel);
            app.input_torque_ratio_max.Step = 5;
            app.input_torque_ratio_max.Limits = [0 95];
            app.input_torque_ratio_max.Value = 50;
            app.input_axle_angle_1_max = uispinner(app.OthersPanel);
            app.input_axle_angle_1_max.Step = 0.5;
            app.input_axle_angle_1_max.Limits = [0 13];
            app.input_axle_angle_1_max.ValueDisplayFormat = '%.1f';
            app.input_axle_angle_1_max.Value = 7;
            app.input_axle_angle_2_max = uispinner(app.OthersPanel);
            app.input_axle_angle_2_max.Step = 0.5;
            app.input_axle_angle_2_max.Limits = [0 13];
            app.input_axle_angle_2_max.ValueDisplayFormat = '%.1f';
            app.input_axle_angle_2_max.Value = 7;
            app.input_gear_ratio_2_rear_max = uispinner(app.OthersPanel);
            app.input_gear_ratio_2_rear_max.Step = 0.2;
            app.input_gear_ratio_2_rear_max.Limits = [0 10];
            app.input_gear_ratio_2_rear_max.ValueDisplayFormat = '%.1f';
            app.input_gear_ratio_2_rear_max.Value = 10;
            app.input_gear_ratio_2_front_max = uispinner(app.OthersPanel);
            app.input_gear_ratio_2_front_max.Step = 0.2;
            app.input_gear_ratio_2_front_max.Limits = [0 10];
            app.input_gear_ratio_2_front_max.ValueDisplayFormat = '%.1f';
            app.input_gear_ratio_2_front_max.Value = 10;
            app.input_gear_ratio_1_rear_max = uispinner(app.OthersPanel);
            app.input_gear_ratio_1_rear_max.Step = 0.2;
            app.input_gear_ratio_1_rear_max.Limits = [0 10];
            app.input_gear_ratio_1_rear_max.ValueDisplayFormat = '%.1f';
            app.input_gear_ratio_1_rear_max.Value = 10;
            app.input_gear_ratio_1_front_max = uispinner(app.OthersPanel);
            app.input_gear_ratio_1_front_max.Step = 0.2;
            app.input_gear_ratio_1_front_max.Limits = [0 10];
            app.input_gear_ratio_1_front_max.ValueDisplayFormat = '%.1f';
            app.input_gear_ratio_1_front_max.Value = 10;
            app.Panel_2 = uipanel(app.OthersPanel);
            app.SoftwarePanel = uipanel(app.ComponentParametersTab);
            app.SoftwarePanel.Title = 'Software';
            app.input_eco_driving_check = uicheckbox(app.SoftwarePanel);
            app.input_eco_driving_check.Text = 'Eco Driving';
            app.input_eco_driving_check.Value = true;
            app.SavingsinLabel = uilabel(app.SoftwarePanel);
            app.SavingsinLabel.Text = 'Savings in %';
            app.input_eco_driving_percentage = uispinner(app.SoftwarePanel);
            app.input_eco_driving_percentage.Limits = [0 20];
            app.input_eco_driving_percentage.Value = 5;
            app.ManufacturingandMaterialsPanel = uipanel(app.ComponentParametersTab);
            app.ManufacturingandMaterialsPanel.Title = 'Manufacturing and Materials';
            app.EstimatedassemblytimehLabel = uilabel(app.ManufacturingandMaterialsPanel);
            app.EstimatedassemblytimehLabel.Text = 'Estimated assembly time [h]';
            app.input_assembly_time = uispinner(app.ManufacturingandMaterialsPanel);
            app.input_assembly_time.Step = 2;
            app.input_assembly_time.Limits = [0 100];
            app.input_assembly_time.Value = 25;
            app.AluminiumpercentageBIWLabel = uilabel(app.ManufacturingandMaterialsPanel);
            app.AluminiumpercentageBIWLabel.Text = 'Aluminium percentage BIW [%]';
            app.input_biw_alu_perc = uispinner(app.ManufacturingandMaterialsPanel);
            app.input_biw_alu_perc.Step = 5;
            app.input_biw_alu_perc.Limits = [0 100];
            app.input_biw_alu_perc.Value = 100;
            app.InteriorParametersTab = uitab(app.TabGroup);
            app.InteriorParametersTab.Title = ' Interior Parameters';
            app.FirstrowPanel = uipanel(app.InteriorParametersTab);
            app.FirstrowPanel.Title = 'First row';
            app.input_int_double_ar_1 = uicheckbox(app.FirstrowPanel);
            app.input_int_double_ar_1.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_1ValueChanged, true);
            app.input_int_double_ar_1.Text = 'Double armrest between seats';
            app.input_int_single_ar_between_1 = uicheckbox(app.FirstrowPanel);
            app.input_int_single_ar_between_1.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_1ValueChanged, true);
            app.input_int_single_ar_between_1.Text = 'Single armrest between seats';
            app.input_int_single_ar_outside_1 = uicheckbox(app.FirstrowPanel);
            app.input_int_single_ar_outside_1.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_1ValueChanged, true);
            app.input_int_single_ar_outside_1.Text = 'Single armrest outside';
            app.UpperBodylengthmmLabel = uilabel(app.FirstrowPanel);
            app.UpperBodylengthmmLabel.Text = 'Upper Body length [mm]';
            app.input_int_upperbody_length_1 = uispinner(app.FirstrowPanel);
            app.input_int_upperbody_length_1.Step = 25;
            app.input_int_upperbody_length_1.Limits = [400 1200];
            app.input_int_upperbody_length_1.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_1ValueChanged, true);
            app.input_int_upperbody_length_1.Value = 980;
            app.BackrestanglemaxdegLabel = uilabel(app.FirstrowPanel);
            app.BackrestanglemaxdegLabel.Text = 'Backrest angle (max) [deg]';
            app.input_int_angle_br_max_1 = uispinner(app.FirstrowPanel);
            app.input_int_angle_br_max_1.Step = 5;
            app.input_int_angle_br_max_1.Limits = [30 90];
            app.input_int_angle_br_max_1.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_1ValueChanged, true);
            app.input_int_angle_br_max_1.Tag = 'int.test';
            app.input_int_angle_br_max_1.Value = 80;
            app.HeadroommmLabel = uilabel(app.FirstrowPanel);
            app.HeadroommmLabel.Text = 'Headroom [mm]';
            app.input_int_headroom_1 = uispinner(app.FirstrowPanel);
            app.input_int_headroom_1.Step = 10;
            app.input_int_headroom_1.Limits = [0 500];
            app.input_int_headroom_1.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_1ValueChanged, true);
            app.input_int_headroom_1.Value = 150;
            app.SeatdepthmmLabel = uilabel(app.FirstrowPanel);
            app.SeatdepthmmLabel.Text = 'Seat depth [mm]';
            app.input_int_seat_depth_1 = uispinner(app.FirstrowPanel);
            app.input_int_seat_depth_1.Step = 10;
            app.input_int_seat_depth_1.Limits = [5 800];
            app.input_int_seat_depth_1.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_1ValueChanged, true);
            app.input_int_seat_depth_1.Value = 460;
            app.SeatadjustmentxmmLabel = uilabel(app.FirstrowPanel);
            app.SeatadjustmentxmmLabel.Text = 'Seat adjustment x [mm]';
            app.input_int_seat_adjustment_x_1 = uispinner(app.FirstrowPanel);
            app.input_int_seat_adjustment_x_1.Step = 5;
            app.input_int_seat_adjustment_x_1.Limits = [0 200];
            app.input_int_seat_adjustment_x_1.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_1ValueChanged, true);
            app.input_int_seat_adjustment_x_1.Value = 120;
            app.SeatheightmmLabel = uilabel(app.FirstrowPanel);
            app.SeatheightmmLabel.Text = 'Seat height [mm]';
            app.input_int_seat_height_1 = uispinner(app.FirstrowPanel);
            app.input_int_seat_height_1.Step = 25;
            app.input_int_seat_height_1.Limits = [5 800];
            app.input_int_seat_height_1.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_1ValueChanged, true);
            app.input_int_seat_height_1.Value = 400;
            app.SeatadjustmentzmmLabel = uilabel(app.FirstrowPanel);
            app.SeatadjustmentzmmLabel.Text = 'Seat adjustment z [mm]';
            app.input_int_seat_adjustment_z_1 = uispinner(app.FirstrowPanel);
            app.input_int_seat_adjustment_z_1.Step = 10;
            app.input_int_seat_adjustment_z_1.Limits = [0 300];
            app.input_int_seat_adjustment_z_1.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_1ValueChanged, true);
            app.input_int_seat_adjustment_z_1.Value = 100;
            app.FootlengthmmLabel = uilabel(app.FirstrowPanel);
            app.FootlengthmmLabel.Text = 'Foot length [mm]';
            app.input_int_foot_length_1 = uispinner(app.FirstrowPanel);
            app.input_int_foot_length_1.Step = 5;
            app.input_int_foot_length_1.Limits = [200 350];
            app.input_int_foot_length_1.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_1ValueChanged, true);
            app.input_int_foot_length_1.Value = 310;
            app.LegroomadditionalmmLabel = uilabel(app.FirstrowPanel);
            app.LegroomadditionalmmLabel.Text = 'Legroom additional [mm]';
            app.input_int_legroom_additional_1 = uispinner(app.FirstrowPanel);
            app.input_int_legroom_additional_1.Step = 10;
            app.input_int_legroom_additional_1.Limits = [-800 800];
            app.input_int_legroom_additional_1.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_1ValueChanged, true);
            app.input_int_legroom_additional_1.Value = 200;
            app.BackrestanglemindegLabel = uilabel(app.FirstrowPanel);
            app.BackrestanglemindegLabel.Text = 'Backrest angle (min) [deg]';
            app.input_int_angle_br_min_1 = uispinner(app.FirstrowPanel);
            app.input_int_angle_br_min_1.Step = 5;
            app.input_int_angle_br_min_1.Limits = [1 90];
            app.input_int_angle_br_min_1.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_1ValueChanged, true);
            app.input_int_angle_br_min_1.Value = 70;
            app.BackrestwidthmmLabel = uilabel(app.FirstrowPanel);
            app.BackrestwidthmmLabel.Text = 'Backrest width [mm]';
            app.input_int_backrest_width_1 = uispinner(app.FirstrowPanel);
            app.input_int_backrest_width_1.Step = 10;
            app.input_int_backrest_width_1.Limits = [300 800];
            app.input_int_backrest_width_1.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_1ValueChanged, true);
            app.input_int_backrest_width_1.Value = 530;
            app.SinglearmrestwidthmmLabel = uilabel(app.FirstrowPanel);
            app.SinglearmrestwidthmmLabel.Text = 'Single armrest width [mm]';
            app.input_int_armrest_width_single_1 = uispinner(app.FirstrowPanel);
            app.input_int_armrest_width_single_1.Step = 10;
            app.input_int_armrest_width_single_1.Limits = [0 200];
            app.input_int_armrest_width_single_1.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_1ValueChanged, true);
            app.input_int_armrest_width_single_1.Value = 80;
            app.GapbetweenseatsmmLabel = uilabel(app.FirstrowPanel);
            app.GapbetweenseatsmmLabel.Text = 'Gap between seats [mm]';
            app.input_int_seatgap_1 = uispinner(app.FirstrowPanel);
            app.input_int_seatgap_1.Step = 25;
            app.input_int_seatgap_1.Limits = [0 800];
            app.input_int_seatgap_1.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_1ValueChanged, true);
            app.input_int_seatgap_1.Value = 40;
            app.BackrestthicknessmmLabel = uilabel(app.FirstrowPanel);
            app.BackrestthicknessmmLabel.Text = 'Backrest thickness [mm]';
            app.input_int_thickness_br_1 = uispinner(app.FirstrowPanel);
            app.input_int_thickness_br_1.Step = 10;
            app.input_int_thickness_br_1.Limits = [10 350];
            app.input_int_thickness_br_1.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_1ValueChanged, true);
            app.input_int_thickness_br_1.Value = 150;
            app.SeatthicknessmmLabel = uilabel(app.FirstrowPanel);
            app.SeatthicknessmmLabel.Text = 'Seat thickness [mm]';
            app.input_int_thickness_seat_1 = uispinner(app.FirstrowPanel);
            app.input_int_thickness_seat_1.Step = 10;
            app.input_int_thickness_seat_1.Limits = [10 350];
            app.input_int_thickness_seat_1.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_1ValueChanged, true);
            app.input_int_thickness_seat_1.Value = 150;
            app.BackrestlengthmmLabel_2 = uilabel(app.FirstrowPanel);
            app.BackrestlengthmmLabel_2.Text = 'Backrest length [mm]';
            app.input_int_backrest_length_1 = uispinner(app.FirstrowPanel);
            app.input_int_backrest_length_1.Step = 50;
            app.input_int_backrest_length_1.Limits = [0 1200];
            app.input_int_backrest_length_1.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_1ValueChanged, true);
            app.input_int_backrest_length_1.Value = 800;
            app.DoublearmrestwidthmmLabel = uilabel(app.FirstrowPanel);
            app.DoublearmrestwidthmmLabel.Text = 'Double armrest width [mm]';
            app.input_int_armrest_width_double_1 = uispinner(app.FirstrowPanel);
            app.input_int_armrest_width_double_1.Step = 10;
            app.input_int_armrest_width_double_1.Limits = [0 200];
            app.input_int_armrest_width_double_1.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_1ValueChanged, true);
            app.input_int_armrest_width_double_1.Value = 80;
            app.GaptowallmmLabel = uilabel(app.FirstrowPanel);
            app.GaptowallmmLabel.Text = 'Gap to wall [mm]';
            app.input_int_gaptowall_1 = uispinner(app.FirstrowPanel);
            app.input_int_gaptowall_1.Step = 25;
            app.input_int_gaptowall_1.Limits = [0 800];
            app.input_int_gaptowall_1.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_1ValueChanged, true);
            app.input_int_gaptowall_1.Value = 40;
            app.SeatsfirstrowLabel = uilabel(app.FirstrowPanel);
            app.SeatsfirstrowLabel.Text = 'Seats first row';
            app.input_seats_row_1 = uispinner(app.FirstrowPanel);
            app.input_seats_row_1.Limits = [1 3];
            app.input_seats_row_1.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_1ValueChanged, true);
            app.input_seats_row_1.Value = 2;
            app.ThirdrowPanel = uipanel(app.InteriorParametersTab);
            app.ThirdrowPanel.Title = 'Third row';
            app.input_int_double_ar_3 = uicheckbox(app.ThirdrowPanel);
            app.input_int_double_ar_3.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_3ValueChanged, true);
            app.input_int_double_ar_3.Text = 'Double armrest between seats';
            app.input_int_single_ar_between_3 = uicheckbox(app.ThirdrowPanel);
            app.input_int_single_ar_between_3.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_3ValueChanged, true);
            app.input_int_single_ar_between_3.Text = 'Single armrest between seats';
            app.input_int_single_ar_outside_3 = uicheckbox(app.ThirdrowPanel);
            app.input_int_single_ar_outside_3.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_3ValueChanged, true);

            app.input_int_single_ar_outside_3.Text = 'Single armrest outside';
            app.UpperBodylengthmmLabel_2 = uilabel(app.ThirdrowPanel);
            app.UpperBodylengthmmLabel_2.Text = 'Upper Body length [mm]';
            app.input_int_upperbody_length_3 = uispinner(app.ThirdrowPanel);
            app.input_int_upperbody_length_3.Step = 25;
            app.input_int_upperbody_length_3.Limits = [400 1200];
            app.input_int_upperbody_length_3.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_3ValueChanged, true);
            app.input_int_upperbody_length_3.Value = 980;
            app.BackrestanglemaxdegLabel_2 = uilabel(app.ThirdrowPanel);
            app.BackrestanglemaxdegLabel_2.Text = 'Backrest angle (max) [deg]';
            app.input_int_angle_br_max_3 = uispinner(app.ThirdrowPanel);
            app.input_int_angle_br_max_3.Step = 5;
            app.input_int_angle_br_max_3.Limits = [30 90];
            app.input_int_angle_br_max_3.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_3ValueChanged, true);
            app.input_int_angle_br_max_3.Tag = 'int.test';
            app.input_int_angle_br_max_3.Value = 80;
            app.HeadroommmLabel_2 = uilabel(app.ThirdrowPanel);
            app.HeadroommmLabel_2.Text = 'Headroom [mm]';
            app.input_int_headroom_3 = uispinner(app.ThirdrowPanel);
            app.input_int_headroom_3.Step = 10;
            app.input_int_headroom_3.Limits = [0 500];
            app.input_int_headroom_3.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_3ValueChanged, true);
            app.input_int_headroom_3.Value = 150;
            app.SeatdepthmmLabel_2 = uilabel(app.ThirdrowPanel);
            app.SeatdepthmmLabel_2.Text = 'Seat depth [mm]';
            app.input_int_seat_depth_3 = uispinner(app.ThirdrowPanel);
            app.input_int_seat_depth_3.Step = 10;
            app.input_int_seat_depth_3.Limits = [5 800];
            app.input_int_seat_depth_3.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_3ValueChanged, true);
            app.input_int_seat_depth_3.Value = 460;
            app.SeatadjustmentxmmLabel_2 = uilabel(app.ThirdrowPanel);
            app.SeatadjustmentxmmLabel_2.Text = 'Seat adjustment x [mm]';
            app.input_int_seat_adjustment_x_3 = uispinner(app.ThirdrowPanel);
            app.input_int_seat_adjustment_x_3.Step = 5;
            app.input_int_seat_adjustment_x_3.Limits = [0 200];
            app.input_int_seat_adjustment_x_3.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_3ValueChanged, true);
            app.input_int_seat_adjustment_x_3.Value = 120;
            app.SeatheightmmLabel_2 = uilabel(app.ThirdrowPanel);
            app.SeatheightmmLabel_2.Text = 'Seat height [mm]';
            app.input_int_seat_height_3 = uispinner(app.ThirdrowPanel);
            app.input_int_seat_height_3.Step = 25;
            app.input_int_seat_height_3.Limits = [5 800];
            app.input_int_seat_height_3.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_3ValueChanged, true);
            app.input_int_seat_height_3.Value = 400;
            app.SeatadjustmentzmmLabel_2 = uilabel(app.ThirdrowPanel);
            app.SeatadjustmentzmmLabel_2.Text = 'Seat adjustment z [mm]';
            app.input_int_seat_adjustment_z_3 = uispinner(app.ThirdrowPanel);
            app.input_int_seat_adjustment_z_3.Step = 10;
            app.input_int_seat_adjustment_z_3.Limits = [0 300];
            app.input_int_seat_adjustment_z_3.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_3ValueChanged, true);
            app.input_int_seat_adjustment_z_3.Value = 100;
            app.FootlengthmmLabel_2 = uilabel(app.ThirdrowPanel);
            app.FootlengthmmLabel_2.Text = 'Foot length [mm]';
            app.input_int_foot_length_3 = uispinner(app.ThirdrowPanel);
            app.input_int_foot_length_3.Step = 5;
            app.input_int_foot_length_3.Limits = [200 350];
            app.input_int_foot_length_3.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_3ValueChanged, true);
            app.input_int_foot_length_3.Value = 310;
            app.LegroomadditionalmmLabel_2 = uilabel(app.ThirdrowPanel);
            app.LegroomadditionalmmLabel_2.Text = 'Legroom additional [mm]';
            app.input_int_legroom_additional_3 = uispinner(app.ThirdrowPanel);
            app.input_int_legroom_additional_3.Step = 10;
            app.input_int_legroom_additional_3.Limits = [-800 800];
            app.input_int_legroom_additional_3.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_3ValueChanged, true);
            app.input_int_legroom_additional_3.Value = 200;
            app.BackrestanglemindegLabel_2 = uilabel(app.ThirdrowPanel);
            app.BackrestanglemindegLabel_2.Text = 'Backrest angle (min) [deg]';
            app.input_int_angle_br_min_3 = uispinner(app.ThirdrowPanel);
            app.input_int_angle_br_min_3.Step = 5;
            app.input_int_angle_br_min_3.Limits = [1 90];
            app.input_int_angle_br_min_3.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_3ValueChanged, true);
            app.input_int_angle_br_min_3.Value = 70;
            app.BackrestwidthmmLabel_2 = uilabel(app.ThirdrowPanel);
            app.BackrestwidthmmLabel_2.Text = 'Backrest width [mm]';
            app.input_int_backrest_width_3 = uispinner(app.ThirdrowPanel);
            app.input_int_backrest_width_3.Step = 10;
            app.input_int_backrest_width_3.Limits = [300 800];
            app.input_int_backrest_width_3.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_3ValueChanged, true);
            app.input_int_backrest_width_3.Value = 530;
            app.SinglearmrestwidthmmLabel_2 = uilabel(app.ThirdrowPanel);
            app.SinglearmrestwidthmmLabel_2.Text = 'Single armrest width [mm]';
            app.input_int_armrest_width_single_3 = uispinner(app.ThirdrowPanel);
            app.input_int_armrest_width_single_3.Step = 10;
            app.input_int_armrest_width_single_3.Limits = [0 200];
            app.input_int_armrest_width_single_3.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_3ValueChanged, true);
            app.input_int_armrest_width_single_3.Value = 80;
            app.GapbetweenseatsmmLabel_2 = uilabel(app.ThirdrowPanel);
            app.GapbetweenseatsmmLabel_2.Text = 'Gap between seats [mm]';
            app.input_int_seatgap_3 = uispinner(app.ThirdrowPanel);
            app.input_int_seatgap_3.Step = 25;
            app.input_int_seatgap_3.Limits = [0 800];
            app.input_int_seatgap_3.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_3ValueChanged, true);
            app.input_int_seatgap_3.Value = 40;
            app.BackrestthicknessmmLabel_2 = uilabel(app.ThirdrowPanel);
            app.BackrestthicknessmmLabel_2.Text = 'Backrest thickness [mm]';
            app.input_int_thickness_br_3 = uispinner(app.ThirdrowPanel);
            app.input_int_thickness_br_3.Step = 10;
            app.input_int_thickness_br_3.Limits = [10 350];
            app.input_int_thickness_br_3.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_3ValueChanged, true);
            app.input_int_thickness_br_3.Value = 150;
            app.SeatthicknessmmLabel_2 = uilabel(app.ThirdrowPanel);
            app.SeatthicknessmmLabel_2.Text = 'Seat thickness [mm]';
            app.input_int_thickness_seat_3 = uispinner(app.ThirdrowPanel);
            app.input_int_thickness_seat_3.Step = 10;
            app.input_int_thickness_seat_3.Limits = [10 350];
            app.input_int_thickness_seat_3.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_3ValueChanged, true);
            app.input_int_thickness_seat_3.Value = 150;
            app.BackrestlengthmmLabel_3 = uilabel(app.ThirdrowPanel);
            app.BackrestlengthmmLabel_3.Text = 'Backrest length [mm]';
            app.input_int_backrest_length_3 = uispinner(app.ThirdrowPanel);
            app.input_int_backrest_length_3.Step = 50;
            app.input_int_backrest_length_3.Limits = [0 1200];
            app.input_int_backrest_length_3.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_3ValueChanged, true);
            app.input_int_backrest_length_3.Value = 800;
            app.DoublearmrestwidthmmLabel_2 = uilabel(app.ThirdrowPanel);
            app.DoublearmrestwidthmmLabel_2.Text = 'Double armrest width [mm]';
            app.input_int_armrest_width_double_3 = uispinner(app.ThirdrowPanel);
            app.input_int_armrest_width_double_3.Step = 10;
            app.input_int_armrest_width_double_3.Limits = [0 200];
            app.input_int_armrest_width_double_3.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_3ValueChanged, true);
            app.input_int_armrest_width_double_3.Value = 80;
            app.GaptowallmmLabel_2 = uilabel(app.ThirdrowPanel);
            app.GaptowallmmLabel_2.Text = 'Gap to wall [mm]';
            app.input_int_gaptowall_3 = uispinner(app.ThirdrowPanel);
            app.input_int_gaptowall_3.Step = 25;
            app.input_int_gaptowall_3.Limits = [0 800];
            app.input_int_gaptowall_3.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_3ValueChanged, true);
            app.input_int_gaptowall_3.Value = 40;
            app.SeatsthirdrowLabel_2 = uilabel(app.ThirdrowPanel);
            app.SeatsthirdrowLabel_2.Text = 'Seats third row';
            app.input_seats_row_3 = uispinner(app.ThirdrowPanel);
            app.input_seats_row_3.Limits = [0 3];
            app.input_seats_row_3.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_3ValueChanged, true);
            app.input_seats_row_3.Value = 2;
            app.SecondrowPanel = uipanel(app.InteriorParametersTab);
            app.SecondrowPanel.Title = 'Second row';
            app.input_int_double_ar_2 = uicheckbox(app.SecondrowPanel);
            app.input_int_double_ar_2.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_2ValueChanged, true);
            app.input_int_double_ar_2.Text = 'Double armrest between seats';
            app.input_int_single_ar_between_2 = uicheckbox(app.SecondrowPanel);
            app.input_int_single_ar_between_2.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_2ValueChanged, true);
            app.input_int_single_ar_between_2.Text = 'Single armrest between seats';
            app.input_int_single_ar_outside_2 = uicheckbox(app.SecondrowPanel);
            app.input_int_single_ar_outside_2.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_2ValueChanged, true);
            app.input_int_single_ar_outside_2.Text = 'Single armrest outside';
            app.UpperBodylengthmmLabel_3 = uilabel(app.SecondrowPanel);
            app.UpperBodylengthmmLabel_3.Text = 'Upper Body length [mm]';
            app.input_int_upperbody_length_2 = uispinner(app.SecondrowPanel);
            app.input_int_upperbody_length_2.Step = 25;
            app.input_int_upperbody_length_2.Limits = [400 1200];
            app.input_int_upperbody_length_2.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_2ValueChanged, true);
            app.input_int_upperbody_length_2.Value = 980;
            app.BackrestanglemaxdegLabel_3 = uilabel(app.SecondrowPanel);
            app.BackrestanglemaxdegLabel_3.Text = 'Backrest angle (max) [deg]';
            app.input_int_angle_br_max_2 = uispinner(app.SecondrowPanel);
            app.input_int_angle_br_max_2.Step = 5;
            app.input_int_angle_br_max_2.Limits = [30 90];
            app.input_int_angle_br_max_2.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_2ValueChanged, true);
            app.input_int_angle_br_max_2.Tag = 'int.test';
            app.input_int_angle_br_max_2.Value = 80;
            app.HeadroommmLabel_3 = uilabel(app.SecondrowPanel);
            app.HeadroommmLabel_3.Text = 'Headroom [mm]';
            app.input_int_headroom_2 = uispinner(app.SecondrowPanel);
            app.input_int_headroom_2.Step = 10;
            app.input_int_headroom_2.Limits = [0 500];
            app.input_int_headroom_2.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_2ValueChanged, true);
            app.input_int_headroom_2.Value = 150;
            app.SeatdepthmmLabel_3 = uilabel(app.SecondrowPanel);
            app.SeatdepthmmLabel_3.Text = 'Seat depth [mm]';
            app.input_int_seat_depth_2 = uispinner(app.SecondrowPanel);
            app.input_int_seat_depth_2.Step = 10;
            app.input_int_seat_depth_2.Limits = [5 800];
            app.input_int_seat_depth_2.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_2ValueChanged, true);
            app.input_int_seat_depth_2.Value = 460;
            app.SeatadjustmentxmmLabel_3 = uilabel(app.SecondrowPanel);
            app.SeatadjustmentxmmLabel_3.Text = 'Seat adjustment x [mm]';
            app.input_int_seat_adjustment_x_2 = uispinner(app.SecondrowPanel);
            app.input_int_seat_adjustment_x_2.Step = 5;
            app.input_int_seat_adjustment_x_2.Limits = [0 200];
            app.input_int_seat_adjustment_x_2.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_2ValueChanged, true);
            app.input_int_seat_adjustment_x_2.Value = 120;
            app.SeatheightmmLabel_3 = uilabel(app.SecondrowPanel);
            app.SeatheightmmLabel_3.Text = 'Seat height [mm]';
            app.input_int_seat_height_2 = uispinner(app.SecondrowPanel);
            app.input_int_seat_height_2.Step = 25;
            app.input_int_seat_height_2.Limits = [5 800];
            app.input_int_seat_height_2.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_2ValueChanged, true);
            app.input_int_seat_height_2.Value = 400;
            app.SeatadjustmentzmmLabel_3 = uilabel(app.SecondrowPanel);
            app.SeatadjustmentzmmLabel_3.Text = 'Seat adjustment z [mm]';
            app.input_int_seat_adjustment_z_2 = uispinner(app.SecondrowPanel);
            app.input_int_seat_adjustment_z_2.Step = 10;
            app.input_int_seat_adjustment_z_2.Limits = [0 300];
            app.input_int_seat_adjustment_z_2.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_2ValueChanged, true);
            app.input_int_seat_adjustment_z_2.Value = 100;
            app.FootlengthmmLabel_3 = uilabel(app.SecondrowPanel);
            app.FootlengthmmLabel_3.Text = 'Foot length [mm]';
            app.input_int_foot_length_2 = uispinner(app.SecondrowPanel);
            app.input_int_foot_length_2.Step = 5;
            app.input_int_foot_length_2.Limits = [200 350];
            app.input_int_foot_length_2.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_2ValueChanged, true);
            app.input_int_foot_length_2.Value = 310;
            app.LegroomadditionalmmLabel_3 = uilabel(app.SecondrowPanel);
            app.LegroomadditionalmmLabel_3.Text = 'Legroom additional [mm]';
            app.input_int_legroom_additional_2 = uispinner(app.SecondrowPanel);
            app.input_int_legroom_additional_2.Step = 10;
            app.input_int_legroom_additional_2.Limits = [-800 800];
            app.input_int_legroom_additional_2.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_2ValueChanged, true);
            app.input_int_legroom_additional_2.Value = 200;
            app.BackrestanglemindegLabel_3 = uilabel(app.SecondrowPanel);
            app.BackrestanglemindegLabel_3.Text = 'Backrest angle (min) [deg]';
            app.input_int_angle_br_min_2 = uispinner(app.SecondrowPanel);
            app.input_int_angle_br_min_2.Step = 5;
            app.input_int_angle_br_min_2.Limits = [1 90];
            app.input_int_angle_br_min_2.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_2ValueChanged, true);
            app.input_int_angle_br_min_2.Value = 70;
            app.BackrestwidthmmLabel_3 = uilabel(app.SecondrowPanel);
            app.BackrestwidthmmLabel_3.Text = 'Backrest width [mm]';
            app.input_int_backrest_width_2 = uispinner(app.SecondrowPanel);
            app.input_int_backrest_width_2.Step = 10;
            app.input_int_backrest_width_2.Limits = [300 800];
            app.input_int_backrest_width_2.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_2ValueChanged, true);
            app.input_int_backrest_width_2.Value = 530;
            app.SinglearmrestwidthmmLabel_3 = uilabel(app.SecondrowPanel);
            app.SinglearmrestwidthmmLabel_3.Text = 'Single armrest width [mm]';
            app.input_int_armrest_width_single_2 = uispinner(app.SecondrowPanel);
            app.input_int_armrest_width_single_2.Step = 10;
            app.input_int_armrest_width_single_2.Limits = [0 200];
            app.input_int_armrest_width_single_2.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_2ValueChanged, true);
            app.input_int_armrest_width_single_2.Value = 80;
            app.GapbetweenseatsmmLabel_3 = uilabel(app.SecondrowPanel);
            app.GapbetweenseatsmmLabel_3.Text = 'Gap between seats [mm]';
            app.input_int_seatgap_2 = uispinner(app.SecondrowPanel);
            app.input_int_seatgap_2.Step = 25;
            app.input_int_seatgap_2.Limits = [0 800];
            app.input_int_seatgap_2.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_2ValueChanged, true);
            app.input_int_seatgap_2.Value = 40;
            app.BackrestthicknessmmLabel_3 = uilabel(app.SecondrowPanel);
            app.BackrestthicknessmmLabel_3.Text = 'Backrest thickness [mm]';
            app.input_int_thickness_br_2 = uispinner(app.SecondrowPanel);
            app.input_int_thickness_br_2.Step = 10;
            app.input_int_thickness_br_2.Limits = [10 350];
            app.input_int_thickness_br_2.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_2ValueChanged, true);
            app.input_int_thickness_br_2.Value = 150;
            app.SeatthicknessmmLabel_3 = uilabel(app.SecondrowPanel);
            app.SeatthicknessmmLabel_3.Text = 'Seat thickness [mm]';
            app.input_int_thickness_seat_2 = uispinner(app.SecondrowPanel);
            app.input_int_thickness_seat_2.Step = 10;
            app.input_int_thickness_seat_2.Limits = [10 350];
            app.input_int_thickness_seat_2.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_2ValueChanged, true);
            app.input_int_thickness_seat_2.Value = 150;
            app.BackrestlengthmmLabel_4 = uilabel(app.SecondrowPanel);
            app.BackrestlengthmmLabel_4.Text = 'Backrest length [mm]';
            app.input_int_backrest_length_2 = uispinner(app.SecondrowPanel);
            app.input_int_backrest_length_2.Step = 50;
            app.input_int_backrest_length_2.Limits = [0 1200];
            app.input_int_backrest_length_2.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_2ValueChanged, true);
            app.input_int_backrest_length_2.Value = 800;
            app.DoublearmrestwidthmmLabel_3 = uilabel(app.SecondrowPanel);
            app.DoublearmrestwidthmmLabel_3.Text = 'Double armrest width [mm]';
            app.input_int_armrest_width_double_2 = uispinner(app.SecondrowPanel);
            app.input_int_armrest_width_double_2.Step = 10;
            app.input_int_armrest_width_double_2.Limits = [0 200];
            app.input_int_armrest_width_double_2.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_2ValueChanged, true);
            app.input_int_armrest_width_double_2.Value = 80;
            app.GaptowallmmLabel_3 = uilabel(app.SecondrowPanel);
            app.GaptowallmmLabel_3.Text = 'Gap to wall [mm]';
            app.input_int_gaptowall_2 = uispinner(app.SecondrowPanel);
            app.input_int_gaptowall_2.Step = 25;
            app.input_int_gaptowall_2.Limits = [0 800];
            app.input_int_gaptowall_2.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_2ValueChanged, true);
            app.input_int_gaptowall_2.Value = 40;
            app.SeatssecondrowLabel_5 = uilabel(app.SecondrowPanel);
            app.SeatssecondrowLabel_5.Text = 'Seats second row';
            app.input_seats_row_2 = uispinner(app.SecondrowPanel);
            app.input_seats_row_2.Limits = [0 3];
            app.input_seats_row_2.ValueChangedFcn = createCallbackFcn(app, @input_seats_row_2ValueChanged, true);
            app.input_seats_row_2.Value = 2;
            app.NumberofrowsLabel_2 = uilabel(app.InteriorParametersTab);
            app.NumberofrowsLabel_2.Text = 'Number of rows';
            app.input_int_n_rows = uispinner(app.InteriorParametersTab);
            app.input_int_n_rows.Limits = [1 3];
            app.input_int_n_rows.ValueChangedFcn = createCallbackFcn(app, @input_int_n_rowsValueChanged, true);
            app.input_int_n_rows.Value = 2;
            app.GeneralPanel = uipanel(app.InteriorParametersTab);
            app.GeneralPanel.Title = 'General';
            app.input_int_comfort = uicheckbox(app.GeneralPanel);
            app.input_int_comfort.Text = 'Comfort Package';
            app.input_int_sleep = uicheckbox(app.GeneralPanel);
            app.input_int_sleep.Text = 'Sleep Package';
            app.input_int_ergonomic = uicheckbox(app.GeneralPanel);
            app.input_int_ergonomic.Text = 'Ergonomic Package';
            app.input_int_work = uicheckbox(app.GeneralPanel);
            app.input_int_work.Text = 'Work Package';
            app.WalldistancemmLabel = uilabel(app.GeneralPanel);
            app.WalldistancemmLabel.Enable = 'off';
            app.WalldistancemmLabel.Text = 'Wall distance [mm]';
            app.input_int_b2b_walldistance = uispinner(app.GeneralPanel);
            app.input_int_b2b_walldistance.Step = 10;
            app.input_int_b2b_walldistance.Limits = [0 1000];
            app.input_int_b2b_walldistance.Enable = 'off';
            app.input_int_b2b_walldistance.Value = 200;
            app.LegoverlapmmLabel = uilabel(app.GeneralPanel);
            app.LegoverlapmmLabel.Text = 'Leg overlap [mm]';
            app.input_int_x_overlap = uispinner(app.GeneralPanel);
            app.input_int_x_overlap.Step = 10;
            app.input_int_x_overlap.Limits = [0 1000];
            app.input_int_x_overlap.Value = 100;
            app.UseCaseLabel = uilabel(app.GeneralPanel);
            app.UseCaseLabel.Text = 'Use Case';
            app.input_int_usecase = uidropdown(app.GeneralPanel);
            app.input_int_usecase.Items = {'Individual', 'Comfort Class', 'Middle Class', 'Low Class', '', ''};
            app.input_int_usecase.Value = 'Individual';
            app.MinH30frontLabel = uilabel(app.GeneralPanel);
            app.MinH30frontLabel.Text = 'Min. H30 front';
            app.input_minH30_front = uispinner(app.GeneralPanel);
            app.input_minH30_front.Step = 20;
            app.input_minH30_front.Limits = [100 800];
            app.input_minH30_front.Value = 300;
            app.MinH30rearLabel = uilabel(app.GeneralPanel);
            app.MinH30rearLabel.Text = 'Min. H30 rear';
            app.input_minH30_rear = uispinner(app.GeneralPanel);
            app.input_minH30_rear.Step = 20;
            app.input_minH30_rear.Limits = [100 800];
            app.input_minH30_rear.Value = 300;
            app.MininteriorheightmmLabel = uilabel(app.GeneralPanel);
            app.MininteriorheightmmLabel.Text = 'Min. interior height [mm]';
            app.input_int_height_min = uispinner(app.GeneralPanel);
            app.input_int_height_min.Step = 20;
            app.input_int_height_min.Limits = [200 2000];
            app.input_int_height_min.Value = 500;
            app.MaxinteriorheightmmLabel = uilabel(app.GeneralPanel);
            app.MaxinteriorheightmmLabel.Text = 'Max. interior height [mm]';
            app.input_int_height_max = uispinner(app.GeneralPanel);
            app.input_int_height_max.Step = 20;
            app.input_int_height_max.Limits = [300 2000];
            app.input_int_height_max.Value = 500;
            app.MaxH30frontLabel = uilabel(app.GeneralPanel);
            app.MaxH30frontLabel.Text = 'Max. H30 front';
            app.input_maxH30_front = uispinner(app.GeneralPanel);
            app.input_maxH30_front.Step = 20;
            app.input_maxH30_front.Limits = [100 800];
            app.input_maxH30_front.Value = 400;
            app.MaxH30rearLabel = uilabel(app.GeneralPanel);
            app.MaxH30rearLabel.Text = 'Max. H30 rear';
            app.input_maxH30_rear = uispinner(app.GeneralPanel);
            app.input_maxH30_rear.Step = 20;
            app.input_maxH30_rear.Limits = [100 800];
            app.input_maxH30_rear.Value = 400;
            app.SeatingLayoutDropDown_7Label_14 = uilabel(app.InteriorParametersTab);
            app.SeatingLayoutDropDown_7Label_14.Text = 'Seating Layout';
            app.input_seating_layout_copy = uidropdown(app.InteriorParametersTab);
            app.input_seating_layout_copy.Items = {'Vis-a-Vis', 'Conventional', 'Single Row', 'Back-to-Back', 'Vis-à-Vis in front', 'Vis-à-Vis in back'};
            app.input_seating_layout_copy.ValueChangedFcn = createCallbackFcn(app, @input_seating_layout_copyValueChanged, true);
            app.input_seating_layout_copy.Value = 'Vis-a-Vis';
            app.TechnicalPropertiesTab = uitab(app.TabGroup);
            app.TechnicalPropertiesTab.Title = 'Technical Properties';
            app.TopspeedinkmhLabel = uilabel(app.TechnicalPropertiesTab);
            app.TopspeedinkmhLabel.Text = 'Top speed [km/h]';
            app.input_maximal_speed = uispinner(app.TechnicalPropertiesTab);
            app.input_maximal_speed.Step = 10;
            app.input_maximal_speed.Limits = [50 250];
            app.input_maximal_speed.Value = 150;
            app.Minimumaccelerationtimefrom0to100kmhinsLabel = uilabel(app.TechnicalPropertiesTab);
            app.Minimumaccelerationtimefrom0to100kmhinsLabel.Text = 'Minimum acceleration time (0 - 100km/h) [s]';
            app.input_maximal_acceleration = uispinner(app.TechnicalPropertiesTab);
            app.input_maximal_acceleration.Step = 0.1;
            app.input_maximal_acceleration.Limits = [2 14];
            app.input_maximal_acceleration.ValueDisplayFormat = '%.1f';
            app.input_maximal_acceleration.Value = 10;
            app.GroundclearanceLabel = uilabel(app.TechnicalPropertiesTab);
            app.GroundclearanceLabel.Text = 'Ground clearance';
            app.input_ground_clearance = uidropdown(app.TechnicalPropertiesTab);
            app.input_ground_clearance.Items = {'flatfloor', 'highfloor'};
            app.input_ground_clearance.Value = 'flatfloor';
            app.MinrangekmLabel = uilabel(app.TechnicalPropertiesTab);
            app.MinrangekmLabel.Text = 'Min. range [km]';
            app.input_range = uispinner(app.TechnicalPropertiesTab);
            app.input_range.Step = 5;
            app.input_range.Limits = [50 2000];
            app.input_range.Value = 250;
            app.FreecrashlengthfrontmmLabel = uilabel(app.TechnicalPropertiesTab);
            app.FreecrashlengthfrontmmLabel.Text = 'Free crash length front [mm]';
            app.input_free_crash_length = uispinner(app.TechnicalPropertiesTab);
            app.input_free_crash_length.Step = 50;
            app.input_free_crash_length.Limits = [0 1500];
            app.input_free_crash_length.Value = 500;
            app.TurningdiametermLabel = uilabel(app.TechnicalPropertiesTab);
            app.TurningdiametermLabel.Text = 'Turning diameter [m]';
            app.input_turning_diameter = uispinner(app.TechnicalPropertiesTab);
            app.input_turning_diameter.Step = 0.5;
            app.input_turning_diameter.Limits = [0 20];
            app.input_turning_diameter.Value = 5.5;
            app.TotaltrunkvolumeLLabel = uilabel(app.TechnicalPropertiesTab);
            app.TotaltrunkvolumeLLabel.Text = 'Total trunk volume [L] ';
            app.input_trunk_volume = uispinner(app.TechnicalPropertiesTab);
            app.input_trunk_volume.Step = 20;
            app.input_trunk_volume.Limits = [0 10000];
            app.input_trunk_volume.ValueDisplayFormat = '%.0f';
            app.input_trunk_volume.Value = 200;
            app.CustomerrelevantPropertiesTab = uitab(app.TabGroup);
            app.CustomerrelevantPropertiesTab.Title = 'Customer-relevant Properties';
            app.DynamicsGraphPanel = uipanel(app.CustomerrelevantPropertiesTab);
            app.DynamicsGraphPanel.Tooltip = {'The red line shows the minimal acceptable value of the Customer-Relevant Property. '};
            app.DynamicsGraphPanel.Title = 'Dynamics';
            app.DynamicsGraph = uiaxes(app.DynamicsGraphPanel);
            xlabel(app.DynamicsGraph, 'X')
            ylabel(app.DynamicsGraph, 'Y')
            zlabel(app.DynamicsGraph, 'Z')
            app.DynamicsGraph.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ErgonomicsGraphPanel = uipanel(app.CustomerrelevantPropertiesTab);
            app.ErgonomicsGraphPanel.Tooltip = {'The red line shows the minimal acceptable value of the Customer-Relevant Property. '};
            app.ErgonomicsGraphPanel.Title = 'Ergonomics';
            app.ErgonomicsGraph = uiaxes(app.ErgonomicsGraphPanel);
            xlabel(app.ErgonomicsGraph, 'X')
            ylabel(app.ErgonomicsGraph, 'Y')
            zlabel(app.ErgonomicsGraph, 'Z')
            app.ErgonomicsGraph.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.SecurityGraphPanel = uipanel(app.CustomerrelevantPropertiesTab);
            app.SecurityGraphPanel.Tooltip = {'The red line shows the minimal acceptable value of the Customer-Relevant Property. '};
            app.SecurityGraphPanel.Title = 'Security';
            app.SecurityGraph = uiaxes(app.SecurityGraphPanel);
            xlabel(app.SecurityGraph, 'X')
            ylabel(app.SecurityGraph, 'Y')
            zlabel(app.SecurityGraph, 'Z')
            app.SecurityGraph.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UsabilityGraphPanel = uipanel(app.CustomerrelevantPropertiesTab);
            app.UsabilityGraphPanel.Tooltip = {'The red line shows the minimal acceptable value of the Customer-Relevant Property. '};
            app.UsabilityGraphPanel.Title = 'Usability';
            app.UsabilityGraph = uiaxes(app.UsabilityGraphPanel);
            xlabel(app.UsabilityGraph, 'X')
            ylabel(app.UsabilityGraph, 'Y')
            zlabel(app.UsabilityGraph, 'Z')
            app.UsabilityGraph.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.DrivingStyleGraphPanel = uipanel(app.CustomerrelevantPropertiesTab);
            app.DrivingStyleGraphPanel.Tooltip = {'The red line shows the minimal acceptable value of the Customer-Relevant Property. '};
            app.DrivingStyleGraphPanel.Title = 'Driving Style';
            app.DrivingStyleGraph = uiaxes(app.DrivingStyleGraphPanel);
            xlabel(app.DrivingStyleGraph, 'X')
            ylabel(app.DrivingStyleGraph, 'Y')
            zlabel(app.DrivingStyleGraph, 'Z')
            app.DrivingStyleGraph.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.LuxuryGraphPanel = uipanel(app.CustomerrelevantPropertiesTab);
            app.LuxuryGraphPanel.Tooltip = {'The red line shows the minimal acceptable value of the Customer-Relevant Property. '};
            app.LuxuryGraphPanel.Title = 'Luxury';
            app.LuxuryGraph = uiaxes(app.LuxuryGraphPanel);
            xlabel(app.LuxuryGraph, 'X')
            ylabel(app.LuxuryGraph, 'Y')
            zlabel(app.LuxuryGraph, 'Z')
            app.LuxuryGraph.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.VisualizationOptionsTab = uitab(app.TabGroup);
            app.VisualizationOptionsTab.Title = 'Visualization Options';
            app.HVACPanel = uipanel(app.VisualizationOptionsTab);
            app.HVACPanel.Title = 'HVAC';
            app.HVACPanel.Visible = 'off';
            app.ScenarioLabel = uilabel(app.HVACPanel);
            app.ScenarioLabel.Text = 'Scenario';
            app.input_weather_scenario = uidropdown(app.HVACPanel);
            app.input_weather_scenario.Items = {'Munich - Winter', 'Munich - Spring/Fall', 'Munich - Summer'};
            app.input_weather_scenario.Value = 'Munich - Summer';
            app.AmbienttemperatureCLabel = uilabel(app.HVACPanel);
            app.AmbienttemperatureCLabel.Text = 'Ambient temperature [°C]';
            app.input_ambient_temp = uispinner(app.HVACPanel);
            app.input_ambient_temp.Step = 2;
            app.input_ambient_temp.Limits = [-7 30];
            app.input_ambient_temp.Value = 25;
            app.MassflowratekgminLabel = uilabel(app.HVACPanel);
            app.MassflowratekgminLabel.Text = 'Mass flow rate [kg/min]';
            app.input_massflow_rate = uispinner(app.HVACPanel);
            app.input_massflow_rate.Step = 0.1;
            app.input_massflow_rate.Limits = [3 7];
            app.input_massflow_rate.ValueDisplayFormat = '%.1f';
            app.input_massflow_rate.Value = 5;
            app.HVACcalculationLabel = uilabel(app.HVACPanel);
            app.HVACcalculationLabel.Text = 'HVAC calculation';
            app.HVAC_calc = uicheckbox(app.HVACPanel);
            app.RecirculationpercentageLabel = uilabel(app.HVACPanel);
            app.RecirculationpercentageLabel.Text = 'Recirculation percentage [%]';
            app.input_percentage_circair = uispinner(app.HVACPanel);
            app.input_percentage_circair.Step = 5;
            app.input_percentage_circair.Limits = [0 100];
            app.SettemperatureDropDownLabel = uilabel(app.HVACPanel);
            app.SettemperatureDropDownLabel.Text = 'Set temperature';
            app.input_temperature_set = uidropdown(app.HVACPanel);
            app.input_temperature_set.Items = {'22', '23', '24'};
            app.input_temperature_set.Value = '22';
            app.OptimizationParametersPanel = uipanel(app.VisualizationOptionsTab);
            app.OptimizationParametersPanel.Title = 'Parameters needed for Optimization (Extra Window in TP App)';
            app.OptimizationParametersPanel.Visible = 'off';
            app.sing_veh = uicheckbox(app.OptimizationParametersPanel);
            app.sing_veh.Text = 'Single vehicle';
            app.sing_veh.Value = true;
            app.PopulationSizeLabel = uilabel(app.OptimizationParametersPanel);
            app.PopulationSizeLabel.Text = 'Pop. size';
            app.input_pop_size = uispinner(app.OptimizationParametersPanel);
            app.input_pop_size.Step = 25;
            app.input_pop_size.Limits = [30 10000];
            app.input_pop_size.Value = 300;
            app.MaxgenerationLabel = uilabel(app.OptimizationParametersPanel);
            app.MaxgenerationLabel.Text = 'Max. generation';
            app.input_max_gen = uispinner(app.OptimizationParametersPanel);
            app.input_max_gen.Step = 5;
            app.input_max_gen.Limits = [10 1000];
            app.input_max_gen.Value = 30;
            app.input_parallel_comp = uicheckbox(app.OptimizationParametersPanel);
            app.input_parallel_comp.Text = 'Parallel computing';
            app.TimeframeDropDownLabel = uilabel(app.OptimizationParametersPanel);
            app.TimeframeDropDownLabel.Text = 'Timeframe';
            app.input_timeframe = uidropdown(app.OptimizationParametersPanel);
            app.input_timeframe.Items = {'2020', '2025', '2030'};
            app.input_timeframe.Value = '2020';
            app.Goal1Label = uilabel(app.OptimizationParametersPanel);
            app.Goal1Label.Text = ' Goal 1';
            app.input_goal_1 = uidropdown(app.OptimizationParametersPanel);
            app.input_goal_1.Items = {'Min. Costs', 'Min. Battery Capacity', 'Min. Consumption', 'Min. Weight'};
            app.input_goal_1.Value = 'Min. Battery Capacity';
            app.Goal2Label = uilabel(app.OptimizationParametersPanel);
            app.Goal2Label.Text = 'Goal 2';
            app.input_goal_2 = uidropdown(app.OptimizationParametersPanel);
            app.input_goal_2.Items = {'Min. Costs', 'Min. Battery Capacity', 'Min. Consumption', 'Min. Weight'};
            app.input_goal_2.Value = 'Min. Costs';
            app.input_scale_axis = uicheckbox(app.OptimizationParametersPanel);
            app.input_scale_axis.Text = 'Scale axis';
            app.DrivingcycleLabel = uilabel(app.OptimizationParametersPanel);
            app.DrivingcycleLabel.Text = 'Driving cycle';
            app.input_driving_cycle = uidropdown(app.OptimizationParametersPanel);
            app.input_driving_cycle.Items = {'WLTP', 'FTP72', 'FTP75', 'US_Highway', 'NEFZ', 'EUROPE_ARTEMIS_URBAN'};
            app.input_driving_cycle.Value = 'WLTP';
            app.input_H30Opt = uicheckbox(app.OptimizationParametersPanel);
            app.input_H30Opt.Text = 'H30 Optimization';
            app.VisualizationOptionsPanel = uipanel(app.VisualizationOptionsTab);
            app.VisualizationOptionsPanel.Title = 'Visualization Options';
            app.plot_battery = uicheckbox(app.VisualizationOptionsPanel);
            app.plot_battery.Text = 'Plot battery';
            app.plot_manikin = uicheckbox(app.VisualizationOptionsPanel);
            app.plot_manikin.Text = 'Plot manikins';
            app.plot_boundary = uicheckbox(app.VisualizationOptionsPanel);
            app.plot_boundary.Text = 'Plot boundaries';
            app.input_showwarning = uicheckbox(app.VisualizationOptionsPanel);
            app.input_showwarning.Text = 'Show warnings';
            app.plot_wheel_env = uicheckbox(app.VisualizationOptionsPanel);
            app.plot_wheel_env.Text = 'Plot wheel envelope';
            % Show the figure after all components are created
            app.MainScreenDP.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DesignParameters(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainScreenDP)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainScreenDP)
        end
    end
end