classdef Start < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainScreenStart                matlab.ui.Figure
        HomeUserCenteredDevelopmentofAutonomousVehiclesLabel  matlab.ui.control.Label
        MainMenuPanel                  matlab.ui.container.Panel
        AppSelectionButtonGroup        matlab.ui.container.ButtonGroup
        App11UserboundMobilityNeedsButton  matlab.ui.control.ToggleButton
        App12VehicleProvisionButton    matlab.ui.control.ToggleButton
        App2CustomerrelevantPropertiesButton  matlab.ui.control.ToggleButton
        ImprintMATLABRequirementsButton  matlab.ui.control.ToggleButton
        App3TechnicalPropertiesButton  matlab.ui.control.ToggleButton
        App4DesignParametersButton     matlab.ui.control.ToggleButton
        App5VehicleConceptButton       matlab.ui.control.ToggleButton
        ProcessOverviewButton          matlab.ui.control.ToggleButton
        TUMlogo                        matlab.ui.control.Image
        ProcessOverviewPanel           matlab.ui.container.Panel
        Image0                         matlab.ui.control.Image
        App11Button                    matlab.ui.control.Button
        App12Button                    matlab.ui.control.Button
        App2Button                     matlab.ui.control.Button
        App3Button                     matlab.ui.control.Button
        App4Button                     matlab.ui.control.Button
        App5Button                     matlab.ui.control.Button
        ImprintandMATLABRequirementsPanel  matlab.ui.container.Panel
        ImprintPanel                   matlab.ui.container.Panel
        TextArea                       matlab.ui.control.TextArea
        MATLABRequirementsPanel        matlab.ui.container.Panel
        TextArea_6                     matlab.ui.control.TextArea
        PublicationsPanel              matlab.ui.container.Panel
        TextArea_7                     matlab.ui.control.TextArea
        Step11UserBoundMobilityNeedsPanel  matlab.ui.container.Panel
        Image1_1                       matlab.ui.control.Image
        RunAppUserboundMobilityNeedsButton  matlab.ui.control.Button
        DescriptionPanel               matlab.ui.container.Panel
        TextArea_3                     matlab.ui.control.TextArea
        Step12VehicleBoundMobilityProvisionPanel  matlab.ui.container.Panel
        DescriptionPanel_2             matlab.ui.container.Panel
        TextArea_4                     matlab.ui.control.TextArea
        Image1_2                       matlab.ui.control.Image
        RunAppVehicleProvisionButton   matlab.ui.control.Button
        RunwithScenarioCheckBox        matlab.ui.control.CheckBox
        Tree                           matlab.ui.container.Tree
        FamilyNode                     matlab.ui.container.TreeNode
        Family50UF                     matlab.ui.container.TreeNode
        Family60UF                     matlab.ui.container.TreeNode
        Family70UF                     matlab.ui.container.TreeNode
        Family80UF                     matlab.ui.container.TreeNode
        CommuterNode                   matlab.ui.container.TreeNode
        Commuter50UF                   matlab.ui.container.TreeNode
        Commuter60UF                   matlab.ui.container.TreeNode
        Commuter70UF                   matlab.ui.container.TreeNode
        Commuter80UF                   matlab.ui.container.TreeNode
        DailyGrindNode                 matlab.ui.container.TreeNode
        DailyGrind50UF                 matlab.ui.container.TreeNode
        DailyGrind60UF                 matlab.ui.container.TreeNode
        DailyGrind70UF                 matlab.ui.container.TreeNode
        DailyGrind80UF                 matlab.ui.container.TreeNode
        Step2CustomerRelevantPropertiesPanel  matlab.ui.container.Panel
        Image2                         matlab.ui.control.Image
        DescriptionPanel_3             matlab.ui.container.Panel
        TextArea_5                     matlab.ui.control.TextArea
        RunAppCustomerrelevantPropertiesButton  matlab.ui.control.Button
        Step3TechnicalPropertiesPanel  matlab.ui.container.Panel
        Image3                         matlab.ui.control.Image
        DescriptionPanel_4             matlab.ui.container.Panel
        TextArea_8                     matlab.ui.control.TextArea
        RunAppTechnicalPropertiesButton  matlab.ui.control.Button
        Step4DesignParameterPanel      matlab.ui.container.Panel
        Image4                         matlab.ui.control.Image
        DescriptionPanel_5             matlab.ui.container.Panel
        TextArea_9                     matlab.ui.control.TextArea
        RunAppDesignParametersBUtton   matlab.ui.control.Button
        Step5VehicleConceptsPanel      matlab.ui.container.Panel
        Image5                         matlab.ui.control.Image
        DescriptionPanel_6             matlab.ui.container.Panel
        TextArea_10                    matlab.ui.control.TextArea
        RunAppVehicleConceptsButton    matlab.ui.control.Button
    end

     %% General Information:
    
    % Designed by Ferdinand Schockenhoff, David Fischer (FTM, Techical University of Munich)
    %---------------------------------------
    % Created on July 2021
    % Last update: 2022-04-26
    %---------------------------------------
    % Version: Matlab2020b
    %---------------------------------------
    % Description: Launcher of the Apps "User-Needs", "Vehicle Provision",
    % "Customer-Relevant Properties"
    %---------------------------------------
    % Input: -
    %---------------------------------------
    % Output: -
    
    
    % Create private variables
    properties (Access = private)
        oldpath = addpath('99_General_Elements\') % Needed for first build up of app (TUM image)
        oldpath2 = addpath('00_StartApp\') % add needed paths
    end
    
    methods (Access = private)
        
        %__________________________________________________________________
        %Getting the data for the selected scenario VP
        function [user_matrix,user_Names,user_wish,derivative_suggestion,derivative_suggestion_extend,...
                user_fulfillment,user2derivative,user2derivative_extend] = VPDataFunction(app)
        
        %First check for the header nodes
        if app.Tree.SelectedNodes == app.FamilyNode
            load('02_Simulation_UN2VP\03_SimulationDataResults\VP Family 50UF','user_matrix','user_Names','user_wish','derivative_suggestion','derivative_suggestion_extend',...
                'user_fulfillment','user2derivative','user2derivative_extend')
        elseif app.Tree.SelectedNodes == app.CommuterNode
            load('02_Simulation_UN2VP\03_SimulationDataResults\VP Commuter 50UF','user_matrix','user_Names','user_wish','derivative_suggestion','derivative_suggestion_extend',...
                'user_fulfillment','user2derivative','user2derivative_extend')
        elseif app.Tree.SelectedNodes == app.DailyGrindNode
            load('02_Simulation_UN2VP\03_SimulationDataResults\VP DailyGrind 50UF','user_matrix','user_Names','user_wish','derivative_suggestion','derivative_suggestion_extend',...
                'user_fulfillment','user2derivative','user2derivative_extend')
        
        %Check for the child nodes
        else
            %Check the Family Nodes
            for i=50:10:80
                nodeName = append('Family',num2str(i),'UF');
                if app.Tree.SelectedNodes == app.(nodeName)
                    pathname = append('02_Simulation_UN2VP\03_SimulationDataResults\VP Family ',num2str(i),'UF');
                    load(pathname,'user_matrix','user_Names','user_wish','derivative_suggestion','derivative_suggestion_extend',...
                        'user_fulfillment','user2derivative','user2derivative_extend');
                end
            end
            %Check the Commuter Nodes
            for i=50:10:80
                nodeName = append('Commuter',num2str(i),'UF');
                if app.Tree.SelectedNodes == app.(nodeName)
                    pathname = append('02_Simulation_UN2VP\03_SimulationDataResults\VP Commuter ',num2str(i),'UF');
                    load(pathname,'user_matrix','user_Names','user_wish','derivative_suggestion','derivative_suggestion_extend',...
                        'user_fulfillment','user2derivative','user2derivative_extend');
                end
            end
            %Check the DailyGrind Nodes
            for i=50:10:80
                nodeName = append('DailyGrind',num2str(i),'UF');
                if app.Tree.SelectedNodes == app.(nodeName)
                    pathname = append('02_Simulation_UN2VP\03_SimulationDataResults\VP DailyGrind ',num2str(i),'UF');
                    load(pathname,'user_matrix','user_Names','user_wish','derivative_suggestion','derivative_suggestion_extend',...
                        'user_fulfillment','user2derivative','user2derivative_extend');
                end
            end
        end
        
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Getting the data for the selected scenario CRP
        function panelVisibilitySetter(app,numberOfPanel)
            %define Helpvector
            helpVector = [false, false, false, false, false, false, false, false];
            helpVector(numberOfPanel) = true;
            
            %Set visibility states
            app.ProcessOverviewPanel.Visible = helpVector(1);
            app.Step11UserBoundMobilityNeedsPanel.Visible = helpVector(2);
            app.Step12VehicleBoundMobilityProvisionPanel.Visible = helpVector(3);
            app.Step2CustomerRelevantPropertiesPanel.Visible = helpVector(4);
            app.Step3TechnicalPropertiesPanel.Visible = helpVector(5);
            app.Step4DesignParameterPanel.Visible = helpVector(6);
            app.Step5VehicleConceptsPanel.Visible = helpVector(7);            
            app.ImprintandMATLABRequirementsPanel.Visible = helpVector(8);
        end
        %__________________________________________________________________
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Adding necessary folders to path
            addpath('99_General_Elements\')
            addpath('00_StartApp\')
            
            %Setting up the first view
            panelVisibilitySetter(app,1)
            
            %Opening the window maximized
            drawnow;
            app.MainScreenStart.WindowState = 'maximized';
        end

        % Selection changed function: AppSelectionButtonGroup
        function AppSelectionButtonGroupSelectionChanged(app, event)
            % show the panel of the selected Button (for the wanted Step)
            switch app.AppSelectionButtonGroup.SelectedObject
                case app.ProcessOverviewButton
                    panelVisibilitySetter(app,1)
                case app.App11UserboundMobilityNeedsButton
                    panelVisibilitySetter(app,2)
                case app.App12VehicleProvisionButton
                    panelVisibilitySetter(app,3)
                case app.App2CustomerrelevantPropertiesButton
                    panelVisibilitySetter(app,4)
                case app.App3TechnicalPropertiesButton
                    panelVisibilitySetter(app,5)
                case app.App4DesignParametersButton
                    panelVisibilitySetter(app,6)
                case app.App5VehicleConceptButton
                    panelVisibilitySetter(app,7)                    
                case app.ImprintMATLABRequirementsButton
                    panelVisibilitySetter(app,8)
            end
        end

        % Value changed function: RunwithScenarioCheckBox
        function RunwithScenarioCheckBoxValueChanged(app, event)
            % Check Box for the selection of a scenario of the Step Vehicle-Bound Mobility Provision 
            % Enables the possibility to choose a scenario
            value = app.RunwithScenarioCheckBox.Value;
            if value == true
                app.Tree.Enable = true;
                app.Tree.SelectedNodes = app.Family50UF;
            else
                app.Tree.Enable = false;
            end
        end

        % Button pushed function: RunAppUserboundMobilityNeedsButton
        function RunAppUserboundMobilityNeedsButtonPushed(app, event)
            % Start the App for the step User Needs
            %Check if CRP window already exists
            allfigs = findall(0,'Type', 'figure');
            app2Handle = findall(allfigs, 'Name', 'User Needs');
            if isempty(app2Handle) == false
                close(app2Handle) 
            end
            
            UserNeeds;
            delete(app);
        end

        % Button pushed function: RunAppVehicleProvisionButton
        function RunAppVehicleProvisionButtonPushed(app, event)
            % Start the App for the step Vehicle-Bound Mobility Provision 
            %Check if VP window already exists
            allfigs = findall(0,'Type', 'figure');
            app2Handle = findall(allfigs, 'Name', 'Vehicle Provision');
            if isempty(app2Handle) == false
                close(app2Handle) 
            end
            
            if app.RunwithScenarioCheckBox.Value == true
                %load the data
                [user_matrix,user_Names,user_wish,derivative_suggestion,derivative_suggestion_extend,...
                user_fulfillment,user2derivative,user2derivative_extend] = VPDataFunction(app);
                
                %run the app with the data
                VehicleProvision(user_matrix,user_Names,user_wish,derivative_suggestion,derivative_suggestion_extend,...
                user_fulfillment,user2derivative,user2derivative_extend)
            else
                VehicleProvision;
            end
            delete(app);
        end

        % Button pushed function: 
        % RunAppCustomerrelevantPropertiesButton
        function RunAppCustomerrelevantPropertiesButtonPushed(app, event)
            % Start the App for the step Customer-relevant Properties
            %Check if CRP window already exists
            allfigs = findall(0,'Type', 'figure');
            app2Handle = findall(allfigs, 'Name', 'Customer-Relevant Properties');
            if isempty(app2Handle) == false
                close(app2Handle) 
            end            
            CustomerRelevantProperties;
            delete(app);
        end

        % Button pushed function: RunAppTechnicalPropertiesButton
        function RunAppTechnicalPropertiesButtonPushed(app, event)
            % Start the App for the step Technical Properties
            %Check if TP window already exists
            allfigs = findall(0,'Type', 'figure');
            app2Handle = findall(allfigs, 'Name', 'Technical Properties');
            if isempty(app2Handle) == false
                close(app2Handle) 
            end            
            TechnicalProperties;
            delete(app);
        end

        % Button pushed function: App11Button
        function App11ButtonPushed(app, event)
            % Show App 1.1
            app.ProcessOverviewButton.Value = false;
            app.App11UserboundMobilityNeedsButton.Value = true;
            panelVisibilitySetter(app,2);
        end

        % Button pushed function: App12Button
        function App12ButtonPushed(app, event)
            % Show App 1.2
            app.ProcessOverviewButton.Value = false;
            app.App12VehicleProvisionButton.Value = true;
            panelVisibilitySetter(app,3);
        end

        % Button pushed function: App2Button
        function App2ButtonPushed(app, event)
            % Show App 2
            app.ProcessOverviewButton.Value = false;
            app.App2CustomerrelevantPropertiesButton.Value = true;
            panelVisibilitySetter(app,4);
        end

        % Button pushed function: App3Button
        function App3ButtonPushed(app, event)
            % Show App 3
            app.ProcessOverviewButton.Value = false;
            app.App3TechnicalPropertiesButton.Value = true;
            panelVisibilitySetter(app,5)
        end

        % Button pushed function: App4Button
        function App4ButtonPushed(app, event)
            % Show App 4
            app.ProcessOverviewButton.Value = false;
            app.App4DesignParametersButton.Value = true;
            panelVisibilitySetter(app,6)
        end

        % Button pushed function: App5Button
        function App5ButtonPushed(app, event)
            % Show App 5
            app.ProcessOverviewButton.Value = false;
            app.App5VehicleConceptButton.Value = true;
            panelVisibilitySetter(app,7);
        end

        % Button pushed function: RunAppDesignParametersBUtton
        function RunAppDesignParametersBUttonButtonPushed(app, event)
            % Start the App for the step Technical Properties
            %Check if DP window already exists
            allfigs = findall(0,'Type', 'figure');
            app2Handle = findall(allfigs, 'Name', 'Design Parameters');
            if isempty(app2Handle) == false
                close(app2Handle) 
            end            
            DesignParameters;
            delete(app);
        end

        % Button pushed function: RunAppVehicleConceptsButton
        function RunAppVehicleConceptsButtonPushed(app, event)
            % Start the App for the step Technical Properties
            %Check if VC window already exists
            allfigs = findall(0,'Type', 'figure');
            app2Handle = findall(allfigs, 'Name', 'Vehicle Concepts');
            if isempty(app2Handle) == false
                close(app2Handle) 
            end            
            VehicleConcepts;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainScreenStart = uifigure('Visible', 'off');
            app.MainScreenStart.Name = 'Start';
            app.MainScreenStart.Icon = 'FTMLogo.PNG';
            app.HomeUserCenteredDevelopmentofAutonomousVehiclesLabel = uilabel(app.MainScreenStart);
            app.HomeUserCenteredDevelopmentofAutonomousVehiclesLabel.Text = '       Vehicle concept development for Autonomous, Shared and Electric Mobility (VASEM)';
            app.MainMenuPanel = uipanel(app.MainScreenStart);
            app.MainMenuPanel.Title = 'Main Menu';
            app.AppSelectionButtonGroup = uibuttongroup(app.MainMenuPanel);
            app.AppSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AppSelectionButtonGroupSelectionChanged, true);
            app.AppSelectionButtonGroup.Title = 'App Selection';
            app.App11UserboundMobilityNeedsButton = uitogglebutton(app.AppSelectionButtonGroup);
            app.App11UserboundMobilityNeedsButton.Tooltip = {''};
            app.App11UserboundMobilityNeedsButton.Text = 'App1.1: User-bound Mobility Needs';
            app.App12VehicleProvisionButton = uitogglebutton(app.AppSelectionButtonGroup);
            app.App12VehicleProvisionButton.Text = 'App1.2: Vehicle Provision';
            app.App2CustomerrelevantPropertiesButton = uitogglebutton(app.AppSelectionButtonGroup);
            app.App2CustomerrelevantPropertiesButton.Tooltip = {'CRProperties = Customer-Relevant Properties'};
            app.App2CustomerrelevantPropertiesButton.Text = 'App 2: Customer-relevant Properties';
            app.ImprintMATLABRequirementsButton = uitogglebutton(app.AppSelectionButtonGroup);
            app.ImprintMATLABRequirementsButton.Tooltip = {''};
            app.ImprintMATLABRequirementsButton.Text = 'Imprint / MATLAB Requirements';
            app.App3TechnicalPropertiesButton = uitogglebutton(app.AppSelectionButtonGroup);
            app.App3TechnicalPropertiesButton.Tooltip = {'CRProperties = Customer-Relevant Properties'};
            app.App3TechnicalPropertiesButton.Text = 'App 3: Technical Properties';
            app.App4DesignParametersButton = uitogglebutton(app.AppSelectionButtonGroup);
            app.App4DesignParametersButton.Tooltip = {'CRProperties = Customer-Relevant Properties'};
            app.App4DesignParametersButton.Text = 'App 4: Design Parameters';
            app.App5VehicleConceptButton = uitogglebutton(app.AppSelectionButtonGroup);
            app.App5VehicleConceptButton.Tooltip = {'CRProperties = Customer-Relevant Properties'};
            app.App5VehicleConceptButton.Text = 'App 5: Vehicle Concept';
            app.ProcessOverviewButton = uitogglebutton(app.AppSelectionButtonGroup);
            app.ProcessOverviewButton.Text = 'Process Overview';
            app.ProcessOverviewButton.Value = true;
            app.TUMlogo = uiimage(app.MainScreenStart);
            app.TUMlogo.ImageSource = '2015_Logo_TUM_neg_RGB.svg';
            app.ProcessOverviewPanel = uipanel(app.MainScreenStart);
            app.ProcessOverviewPanel.Title = 'Process Overview';
            app.Image0 = uiimage(app.ProcessOverviewPanel);
            app.Image0.ImageSource = 'Overview.svg';
            app.App11Button = uibutton(app.ProcessOverviewPanel, 'push');
            app.App11Button.ButtonPushedFcn = createCallbackFcn(app, @App11ButtonPushed, true);
            app.App11Button.Text = 'App 1.1';
            app.App12Button = uibutton(app.ProcessOverviewPanel, 'push');
            app.App12Button.ButtonPushedFcn = createCallbackFcn(app, @App12ButtonPushed, true);
            app.App12Button.Text = 'App 1.2';
            app.App2Button = uibutton(app.ProcessOverviewPanel, 'push');
            app.App2Button.ButtonPushedFcn = createCallbackFcn(app, @App2ButtonPushed, true);
            app.App2Button.Text = 'App 2';
            app.App3Button = uibutton(app.ProcessOverviewPanel, 'push');
            app.App3Button.ButtonPushedFcn = createCallbackFcn(app, @App3ButtonPushed, true);
            app.App3Button.Text = 'App 3';
            app.App4Button = uibutton(app.ProcessOverviewPanel, 'push');
            app.App4Button.ButtonPushedFcn = createCallbackFcn(app, @App4ButtonPushed, true);
            app.App4Button.Text = 'App 4';
            app.App5Button = uibutton(app.ProcessOverviewPanel, 'push');
            app.App5Button.ButtonPushedFcn = createCallbackFcn(app, @App5ButtonPushed, true);
            app.App5Button.Text = 'App 5';
            app.ImprintandMATLABRequirementsPanel = uipanel(app.MainScreenStart);
            app.ImprintandMATLABRequirementsPanel.Title = 'Imprint and MATLAB Requirements';
            app.ImprintPanel = uipanel(app.ImprintandMATLABRequirementsPanel);
            app.ImprintPanel.Title = 'Imprint';
            app.TextArea = uitextarea(app.ImprintPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Lehrstuhl für Fahrzeugtechnik (FTM)'; 'Technische Universität München'; 'Boltzmannstr. 15'; '85748 Garching b. München'; 'Germany'; 'ftm@ftm.mw.tum.de'; ''; 'Contact: schockenhoff@ftm.mw.tum.de'; ''; 'Concept: Ferdinand Schockenhoff'; 'Scientific Elaboration: Ferdinand Schockenhoff'; 'Apps: Ferdinand Schockenhoff & David Fischer (initial concept App 1.1, App1.2 & App 2) & Marc Raudszus (initial concept App 4 & App 5)'; 'Backend step 1 “User Needs” to step 2.1 “vehicle provision”: Maximilian Zähringer, supervised by Ferdinand Schockenhoff'; 'Backend step 2.1 “vehicle provision” to step 2.2 “customer-relevant properties”: Maximilian Zähringer, supervised by Ferdinand Schockenhoff'; 'Backend step 2.2 “customer-relevant properties” to step 3 “technical properties”: Ferdinand Schockenhoff'; 'Backend step 3 “technical properties” to step 4 “design parameter”: original tool by Adrian König and Lorenzo Nicoletti, adapted by Ferdinand Schockenhoff & Marc Raudszus (supervised by Ferdinand Schockenhoff)'; 'Backend step 4 “design parameter” to step 5 “vehicle concept”: original tool by Adrian König and Lorenzo Nicoletti, adapted by Ferdinand Schockenhoff & Marc Raudszus (supervised by Ferdinand Schockenhoff)'; ''; 'Support during the development of the tool:'; 'Xucheng Duan'; 'Max Michel'; 'Leonhard Langer'; 'Roland Tutzauer'; 'Ana Clara Serra do Nascimento'; 'Fengtao Zeng'; 'Lukas Pfeiffer'; 'Maximilian Ernster'; 'Miguel Lerma Garro'; 'Jana Weber'; 'Hannes Nehse'; 'Carl-Philipp Grunenwald'; 'Vivien Henke'; 'Michael Ponnath'; 'Mario Kuppel'; 'Franz Müller'};
            app.MATLABRequirementsPanel = uipanel(app.ImprintandMATLABRequirementsPanel);
            app.MATLABRequirementsPanel.Title = 'MATLAB Requirements';
            app.TextArea_6 = uitextarea(app.MATLABRequirementsPanel);
            app.TextArea_6.Editable = 'off';
            app.TextArea_6.Value = {'Apps and Backend created with MATLAB R2020b'; ''; 'Required Toolboxes:'; '- Global Optimization Toolbox'; '- Statistic and Machine Learning Toolbox'; '- Fuzzy Logic Toolbox'};
            app.PublicationsPanel = uipanel(app.ImprintandMATLABRequirementsPanel);
            app.PublicationsPanel.Title = 'Publications';
            app.TextArea_7 = uitextarea(app.PublicationsPanel);
            app.TextArea_7.Editable = 'off';
            app.TextArea_7.Value = {'Dissertation:'; 'F. Schockenhoff, „Fahrzeugkonzeptentwicklung für autonome, geteilte und elektrische Mobilität“. Dissertation, Institute of Automotive Technology, Technical University Munich (TUM), Munich, 2022. (in progress)'; ''; 'Paper:'; 'F. Schockenhoff, A. König, M. Zähringer und M. Lienkamp, „USER NEED-ORIENTED CONCEPT DEVELOPMENT OF AUTONOMOUS VEHICLES“, Proc. Des. Soc., Jg. 1, S. 3349–3358, 2021, doi: 10.1017/pds.2021.596.'; ''; 'F. Schockenhoff, M. Zähringer, M. Brönner, and M. Lienkamp, “Combining a Genetic Algorithm and a Fuzzy System to Optimize User Centricity in Autonomous Vehicle Concept Development,” Systems, vol. 9, no. 2, p. 25, 2021, doi: 10.3390/systems9020025.'; ''; 'F. Schockenhoff, A. König, A. Koch, and M. Lienkamp, “Customer-Relevant Properties of Autonomous Vehicle Concepts,” Procedia CIRP, vol. 91, pp. 55–60, 2020, doi: 10.1016/j.procir.2020.02.150.'; ''; 'F. Schockenhoff, H. Nehse, and M. Lienkamp, “Maneuver-Based Objectification of User Comfort Affecting Aspects of Driving Style of Autonomous Vehicle Concepts,” Applied Sciences, vol. 10, no. 11, p. 3946, 2020, doi: 10.3390/app10113946.'; ''; 'A. Koenig, F. Schockenhoff, A. Koch, and M. Lienkamp, “Concept Design Optimization of Autonomous and Electric Vehicles,” in 2019 8th International Conference on Power Science and Engineering (ICPSE), Dublin, Ireland, 2019, pp. 44–49.'; ''; 'L. Nicoletti, M. Brönner, B. Danquah, A. Koch, A. König, S. Krapf, A. Pathak, F. Schockenhoff, G. Sethuraman, S. Wolff; and M. Lienkamp, “Review of Trends and Potentials in the Vehicle Concept Development Process,” 2020 Fifteenth International Conference on Ecological Vehicles and Renewable Energies (EVER), 2020.'; ''; 'L. Nicoletti, S. Mayer, M. Brönner, F. Schockenhoff, and M. Lienkamp, “Design Parameters for the Early Development Phase of Battery Electric Vehicles,” WEVJ, vol. 11, no. 3, p. 47, 2020, doi: 10.3390/wevj11030047.'; ''; 'L. Nicoletti, S. Mirti, F. Schockenhoff, A. König, and M. Lienkamp, “Derivation of Geometrical Interdependencies between the Passenger Compartment and the Traction Battery Using Dimensional Chains,” WEVJ, vol. 11, no. 2, p. 39, 2020, doi: 10.3390/wevj11020039.'; ''; 'L. Nicoletti, A. Romano, A. König, F. Schockenhoff, and M. Lienkamp, “Parametric Modeling of Mass and Volume Effects for Battery Electric Vehicles, with Focus on the Wheel Components,” WEVJ, vol. 11, no. 4, p. 63, 2020, doi: 10.3390/wevj11040063.'; ''; 'M. Kipp, I. Bubb, J. Schwiebacher, F. Schockenhoff, A. Koenig, and K. Bengler, “Requirements for an Autonomous Taxi and a Resulting Interior Concept,” in Communications in Computer and Information Science, HCI International 2020 - Posters, C. Stephanidis and M. Antona, Eds., Cham: Springer International Publishing, 2020, pp. 374–381.'; ''; 'S. Wolff, J. Auernhammer, F. Schockenhoff, C. Angerer, and M. Wittmann, “MOBILITY BOX: A DESIGN RESEARCH METHODOLOGY TO EXAMINE PEOPLE''S NEEDS IN RELATION TO AUTONOMOUS VEHICLE DESIGNS AND MOBILITY BUSINESS MODEL,” Proc. Des. Soc.: Des. Conf., vol. 1, pp. 1185–1194, 2020, doi: 10.1017/dsd.2020.285.'};
            app.Step11UserBoundMobilityNeedsPanel = uipanel(app.MainScreenStart);
            app.Step11UserBoundMobilityNeedsPanel.Title = 'Step 1.1: User-Centered Mobility Needs';
            app.Image1_1 = uiimage(app.Step11UserBoundMobilityNeedsPanel);
            app.Image1_1.ImageSource = 'PictureUserNeeds.png';
            app.RunAppUserboundMobilityNeedsButton = uibutton(app.Step11UserBoundMobilityNeedsPanel, 'push');
            app.RunAppUserboundMobilityNeedsButton.ButtonPushedFcn = createCallbackFcn(app, @RunAppUserboundMobilityNeedsButtonPushed, true);
            app.RunAppUserboundMobilityNeedsButton.Text = {'Run App User-bound'; 'Mobility Needs'};
            app.DescriptionPanel = uipanel(app.Step11UserBoundMobilityNeedsPanel);
            app.DescriptionPanel.Title = 'Description';
            app.TextArea_3 = uitextarea(app.DescriptionPanel);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'The app "User-Centered Mobility Needs" represents the first step in the User-Centered development process of autonomous vehicles. All user inputs are made here for the following process steps. It is possible to create Users manually by selecting the parameters or choose a predefined scenario. After determining the Users and the degree of satisfaction of the later calculated Vehicle Provision (User Fulfillment), the first calculation step can be started. Successfully determining a set of Derivatives, will subsequently open the next app.'};
            app.Step12VehicleBoundMobilityProvisionPanel = uipanel(app.MainScreenStart);
            app.Step12VehicleBoundMobilityProvisionPanel.Title = 'Step 1.2: Vehicle-Bound Mobility Provision';
            app.DescriptionPanel_2 = uipanel(app.Step12VehicleBoundMobilityProvisionPanel);
            app.DescriptionPanel_2.Title = 'Description';
            app.TextArea_4 = uitextarea(app.DescriptionPanel_2);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'The app "Vehicle-Bound Mobility Provision" presents the results from the first calculation step, where a set of Derivatives is determined, which fulfill the Users Mobility Needs to the predefined degree of satisfaction. There are several view options to make the results visible. For example the suggested Derivatives for each User can be seen or the Users for each suggested Derivative. It is also possible to select the wanted Derivatives and Users, which shall be shown in one spidergraph. As an extended function, the number of suggested Derivatives can be enhanced to provide different Derivatives for different mobility parameters. After reviewing all Derivatives, the next calculation step can be started to determine the Customer-Relevant Properties, which will result in opening the related app.'};
            app.Image1_2 = uiimage(app.Step12VehicleBoundMobilityProvisionPanel);
            app.Image1_2.ImageSource = 'PictureVehicleProvision.png';
            app.RunAppVehicleProvisionButton = uibutton(app.Step12VehicleBoundMobilityProvisionPanel, 'push');
            app.RunAppVehicleProvisionButton.ButtonPushedFcn = createCallbackFcn(app, @RunAppVehicleProvisionButtonPushed, true);
            app.RunAppVehicleProvisionButton.Text = {'Run App'; 'Vehicle Provision'};
            app.RunwithScenarioCheckBox = uicheckbox(app.Step12VehicleBoundMobilityProvisionPanel);
            app.RunwithScenarioCheckBox.ValueChangedFcn = createCallbackFcn(app, @RunwithScenarioCheckBoxValueChanged, true);
            app.RunwithScenarioCheckBox.Tooltip = {'Enables the choice of a predefined scenario.'};
            app.RunwithScenarioCheckBox.Text = ' Run with Scenario';
            app.Tree = uitree(app.Step12VehicleBoundMobilityProvisionPanel);
            app.Tree.Enable = 'off';
            app.FamilyNode = uitreenode(app.Tree);
            app.FamilyNode.Text = 'Family';
            app.Family50UF = uitreenode(app.FamilyNode);
            app.Family50UF.Text = '50 UF';
            app.Family60UF = uitreenode(app.FamilyNode);
            app.Family60UF.Text = '60 UF';
            app.Family70UF = uitreenode(app.FamilyNode);
            app.Family70UF.Text = '70 UF';
            app.Family80UF = uitreenode(app.FamilyNode);
            app.Family80UF.Text = '80 UF';
            app.CommuterNode = uitreenode(app.Tree);
            app.CommuterNode.Text = 'Commuter';
            app.Commuter50UF = uitreenode(app.CommuterNode);
            app.Commuter50UF.Text = '50 UF';
            app.Commuter60UF = uitreenode(app.CommuterNode);
            app.Commuter60UF.Text = '60 UF';
            app.Commuter70UF = uitreenode(app.CommuterNode);
            app.Commuter70UF.Text = '70 UF';
            app.Commuter80UF = uitreenode(app.CommuterNode);
            app.Commuter80UF.Text = '80 UF';
            app.DailyGrindNode = uitreenode(app.Tree);
            app.DailyGrindNode.Text = 'Daily Grind';
            app.DailyGrind50UF = uitreenode(app.DailyGrindNode);
            app.DailyGrind50UF.Text = '50 UF';
            app.DailyGrind60UF = uitreenode(app.DailyGrindNode);
            app.DailyGrind60UF.Text = '60 UF';
            app.DailyGrind70UF = uitreenode(app.DailyGrindNode);
            app.DailyGrind70UF.Text = '70 UF';
            app.DailyGrind80UF = uitreenode(app.DailyGrindNode);
            app.DailyGrind80UF.Text = '80 UF';
            app.Step2CustomerRelevantPropertiesPanel = uipanel(app.MainScreenStart);
            app.Step2CustomerRelevantPropertiesPanel.Title = 'Step 2: Customer-Relevant Properties of Autonomous Vehicle Concepts';
            app.Image2 = uiimage(app.Step2CustomerRelevantPropertiesPanel);
            app.Image2.ImageSource = 'PictureCustomerRelevantProperties.png';
            app.DescriptionPanel_3 = uipanel(app.Step2CustomerRelevantPropertiesPanel);
            app.DescriptionPanel_3.Title = 'Description';
            app.TextArea_5 = uitextarea(app.DescriptionPanel_3);
            app.TextArea_5.Editable = 'off';
            app.TextArea_5.Value = {'The app "Customer-Relevant Properties of Autonomous, Shared and Electric  Vehicle Concepts" presents the results from the second calculation step, where for each suggested Derivative a Vehicle Concept with Customer-Relevant Properties is determined. These Customer-Relevant Properties are divided into six groups and are each visualized by a spidergraph. The calculated Customer-Relevant Properties can also be changed in order to manipulate the Vehicle Concepts. After editing and reviewing the Customer-Relevant Properties for each Vehicle Concept, the next calculation step can be started and the next App opened.'};
            app.RunAppCustomerrelevantPropertiesButton = uibutton(app.Step2CustomerRelevantPropertiesPanel, 'push');
            app.RunAppCustomerrelevantPropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @RunAppCustomerrelevantPropertiesButtonPushed, true);
            app.RunAppCustomerrelevantPropertiesButton.Text = {'Run App Customer-'; 'relevant Properties'};
            app.Step3TechnicalPropertiesPanel = uipanel(app.MainScreenStart);
            app.Step3TechnicalPropertiesPanel.Title = 'Step 3: Technical Properties of Autonomous Vehicle Concepts';
            app.Image3 = uiimage(app.Step3TechnicalPropertiesPanel);
            app.Image3.ImageSource = 'PictureTechnicalProperties.png';
            app.DescriptionPanel_4 = uipanel(app.Step3TechnicalPropertiesPanel);
            app.DescriptionPanel_4.Title = 'Description';
            app.TextArea_8 = uitextarea(app.DescriptionPanel_4);
            app.TextArea_8.Editable = 'off';
            app.TextArea_8.Value = {'The app “Technical Properties for Autonomous, Shared and Electric Vehicle Concepts” presents the physical values of the properties. They are calculated by correlation functions which can be seen in this app on a specific tab. The main tab presents tables of the Technical Properties and their values. These values can also be changed in order to manipulate the Vehicle Concepts. After editing and reviewing the Technical Properties for each Vehicle Concept, the next calculation step can be started and the next App opened.'};
            app.RunAppTechnicalPropertiesButton = uibutton(app.Step3TechnicalPropertiesPanel, 'push');
            app.RunAppTechnicalPropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @RunAppTechnicalPropertiesButtonPushed, true);
            app.RunAppTechnicalPropertiesButton.Text = {'Run App Technical'; 'Properties'};
            app.Step4DesignParameterPanel = uipanel(app.MainScreenStart);
            app.Step4DesignParameterPanel.Title = 'Step 4: Design Parameter';
            app.Image4 = uiimage(app.Step4DesignParameterPanel);
            app.Image4.ImageSource = 'PictureDesignParameter.png';
            app.DescriptionPanel_5 = uipanel(app.Step4DesignParameterPanel);
            app.DescriptionPanel_5.Title = 'Description';
            app.TextArea_9 = uitextarea(app.DescriptionPanel_5);
            app.TextArea_9.Editable = 'off';
            app.TextArea_9.Value = {'The app “Design Parameters for Autonomous, Shared and Electric Vehicle Concepts” presents about 150 parameters which are needed to simulate the Vehicle Concept. These Design Parameters are topology and technology decisions and detailed parameter selections. Based on the values of the Technical Properties, a preselection is made for the Design Parameters. These values can also be changed in order to manipulate the Vehicle Concepts. Sometimes they have to be changed to reach a feasible Vehicle Concept. After editing and reviewing the Design Parameters for each Vehicle Concept, the final vehicle concept simulation can be started, and the corresponding app opened.'};
            app.RunAppDesignParametersBUtton = uibutton(app.Step4DesignParameterPanel, 'push');
            app.RunAppDesignParametersBUtton.ButtonPushedFcn = createCallbackFcn(app, @RunAppDesignParametersBUttonButtonPushed, true);
            app.RunAppDesignParametersBUtton.Text = {'Run App'; 'Design Parameters'};
            app.Step5VehicleConceptsPanel = uipanel(app.MainScreenStart);
            app.Step5VehicleConceptsPanel.Title = 'Step 5: Vehicle Concepts';
            app.Image5 = uiimage(app.Step5VehicleConceptsPanel);
            app.Image5.ImageSource = 'PictureVehicleConcept.png';
            app.DescriptionPanel_6 = uipanel(app.Step5VehicleConceptsPanel);
            app.DescriptionPanel_6.Title = 'Description';
            app.TextArea_10 = uitextarea(app.DescriptionPanel_6);
            app.TextArea_10.Editable = 'off';
            app.TextArea_10.Value = {'The app “Autonomous, Shared and Electric Vehicle Concepts” is the final result and presents the designed Vehicle Concepts. You can analyze them by their package and parameter list. '};
            app.RunAppVehicleConceptsButton = uibutton(app.Step5VehicleConceptsPanel, 'push');
            app.RunAppVehicleConceptsButton.ButtonPushedFcn = createCallbackFcn(app, @RunAppVehicleConceptsButtonPushed, true);
            app.RunAppVehicleConceptsButton.Text = {'Run App Vehicle'; 'Concepts'};
            % Show the figure after all components are created
            app.MainScreenStart.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Start

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainScreenStart)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainScreenStart)
        end
    end
end