classdef TechnicalProperties < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainScreenTP                    matlab.ui.Figure
        Step3TechnicalPropertiesofAutonomousVehicleConceptsLabel  matlab.ui.control.Label
        TUMlogo                         matlab.ui.control.Image
        MainMenuPanel                   matlab.ui.container.Panel
        DataOptionsPanel                matlab.ui.container.Panel
        LoadButton                      matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        ManipulateButton                matlab.ui.control.StateButton
        ProceedtoDesignParametersButton  matlab.ui.control.Button
        VehicleConceptsSelectionPanel   matlab.ui.container.Panel
        CheckBox1                       matlab.ui.control.CheckBox
        VCLamp1                         matlab.ui.control.Lamp
        VehicleConcept1Label            matlab.ui.control.Label
        CheckBox2                       matlab.ui.control.CheckBox
        VCLamp2                         matlab.ui.control.Lamp
        VehicleConcept2Label            matlab.ui.control.Label
        CheckBox3                       matlab.ui.control.CheckBox
        VCLamp3                         matlab.ui.control.Lamp
        VehicleConcept3Label            matlab.ui.control.Label
        CheckBox4                       matlab.ui.control.CheckBox
        VCLamp4                         matlab.ui.control.Lamp
        VehicleConcept4Label            matlab.ui.control.Label
        CheckBox5                       matlab.ui.control.CheckBox
        VCLamp5                         matlab.ui.control.Lamp
        VehicleConcept5Label            matlab.ui.control.Label
        CheckBox6                       matlab.ui.control.CheckBox
        VCLamp6                         matlab.ui.control.Lamp
        VehicleConcept6Label            matlab.ui.control.Label
        CheckBox7                       matlab.ui.control.CheckBox
        VCLamp7                         matlab.ui.control.Lamp
        VehicleConcept7Label            matlab.ui.control.Label
        CheckBox8                       matlab.ui.control.CheckBox
        VCLamp8                         matlab.ui.control.Lamp
        VehicleConcept8Label            matlab.ui.control.Label
        CheckBox9                       matlab.ui.control.CheckBox
        VCLamp9                         matlab.ui.control.Lamp
        VehicleConcept9Label            matlab.ui.control.Label
        CheckBox10                      matlab.ui.control.CheckBox
        VCLamp10                        matlab.ui.control.Lamp
        VehicleConcept10Label           matlab.ui.control.Label
        CheckBox11                      matlab.ui.control.CheckBox
        VCLamp11                        matlab.ui.control.Lamp
        VehicleConcept11Label           matlab.ui.control.Label
        CheckBox12                      matlab.ui.control.CheckBox
        VCLamp12                        matlab.ui.control.Lamp
        VehicleConcept12Label           matlab.ui.control.Label
        CheckBox13                      matlab.ui.control.CheckBox
        VCLamp13                        matlab.ui.control.Lamp
        VehicleConcept13Label           matlab.ui.control.Label
        CheckBox14                      matlab.ui.control.CheckBox
        VCLamp14                        matlab.ui.control.Lamp
        VehicleConcept14Label           matlab.ui.control.Label
        DisplayOptionsButtonGroup       matlab.ui.container.ButtonGroup
        allTPforoneVCButton             matlab.ui.control.ToggleButton
        CRPforallVCButton               matlab.ui.control.ToggleButton
        TPforallVCButton                matlab.ui.control.ToggleButton
        CorrelationsButton              matlab.ui.control.ToggleButton
        ConfirmManipulationButton       matlab.ui.control.Button
        CorrelationsPanel               matlab.ui.container.Panel
        ButtonGroupVC_3                 matlab.ui.container.ButtonGroup
        DynamicsButton_3                matlab.ui.control.ToggleButton
        ErgonomicsButton_3              matlab.ui.control.ToggleButton
        SecurityButton_3                matlab.ui.control.ToggleButton
        UsabilityButton_3               matlab.ui.control.ToggleButton
        DrivingStyleButton_3            matlab.ui.control.ToggleButton
        LuxuryButton_3                  matlab.ui.control.ToggleButton
        UIAxes_1                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_7                        matlab.ui.control.UIAxes
        CRPforallVC                     matlab.ui.container.Panel
        ButtonGroupVC                   matlab.ui.container.ButtonGroup
        DynamicsButton                  matlab.ui.control.ToggleButton
        ErgonomicsButton                matlab.ui.control.ToggleButton
        SecurityButton                  matlab.ui.control.ToggleButton
        UsabilityButton                 matlab.ui.control.ToggleButton
        DrivingStyleButton              matlab.ui.control.ToggleButton
        LuxuryButton                    matlab.ui.control.ToggleButton
        UIAxesCRPGraph                  matlab.ui.control.UIAxes
        HomeButton                      matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        TPforVCPanel                    matlab.ui.container.Panel
        ButtonGroupVC_2                 matlab.ui.container.ButtonGroup
        DynamicsButton_2                matlab.ui.control.ToggleButton
        ErgonomicsButton_2              matlab.ui.control.ToggleButton
        SecurityButton_2                matlab.ui.control.ToggleButton
        UsabilityButton_2               matlab.ui.control.ToggleButton
        DrivingStyleButton_2            matlab.ui.control.ToggleButton
        LuxuryButton_2                  matlab.ui.control.ToggleButton
        ErgonomicsPanel_2               matlab.ui.container.Panel
        BoardingComfortLabel_2          matlab.ui.control.Label
        BoardingTimeLabel_2             matlab.ui.control.Label
        LegRoomLabel_2                  matlab.ui.control.Label
        ShouldersRoomLabel_2            matlab.ui.control.Label
        HeadRoomLabel_2                 matlab.ui.control.Label
        InteriorAcousticsLabel_2        matlab.ui.control.Label
        mmLabel_12                      matlab.ui.control.Label
        SillHeightSpinner_2Label        matlab.ui.control.Label
        SillHeightSpinner_2             matlab.ui.control.Spinner
        mmLabel_13                      matlab.ui.control.Label
        ClearanceHeightSpinner_2Label   matlab.ui.control.Label
        ClearanceHeightSpinner_2        matlab.ui.control.Spinner
        mmLabel_14                      matlab.ui.control.Label
        DoorOpeningWidthperSeatSpinner_2Label  matlab.ui.control.Label
        DoorOpeningWidthperSeatSpinner_2  matlab.ui.control.Spinner
        mmLabel_15                      matlab.ui.control.Label
        InteriorDimensionL53Spinner_2Label  matlab.ui.control.Label
        InteriorDimensionL53Spinner_2   matlab.ui.control.Spinner
        mmLabel_16                      matlab.ui.control.Label
        InteriorDimensionW3Spinner_2Label  matlab.ui.control.Label
        InteriorDimensionW3Spinner_2    matlab.ui.control.Spinner
        mmLabel_17                      matlab.ui.control.Label
        InteriorDimensionH61Spinner_2Label  matlab.ui.control.Label
        InteriorDimensionH61Spinner_2   matlab.ui.control.Spinner
        dBLabel_2                       matlab.ui.control.Label
        AveragedSoundPressureLevelSpinner_2Label  matlab.ui.control.Label
        AveragedSoundPressureLevelSpinner_2  matlab.ui.control.Spinner
        ErgonomicsPanel_3               matlab.ui.container.Panel
        BoardingComfortLabel_3          matlab.ui.control.Label
        BoardingTimeLabel_3             matlab.ui.control.Label
        LegRoomLabel_3                  matlab.ui.control.Label
        ShouldersRoomLabel_3            matlab.ui.control.Label
        HeadRoomLabel_3                 matlab.ui.control.Label
        InteriorAcousticsLabel_3        matlab.ui.control.Label
        mmLabel_18                      matlab.ui.control.Label
        SillHeightSpinner_3Label        matlab.ui.control.Label
        SillHeightSpinner_3             matlab.ui.control.Spinner
        mmLabel_19                      matlab.ui.control.Label
        ClearanceHeightSpinner_3Label   matlab.ui.control.Label
        ClearanceHeightSpinner_3        matlab.ui.control.Spinner
        mmLabel_20                      matlab.ui.control.Label
        DoorOpeningWidthperSeatSpinner_3Label  matlab.ui.control.Label
        DoorOpeningWidthperSeatSpinner_3  matlab.ui.control.Spinner
        mmLabel_21                      matlab.ui.control.Label
        InteriorDimensionL53Spinner_3Label  matlab.ui.control.Label
        InteriorDimensionL53Spinner_3   matlab.ui.control.Spinner
        mmLabel_22                      matlab.ui.control.Label
        InteriorDimensionW3Spinner_3Label  matlab.ui.control.Label
        InteriorDimensionW3Spinner_3    matlab.ui.control.Spinner
        mmLabel_23                      matlab.ui.control.Label
        InteriorDimensionH61Spinner_3Label  matlab.ui.control.Label
        InteriorDimensionH61Spinner_3   matlab.ui.control.Spinner
        dBLabel_3                       matlab.ui.control.Label
        AveragedSoundPressureLevelSpinner_3Label  matlab.ui.control.Label
        AveragedSoundPressureLevelSpinner_3  matlab.ui.control.Spinner
        ErgonomicsPanel_4               matlab.ui.container.Panel
        BoardingComfortLabel_4          matlab.ui.control.Label
        BoardingTimeLabel_4             matlab.ui.control.Label
        LegRoomLabel_4                  matlab.ui.control.Label
        ShouldersRoomLabel_4            matlab.ui.control.Label
        HeadRoomLabel_4                 matlab.ui.control.Label
        InteriorAcousticsLabel_4        matlab.ui.control.Label
        mmLabel_24                      matlab.ui.control.Label
        SillHeightSpinner_4Label        matlab.ui.control.Label
        SillHeightSpinner_4             matlab.ui.control.Spinner
        mmLabel_25                      matlab.ui.control.Label
        ClearanceHeightSpinner_4Label   matlab.ui.control.Label
        ClearanceHeightSpinner_4        matlab.ui.control.Spinner
        mmLabel_26                      matlab.ui.control.Label
        DoorOpeningWidthperSeatSpinner_4Label  matlab.ui.control.Label
        DoorOpeningWidthperSeatSpinner_4  matlab.ui.control.Spinner
        mmLabel_27                      matlab.ui.control.Label
        InteriorDimensionL53Spinner_4Label  matlab.ui.control.Label
        InteriorDimensionL53Spinner_4   matlab.ui.control.Spinner
        mmLabel_28                      matlab.ui.control.Label
        InteriorDimensionW3Spinner_4Label  matlab.ui.control.Label
        InteriorDimensionW3Spinner_4    matlab.ui.control.Spinner
        mmLabel_29                      matlab.ui.control.Label
        InteriorDimensionH61Spinner_4Label  matlab.ui.control.Label
        InteriorDimensionH61Spinner_4   matlab.ui.control.Spinner
        dBLabel_4                       matlab.ui.control.Label
        AveragedSoundPressureLevelSpinner_4Label  matlab.ui.control.Label
        AveragedSoundPressureLevelSpinner_4  matlab.ui.control.Spinner
        ErgonomicsPanel_5               matlab.ui.container.Panel
        BoardingComfortLabel_5          matlab.ui.control.Label
        BoardingTimeLabel_5             matlab.ui.control.Label
        LegRoomLabel_5                  matlab.ui.control.Label
        ShouldersRoomLabel_5            matlab.ui.control.Label
        HeadRoomLabel_5                 matlab.ui.control.Label
        InteriorAcousricsLabel_5        matlab.ui.control.Label
        mmLabel_30                      matlab.ui.control.Label
        SillHeightSpinner_5Label        matlab.ui.control.Label
        SillHeightSpinner_5             matlab.ui.control.Spinner
        mmLabel_31                      matlab.ui.control.Label
        ClearanceHeightSpinner_5Label   matlab.ui.control.Label
        ClearanceHeightSpinner_5        matlab.ui.control.Spinner
        mmLabel_32                      matlab.ui.control.Label
        DoorOpeningWidthperSeatSpinner_5Label  matlab.ui.control.Label
        DoorOpeningWidthperSeatSpinner_5  matlab.ui.control.Spinner
        mmLabel_33                      matlab.ui.control.Label
        InteriorDimensionL53Spinner_5Label  matlab.ui.control.Label
        InteriorDimensionL53Spinner_5   matlab.ui.control.Spinner
        mmLabel_34                      matlab.ui.control.Label
        InteriorDimensionW3Spinner_5Label  matlab.ui.control.Label
        InteriorDimensionW3Spinner_5    matlab.ui.control.Spinner
        mmLabel_35                      matlab.ui.control.Label
        InteriorDimensionH61Spinner_5Label  matlab.ui.control.Label
        InteriorDimensionH61Spinner_5   matlab.ui.control.Spinner
        dBLabel_5                       matlab.ui.control.Label
        AveragedSoundPressureLevelSpinner_5Label  matlab.ui.control.Label
        AveragedSoundPressureLevelSpinner_5  matlab.ui.control.Spinner
        ErgonomicsPanel_6               matlab.ui.container.Panel
        BoardingComfortLabel_6          matlab.ui.control.Label
        BoardingTimeLabel_6             matlab.ui.control.Label
        LegRoomLabel_6                  matlab.ui.control.Label
        ShouldersRoomLabel_6            matlab.ui.control.Label
        HeadRoomLabel_6                 matlab.ui.control.Label
        InteriorAcousricsLabel_6        matlab.ui.control.Label
        mmLabel_36                      matlab.ui.control.Label
        SillHeightSpinner_6Label        matlab.ui.control.Label
        SillHeightSpinner_6             matlab.ui.control.Spinner
        mmLabel_37                      matlab.ui.control.Label
        ClearanceHeightSpinner_6Label   matlab.ui.control.Label
        ClearanceHeightSpinner_6        matlab.ui.control.Spinner
        mmLabel_38                      matlab.ui.control.Label
        DoorOpeningWidthperSeatSpinner_6Label  matlab.ui.control.Label
        DoorOpeningWidthperSeatSpinner_6  matlab.ui.control.Spinner
        mmLabel_39                      matlab.ui.control.Label
        InteriorDimensionL53Spinner_6Label  matlab.ui.control.Label
        InteriorDimensionL53Spinner_6   matlab.ui.control.Spinner
        mmLabel_40                      matlab.ui.control.Label
        InteriorDimensionW3Spinner_6Label  matlab.ui.control.Label
        InteriorDimensionW3Spinner_6    matlab.ui.control.Spinner
        mmLabel_41                      matlab.ui.control.Label
        InteriorDimensionH61Spinner_6Label  matlab.ui.control.Label
        InteriorDimensionH61Spinner_6   matlab.ui.control.Spinner
        dBLabel_6                       matlab.ui.control.Label
        AveragedSoundPressureLevelSpinner_6Label  matlab.ui.control.Label
        AveragedSoundPressureLevelSpinner_6  matlab.ui.control.Spinner
        ErgonomicsPanel_7               matlab.ui.container.Panel
        BoardingComfortLabel_7          matlab.ui.control.Label
        BoardingTimeLabel_7             matlab.ui.control.Label
        LegRoomLabel_7                  matlab.ui.control.Label
        ShouldersRoomLabel_7            matlab.ui.control.Label
        HeadRoomLabel_7                 matlab.ui.control.Label
        InteriorAcousricsLabel_7        matlab.ui.control.Label
        mmLabel_42                      matlab.ui.control.Label
        SillHeightSpinner_7Label        matlab.ui.control.Label
        SillHeightSpinner_7             matlab.ui.control.Spinner
        mmLabel_43                      matlab.ui.control.Label
        ClearanceHeightSpinner_7Label   matlab.ui.control.Label
        ClearanceHeightSpinner_7        matlab.ui.control.Spinner
        mmLabel_44                      matlab.ui.control.Label
        DoorOpeningWidthperSeatSpinner_7Label  matlab.ui.control.Label
        DoorOpeningWidthperSeatSpinner_7  matlab.ui.control.Spinner
        mmLabel_45                      matlab.ui.control.Label
        InteriorDimensionL53Spinner_7Label  matlab.ui.control.Label
        InteriorDimensionL53Spinner_7   matlab.ui.control.Spinner
        mmLabel_46                      matlab.ui.control.Label
        InteriorDimensionW3Spinner_7Label  matlab.ui.control.Label
        InteriorDimensionW3Spinner_7    matlab.ui.control.Spinner
        mmLabel_47                      matlab.ui.control.Label
        InteriorDimensionH61Spinner_7Label  matlab.ui.control.Label
        InteriorDimensionH61Spinner_7   matlab.ui.control.Spinner
        dBLabel_7                       matlab.ui.control.Label
        AveragedSoundPressureLevelSpinner_7Label  matlab.ui.control.Label
        AveragedSoundPressureLevelSpinner_7  matlab.ui.control.Spinner
        UsabilityPanel_2                matlab.ui.container.Panel
        PassengersLabel_2               matlab.ui.control.Label
        LuggageSpaceLabel_2             matlab.ui.control.Label
        RadiusofMovementLabel_3         matlab.ui.control.Label
        EconomyLabel_2                  matlab.ui.control.Label
        EcologyLabel_2                  matlab.ui.control.Label
        BadRoadCapabilityLabel_3        matlab.ui.control.Label
        NumberofSeatsSpinner_2Label     matlab.ui.control.Label
        NumberofSeatsSpinner_2          matlab.ui.control.Spinner
        lLabel_2                        matlab.ui.control.Label
        StorageVolumeSpinner_2Label     matlab.ui.control.Label
        StorageVolumeSpinner_2          matlab.ui.control.Spinner
        kmLabel_2                       matlab.ui.control.Label
        RangeSpinner_2Label             matlab.ui.control.Label
        RangeSpinner_2                  matlab.ui.control.Spinner
        Label_4                         matlab.ui.control.Label
        AcquisitionCostsSpinner_2Label  matlab.ui.control.Label
        AcquisitionCostsSpinner_2       matlab.ui.control.Spinner
        tCO2eqLabel_2                   matlab.ui.control.Label
        ProductionEmissionsSpinner_2Label  matlab.ui.control.Label
        ProductionEmissionsSpinner_2    matlab.ui.control.Spinner
        mmLabel_60                      matlab.ui.control.Label
        GroundClearanceSpinner_2Label   matlab.ui.control.Label
        GroundClearanceSpinner_2        matlab.ui.control.Spinner
        UsabilityPanel_3                matlab.ui.container.Panel
        PassengersLabel_3               matlab.ui.control.Label
        LuggageSpaceLabel_3             matlab.ui.control.Label
        RadiusofMovementLabel_4         matlab.ui.control.Label
        EconomyLabel_3                  matlab.ui.control.Label
        EcologyLabel_3                  matlab.ui.control.Label
        BadRoadCapabilityLabel_4        matlab.ui.control.Label
        NumberofSeatsSpinner_3Label     matlab.ui.control.Label
        NumberofSeatsSpinner_3          matlab.ui.control.Spinner
        lLabel_3                        matlab.ui.control.Label
        StorageVolumeSpinner_3Label     matlab.ui.control.Label
        StorageVolumeSpinner_3          matlab.ui.control.Spinner
        kmLabel_3                       matlab.ui.control.Label
        RangeSpinner_3Label             matlab.ui.control.Label
        RangeSpinner_3                  matlab.ui.control.Spinner
        Label_5                         matlab.ui.control.Label
        AcquisitionCostsSpinner_3Label  matlab.ui.control.Label
        AcquisitionCostsSpinner_3       matlab.ui.control.Spinner
        tCO2eqLabel_3                   matlab.ui.control.Label
        ProductionEmissionsSpinner_3Label  matlab.ui.control.Label
        ProductionEmissionsSpinner_3    matlab.ui.control.Spinner
        mmLabel_61                      matlab.ui.control.Label
        GroundClearanceSpinner_3Label   matlab.ui.control.Label
        GroundClearanceSpinner_3        matlab.ui.control.Spinner
        UsabilityPanel_4                matlab.ui.container.Panel
        PassengersLabel_4               matlab.ui.control.Label
        LuggageSpaceLabel_4             matlab.ui.control.Label
        RadiusofMovementLabel_5         matlab.ui.control.Label
        EconomyLabel_4                  matlab.ui.control.Label
        EcologyLabel_4                  matlab.ui.control.Label
        BadRoadCapabilityLabel_5        matlab.ui.control.Label
        NumberofSeatsSpinner_4Label     matlab.ui.control.Label
        NumberofSeatsSpinner_4          matlab.ui.control.Spinner
        lLabel_4                        matlab.ui.control.Label
        StorageVolumeSpinner_4Label     matlab.ui.control.Label
        StorageVolumeSpinner_4          matlab.ui.control.Spinner
        kmLabel_4                       matlab.ui.control.Label
        RangeSpinner_4Label             matlab.ui.control.Label
        RangeSpinner_4                  matlab.ui.control.Spinner
        Label_6                         matlab.ui.control.Label
        AcquisitionCostsSpinner_4Label  matlab.ui.control.Label
        AcquisitionCostsSpinner_4       matlab.ui.control.Spinner
        tCO2eqLabel_4                   matlab.ui.control.Label
        ProductionEmissionsSpinner_4Label  matlab.ui.control.Label
        ProductionEmissionsSpinner_4    matlab.ui.control.Spinner
        mmLabel_62                      matlab.ui.control.Label
        GroundClearanceSpinner_4Label   matlab.ui.control.Label
        GroundClearanceSpinner_4        matlab.ui.control.Spinner
        UsabilityPanel_5                matlab.ui.container.Panel
        PassengersLabel_5               matlab.ui.control.Label
        LuggageSpaceLabel_5             matlab.ui.control.Label
        RadiusofMovementLabel_6         matlab.ui.control.Label
        EconomyLabel_5                  matlab.ui.control.Label
        EcologyLabel_5                  matlab.ui.control.Label
        BadRoadCapabilityLabel_6        matlab.ui.control.Label
        NumberofSeatsSpinner_5Label     matlab.ui.control.Label
        NumberofSeatsSpinner_5          matlab.ui.control.Spinner
        lLabel_5                        matlab.ui.control.Label
        StorageVolumeSpinner_5Label     matlab.ui.control.Label
        StorageVolumeSpinner_5          matlab.ui.control.Spinner
        kmLabel_5                       matlab.ui.control.Label
        RangeSpinner_5Label             matlab.ui.control.Label
        RangeSpinner_5                  matlab.ui.control.Spinner
        Label_7                         matlab.ui.control.Label
        AcquisitionCostsSpinner_5Label  matlab.ui.control.Label
        AcquisitionCostsSpinner_5       matlab.ui.control.Spinner
        tCO2eqLabel_5                   matlab.ui.control.Label
        ProductionEmissionsSpinner_5Label  matlab.ui.control.Label
        ProductionEmissionsSpinner_5    matlab.ui.control.Spinner
        mmLabel_63                      matlab.ui.control.Label
        GroundClearanceSpinner_5Label   matlab.ui.control.Label
        GroundClearanceSpinner_5        matlab.ui.control.Spinner
        UsabilityPanel_6                matlab.ui.container.Panel
        PassengersLabel_6               matlab.ui.control.Label
        LuggageSpaceLabel_6             matlab.ui.control.Label
        RadiusofMovementLabel_7         matlab.ui.control.Label
        EconomyLabel_6                  matlab.ui.control.Label
        EcologyLabel_6                  matlab.ui.control.Label
        BadRoadCapabilityLabel_7        matlab.ui.control.Label
        NumberofSeatsSpinner_6Label     matlab.ui.control.Label
        NumberofSeatsSpinner_6          matlab.ui.control.Spinner
        lLabel_6                        matlab.ui.control.Label
        StorageVolumeSpinner_6Label     matlab.ui.control.Label
        StorageVolumeSpinner_6          matlab.ui.control.Spinner
        kmLabel_6                       matlab.ui.control.Label
        RangeSpinner_6Label             matlab.ui.control.Label
        RangeSpinner_6                  matlab.ui.control.Spinner
        Label_8                         matlab.ui.control.Label
        AcquisitionCostsSpinner_6Label  matlab.ui.control.Label
        AcquisitionCostsSpinner_6       matlab.ui.control.Spinner
        tCO2eqLabel_6                   matlab.ui.control.Label
        ProductionEmissionsSpinner_6Label  matlab.ui.control.Label
        ProductionEmissionsSpinner_6    matlab.ui.control.Spinner
        mmLabel_64                      matlab.ui.control.Label
        GroundClearanceSpinner_6Label   matlab.ui.control.Label
        GroundClearanceSpinner_6        matlab.ui.control.Spinner
        UsabilityPanel_7                matlab.ui.container.Panel
        PassengersLabel_7               matlab.ui.control.Label
        LuggageSpaceLabel_7             matlab.ui.control.Label
        RadiusofMovementLabel_8         matlab.ui.control.Label
        EconomyLabel_7                  matlab.ui.control.Label
        EcologyLabel_7                  matlab.ui.control.Label
        BadRoadCapabilityLabel_8        matlab.ui.control.Label
        NumberofSeatsSpinner_7Label     matlab.ui.control.Label
        NumberofSeatsSpinner_7          matlab.ui.control.Spinner
        lLabel_7                        matlab.ui.control.Label
        StorageVolumeSpinner_7Label     matlab.ui.control.Label
        StorageVolumeSpinner_7          matlab.ui.control.Spinner
        kmLabel_7                       matlab.ui.control.Label
        RangeSpinner_7Label             matlab.ui.control.Label
        RangeSpinner_7                  matlab.ui.control.Spinner
        Label_9                         matlab.ui.control.Label
        AcquisitionCostsSpinner_7Label  matlab.ui.control.Label
        AcquisitionCostsSpinner_7       matlab.ui.control.Spinner
        tCO2eqLabel_7                   matlab.ui.control.Label
        ProductionEmissionsSpinner_7Label  matlab.ui.control.Label
        ProductionEmissionsSpinner_7    matlab.ui.control.Spinner
        mmLabel_65                      matlab.ui.control.Label
        GroundClearanceSpinner_7Label   matlab.ui.control.Label
        GroundClearanceSpinner_7        matlab.ui.control.Spinner
        LuxuryPanel_2                   matlab.ui.container.Panel
        InfotainmentIndividualizationLabel_3  matlab.ui.control.Label
        ExteriorDesignLabel_2           matlab.ui.control.Label
        BuiltInInfotainmentLabel_2      matlab.ui.control.Label
        cmLabel_2                       matlab.ui.control.Label
        ScreenAreaperSeatSpinner_2Label  matlab.ui.control.Label
        ScreenAreaperSeatSpinner_2      matlab.ui.control.Spinner
        mmLabel_66                      matlab.ui.control.Label
        WheelDiameterSpinner_2Label     matlab.ui.control.Label
        WheelDiameterSpinner_2          matlab.ui.control.Spinner
        ResearchontechnicalpropertypendingLabel_16  matlab.ui.control.Label
        LuxuryPanel_3                   matlab.ui.container.Panel
        InfotainmentIndividualizationLabel_4  matlab.ui.control.Label
        ExteriorDesignLabel_3           matlab.ui.control.Label
        BuiltInInfotainmentLabel_3      matlab.ui.control.Label
        cmLabel_3                       matlab.ui.control.Label
        ScreenAreaperSeatSpinner_3Label  matlab.ui.control.Label
        ScreenAreaperSeatSpinner_3      matlab.ui.control.Spinner
        mmLabel_67                      matlab.ui.control.Label
        WheelDiameterSpinner_3Label     matlab.ui.control.Label
        WheelDiameterSpinner_3          matlab.ui.control.Spinner
        ResearchontechnicalpropertypendingLabel_17  matlab.ui.control.Label
        LuxuryPanel_4                   matlab.ui.container.Panel
        InfotainmentIndividualizationLabel_5  matlab.ui.control.Label
        ExteriorDesignLabel_4           matlab.ui.control.Label
        BuiltInInfotainmentLabel_4      matlab.ui.control.Label
        cmLabel_4                       matlab.ui.control.Label
        ScreenAreaperSeatSpinner_4Label  matlab.ui.control.Label
        ScreenAreaperSeatSpinner_4      matlab.ui.control.Spinner
        mmLabel_68                      matlab.ui.control.Label
        WheelDiameterSpinner_4Label     matlab.ui.control.Label
        WheelDiameterSpinner_4          matlab.ui.control.Spinner
        ResearchontechnicalpropertypendingLabel_18  matlab.ui.control.Label
        LuxuryPanel_5                   matlab.ui.container.Panel
        InfotainmentIndividualizationLabel_6  matlab.ui.control.Label
        ExteriorDesignLabel_5           matlab.ui.control.Label
        BuiltInInfotainmentLabel_5      matlab.ui.control.Label
        cmLabel_5                       matlab.ui.control.Label
        ScreenAreaperSeatSpinner_5Label  matlab.ui.control.Label
        ScreenAreaperSeatSpinner_5      matlab.ui.control.Spinner
        mmLabel_69                      matlab.ui.control.Label
        WheelDiameterSpinner_5Label     matlab.ui.control.Label
        WheelDiameterSpinner_5          matlab.ui.control.Spinner
        ResearchontechnicalpropertypendingLabel_19  matlab.ui.control.Label
        LuxuryPanel_6                   matlab.ui.container.Panel
        InfotainmentIndividualizationLabel_7  matlab.ui.control.Label
        ExteriorDesignLabel_6           matlab.ui.control.Label
        BuiltInInfotainmentLabel_6      matlab.ui.control.Label
        cmLabel_6                       matlab.ui.control.Label
        ScreenAreaperSeatSpinner_6Label  matlab.ui.control.Label
        ScreenAreaperSeatSpinner_6      matlab.ui.control.Spinner
        mmLabel_70                      matlab.ui.control.Label
        WheelDiameterSpinner_6Label     matlab.ui.control.Label
        WheelDiameterSpinner_6          matlab.ui.control.Spinner
        ResearchontechnicalpropertypendingLabel_20  matlab.ui.control.Label
        LuxuryPanel_7                   matlab.ui.container.Panel
        InfotainmentIndividualizationLabel_8  matlab.ui.control.Label
        ExteriorDesignLabel_7           matlab.ui.control.Label
        BuiltInInfotainmentLabel_7      matlab.ui.control.Label
        cmLabel_7                       matlab.ui.control.Label
        ScreenAreaperSeatSpinner_7Label  matlab.ui.control.Label
        ScreenAreaperSeatSpinner_7      matlab.ui.control.Spinner
        mmLabel_71                      matlab.ui.control.Label
        WheelDiameterSpinner_7Label     matlab.ui.control.Label
        WheelDiameterSpinner_7          matlab.ui.control.Spinner
        ResearchontechnicalpropertypendingLabel_21  matlab.ui.control.Label
        DynamicsPanel_2                 matlab.ui.container.Panel
        Accelerationfrom0kmhto100kmhSpinner_2Label  matlab.ui.control.Label
        Accelerationfrom0kmhto100kmhSpinner_2  matlab.ui.control.Spinner
        sLabel_9                        matlab.ui.control.Label
        TopSpeedSpinner_2Label          matlab.ui.control.Label
        TopSpeedSpinner_2               matlab.ui.control.Spinner
        kmhLabel_2                      matlab.ui.control.Label
        LongitunalDynamicsLabel_2       matlab.ui.control.Label
        mmLabel_72                      matlab.ui.control.Label
        HeightoftheCenterofGravitySpinner_2Label  matlab.ui.control.Label
        HeightoftheCenterofGravitySpinner_2  matlab.ui.control.Spinner
        LateralDynamicsLabel_2          matlab.ui.control.Label
        VerticalAccelerationSpinner_2Label  matlab.ui.control.Label
        VerticalAccelerationSpinner_2   matlab.ui.control.Spinner
        msLabel_16                      matlab.ui.control.Label
        VerticalDynamicsLabel_2         matlab.ui.control.Label
        TurningCircleSpinner_2Label     matlab.ui.control.Label
        TurningCircleSpinner_2          matlab.ui.control.Spinner
        mLabel_9                        matlab.ui.control.Label
        ManeuverabilityLabel_2          matlab.ui.control.Label
        DynamicsPanel_3                 matlab.ui.container.Panel
        Accelerationfrom0kmhto100kmhSpinner_3Label  matlab.ui.control.Label
        Accelerationfrom0kmhto100kmhSpinner_3  matlab.ui.control.Spinner
        sLabel_10                       matlab.ui.control.Label
        TopSpeedSpinner_3Label          matlab.ui.control.Label
        TopSpeedSpinner_3               matlab.ui.control.Spinner
        kmhLabel_3                      matlab.ui.control.Label
        LongitunalDynamicsLabel_3       matlab.ui.control.Label
        mmLabel_73                      matlab.ui.control.Label
        HeightoftheCenterofGravitySpinner_3Label  matlab.ui.control.Label
        HeightoftheCenterofGravitySpinner_3  matlab.ui.control.Spinner
        LateralDynamicsLabel_3          matlab.ui.control.Label
        VerticalAccelerationSpinner_3Label  matlab.ui.control.Label
        VerticalAccelerationSpinner_3   matlab.ui.control.Spinner
        msLabel_17                      matlab.ui.control.Label
        VerticalDynamicsLabel_3         matlab.ui.control.Label
        TurningCircleSpinner_3Label     matlab.ui.control.Label
        TurningCircleSpinner_3          matlab.ui.control.Spinner
        mLabel_10                       matlab.ui.control.Label
        ManeuverabilityLabel_3          matlab.ui.control.Label
        DynamicsPanel_4                 matlab.ui.container.Panel
        Accelerationfrom0kmhto100kmhSpinner_4Label  matlab.ui.control.Label
        Accelerationfrom0kmhto100kmhSpinner_4  matlab.ui.control.Spinner
        sLabel_11                       matlab.ui.control.Label
        TopSpeedSpinner_4Label          matlab.ui.control.Label
        TopSpeedSpinner_4               matlab.ui.control.Spinner
        kmhLabel_4                      matlab.ui.control.Label
        LongitunalDynamicsLabel_4       matlab.ui.control.Label
        mmLabel_74                      matlab.ui.control.Label
        HeightoftheCenterofGravitySpinner_4Label  matlab.ui.control.Label
        HeightoftheCenterofGravitySpinner_4  matlab.ui.control.Spinner
        LateralDynamicsLabel_4          matlab.ui.control.Label
        VerticalAccelerationSpinner_4Label  matlab.ui.control.Label
        VerticalAccelerationSpinner_4   matlab.ui.control.Spinner
        msLabel_18                      matlab.ui.control.Label
        VerticalDynamicsLabel_4         matlab.ui.control.Label
        TurningCircleSpinner_4Label     matlab.ui.control.Label
        TurningCircleSpinner_4          matlab.ui.control.Spinner
        mLabel_11                       matlab.ui.control.Label
        ManeuverabilityLabel_4          matlab.ui.control.Label
        DynamicsPanel_5                 matlab.ui.container.Panel
        Accelerationfrom0kmhto100kmhSpinner_5Label  matlab.ui.control.Label
        Accelerationfrom0kmhto100kmhSpinner_5  matlab.ui.control.Spinner
        sLabel_12                       matlab.ui.control.Label
        TopSpeedSpinner_5Label          matlab.ui.control.Label
        TopSpeedSpinner_5               matlab.ui.control.Spinner
        kmhLabel_5                      matlab.ui.control.Label
        LongitunalDynamicsLabel_5       matlab.ui.control.Label
        mmLabel_75                      matlab.ui.control.Label
        HeightoftheCenterofGravitySpinner_5Label  matlab.ui.control.Label
        HeightoftheCenterofGravitySpinner_5  matlab.ui.control.Spinner
        LateralDynamicsLabel_5          matlab.ui.control.Label
        VerticalAccelerationSpinner_5Label  matlab.ui.control.Label
        VerticalAccelerationSpinner_5   matlab.ui.control.Spinner
        msLabel_19                      matlab.ui.control.Label
        VerticalDynamicsLabel_5         matlab.ui.control.Label
        TurningCircleSpinner_5Label     matlab.ui.control.Label
        TurningCircleSpinner_5          matlab.ui.control.Spinner
        mLabel_12                       matlab.ui.control.Label
        ManeuverabilityLabel_5          matlab.ui.control.Label
        DynamicsPanel_6                 matlab.ui.container.Panel
        Accelerationfrom0kmhto100kmhSpinner_6Label  matlab.ui.control.Label
        Accelerationfrom0kmhto100kmhSpinner_6  matlab.ui.control.Spinner
        sLabel_13                       matlab.ui.control.Label
        TopSpeedSpinner_6Label          matlab.ui.control.Label
        TopSpeedSpinner_6               matlab.ui.control.Spinner
        kmhLabel_6                      matlab.ui.control.Label
        LongitunalDynamicsLabel_6       matlab.ui.control.Label
        mmLabel_76                      matlab.ui.control.Label
        HeightoftheCenterofGravitySpinner_6Label  matlab.ui.control.Label
        HeightoftheCenterofGravitySpinner_6  matlab.ui.control.Spinner
        LateralDynamicsLabel_6          matlab.ui.control.Label
        VerticalAccelerationSpinner_6Label  matlab.ui.control.Label
        VerticalAccelerationSpinner_6   matlab.ui.control.Spinner
        msLabel_20                      matlab.ui.control.Label
        VerticalDynamicsLabel_6         matlab.ui.control.Label
        TurningCircleSpinner_6Label     matlab.ui.control.Label
        TurningCircleSpinner_6          matlab.ui.control.Spinner
        mLabel_13                       matlab.ui.control.Label
        ManeuverabilityLabel_6          matlab.ui.control.Label
        DynamicsPanel_7                 matlab.ui.container.Panel
        Accelerationfrom0kmhto100kmhSpinner_7Label  matlab.ui.control.Label
        Accelerationfrom0kmhto100kmhSpinner_7  matlab.ui.control.Spinner
        sLabel_14                       matlab.ui.control.Label
        TopSpeedSpinner_7Label          matlab.ui.control.Label
        TopSpeedSpinner_7               matlab.ui.control.Spinner
        kmhLabel_7                      matlab.ui.control.Label
        LongitunalDynamicsLabel_7       matlab.ui.control.Label
        mmLabel_77                      matlab.ui.control.Label
        HeightoftheCenterofGravitySpinner_7Label  matlab.ui.control.Label
        HeightoftheCenterofGravitySpinner_7  matlab.ui.control.Spinner
        LateralDynamicsLabel_7          matlab.ui.control.Label
        VerticalAccelerationSpinner_7Label  matlab.ui.control.Label
        VerticalAccelerationSpinner_7   matlab.ui.control.Spinner
        msLabel_21                      matlab.ui.control.Label
        VerticalDynamicsLabel_7         matlab.ui.control.Label
        TurningCircleSpinner_7Label     matlab.ui.control.Label
        TurningCircleSpinner_7          matlab.ui.control.Spinner
        mLabel_14                       matlab.ui.control.Label
        ManeuverabilityLabel_7          matlab.ui.control.Label
        DrivingStylePanel_7             matlab.ui.container.Panel
        ConsumptionLabel_7              matlab.ui.control.Label
        DSTimePotentialLabel_7          matlab.ui.control.Label
        DSSafetyLabel_7                 matlab.ui.control.Label
        DSComfortLabel_7                matlab.ui.control.Label
        msLabel_14                      matlab.ui.control.Label
        AccelerationSpinner_7Label      matlab.ui.control.Label
        AccelerationSpinner_7           matlab.ui.control.Spinner
        msLabel_15                      matlab.ui.control.Label
        JerkSpinner_7Label              matlab.ui.control.Label
        JerkSpinner_7                   matlab.ui.control.Spinner
        HomeostaticRiskPerceptionSpinner_4Label  matlab.ui.control.Label
        HomeostaticRiskPerceptionSpinner_7  matlab.ui.control.Spinner
        FastnessSpinner_6Label          matlab.ui.control.Label
        FastnessSpinner_7               matlab.ui.control.Spinner
        kWhLabel_7                      matlab.ui.control.Label
        Consumptionper100kmSpinner_7Label  matlab.ui.control.Label
        Consumptionper100kmSpinner_7    matlab.ui.control.Spinner
        DrivingStylePanel_6             matlab.ui.container.Panel
        ConsumptionLabel_6              matlab.ui.control.Label
        DSTimePotentialLabel_6          matlab.ui.control.Label
        DSSafetyLabel_6                 matlab.ui.control.Label
        DSComfortLabel_6                matlab.ui.control.Label
        msLabel_12                      matlab.ui.control.Label
        AccelerationSpinner_6Label      matlab.ui.control.Label
        AccelerationSpinner_6           matlab.ui.control.Spinner
        msLabel_13                      matlab.ui.control.Label
        JerkSpinner_6Label              matlab.ui.control.Label
        JerkSpinner_6                   matlab.ui.control.Spinner
        HomeostaticRiskPerceptionSpinner_5Label  matlab.ui.control.Label
        HomeostaticRiskPerceptionSpinner_6  matlab.ui.control.Spinner
        FastnessSpinner_5Label          matlab.ui.control.Label
        FastnessSpinner_6               matlab.ui.control.Spinner
        kWhLabel_6                      matlab.ui.control.Label
        Consumptionper100kmSpinner_6Label  matlab.ui.control.Label
        Consumptionper100kmSpinner_6    matlab.ui.control.Spinner
        DrivingStylePanel_5             matlab.ui.container.Panel
        ConsumptionLabel_5              matlab.ui.control.Label
        DSTimePotentialLabel_5          matlab.ui.control.Label
        DSSafetyLabel_5                 matlab.ui.control.Label
        DSComfortLabel_5                matlab.ui.control.Label
        msLabel_10                      matlab.ui.control.Label
        AccelerationSpinner_5Label      matlab.ui.control.Label
        AccelerationSpinner_5           matlab.ui.control.Spinner
        msLabel_11                      matlab.ui.control.Label
        JerkSpinner_5Label              matlab.ui.control.Label
        JerkSpinner_5                   matlab.ui.control.Spinner
        HomeostaticRiskPerceptionSpinner_6Label  matlab.ui.control.Label
        HomeostaticRiskPerceptionSpinner_5  matlab.ui.control.Spinner
        FastnessSpinner_4Label          matlab.ui.control.Label
        FastnessSpinner_5               matlab.ui.control.Spinner
        kWhLabel_5                      matlab.ui.control.Label
        Consumptionper100kmSpinner_5Label  matlab.ui.control.Label
        Consumptionper100kmSpinner_5    matlab.ui.control.Spinner
        DrivingStylePanel_2             matlab.ui.container.Panel
        ConsumptionLabel_2              matlab.ui.control.Label
        DSTimePotentialLabel_2          matlab.ui.control.Label
        DSSafetyLabel_2                 matlab.ui.control.Label
        DSComfortLabel_2                matlab.ui.control.Label
        msLabel_4                       matlab.ui.control.Label
        AccelerationSpinner_2Label      matlab.ui.control.Label
        AccelerationSpinner_2           matlab.ui.control.Spinner
        msLabel_5                       matlab.ui.control.Label
        JerkSpinner_2Label              matlab.ui.control.Label
        JerkSpinner_2                   matlab.ui.control.Spinner
        HomeostaticRiskPerceptionSpinnerLabel  matlab.ui.control.Label
        HomeostaticRiskPerceptionSpinner_2  matlab.ui.control.Spinner
        FastnessSpinnerLabel            matlab.ui.control.Label
        FastnessSpinner_2               matlab.ui.control.Spinner
        kWhLabel_2                      matlab.ui.control.Label
        Consumptionper100kmSpinner_2Label  matlab.ui.control.Label
        Consumptionper100kmSpinner_2    matlab.ui.control.Spinner
        DrivingStylePanel_3             matlab.ui.container.Panel
        ConsumptionLabel_3              matlab.ui.control.Label
        DSTimePotentialLabel_3          matlab.ui.control.Label
        DSSafetyLabel_3                 matlab.ui.control.Label
        DSComfortLabel_3                matlab.ui.control.Label
        msLabel_6                       matlab.ui.control.Label
        AccelerationSpinner_3Label      matlab.ui.control.Label
        AccelerationSpinner_3           matlab.ui.control.Spinner
        msLabel_7                       matlab.ui.control.Label
        JerkSpinner_3Label              matlab.ui.control.Label
        JerkSpinner_3                   matlab.ui.control.Spinner
        HomeostaticRiskPerceptionSpinner_2Label  matlab.ui.control.Label
        HomeostaticRiskPerceptionSpinner_3  matlab.ui.control.Spinner
        FastnessSpinner_2Label          matlab.ui.control.Label
        FastnessSpinner_3               matlab.ui.control.Spinner
        kWhLabel_3                      matlab.ui.control.Label
        Consumptionper100kmSpinner_3Label  matlab.ui.control.Label
        Consumptionper100kmSpinner_3    matlab.ui.control.Spinner
        DrivingStylePanel_4             matlab.ui.container.Panel
        ConsumptionLabel_4              matlab.ui.control.Label
        DSTimePotentialLabel_4          matlab.ui.control.Label
        DSSafetyLabel_4                 matlab.ui.control.Label
        DSComfortLabel_4                matlab.ui.control.Label
        msLabel_8                       matlab.ui.control.Label
        AccelerationSpinner_4Label      matlab.ui.control.Label
        AccelerationSpinner_4           matlab.ui.control.Spinner
        msLabel_9                       matlab.ui.control.Label
        JerkSpinner_4Label              matlab.ui.control.Label
        JerkSpinner_4                   matlab.ui.control.Spinner
        HomeostaticRiskPerceptionSpinner_3Label  matlab.ui.control.Label
        HomeostaticRiskPerceptionSpinner_4  matlab.ui.control.Spinner
        FastnessSpinner_3Label          matlab.ui.control.Label
        FastnessSpinner_4               matlab.ui.control.Spinner
        kWhLabel_4                      matlab.ui.control.Label
        Consumptionper100kmSpinner_4Label  matlab.ui.control.Label
        Consumptionper100kmSpinner_4    matlab.ui.control.Spinner
        SecurityPanel_7                 matlab.ui.container.Panel
        ExternalCommunicationLabel_7    matlab.ui.control.Label
        QualityofAutomationLabel_7      matlab.ui.control.Label
        ActiveSafetyLabel_7             matlab.ui.control.Label
        PassiveSafetyLabel_7            matlab.ui.control.Label
        InteriorRecognitionLabel_7      matlab.ui.control.Label
        mmLabel_58                      matlab.ui.control.Label
        CrashLengthSpinner_7Label       matlab.ui.control.Label
        CrashLengthSpinner_7            matlab.ui.control.Spinner
        sLabel_21                       matlab.ui.control.Label
        Elasticityfrom60kmhto100kmhSpinner_7Label  matlab.ui.control.Label
        Elasticityfrom60kmhto100kmhSpinner_7  matlab.ui.control.Spinner
        Disengagementper100000kmSpinner_7Label  matlab.ui.control.Label
        Disengagementper100000kmSpinner_7  matlab.ui.control.Spinner
        ResearchontechnicalpropertypendingLabel_14  matlab.ui.control.Label
        ResearchontechnicalpropertypendingLabel_15  matlab.ui.control.Label
        SecurityPanel_6                 matlab.ui.container.Panel
        ExternalCommunicationLabel_6    matlab.ui.control.Label
        QualityofAutomationLabel_6      matlab.ui.control.Label
        ActiveSafetyLabel_6             matlab.ui.control.Label
        PassiveSafetyLabel_6            matlab.ui.control.Label
        InteriorRecognitionLabel_6      matlab.ui.control.Label
        mmLabel_56                      matlab.ui.control.Label
        CrashLengthSpinner_6Label       matlab.ui.control.Label
        CrashLengthSpinner_6            matlab.ui.control.Spinner
        sLabel_15                       matlab.ui.control.Label
        Elasticityfrom60kmhto100kmhSpinner_6Label  matlab.ui.control.Label
        Elasticityfrom60kmhto100kmhSpinner_6  matlab.ui.control.Spinner
        Disengagementper100000kmSpinner_6Label  matlab.ui.control.Label
        Disengagementper100000kmSpinner_6  matlab.ui.control.Spinner
        ResearchontechnicalpropertypendingLabel_12  matlab.ui.control.Label
        ResearchontechnicalpropertypendingLabel_13  matlab.ui.control.Label
        SecurityPanel_5                 matlab.ui.container.Panel
        ExternalCommunicationLabel_5    matlab.ui.control.Label
        QualityofAutomationLabel_5      matlab.ui.control.Label
        ActiveSafetyLabel_5             matlab.ui.control.Label
        PassiveSafetyLabel_5            matlab.ui.control.Label
        InteriorRecognitionLabel_5      matlab.ui.control.Label
        mmLabel_54                      matlab.ui.control.Label
        CrashLengthSpinner_5Label       matlab.ui.control.Label
        CrashLengthSpinner_5            matlab.ui.control.Spinner
        sLabel_20                       matlab.ui.control.Label
        Elasticityfrom60kmhto100kmhSpinner_5Label  matlab.ui.control.Label
        Elasticityfrom60kmhto100kmhSpinner_5  matlab.ui.control.Spinner
        Disengagementper100000kmSpinner_5Label  matlab.ui.control.Label
        Disengagementper100000kmSpinner_5  matlab.ui.control.Spinner
        ResearchontechnicalpropertypendingLabel_10  matlab.ui.control.Label
        ResearchontechnicalpropertypendingLabel_11  matlab.ui.control.Label
        SecurityPanel_4                 matlab.ui.container.Panel
        ExternalCommunicationLabel_4    matlab.ui.control.Label
        QualityofAutomationLabel_4      matlab.ui.control.Label
        ActiveSafetyLabel_4             matlab.ui.control.Label
        PassiveSafetyLabel_4            matlab.ui.control.Label
        InteriorRecognitionLabel_4      matlab.ui.control.Label
        mmLabel_52                      matlab.ui.control.Label
        CrashLengthSpinner_4Label       matlab.ui.control.Label
        CrashLengthSpinner_4            matlab.ui.control.Spinner
        sLabel_19                       matlab.ui.control.Label
        Elasticityfrom60kmhto100kmhSpinner_4Label  matlab.ui.control.Label
        Elasticityfrom60kmhto100kmhSpinner_4  matlab.ui.control.Spinner
        Disengagementper100000kmSpinner_4Label  matlab.ui.control.Label
        Disengagementper100000kmSpinner_4  matlab.ui.control.Spinner
        ResearchontechnicalpropertypendingLabel_8  matlab.ui.control.Label
        ResearchontechnicalpropertypendingLabel_9  matlab.ui.control.Label
        SecurityPanel_3                 matlab.ui.container.Panel
        ExternalCommunicationLabel_3    matlab.ui.control.Label
        QualityofAutomationLabel_3      matlab.ui.control.Label
        ActiveSafetyLabel_3             matlab.ui.control.Label
        PassiveSafetyLabel_3            matlab.ui.control.Label
        InteriorRecognitionLabel_3      matlab.ui.control.Label
        mmLabel_50                      matlab.ui.control.Label
        CrashLengthSpinner_3Label       matlab.ui.control.Label
        CrashLengthSpinner_3            matlab.ui.control.Spinner
        sLabel_18                       matlab.ui.control.Label
        Elasticityfrom60kmhto100kmhSpinner_3Label  matlab.ui.control.Label
        Elasticityfrom60kmhto100kmhSpinner_3  matlab.ui.control.Spinner
        Disengagementper100000kmSpinner_3Label  matlab.ui.control.Label
        Disengagementper100000kmSpinner_3  matlab.ui.control.Spinner
        ResearchontechnicalpropertypendingLabel_6  matlab.ui.control.Label
        ResearchontechnicalpropertypendingLabel_7  matlab.ui.control.Label
        SecurityPanel_2                 matlab.ui.container.Panel
        ExternalCommunicationLabel_2    matlab.ui.control.Label
        QualityofAutomationLabel_2      matlab.ui.control.Label
        ActiveSafetyLabel_2             matlab.ui.control.Label
        PassiveSafetyLabel_2            matlab.ui.control.Label
        InteriorRecognitionLabel_2      matlab.ui.control.Label
        mmLabel_48                      matlab.ui.control.Label
        CrashLengthSpinner_2Label       matlab.ui.control.Label
        CrashLengthSpinner_2            matlab.ui.control.Spinner
        sLabel_17                       matlab.ui.control.Label
        Elasticityfrom60kmhto100kmhSpinner_2Label  matlab.ui.control.Label
        Elasticityfrom60kmhto100kmhSpinner_2  matlab.ui.control.Spinner
        Disengagementper100000kmSpinner_2Label  matlab.ui.control.Label
        Disengagementper100000kmSpinner_2  matlab.ui.control.Spinner
        ResearchontechnicalpropertypendingLabel_4  matlab.ui.control.Label
        ResearchontechnicalpropertypendingLabel_5  matlab.ui.control.Label
        allTPforoneVCPanel              matlab.ui.container.Panel
        DynamicsPanel_1                 matlab.ui.container.Panel
        Accelerationfrom0kmhto100kmhSpinnerLabel  matlab.ui.control.Label
        Accelerationfrom0kmhto100kmhSpinner_1  matlab.ui.control.Spinner
        TopSpeedSpinnerLabel            matlab.ui.control.Label
        TopSpeedSpinner_1               matlab.ui.control.Spinner
        sLabel                          matlab.ui.control.Label
        kmhLabel                        matlab.ui.control.Label
        mmLabel                         matlab.ui.control.Label
        HeightoftheCenterofGravitySpinnerLabel  matlab.ui.control.Label
        HeightoftheCenterofGravitySpinner_1  matlab.ui.control.Spinner
        VerticalAccelerationSpinnerLabel  matlab.ui.control.Label
        VerticalAccelerationSpinner_1   matlab.ui.control.Spinner
        TurningCircleSpinnerLabel       matlab.ui.control.Label
        TurningCircleSpinner_1          matlab.ui.control.Spinner
        msLabel                         matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        LongitunalDynamicsLabel         matlab.ui.control.Label
        LateralDynamicsLabel            matlab.ui.control.Label
        VerticalDynamicsLabel           matlab.ui.control.Label
        ManeuverabilityLabel            matlab.ui.control.Label
        ErgonomicsPanel_1               matlab.ui.container.Panel
        BoardingComfortLabel            matlab.ui.control.Label
        BoardingTimeLabel               matlab.ui.control.Label
        LegRoomLabel                    matlab.ui.control.Label
        ShouldersRoomLabel              matlab.ui.control.Label
        HeadRoomLabel                   matlab.ui.control.Label
        InteriorAcousticsLabel          matlab.ui.control.Label
        mmLabel_2                       matlab.ui.control.Label
        SillHeightSpinnerLabel          matlab.ui.control.Label
        SillHeightSpinner_1             matlab.ui.control.Spinner
        mmLabel_3                       matlab.ui.control.Label
        ClearanceHeightSpinnerLabel     matlab.ui.control.Label
        ClearanceHeightSpinner_1        matlab.ui.control.Spinner
        mmLabel_4                       matlab.ui.control.Label
        DoorOpeningWidthperSeatSpinnerLabel  matlab.ui.control.Label
        DoorOpeningWidthperSeatSpinner_1  matlab.ui.control.Spinner
        mmLabel_5                       matlab.ui.control.Label
        InteriorDimensionL53SpinnerLabel  matlab.ui.control.Label
        InteriorDimensionL53Spinner_1   matlab.ui.control.Spinner
        mmLabel_6                       matlab.ui.control.Label
        InteriorDimensionW3SpinnerLabel  matlab.ui.control.Label
        InteriorDimensionW3Spinner_1    matlab.ui.control.Spinner
        mmLabel_7                       matlab.ui.control.Label
        InteriorDimensionH61SpinnerLabel  matlab.ui.control.Label
        InteriorDimensionH61Spinner_1   matlab.ui.control.Spinner
        dBLabel                         matlab.ui.control.Label
        AveragedSoundPressureLevelSpinnerLabel  matlab.ui.control.Label
        AveragedSoundPressureLevelSpinner_1  matlab.ui.control.Spinner
        SecurityPanel_1                 matlab.ui.container.Panel
        ExternalCommunicationLabel      matlab.ui.control.Label
        QualityofAutomationLabel        matlab.ui.control.Label
        ActiveSafetyLabel               matlab.ui.control.Label
        PassiveSafetyLabel              matlab.ui.control.Label
        InteriorRecognitionLabel        matlab.ui.control.Label
        mmLabel_8                       matlab.ui.control.Label
        CrashLengthSpinnerLabel         matlab.ui.control.Label
        CrashLengthSpinner_1            matlab.ui.control.Spinner
        sLabel_16                       matlab.ui.control.Label
        Elasticityfrom60kmhto100kmhSpinnerLabel  matlab.ui.control.Label
        Elasticityfrom60kmhto100kmhSpinner_1  matlab.ui.control.Spinner
        Disengagementper100000kmSpinnerLabel  matlab.ui.control.Label
        Disengagementper100000kmSpinner_1  matlab.ui.control.Spinner
        ResearchontechnicalpropertypendingLabel  matlab.ui.control.Label
        ResearchontechnicalpropertypendingLabel_2  matlab.ui.control.Label
        UsabilityPanel_1                matlab.ui.container.Panel
        PassengersLabel                 matlab.ui.control.Label
        LuggageSpaceLabel               matlab.ui.control.Label
        RadiusofMovementLabel_2         matlab.ui.control.Label
        EconomyLabel                    matlab.ui.control.Label
        EcologyLabel                    matlab.ui.control.Label
        BadRoadCapabilityLabel_2        matlab.ui.control.Label
        NumberofSeatsSpinnerLabel       matlab.ui.control.Label
        NumberofSeatsSpinner_1          matlab.ui.control.Spinner
        lLabel                          matlab.ui.control.Label
        StorageVolumeSpinnerLabel       matlab.ui.control.Label
        StorageVolumeSpinner_1          matlab.ui.control.Spinner
        kmLabel                         matlab.ui.control.Label
        RangeSpinnerLabel               matlab.ui.control.Label
        RangeSpinner_1                  matlab.ui.control.Spinner
        Label_3                         matlab.ui.control.Label
        AcquisitionCostsSpinnerLabel    matlab.ui.control.Label
        AcquisitionCostsSpinner_1       matlab.ui.control.Spinner
        tCO2eqLabel                     matlab.ui.control.Label
        ProductionEmissionsSpinnerLabel  matlab.ui.control.Label
        ProductionEmissionsSpinner_1    matlab.ui.control.Spinner
        mmLabel_10                      matlab.ui.control.Label
        GroundClearanceSpinnerLabel     matlab.ui.control.Label
        GroundClearanceSpinner_1        matlab.ui.control.Spinner
        DrivingStylePanel_1             matlab.ui.container.Panel
        ConsumptionLabel                matlab.ui.control.Label
        DSTimePotentialLabel            matlab.ui.control.Label
        DSSafetyLabel                   matlab.ui.control.Label
        DSComfortLabel                  matlab.ui.control.Label
        msLabel_2                       matlab.ui.control.Label
        AccelerationSpinnerLabel        matlab.ui.control.Label
        AccelerationSpinner_1           matlab.ui.control.Spinner
        msLabel_3                       matlab.ui.control.Label
        JerkSpinnerLabel                matlab.ui.control.Label
        JerkSpinner_1                   matlab.ui.control.Spinner
        HomeostaticRiskPerceptionSpinner_8Label  matlab.ui.control.Label
        HomeostaticRiskPerceptionSpinner_1  matlab.ui.control.Spinner
        FastnessSpinner_34Label         matlab.ui.control.Label
        FastnessSpinner_1               matlab.ui.control.Spinner
        kWhLabel                        matlab.ui.control.Label
        Consumptionper100kmSpinnerLabel  matlab.ui.control.Label
        Consumptionper100kmSpinner_1    matlab.ui.control.Spinner
        LuxuryPanel_1                   matlab.ui.container.Panel
        InfotainmentIndividualizationLabel_2  matlab.ui.control.Label
        ExteriorDesignLabel             matlab.ui.control.Label
        BuiltInInfotainmentLabel        matlab.ui.control.Label
        cmLabel                         matlab.ui.control.Label
        ScreenAreaperSeatSpinnerLabel   matlab.ui.control.Label
        ScreenAreaperSeatSpinner_1      matlab.ui.control.Spinner
        mmLabel_11                      matlab.ui.control.Label
        WheelDiameterSpinnerLabel       matlab.ui.control.Label
        WheelDiameterSpinner_1          matlab.ui.control.Spinner
        ResearchontechnicalpropertypendingLabel_3  matlab.ui.control.Label
        OnlythevehicleconceptwiththelowestnumberisshownLabel  matlab.ui.control.Label
    end

    %% General Information:
    
    % Designed by Ferdinand Schockenhoff (FTM, Techical University of Munich)
    % Template and few parts of the code by David Fischer (FTM, Techical University of Munich)
    %---------------------------------------
    % Created on September 2021
    % Last update: 2021-12-07
    %---------------------------------------
    % Version: Matlab2020b
    %---------------------------------------
    % Description: GUI of the process step "Technical Properties"
    %---------------------------------------
    % Input: TechnicalPropertiesVC           (technical properties of the Vehicle Concepts)         double (n x 30) n= number of vehicle concepts
    %        CustomerRelevantPropertiesVC    (customer-relevant properties of the Vehicle Concepts) double (n x 29) n= number of vehicle concepts
    %        Derivative                      (previous derivative of the Vehicle Concepts)          double (n x 18) n = number of vehicle concepts
    %        MinMaxTP                        (Min and Max Value of the 30 technical properties)     double (2 x 30)
    %        corrfunct                       (correlation functions of the CRPs and the TPs)        cell (1 x 30) (input of the cells functions formated as string)
    %        VehicleType                     (Vehicle Type of the VCs)                              string (1x n) n = number of vehicle concepts
    %---------------------------------------
    % Output: 
    % Start of the app Design Parameters
    
    properties (Access = private)
        oldpath = addpath('99_General_Elements\') % Needed for first build up of app (TUM image)
        
        %imported input data from vcustomer relevant properties app
        vehicleConceptsTP;             %technical properties for each vehicle concept
        vehicleConceptsTPBackup;       %Backup
        vehicleConceptsCRP;            %CRP for each vehcilce concept
        Derivatives;                   %Derivatives of the VCs
        MinMaxValues;                  % Max an dMin Values of the TPs
        CorrelationFunctions;          % Correlation functions between CRPs and TPs
        VCType;                        % Type of the VC (Private, Taxi, Shuttle)
        ColorVC = [[249 186 0]./255; [0 124 48]./255;  [105 8 90]./255; [227 114 34]./255; [162 173 0]./255; [152 198 234]./255; [218 215 203]./255;...
                 [255 220 0]./255; [103 154 29]./255; [156 13 22]./255; [214 76 19]./255; [50 119 138]./255; [100 160 200]./255; [15 27 9]./255;]; %TUM Colors for Vehicle Concepts
    end
    
    properties (Access = public)
        
    end
        
    
    methods (Access = private)
               
        
        %__________________________________________________________________
        %Determinates the visible Vehicles for main view
        function checkBoxesEnabler(app,lampname,labelname,checkboxname,maxfields)
            %Enable the user check boxes
            for i=1:maxfields
                fieldName = sprintf(lampname,i);
                app.(fieldName).Visible = 'on';
                labelName = sprintf(labelname,i);
                app.(labelName).Visible = 'on';
                checkBoxName = sprintf(checkboxname,i);
                app.(checkBoxName).Visible = 'on';
                app.(checkBoxName).Enable = 'on';
            end
            if maxfields < 14
                extendedNumber = maxfields + 1;
                for k=extendedNumber:14
                    fieldName = sprintf(lampname,k);
                    app.(fieldName).Visible = 'off';
                    labelName = sprintf(labelname,k);
                    app.(labelName).Visible = 'off';
                    checkBoxName = sprintf(checkboxname,k);
                    app.(checkBoxName).Visible = 'off';
                    app.(checkBoxName).Enable = 'off';
                end
            end
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Evaluating the color of the lamps
        function colorEvaluator(app,number,LampName,checkboxName)
            value = app.(checkboxName).Value;
            VCCol = app.ColorVC;
            if value == true
                app.(LampName).Color = VCCol(number,:);
            else
                app.(LampName).Color = 'white';
            end
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Determinates a matrix with the ticked Vehicles for view
        function [tickMatrixVehicles] = checkBoxChecker(app)
            %Check the ticked Vehicles
            [vehicleNumber,~] = size(app.vehicleConceptsCRP);
            tickMatrixVehicles = zeros(vehicleNumber,1);
            for i=1:vehicleNumber
                checkBoxName = sprintf('CheckBox%d',i);
                if app.(checkBoxName).Value == true
                    tickMatrixVehicles(i) = 1;
                else
                    tickMatrixVehicles(i) = 0;
                end
            end
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        % Enables every Spinner       
        function Enabler(app)
            for i = 1:7
                %Dynamics
                SpinnerName = sprintf('TopSpeedSpinner_%d',i);
                app.(SpinnerName).Enable = 'on';
                SpinnerName = sprintf('Accelerationfrom0kmhto100kmhSpinner_%d',i);
                app.(SpinnerName).Enable = 'on';
                SpinnerName = sprintf('HeightoftheCenterofGravitySpinner_%d',i);
                app.(SpinnerName).Enable = 'on';
                SpinnerName = sprintf('VerticalAccelerationSpinner_%d',i);
                app.(SpinnerName).Enable = 'on';
                SpinnerName = sprintf('TurningCircleSpinner_%d',i);
                app.(SpinnerName).Enable = 'on';
                
                %Ergonomics
                SpinnerName = sprintf('SillHeightSpinner_%d',i);
                app.(SpinnerName).Enable = 'on';
                SpinnerName = sprintf('ClearanceHeightSpinner_%d',i);
                app.(SpinnerName).Enable = 'on';
                SpinnerName = sprintf('DoorOpeningWidthperSeatSpinner_%d',i);
                app.(SpinnerName).Enable = 'on';
                SpinnerName = sprintf('InteriorDimensionL53Spinner_%d',i);
                app.(SpinnerName).Enable = 'on';
                SpinnerName = sprintf('InteriorDimensionW3Spinner_%d',i);
                app.(SpinnerName).Enable = 'on';
                SpinnerName = sprintf('InteriorDimensionH61Spinner_%d',i);
                app.(SpinnerName).Enable = 'on';
                SpinnerName = sprintf('AveragedSoundPressureLevelSpinner_%d',i);
                app.(SpinnerName).Enable = 'on';
                
                %Security
                SpinnerName = sprintf('CrashLengthSpinner_%d',i);
                app.(SpinnerName).Enable = 'on';
                SpinnerName = sprintf('Elasticityfrom60kmhto100kmhSpinner_%d',i);
                app.(SpinnerName).Enable = 'on';
                SpinnerName = sprintf('Disengagementper100000kmSpinner_%d',i);
                app.(SpinnerName).Enable = 'on';
                
                %Usability
                SpinnerName = sprintf('NumberofSeatsSpinner_%d',i);
                app.(SpinnerName).Enable = 'on';
                SpinnerName = sprintf('StorageVolumeSpinner_%d',i);
                app.(SpinnerName).Enable = 'on';
                SpinnerName = sprintf('RangeSpinner_%d',i);
                app.(SpinnerName).Enable = 'on';
                SpinnerName = sprintf('AcquisitionCostsSpinner_%d',i);
                app.(SpinnerName).Enable = 'on';
                SpinnerName = sprintf('ProductionEmissionsSpinner_%d',i);
                app.(SpinnerName).Enable = 'on';
                SpinnerName = sprintf('GroundClearanceSpinner_%d',i);
                app.(SpinnerName).Enable = 'on';
                
                %Driving Style
                SpinnerName = sprintf('AccelerationSpinner_%d',i);
                app.(SpinnerName).Enable = 'on';
                SpinnerName = sprintf('JerkSpinner_%d',i);
                app.(SpinnerName).Enable = 'on';
                SpinnerName = sprintf('HomeostaticRiskPerceptionSpinner_%d',i);
                app.(SpinnerName).Enable = 'on';
                SpinnerName = sprintf('FastnessSpinner_%d',i);
                app.(SpinnerName).Enable = 'on';
                SpinnerName = sprintf('Consumptionper100kmSpinner_%d',i);
                app.(SpinnerName).Enable = 'on';
                
                %Luxury
                SpinnerName = sprintf('WheelDiameterSpinner_%d',i);
                app.(SpinnerName).Enable = 'on';
                SpinnerName = sprintf('ScreenAreaperSeatSpinner_%d',i);
                app.(SpinnerName).Enable = 'on';
                
            end
            
            
            app.SaveButton.Enable = 'on';
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        % Disables every Spinner 
        function Disabler(app)
            for i = 1:7
                %Dynamics
                SpinnerName = sprintf('TopSpeedSpinner_%d',i);
                app.(SpinnerName).Enable = 'off';
                SpinnerName = sprintf('Accelerationfrom0kmhto100kmhSpinner_%d',i);
                app.(SpinnerName).Enable = 'off';
                SpinnerName = sprintf('HeightoftheCenterofGravitySpinner_%d',i);
                app.(SpinnerName).Enable = 'off';
                SpinnerName = sprintf('VerticalAccelerationSpinner_%d',i);
                app.(SpinnerName).Enable = 'off';
                SpinnerName = sprintf('TurningCircleSpinner_%d',i);
                app.(SpinnerName).Enable = 'off';
                
                %Ergonomics
                SpinnerName = sprintf('SillHeightSpinner_%d',i);
                app.(SpinnerName).Enable = 'off';
                SpinnerName = sprintf('ClearanceHeightSpinner_%d',i);
                app.(SpinnerName).Enable = 'off';
                SpinnerName = sprintf('DoorOpeningWidthperSeatSpinner_%d',i);
                app.(SpinnerName).Enable = 'off';
                SpinnerName = sprintf('InteriorDimensionL53Spinner_%d',i);
                app.(SpinnerName).Enable = 'off';
                SpinnerName = sprintf('InteriorDimensionW3Spinner_%d',i);
                app.(SpinnerName).Enable = 'off';
                SpinnerName = sprintf('InteriorDimensionH61Spinner_%d',i);
                app.(SpinnerName).Enable = 'off';
                SpinnerName = sprintf('AveragedSoundPressureLevelSpinner_%d',i);
                app.(SpinnerName).Enable = 'off';
                
                %Security
                SpinnerName = sprintf('CrashLengthSpinner_%d',i);
                app.(SpinnerName).Enable = 'off';
                SpinnerName = sprintf('Elasticityfrom60kmhto100kmhSpinner_%d',i);
                app.(SpinnerName).Enable = 'off';
                SpinnerName = sprintf('Disengagementper100000kmSpinner_%d',i);
                app.(SpinnerName).Enable = 'off';
                
                %Usability
                SpinnerName = sprintf('NumberofSeatsSpinner_%d',i);
                app.(SpinnerName).Enable = 'off';
                SpinnerName = sprintf('StorageVolumeSpinner_%d',i);
                app.(SpinnerName).Enable = 'off';
                SpinnerName = sprintf('RangeSpinner_%d',i);
                app.(SpinnerName).Enable = 'off';
                SpinnerName = sprintf('AcquisitionCostsSpinner_%d',i);
                app.(SpinnerName).Enable = 'off';
                SpinnerName = sprintf('ProductionEmissionsSpinner_%d',i);
                app.(SpinnerName).Enable = 'off';
                SpinnerName = sprintf('GroundClearanceSpinner_%d',i);
                app.(SpinnerName).Enable = 'off';
                
                %Driving Style
                SpinnerName = sprintf('AccelerationSpinner_%d',i);
                app.(SpinnerName).Enable = 'off';
                SpinnerName = sprintf('JerkSpinner_%d',i);
                app.(SpinnerName).Enable = 'off';
                SpinnerName = sprintf('HomeostaticRiskPerceptionSpinner_%d',i);
                app.(SpinnerName).Enable = 'off';
                SpinnerName = sprintf('FastnessSpinner_%d',i);
                app.(SpinnerName).Enable = 'off';
                SpinnerName = sprintf('Consumptionper100kmSpinner_%d',i);
                app.(SpinnerName).Enable = 'off';
                
                %Luxury
                SpinnerName = sprintf('WheelDiameterSpinner_%d',i);
                app.(SpinnerName).Enable = 'off';
                SpinnerName = sprintf('ScreenAreaperSeatSpinner_%d',i);
                app.(SpinnerName).Enable = 'off';
            end
            
            app.SaveButton.Enable = 'off';
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        % Connect the Values of the Spinners (same TP of the same VC) and set the Limits, the Step (30 as one step is 0,2 customer value) and the rounding
        function ConnectValues(app,showedTechnicalProp, showedDerivatives)
            for i = 1:height(showedTechnicalProp)
                %Dynamics
                SpinnerName = sprintf('TopSpeedSpinner_%d',i);
                set(app.(SpinnerName), 'Value', showedTechnicalProp (i,1), 'Limits', [app.MinMaxValues(1,1), app.MinMaxValues(2,1)], 'Step', (app.MinMaxValues(2,1) - app.MinMaxValues(1,1))/30, 'ValueDisplayFormat', '%.0f');                
                SpinnerName = sprintf('Accelerationfrom0kmhto100kmhSpinner_%d',i);
                set(app.(SpinnerName), 'Value', showedTechnicalProp (i,2), 'Limits', [app.MinMaxValues(1,2), app.MinMaxValues(2,2)], 'Step', (app.MinMaxValues(2,2) - app.MinMaxValues(1,2))/30, 'ValueDisplayFormat', '%.1f');
                SpinnerName = sprintf('HeightoftheCenterofGravitySpinner_%d',i);
                set(app.(SpinnerName), 'Value', showedTechnicalProp (i,3), 'Limits', [app.MinMaxValues(1,3), app.MinMaxValues(2,3)], 'Step', (app.MinMaxValues(2,3) - app.MinMaxValues(1,3))/30, 'ValueDisplayFormat', '%.0f');
                SpinnerName = sprintf('VerticalAccelerationSpinner_%d',i);
                set(app.(SpinnerName), 'Value', showedTechnicalProp (i,4), 'Limits', [app.MinMaxValues(1,4), app.MinMaxValues(2,4)], 'Step', (app.MinMaxValues(2,4) - app.MinMaxValues(1,4))/30, 'ValueDisplayFormat', '%.2f');
                SpinnerName = sprintf('TurningCircleSpinner_%d',i);
                set(app.(SpinnerName), 'Value', showedTechnicalProp (i,5), 'Limits', [app.MinMaxValues(1,5), app.MinMaxValues(2,5)], 'Step', (app.MinMaxValues(2,5) - app.MinMaxValues(1,5))/30, 'ValueDisplayFormat', '%.1f');
                
                %Ergonomics
                SpinnerName = sprintf('SillHeightSpinner_%d',i);
                set(app.(SpinnerName), 'Value', showedTechnicalProp (i,6), 'Limits', [app.MinMaxValues(1,6), app.MinMaxValues(2,6)], 'Step', (app.MinMaxValues(2,6) - app.MinMaxValues(1,6))/30, 'ValueDisplayFormat', '%.0f');
                SpinnerName = sprintf('ClearanceHeightSpinner_%d',i);
                set(app.(SpinnerName), 'Value', showedTechnicalProp (i,7), 'Limits', [app.MinMaxValues(1,7), app.MinMaxValues(2,7)], 'Step', (app.MinMaxValues(2,7) - app.MinMaxValues(1,7))/30, 'ValueDisplayFormat', '%.0f');
                SpinnerName = sprintf('DoorOpeningWidthperSeatSpinner_%d',i);
                set(app.(SpinnerName), 'Value', showedTechnicalProp (i,8), 'Limits', [app.MinMaxValues(1,8), app.MinMaxValues(2,8)], 'Step', (app.MinMaxValues(2,8) - app.MinMaxValues(1,8))/30, 'ValueDisplayFormat', '%.0f');
                SpinnerName = sprintf('InteriorDimensionL53Spinner_%d',i);
                set(app.(SpinnerName), 'Value', showedTechnicalProp (i,9), 'Limits', [app.MinMaxValues(1,9), app.MinMaxValues(2,9)], 'Step', (app.MinMaxValues(2,9) - app.MinMaxValues(1,9))/30, 'ValueDisplayFormat', '%.0f');
                SpinnerName = sprintf('InteriorDimensionW3Spinner_%d',i);
                set(app.(SpinnerName), 'Value', showedTechnicalProp (i,10), 'Limits', [app.MinMaxValues(1,10), app.MinMaxValues(2,10)], 'Step', (app.MinMaxValues(2,10) - app.MinMaxValues(1,10))/30, 'ValueDisplayFormat', '%.0f');
                SpinnerName = sprintf('InteriorDimensionH61Spinner_%d',i);
                set(app.(SpinnerName), 'Value', showedTechnicalProp (i,11), 'Limits', [app.MinMaxValues(1,11), app.MinMaxValues(2,11)], 'Step', (app.MinMaxValues(2,11) - app.MinMaxValues(1,11))/30, 'ValueDisplayFormat', '%.0f');
                SpinnerName = sprintf('AveragedSoundPressureLevelSpinner_%d',i);
                set(app.(SpinnerName), 'Value', showedTechnicalProp (i,12), 'Limits', [app.MinMaxValues(1,12), app.MinMaxValues(2,12)], 'Step', (app.MinMaxValues(2,12) - app.MinMaxValues(1,12))/30, 'ValueDisplayFormat', '%.0f');
                
                %Security
                SpinnerName = sprintf('CrashLengthSpinner_%d',i);
                set(app.(SpinnerName), 'Value', showedTechnicalProp (i,13), 'Limits', [app.MinMaxValues(1,13), app.MinMaxValues(2,13)], 'Step', (app.MinMaxValues(2,13) - app.MinMaxValues(1,13))/30, 'ValueDisplayFormat', '%.0f');
                SpinnerName = sprintf('Disengagementper100000kmSpinner_%d',i);
                set(app.(SpinnerName), 'Value', showedTechnicalProp (i,14), 'Limits', [app.MinMaxValues(1,14), app.MinMaxValues(2,14)], 'Step', (app.MinMaxValues(2,14) - app.MinMaxValues(1,14))/30, 'ValueDisplayFormat', '%.0f');
                SpinnerName = sprintf('Elasticityfrom60kmhto100kmhSpinner_%d',i);
                set(app.(SpinnerName), 'Limits', [app.MinMaxValues(1,15), app.MinMaxValues(2,15)], 'Value', showedTechnicalProp (i,15), 'Step', (app.MinMaxValues(2,15) - app.MinMaxValues(1,15))/30, 'ValueDisplayFormat', '%.1f');
                                
                %Usability
                SpinnerName = sprintf('GroundClearanceSpinner_%d',i);
                set(app.(SpinnerName), 'Value', showedTechnicalProp (i,16), 'Limits', [app.MinMaxValues(1,16), app.MinMaxValues(2,16)], 'Step', (app.MinMaxValues(2,16) - app.MinMaxValues(1,16))/30, 'ValueDisplayFormat', '%.0f');
                
                SpinnerName = sprintf('StorageVolumeSpinner_%d',i);
                % Different Values for load vehicle and passenger vehicle
                if showedDerivatives(i,1) <= 5
                    set(app.(SpinnerName), 'Limits', [app.MinMaxValues(1,17), app.MinMaxValues(2,17)], 'Value', showedTechnicalProp (i,17), 'Step', (app.MinMaxValues(2,17) - app.MinMaxValues(1,17))/30, 'ValueDisplayFormat', '%.0f');
                else
                    set(app.(SpinnerName), 'Limits', [app.MinMaxValues(1,18), app.MinMaxValues(2,18)], 'Value', showedTechnicalProp (i,18), 'Step', (app.MinMaxValues(2,18) - app.MinMaxValues(1,18))/30, 'ValueDisplayFormat', '%.0f');
                end                
                
                SpinnerName = sprintf('RangeSpinner_%d',i);
                set(app.(SpinnerName), 'Value', showedTechnicalProp (i,19), 'Limits', [app.MinMaxValues(1,19), app.MinMaxValues(2,19)], 'Step', (app.MinMaxValues(2,19) - app.MinMaxValues(1,19))/30, 'ValueDisplayFormat', '%.0f');
                SpinnerName = sprintf('AcquisitionCostsSpinner_%d',i);
                set(app.(SpinnerName), 'Value', showedTechnicalProp (i,20), 'Limits', [app.MinMaxValues(1,20), app.MinMaxValues(2,20)], 'Step', (app.MinMaxValues(2,20) - app.MinMaxValues(1,20))/30, 'ValueDisplayFormat', '%.0f');
                SpinnerName = sprintf('ProductionEmissionsSpinner_%d',i);
                set(app.(SpinnerName), 'Limits', [app.MinMaxValues(1,21), app.MinMaxValues(2,21)], 'Value', showedTechnicalProp (i,21), 'Step', (app.MinMaxValues(2,21) - app.MinMaxValues(1,21))/30, 'ValueDisplayFormat', '%.1f');
                SpinnerName = sprintf('NumberofSeatsSpinner_%d',i);
                set(app.(SpinnerName), 'Value', showedTechnicalProp (i,22), 'Limits', [app.MinMaxValues(1,22), app.MinMaxValues(2,22)], 'Step', (app.MinMaxValues(2,22) - app.MinMaxValues(1,22))/30, 'ValueDisplayFormat', '%.0f');
                                
                
                %Driving Style
                SpinnerName = sprintf('JerkSpinner_%d',i);
                set(app.(SpinnerName), 'Value', showedTechnicalProp (i,23), 'Limits', [app.MinMaxValues(1,23), app.MinMaxValues(2,23)], 'Step', (app.MinMaxValues(2,23) - app.MinMaxValues(1,23))/30, 'ValueDisplayFormat', '%.2f');
                SpinnerName = sprintf('AccelerationSpinner_%d',i);
                set(app.(SpinnerName), 'Value', showedTechnicalProp (i,24), 'Limits', [app.MinMaxValues(1,24), app.MinMaxValues(2,24)], 'Step', (app.MinMaxValues(2,24) - app.MinMaxValues(1,24))/30, 'ValueDisplayFormat', '%.2f');
                SpinnerName = sprintf('HomeostaticRiskPerceptionSpinner_%d',i);
                set(app.(SpinnerName), 'Value', showedTechnicalProp (i,25), 'Limits', [app.MinMaxValues(1,25), app.MinMaxValues(2,25)], 'Step', (app.MinMaxValues(2,25) - app.MinMaxValues(1,25))/30, 'ValueDisplayFormat', '%.2f');
                SpinnerName = sprintf('FastnessSpinner_%d',i);
                set(app.(SpinnerName), 'Value', showedTechnicalProp (i,26), 'Limits', [app.MinMaxValues(1,26), app.MinMaxValues(2,26)], 'Step', (app.MinMaxValues(2,26) - app.MinMaxValues(1,26))/30, 'ValueDisplayFormat', '%.2f');
                SpinnerName = sprintf('Consumptionper100kmSpinner_%d',i);
                set(app.(SpinnerName), 'Value', showedTechnicalProp (i,27), 'Limits', [app.MinMaxValues(1,27), app.MinMaxValues(2,27)], 'Step', (app.MinMaxValues(2,27) - app.MinMaxValues(1,27))/30, 'ValueDisplayFormat', '%.1f');
                
                %Luxury
                SpinnerName = sprintf('ScreenAreaperSeatSpinner_%d',i);
                set(app.(SpinnerName), 'Value', showedTechnicalProp (i,28), 'Limits', [app.MinMaxValues(1,28), app.MinMaxValues(2,28)], 'Step', (app.MinMaxValues(2,28) - app.MinMaxValues(1,28))/30, 'ValueDisplayFormat', '%.0f');
                SpinnerName = sprintf('WheelDiameterSpinner_%d',i);
                set(app.(SpinnerName), 'Value', showedTechnicalProp (i,29), 'Limits', [app.MinMaxValues(1,29), app.MinMaxValues(2,29)], 'Step', (app.MinMaxValues(2,29) - app.MinMaxValues(1,29))/30, 'ValueDisplayFormat', '%.0f');
            end
        end        
        %__________________________________________________________________
        
        %__________________________________________________________________
        % read the values of the spinners
        function readspinnervalues(app)
            % get ticked VCs
            [tickMatrixVehicles] = checkBoxChecker(app);
            
            %Creating vector with numbers of ticked vehicles
            tickedVCs = zeros(nnz(tickMatrixVehicles),1);
            [numberVCs,~] = size(app.vehicleConceptsTP);
                    
            num = 1;
            for i=1:numberVCs
                   if tickMatrixVehicles(i) == 1
                       tickedVCs(num) = i;
                       num = num + 1;
                   end
            end
            
            % Differ between the Views allTPforoneVC and TPforallVC
            switch app.DisplayOptionsButtonGroup.SelectedObject
                
                % View: allTPforoneVC
                case app.allTPforoneVCButton
                    
                    % Dynamics    
                    app.vehicleConceptsTP(tickedVCs(1), 1) = round(app.TopSpeedSpinner_1.Value);
                    app.vehicleConceptsTP(tickedVCs(1), 2) = round(app.Accelerationfrom0kmhto100kmhSpinner_1.Value,1);
                    app.vehicleConceptsTP(tickedVCs(1), 3) = round(app.HeightoftheCenterofGravitySpinner_1.Value, 1);
                    app.vehicleConceptsTP(tickedVCs(1), 4) = round(app.VerticalAccelerationSpinner_1.Value, 2);
                    app.vehicleConceptsTP(tickedVCs(1), 5) = round(app.TurningCircleSpinner_1.Value,1);
                
                    %Ergonomics
                    app.vehicleConceptsTP(tickedVCs(1), 6) = round(app.SillHeightSpinner_1.Value);
                    app.vehicleConceptsTP(tickedVCs(1), 7) = round(app.ClearanceHeightSpinner_1.Value);
                    app.vehicleConceptsTP(tickedVCs(1), 8) = round(app.DoorOpeningWidthperSeatSpinner_1.Value, -1);
                    app.vehicleConceptsTP(tickedVCs(1), 9) = round(app.InteriorDimensionL53Spinner_1.Value, -1);
                    app.vehicleConceptsTP(tickedVCs(1), 10) = round(app.InteriorDimensionW3Spinner_1.Value, -1);
                    app.vehicleConceptsTP(tickedVCs(1), 11) = round(app.InteriorDimensionH61Spinner_1.Value);
                    app.vehicleConceptsTP(tickedVCs(1), 12) = round(app.AveragedSoundPressureLevelSpinner_1.Value);
                
                    %Security
                    app.vehicleConceptsTP(tickedVCs(1), 13) = round(app.CrashLengthSpinner_1.Value, -1);
                    app.vehicleConceptsTP(tickedVCs(1), 14) = round(app.Disengagementper100000kmSpinner_1.Value);
                    app.vehicleConceptsTP(tickedVCs(1), 15) = round(app.Elasticityfrom60kmhto100kmhSpinner_1.Value, 1);                
                                
                    %Usability
                    app.vehicleConceptsTP(tickedVCs(1), 16) = round(app.GroundClearanceSpinner_1.Value);                    
                    % Different Values for load vehicle and passenger vehicle
                    if app.Derivatives(tickedVCs(1),6) <= 5
                        app.vehicleConceptsTP(tickedVCs(1), 17) = round(app.StorageVolumeSpinner_1.Value);
                    else
                        app.vehicleConceptsTP(tickedVCs(1), 18) = round(app.StorageVolumeSpinner_1.Value, -1);
                    end               
                
                    app.vehicleConceptsTP(tickedVCs(1), 19) = round(app.RangeSpinner_1.Value);
                    app.vehicleConceptsTP(tickedVCs(1), 20) = round(app.AcquisitionCostsSpinner_1.Value, -2);
                    app.vehicleConceptsTP(tickedVCs(1), 21) = round(app.ProductionEmissionsSpinner_1.Value, 1);
                    app.vehicleConceptsTP(tickedVCs(1), 22) = round(app.NumberofSeatsSpinner_1.Value);
                
                    %Driving Style
                    app.vehicleConceptsTP(tickedVCs(1), 23) = round(app.JerkSpinner_1.Value);
                    app.vehicleConceptsTP(tickedVCs(1), 24) = round(app.AccelerationSpinner_1.Value);
                    app.vehicleConceptsTP(tickedVCs(1), 25) = round(app.HomeostaticRiskPerceptionSpinner_1.Value);
                    app.vehicleConceptsTP(tickedVCs(1), 26) = round(app.FastnessSpinner_1.Value);
                    app.vehicleConceptsTP(tickedVCs(1), 27) = round(app.Consumptionper100kmSpinner_1.Value, 1);
                                    
                    %Luxury
                    app.vehicleConceptsTP(tickedVCs(1), 28) = round(app.ScreenAreaperSeatSpinner_1.Value,-1);
                    app.vehicleConceptsTP(tickedVCs(1), 29) = round(app.WheelDiameterSpinner_1.Value);
                    
                % View: TPforallVC    
                case app.TPforallVCButton
                    
                    for i=1:height(tickedVCs)
                        % Dynamics    
                        SpinnerName = sprintf('TopSpeedSpinner_%d',i+1); app.vehicleConceptsTP(tickedVCs(i), 1) = round(app.(SpinnerName).Value);
                        SpinnerName = sprintf('Accelerationfrom0kmhto100kmhSpinner_%d',i+1); app.vehicleConceptsTP(tickedVCs(i), 2) = round(app.(SpinnerName).Value, 1);
                        SpinnerName = sprintf('HeightoftheCenterofGravitySpinner_%d',i+1); app.vehicleConceptsTP(tickedVCs(i), 3) = round(app.(SpinnerName).Value);
                        SpinnerName = sprintf('VerticalAccelerationSpinner_%d',i+1); app.vehicleConceptsTP(tickedVCs(i), 4) = round(app.(SpinnerName).Value, 2);
                        SpinnerName = sprintf('TurningCircleSpinner_%d',i+1); app.vehicleConceptsTP(tickedVCs(i), 5) = round(app.(SpinnerName).Value, 1);
                
                        %Ergonomics
                        SpinnerName = sprintf('SillHeightSpinner_%d',i+1); app.vehicleConceptsTP(tickedVCs(i), 6) = round(app.(SpinnerName).Value);
                        SpinnerName = sprintf('ClearanceHeightSpinner_%d',i+1); app.vehicleConceptsTP(tickedVCs(i), 7) = round(app.(SpinnerName).Value);
                        SpinnerName = sprintf('DoorOpeningWidthperSeatSpinner_%d',i+1); app.vehicleConceptsTP(tickedVCs(i), 8) = round(app.(SpinnerName).Value, -1);
                        SpinnerName = sprintf('InteriorDimensionL53Spinner_%d',i+1); app.vehicleConceptsTP(tickedVCs(i), 9) = round(app.(SpinnerName).Value, -1);
                        SpinnerName = sprintf('InteriorDimensionW3Spinner_%d',i+1); app.vehicleConceptsTP(tickedVCs(i), 10) = round(app.(SpinnerName).Value, -1);
                        SpinnerName = sprintf('InteriorDimensionH61Spinner_%d',i+1); app.vehicleConceptsTP(tickedVCs(i), 11) = round(app.(SpinnerName).Value);
                        SpinnerName = sprintf('AveragedSoundPressureLevelSpinner_%d',i+1); app.vehicleConceptsTP(tickedVCs(i), 12) = round(app.(SpinnerName).Value);
                
                        %Security
                        SpinnerName = sprintf('CrashLengthSpinner_%d',i+1); app.vehicleConceptsTP(tickedVCs(i), 13) = round(app.(SpinnerName).Value, -1);
                        SpinnerName = sprintf('Disengagementper100000kmSpinner_%d',i+1); app.vehicleConceptsTP(tickedVCs(i), 14) = round(app.(SpinnerName).Value);
                        SpinnerName = sprintf('Elasticityfrom60kmhto100kmhSpinner_%d',i+1); app.vehicleConceptsTP(tickedVCs(i), 15) = round(app.(SpinnerName).Value, 1);
                                
                        %Usability
                        SpinnerName = sprintf('GroundClearanceSpinner_%d',i+1); app.vehicleConceptsTP(tickedVCs(i), 16) = round(app.(SpinnerName).Value);
                        % Different Values for load vehicle and passenger vehicle
                        SpinnerName = sprintf('StorageVolumeSpinner_%d',i+1); 
                        if app.Derivatives(tickedVCs(1),6) <= 5
                            app.vehicleConceptsTP(tickedVCs(i), 17) = round(app.(SpinnerName).Value);
                        else
                            app.vehicleConceptsTP(tickedVCs(i), 18) = round(app.(SpinnerName).Value, -1);
                        end               
                
                        SpinnerName = sprintf('RangeSpinner_%d',i+1); app.vehicleConceptsTP(tickedVCs(i), 19) = round(app.(SpinnerName).Value);
                        SpinnerName = sprintf('AcquisitionCostsSpinner_%d',i+1); app.vehicleConceptsTP(tickedVCs(i), 20) = round(app.(SpinnerName).Value, -2);
                        SpinnerName = sprintf('ProductionEmissionsSpinner_%d',i+1); app.vehicleConceptsTP(tickedVCs(i), 21) = round(app.(SpinnerName).Value, 1);
                        SpinnerName = sprintf('NumberofSeatsSpinner_%d',i+1); app.vehicleConceptsTP(tickedVCs(i), 22) = round(app.(SpinnerName).Value);
                
                        %Driving Style
                        SpinnerName = sprintf('JerkSpinner_%d',i+1); app.vehicleConceptsTP(tickedVCs(i), 23) = round(app.(SpinnerName).Value);
                        SpinnerName = sprintf('AccelerationSpinner_%d',i+1); app.vehicleConceptsTP(tickedVCs(i), 24) = round(app.(SpinnerName).Value);
                        SpinnerName = sprintf('HomeostaticRiskPerceptionSpinner_%d',i+1); app.vehicleConceptsTP(tickedVCs(i), 25) = round(app.(SpinnerName).Value);
                        SpinnerName = sprintf('FastnessSpinner_%d',i+1); app.vehicleConceptsTP(tickedVCs(i), 26) = round(app.(SpinnerName).Value);
                        SpinnerName = sprintf('Consumptionper100kmSpinner_%d',i+1); app.vehicleConceptsTP(tickedVCs(i), 27) = round(app.(SpinnerName).Value, 1);
                        SpinnerName = sprintf('NumberofdifferentDSSpinner_%d',i+1); app.vehicleConceptsTP(tickedVCs(i), 28) = round(app.(SpinnerName).Value);
                
                        %Luxury
                        SpinnerName = sprintf('NumberofdifferentDSSpinner_%d',i+1); app.vehicleConceptsTP(tickedVCs(i), 29) = round(app.(SpinnerName).Value, -1);       
                        SpinnerName = sprintf('WheelDiameterSpinner_%d',i+1); app.vehicleConceptsTP(tickedVCs(i), 30) = round(app.(SpinnerName).Value);
                        
                    end
            end
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        % Plot the corrleations functions
        

        function plotcorrelations(app)
            % make all UIAxes invisible
            for i = 1:7
                UIAxesName = sprintf('UIAxes_%d',i);
                app.(UIAxesName).Visible = 'off';
                cla(app.(UIAxesName));                
            end
            
            % set titel and xAxeslabel
            titlestring = {'Longitunal Dynamics'; 'Longitunal Dynamics'; 'Lateral Dynamics'; 'Vertical Dynamics'; 'Maneuverability';...
                'Boarding Comfort'; 'Boarding Comfort'; 'Boarding Time'; 'Leg Room'; 'Shoulders Room'; 'Head Room';'Interior Acoustics';...
                'Passive Safety';'Quality of Automation'; 'Active Safety';   
                'Bad Road Capability ';'Luggage Space';'Luggage Space';'Radius of Movement'; 'Economy'; 'Ecology';'Passengers';   
                'DS: Comfort';'DS: Comfort';'DS: Safety';'DS: Time Potential'; 'DS: Consumption'; ...
                'Built-In Infotainment'; 'Exterior Design'};
            xAxesstring = {'Top Speed [km/h]'; 'Acceleration from 0km/h to 100km/h [s]'; 'Height of the Center of Gravity [mm]'; 'Vertical Acceleration [m/s^2]'; 'Turning Circle [m]';...
                'Sill Height [mm]'; 'Clearance Height [mm]'; 'Door Opening Width per Seat [mm]'; 'Interior Dimension L53 [mm]'; 'Interior Dimension W3 [mm]'; 'Interior Dimension H61 [mm]';'Averaged Sound Pressure Level [dB]';...
                'Crash Length [mm]';'Disengagement per 100.000km [-]'; 'Elasticity from 60km/h to 100km/h [s]';   
                'Ground Clearance [mm]';'Storage Volume (Passenger Vehicle) [l]';'Storage Volume (Load Vehicle) [l]';'Range [km]'; 'Acquisition Costs [€]'; 'Production Emissions [tCO_2-eq.]';'Number of Seats [-]';   
                'Jerk [m/s^3]';'Acceleration [m/s^2]';'Homeostatic Risk Perception [-]';'Fastness [-]'; 'Consumption [kWh/100km]';...
                'Screen Area per Seat [cm^2]'; 'Wheel Diameter [mm]'};
            
            
            % select needed Correlation functions
            switch app.ButtonGroupVC_3.SelectedObject
                
                % Dynamics
                case app.DynamicsButton_3
                    for i = 1:5
                        num = i;
                        % make needed UIAxes visible
                        UIAxesName = sprintf('UIAxes_%d',i);
                        app.(UIAxesName).Visible = 'on';
                        % title and xAxes label
                        app.(UIAxesName).XLabel.String = xAxesstring{num,1};
                        app.(UIAxesName).Title.String = titlestring{num, 1};
                        
                        % plot functions
                        yline(app.(UIAxesName), 5,'r', 'min', 'LineWidth',0.25);
                        fplot(app.(UIAxesName), str2func(app.CorrelationFunctions{1,num}), [app.MinMaxValues(1,num), app.MinMaxValues(2,num)]);
                    end 
                case app.ErgonomicsButton_3
                    for i = 1:7
                        num = i+5;
                        % make needed UIAxes visible
                        UIAxesName = sprintf('UIAxes_%d',i);
                        app.(UIAxesName).Visible = 'on';
                        % title and xAxes label
                        app.(UIAxesName).XLabel.String = xAxesstring{num,1};
                        app.(UIAxesName).Title.String = titlestring{num, 1};
                        
                        % plot functions
                        yline(app.(UIAxesName), 5,'r', 'min', 'LineWidth',0.25);
                        fplot(app.(UIAxesName), str2func(app.CorrelationFunctions{1,num}), [app.MinMaxValues(1,num), app.MinMaxValues(2,num)]);                       
                    end                   
                case app.SecurityButton_3
                    for i = 1:3
                        num = i+12;
                        % make needed UIAxes visible
                        UIAxesName = sprintf('UIAxes_%d',i);
                        app.(UIAxesName).Visible = 'on';
                        % title and xAxes label
                        app.(UIAxesName).XLabel.String = xAxesstring{num,1};
                        app.(UIAxesName).Title.String = titlestring{num, 1};
                        
                        % plot functions
                        yline(app.(UIAxesName), 5,'r', 'min', 'LineWidth',0.25);
                        fplot(app.(UIAxesName), str2func(app.CorrelationFunctions{1,num}), [app.MinMaxValues(1,num), app.MinMaxValues(2,num)]);                       
                    end                   
                case app.UsabilityButton_3
                    for i = 1:7
                        num = i+15;
                        % make needed UIAxes visible
                        UIAxesName = sprintf('UIAxes_%d',i);
                        app.(UIAxesName).Visible = 'on';
                        % title and xAxes label
                        app.(UIAxesName).XLabel.String = xAxesstring{num,1};
                        app.(UIAxesName).Title.String = titlestring{num, 1};
                        
                        % plot functions
                        yline(app.(UIAxesName), 5,'r', 'min', 'LineWidth',0.25);
                        fplot(app.(UIAxesName), str2func(app.CorrelationFunctions{1,num}), [app.MinMaxValues(1,num), app.MinMaxValues(2,num)]);                       
                    end                   
                case app.DrivingStyleButton_3
                    for i = 1:5
                        num = i+22;
                        % make needed UIAxes visible
                        UIAxesName = sprintf('UIAxes_%d',i);
                        app.(UIAxesName).Visible = 'on';
                        % title and xAxes label
                        app.(UIAxesName).XLabel.String = xAxesstring{num,1};
                        app.(UIAxesName).Title.String = titlestring{num, 1};
                        
                        % plot functions
                        yline(app.(UIAxesName), 5,'r', 'min', 'LineWidth',0.25);
                        fplot(app.(UIAxesName), str2func(app.CorrelationFunctions{1,num}), [app.MinMaxValues(1,num), app.MinMaxValues(2,num)]);                       
                    end                   
                case app.LuxuryButton_3
                    for i = 1:2
                        num = i+27;
                        % make needed UIAxes visible
                        UIAxesName = sprintf('UIAxes_%d',i);
                        app.(UIAxesName).Visible = 'on';
                        % title and xAxes label
                        app.(UIAxesName).XLabel.String = xAxesstring{num,1};
                        app.(UIAxesName).Title.String = titlestring{num, 1};
                        
                        % plot functions
                        yline(app.(UIAxesName), 5,'r', 'min', 'LineWidth',0.25);
                        fplot(app.(UIAxesName), str2func(app.CorrelationFunctions{1,num}), [app.MinMaxValues(1,num), app.MinMaxValues(2,num)]);                       
                    end   
           end
        
        end
        
        %__________________________________________________________________
        
        %__________________________________________________________________
        % plot the current selected vehicle concepts into corrleations functions
        function plotVCintoCorrelations(app)
            
            % select current selected VCs
            [tickMatrixVehicles] = checkBoxChecker(app);
            
            for i=1:height(tickMatrixVehicles)
                if tickMatrixVehicles(i) == 1
                    LampName = sprintf('VCLamp%d', i);
                    
                    switch app.ButtonGroupVC_3.SelectedObject
                        
                        case app.DynamicsButton_3
                            for j = 1:5
                                num = j;
                                % make needed UIAxes visible
                                UIAxesName = sprintf('UIAxes_%d',j);
                                
                                % Correlation Function of the TP
                                corf = str2func(app.CorrelationFunctions{1,num});
                                % corresponding CRP of the TP
                                CRP = corf(app.vehicleConceptsTP(i,num));
                                                                
                                plot(app.(UIAxesName), app.vehicleConceptsTP(i,num), CRP,'*','Color', app.(LampName).Color, 'MarkerSize', 14);                            
                            end
                            
                        case app.ErgonomicsButton_3
                            for j = 1:7
                                num = j+5;
                                % make needed UIAxes visible
                                UIAxesName = sprintf('UIAxes_%d',j);
                                
                                % Correlation Function of the TP
                                corf = str2func(app.CorrelationFunctions{1,num});
                                % corresponding CRP of the TP
                                CRP = corf(app.vehicleConceptsTP(i,num));
                                                                
                                plot(app.(UIAxesName), app.vehicleConceptsTP(i,num), CRP,'*','Color', app.(LampName).Color, 'MarkerSize', 14);                            
                            end
                        
                        case app.SecurityButton_3
                            for j = 1:3
                                num = j+12;
                                % make needed UIAxes visible
                                UIAxesName = sprintf('UIAxes_%d',j);
                                
                                % Correlation Function of the TP
                                corf = str2func(app.CorrelationFunctions{1,num});
                                % corresponding CRP of the TP
                                CRP = corf(app.vehicleConceptsTP(i,num));
                                                                
                                plot(app.(UIAxesName), app.vehicleConceptsTP(i,num), CRP,'*','Color', app.(LampName).Color, 'MarkerSize', 14);                            
                            end
                        
                        case app.UsabilityButton_3
                            for j = 1:7
                                num = j+15;
                                % make needed UIAxes visible
                                UIAxesName = sprintf('UIAxes_%d',j);
                                
                                % differ between passenger and load vehicle fpr the lugguage room
                                if num == 17
                                    if app.Derivatives(i,6) <= 5
                                        % Correlation Function of the TP
                                        corf = str2func(app.CorrelationFunctions{1,num});
                                        % corresponding CRP of the TP
                                        CRP = corf(app.vehicleConceptsTP(i,num));
                                                                
                                        plot(app.(UIAxesName), app.vehicleConceptsTP(i,num), CRP,'*','Color', app.(LampName).Color, 'MarkerSize', 14);                                        
                                    end                                                                  
                                elseif num == 18
                                    if app.Derivatives(i,6) > 5
                                        % Correlation Function of the TP
                                        corf = str2func(app.CorrelationFunctions{1,num});
                                        % corresponding CRP of the TP
                                        CRP = corf(app.vehicleConceptsTP(i,num));
                                                                
                                        plot(app.(UIAxesName), app.vehicleConceptsTP(i,num), CRP,'*','Color', app.(LampName).Color, 'MarkerSize', 14);                                        
                                    end
                                else
                                    % Correlation Function of the TP
                                    corf = str2func(app.CorrelationFunctions{1,num});
                                    % corresponding CRP of the TP
                                    CRP = corf(app.vehicleConceptsTP(i,num));
                                                                
                                    plot(app.(UIAxesName), app.vehicleConceptsTP(i,num), CRP,'*','Color', app.(LampName).Color, 'MarkerSize', 14);                                 
                                end                    
                            end
                        
                        case app.DrivingStyleButton_3
                            for j = 1:5
                                num = j+22;
                                % make needed UIAxes visible
                                UIAxesName = sprintf('UIAxes_%d',j);
                                
                                % Correlation Function of the TP
                                corf = str2func(app.CorrelationFunctions{1,num});
                                % corresponding CRP of the TP
                                CRP = corf(app.vehicleConceptsTP(i,num));
                                                                
                                plot(app.(UIAxesName), app.vehicleConceptsTP(i,num), CRP,'*','Color', app.(LampName).Color, 'MarkerSize', 14);                            
                            end
                        
                        case app.LuxuryButton_3
                            for j = 1:2
                                num = j+27;
                                % make needed UIAxes visible
                                UIAxesName = sprintf('UIAxes_%d',j);
                                
                                % Correlation Function of the TP
                                corf = str2func(app.CorrelationFunctions{1,num});
                                % corresponding CRP of the TP
                                CRP = corf(app.vehicleConceptsTP(i,num));
                                                                
                                plot(app.(UIAxesName), app.vehicleConceptsTP(i,num), CRP,'*','Color', app.(LampName).Color, 'MarkerSize', 14);                            
                            end
                    end
                    
                end
                
            end
        end
        
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Plot function for spider graph
        function plotSpiderGraph(app,data,ax,fontsize,tickMatrixVehicles)
            
            %%Data for Vehicle Concept
            switch app.ButtonGroupVC.SelectedObject.Text
                case 'Dynamics'
                    [dataTemp] = data(:, 1:4);
                case 'Ergonomics'
                    [dataTemp] = data(:,5:10);
                case 'Security'
                    [dataTemp] = data(:,11:15);
                case 'Usability'
                    [dataTemp] = data(:,16:21);
                case 'DrivingStyle'
                    [dataTemp] = data(:,22:25);
                case 'Luxury'
                    [dataTemp] = data(:,26:28);
            end
            data = dataTemp';
            
            %%Label for Secondary Activities
            switch app.ButtonGroupVC.SelectedObject.Text
                case 'Dynamics'
                    lbl = {'Axial Dynamics';sprintf('Lateral \nDynamics');'Vertical Dynamics';'Maneuverability'};
                case 'Ergonomics'
                    lbl = {'Boarding Comfort';'Boarding Time';'Leg Room';'Shoulders Room';'Head Room';'Interior Acoustics'};
                case 'Security'
                    lbl = {'Passive Safety';sprintf('External \nCommunication');sprintf('Interior \nRecognition');'Environmental Monitoring';...
                        'Active Safety'};
                case 'Usability'
                    lbl = {'Bad Road Capability';'Luggage Space';'Radius of Movement';'Costs';'Ecology';'Passengers'};
                case 'DrivingStyle'
                    lbl = {'DS Comfort';'DS Safety';'DS Time Potential';'DS Consumption'};
                case 'Luxury'
                    lbl = {'Built-In Infotainment';'Info Individualization';'Exterior Design'};
            end
            
            
            % size segments and number of cases
            [r,s] = size(data);
            
            min_max_rng = [0 10];
            rng = repmat (min_max_rng, r, 1);
            
            %Clean up the initial plot
            legend(ax,'off'); reset(ax); cla(ax); hold(ax,'on'); ax.BackgroundColor = [1,1,1];
            
            % set to add plot
            set(ax,'nextplot','add');
            
            % location of the plot
            placement=[-1.3,1.3;-1.3,1.3];
            
            % clear figure and set limits
            set(ax,'visible','off'); set(ax,'color','w');        %makes 2D plot invisible and sets background color white
            set(ax,'xlim',placement(1,:),'ylim',placement(2,:)); axis(ax,'equal','manual') %Defines size of the plot
            
            ax.Visible = 'off'; set(ax,'color','w');
            
            % scale by range
            angw = linspace(0,2*pi,r+1)';
            % wrap data (close the last axis to the first)
            ang = angw(1:end-1); 
            angs = [ang(:); ang(1)];
            
            % make the plot
            % define the axis locations
            start_ax = [zeros(1,r)+0.5; sin(ang')+0.5];
            stop_ax = [zeros(1,r); cos(ang')];
            
            % Transformation of data
            start = [zeros(1,r); sin(ang')];
            A_xaxis = data .* start(2,:)';
            A_xaxis = [A_xaxis; A_xaxis(1,:)];
            A_xaxis = A_xaxis *(1/10)+0.5;
            
            stop = [zeros(1,r); cos(ang')];
            A_yaxis = data .* stop(2,:)';
            A_yaxis = [A_yaxis; A_yaxis(1,:)];
            A_yaxis = A_yaxis *(1/10);
            
            % plot the axes
            plot(ax,start_ax,stop_ax,'color','k','linestyle','-','linewidth', 1); hold(ax, 'on'); axis equal; % open figure...
            
            % plot axes markers
            inc = (1/5):(1/5):1; 
            tx = 0.05 * ones(1,5);
            
            %fontsize tick marks
            fonttm = fontsize-6;
            
            % loop each axis ang plot the line markers and labels
            % add axes
            for ii = 1:r
            	% plot tick lines
                plot(ax,[sin(angs(ii)) * inc; sin(angs(ii+1)) * inc] +0.5, ...
            		[(cos(angs(ii)) * inc); cos(angs(ii+1)) * inc],'color','k','linewidth', 0.75);
                
            	% label the tick marks
            	for jj = 1:5
                    temp = text(ax,sin(angs(ii)) * inc(jj) + cos(angs(ii)) * tx(jj)+0.5, ...
            			cos(angs(ii)) * inc(jj) - sin(angs(ii)) * tx(jj), ...
            			num2str(rd(app,rng(ii,1) + inc(jj)*diff(rng(ii,:)),-2)), ...
            			'fontsize',fonttm, 'FontName', 'Arial');
            		% flip the text alignment for lower axes
            		if angs(ii) >= pi
            			set(temp,'HorizontalAlignment','right');
            		end
            	end
            	% label each axis
            	temp = text(ax,sin(angs(ii)) * 1.1 + cos(angs(ii)) * 0+0.5, cos(angs(ii)) * 1.1 - sin(angs(ii)) * 0, ...
        			char(lbl(ii,:)),'fontsize',fontsize, 'FontName', 'Arial');
            	% flip the text alignment for right side axes
            	if angs(ii) >= pi
            		set(temp,'HorizontalAlignment','right');
            	end
            end
            
            %Draw additional red lines for min
            % prepare data
            dataMyLine = ones(1,s)*4;
            A_xaxis2 = dataMyLine .* start(2,:)';
            A_xaxis2 = [A_xaxis2; A_xaxis2(1,:)];
            A_xaxis2 = A_xaxis2 *(1/10)+0.5;
            A_yaxis2 = dataMyLine .* stop(2,:)';
            A_yaxis2 = [A_yaxis2; A_yaxis2(1,:)];
            A_yaxis2 = A_yaxis2 *(1/10);
                
            % Draw the line
            plot(ax,A_xaxis2,A_yaxis2,"Color",'red',"LineWidth",1.5)
            
            %plot the data
            o = plot(ax,A_xaxis,A_yaxis);
            
            % define data case colors
            VCCol = app.ColorVC;
            
            % set color
            %Creating vector with numbers of ticked vehicles
            tickedVCs = zeros(nnz(tickMatrixVehicles),1);
            [numberVCs,~] = size(app.vehicleConceptsCRP);
                    
            num = 1;
            for i=1:numberVCs
                   if tickMatrixVehicles(i) == 1
                       tickedVCs(num) = i;
                       num = num + 1;
                   end
            end
            
            %Setting the color accordingly
            
            for ii=1:nnz(tickMatrixVehicles)
                 set(o(ii),'color',VCCol(tickedVCs(ii),:), ...
                    'LineWidth', 3, 'LineStyle', '--');
            end
            
            hold on
            close
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %quick round function (to specified decimal)
        function [v] = rd(~,v,dec)
            if nargin == 1; dec = 0; end
            
            v = v / 10^dec;
            v = round(v);
            v = v * 10^dec;
        end
        %__________________________________________________________________
        
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, TechnicalPropertiesVC, CustomerRelevantPropertiesVC, Derivative, MinMaxTP, corrfunct, VehicleTypes)
            %Adding necessary folders to path
            addpath('99_General_Elements\')
            addpath(genpath('07_Technical Properties\'))
            addpath(genpath('08_Simulation_TP2DP\'))
            addpath(genpath('09_Design Parameters\'))
            
            %Check if data in the workspace is available
            dataExistCheck = exist('CustomerRelevantPropertiesVC',"var");
            if dataExistCheck == 0
                uiconfirm(app.MainScreenTP,'App was loaded with no dataset. Please load manually a predefined dataset with the "Load" Button',...
                    'No dataset chosen','Options',{'Ok'},'Icon','warning');
                app.allTPforoneVCPanel.Enable = 'off';
                app.ManipulateButton.Enable = 'off';
                app.ResetButton.Enable = 'off';
                app.SaveButton.Enable = 'off';
                app.DisplayOptionsButtonGroup.Enable = 'off';
                app.VehicleConceptsSelectionPanel.Enable = 'off';
                app.ProceedtoDesignParametersButton.Enable = 'off';
            else
                %Defining the input parameters
                app.vehicleConceptsTP = TechnicalPropertiesVC;
                app.vehicleConceptsCRP = CustomerRelevantPropertiesVC;
                app.vehicleConceptsTPBackup = app.vehicleConceptsTP;
                app.Derivatives = Derivative;
                app.MinMaxValues = MinMaxTP;
                app.CorrelationFunctions = corrfunct;
                app.VCType = VehicleTypes;
                app.CheckBox1.Value = true;
                app.VCLamp1.Color = "#EFD4B4";
                CheckBox1ValueChanged(app);                          
                                  
            end


            %Setting up the main view
            [vehicleNumber,~] = size(app.vehicleConceptsCRP);
            checkBoxesEnabler(app,'VCLamp%d','VehicleConcept%dLabel','CheckBox%d',vehicleNumber)
            Disabler(app);
            app.CRPforallVC.Visible = "off";
            app.allTPforoneVCPanel.Visible = "on";
            app.TPforVCPanel.Visible = "off";
            app.CorrelationsPanel.Visible = "off";         
                        
            %Opening the window maximized
            drawnow;
            app.MainScreenTP.WindowState = 'maximized';
        end

        % Value changed function: CheckBox1, CheckBox10, 
        % CheckBox11, CheckBox12, CheckBox13, CheckBox14, 
        % CheckBox2, CheckBox3, CheckBox4, CheckBox5, CheckBox6, 
        % CheckBox7, CheckBox8, CheckBox9
        function CheckBox1ValueChanged(app, event)
            [tickMatrixVehicles] = checkBoxChecker(app);
            numBoxes = nnz(tickMatrixVehicles);
            [vehicleNumber,~] = size(app.vehicleConceptsCRP);
                       
            % maximal 6 boxes could be checked
            % warning
            if numBoxes > 6
                fig = app.MainScreenTP;
                uialert(fig,'Please select a maximum of 6 Vehicle Concepts','Maximal Number of Vehicle Concepts exceeded','Icon','warning');
            end
           
            % uncheck the 7th checked box
            num = 1;
            for i=1:vehicleNumber
                if tickMatrixVehicles(i) == 1
                    if num > 6
                        checkboxName = sprintf('CheckBox%d',i);
                        app.(checkboxName).Value = false;
                    end
                    num = num +1;
                end
                
                % display the color of the checked VCs
                lampName = sprintf('VCLamp%d',i);
                checkboxName = sprintf('CheckBox%d',i);
                colorEvaluator(app,i,lampName,checkboxName)
            end
           
           [tickMatrixVehicles] = checkBoxChecker(app); 
           %Data for showed technical Properties
           showedTechnicalProp = zeros(nnz(tickMatrixVehicles)+1,29);
           numberVC = height(app.vehicleConceptsTP);
           showedDerivatives = zeros(nnz(tickMatrixVehicles)+1,1);
           showedCRP = zeros(nnz(tickMatrixVehicles),28);
           num = 2;
           for k=1:numberVC
               if tickMatrixVehicles(k) == 1
                   showedTechnicalProp(num,:) = app.vehicleConceptsTP(k,:);
                   showedDerivatives(num, 1) = app.Derivatives(k,6);
                   showedCRP(num-1, :) = app.vehicleConceptsCRP(k,:);
                   num = num + 1;
               end
           end
           showedTechnicalProp(1,:) = showedTechnicalProp(2,:);
           showedDerivatives(1, 1) = showedDerivatives(2, 1);
           
           % Display waring of shown VC on the Panel all TP for one VC
           if nnz(tickMatrixVehicles) >=2
               app.OnlythevehicleconceptwiththelowestnumberisshownLabel.Visible = 'on';
           else
               app.OnlythevehicleconceptwiththelowestnumberisshownLabel.Visible = 'off';
           end
                     
           % Display the selected Property group
           ButtonGroupVC_2SelectionChanged(app);
           % Connect the data of the different views
           ConnectValues(app,showedTechnicalProp, showedDerivatives);
           
           % plot selected VCs into the Correlation Functions
           plotcorrelations(app);
           plotVCintoCorrelations(app);
           
           % plot spidergraph of the CRP
           plotSpiderGraph(app,showedCRP,app.UIAxesCRPGraph,14,tickMatrixVehicles);
            
        end

        % Value changed function: ManipulateButton
        function ManipulateButtonValueChanged(app, event)
            % Enable the possibilty to manipulate the values of the properties
            if app.ManipulateButton.Value == false
                fig = app.MainScreenTP;
                selection = uiconfirm(fig,['Do you really want to end the manipulation mode? This will delete all your made Parameter changes.' ...
                    ' Consider saving your manipulated Parameters before.'],'Confirm manipulation mode change','Icon','warning');
                tf = strcmp(selection,'OK');
                if tf == true
                    Disabler(app);
                    
                    app.vehicleConceptsTP = app.vehicleConceptsTPBackup;
                    [tickMatrixVehicles] = checkBoxChecker(app);
                    if nnz(tickMatrixVehicles) ~= 0                        
                        CheckBox1ValueChanged(app, event);
                    end
                    
                    % Hide the Conirm Manipulation Buttons
                    app.ConfirmManipulationButton.Visible = 'off';                    
                else
                    app.ManipulateButton.Value = true;
                end
            else
                % Warning to push always first the confir Manipulation Button before the Update Button
                fig = app.MainScreenTP;
                uialert(fig,'After manipulating a parameter, please always push the Confirm Manipulation Button before checking new Vehicle Concepts!','Warning','Icon','warning');
                
                %Enable the view
                app.vehicleConceptsTPBackup = app.vehicleConceptsTP;
                                
                %Enable the panels
                Enabler(app);
                
                % Show the Confirm Manipulation Buttons
                app.ConfirmManipulationButton.Visible = 'on';
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %Open the dialog box to save .mat file
            filename = uiputfile('*.mat','myScenario_TP','07_Technical Properties\TP_Saved_Scenarios\');
            if filename ~= 0
                               
                % prepare variables
                TechnicalPropertiesVC = app.vehicleConceptsTP;
                CustomerRelevantPropertiesVC = app.vehicleConceptsCRP;
                Derivative = app.Derivatives;
                MinMaxTP = app.MinMaxValues;
                corrfunct = app.CorrelationFunctions;
                VehicleTypes = app.VCType;
                
                saveName = append('07_Technical Properties\TP_Saved_Scenarios\',filename);
                save(saveName,'TechnicalPropertiesVC','CustomerRelevantPropertiesVC', 'Derivative', 'MinMaxTP', 'corrfunct', 'VehicleTypes')
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            %Open the dialog box to choose .mat file
            filename = uigetfile('07_Technical Properties\TP_Saved_Scenarios\','*.mat');
            if filename ~= 0
                load(filename,'TechnicalPropertiesVC','CustomerRelevantPropertiesVC', 'Derivative', 'MinMaxTP', 'corrfunct', 'VehicleTypes');
                
                %Filling the loaded data into parameter edit fields
                app.vehicleConceptsTP = TechnicalPropertiesVC;
                app.vehicleConceptsCRP = CustomerRelevantPropertiesVC;
                app.Derivatives = Derivative;
                app.MinMaxValues = MinMaxTP;
                app.CorrelationFunctions = corrfunct;
                app.VCType = VehicleTypes;
                
                %Making a Backup for input data
                app.vehicleConceptsTPBackup = app.vehicleConceptsTP;
                
                %Enabling the view in case of non-parameter loaded start
                app.CRPforallVC.Enable = 'on';
                app.ManipulateButton.Enable = 'on';
                app.ResetButton.Enable = 'on';
                app.DisplayOptionsButtonGroup.Enable = 'on';
                app.VehicleConceptsSelectionPanel.Enable = 'on';
                app.ProceedtoDesignParametersButton.Enable = 'on';
                app.allTPforoneVCPanel.Enable = 'on';
                                
                %Resetting view
                [vehicleNumber,~] = size(app.vehicleConceptsCRP);
                checkBoxesEnabler(app,'VCLamp%d','VehicleConcept%dLabel','CheckBox%d',vehicleNumber)
                app.SaveButton.Enable = 'off';
                app.ManipulateButton.Value = false;                
                                
                % check box 1 to display the values of VC 1
                app.CheckBox1.Value = true;
                app.VCLamp1.Color = "#EFD4B4";
                
                %Updating the view with all vehicle concept
                CheckBox1ValueChanged(app, event)           
             end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %Open a confirmation dialog box
            fig = app.MainScreenTP;
            selection = uiconfirm(fig,['Do you really want to reset all parameters? This will delete all your made parameter changes.' ...
                ' Consider saving your manipulated parameters before.'],'Confirm parameter reset','Icon','warning');
            tf = strcmp(selection,'OK');
            %Execute only when the user agrees
            if tf == true
                %Resetting view
                app.DynamicsPanel_1.Enable = 'off';
                app.ErgonomicsPanel_1.Enable = 'off';
                app.SecurityPanel_1.Enable = 'off';
                app.UsabilityPanel_1.Enable = 'off';
                app.DrivingStylePanel_1.Enable = 'off';
                app.LuxuryPanel_1.Enable = 'off';
                app.SaveButton.Enable = 'off';
                app.ButtonGroupVC.SelectedObject = app.DynamicsButton;
                app.CRPforallVC.Visible = 'on';
                app.allTPforoneVCPanel.Visible = 'off';
                
                app.ManipulateButton.Value = false;
                app.vehicleConceptsTP = app.vehicleConceptsTPBackup;
                %Updating the view with vehicle concept 1
                [vehicleNumber,~] = size(app.vehicleConceptsCRP);
                app.CheckBox1.Value = true;
                app.VCLamp1.Color = "#EFD4B4";
                for i=2:vehicleNumber
                    boxName = sprintf('CheckBox%d',i);
                    app.(boxName).Value = false;
                    lampName = sprintf('VCLamp%d',i);
                    app.(lampName).Color = 'white';
                end
                CheckBox1ValueChanged(app, event)
            end
        end

        % Callback function
        function DerivativeVCButtonPushed(app, event)
            % Change the view to show the VP and VC
            app.CRPforallVC.Visible = 'on';
            app.allTPforoneVCPanel.Visible = 'off';
            CheckBox1ValueChanged(app, event)
        end

        % Callback function
        function AllVCGraphsButtonPushed(app, event)
            % Change the view to show all VCs
            app.CRPforallVC.Visible = 'off';
            app.allTPforoneVCPanel.Visible = 'on';
            CheckBox1ValueChanged(app, event)        
        end

        % Selection changed function: DisplayOptionsButtonGroup
        function DisplayOptionsButtonGroupSelectionChanged(app, event)
            % Show the selected view/ panel            
            selectedButton = app.DisplayOptionsButtonGroup.SelectedObject;
            
            if selectedButton == app.allTPforoneVCButton
                app.allTPforoneVCPanel.Visible = "on";
                app.CRPforallVC.Visible = "off";
                app.TPforVCPanel.Visible = "off";
                app.CorrelationsPanel.Visible = "off";                
                
            elseif selectedButton == app.CRPforallVCButton
                app.allTPforoneVCPanel.Visible = "off";
                app.CRPforallVC.Visible = "on";
                app.TPforVCPanel.Visible = "off";
                app.CorrelationsPanel.Visible = "off";                
              
            elseif selectedButton == app.TPforallVCButton
                app.allTPforoneVCPanel.Visible = "off";
                app.CRPforallVC.Visible = "off";
                app.TPforVCPanel.Visible = "on";
                app.CorrelationsPanel.Visible = "off";                
                
            elseif selectedButton == app.CorrelationsButton
                app.allTPforoneVCPanel.Visible = "off";
                app.CRPforallVC.Visible = "off";
                app.TPforVCPanel.Visible = "off";
                app.CorrelationsPanel.Visible = "on";
                % plot corrletaions functions
                plotcorrelations(app);
                % plot selected VCs into the Correlation Functions
                plotVCintoCorrelations(app);
                
            end
        end

        % Selection changed function: ButtonGroupVC_2
        function ButtonGroupVC_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroupVC_2.SelectedObject.Text;
            
            for i=2:7                
                % make all Panels unvisible
                PanelName = sprintf('DynamicsPanel_%d',i); app.(PanelName).Visible = 'off';
                PanelName = sprintf('ErgonomicsPanel_%d',i); app.(PanelName).Visible = 'off';
                PanelName = sprintf('SecurityPanel_%d',i); app.(PanelName).Visible = 'off';
                PanelName = sprintf('DrivingStylePanel_%d',i); app.(PanelName).Visible = 'off';
                PanelName = sprintf('UsabilityPanel_%d',i); app.(PanelName).Visible = 'off';
                PanelName = sprintf('LuxuryPanel_%d',i); app.(PanelName).Visible = 'off';
            end
            
            % make selected Panels visible and name them with the showed VC
            [tickMatrixVehicles] = checkBoxChecker(app);
            numberVC = height(app.vehicleConceptsTP);
            VehicleConceptnumbers = zeros(nnz(tickMatrixVehicles), 1);
            
            num = 1;
            for k=1:numberVC
               if tickMatrixVehicles(k) == 1
                   VehicleConceptnumbers(num,1) = k;
                   num = num+1;
               end
           end
            
            for i=2:(nnz(tickMatrixVehicles))+1
                %make the selected panel visible
                PanelName = sprintf('%sPanel_%d',selectedButton,i); app.(PanelName).Title = sprintf('Vehicle Concept %d', VehicleConceptnumbers(i-1,1)); app.(PanelName).Visible = 'on';
            end 
             
        end

        % Button pushed function: ConfirmManipulationButton
        function ConfirmManipulationButtonPushed(app, event)
           readspinnervalues(app);                                
        end

        % Selection changed function: ButtonGroupVC_3
        function ButtonGroupVC_3SelectionChanged(app, event)
            % plot correlations functions of selected property group
            plotcorrelations(app);
            
            % plot selected VCs into the Correlation Functions
            plotVCintoCorrelations(app);
            
        end

        % Selection changed function: ButtonGroupVC
        function ButtonGroupVCSelectionChanged(app, event)
            % Check ticked boxes
           [tickMatrixVehicles] = checkBoxChecker(app);
           
           %Data for showed technical Properties
           numberVC = height(app.vehicleConceptsCRP);
           showedCRP = zeros(nnz(tickMatrixVehicles),28);
           num = 1;
           for k=1:numberVC
               if tickMatrixVehicles(k) == 1
                   showedCRP(num, :) = app.vehicleConceptsCRP(k,:);
                   num = num +1;
               end
           end
           
           % plot spidergraph of the CRP
           plotSpiderGraph(app,showedCRP,app.UIAxesCRPGraph,14,tickMatrixVehicles);          
        end

        % Callback function
        function ConfirmManipulationButton_1Pushed(app, event)
            readspinnervalues(app);
        end

        % Callback function
        function ConfirmManipulationButton_2Pushed(app, event)
            readspinnervalues(app);
        end

        % Button pushed function: ProceedtoDesignParametersButton
        function ProceedtoDesignParametersButtonPushed(app, event)
             %Open a confirmation dialog box
            fig = app.MainScreenTP;
            message = sprintf(['Do you want to proceed and start the calculation? \n\n'...
                'From the next step on, vehicle concepts designed primarily for Laod can no longer be considered, since the (external) tool used can only calculate passenger vehicles!']);
            selection = uiconfirm(fig,message,'Confirm calculation start','Icon','question');
            tf = strcmp(selection,'OK');
            %Execute only when the user agrees
            if tf == true                
                            
                % Prepare data for the next step Design Parameters
                TechnicalPropertiesVC = app.vehicleConceptsTP;
                CustomerRelevantPropertiesVC = app.vehicleConceptsCRP;
                Derivative = app.Derivatives;
                MinMaxTP = app.MinMaxValues;                
                VehicleType = app.VCType;   
                 
                % delete all VCs with load as primary secondary acctivity as the connected AuVeCoDe-Tool can not simulate them
                [numberVC, ~] = size(Derivative);
                n=1;
                for ii=1:numberVC
                    if Derivative(ii,6)>5
                        delete_load(n)=ii;
                        n=n+1;
                    end
                end
                
                TechnicalPropertiesVC(delete_load,:) = [];
                CustomerRelevantPropertiesVC(delete_load,:) = [];
                Derivative(delete_load,:) = [];                              
                VehicleType([delete_load]) = [];
                
                % calculate the CRP of the current TP (after manipulation)
                [numberVC, numberTP] = size(TechnicalPropertiesVC);
                numberCRP = length(CustomerRelevantPropertiesVC);
                CRPforTP = zeros(numberVC, numberTP);
                currentCRP = zeros(numberVC, numberCRP);
                
                for i=1:numberVC
                    for j=1:numberTP
                        corf = str2func(app.CorrelationFunctions{1,j});
                        CRPforTP(i,j) = corf(app.vehicleConceptsTP(i,j));
                    end
                end
            
                % merge the CRPs, if 2 TPs are connected with 1 CRP, AND copy the CRP, if no TP exist fpr the CRP
                % Dynamics
                currentCRP(:,1) = mean(CRPforTP(:,1:2),2); currentCRP(:,2) = CRPforTP(:,3); currentCRP(:,3) = CRPforTP(:,4); currentCRP(:,4) = CRPforTP(:,5);
                % Ergonomics
                currentCRP(:,5) = mean(CRPforTP(:,6:7),2); currentCRP(:,6) = CRPforTP(:,8); currentCRP(:,7) = CRPforTP(:,9); currentCRP(:,8) = CRPforTP(:,10); currentCRP(:,9) = CRPforTP(:,11); currentCRP(:,10) = CRPforTP(:,12);
                % Security
                currentCRP(:,11) = CRPforTP(:,13); currentCRP(:,12) = CustomerRelevantPropertiesVC(:,12); currentCRP(:,13) = CustomerRelevantPropertiesVC(:,13); currentCRP(:,14) = CRPforTP(:,14); currentCRP(:,15) = CRPforTP(:,15);
                % Usability
                currentCRP(:,16) = CRPforTP(:,16); currentCRP(:,18) = CRPforTP(:,19); currentCRP(:,19) = CRPforTP(:,20); currentCRP(:,20) = CRPforTP(:,21); currentCRP(:,21) = CRPforTP(:,22);
                % Storage Volume for Passenger or Load Vehicle
                for i = 1:numberVC
                    if app.Derivatives(i,6) <= 5
                        currentCRP(:,17) = CRPforTP(i,17);
                    else
                        currentCRP(:,17) = CRPforTP(i,18);                
                    end
                end
                % Driving Style
                currentCRP(:,22) = mean(CRPforTP(:,23:24),2); currentCRP(:,23) = CRPforTP(:,25); currentCRP(:,24) = CRPforTP(:,26); currentCRP(:,25) = CRPforTP(:,27);
                % Luxury
                currentCRP(:,26) = CRPforTP(:,28); currentCRP(:,27) = CustomerRelevantPropertiesVC(:,27); currentCRP(:,28) = CRPforTP(:,29);
                                             
                % Start function to convert TP to input data for AuVeCoDe tool 
                [input_parameter_matrix]=ConvertTP2DP(TechnicalPropertiesVC,Derivative,MinMaxTP,VehicleType,currentCRP);
                
                %Save the results of the simulation if the user agrees
                selection2 = uiconfirm(fig,'Do you want to save the results?','Saving Results','Icon','question');
                tf2 = strcmp(selection2,'OK');
                if tf2 == true
                    filename = uiputfile('*.mat','myScenario_DP','09_Design Parameters\DP_Saved_Scenarios\');
                    if filename ~= 0
                        newName = append('09_Design Parameters\DP_Saved_Scenarios\',filename);
                        save(newName,'input_parameter_matrix', 'currentCRP', 'CustomerRelevantPropertiesVC', 'TechnicalPropertiesVC', 'Derivative')
                    end
                end
           
                % Open Design Parameters App
                selection3 = uiconfirm(fig,'Do you want to open the Design Parameters app?','Open Design Parameters app','Icon','question');
                tf3 = strcmp(selection3,'OK');
                if tf3 == true
                    DesignParameters(input_parameter_matrix, currentCRP, CustomerRelevantPropertiesVC, TechnicalPropertiesVC, Derivative)
                end
            end           
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            %Open a confirmation dialog box
            fig = app.MainScreenTP;
            message = sprintf(['Do you want to return to the Home screen? \n\n'...
                'Consider saving their current results. Otherwise it will be deleted.']);
            selection = uiconfirm(fig,message,'Confirm return to Home screen','Icon','question');
            tf = strcmp(selection,'OK');
            %Execute only when the user agrees
            if tf == true
                Start;
                delete(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainScreenTP = uifigure('Visible', 'off');
            app.MainScreenTP.Name = 'Technical Properties';
            app.MainScreenTP.Icon = 'FTMLogo.PNG';
            app.Step3TechnicalPropertiesofAutonomousVehicleConceptsLabel = uilabel(app.MainScreenTP);
            app.Step3TechnicalPropertiesofAutonomousVehicleConceptsLabel.Text = '              Step 3: Technical Properties of Autonomous, Shared and Electric Vehicle Concepts';
            app.TUMlogo = uiimage(app.MainScreenTP);
            app.TUMlogo.ImageSource = '2015_Logo_TUM_neg_RGB.svg';
            app.MainMenuPanel = uipanel(app.MainScreenTP);
            app.MainMenuPanel.Title = 'Main Menu';
            app.DataOptionsPanel = uipanel(app.MainMenuPanel);
            app.DataOptionsPanel.Title = 'Data Options';
            app.LoadButton = uibutton(app.DataOptionsPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Tooltip = {'Loads saved calcualtion or parameters from .mat file.'; 'Notice: Only previously saved files can be openend!'};
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.DataOptionsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tooltip = {'Saves the current parameters into a .mat file. This file can be opened later by the Load button.'};
            app.SaveButton.Text = 'Save';
            app.ResetButton = uibutton(app.DataOptionsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tooltip = {'Resets all parameters and sets display to the start view.'};
            app.ResetButton.Text = 'Reset';
            app.ManipulateButton = uibutton(app.DataOptionsPanel, 'state');
            app.ManipulateButton.ValueChangedFcn = createCallbackFcn(app, @ManipulateButtonValueChanged, true);
            app.ManipulateButton.Tooltip = {'Enables the manipulation of the Vehicle Concepts parameters.'};
            app.ManipulateButton.Text = 'Manipulate';
            app.ProceedtoDesignParametersButton = uibutton(app.MainMenuPanel, 'push');
            app.ProceedtoDesignParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedtoDesignParametersButtonPushed, true);
            app.ProceedtoDesignParametersButton.Tooltip = {'Proceed to the "Component Selection" GUI by starting the calculation of Component Parameters for each selected Vehicle Concept.'};
            app.ProceedtoDesignParametersButton.Text = 'Proceed to Design Parameters';
            app.VehicleConceptsSelectionPanel = uipanel(app.MainMenuPanel);
            app.VehicleConceptsSelectionPanel.Title = 'Vehicle Concepts Selection';
            app.CheckBox1 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox1.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox1.Text = '';
            app.VCLamp1 = uilamp(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept1Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept1Label.Text = 'Vehicle Concept 1';
            app.CheckBox2 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox2.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox2.Text = '';
            app.VCLamp2 = uilamp(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept2Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept2Label.Text = 'Vehicle Concept 2';
            app.CheckBox3 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox3.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox3.Text = '';
            app.VCLamp3 = uilamp(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept3Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept3Label.Text = 'Vehicle Concept 3';
            app.CheckBox4 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox4.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox4.Text = '';
            app.VCLamp4 = uilamp(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept4Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept4Label.Text = 'Vehicle Concept 4';
            app.CheckBox5 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox5.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox5.Text = '';
            app.VCLamp5 = uilamp(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept5Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept5Label.Text = 'Vehicle Concept 5';
            app.CheckBox6 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox6.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox6.Text = '';
            app.VCLamp6 = uilamp(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept6Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept6Label.Text = 'Vehicle Concept 6';
            app.CheckBox7 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox7.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox7.Text = '';
            app.VCLamp7 = uilamp(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept7Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept7Label.Text = 'Vehicle Concept 7';
            app.CheckBox8 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox8.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox8.Text = '';
            app.VCLamp8 = uilamp(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept8Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept8Label.Text = 'Vehicle Concept 8';
            app.CheckBox9 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox9.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox9.Text = '';
            app.VCLamp9 = uilamp(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept9Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept9Label.Text = 'Vehicle Concept 9';
            app.CheckBox10 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox10.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox10.Text = '';
            app.VCLamp10 = uilamp(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept10Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept10Label.Text = 'Vehicle Concept 10';
            app.CheckBox11 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox11.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox11.Text = '';
            app.VCLamp11 = uilamp(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept11Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept11Label.Text = 'Vehicle Concept 11';
            app.CheckBox12 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox12.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox12.Text = '';
            app.VCLamp12 = uilamp(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept12Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept12Label.Text = 'Vehicle Concept 12';
            app.CheckBox13 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox13.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox13.Text = '';
            app.VCLamp13 = uilamp(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept13Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept13Label.Text = 'Vehicle Concept 13';
            app.CheckBox14 = uicheckbox(app.VehicleConceptsSelectionPanel);
            app.CheckBox14.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox14.Text = '';
            app.VCLamp14 = uilamp(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept14Label = uilabel(app.VehicleConceptsSelectionPanel);
            app.VehicleConcept14Label.Text = 'Vehicle Concept 14';
            app.DisplayOptionsButtonGroup = uibuttongroup(app.MainMenuPanel);
            app.DisplayOptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplayOptionsButtonGroupSelectionChanged, true);
            app.DisplayOptionsButtonGroup.Title = 'Display Options';
            app.allTPforoneVCButton = uitogglebutton(app.DisplayOptionsButtonGroup);
            app.allTPforoneVCButton.Tooltip = {'Shows all TPs for one VC.'; 'TP = Technical Property'; 'VC = Vehicle Concept'; ''};
            app.allTPforoneVCButton.Text = 'all TP for one VC';
            app.allTPforoneVCButton.Value = true;
            app.CRPforallVCButton = uitogglebutton(app.DisplayOptionsButtonGroup);
            app.CRPforallVCButton.Tooltip = {'Shows the CRPs separated by the categories for all selected VCs.'; 'CPR = Customer-Relevant Property'; 'VC = Vehicle Concept'; ''};
            app.CRPforallVCButton.Text = 'CRP for all VC  ';
            app.TPforallVCButton = uitogglebutton(app.DisplayOptionsButtonGroup);
            app.TPforallVCButton.Tooltip = {'Shows the TPs separated by the categories for all selected VCs.'; 'TP = Technical Property'; 'VC = Vehicle Concept'; ''};
            app.TPforallVCButton.Text = 'TP for all VC';
            app.CorrelationsButton = uitogglebutton(app.DisplayOptionsButtonGroup);
            app.CorrelationsButton.Tooltip = {'Shows all Correlations between the CPR and the TP of the VCs.'; 'CPR = Customer-Relevant Property'; 'TP = Technical Property'; 'VC = Vehicle Concept'};
            app.CorrelationsButton.Text = 'Correlations';
            app.ConfirmManipulationButton = uibutton(app.MainMenuPanel, 'push');
            app.ConfirmManipulationButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmManipulationButtonPushed, true);
            app.ConfirmManipulationButton.Visible = 'off';
            app.ConfirmManipulationButton.Tooltip = {'After manipulating the parameters, please confirm your inputs before continuing.'};
            app.ConfirmManipulationButton.Text = 'Confirm Manipulation';
            app.CorrelationsPanel = uipanel(app.MainScreenTP);
            app.CorrelationsPanel.Title = 'Correlations';
            app.ButtonGroupVC_3 = uibuttongroup(app.CorrelationsPanel);
            app.ButtonGroupVC_3.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupVC_3SelectionChanged, true);
            app.ButtonGroupVC_3.Tooltip = {'Select the Properties category.'};
            app.DynamicsButton_3 = uitogglebutton(app.ButtonGroupVC_3);
            app.DynamicsButton_3.Text = 'Dynamics';
            app.DynamicsButton_3.Value = true;
            app.ErgonomicsButton_3 = uitogglebutton(app.ButtonGroupVC_3);
            app.ErgonomicsButton_3.Text = 'Ergonomics';
            app.SecurityButton_3 = uitogglebutton(app.ButtonGroupVC_3);
            app.SecurityButton_3.Text = 'Security';
            app.UsabilityButton_3 = uitogglebutton(app.ButtonGroupVC_3);
            app.UsabilityButton_3.Text = 'Usability';
            app.DrivingStyleButton_3 = uitogglebutton(app.ButtonGroupVC_3);
            app.DrivingStyleButton_3.Text = 'DrivingStyle';
            app.LuxuryButton_3 = uitogglebutton(app.ButtonGroupVC_3);
            app.LuxuryButton_3.Text = 'Luxury';
            app.UIAxes_1 = uiaxes(app.CorrelationsPanel);
            title(app.UIAxes_1, 'Title')
            xlabel(app.UIAxes_1, 'X')
            ylabel(app.UIAxes_1, 'Customer Value')
            zlabel(app.UIAxes_1, 'Z')
            app.UIAxes_2 = uiaxes(app.CorrelationsPanel);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Customer Value')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.CorrelationsPanel);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Customer Value')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.CorrelationsPanel);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Customer Value')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.CorrelationsPanel);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Customer Value')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.CorrelationsPanel);
            title(app.UIAxes_6, 'Title')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Customer Value')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_7 = uiaxes(app.CorrelationsPanel);
            title(app.UIAxes_7, 'Title')
            xlabel(app.UIAxes_7, 'X')
            ylabel(app.UIAxes_7, 'Customer Value')
            zlabel(app.UIAxes_7, 'Z')
            app.CRPforallVC = uipanel(app.MainScreenTP);
            app.CRPforallVC.Tooltip = {'The red line shows the minimal acceptable value of the Customer-Relevant Property.'};
            app.CRPforallVC.Title = 'Customer-relevant Properties for Vehicle Concepts';
            app.ButtonGroupVC = uibuttongroup(app.CRPforallVC);
            app.ButtonGroupVC.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupVCSelectionChanged, true);
            app.ButtonGroupVC.Tooltip = {'Select the Properties category.'};
            app.DynamicsButton = uitogglebutton(app.ButtonGroupVC);
            app.DynamicsButton.Text = 'Dynamics';
            app.DynamicsButton.Value = true;
            app.ErgonomicsButton = uitogglebutton(app.ButtonGroupVC);
            app.ErgonomicsButton.Text = 'Ergonomics';
            app.SecurityButton = uitogglebutton(app.ButtonGroupVC);
            app.SecurityButton.Text = 'Security';
            app.UsabilityButton = uitogglebutton(app.ButtonGroupVC);
            app.UsabilityButton.Text = 'Usability';
            app.DrivingStyleButton = uitogglebutton(app.ButtonGroupVC);
            app.DrivingStyleButton.Text = 'DrivingStyle';
            app.LuxuryButton = uitogglebutton(app.ButtonGroupVC);
            app.LuxuryButton.Text = 'Luxury';
            app.UIAxesCRPGraph = uiaxes(app.CRPforallVC);
            xlabel(app.UIAxesCRPGraph, 'X')
            ylabel(app.UIAxesCRPGraph, 'Y')
            zlabel(app.UIAxesCRPGraph, 'Z')
            app.HomeButton = uibutton(app.MainScreenTP, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Icon = 'Home.svg';
            app.HomeButton.Text = '';
            app.Button = uibutton(app.MainScreenTP, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'Home.svg';
            app.Button.Text = '';
            app.TPforVCPanel = uipanel(app.MainScreenTP);
            app.TPforVCPanel.Title = 'Technical Properties for Vehicle Concepts';
            app.ButtonGroupVC_2 = uibuttongroup(app.TPforVCPanel);
            app.ButtonGroupVC_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupVC_2SelectionChanged, true);
            app.ButtonGroupVC_2.Tooltip = {'Select the Properties category.'};
            app.DynamicsButton_2 = uitogglebutton(app.ButtonGroupVC_2);
            app.DynamicsButton_2.Text = 'Dynamics';
            app.DynamicsButton_2.Value = true;
            app.ErgonomicsButton_2 = uitogglebutton(app.ButtonGroupVC_2);
            app.ErgonomicsButton_2.Text = 'Ergonomics';
            app.SecurityButton_2 = uitogglebutton(app.ButtonGroupVC_2);
            app.SecurityButton_2.Text = 'Security';
            app.UsabilityButton_2 = uitogglebutton(app.ButtonGroupVC_2);
            app.UsabilityButton_2.Text = 'Usability';
            app.DrivingStyleButton_2 = uitogglebutton(app.ButtonGroupVC_2);
            app.DrivingStyleButton_2.Text = 'DrivingStyle';
            app.LuxuryButton_2 = uitogglebutton(app.ButtonGroupVC_2);
            app.LuxuryButton_2.Text = 'Luxury';
            app.ErgonomicsPanel_2 = uipanel(app.TPforVCPanel);
            app.ErgonomicsPanel_2.Tooltip = {''};
            app.ErgonomicsPanel_2.Title = 'Ergonomics';
            app.BoardingComfortLabel_2 = uilabel(app.ErgonomicsPanel_2);
            app.BoardingComfortLabel_2.Text = 'Boarding Comfort';
            app.BoardingTimeLabel_2 = uilabel(app.ErgonomicsPanel_2);
            app.BoardingTimeLabel_2.Text = 'Boarding Time';
            app.LegRoomLabel_2 = uilabel(app.ErgonomicsPanel_2);
            app.LegRoomLabel_2.Text = 'Leg Room';
            app.ShouldersRoomLabel_2 = uilabel(app.ErgonomicsPanel_2);
            app.ShouldersRoomLabel_2.Text = 'Shoulders Room';
            app.HeadRoomLabel_2 = uilabel(app.ErgonomicsPanel_2);
            app.HeadRoomLabel_2.Text = 'Head Room';
            app.InteriorAcousticsLabel_2 = uilabel(app.ErgonomicsPanel_2);
            app.InteriorAcousticsLabel_2.Text = 'Interior Acoustics';
            app.mmLabel_12 = uilabel(app.ErgonomicsPanel_2);
            app.mmLabel_12.Text = 'mm';
            app.SillHeightSpinner_2Label = uilabel(app.ErgonomicsPanel_2);
            app.SillHeightSpinner_2Label.Text = 'Sill Height';
            app.SillHeightSpinner_2 = uispinner(app.ErgonomicsPanel_2);
            app.mmLabel_13 = uilabel(app.ErgonomicsPanel_2);
            app.mmLabel_13.Text = 'mm';
            app.ClearanceHeightSpinner_2Label = uilabel(app.ErgonomicsPanel_2);
            app.ClearanceHeightSpinner_2Label.Text = 'Clearance Height';
            app.ClearanceHeightSpinner_2 = uispinner(app.ErgonomicsPanel_2);
            app.mmLabel_14 = uilabel(app.ErgonomicsPanel_2);
            app.mmLabel_14.Text = 'mm';
            app.DoorOpeningWidthperSeatSpinner_2Label = uilabel(app.ErgonomicsPanel_2);
            app.DoorOpeningWidthperSeatSpinner_2Label.Text = 'Door Opening Width per Seat';
            app.DoorOpeningWidthperSeatSpinner_2 = uispinner(app.ErgonomicsPanel_2);
            app.mmLabel_15 = uilabel(app.ErgonomicsPanel_2);
            app.mmLabel_15.Text = 'mm';
            app.InteriorDimensionL53Spinner_2Label = uilabel(app.ErgonomicsPanel_2);
            app.InteriorDimensionL53Spinner_2Label.Text = 'Interior Dimension L53';
            app.InteriorDimensionL53Spinner_2 = uispinner(app.ErgonomicsPanel_2);
            app.mmLabel_16 = uilabel(app.ErgonomicsPanel_2);
            app.mmLabel_16.Text = 'mm';
            app.InteriorDimensionW3Spinner_2Label = uilabel(app.ErgonomicsPanel_2);
            app.InteriorDimensionW3Spinner_2Label.Text = 'Interior Dimension W3';
            app.InteriorDimensionW3Spinner_2 = uispinner(app.ErgonomicsPanel_2);
            app.mmLabel_17 = uilabel(app.ErgonomicsPanel_2);
            app.mmLabel_17.Text = 'mm';
            app.InteriorDimensionH61Spinner_2Label = uilabel(app.ErgonomicsPanel_2);
            app.InteriorDimensionH61Spinner_2Label.Text = 'Interior Dimension H61';
            app.InteriorDimensionH61Spinner_2 = uispinner(app.ErgonomicsPanel_2);
            app.dBLabel_2 = uilabel(app.ErgonomicsPanel_2);
            app.dBLabel_2.Text = 'dB';
            app.AveragedSoundPressureLevelSpinner_2Label = uilabel(app.ErgonomicsPanel_2);
            app.AveragedSoundPressureLevelSpinner_2Label.Text = 'Averaged Sound Pressure Level';
            app.AveragedSoundPressureLevelSpinner_2 = uispinner(app.ErgonomicsPanel_2);
            app.ErgonomicsPanel_3 = uipanel(app.TPforVCPanel);
            app.ErgonomicsPanel_3.Tooltip = {''};
            app.ErgonomicsPanel_3.Title = 'Ergonomics';
            app.BoardingComfortLabel_3 = uilabel(app.ErgonomicsPanel_3);
            app.BoardingComfortLabel_3.Text = 'Boarding Comfort';
            app.BoardingTimeLabel_3 = uilabel(app.ErgonomicsPanel_3);
            app.BoardingTimeLabel_3.Text = 'Boarding Time';
            app.LegRoomLabel_3 = uilabel(app.ErgonomicsPanel_3);
            app.LegRoomLabel_3.Text = 'Leg Room';
            app.ShouldersRoomLabel_3 = uilabel(app.ErgonomicsPanel_3);
            app.ShouldersRoomLabel_3.Text = 'Shoulders Room';
            app.HeadRoomLabel_3 = uilabel(app.ErgonomicsPanel_3);
            app.HeadRoomLabel_3.Text = 'Head Room';
            app.InteriorAcousticsLabel_3 = uilabel(app.ErgonomicsPanel_3);
            app.InteriorAcousticsLabel_3.Text = 'Interior Acoustics';
            app.mmLabel_18 = uilabel(app.ErgonomicsPanel_3);
            app.mmLabel_18.Text = 'mm';
            app.SillHeightSpinner_3Label = uilabel(app.ErgonomicsPanel_3);
            app.SillHeightSpinner_3Label.Text = 'Sill Height';
            app.SillHeightSpinner_3 = uispinner(app.ErgonomicsPanel_3);
            app.mmLabel_19 = uilabel(app.ErgonomicsPanel_3);
            app.mmLabel_19.Text = 'mm';
            app.ClearanceHeightSpinner_3Label = uilabel(app.ErgonomicsPanel_3);
            app.ClearanceHeightSpinner_3Label.Text = 'Clearance Height';
            app.ClearanceHeightSpinner_3 = uispinner(app.ErgonomicsPanel_3);
            app.mmLabel_20 = uilabel(app.ErgonomicsPanel_3);
            app.mmLabel_20.Text = 'mm';
            app.DoorOpeningWidthperSeatSpinner_3Label = uilabel(app.ErgonomicsPanel_3);
            app.DoorOpeningWidthperSeatSpinner_3Label.Text = 'Door Opening Width per Seat';
            app.DoorOpeningWidthperSeatSpinner_3 = uispinner(app.ErgonomicsPanel_3);
            app.mmLabel_21 = uilabel(app.ErgonomicsPanel_3);
            app.mmLabel_21.Text = 'mm';
            app.InteriorDimensionL53Spinner_3Label = uilabel(app.ErgonomicsPanel_3);
            app.InteriorDimensionL53Spinner_3Label.Text = 'Interior Dimension L53';
            app.InteriorDimensionL53Spinner_3 = uispinner(app.ErgonomicsPanel_3);
            app.mmLabel_22 = uilabel(app.ErgonomicsPanel_3);
            app.mmLabel_22.Text = 'mm';
            app.InteriorDimensionW3Spinner_3Label = uilabel(app.ErgonomicsPanel_3);
            app.InteriorDimensionW3Spinner_3Label.Text = 'Interior Dimension W3';
            app.InteriorDimensionW3Spinner_3 = uispinner(app.ErgonomicsPanel_3);
            app.mmLabel_23 = uilabel(app.ErgonomicsPanel_3);
            app.mmLabel_23.Text = 'mm';
            app.InteriorDimensionH61Spinner_3Label = uilabel(app.ErgonomicsPanel_3);
            app.InteriorDimensionH61Spinner_3Label.Text = 'Interior Dimension H61';
            app.InteriorDimensionH61Spinner_3 = uispinner(app.ErgonomicsPanel_3);
            app.dBLabel_3 = uilabel(app.ErgonomicsPanel_3);
            app.dBLabel_3.Text = 'dB';
            app.AveragedSoundPressureLevelSpinner_3Label = uilabel(app.ErgonomicsPanel_3);
            app.AveragedSoundPressureLevelSpinner_3Label.Text = 'Averaged Sound Pressure Level';
            app.AveragedSoundPressureLevelSpinner_3 = uispinner(app.ErgonomicsPanel_3);
            app.ErgonomicsPanel_4 = uipanel(app.TPforVCPanel);
            app.ErgonomicsPanel_4.Tooltip = {''};
            app.ErgonomicsPanel_4.Title = 'Ergonomics';
            app.BoardingComfortLabel_4 = uilabel(app.ErgonomicsPanel_4);
            app.BoardingComfortLabel_4.Text = 'Boarding Comfort';
            app.BoardingTimeLabel_4 = uilabel(app.ErgonomicsPanel_4);
            app.BoardingTimeLabel_4.Text = 'Boarding Time';
            app.LegRoomLabel_4 = uilabel(app.ErgonomicsPanel_4);
            app.LegRoomLabel_4.Text = 'Leg Room';
            app.ShouldersRoomLabel_4 = uilabel(app.ErgonomicsPanel_4);
            app.ShouldersRoomLabel_4.Text = 'Shoulders Room';
            app.HeadRoomLabel_4 = uilabel(app.ErgonomicsPanel_4);
            app.HeadRoomLabel_4.Text = 'Head Room';
            app.InteriorAcousticsLabel_4 = uilabel(app.ErgonomicsPanel_4);
            app.InteriorAcousticsLabel_4.Text = 'Interior Acoustics';
            app.mmLabel_24 = uilabel(app.ErgonomicsPanel_4);
            app.mmLabel_24.Text = 'mm';
            app.SillHeightSpinner_4Label = uilabel(app.ErgonomicsPanel_4);
            app.SillHeightSpinner_4Label.Text = 'Sill Height';
            app.SillHeightSpinner_4 = uispinner(app.ErgonomicsPanel_4);
            app.mmLabel_25 = uilabel(app.ErgonomicsPanel_4);
            app.mmLabel_25.Text = 'mm';
            app.ClearanceHeightSpinner_4Label = uilabel(app.ErgonomicsPanel_4);
            app.ClearanceHeightSpinner_4Label.Text = 'Clearance Height';
            app.ClearanceHeightSpinner_4 = uispinner(app.ErgonomicsPanel_4);
            app.mmLabel_26 = uilabel(app.ErgonomicsPanel_4);
            app.mmLabel_26.Text = 'mm';
            app.DoorOpeningWidthperSeatSpinner_4Label = uilabel(app.ErgonomicsPanel_4);
            app.DoorOpeningWidthperSeatSpinner_4Label.Text = 'Door Opening Width per Seat';
            app.DoorOpeningWidthperSeatSpinner_4 = uispinner(app.ErgonomicsPanel_4);
            app.mmLabel_27 = uilabel(app.ErgonomicsPanel_4);
            app.mmLabel_27.Text = 'mm';
            app.InteriorDimensionL53Spinner_4Label = uilabel(app.ErgonomicsPanel_4);
            app.InteriorDimensionL53Spinner_4Label.Text = 'Interior Dimension L53';
            app.InteriorDimensionL53Spinner_4 = uispinner(app.ErgonomicsPanel_4);
            app.mmLabel_28 = uilabel(app.ErgonomicsPanel_4);
            app.mmLabel_28.Text = 'mm';
            app.InteriorDimensionW3Spinner_4Label = uilabel(app.ErgonomicsPanel_4);
            app.InteriorDimensionW3Spinner_4Label.Text = 'Interior Dimension W3';
            app.InteriorDimensionW3Spinner_4 = uispinner(app.ErgonomicsPanel_4);
            app.mmLabel_29 = uilabel(app.ErgonomicsPanel_4);
            app.mmLabel_29.Text = 'mm';
            app.InteriorDimensionH61Spinner_4Label = uilabel(app.ErgonomicsPanel_4);
            app.InteriorDimensionH61Spinner_4Label.Text = 'Interior Dimension H61';
            app.InteriorDimensionH61Spinner_4 = uispinner(app.ErgonomicsPanel_4);
            app.dBLabel_4 = uilabel(app.ErgonomicsPanel_4);
            app.dBLabel_4.Text = 'dB';
            app.AveragedSoundPressureLevelSpinner_4Label = uilabel(app.ErgonomicsPanel_4);
            app.AveragedSoundPressureLevelSpinner_4Label.Text = 'Averaged Sound Pressure Level';
            app.AveragedSoundPressureLevelSpinner_4 = uispinner(app.ErgonomicsPanel_4);
            app.ErgonomicsPanel_5 = uipanel(app.TPforVCPanel);
            app.ErgonomicsPanel_5.Tooltip = {''};
            app.ErgonomicsPanel_5.Title = 'Ergonomics';
            app.BoardingComfortLabel_5 = uilabel(app.ErgonomicsPanel_5);
            app.BoardingComfortLabel_5.Text = 'Boarding Comfort';
            app.BoardingTimeLabel_5 = uilabel(app.ErgonomicsPanel_5);
            app.BoardingTimeLabel_5.Text = 'Boarding Time';
            app.LegRoomLabel_5 = uilabel(app.ErgonomicsPanel_5);
            app.LegRoomLabel_5.Text = 'Leg Room';
            app.ShouldersRoomLabel_5 = uilabel(app.ErgonomicsPanel_5);
            app.ShouldersRoomLabel_5.Text = 'Shoulders Room';
            app.HeadRoomLabel_5 = uilabel(app.ErgonomicsPanel_5);
            app.HeadRoomLabel_5.Text = 'Head Room';
            app.InteriorAcousricsLabel_5 = uilabel(app.ErgonomicsPanel_5);
            app.InteriorAcousricsLabel_5.Text = 'Interior Acousrics';
            app.mmLabel_30 = uilabel(app.ErgonomicsPanel_5);
            app.mmLabel_30.Text = 'mm';
            app.SillHeightSpinner_5Label = uilabel(app.ErgonomicsPanel_5);
            app.SillHeightSpinner_5Label.Text = 'Sill Height';
            app.SillHeightSpinner_5 = uispinner(app.ErgonomicsPanel_5);
            app.mmLabel_31 = uilabel(app.ErgonomicsPanel_5);
            app.mmLabel_31.Text = 'mm';
            app.ClearanceHeightSpinner_5Label = uilabel(app.ErgonomicsPanel_5);
            app.ClearanceHeightSpinner_5Label.Text = 'Clearance Height';
            app.ClearanceHeightSpinner_5 = uispinner(app.ErgonomicsPanel_5);
            app.mmLabel_32 = uilabel(app.ErgonomicsPanel_5);
            app.mmLabel_32.Text = 'mm';
            app.DoorOpeningWidthperSeatSpinner_5Label = uilabel(app.ErgonomicsPanel_5);
            app.DoorOpeningWidthperSeatSpinner_5Label.Text = 'Door Opening Width per Seat';
            app.DoorOpeningWidthperSeatSpinner_5 = uispinner(app.ErgonomicsPanel_5);
            app.mmLabel_33 = uilabel(app.ErgonomicsPanel_5);
            app.mmLabel_33.Text = 'mm';
            app.InteriorDimensionL53Spinner_5Label = uilabel(app.ErgonomicsPanel_5);
            app.InteriorDimensionL53Spinner_5Label.Text = 'Interior Dimension L53';
            app.InteriorDimensionL53Spinner_5 = uispinner(app.ErgonomicsPanel_5);
            app.mmLabel_34 = uilabel(app.ErgonomicsPanel_5);
            app.mmLabel_34.Text = 'mm';
            app.InteriorDimensionW3Spinner_5Label = uilabel(app.ErgonomicsPanel_5);
            app.InteriorDimensionW3Spinner_5Label.Text = 'Interior Dimension W3';
            app.InteriorDimensionW3Spinner_5 = uispinner(app.ErgonomicsPanel_5);
            app.mmLabel_35 = uilabel(app.ErgonomicsPanel_5);
            app.mmLabel_35.Text = 'mm';
            app.InteriorDimensionH61Spinner_5Label = uilabel(app.ErgonomicsPanel_5);
            app.InteriorDimensionH61Spinner_5Label.Text = 'Interior Dimension H61';
            app.InteriorDimensionH61Spinner_5 = uispinner(app.ErgonomicsPanel_5);
            app.dBLabel_5 = uilabel(app.ErgonomicsPanel_5);
            app.dBLabel_5.Text = 'dB';
            app.AveragedSoundPressureLevelSpinner_5Label = uilabel(app.ErgonomicsPanel_5);
            app.AveragedSoundPressureLevelSpinner_5Label.Text = 'Averaged Sound Pressure Level';
            app.AveragedSoundPressureLevelSpinner_5 = uispinner(app.ErgonomicsPanel_5);
            app.ErgonomicsPanel_6 = uipanel(app.TPforVCPanel);
            app.ErgonomicsPanel_6.Tooltip = {''};
            app.ErgonomicsPanel_6.Title = 'Ergonomics';
            app.BoardingComfortLabel_6 = uilabel(app.ErgonomicsPanel_6);
            app.BoardingComfortLabel_6.Text = 'Boarding Comfort';
            app.BoardingTimeLabel_6 = uilabel(app.ErgonomicsPanel_6);
            app.BoardingTimeLabel_6.Text = 'Boarding Time';
            app.LegRoomLabel_6 = uilabel(app.ErgonomicsPanel_6);
            app.LegRoomLabel_6.Text = 'Leg Room';
            app.ShouldersRoomLabel_6 = uilabel(app.ErgonomicsPanel_6);
            app.ShouldersRoomLabel_6.Text = 'Shoulders Room';
            app.HeadRoomLabel_6 = uilabel(app.ErgonomicsPanel_6);
            app.HeadRoomLabel_6.Text = 'Head Room';
            app.InteriorAcousricsLabel_6 = uilabel(app.ErgonomicsPanel_6);
            app.InteriorAcousricsLabel_6.Text = 'Interior Acousrics';
            app.mmLabel_36 = uilabel(app.ErgonomicsPanel_6);
            app.mmLabel_36.Text = 'mm';
            app.SillHeightSpinner_6Label = uilabel(app.ErgonomicsPanel_6);
            app.SillHeightSpinner_6Label.Text = 'Sill Height';
            app.SillHeightSpinner_6 = uispinner(app.ErgonomicsPanel_6);
            app.mmLabel_37 = uilabel(app.ErgonomicsPanel_6);
            app.mmLabel_37.Text = 'mm';
            app.ClearanceHeightSpinner_6Label = uilabel(app.ErgonomicsPanel_6);
            app.ClearanceHeightSpinner_6Label.Text = 'Clearance Height';
            app.ClearanceHeightSpinner_6 = uispinner(app.ErgonomicsPanel_6);
            app.mmLabel_38 = uilabel(app.ErgonomicsPanel_6);
            app.mmLabel_38.Text = 'mm';
            app.DoorOpeningWidthperSeatSpinner_6Label = uilabel(app.ErgonomicsPanel_6);
            app.DoorOpeningWidthperSeatSpinner_6Label.Text = 'Door Opening Width per Seat';
            app.DoorOpeningWidthperSeatSpinner_6 = uispinner(app.ErgonomicsPanel_6);
            app.mmLabel_39 = uilabel(app.ErgonomicsPanel_6);
            app.mmLabel_39.Text = 'mm';
            app.InteriorDimensionL53Spinner_6Label = uilabel(app.ErgonomicsPanel_6);
            app.InteriorDimensionL53Spinner_6Label.Text = 'Interior Dimension L53';
            app.InteriorDimensionL53Spinner_6 = uispinner(app.ErgonomicsPanel_6);
            app.mmLabel_40 = uilabel(app.ErgonomicsPanel_6);
            app.mmLabel_40.Text = 'mm';
            app.InteriorDimensionW3Spinner_6Label = uilabel(app.ErgonomicsPanel_6);
            app.InteriorDimensionW3Spinner_6Label.Text = 'Interior Dimension W3';
            app.InteriorDimensionW3Spinner_6 = uispinner(app.ErgonomicsPanel_6);
            app.mmLabel_41 = uilabel(app.ErgonomicsPanel_6);
            app.mmLabel_41.Text = 'mm';
            app.InteriorDimensionH61Spinner_6Label = uilabel(app.ErgonomicsPanel_6);
            app.InteriorDimensionH61Spinner_6Label.Text = 'Interior Dimension H61';
            app.InteriorDimensionH61Spinner_6 = uispinner(app.ErgonomicsPanel_6);
            app.dBLabel_6 = uilabel(app.ErgonomicsPanel_6);
            app.dBLabel_6.Text = 'dB';
            app.AveragedSoundPressureLevelSpinner_6Label = uilabel(app.ErgonomicsPanel_6);
            app.AveragedSoundPressureLevelSpinner_6Label.Text = 'Averaged Sound Pressure Level';
            app.AveragedSoundPressureLevelSpinner_6 = uispinner(app.ErgonomicsPanel_6);
            app.ErgonomicsPanel_7 = uipanel(app.TPforVCPanel);
            app.ErgonomicsPanel_7.Tooltip = {''};
            app.ErgonomicsPanel_7.Title = 'Ergonomics';
            app.BoardingComfortLabel_7 = uilabel(app.ErgonomicsPanel_7);
            app.BoardingComfortLabel_7.Text = 'Boarding Comfort';
            app.BoardingTimeLabel_7 = uilabel(app.ErgonomicsPanel_7);
            app.BoardingTimeLabel_7.Text = 'Boarding Time';
            app.LegRoomLabel_7 = uilabel(app.ErgonomicsPanel_7);
            app.LegRoomLabel_7.Text = 'Leg Room';
            app.ShouldersRoomLabel_7 = uilabel(app.ErgonomicsPanel_7);
            app.ShouldersRoomLabel_7.Text = 'Shoulders Room';
            app.HeadRoomLabel_7 = uilabel(app.ErgonomicsPanel_7);
            app.HeadRoomLabel_7.Text = 'Head Room';
            app.InteriorAcousricsLabel_7 = uilabel(app.ErgonomicsPanel_7);
            app.InteriorAcousricsLabel_7.Text = 'Interior Acousrics';
            app.mmLabel_42 = uilabel(app.ErgonomicsPanel_7);
            app.mmLabel_42.Text = 'mm';
            app.SillHeightSpinner_7Label = uilabel(app.ErgonomicsPanel_7);
            app.SillHeightSpinner_7Label.Text = 'Sill Height';
            app.SillHeightSpinner_7 = uispinner(app.ErgonomicsPanel_7);
            app.mmLabel_43 = uilabel(app.ErgonomicsPanel_7);
            app.mmLabel_43.Text = 'mm';
            app.ClearanceHeightSpinner_7Label = uilabel(app.ErgonomicsPanel_7);
            app.ClearanceHeightSpinner_7Label.Text = 'Clearance Height';
            app.ClearanceHeightSpinner_7 = uispinner(app.ErgonomicsPanel_7);
            app.mmLabel_44 = uilabel(app.ErgonomicsPanel_7);
            app.mmLabel_44.Text = 'mm';
            app.DoorOpeningWidthperSeatSpinner_7Label = uilabel(app.ErgonomicsPanel_7);
            app.DoorOpeningWidthperSeatSpinner_7Label.Text = 'Door Opening Width per Seat';
            app.DoorOpeningWidthperSeatSpinner_7 = uispinner(app.ErgonomicsPanel_7);
            app.mmLabel_45 = uilabel(app.ErgonomicsPanel_7);
            app.mmLabel_45.Text = 'mm';
            app.InteriorDimensionL53Spinner_7Label = uilabel(app.ErgonomicsPanel_7);
            app.InteriorDimensionL53Spinner_7Label.Text = 'Interior Dimension L53';
            app.InteriorDimensionL53Spinner_7 = uispinner(app.ErgonomicsPanel_7);
            app.mmLabel_46 = uilabel(app.ErgonomicsPanel_7);
            app.mmLabel_46.Text = 'mm';
            app.InteriorDimensionW3Spinner_7Label = uilabel(app.ErgonomicsPanel_7);
            app.InteriorDimensionW3Spinner_7Label.Text = 'Interior Dimension W3';
            app.InteriorDimensionW3Spinner_7 = uispinner(app.ErgonomicsPanel_7);
            app.mmLabel_47 = uilabel(app.ErgonomicsPanel_7);
            app.mmLabel_47.Text = 'mm';
            app.InteriorDimensionH61Spinner_7Label = uilabel(app.ErgonomicsPanel_7);
            app.InteriorDimensionH61Spinner_7Label.Text = 'Interior Dimension H61';
            app.InteriorDimensionH61Spinner_7 = uispinner(app.ErgonomicsPanel_7);
            app.dBLabel_7 = uilabel(app.ErgonomicsPanel_7);
            app.dBLabel_7.Text = 'dB';
            app.AveragedSoundPressureLevelSpinner_7Label = uilabel(app.ErgonomicsPanel_7);
            app.AveragedSoundPressureLevelSpinner_7Label.Text = 'Averaged Sound Pressure Level';
            app.AveragedSoundPressureLevelSpinner_7 = uispinner(app.ErgonomicsPanel_7);
            app.UsabilityPanel_2 = uipanel(app.TPforVCPanel);
            app.UsabilityPanel_2.Tooltip = {''};
            app.UsabilityPanel_2.Title = 'Usability';
            app.PassengersLabel_2 = uilabel(app.UsabilityPanel_2);
            app.PassengersLabel_2.Text = 'Passengers';
            app.LuggageSpaceLabel_2 = uilabel(app.UsabilityPanel_2);
            app.LuggageSpaceLabel_2.Text = 'Luggage Space';
            app.RadiusofMovementLabel_3 = uilabel(app.UsabilityPanel_2);
            app.RadiusofMovementLabel_3.Text = 'Radius of Movement';
            app.EconomyLabel_2 = uilabel(app.UsabilityPanel_2);
            app.EconomyLabel_2.Text = 'Economy';
            app.EcologyLabel_2 = uilabel(app.UsabilityPanel_2);
            app.EcologyLabel_2.Text = 'Ecology';
            app.BadRoadCapabilityLabel_3 = uilabel(app.UsabilityPanel_2);
            app.BadRoadCapabilityLabel_3.Text = 'Bad Road Capability';
            app.NumberofSeatsSpinner_2Label = uilabel(app.UsabilityPanel_2);
            app.NumberofSeatsSpinner_2Label.Text = 'Number of Seats';
            app.NumberofSeatsSpinner_2 = uispinner(app.UsabilityPanel_2);
            app.lLabel_2 = uilabel(app.UsabilityPanel_2);
            app.lLabel_2.Text = 'l';
            app.StorageVolumeSpinner_2Label = uilabel(app.UsabilityPanel_2);
            app.StorageVolumeSpinner_2Label.Text = 'Storage Volume';
            app.StorageVolumeSpinner_2 = uispinner(app.UsabilityPanel_2);
            app.kmLabel_2 = uilabel(app.UsabilityPanel_2);
            app.kmLabel_2.Text = 'km';
            app.RangeSpinner_2Label = uilabel(app.UsabilityPanel_2);
            app.RangeSpinner_2Label.Text = 'Range';
            app.RangeSpinner_2 = uispinner(app.UsabilityPanel_2);
            app.Label_4 = uilabel(app.UsabilityPanel_2);
            app.Label_4.Text = '€';
            app.AcquisitionCostsSpinner_2Label = uilabel(app.UsabilityPanel_2);
            app.AcquisitionCostsSpinner_2Label.Text = 'Acquisition Costs';
            app.AcquisitionCostsSpinner_2 = uispinner(app.UsabilityPanel_2);
            app.tCO2eqLabel_2 = uilabel(app.UsabilityPanel_2);
            app.tCO2eqLabel_2.Text = 'tCO2-eq.';
            app.ProductionEmissionsSpinner_2Label = uilabel(app.UsabilityPanel_2);
            app.ProductionEmissionsSpinner_2Label.Text = 'Production Emissions';
            app.ProductionEmissionsSpinner_2 = uispinner(app.UsabilityPanel_2);
            app.mmLabel_60 = uilabel(app.UsabilityPanel_2);
            app.mmLabel_60.Text = 'mm';
            app.GroundClearanceSpinner_2Label = uilabel(app.UsabilityPanel_2);
            app.GroundClearanceSpinner_2Label.Text = 'Ground Clearance';
            app.GroundClearanceSpinner_2 = uispinner(app.UsabilityPanel_2);
            app.UsabilityPanel_3 = uipanel(app.TPforVCPanel);
            app.UsabilityPanel_3.Tooltip = {''};
            app.UsabilityPanel_3.Title = 'Usability';
            app.PassengersLabel_3 = uilabel(app.UsabilityPanel_3);
            app.PassengersLabel_3.Text = 'Passengers';
            app.LuggageSpaceLabel_3 = uilabel(app.UsabilityPanel_3);
            app.LuggageSpaceLabel_3.Text = 'Luggage Space';
            app.RadiusofMovementLabel_4 = uilabel(app.UsabilityPanel_3);
            app.RadiusofMovementLabel_4.Text = 'Radius of Movement';
            app.EconomyLabel_3 = uilabel(app.UsabilityPanel_3);
            app.EconomyLabel_3.Text = 'Economy';
            app.EcologyLabel_3 = uilabel(app.UsabilityPanel_3);
            app.EcologyLabel_3.Text = 'Ecology';
            app.BadRoadCapabilityLabel_4 = uilabel(app.UsabilityPanel_3);
            app.BadRoadCapabilityLabel_4.Text = 'Bad Road Capability';
            app.NumberofSeatsSpinner_3Label = uilabel(app.UsabilityPanel_3);
            app.NumberofSeatsSpinner_3Label.Text = 'Number of Seats';
            app.NumberofSeatsSpinner_3 = uispinner(app.UsabilityPanel_3);
            app.lLabel_3 = uilabel(app.UsabilityPanel_3);
            app.lLabel_3.Text = 'l';
            app.StorageVolumeSpinner_3Label = uilabel(app.UsabilityPanel_3);
            app.StorageVolumeSpinner_3Label.Text = 'Storage Volume';
            app.StorageVolumeSpinner_3 = uispinner(app.UsabilityPanel_3);
            app.kmLabel_3 = uilabel(app.UsabilityPanel_3);
            app.kmLabel_3.Text = 'km';
            app.RangeSpinner_3Label = uilabel(app.UsabilityPanel_3);
            app.RangeSpinner_3Label.Text = 'Range';
            app.RangeSpinner_3 = uispinner(app.UsabilityPanel_3);
            app.Label_5 = uilabel(app.UsabilityPanel_3);
            app.Label_5.Text = '€';
            app.AcquisitionCostsSpinner_3Label = uilabel(app.UsabilityPanel_3);
            app.AcquisitionCostsSpinner_3Label.Text = 'Acquisition Costs';
            app.AcquisitionCostsSpinner_3 = uispinner(app.UsabilityPanel_3);
            app.tCO2eqLabel_3 = uilabel(app.UsabilityPanel_3);
            app.tCO2eqLabel_3.Text = 'tCO2-eq.';
            app.ProductionEmissionsSpinner_3Label = uilabel(app.UsabilityPanel_3);
            app.ProductionEmissionsSpinner_3Label.Text = 'Production Emissions';
            app.ProductionEmissionsSpinner_3 = uispinner(app.UsabilityPanel_3);
            app.mmLabel_61 = uilabel(app.UsabilityPanel_3);
            app.mmLabel_61.Text = 'mm';
            app.GroundClearanceSpinner_3Label = uilabel(app.UsabilityPanel_3);
            app.GroundClearanceSpinner_3Label.Text = 'Ground Clearance';
            app.GroundClearanceSpinner_3 = uispinner(app.UsabilityPanel_3);
            app.UsabilityPanel_4 = uipanel(app.TPforVCPanel);
            app.UsabilityPanel_4.Tooltip = {''};
            app.UsabilityPanel_4.Title = 'Usability';
            app.PassengersLabel_4 = uilabel(app.UsabilityPanel_4);
            app.PassengersLabel_4.Text = 'Passengers';
            app.LuggageSpaceLabel_4 = uilabel(app.UsabilityPanel_4);
            app.LuggageSpaceLabel_4.Text = 'Luggage Space';
            app.RadiusofMovementLabel_5 = uilabel(app.UsabilityPanel_4);
            app.RadiusofMovementLabel_5.Text = 'Radius of Movement';
            app.EconomyLabel_4 = uilabel(app.UsabilityPanel_4);
            app.EconomyLabel_4.Text = 'Economy';
            app.EcologyLabel_4 = uilabel(app.UsabilityPanel_4);
            app.EcologyLabel_4.Text = 'Ecology';
            app.BadRoadCapabilityLabel_5 = uilabel(app.UsabilityPanel_4);
            app.BadRoadCapabilityLabel_5.Text = 'Bad Road Capability';
            app.NumberofSeatsSpinner_4Label = uilabel(app.UsabilityPanel_4);
            app.NumberofSeatsSpinner_4Label.Text = 'Number of Seats';
            app.NumberofSeatsSpinner_4 = uispinner(app.UsabilityPanel_4);
            app.lLabel_4 = uilabel(app.UsabilityPanel_4);
            app.lLabel_4.Text = 'l';
            app.StorageVolumeSpinner_4Label = uilabel(app.UsabilityPanel_4);
            app.StorageVolumeSpinner_4Label.Text = 'Storage Volume';
            app.StorageVolumeSpinner_4 = uispinner(app.UsabilityPanel_4);
            app.kmLabel_4 = uilabel(app.UsabilityPanel_4);
            app.kmLabel_4.Text = 'km';
            app.RangeSpinner_4Label = uilabel(app.UsabilityPanel_4);
            app.RangeSpinner_4Label.Text = 'Range';
            app.RangeSpinner_4 = uispinner(app.UsabilityPanel_4);
            app.Label_6 = uilabel(app.UsabilityPanel_4);
            app.Label_6.Text = '€';
            app.AcquisitionCostsSpinner_4Label = uilabel(app.UsabilityPanel_4);
            app.AcquisitionCostsSpinner_4Label.Text = 'Acquisition Costs';
            app.AcquisitionCostsSpinner_4 = uispinner(app.UsabilityPanel_4);
            app.tCO2eqLabel_4 = uilabel(app.UsabilityPanel_4);
            app.tCO2eqLabel_4.Text = 'tCO2-eq.';
            app.ProductionEmissionsSpinner_4Label = uilabel(app.UsabilityPanel_4);
            app.ProductionEmissionsSpinner_4Label.Text = 'Production Emissions';
            app.ProductionEmissionsSpinner_4 = uispinner(app.UsabilityPanel_4);
            app.mmLabel_62 = uilabel(app.UsabilityPanel_4);
            app.mmLabel_62.Text = 'mm';
            app.GroundClearanceSpinner_4Label = uilabel(app.UsabilityPanel_4);
            app.GroundClearanceSpinner_4Label.Text = 'Ground Clearance';
            app.GroundClearanceSpinner_4 = uispinner(app.UsabilityPanel_4);
            app.UsabilityPanel_5 = uipanel(app.TPforVCPanel);
            app.UsabilityPanel_5.Tooltip = {''};
            app.UsabilityPanel_5.Title = 'Usability';
            app.PassengersLabel_5 = uilabel(app.UsabilityPanel_5);
            app.PassengersLabel_5.Text = 'Passengers';
            app.LuggageSpaceLabel_5 = uilabel(app.UsabilityPanel_5);
            app.LuggageSpaceLabel_5.Text = 'Luggage Space';
            app.RadiusofMovementLabel_6 = uilabel(app.UsabilityPanel_5);
            app.RadiusofMovementLabel_6.Text = 'Radius of Movement';
            app.EconomyLabel_5 = uilabel(app.UsabilityPanel_5);
            app.EconomyLabel_5.Text = 'Economy';
            app.EcologyLabel_5 = uilabel(app.UsabilityPanel_5);
            app.EcologyLabel_5.Text = 'Ecology';
            app.BadRoadCapabilityLabel_6 = uilabel(app.UsabilityPanel_5);
            app.BadRoadCapabilityLabel_6.Text = 'Bad Road Capability';
            app.NumberofSeatsSpinner_5Label = uilabel(app.UsabilityPanel_5);
            app.NumberofSeatsSpinner_5Label.Text = 'Number of Seats';
            app.NumberofSeatsSpinner_5 = uispinner(app.UsabilityPanel_5);
            app.lLabel_5 = uilabel(app.UsabilityPanel_5);
            app.lLabel_5.Text = 'l';
            app.StorageVolumeSpinner_5Label = uilabel(app.UsabilityPanel_5);
            app.StorageVolumeSpinner_5Label.Text = 'Storage Volume';
            app.StorageVolumeSpinner_5 = uispinner(app.UsabilityPanel_5);
            app.kmLabel_5 = uilabel(app.UsabilityPanel_5);
            app.kmLabel_5.Text = 'km';
            app.RangeSpinner_5Label = uilabel(app.UsabilityPanel_5);
            app.RangeSpinner_5Label.Text = 'Range';
            app.RangeSpinner_5 = uispinner(app.UsabilityPanel_5);
            app.Label_7 = uilabel(app.UsabilityPanel_5);
            app.Label_7.Text = '€';
            app.AcquisitionCostsSpinner_5Label = uilabel(app.UsabilityPanel_5);
            app.AcquisitionCostsSpinner_5Label.Text = 'Acquisition Costs';
            app.AcquisitionCostsSpinner_5 = uispinner(app.UsabilityPanel_5);
            app.tCO2eqLabel_5 = uilabel(app.UsabilityPanel_5);
            app.tCO2eqLabel_5.Text = 'tCO2-eq.';
            app.ProductionEmissionsSpinner_5Label = uilabel(app.UsabilityPanel_5);
            app.ProductionEmissionsSpinner_5Label.Text = 'Production Emissions';
            app.ProductionEmissionsSpinner_5 = uispinner(app.UsabilityPanel_5);
            app.mmLabel_63 = uilabel(app.UsabilityPanel_5);
            app.mmLabel_63.Text = 'mm';
            app.GroundClearanceSpinner_5Label = uilabel(app.UsabilityPanel_5);
            app.GroundClearanceSpinner_5Label.Text = 'Ground Clearance';
            app.GroundClearanceSpinner_5 = uispinner(app.UsabilityPanel_5);
            app.UsabilityPanel_6 = uipanel(app.TPforVCPanel);
            app.UsabilityPanel_6.Tooltip = {''};
            app.UsabilityPanel_6.Title = 'Usability';
            app.PassengersLabel_6 = uilabel(app.UsabilityPanel_6);
            app.PassengersLabel_6.Text = 'Passengers';
            app.LuggageSpaceLabel_6 = uilabel(app.UsabilityPanel_6);
            app.LuggageSpaceLabel_6.Text = 'Luggage Space';
            app.RadiusofMovementLabel_7 = uilabel(app.UsabilityPanel_6);
            app.RadiusofMovementLabel_7.Text = 'Radius of Movement';
            app.EconomyLabel_6 = uilabel(app.UsabilityPanel_6);
            app.EconomyLabel_6.Text = 'Economy';
            app.EcologyLabel_6 = uilabel(app.UsabilityPanel_6);
            app.EcologyLabel_6.Text = 'Ecology';
            app.BadRoadCapabilityLabel_7 = uilabel(app.UsabilityPanel_6);
            app.BadRoadCapabilityLabel_7.Text = 'Bad Road Capability';
            app.NumberofSeatsSpinner_6Label = uilabel(app.UsabilityPanel_6);
            app.NumberofSeatsSpinner_6Label.Text = 'Number of Seats';
            app.NumberofSeatsSpinner_6 = uispinner(app.UsabilityPanel_6);
            app.lLabel_6 = uilabel(app.UsabilityPanel_6);
            app.lLabel_6.Text = 'l';
            app.StorageVolumeSpinner_6Label = uilabel(app.UsabilityPanel_6);
            app.StorageVolumeSpinner_6Label.Text = 'Storage Volume';
            app.StorageVolumeSpinner_6 = uispinner(app.UsabilityPanel_6);
            app.kmLabel_6 = uilabel(app.UsabilityPanel_6);
            app.kmLabel_6.Text = 'km';
            app.RangeSpinner_6Label = uilabel(app.UsabilityPanel_6);
            app.RangeSpinner_6Label.Text = 'Range';
            app.RangeSpinner_6 = uispinner(app.UsabilityPanel_6);
            app.Label_8 = uilabel(app.UsabilityPanel_6);
            app.Label_8.Text = '€';
            app.AcquisitionCostsSpinner_6Label = uilabel(app.UsabilityPanel_6);
            app.AcquisitionCostsSpinner_6Label.Text = 'Acquisition Costs';
            app.AcquisitionCostsSpinner_6 = uispinner(app.UsabilityPanel_6);
            app.tCO2eqLabel_6 = uilabel(app.UsabilityPanel_6);
            app.tCO2eqLabel_6.Text = 'tCO2-eq.';
            app.ProductionEmissionsSpinner_6Label = uilabel(app.UsabilityPanel_6);
            app.ProductionEmissionsSpinner_6Label.Text = 'Production Emissions';
            app.ProductionEmissionsSpinner_6 = uispinner(app.UsabilityPanel_6);
            app.mmLabel_64 = uilabel(app.UsabilityPanel_6);
            app.mmLabel_64.Text = 'mm';
            app.GroundClearanceSpinner_6Label = uilabel(app.UsabilityPanel_6);
            app.GroundClearanceSpinner_6Label.Text = 'Ground Clearance';
            app.GroundClearanceSpinner_6 = uispinner(app.UsabilityPanel_6);
            app.UsabilityPanel_7 = uipanel(app.TPforVCPanel);
            app.UsabilityPanel_7.Tooltip = {''};
            app.UsabilityPanel_7.Title = 'Usability';
            app.PassengersLabel_7 = uilabel(app.UsabilityPanel_7);
            app.PassengersLabel_7.Text = 'Passengers';
            app.LuggageSpaceLabel_7 = uilabel(app.UsabilityPanel_7);
            app.LuggageSpaceLabel_7.Text = 'Luggage Space';
            app.RadiusofMovementLabel_8 = uilabel(app.UsabilityPanel_7);
            app.RadiusofMovementLabel_8.Text = 'Radius of Movement';
            app.EconomyLabel_7 = uilabel(app.UsabilityPanel_7);
            app.EconomyLabel_7.Text = 'Economy';
            app.EcologyLabel_7 = uilabel(app.UsabilityPanel_7);
            app.EcologyLabel_7.Text = 'Ecology';
            app.BadRoadCapabilityLabel_8 = uilabel(app.UsabilityPanel_7);
            app.BadRoadCapabilityLabel_8.Text = 'Bad Road Capability';
            app.NumberofSeatsSpinner_7Label = uilabel(app.UsabilityPanel_7);
            app.NumberofSeatsSpinner_7Label.Text = 'Number of Seats';
            app.NumberofSeatsSpinner_7 = uispinner(app.UsabilityPanel_7);
            app.lLabel_7 = uilabel(app.UsabilityPanel_7);
            app.lLabel_7.Text = 'l';
            app.StorageVolumeSpinner_7Label = uilabel(app.UsabilityPanel_7);
            app.StorageVolumeSpinner_7Label.Text = 'Storage Volume';
            app.StorageVolumeSpinner_7 = uispinner(app.UsabilityPanel_7);
            app.kmLabel_7 = uilabel(app.UsabilityPanel_7);
            app.kmLabel_7.Text = 'km';
            app.RangeSpinner_7Label = uilabel(app.UsabilityPanel_7);
            app.RangeSpinner_7Label.Text = 'Range';
            app.RangeSpinner_7 = uispinner(app.UsabilityPanel_7);
            app.Label_9 = uilabel(app.UsabilityPanel_7);
            app.Label_9.Text = '€';
            app.AcquisitionCostsSpinner_7Label = uilabel(app.UsabilityPanel_7);
            app.AcquisitionCostsSpinner_7Label.Text = 'Acquisition Costs';
            app.AcquisitionCostsSpinner_7 = uispinner(app.UsabilityPanel_7);
            app.tCO2eqLabel_7 = uilabel(app.UsabilityPanel_7);
            app.tCO2eqLabel_7.Text = 'tCO2-eq.';
            app.ProductionEmissionsSpinner_7Label = uilabel(app.UsabilityPanel_7);
            app.ProductionEmissionsSpinner_7Label.Text = 'Production Emissions';

            app.ProductionEmissionsSpinner_7 = uispinner(app.UsabilityPanel_7);
            app.mmLabel_65 = uilabel(app.UsabilityPanel_7);
            app.mmLabel_65.Text = 'mm';
            app.GroundClearanceSpinner_7Label = uilabel(app.UsabilityPanel_7);
            app.GroundClearanceSpinner_7Label.Text = 'Ground Clearance';
            app.GroundClearanceSpinner_7 = uispinner(app.UsabilityPanel_7);
            app.LuxuryPanel_2 = uipanel(app.TPforVCPanel);
            app.LuxuryPanel_2.Tooltip = {''};
            app.LuxuryPanel_2.Title = 'Luxury';
            app.InfotainmentIndividualizationLabel_3 = uilabel(app.LuxuryPanel_2);
            app.InfotainmentIndividualizationLabel_3.Text = 'Infotainment Individualization';
            app.ExteriorDesignLabel_2 = uilabel(app.LuxuryPanel_2);
            app.ExteriorDesignLabel_2.Text = 'Exterior Design';
            app.BuiltInInfotainmentLabel_2 = uilabel(app.LuxuryPanel_2);
            app.BuiltInInfotainmentLabel_2.Text = 'Built-In Infotainment';
            app.cmLabel_2 = uilabel(app.LuxuryPanel_2);
            app.cmLabel_2.Text = 'cm²';
            app.ScreenAreaperSeatSpinner_2Label = uilabel(app.LuxuryPanel_2);
            app.ScreenAreaperSeatSpinner_2Label.Text = 'Screen Area per Seat';
            app.ScreenAreaperSeatSpinner_2 = uispinner(app.LuxuryPanel_2);
            app.mmLabel_66 = uilabel(app.LuxuryPanel_2);
            app.mmLabel_66.Text = 'mm';
            app.WheelDiameterSpinner_2Label = uilabel(app.LuxuryPanel_2);
            app.WheelDiameterSpinner_2Label.Text = 'Wheel Diameter';
            app.WheelDiameterSpinner_2 = uispinner(app.LuxuryPanel_2);
            app.ResearchontechnicalpropertypendingLabel_16 = uilabel(app.LuxuryPanel_2);
            app.ResearchontechnicalpropertypendingLabel_16.Text = 'Research on technical property pending';
            app.LuxuryPanel_3 = uipanel(app.TPforVCPanel);
            app.LuxuryPanel_3.Tooltip = {''};
            app.LuxuryPanel_3.Title = 'Luxury';
            app.InfotainmentIndividualizationLabel_4 = uilabel(app.LuxuryPanel_3);
            app.InfotainmentIndividualizationLabel_4.Text = 'Infotainment Individualization';
            app.ExteriorDesignLabel_3 = uilabel(app.LuxuryPanel_3);
            app.ExteriorDesignLabel_3.Text = 'Exterior Design';
            app.BuiltInInfotainmentLabel_3 = uilabel(app.LuxuryPanel_3);
            app.BuiltInInfotainmentLabel_3.Text = 'Built-In Infotainment';
            app.cmLabel_3 = uilabel(app.LuxuryPanel_3);
            app.cmLabel_3.Text = 'cm²';
            app.ScreenAreaperSeatSpinner_3Label = uilabel(app.LuxuryPanel_3);
            app.ScreenAreaperSeatSpinner_3Label.Text = 'Screen Area per Seat';
            app.ScreenAreaperSeatSpinner_3 = uispinner(app.LuxuryPanel_3);
            app.mmLabel_67 = uilabel(app.LuxuryPanel_3);
            app.mmLabel_67.Text = 'mm';
            app.WheelDiameterSpinner_3Label = uilabel(app.LuxuryPanel_3);
            app.WheelDiameterSpinner_3Label.Text = 'Wheel Diameter';
            app.WheelDiameterSpinner_3 = uispinner(app.LuxuryPanel_3);
            app.ResearchontechnicalpropertypendingLabel_17 = uilabel(app.LuxuryPanel_3);
            app.ResearchontechnicalpropertypendingLabel_17.Text = 'Research on technical property pending';
            app.LuxuryPanel_4 = uipanel(app.TPforVCPanel);
            app.LuxuryPanel_4.Tooltip = {''};
            app.LuxuryPanel_4.Title = 'Luxury';
            app.InfotainmentIndividualizationLabel_5 = uilabel(app.LuxuryPanel_4);
            app.InfotainmentIndividualizationLabel_5.Text = 'Infotainment Individualization';
            app.ExteriorDesignLabel_4 = uilabel(app.LuxuryPanel_4);
            app.ExteriorDesignLabel_4.Text = 'Exterior Design';
            app.BuiltInInfotainmentLabel_4 = uilabel(app.LuxuryPanel_4);
            app.BuiltInInfotainmentLabel_4.Text = 'Built-In Infotainment';
            app.cmLabel_4 = uilabel(app.LuxuryPanel_4);
            app.cmLabel_4.Text = 'cm²';
            app.ScreenAreaperSeatSpinner_4Label = uilabel(app.LuxuryPanel_4);
            app.ScreenAreaperSeatSpinner_4Label.Text = 'Screen Area per Seat';
            app.ScreenAreaperSeatSpinner_4 = uispinner(app.LuxuryPanel_4);
            app.mmLabel_68 = uilabel(app.LuxuryPanel_4);
            app.mmLabel_68.Text = 'mm';
            app.WheelDiameterSpinner_4Label = uilabel(app.LuxuryPanel_4);
            app.WheelDiameterSpinner_4Label.Text = 'Wheel Diameter';
            app.WheelDiameterSpinner_4 = uispinner(app.LuxuryPanel_4);
            app.ResearchontechnicalpropertypendingLabel_18 = uilabel(app.LuxuryPanel_4);
            app.ResearchontechnicalpropertypendingLabel_18.Text = 'Research on technical property pending';
            app.LuxuryPanel_5 = uipanel(app.TPforVCPanel);
            app.LuxuryPanel_5.Tooltip = {''};
            app.LuxuryPanel_5.Title = 'Luxury';
            app.InfotainmentIndividualizationLabel_6 = uilabel(app.LuxuryPanel_5);
            app.InfotainmentIndividualizationLabel_6.Text = 'Infotainment Individualization';
            app.ExteriorDesignLabel_5 = uilabel(app.LuxuryPanel_5);
            app.ExteriorDesignLabel_5.Text = 'Exterior Design';
            app.BuiltInInfotainmentLabel_5 = uilabel(app.LuxuryPanel_5);
            app.BuiltInInfotainmentLabel_5.Text = 'Built-In Infotainment';
            app.cmLabel_5 = uilabel(app.LuxuryPanel_5);
            app.cmLabel_5.Text = 'cm²';
            app.ScreenAreaperSeatSpinner_5Label = uilabel(app.LuxuryPanel_5);
            app.ScreenAreaperSeatSpinner_5Label.Text = 'Screen Area per Seat';
            app.ScreenAreaperSeatSpinner_5 = uispinner(app.LuxuryPanel_5);
            app.mmLabel_69 = uilabel(app.LuxuryPanel_5);
            app.mmLabel_69.Text = 'mm';
            app.WheelDiameterSpinner_5Label = uilabel(app.LuxuryPanel_5);
            app.WheelDiameterSpinner_5Label.Text = 'Wheel Diameter';
            app.WheelDiameterSpinner_5 = uispinner(app.LuxuryPanel_5);
            app.ResearchontechnicalpropertypendingLabel_19 = uilabel(app.LuxuryPanel_5);
            app.ResearchontechnicalpropertypendingLabel_19.Text = 'Research on technical property pending';
            app.LuxuryPanel_6 = uipanel(app.TPforVCPanel);
            app.LuxuryPanel_6.Tooltip = {''};
            app.LuxuryPanel_6.Title = 'Luxury';
            app.InfotainmentIndividualizationLabel_7 = uilabel(app.LuxuryPanel_6);
            app.InfotainmentIndividualizationLabel_7.Text = 'Infotainment Individualization';
            app.ExteriorDesignLabel_6 = uilabel(app.LuxuryPanel_6);
            app.ExteriorDesignLabel_6.Text = 'Exterior Design';
            app.BuiltInInfotainmentLabel_6 = uilabel(app.LuxuryPanel_6);
            app.BuiltInInfotainmentLabel_6.Text = 'Built-In Infotainment';
            app.cmLabel_6 = uilabel(app.LuxuryPanel_6);
            app.cmLabel_6.Text = 'cm²';
            app.ScreenAreaperSeatSpinner_6Label = uilabel(app.LuxuryPanel_6);
            app.ScreenAreaperSeatSpinner_6Label.Text = 'Screen Area per Seat';
            app.ScreenAreaperSeatSpinner_6 = uispinner(app.LuxuryPanel_6);
            app.mmLabel_70 = uilabel(app.LuxuryPanel_6);
            app.mmLabel_70.Text = 'mm';
            app.WheelDiameterSpinner_6Label = uilabel(app.LuxuryPanel_6);
            app.WheelDiameterSpinner_6Label.Text = 'Wheel Diameter';
            app.WheelDiameterSpinner_6 = uispinner(app.LuxuryPanel_6);
            app.ResearchontechnicalpropertypendingLabel_20 = uilabel(app.LuxuryPanel_6);
            app.ResearchontechnicalpropertypendingLabel_20.Text = 'Research on technical property pending';
            app.LuxuryPanel_7 = uipanel(app.TPforVCPanel);
            app.LuxuryPanel_7.Tooltip = {''};
            app.LuxuryPanel_7.Title = 'Luxury';
            app.InfotainmentIndividualizationLabel_8 = uilabel(app.LuxuryPanel_7);
            app.InfotainmentIndividualizationLabel_8.Text = 'Infotainment Individualization';
            app.ExteriorDesignLabel_7 = uilabel(app.LuxuryPanel_7);
            app.ExteriorDesignLabel_7.Text = 'Exterior Design';
            app.BuiltInInfotainmentLabel_7 = uilabel(app.LuxuryPanel_7);
            app.BuiltInInfotainmentLabel_7.Text = 'Built-In Infotainment';
            app.cmLabel_7 = uilabel(app.LuxuryPanel_7);
            app.cmLabel_7.Text = 'cm²';
            app.ScreenAreaperSeatSpinner_7Label = uilabel(app.LuxuryPanel_7);
            app.ScreenAreaperSeatSpinner_7Label.Text = 'Screen Area per Seat';
            app.ScreenAreaperSeatSpinner_7 = uispinner(app.LuxuryPanel_7);
            app.mmLabel_71 = uilabel(app.LuxuryPanel_7);
            app.mmLabel_71.Text = 'mm';
            app.WheelDiameterSpinner_7Label = uilabel(app.LuxuryPanel_7);
            app.WheelDiameterSpinner_7Label.Text = 'Wheel Diameter';
            app.WheelDiameterSpinner_7 = uispinner(app.LuxuryPanel_7);
            app.ResearchontechnicalpropertypendingLabel_21 = uilabel(app.LuxuryPanel_7);
            app.ResearchontechnicalpropertypendingLabel_21.Text = 'Research on technical property pending';
            app.DynamicsPanel_2 = uipanel(app.TPforVCPanel);
            app.DynamicsPanel_2.Tooltip = {''};
            app.DynamicsPanel_2.Title = 'Dynamics';
            app.Accelerationfrom0kmhto100kmhSpinner_2Label = uilabel(app.DynamicsPanel_2);
            app.Accelerationfrom0kmhto100kmhSpinner_2Label.Text = 'Acceleration from 0km/h to 100km/h';
            app.Accelerationfrom0kmhto100kmhSpinner_2 = uispinner(app.DynamicsPanel_2);
            app.sLabel_9 = uilabel(app.DynamicsPanel_2);
            app.sLabel_9.Text = 's';
            app.TopSpeedSpinner_2Label = uilabel(app.DynamicsPanel_2);
            app.TopSpeedSpinner_2Label.Text = 'Top Speed';
            app.TopSpeedSpinner_2 = uispinner(app.DynamicsPanel_2);
            app.kmhLabel_2 = uilabel(app.DynamicsPanel_2);
            app.kmhLabel_2.Text = 'km/h';
            app.LongitunalDynamicsLabel_2 = uilabel(app.DynamicsPanel_2);
            app.LongitunalDynamicsLabel_2.Text = 'Longitunal Dynamics';
            app.mmLabel_72 = uilabel(app.DynamicsPanel_2);
            app.mmLabel_72.Text = 'mm';
            app.HeightoftheCenterofGravitySpinner_2Label = uilabel(app.DynamicsPanel_2);
            app.HeightoftheCenterofGravitySpinner_2Label.Text = 'Height of the Center of Gravity';
            app.HeightoftheCenterofGravitySpinner_2 = uispinner(app.DynamicsPanel_2);
            app.LateralDynamicsLabel_2 = uilabel(app.DynamicsPanel_2);
            app.LateralDynamicsLabel_2.Text = 'Lateral Dynamics';
            app.VerticalAccelerationSpinner_2Label = uilabel(app.DynamicsPanel_2);
            app.VerticalAccelerationSpinner_2Label.Text = 'Vertical Acceleration';
            app.VerticalAccelerationSpinner_2 = uispinner(app.DynamicsPanel_2);
            app.msLabel_16 = uilabel(app.DynamicsPanel_2);
            app.msLabel_16.Text = 'm/s²';
            app.VerticalDynamicsLabel_2 = uilabel(app.DynamicsPanel_2);
            app.VerticalDynamicsLabel_2.Text = 'Vertical Dynamics';
            app.TurningCircleSpinner_2Label = uilabel(app.DynamicsPanel_2);
            app.TurningCircleSpinner_2Label.Text = 'Turning Circle';
            app.TurningCircleSpinner_2 = uispinner(app.DynamicsPanel_2);
            app.mLabel_9 = uilabel(app.DynamicsPanel_2);
            app.mLabel_9.Text = 'm';
            app.ManeuverabilityLabel_2 = uilabel(app.DynamicsPanel_2);
            app.ManeuverabilityLabel_2.Text = 'Maneuverability';
            app.DynamicsPanel_3 = uipanel(app.TPforVCPanel);
            app.DynamicsPanel_3.Tooltip = {''};
            app.DynamicsPanel_3.Title = 'Dynamics';
            app.Accelerationfrom0kmhto100kmhSpinner_3Label = uilabel(app.DynamicsPanel_3);
            app.Accelerationfrom0kmhto100kmhSpinner_3Label.Text = 'Acceleration from 0km/h to 100km/h';
            app.Accelerationfrom0kmhto100kmhSpinner_3 = uispinner(app.DynamicsPanel_3);
            app.sLabel_10 = uilabel(app.DynamicsPanel_3);
            app.sLabel_10.Text = 's';
            app.TopSpeedSpinner_3Label = uilabel(app.DynamicsPanel_3);
            app.TopSpeedSpinner_3Label.Text = 'Top Speed';
            app.TopSpeedSpinner_3 = uispinner(app.DynamicsPanel_3);
            app.kmhLabel_3 = uilabel(app.DynamicsPanel_3);
            app.kmhLabel_3.Text = 'km/h';
            app.LongitunalDynamicsLabel_3 = uilabel(app.DynamicsPanel_3);
            app.LongitunalDynamicsLabel_3.Text = 'Longitunal Dynamics';
            app.mmLabel_73 = uilabel(app.DynamicsPanel_3);
            app.mmLabel_73.Text = 'mm';
            app.HeightoftheCenterofGravitySpinner_3Label = uilabel(app.DynamicsPanel_3);
            app.HeightoftheCenterofGravitySpinner_3Label.Text = 'Height of the Center of Gravity';
            app.HeightoftheCenterofGravitySpinner_3 = uispinner(app.DynamicsPanel_3);
            app.LateralDynamicsLabel_3 = uilabel(app.DynamicsPanel_3);
            app.LateralDynamicsLabel_3.Text = 'Lateral Dynamics';
            app.VerticalAccelerationSpinner_3Label = uilabel(app.DynamicsPanel_3);
            app.VerticalAccelerationSpinner_3Label.Text = 'Vertical Acceleration';
            app.VerticalAccelerationSpinner_3 = uispinner(app.DynamicsPanel_3);
            app.msLabel_17 = uilabel(app.DynamicsPanel_3);
            app.msLabel_17.Text = 'm/s²';
            app.VerticalDynamicsLabel_3 = uilabel(app.DynamicsPanel_3);
            app.VerticalDynamicsLabel_3.Text = 'Vertical Dynamics';
            app.TurningCircleSpinner_3Label = uilabel(app.DynamicsPanel_3);
            app.TurningCircleSpinner_3Label.Text = 'Turning Circle';
            app.TurningCircleSpinner_3 = uispinner(app.DynamicsPanel_3);
            app.mLabel_10 = uilabel(app.DynamicsPanel_3);
            app.mLabel_10.Text = 'm';
            app.ManeuverabilityLabel_3 = uilabel(app.DynamicsPanel_3);
            app.ManeuverabilityLabel_3.Text = 'Maneuverability';
            app.DynamicsPanel_4 = uipanel(app.TPforVCPanel);
            app.DynamicsPanel_4.Tooltip = {''};
            app.DynamicsPanel_4.Title = 'Dynamics';
            app.Accelerationfrom0kmhto100kmhSpinner_4Label = uilabel(app.DynamicsPanel_4);
            app.Accelerationfrom0kmhto100kmhSpinner_4Label.Text = 'Acceleration from 0km/h to 100km/h';
            app.Accelerationfrom0kmhto100kmhSpinner_4 = uispinner(app.DynamicsPanel_4);
            app.sLabel_11 = uilabel(app.DynamicsPanel_4);
            app.sLabel_11.Text = 's';
            app.TopSpeedSpinner_4Label = uilabel(app.DynamicsPanel_4);
            app.TopSpeedSpinner_4Label.Text = 'Top Speed';
            app.TopSpeedSpinner_4 = uispinner(app.DynamicsPanel_4);
            app.kmhLabel_4 = uilabel(app.DynamicsPanel_4);
            app.kmhLabel_4.Text = 'km/h';
            app.LongitunalDynamicsLabel_4 = uilabel(app.DynamicsPanel_4);
            app.LongitunalDynamicsLabel_4.Text = 'Longitunal Dynamics';
            app.mmLabel_74 = uilabel(app.DynamicsPanel_4);
            app.mmLabel_74.Text = 'mm';
            app.HeightoftheCenterofGravitySpinner_4Label = uilabel(app.DynamicsPanel_4);
            app.HeightoftheCenterofGravitySpinner_4Label.Text = 'Height of the Center of Gravity';
            app.HeightoftheCenterofGravitySpinner_4 = uispinner(app.DynamicsPanel_4);
            app.LateralDynamicsLabel_4 = uilabel(app.DynamicsPanel_4);
            app.LateralDynamicsLabel_4.Text = 'Lateral Dynamics';
            app.VerticalAccelerationSpinner_4Label = uilabel(app.DynamicsPanel_4);
            app.VerticalAccelerationSpinner_4Label.Text = 'Vertical Acceleration';
            app.VerticalAccelerationSpinner_4 = uispinner(app.DynamicsPanel_4);
            app.msLabel_18 = uilabel(app.DynamicsPanel_4);
            app.msLabel_18.Text = 'm/s²';
            app.VerticalDynamicsLabel_4 = uilabel(app.DynamicsPanel_4);
            app.VerticalDynamicsLabel_4.Text = 'Vertical Dynamics';
            app.TurningCircleSpinner_4Label = uilabel(app.DynamicsPanel_4);
            app.TurningCircleSpinner_4Label.Text = 'Turning Circle';
            app.TurningCircleSpinner_4 = uispinner(app.DynamicsPanel_4);
            app.mLabel_11 = uilabel(app.DynamicsPanel_4);
            app.mLabel_11.Text = 'm';
            app.ManeuverabilityLabel_4 = uilabel(app.DynamicsPanel_4);
            app.ManeuverabilityLabel_4.Text = 'Maneuverability';
            app.DynamicsPanel_5 = uipanel(app.TPforVCPanel);
            app.DynamicsPanel_5.Tooltip = {'.'};
            app.DynamicsPanel_5.Title = 'Dynamics';
            app.Accelerationfrom0kmhto100kmhSpinner_5Label = uilabel(app.DynamicsPanel_5);
            app.Accelerationfrom0kmhto100kmhSpinner_5Label.Text = 'Acceleration from 0km/h to 100km/h';
            app.Accelerationfrom0kmhto100kmhSpinner_5 = uispinner(app.DynamicsPanel_5);
            app.sLabel_12 = uilabel(app.DynamicsPanel_5);
            app.sLabel_12.Text = 's';
            app.TopSpeedSpinner_5Label = uilabel(app.DynamicsPanel_5);
            app.TopSpeedSpinner_5Label.Text = 'Top Speed';
            app.TopSpeedSpinner_5 = uispinner(app.DynamicsPanel_5);
            app.kmhLabel_5 = uilabel(app.DynamicsPanel_5);
            app.kmhLabel_5.Text = 'km/h';
            app.LongitunalDynamicsLabel_5 = uilabel(app.DynamicsPanel_5);
            app.LongitunalDynamicsLabel_5.Text = 'Longitunal Dynamics';
            app.mmLabel_75 = uilabel(app.DynamicsPanel_5);
            app.mmLabel_75.Text = 'mm';
            app.HeightoftheCenterofGravitySpinner_5Label = uilabel(app.DynamicsPanel_5);
            app.HeightoftheCenterofGravitySpinner_5Label.Text = 'Height of the Center of Gravity';
            app.HeightoftheCenterofGravitySpinner_5 = uispinner(app.DynamicsPanel_5);
            app.LateralDynamicsLabel_5 = uilabel(app.DynamicsPanel_5);
            app.LateralDynamicsLabel_5.Text = 'Lateral Dynamics';
            app.VerticalAccelerationSpinner_5Label = uilabel(app.DynamicsPanel_5);
            app.VerticalAccelerationSpinner_5Label.Text = 'Vertical Acceleration';
            app.VerticalAccelerationSpinner_5 = uispinner(app.DynamicsPanel_5);
            app.msLabel_19 = uilabel(app.DynamicsPanel_5);
            app.msLabel_19.Text = 'm/s²';
            app.VerticalDynamicsLabel_5 = uilabel(app.DynamicsPanel_5);
            app.VerticalDynamicsLabel_5.Text = 'Vertical Dynamics';
            app.TurningCircleSpinner_5Label = uilabel(app.DynamicsPanel_5);
            app.TurningCircleSpinner_5Label.Text = 'Turning Circle';
            app.TurningCircleSpinner_5 = uispinner(app.DynamicsPanel_5);
            app.mLabel_12 = uilabel(app.DynamicsPanel_5);
            app.mLabel_12.Text = 'm';
            app.ManeuverabilityLabel_5 = uilabel(app.DynamicsPanel_5);
            app.ManeuverabilityLabel_5.Text = 'Maneuverability';
            app.DynamicsPanel_6 = uipanel(app.TPforVCPanel);
            app.DynamicsPanel_6.Tooltip = {''};
            app.DynamicsPanel_6.Title = 'Dynamics';
            app.Accelerationfrom0kmhto100kmhSpinner_6Label = uilabel(app.DynamicsPanel_6);
            app.Accelerationfrom0kmhto100kmhSpinner_6Label.Text = 'Acceleration from 0km/h to 100km/h';
            app.Accelerationfrom0kmhto100kmhSpinner_6 = uispinner(app.DynamicsPanel_6);
            app.sLabel_13 = uilabel(app.DynamicsPanel_6);
            app.sLabel_13.Text = 's';
            app.TopSpeedSpinner_6Label = uilabel(app.DynamicsPanel_6);
            app.TopSpeedSpinner_6Label.Text = 'Top Speed';
            app.TopSpeedSpinner_6 = uispinner(app.DynamicsPanel_6);
            app.kmhLabel_6 = uilabel(app.DynamicsPanel_6);
            app.kmhLabel_6.Text = 'km/h';
            app.LongitunalDynamicsLabel_6 = uilabel(app.DynamicsPanel_6);
            app.LongitunalDynamicsLabel_6.Text = 'Longitunal Dynamics';
            app.mmLabel_76 = uilabel(app.DynamicsPanel_6);
            app.mmLabel_76.Text = 'mm';
            app.HeightoftheCenterofGravitySpinner_6Label = uilabel(app.DynamicsPanel_6);
            app.HeightoftheCenterofGravitySpinner_6Label.Text = 'Height of the Center of Gravity';
            app.HeightoftheCenterofGravitySpinner_6 = uispinner(app.DynamicsPanel_6);
            app.LateralDynamicsLabel_6 = uilabel(app.DynamicsPanel_6);
            app.LateralDynamicsLabel_6.Text = 'Lateral Dynamics';
            app.VerticalAccelerationSpinner_6Label = uilabel(app.DynamicsPanel_6);
            app.VerticalAccelerationSpinner_6Label.Text = 'Vertical Acceleration';
            app.VerticalAccelerationSpinner_6 = uispinner(app.DynamicsPanel_6);
            app.msLabel_20 = uilabel(app.DynamicsPanel_6);
            app.msLabel_20.Text = 'm/s²';
            app.VerticalDynamicsLabel_6 = uilabel(app.DynamicsPanel_6);
            app.VerticalDynamicsLabel_6.Text = 'Vertical Dynamics';
            app.TurningCircleSpinner_6Label = uilabel(app.DynamicsPanel_6);
            app.TurningCircleSpinner_6Label.Text = 'Turning Circle';
            app.TurningCircleSpinner_6 = uispinner(app.DynamicsPanel_6);
            app.mLabel_13 = uilabel(app.DynamicsPanel_6);
            app.mLabel_13.Text = 'm';
            app.ManeuverabilityLabel_6 = uilabel(app.DynamicsPanel_6);
            app.ManeuverabilityLabel_6.Text = 'Maneuverability';
            app.DynamicsPanel_7 = uipanel(app.TPforVCPanel);
            app.DynamicsPanel_7.Tooltip = {''};
            app.DynamicsPanel_7.Title = 'Dynamics';
            app.Accelerationfrom0kmhto100kmhSpinner_7Label = uilabel(app.DynamicsPanel_7);
            app.Accelerationfrom0kmhto100kmhSpinner_7Label.Text = 'Acceleration from 0km/h to 100km/h';
            app.Accelerationfrom0kmhto100kmhSpinner_7 = uispinner(app.DynamicsPanel_7);
            app.sLabel_14 = uilabel(app.DynamicsPanel_7);
            app.sLabel_14.Text = 's';
            app.TopSpeedSpinner_7Label = uilabel(app.DynamicsPanel_7);
            app.TopSpeedSpinner_7Label.Text = 'Top Speed';
            app.TopSpeedSpinner_7 = uispinner(app.DynamicsPanel_7);
            app.kmhLabel_7 = uilabel(app.DynamicsPanel_7);
            app.kmhLabel_7.Text = 'km/h';
            app.LongitunalDynamicsLabel_7 = uilabel(app.DynamicsPanel_7);
            app.LongitunalDynamicsLabel_7.Text = 'Longitunal Dynamics';
            app.mmLabel_77 = uilabel(app.DynamicsPanel_7);
            app.mmLabel_77.Text = 'mm';
            app.HeightoftheCenterofGravitySpinner_7Label = uilabel(app.DynamicsPanel_7);
            app.HeightoftheCenterofGravitySpinner_7Label.Text = 'Height of the Center of Gravity';
            app.HeightoftheCenterofGravitySpinner_7 = uispinner(app.DynamicsPanel_7);
            app.LateralDynamicsLabel_7 = uilabel(app.DynamicsPanel_7);
            app.LateralDynamicsLabel_7.Text = 'Lateral Dynamics';
            app.VerticalAccelerationSpinner_7Label = uilabel(app.DynamicsPanel_7);
            app.VerticalAccelerationSpinner_7Label.Text = 'Vertical Acceleration';
            app.VerticalAccelerationSpinner_7 = uispinner(app.DynamicsPanel_7);
            app.msLabel_21 = uilabel(app.DynamicsPanel_7);
            app.msLabel_21.Text = 'm/s²';
            app.VerticalDynamicsLabel_7 = uilabel(app.DynamicsPanel_7);
            app.VerticalDynamicsLabel_7.Text = 'Vertical Dynamics';
            app.TurningCircleSpinner_7Label = uilabel(app.DynamicsPanel_7);
            app.TurningCircleSpinner_7Label.Text = 'Turning Circle';
            app.TurningCircleSpinner_7 = uispinner(app.DynamicsPanel_7);
            app.mLabel_14 = uilabel(app.DynamicsPanel_7);
            app.mLabel_14.Text = 'm';
            app.ManeuverabilityLabel_7 = uilabel(app.DynamicsPanel_7);
            app.ManeuverabilityLabel_7.Text = 'Maneuverability';
            app.DrivingStylePanel_7 = uipanel(app.TPforVCPanel);
            app.DrivingStylePanel_7.Tooltip = {'The driving style is split into the 4 aspects: Comfort, Safety, Time Potential and Consumption.'; ''; 'The comfort is measured as TP by the Root Mean Square of the acceleration and the jerk during the ride.'; 'The Safety is measured as TP by the homeostatic risk perception introduced by Lu. (G. Lu, B. Cheng, Q. Lin, und Y. Wang, “Quantitative indicator of homeostatic risk perception in car following,” Safety Science, Bd. 50, Rn. 9, S. 1898–1905, 2012.)'; 'The Time Potential is measured by the Fastness which is a factor for the mean speed compared with the allowed maximum speed.'; 'Consumption is measured by the consumption per 100km/h.'};
            app.DrivingStylePanel_7.Title = 'Driving Style (DS)';
            app.ConsumptionLabel_7 = uilabel(app.DrivingStylePanel_7);
            app.ConsumptionLabel_7.Text = 'Consumption';
            app.DSTimePotentialLabel_7 = uilabel(app.DrivingStylePanel_7);
            app.DSTimePotentialLabel_7.Text = 'DS: Time Potential';
            app.DSSafetyLabel_7 = uilabel(app.DrivingStylePanel_7);
            app.DSSafetyLabel_7.Text = 'DS: Safety';
            app.DSComfortLabel_7 = uilabel(app.DrivingStylePanel_7);
            app.DSComfortLabel_7.Text = 'DS: Comfort';
            app.msLabel_14 = uilabel(app.DrivingStylePanel_7);
            app.msLabel_14.Text = 'm/s²';
            app.AccelerationSpinner_7Label = uilabel(app.DrivingStylePanel_7);
            app.AccelerationSpinner_7Label.Text = 'Acceleration';
            app.AccelerationSpinner_7 = uispinner(app.DrivingStylePanel_7);
            app.msLabel_15 = uilabel(app.DrivingStylePanel_7);
            app.msLabel_15.Text = 'm/s³';
            app.JerkSpinner_7Label = uilabel(app.DrivingStylePanel_7);
            app.JerkSpinner_7Label.Text = 'Jerk';
            app.JerkSpinner_7 = uispinner(app.DrivingStylePanel_7);
            app.HomeostaticRiskPerceptionSpinner_4Label = uilabel(app.DrivingStylePanel_7);
            app.HomeostaticRiskPerceptionSpinner_4Label.Text = 'Homeostatic Risk Perception';
            app.HomeostaticRiskPerceptionSpinner_7 = uispinner(app.DrivingStylePanel_7);
            app.FastnessSpinner_6Label = uilabel(app.DrivingStylePanel_7);
            app.FastnessSpinner_6Label.Text = 'Fastness';
            app.FastnessSpinner_7 = uispinner(app.DrivingStylePanel_7);
            app.kWhLabel_7 = uilabel(app.DrivingStylePanel_7);
            app.kWhLabel_7.Text = 'kWh';
            app.Consumptionper100kmSpinner_7Label = uilabel(app.DrivingStylePanel_7);
            app.Consumptionper100kmSpinner_7Label.Text = 'Consumption per 100km';
            app.Consumptionper100kmSpinner_7 = uispinner(app.DrivingStylePanel_7);
            app.DrivingStylePanel_6 = uipanel(app.TPforVCPanel);
            app.DrivingStylePanel_6.Tooltip = {'The driving style is split into the 4 aspects: Comfort, Safety, Time Potential and Consumption.'; ''; 'The comfort is measured as TP by the Root Mean Square of the acceleration and the jerk during the ride.'; 'The Safety is measured as TP by the homeostatic risk perception introduced by Lu. (G. Lu, B. Cheng, Q. Lin, und Y. Wang, “Quantitative indicator of homeostatic risk perception in car following,” Safety Science, Bd. 50, Rn. 9, S. 1898–1905, 2012.)'; 'The Time Potential is measured by the Fastness which is a factor for the mean speed compared with the allowed maximum speed.'; 'Consumption is measured by the consumption per 100km/h.'};
            app.DrivingStylePanel_6.Title = 'Driving Style (DS)';
            app.ConsumptionLabel_6 = uilabel(app.DrivingStylePanel_6);
            app.ConsumptionLabel_6.Text = 'Consumption';
            app.DSTimePotentialLabel_6 = uilabel(app.DrivingStylePanel_6);
            app.DSTimePotentialLabel_6.Text = 'DS: Time Potential';
            app.DSSafetyLabel_6 = uilabel(app.DrivingStylePanel_6);
            app.DSSafetyLabel_6.Text = 'DS: Safety';
            app.DSComfortLabel_6 = uilabel(app.DrivingStylePanel_6);
            app.DSComfortLabel_6.Text = 'DS: Comfort';
            app.msLabel_12 = uilabel(app.DrivingStylePanel_6);
            app.msLabel_12.Text = 'm/s²';
            app.AccelerationSpinner_6Label = uilabel(app.DrivingStylePanel_6);
            app.AccelerationSpinner_6Label.Text = 'Acceleration';
            app.AccelerationSpinner_6 = uispinner(app.DrivingStylePanel_6);
            app.msLabel_13 = uilabel(app.DrivingStylePanel_6);
            app.msLabel_13.Text = 'm/s³';
            app.JerkSpinner_6Label = uilabel(app.DrivingStylePanel_6);
            app.JerkSpinner_6Label.Text = 'Jerk';
            app.JerkSpinner_6 = uispinner(app.DrivingStylePanel_6);
            app.JerkSpinner_6.Step = 0.01;
            app.HomeostaticRiskPerceptionSpinner_5Label = uilabel(app.DrivingStylePanel_6);
            app.HomeostaticRiskPerceptionSpinner_5Label.Text = 'Homeostatic Risk Perception';
            app.HomeostaticRiskPerceptionSpinner_6 = uispinner(app.DrivingStylePanel_6);
            app.FastnessSpinner_5Label = uilabel(app.DrivingStylePanel_6);
            app.FastnessSpinner_5Label.Text = 'Fastness';
            app.FastnessSpinner_6 = uispinner(app.DrivingStylePanel_6);
            app.kWhLabel_6 = uilabel(app.DrivingStylePanel_6);
            app.kWhLabel_6.Text = 'kWh';
            app.Consumptionper100kmSpinner_6Label = uilabel(app.DrivingStylePanel_6);
            app.Consumptionper100kmSpinner_6Label.Text = 'Consumption per 100km';
            app.Consumptionper100kmSpinner_6 = uispinner(app.DrivingStylePanel_6);
            app.DrivingStylePanel_5 = uipanel(app.TPforVCPanel);
            app.DrivingStylePanel_5.Tooltip = {'The driving style is split into the 4 aspects: Comfort, Safety, Time Potential and Consumption.'; ''; 'The comfort is measured as TP by the Root Mean Square of the acceleration and the jerk during the ride.'; 'The Safety is measured as TP by the homeostatic risk perception introduced by Lu. (G. Lu, B. Cheng, Q. Lin, und Y. Wang, “Quantitative indicator of homeostatic risk perception in car following,” Safety Science, Bd. 50, Rn. 9, S. 1898–1905, 2012.)'; 'The Time Potential is measured by the Fastness which is a factor for the mean speed compared with the allowed maximum speed.'; 'Consumption is measured by the consumption per 100km/h.'};
            app.DrivingStylePanel_5.Title = 'Driving Style (DS)';
            app.ConsumptionLabel_5 = uilabel(app.DrivingStylePanel_5);
            app.ConsumptionLabel_5.Text = 'Consumption';
            app.DSTimePotentialLabel_5 = uilabel(app.DrivingStylePanel_5);
            app.DSTimePotentialLabel_5.Text = 'DS: Time Potential';
            app.DSSafetyLabel_5 = uilabel(app.DrivingStylePanel_5);
            app.DSSafetyLabel_5.Text = 'DS: Safety';
            app.DSComfortLabel_5 = uilabel(app.DrivingStylePanel_5);
            app.DSComfortLabel_5.Text = 'DS: Comfort';
            app.msLabel_10 = uilabel(app.DrivingStylePanel_5);
            app.msLabel_10.Text = 'm/s²';
            app.AccelerationSpinner_5Label = uilabel(app.DrivingStylePanel_5);
            app.AccelerationSpinner_5Label.Text = 'Acceleration';
            app.AccelerationSpinner_5 = uispinner(app.DrivingStylePanel_5);
            app.msLabel_11 = uilabel(app.DrivingStylePanel_5);
            app.msLabel_11.Text = 'm/s³';
            app.JerkSpinner_5Label = uilabel(app.DrivingStylePanel_5);
            app.JerkSpinner_5Label.Text = 'Jerk';
            app.JerkSpinner_5 = uispinner(app.DrivingStylePanel_5);
            app.JerkSpinner_5.Step = 0.01;
            app.HomeostaticRiskPerceptionSpinner_6Label = uilabel(app.DrivingStylePanel_5);
            app.HomeostaticRiskPerceptionSpinner_6Label.Text = 'Homeostatic Risk Perception';
            app.HomeostaticRiskPerceptionSpinner_5 = uispinner(app.DrivingStylePanel_5);
            app.FastnessSpinner_4Label = uilabel(app.DrivingStylePanel_5);
            app.FastnessSpinner_4Label.Text = 'Fastness';
            app.FastnessSpinner_5 = uispinner(app.DrivingStylePanel_5);
            app.kWhLabel_5 = uilabel(app.DrivingStylePanel_5);
            app.kWhLabel_5.Text = 'kWh';
            app.Consumptionper100kmSpinner_5Label = uilabel(app.DrivingStylePanel_5);
            app.Consumptionper100kmSpinner_5Label.Text = 'Consumption per 100km';
            app.Consumptionper100kmSpinner_5 = uispinner(app.DrivingStylePanel_5);
            app.DrivingStylePanel_2 = uipanel(app.TPforVCPanel);
            app.DrivingStylePanel_2.Tooltip = {'The driving style is split into the 4 aspects: Comfort, Safety, Time Potential and Consumption.'; ''; 'The comfort is measured as TP by the Root Mean Square of the acceleration and the jerk during the ride.'; 'The Safety is measured as TP by the homeostatic risk perception introduced by Lu. (G. Lu, B. Cheng, Q. Lin, und Y. Wang, “Quantitative indicator of homeostatic risk perception in car following,” Safety Science, Bd. 50, Rn. 9, S. 1898–1905, 2012.)'; 'The Time Potential is measured by the Fastness which is a factor for the mean speed compared with the allowed maximum speed.'; 'Consumption is measured by the consumption per 100km/h.'};
            app.DrivingStylePanel_2.Title = 'Driving Style (DS)';
            app.ConsumptionLabel_2 = uilabel(app.DrivingStylePanel_2);
            app.ConsumptionLabel_2.Text = 'Consumption';
            app.DSTimePotentialLabel_2 = uilabel(app.DrivingStylePanel_2);
            app.DSTimePotentialLabel_2.Text = 'DS: Time Potential';
            app.DSSafetyLabel_2 = uilabel(app.DrivingStylePanel_2);
            app.DSSafetyLabel_2.Text = 'DS: Safety';
            app.DSComfortLabel_2 = uilabel(app.DrivingStylePanel_2);
            app.DSComfortLabel_2.Text = 'DS: Comfort';
            app.msLabel_4 = uilabel(app.DrivingStylePanel_2);
            app.msLabel_4.Text = 'm/s²';
            app.AccelerationSpinner_2Label = uilabel(app.DrivingStylePanel_2);
            app.AccelerationSpinner_2Label.Text = 'Acceleration';
            app.AccelerationSpinner_2 = uispinner(app.DrivingStylePanel_2);
            app.msLabel_5 = uilabel(app.DrivingStylePanel_2);
            app.msLabel_5.Text = 'm/s³';
            app.JerkSpinner_2Label = uilabel(app.DrivingStylePanel_2);
            app.JerkSpinner_2Label.Text = 'Jerk';
            app.JerkSpinner_2 = uispinner(app.DrivingStylePanel_2);
            app.HomeostaticRiskPerceptionSpinnerLabel = uilabel(app.DrivingStylePanel_2);
            app.HomeostaticRiskPerceptionSpinnerLabel.Text = 'Homeostatic Risk Perception';
            app.HomeostaticRiskPerceptionSpinner_2 = uispinner(app.DrivingStylePanel_2);
            app.FastnessSpinnerLabel = uilabel(app.DrivingStylePanel_2);
            app.FastnessSpinnerLabel.Text = 'Fastness';
            app.FastnessSpinner_2 = uispinner(app.DrivingStylePanel_2);
            app.kWhLabel_2 = uilabel(app.DrivingStylePanel_2);
            app.kWhLabel_2.Text = 'kWh';
            app.Consumptionper100kmSpinner_2Label = uilabel(app.DrivingStylePanel_2);
            app.Consumptionper100kmSpinner_2Label.Text = 'Consumption per 100km';
            app.Consumptionper100kmSpinner_2 = uispinner(app.DrivingStylePanel_2);
            app.DrivingStylePanel_3 = uipanel(app.TPforVCPanel);
            app.DrivingStylePanel_3.Tooltip = {'The driving style is split into the 4 aspects: Comfort, Safety, Time Potential and Consumption.'; ''; 'The comfort is measured as TP by the Root Mean Square of the acceleration and the jerk during the ride.'; 'The Safety is measured as TP by the homeostatic risk perception introduced by Lu. (G. Lu, B. Cheng, Q. Lin, und Y. Wang, “Quantitative indicator of homeostatic risk perception in car following,” Safety Science, Bd. 50, Rn. 9, S. 1898–1905, 2012.)'; 'The Time Potential is measured by the Fastness which is a factor for the mean speed compared with the allowed maximum speed.'; 'Consumption is measured by the consumption per 100km/h.'};
            app.DrivingStylePanel_3.Title = 'Driving Style (DS)';
            app.ConsumptionLabel_3 = uilabel(app.DrivingStylePanel_3);
            app.ConsumptionLabel_3.Text = 'Consumption';
            app.DSTimePotentialLabel_3 = uilabel(app.DrivingStylePanel_3);
            app.DSTimePotentialLabel_3.Text = 'DS: Time Potential';
            app.DSSafetyLabel_3 = uilabel(app.DrivingStylePanel_3);
            app.DSSafetyLabel_3.Text = 'DS: Safety';
            app.DSComfortLabel_3 = uilabel(app.DrivingStylePanel_3);
            app.DSComfortLabel_3.Text = 'DS: Comfort';
            app.msLabel_6 = uilabel(app.DrivingStylePanel_3);
            app.msLabel_6.Text = 'm/s²';
            app.AccelerationSpinner_3Label = uilabel(app.DrivingStylePanel_3);
            app.AccelerationSpinner_3Label.Text = 'Acceleration';
            app.AccelerationSpinner_3 = uispinner(app.DrivingStylePanel_3);
            app.msLabel_7 = uilabel(app.DrivingStylePanel_3);
            app.msLabel_7.Text = 'm/s³';
            app.JerkSpinner_3Label = uilabel(app.DrivingStylePanel_3);
            app.JerkSpinner_3Label.Text = 'Jerk';
            app.JerkSpinner_3 = uispinner(app.DrivingStylePanel_3);
            app.HomeostaticRiskPerceptionSpinner_2Label = uilabel(app.DrivingStylePanel_3);
            app.HomeostaticRiskPerceptionSpinner_2Label.Text = 'Homeostatic Risk Perception';
            app.HomeostaticRiskPerceptionSpinner_3 = uispinner(app.DrivingStylePanel_3);
            app.FastnessSpinner_2Label = uilabel(app.DrivingStylePanel_3);
            app.FastnessSpinner_2Label.Text = 'Fastness';
            app.FastnessSpinner_3 = uispinner(app.DrivingStylePanel_3);
            app.kWhLabel_3 = uilabel(app.DrivingStylePanel_3);
            app.kWhLabel_3.Text = 'kWh';
            app.Consumptionper100kmSpinner_3Label = uilabel(app.DrivingStylePanel_3);
            app.Consumptionper100kmSpinner_3Label.Text = 'Consumption per 100km';
            app.Consumptionper100kmSpinner_3 = uispinner(app.DrivingStylePanel_3);
            app.DrivingStylePanel_4 = uipanel(app.TPforVCPanel);
            app.DrivingStylePanel_4.Tooltip = {'The driving style is split into the 4 aspects: Comfort, Safety, Time Potential and Consumption.'; ''; 'The comfort is measured as TP by the Root Mean Square of the acceleration and the jerk during the ride.'; 'The Safety is measured as TP by the homeostatic risk perception introduced by Lu. (G. Lu, B. Cheng, Q. Lin, und Y. Wang, “Quantitative indicator of homeostatic risk perception in car following,” Safety Science, Bd. 50, Rn. 9, S. 1898–1905, 2012.)'; 'The Time Potential is measured by the Fastness which is a factor for the mean speed compared with the allowed maximum speed.'; 'Consumption is measured by the consumption per 100km/h.'};
            app.DrivingStylePanel_4.Title = 'Driving Style (DS)';
            app.ConsumptionLabel_4 = uilabel(app.DrivingStylePanel_4);
            app.ConsumptionLabel_4.Text = 'Consumption';
            app.DSTimePotentialLabel_4 = uilabel(app.DrivingStylePanel_4);
            app.DSTimePotentialLabel_4.Text = 'DS: Time Potential';
            app.DSSafetyLabel_4 = uilabel(app.DrivingStylePanel_4);
            app.DSSafetyLabel_4.Text = 'DS: Safety';
            app.DSComfortLabel_4 = uilabel(app.DrivingStylePanel_4);
            app.DSComfortLabel_4.Text = 'DS: Comfort';
            app.msLabel_8 = uilabel(app.DrivingStylePanel_4);
            app.msLabel_8.Text = 'm/s²';
            app.AccelerationSpinner_4Label = uilabel(app.DrivingStylePanel_4);
            app.AccelerationSpinner_4Label.Text = 'Acceleration';
            app.AccelerationSpinner_4 = uispinner(app.DrivingStylePanel_4);
            app.msLabel_9 = uilabel(app.DrivingStylePanel_4);
            app.msLabel_9.Text = 'm/s³';
            app.JerkSpinner_4Label = uilabel(app.DrivingStylePanel_4);
            app.JerkSpinner_4Label.Text = 'Jerk';
            app.JerkSpinner_4 = uispinner(app.DrivingStylePanel_4);
            app.HomeostaticRiskPerceptionSpinner_3Label = uilabel(app.DrivingStylePanel_4);
            app.HomeostaticRiskPerceptionSpinner_3Label.Text = 'Homeostatic Risk Perception';
            app.HomeostaticRiskPerceptionSpinner_4 = uispinner(app.DrivingStylePanel_4);
            app.FastnessSpinner_3Label = uilabel(app.DrivingStylePanel_4);
            app.FastnessSpinner_3Label.Text = 'Fastness';
            app.FastnessSpinner_4 = uispinner(app.DrivingStylePanel_4);
            app.kWhLabel_4 = uilabel(app.DrivingStylePanel_4);
            app.kWhLabel_4.Text = 'kWh';
            app.Consumptionper100kmSpinner_4Label = uilabel(app.DrivingStylePanel_4);
            app.Consumptionper100kmSpinner_4Label.Text = 'Consumption per 100km';
            app.Consumptionper100kmSpinner_4 = uispinner(app.DrivingStylePanel_4);
            app.SecurityPanel_7 = uipanel(app.TPforVCPanel);
            app.SecurityPanel_7.Tooltip = {''};
            app.SecurityPanel_7.Title = 'Security';
            app.ExternalCommunicationLabel_7 = uilabel(app.SecurityPanel_7);
            app.ExternalCommunicationLabel_7.Text = 'External Communication';
            app.QualityofAutomationLabel_7 = uilabel(app.SecurityPanel_7);
            app.QualityofAutomationLabel_7.Text = 'Quality of Automation';
            app.ActiveSafetyLabel_7 = uilabel(app.SecurityPanel_7);
            app.ActiveSafetyLabel_7.Text = 'Active Safety';
            app.PassiveSafetyLabel_7 = uilabel(app.SecurityPanel_7);
            app.PassiveSafetyLabel_7.Text = 'Passive Safety';
            app.InteriorRecognitionLabel_7 = uilabel(app.SecurityPanel_7);
            app.InteriorRecognitionLabel_7.Text = 'Interior Recognition';
            app.mmLabel_58 = uilabel(app.SecurityPanel_7);
            app.mmLabel_58.Text = 'mm';
            app.CrashLengthSpinner_7Label = uilabel(app.SecurityPanel_7);
            app.CrashLengthSpinner_7Label.Text = 'Crash Length';
            app.CrashLengthSpinner_7 = uispinner(app.SecurityPanel_7);
            app.sLabel_21 = uilabel(app.SecurityPanel_7);
            app.sLabel_21.Text = 's';
            app.Elasticityfrom60kmhto100kmhSpinner_7Label = uilabel(app.SecurityPanel_7);
            app.Elasticityfrom60kmhto100kmhSpinner_7Label.Text = 'Elasticity from 60 km/h to 100 km/h';
            app.Elasticityfrom60kmhto100kmhSpinner_7 = uispinner(app.SecurityPanel_7);
            app.Disengagementper100000kmSpinner_7Label = uilabel(app.SecurityPanel_7);
            app.Disengagementper100000kmSpinner_7Label.Text = 'Disengagement per 100.000km';
            app.Disengagementper100000kmSpinner_7 = uispinner(app.SecurityPanel_7);
            app.ResearchontechnicalpropertypendingLabel_14 = uilabel(app.SecurityPanel_7);
            app.ResearchontechnicalpropertypendingLabel_14.Text = 'Research on technical property pending';
            app.ResearchontechnicalpropertypendingLabel_15 = uilabel(app.SecurityPanel_7);
            app.ResearchontechnicalpropertypendingLabel_15.Text = 'Research on technical property pending';
            app.SecurityPanel_6 = uipanel(app.TPforVCPanel);
            app.SecurityPanel_6.Tooltip = {''};
            app.SecurityPanel_6.Title = 'Security';
            app.ExternalCommunicationLabel_6 = uilabel(app.SecurityPanel_6);
            app.ExternalCommunicationLabel_6.Text = 'External Communication';
            app.QualityofAutomationLabel_6 = uilabel(app.SecurityPanel_6);
            app.QualityofAutomationLabel_6.Text = 'Quality of Automation';
            app.ActiveSafetyLabel_6 = uilabel(app.SecurityPanel_6);
            app.ActiveSafetyLabel_6.Text = 'Active Safety';
            app.PassiveSafetyLabel_6 = uilabel(app.SecurityPanel_6);
            app.PassiveSafetyLabel_6.Text = 'Passive Safety';
            app.InteriorRecognitionLabel_6 = uilabel(app.SecurityPanel_6);
            app.InteriorRecognitionLabel_6.Text = 'Interior Recognition';
            app.mmLabel_56 = uilabel(app.SecurityPanel_6);
            app.mmLabel_56.Text = 'mm';
            app.CrashLengthSpinner_6Label = uilabel(app.SecurityPanel_6);
            app.CrashLengthSpinner_6Label.Text = 'Crash Length';
            app.CrashLengthSpinner_6 = uispinner(app.SecurityPanel_6);
            app.sLabel_15 = uilabel(app.SecurityPanel_6);
            app.sLabel_15.Text = 's';
            app.Elasticityfrom60kmhto100kmhSpinner_6Label = uilabel(app.SecurityPanel_6);
            app.Elasticityfrom60kmhto100kmhSpinner_6Label.Text = 'Elasticity from 60 km/h to 100 km/h';
            app.Elasticityfrom60kmhto100kmhSpinner_6 = uispinner(app.SecurityPanel_6);
            app.Disengagementper100000kmSpinner_6Label = uilabel(app.SecurityPanel_6);
            app.Disengagementper100000kmSpinner_6Label.Text = 'Disengagement per 100.000km';
            app.Disengagementper100000kmSpinner_6 = uispinner(app.SecurityPanel_6);
            app.ResearchontechnicalpropertypendingLabel_12 = uilabel(app.SecurityPanel_6);
            app.ResearchontechnicalpropertypendingLabel_12.Text = 'Research on technical property pending';
            app.ResearchontechnicalpropertypendingLabel_13 = uilabel(app.SecurityPanel_6);
            app.ResearchontechnicalpropertypendingLabel_13.Text = 'Research on technical property pending';
            app.SecurityPanel_5 = uipanel(app.TPforVCPanel);
            app.SecurityPanel_5.Tooltip = {''};
            app.SecurityPanel_5.Title = 'Security';
            app.ExternalCommunicationLabel_5 = uilabel(app.SecurityPanel_5);
            app.ExternalCommunicationLabel_5.Text = 'External Communication';
            app.QualityofAutomationLabel_5 = uilabel(app.SecurityPanel_5);
            app.QualityofAutomationLabel_5.Text = 'Quality of Automation';
            app.ActiveSafetyLabel_5 = uilabel(app.SecurityPanel_5);
            app.ActiveSafetyLabel_5.Text = 'Active Safety';
            app.PassiveSafetyLabel_5 = uilabel(app.SecurityPanel_5);
            app.PassiveSafetyLabel_5.Text = 'Passive Safety';
            app.InteriorRecognitionLabel_5 = uilabel(app.SecurityPanel_5);
            app.InteriorRecognitionLabel_5.Text = 'Interior Recognition';
            app.mmLabel_54 = uilabel(app.SecurityPanel_5);
            app.mmLabel_54.Text = 'mm';
            app.CrashLengthSpinner_5Label = uilabel(app.SecurityPanel_5);
            app.CrashLengthSpinner_5Label.Text = 'Crash Length';
            app.CrashLengthSpinner_5 = uispinner(app.SecurityPanel_5);
            app.sLabel_20 = uilabel(app.SecurityPanel_5);
            app.sLabel_20.Text = 's';
            app.Elasticityfrom60kmhto100kmhSpinner_5Label = uilabel(app.SecurityPanel_5);
            app.Elasticityfrom60kmhto100kmhSpinner_5Label.Text = 'Elasticity from 60 km/h to 100 km/h';
            app.Elasticityfrom60kmhto100kmhSpinner_5 = uispinner(app.SecurityPanel_5);
            app.Disengagementper100000kmSpinner_5Label = uilabel(app.SecurityPanel_5);
            app.Disengagementper100000kmSpinner_5Label.Text = 'Disengagement per 100.000km';
            app.Disengagementper100000kmSpinner_5 = uispinner(app.SecurityPanel_5);
            app.ResearchontechnicalpropertypendingLabel_10 = uilabel(app.SecurityPanel_5);
            app.ResearchontechnicalpropertypendingLabel_10.Text = 'Research on technical property pending';
            app.ResearchontechnicalpropertypendingLabel_11 = uilabel(app.SecurityPanel_5);
            app.ResearchontechnicalpropertypendingLabel_11.Text = 'Research on technical property pending';
            app.SecurityPanel_4 = uipanel(app.TPforVCPanel);
            app.SecurityPanel_4.Tooltip = {''};
            app.SecurityPanel_4.Title = 'Security';
            app.ExternalCommunicationLabel_4 = uilabel(app.SecurityPanel_4);
            app.ExternalCommunicationLabel_4.Text = 'External Communication';
            app.QualityofAutomationLabel_4 = uilabel(app.SecurityPanel_4);
            app.QualityofAutomationLabel_4.Text = 'Quality of Automation';
            app.ActiveSafetyLabel_4 = uilabel(app.SecurityPanel_4);
            app.ActiveSafetyLabel_4.Text = 'Active Safety';
            app.PassiveSafetyLabel_4 = uilabel(app.SecurityPanel_4);
            app.PassiveSafetyLabel_4.Text = 'Passive Safety';
            app.InteriorRecognitionLabel_4 = uilabel(app.SecurityPanel_4);
            app.InteriorRecognitionLabel_4.Text = 'Interior Recognition';
            app.mmLabel_52 = uilabel(app.SecurityPanel_4);
            app.mmLabel_52.Text = 'mm';
            app.CrashLengthSpinner_4Label = uilabel(app.SecurityPanel_4);
            app.CrashLengthSpinner_4Label.Text = 'Crash Length';
            app.CrashLengthSpinner_4 = uispinner(app.SecurityPanel_4);
            app.sLabel_19 = uilabel(app.SecurityPanel_4);
            app.sLabel_19.Text = 's';
            app.Elasticityfrom60kmhto100kmhSpinner_4Label = uilabel(app.SecurityPanel_4);
            app.Elasticityfrom60kmhto100kmhSpinner_4Label.Text = 'Elasticity from 60 km/h to 100 km/h';
            app.Elasticityfrom60kmhto100kmhSpinner_4 = uispinner(app.SecurityPanel_4);
            app.Disengagementper100000kmSpinner_4Label = uilabel(app.SecurityPanel_4);
            app.Disengagementper100000kmSpinner_4Label.Text = 'Disengagement per 100.000km';
            app.Disengagementper100000kmSpinner_4 = uispinner(app.SecurityPanel_4);
            app.ResearchontechnicalpropertypendingLabel_8 = uilabel(app.SecurityPanel_4);
            app.ResearchontechnicalpropertypendingLabel_8.Text = 'Research on technical property pending';
            app.ResearchontechnicalpropertypendingLabel_9 = uilabel(app.SecurityPanel_4);
            app.ResearchontechnicalpropertypendingLabel_9.Text = 'Research on technical property pending';
            app.SecurityPanel_3 = uipanel(app.TPforVCPanel);
            app.SecurityPanel_3.Tooltip = {''};
            app.SecurityPanel_3.Title = 'Security';
            app.ExternalCommunicationLabel_3 = uilabel(app.SecurityPanel_3);
            app.ExternalCommunicationLabel_3.Text = 'External Communication';
            app.QualityofAutomationLabel_3 = uilabel(app.SecurityPanel_3);
            app.QualityofAutomationLabel_3.Text = 'Quality of Automation';
            app.ActiveSafetyLabel_3 = uilabel(app.SecurityPanel_3);
            app.ActiveSafetyLabel_3.Text = 'Active Safety';
            app.PassiveSafetyLabel_3 = uilabel(app.SecurityPanel_3);
            app.PassiveSafetyLabel_3.Text = 'Passive Safety';
            app.InteriorRecognitionLabel_3 = uilabel(app.SecurityPanel_3);
            app.InteriorRecognitionLabel_3.Text = 'Interior Recognition';
            app.mmLabel_50 = uilabel(app.SecurityPanel_3);
            app.mmLabel_50.Text = 'mm';
            app.CrashLengthSpinner_3Label = uilabel(app.SecurityPanel_3);
            app.CrashLengthSpinner_3Label.Text = 'Crash Length';
            app.CrashLengthSpinner_3 = uispinner(app.SecurityPanel_3);
            app.sLabel_18 = uilabel(app.SecurityPanel_3);
            app.sLabel_18.Text = 's';
            app.Elasticityfrom60kmhto100kmhSpinner_3Label = uilabel(app.SecurityPanel_3);
            app.Elasticityfrom60kmhto100kmhSpinner_3Label.Text = 'Elasticity from 60 km/h to 100 km/h';
            app.Elasticityfrom60kmhto100kmhSpinner_3 = uispinner(app.SecurityPanel_3);
            app.Disengagementper100000kmSpinner_3Label = uilabel(app.SecurityPanel_3);
            app.Disengagementper100000kmSpinner_3Label.Text = 'Disengagement per 100.000km';
            app.Disengagementper100000kmSpinner_3 = uispinner(app.SecurityPanel_3);
            app.ResearchontechnicalpropertypendingLabel_6 = uilabel(app.SecurityPanel_3);
            app.ResearchontechnicalpropertypendingLabel_6.Text = 'Research on technical property pending';
            app.ResearchontechnicalpropertypendingLabel_7 = uilabel(app.SecurityPanel_3);
            app.ResearchontechnicalpropertypendingLabel_7.Text = 'Research on technical property pending';
            app.SecurityPanel_2 = uipanel(app.TPforVCPanel);
            app.SecurityPanel_2.Tooltip = {''};
            app.SecurityPanel_2.Title = 'Security';
            app.ExternalCommunicationLabel_2 = uilabel(app.SecurityPanel_2);
            app.ExternalCommunicationLabel_2.Text = 'External Communication';
            app.QualityofAutomationLabel_2 = uilabel(app.SecurityPanel_2);
            app.QualityofAutomationLabel_2.Text = 'Quality of Automation';
            app.ActiveSafetyLabel_2 = uilabel(app.SecurityPanel_2);
            app.ActiveSafetyLabel_2.Text = 'Active Safety';
            app.PassiveSafetyLabel_2 = uilabel(app.SecurityPanel_2);
            app.PassiveSafetyLabel_2.Text = 'Passive Safety';
            app.InteriorRecognitionLabel_2 = uilabel(app.SecurityPanel_2);
            app.InteriorRecognitionLabel_2.Text = 'Interior Recognition';
            app.mmLabel_48 = uilabel(app.SecurityPanel_2);
            app.mmLabel_48.Text = 'mm';
            app.CrashLengthSpinner_2Label = uilabel(app.SecurityPanel_2);
            app.CrashLengthSpinner_2Label.Text = 'Crash Length';
            app.CrashLengthSpinner_2 = uispinner(app.SecurityPanel_2);
            app.sLabel_17 = uilabel(app.SecurityPanel_2);
            app.sLabel_17.Text = 's';
            app.Elasticityfrom60kmhto100kmhSpinner_2Label = uilabel(app.SecurityPanel_2);
            app.Elasticityfrom60kmhto100kmhSpinner_2Label.Text = 'Elasticity from 60 km/h to 100 km/h';
            app.Elasticityfrom60kmhto100kmhSpinner_2 = uispinner(app.SecurityPanel_2);
            app.Disengagementper100000kmSpinner_2Label = uilabel(app.SecurityPanel_2);
            app.Disengagementper100000kmSpinner_2Label.Text = 'Disengagement per 100.000km';
            app.Disengagementper100000kmSpinner_2 = uispinner(app.SecurityPanel_2);
            app.ResearchontechnicalpropertypendingLabel_4 = uilabel(app.SecurityPanel_2);
            app.ResearchontechnicalpropertypendingLabel_4.Text = 'Research on technical property pending';
            app.ResearchontechnicalpropertypendingLabel_5 = uilabel(app.SecurityPanel_2);
            app.ResearchontechnicalpropertypendingLabel_5.Text = 'Research on technical property pending';
            app.allTPforoneVCPanel = uipanel(app.MainScreenTP);
            app.allTPforoneVCPanel.Title = 'All Technical Properties for the selected Vehicle Concept';
            app.DynamicsPanel_1 = uipanel(app.allTPforoneVCPanel);
            app.DynamicsPanel_1.Tooltip = {''};
            app.DynamicsPanel_1.Title = 'Dynamics';
            app.Accelerationfrom0kmhto100kmhSpinnerLabel = uilabel(app.DynamicsPanel_1);
            app.Accelerationfrom0kmhto100kmhSpinnerLabel.Text = 'Acceleration from 0km/h to 100km/h';
            app.Accelerationfrom0kmhto100kmhSpinner_1 = uispinner(app.DynamicsPanel_1);
            app.TopSpeedSpinnerLabel = uilabel(app.DynamicsPanel_1);
            app.TopSpeedSpinnerLabel.Text = 'Top Speed';
            app.TopSpeedSpinner_1 = uispinner(app.DynamicsPanel_1);
            app.sLabel = uilabel(app.DynamicsPanel_1);
            app.sLabel.Text = 's';
            app.kmhLabel = uilabel(app.DynamicsPanel_1);
            app.kmhLabel.Text = 'km/h';
            app.mmLabel = uilabel(app.DynamicsPanel_1);
            app.mmLabel.Text = 'mm';
            app.HeightoftheCenterofGravitySpinnerLabel = uilabel(app.DynamicsPanel_1);
            app.HeightoftheCenterofGravitySpinnerLabel.Text = 'Height of the Center of Gravity';
            app.HeightoftheCenterofGravitySpinner_1 = uispinner(app.DynamicsPanel_1);
            app.VerticalAccelerationSpinnerLabel = uilabel(app.DynamicsPanel_1);
            app.VerticalAccelerationSpinnerLabel.Text = 'Vertical Acceleration';
            app.VerticalAccelerationSpinner_1 = uispinner(app.DynamicsPanel_1);
            app.TurningCircleSpinnerLabel = uilabel(app.DynamicsPanel_1);
            app.TurningCircleSpinnerLabel.Text = 'Turning Circle';
            app.TurningCircleSpinner_1 = uispinner(app.DynamicsPanel_1);
            app.msLabel = uilabel(app.DynamicsPanel_1);
            app.msLabel.Text = 'm/s²';
            app.mLabel = uilabel(app.DynamicsPanel_1);
            app.mLabel.Text = 'm';
            app.LongitunalDynamicsLabel = uilabel(app.DynamicsPanel_1);
            app.LongitunalDynamicsLabel.Text = 'Longitunal Dynamics';
            app.LateralDynamicsLabel = uilabel(app.DynamicsPanel_1);
            app.LateralDynamicsLabel.Text = 'Lateral Dynamics';
            app.VerticalDynamicsLabel = uilabel(app.DynamicsPanel_1);
            app.VerticalDynamicsLabel.Text = 'Vertical Dynamics';
            app.ManeuverabilityLabel = uilabel(app.DynamicsPanel_1);
            app.ManeuverabilityLabel.Text = 'Maneuverability';
            app.ErgonomicsPanel_1 = uipanel(app.allTPforoneVCPanel);
            app.ErgonomicsPanel_1.Tooltip = {''};
            app.ErgonomicsPanel_1.Title = 'Ergonomics';
            app.BoardingComfortLabel = uilabel(app.ErgonomicsPanel_1);
            app.BoardingComfortLabel.Text = 'Boarding Comfort';
            app.BoardingTimeLabel = uilabel(app.ErgonomicsPanel_1);
            app.BoardingTimeLabel.Text = 'Boarding Time';
            app.LegRoomLabel = uilabel(app.ErgonomicsPanel_1);
            app.LegRoomLabel.Text = 'Leg Room';
            app.ShouldersRoomLabel = uilabel(app.ErgonomicsPanel_1);
            app.ShouldersRoomLabel.Text = 'Shoulders Room';
            app.HeadRoomLabel = uilabel(app.ErgonomicsPanel_1);
            app.HeadRoomLabel.Text = 'Head Room';
            app.InteriorAcousticsLabel = uilabel(app.ErgonomicsPanel_1);
            app.InteriorAcousticsLabel.Text = 'Interior Acoustics';
            app.mmLabel_2 = uilabel(app.ErgonomicsPanel_1);
            app.mmLabel_2.Text = 'mm';
            app.SillHeightSpinnerLabel = uilabel(app.ErgonomicsPanel_1);
            app.SillHeightSpinnerLabel.Text = 'Sill Height';
            app.SillHeightSpinner_1 = uispinner(app.ErgonomicsPanel_1);
            app.mmLabel_3 = uilabel(app.ErgonomicsPanel_1);
            app.mmLabel_3.Text = 'mm';
            app.ClearanceHeightSpinnerLabel = uilabel(app.ErgonomicsPanel_1);
            app.ClearanceHeightSpinnerLabel.Text = 'Clearance Height';
            app.ClearanceHeightSpinner_1 = uispinner(app.ErgonomicsPanel_1);
            app.mmLabel_4 = uilabel(app.ErgonomicsPanel_1);
            app.mmLabel_4.Text = 'mm';
            app.DoorOpeningWidthperSeatSpinnerLabel = uilabel(app.ErgonomicsPanel_1);
            app.DoorOpeningWidthperSeatSpinnerLabel.Text = 'Door Opening Width per Seat';
            app.DoorOpeningWidthperSeatSpinner_1 = uispinner(app.ErgonomicsPanel_1);
            app.mmLabel_5 = uilabel(app.ErgonomicsPanel_1);
            app.mmLabel_5.Text = 'mm';
            app.InteriorDimensionL53SpinnerLabel = uilabel(app.ErgonomicsPanel_1);
            app.InteriorDimensionL53SpinnerLabel.Text = 'Interior Dimension L53';
            app.InteriorDimensionL53Spinner_1 = uispinner(app.ErgonomicsPanel_1);
            app.mmLabel_6 = uilabel(app.ErgonomicsPanel_1);
            app.mmLabel_6.Text = 'mm';
            app.InteriorDimensionW3SpinnerLabel = uilabel(app.ErgonomicsPanel_1);
            app.InteriorDimensionW3SpinnerLabel.Text = 'Interior Dimension W3';
            app.InteriorDimensionW3Spinner_1 = uispinner(app.ErgonomicsPanel_1);
            app.mmLabel_7 = uilabel(app.ErgonomicsPanel_1);
            app.mmLabel_7.Text = 'mm';
            app.InteriorDimensionH61SpinnerLabel = uilabel(app.ErgonomicsPanel_1);
            app.InteriorDimensionH61SpinnerLabel.Text = 'Interior Dimension H61';
            app.InteriorDimensionH61Spinner_1 = uispinner(app.ErgonomicsPanel_1);
            app.dBLabel = uilabel(app.ErgonomicsPanel_1);
            app.dBLabel.Text = 'dB';
            app.AveragedSoundPressureLevelSpinnerLabel = uilabel(app.ErgonomicsPanel_1);
            app.AveragedSoundPressureLevelSpinnerLabel.Text = 'Averaged Sound Pressure Level';
            app.AveragedSoundPressureLevelSpinner_1 = uispinner(app.ErgonomicsPanel_1);
            app.SecurityPanel_1 = uipanel(app.allTPforoneVCPanel);
            app.SecurityPanel_1.Tooltip = {''};
            app.SecurityPanel_1.Title = 'Security';
            app.ExternalCommunicationLabel = uilabel(app.SecurityPanel_1);
            app.ExternalCommunicationLabel.Text = 'External Communication';
            app.QualityofAutomationLabel = uilabel(app.SecurityPanel_1);
            app.QualityofAutomationLabel.Text = 'Quality of Automation';
            app.ActiveSafetyLabel = uilabel(app.SecurityPanel_1);
            app.ActiveSafetyLabel.Text = 'Active Safety';
            app.PassiveSafetyLabel = uilabel(app.SecurityPanel_1);
            app.PassiveSafetyLabel.Text = 'Passive Safety';
            app.InteriorRecognitionLabel = uilabel(app.SecurityPanel_1);
            app.InteriorRecognitionLabel.Text = 'Interior Recognition';
            app.mmLabel_8 = uilabel(app.SecurityPanel_1);
            app.mmLabel_8.Text = 'mm';
            app.CrashLengthSpinnerLabel = uilabel(app.SecurityPanel_1);
            app.CrashLengthSpinnerLabel.Text = 'Crash Length';
            app.CrashLengthSpinner_1 = uispinner(app.SecurityPanel_1);
            app.sLabel_16 = uilabel(app.SecurityPanel_1);
            app.sLabel_16.Text = 's';
            app.Elasticityfrom60kmhto100kmhSpinnerLabel = uilabel(app.SecurityPanel_1);
            app.Elasticityfrom60kmhto100kmhSpinnerLabel.Text = 'Elasticity from 60 km/h to 100 km/h';
            app.Elasticityfrom60kmhto100kmhSpinner_1 = uispinner(app.SecurityPanel_1);
            app.Disengagementper100000kmSpinnerLabel = uilabel(app.SecurityPanel_1);
            app.Disengagementper100000kmSpinnerLabel.Text = 'Disengagement per 100.000km';
            app.Disengagementper100000kmSpinner_1 = uispinner(app.SecurityPanel_1);
            app.ResearchontechnicalpropertypendingLabel = uilabel(app.SecurityPanel_1);
            app.ResearchontechnicalpropertypendingLabel.Text = 'Research on technical property pending';
            app.ResearchontechnicalpropertypendingLabel_2 = uilabel(app.SecurityPanel_1);
            app.ResearchontechnicalpropertypendingLabel_2.Text = 'Research on technical property pending';
            app.UsabilityPanel_1 = uipanel(app.allTPforoneVCPanel);
            app.UsabilityPanel_1.Tooltip = {''};
            app.UsabilityPanel_1.Title = 'Usability';
            app.PassengersLabel = uilabel(app.UsabilityPanel_1);
            app.PassengersLabel.Text = 'Passengers';
            app.LuggageSpaceLabel = uilabel(app.UsabilityPanel_1);
            app.LuggageSpaceLabel.Text = 'Luggage Space';
            app.RadiusofMovementLabel_2 = uilabel(app.UsabilityPanel_1);
            app.RadiusofMovementLabel_2.Text = 'Radius of Movement';
            app.EconomyLabel = uilabel(app.UsabilityPanel_1);
            app.EconomyLabel.Text = 'Economy';
            app.EcologyLabel = uilabel(app.UsabilityPanel_1);
            app.EcologyLabel.Text = 'Ecology';
            app.BadRoadCapabilityLabel_2 = uilabel(app.UsabilityPanel_1);
            app.BadRoadCapabilityLabel_2.Text = 'Bad Road Capability';
            app.NumberofSeatsSpinnerLabel = uilabel(app.UsabilityPanel_1);
            app.NumberofSeatsSpinnerLabel.Text = 'Number of Seats';
            app.NumberofSeatsSpinner_1 = uispinner(app.UsabilityPanel_1);
            app.lLabel = uilabel(app.UsabilityPanel_1);
            app.lLabel.Text = 'l';
            app.StorageVolumeSpinnerLabel = uilabel(app.UsabilityPanel_1);
            app.StorageVolumeSpinnerLabel.Text = 'Storage Volume';
            app.StorageVolumeSpinner_1 = uispinner(app.UsabilityPanel_1);
            app.kmLabel = uilabel(app.UsabilityPanel_1);
            app.kmLabel.Text = 'km';
            app.RangeSpinnerLabel = uilabel(app.UsabilityPanel_1);
            app.RangeSpinnerLabel.Text = 'Range';
            app.RangeSpinner_1 = uispinner(app.UsabilityPanel_1);
            app.Label_3 = uilabel(app.UsabilityPanel_1);
            app.Label_3.Text = '€';
            app.AcquisitionCostsSpinnerLabel = uilabel(app.UsabilityPanel_1);
            app.AcquisitionCostsSpinnerLabel.Text = 'Acquisition Costs';
            app.AcquisitionCostsSpinner_1 = uispinner(app.UsabilityPanel_1);
            app.tCO2eqLabel = uilabel(app.UsabilityPanel_1);
            app.tCO2eqLabel.Text = 'tCO2-eq.';
            app.ProductionEmissionsSpinnerLabel = uilabel(app.UsabilityPanel_1);
            app.ProductionEmissionsSpinnerLabel.Text = 'Production Emissions';
            app.ProductionEmissionsSpinner_1 = uispinner(app.UsabilityPanel_1);
            app.mmLabel_10 = uilabel(app.UsabilityPanel_1);
            app.mmLabel_10.Text = 'mm';
            app.GroundClearanceSpinnerLabel = uilabel(app.UsabilityPanel_1);
            app.GroundClearanceSpinnerLabel.Text = 'Ground Clearance';
            app.GroundClearanceSpinner_1 = uispinner(app.UsabilityPanel_1);
            app.DrivingStylePanel_1 = uipanel(app.allTPforoneVCPanel);
            app.DrivingStylePanel_1.Tooltip = {'The driving style is split into the 4 aspects: Comfort, Safety, Time Potential and Consumption.'; ''; 'The comfort is measured as TP by the Root Mean Square of the acceleration and the jerk during the ride.'; 'The Safety is measured as TP by the homeostatic risk perception introduced by Lu. (G. Lu, B. Cheng, Q. Lin, und Y. Wang, “Quantitative indicator of homeostatic risk perception in car following,” Safety Science, Bd. 50, Rn. 9, S. 1898–1905, 2012.)'; 'The Time Potential is measured by the Fastness which is a factor for the mean speed compared with the allowed maximum speed.'; 'Consumption is measured by the consumption per 100km/h.'};
            app.DrivingStylePanel_1.Title = 'Driving Style (DS)';
            app.ConsumptionLabel = uilabel(app.DrivingStylePanel_1);
            app.ConsumptionLabel.Text = 'Consumption';
            app.DSTimePotentialLabel = uilabel(app.DrivingStylePanel_1);
            app.DSTimePotentialLabel.Text = 'DS: Time Potential';
            app.DSSafetyLabel = uilabel(app.DrivingStylePanel_1);
            app.DSSafetyLabel.Text = 'DS: Safety';
            app.DSComfortLabel = uilabel(app.DrivingStylePanel_1);
            app.DSComfortLabel.Text = 'DS: Comfort';
            app.msLabel_2 = uilabel(app.DrivingStylePanel_1);
            app.msLabel_2.Text = 'm/s²';
            app.AccelerationSpinnerLabel = uilabel(app.DrivingStylePanel_1);
            app.AccelerationSpinnerLabel.Text = 'Acceleration';
            app.AccelerationSpinner_1 = uispinner(app.DrivingStylePanel_1);
            app.AccelerationSpinner_1.Step = 0.05;
            app.msLabel_3 = uilabel(app.DrivingStylePanel_1);
            app.msLabel_3.Text = 'm/s³';
            app.JerkSpinnerLabel = uilabel(app.DrivingStylePanel_1);
            app.JerkSpinnerLabel.Text = 'Jerk';
            app.JerkSpinner_1 = uispinner(app.DrivingStylePanel_1);
            app.JerkSpinner_1.Step = 0.01;
            app.HomeostaticRiskPerceptionSpinner_8Label = uilabel(app.DrivingStylePanel_1);
            app.HomeostaticRiskPerceptionSpinner_8Label.Text = 'Homeostatic Risk Perception';
            app.HomeostaticRiskPerceptionSpinner_1 = uispinner(app.DrivingStylePanel_1);
            app.FastnessSpinner_34Label = uilabel(app.DrivingStylePanel_1);
            app.FastnessSpinner_34Label.Text = 'Fastness';
            app.FastnessSpinner_1 = uispinner(app.DrivingStylePanel_1);
            app.kWhLabel = uilabel(app.DrivingStylePanel_1);
            app.kWhLabel.Text = 'kWh';
            app.Consumptionper100kmSpinnerLabel = uilabel(app.DrivingStylePanel_1);
            app.Consumptionper100kmSpinnerLabel.Text = 'Consumption per 100km';
            app.Consumptionper100kmSpinner_1 = uispinner(app.DrivingStylePanel_1);
            app.LuxuryPanel_1 = uipanel(app.allTPforoneVCPanel);
            app.LuxuryPanel_1.Tooltip = {''};
            app.LuxuryPanel_1.Title = 'Luxury';
            app.InfotainmentIndividualizationLabel_2 = uilabel(app.LuxuryPanel_1);
            app.InfotainmentIndividualizationLabel_2.Text = 'Infotainment Individualization';
            app.ExteriorDesignLabel = uilabel(app.LuxuryPanel_1);
            app.ExteriorDesignLabel.Text = 'Exterior Design';
            app.BuiltInInfotainmentLabel = uilabel(app.LuxuryPanel_1);
            app.BuiltInInfotainmentLabel.Text = 'Built-In Infotainment';
            app.cmLabel = uilabel(app.LuxuryPanel_1);
            app.cmLabel.Text = 'cm²';
            app.ScreenAreaperSeatSpinnerLabel = uilabel(app.LuxuryPanel_1);
            app.ScreenAreaperSeatSpinnerLabel.Text = 'Screen Area per Seat';
            app.ScreenAreaperSeatSpinner_1 = uispinner(app.LuxuryPanel_1);
            app.mmLabel_11 = uilabel(app.LuxuryPanel_1);
            app.mmLabel_11.Text = 'mm';
            app.WheelDiameterSpinnerLabel = uilabel(app.LuxuryPanel_1);
            app.WheelDiameterSpinnerLabel.Text = 'Wheel Diameter';
            app.WheelDiameterSpinner_1 = uispinner(app.LuxuryPanel_1);
            app.ResearchontechnicalpropertypendingLabel_3 = uilabel(app.LuxuryPanel_1);
            app.ResearchontechnicalpropertypendingLabel_3.Text = 'Research on technical property pending';
            app.OnlythevehicleconceptwiththelowestnumberisshownLabel = uilabel(app.allTPforoneVCPanel);
            app.OnlythevehicleconceptwiththelowestnumberisshownLabel.Text = 'Only the vehicle concept with the lowest number is shown.';
            % Show the figure after all components are created
            app.MainScreenTP.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TechnicalProperties(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainScreenTP)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainScreenTP)
        end
    end
end