classdef UserNeeds < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainScreenUN                    matlab.ui.Figure
        HeaderAppName                   matlab.ui.control.Label
        TUMlogo                         matlab.ui.control.Image
        HomeButton                      matlab.ui.control.Button
        MainMenuPanel                   matlab.ui.container.Panel
        ProceedandRunCalculationButton  matlab.ui.control.Button
        MainParametersPanel             matlab.ui.container.Panel
        NumberofUsersSpinnerLabel       matlab.ui.control.Label
        NumberofUsersSpinner            matlab.ui.control.Spinner
        UserNeedsFulfillmentinSpinnerLabel  matlab.ui.control.Label
        UserNeedsFulfillmentinSpinner   matlab.ui.control.Spinner
        DisplayOptionsPanel             matlab.ui.container.Panel
        ShowInputParametersButton       matlab.ui.control.Button
        VisualizeSecondaryActivitiesButton  matlab.ui.control.Button
        VisualizeCharacterMobilityButton  matlab.ui.control.Button
        ShowallUserstogetherButton_2    matlab.ui.control.Button
        DataOptionsPanel                matlab.ui.container.Panel
        SaveButton                      matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        UpdateButton                    matlab.ui.control.Button
        ScenariosPanel                  matlab.ui.container.Panel
        ScenarioDropDownLabel           matlab.ui.control.Label
        ScenarioDropDown                matlab.ui.control.DropDown
        UserNeedsFulfillmentinDropDownLabel  matlab.ui.control.Label
        UserNeedsFulfillmentinDropDown  matlab.ui.control.DropDown
        UsepredefinedScenarioCheckBox   matlab.ui.control.CheckBox
        SingleUserViewPanel             matlab.ui.container.Panel
        UserPanel_1                     matlab.ui.container.Panel
        TabGroup_1                      matlab.ui.container.TabGroup
        InputParametersTab_1            matlab.ui.container.Tab
        SecondaryActivitiesPanel_1      matlab.ui.container.Panel
        SelfDrivingSpinner_1            matlab.ui.control.Spinner
        RelaxSpinner_1                  matlab.ui.control.Spinner
        WorkSpinner_1                   matlab.ui.control.Spinner
        SleepSpinner_1                  matlab.ui.control.Spinner
        LoadSpinner_1                   matlab.ui.control.Spinner
        PassengersforSelfDrivingSpinner_1  matlab.ui.control.Spinner
        PassengersforRelaxSpinner_1     matlab.ui.control.Spinner
        PassengersforWorkSpinner_1      matlab.ui.control.Spinner
        PassengersforSleepSpinner_1     matlab.ui.control.Spinner
        LoadSpinnerLabel                matlab.ui.control.Label
        SleepSpinnerLabel               matlab.ui.control.Label
        WorkSpinnerLabel                matlab.ui.control.Label
        RelaxSpinnerLabel               matlab.ui.control.Label
        SelfDrivingSpinnerLabel         matlab.ui.control.Label
        PassengersforSleepSpinnerLabel  matlab.ui.control.Label
        PassengersforWorkSpinnerLabel   matlab.ui.control.Label
        PassengersforRelaxSpinnerLabel  matlab.ui.control.Label
        PassengersforSelfDrivingSpinnerLabel  matlab.ui.control.Label
        CharacterPanel_1                matlab.ui.container.Panel
        WillingnessofMaaSSpinnerLabel   matlab.ui.control.Label
        WillingnessofMaaSSpinner_1      matlab.ui.control.Spinner
        WillingnesstoInvestSpinnerLabel  matlab.ui.control.Label
        WillingnesstoInvestSpinner_1    matlab.ui.control.Spinner
        NeedofSecuritySafetySpinnerLabel  matlab.ui.control.Label
        NeedofSecuritySafetySpinner_1   matlab.ui.control.Spinner
        MobilityBehaviorPanel_1         matlab.ui.container.Panel
        DPUrbanSpinner_1                matlab.ui.control.Spinner
        DPRuralSpinner_1                matlab.ui.control.Spinner
        DPHighwaySpinner_1              matlab.ui.control.Spinner
        DPGeneralNeedSpinner_1          matlab.ui.control.Spinner
        DPHighwaySpinnerLabel           matlab.ui.control.Label
        DPUrbanSpinnerLabel             matlab.ui.control.Label
        DPGeneralNeedSpinnerLabel       matlab.ui.control.Label
        DPRuralSpinnerLabel             matlab.ui.control.Label
        ViewSecondaryActivitiesTab_1    matlab.ui.container.Tab
        UIUser1_1                       matlab.ui.control.UIAxes
        ViewCharacterMobilityTab_1      matlab.ui.container.Tab
        UIUser1_2                       matlab.ui.control.UIAxes
        UserPanel_2                     matlab.ui.container.Panel
        TabGroup_2                      matlab.ui.container.TabGroup
        InputParametersTab_2            matlab.ui.container.Tab
        SecondaryActivitiesPanel_2      matlab.ui.container.Panel
        SelfDrivingSpinner_2            matlab.ui.control.Spinner
        RelaxSpinner_2                  matlab.ui.control.Spinner
        WorkSpinner_2                   matlab.ui.control.Spinner
        SleepSpinner_2                  matlab.ui.control.Spinner
        LoadSpinner_2                   matlab.ui.control.Spinner
        PassengersforSelfDrivingSpinner_2  matlab.ui.control.Spinner
        PassengersforRelaxSpinner_2     matlab.ui.control.Spinner
        PassengersforWorkSpinner_2      matlab.ui.control.Spinner
        PassengersforSleepSpinner_2     matlab.ui.control.Spinner
        LoadSpinner_2Label              matlab.ui.control.Label
        SleepSpinner_2Label             matlab.ui.control.Label
        WorkSpinner_2Label              matlab.ui.control.Label
        RelaxSpinner_2Label             matlab.ui.control.Label
        SelfDrivingSpinner_2Label       matlab.ui.control.Label
        PassengersforSleepSpinner_2Label  matlab.ui.control.Label
        PassengersforWorkSpinner_2Label  matlab.ui.control.Label
        PassengersforRelaxSpinner_2Label  matlab.ui.control.Label
        PassengersforSelfDrivingSpinner_2Label  matlab.ui.control.Label
        CharacterPanel_2                matlab.ui.container.Panel
        WillingnessofMaaSSpinner_2Label  matlab.ui.control.Label
        WillingnessofMaaSSpinner_2      matlab.ui.control.Spinner
        WillingnesstoInvestSpinner_2Label  matlab.ui.control.Label
        WillingnesstoInvestSpinner_2    matlab.ui.control.Spinner
        NeedofSecuritySafetySpinner_4Label  matlab.ui.control.Label
        NeedofSecuritySafetySpinner_2   matlab.ui.control.Spinner
        MobilityBehaviorPanel_2         matlab.ui.container.Panel
        DPUrbanSpinner_2                matlab.ui.control.Spinner
        DPRuralSpinner_2                matlab.ui.control.Spinner
        DPHighwaySpinner_2              matlab.ui.control.Spinner
        DPGeneralNeedSpinner_2          matlab.ui.control.Spinner
        DPHighwaySpinner_2Label         matlab.ui.control.Label
        DPUrbanSpinner_4Label           matlab.ui.control.Label
        DPGeneralNeedSpinner_2Label     matlab.ui.control.Label
        DPRuralSpinner_4Label           matlab.ui.control.Label
        ViewSecondaryActivitiesTab_2    matlab.ui.container.Tab
        UIUser2_1                       matlab.ui.control.UIAxes
        ViewCharacterMobilityTab_2      matlab.ui.container.Tab
        UIUser2_2                       matlab.ui.control.UIAxes
        UserPanel_3                     matlab.ui.container.Panel
        TabGroup_3                      matlab.ui.container.TabGroup
        InputParametersTab_3            matlab.ui.container.Tab
        SecondaryActivitiesPanel_3      matlab.ui.container.Panel
        LoadSpinner_5Label              matlab.ui.control.Label
        SleepSpinner_3Label             matlab.ui.control.Label
        WorkSpinner_3Label              matlab.ui.control.Label
        RelaxSpinner_3Label             matlab.ui.control.Label
        SelfDrivingSpinner_6Label       matlab.ui.control.Label
        PassengersforSleepSpinner_3Label  matlab.ui.control.Label
        PassengersforWorkSpinner_3Label  matlab.ui.control.Label
        PassengersforRelaxSpinner_3Label  matlab.ui.control.Label
        PassengersforSelfDrivingSpinner_3Label  matlab.ui.control.Label
        SelfDrivingSpinner_3            matlab.ui.control.Spinner
        RelaxSpinner_3                  matlab.ui.control.Spinner
        WorkSpinner_3                   matlab.ui.control.Spinner
        SleepSpinner_3                  matlab.ui.control.Spinner
        LoadSpinner_3                   matlab.ui.control.Spinner
        PassengersforSelfDrivingSpinner_3  matlab.ui.control.Spinner
        PassengersforRelaxSpinner_3     matlab.ui.control.Spinner
        PassengersforWorkSpinner_3      matlab.ui.control.Spinner
        PassengersforSleepSpinner_3     matlab.ui.control.Spinner
        CharacterPanel_3                matlab.ui.container.Panel
        WillingnessofMaaSSpinner_3Label  matlab.ui.control.Label
        WillingnessofMaaSSpinner_3      matlab.ui.control.Spinner
        WillingnesstoInvestSpinner_3Label  matlab.ui.control.Label
        WillingnesstoInvestSpinner_3    matlab.ui.control.Spinner
        NeedofSecuritySafetySpinner_6Label  matlab.ui.control.Label
        NeedofSecuritySafetySpinner_3   matlab.ui.control.Spinner
        MobilityBehaviorPanel_3         matlab.ui.container.Panel
        DPHighwaySpinner_3Label         matlab.ui.control.Label
        DPUrbanSpinner_5Label           matlab.ui.control.Label
        DPGeneralNeedSpinner_3Label     matlab.ui.control.Label
        DPRuralSpinner_5Label           matlab.ui.control.Label
        DPUrbanSpinner_3                matlab.ui.control.Spinner
        DPRuralSpinner_3                matlab.ui.control.Spinner
        DPHighwaySpinner_3              matlab.ui.control.Spinner
        DPGeneralNeedSpinner_3          matlab.ui.control.Spinner
        ViewSecondaryActivitiesTab_3    matlab.ui.container.Tab
        UIUser3_1                       matlab.ui.control.UIAxes
        ViewCharacterMobilityTab_3      matlab.ui.container.Tab
        UIUser3_2                       matlab.ui.control.UIAxes
        UserPanel_4                     matlab.ui.container.Panel
        TabGroup_4                      matlab.ui.container.TabGroup
        InputParametersTab_4            matlab.ui.container.Tab
        SecondaryActivitiesPanel_4      matlab.ui.container.Panel
        LoadSpinner_3Label              matlab.ui.control.Label
        SleepSpinner_4Label             matlab.ui.control.Label
        WorkSpinner_4Label              matlab.ui.control.Label
        RelaxSpinner_4Label             matlab.ui.control.Label
        SelfDrivingSpinner_3Label       matlab.ui.control.Label
        PassengersforSleepSpinner_4Label  matlab.ui.control.Label
        PassengersforWorkSpinner_4Label  matlab.ui.control.Label
        PassengersforRelaxSpinner_4Label  matlab.ui.control.Label
        PassengersforSelfDrivingSpinner_5Label  matlab.ui.control.Label
        SelfDrivingSpinner_4            matlab.ui.control.Spinner
        RelaxSpinner_4                  matlab.ui.control.Spinner
        WorkSpinner_4                   matlab.ui.control.Spinner
        SleepSpinner_4                  matlab.ui.control.Spinner
        LoadSpinner_4                   matlab.ui.control.Spinner
        PassengersforSelfDrivingSpinner_4  matlab.ui.control.Spinner
        PassengersforRelaxSpinner_4     matlab.ui.control.Spinner
        PassengersforWorkSpinner_4      matlab.ui.control.Spinner
        PassengersforSleepSpinner_4     matlab.ui.control.Spinner
        CharacterPanel_4                matlab.ui.container.Panel
        WillingnessofMaaSSpinner_4Label  matlab.ui.control.Label
        WillingnessofMaaSSpinner_4      matlab.ui.control.Spinner
        WillingnesstoInvestSpinner_4Label  matlab.ui.control.Label
        WillingnesstoInvestSpinner_4    matlab.ui.control.Spinner
        NeedofSecuritySafetySpinner_2Label  matlab.ui.control.Label
        NeedofSecuritySafetySpinner_4   matlab.ui.control.Spinner
        MobilityBehaviorPanel_4         matlab.ui.container.Panel
        DPHighwaySpinner_6Label         matlab.ui.control.Label
        DPUrbanSpinner_2Label           matlab.ui.control.Label
        DPGeneralNeedSpinner_6Label     matlab.ui.control.Label
        DPRuralSpinner_2Label           matlab.ui.control.Label
        DPUrbanSpinner_4                matlab.ui.control.Spinner
        DPRuralSpinner_4                matlab.ui.control.Spinner
        DPHighwaySpinner_4              matlab.ui.control.Spinner
        DPGeneralNeedSpinner_4          matlab.ui.control.Spinner
        ViewSecondaryActivitiesTab_4    matlab.ui.container.Tab
        UIUser4_1                       matlab.ui.control.UIAxes
        ViewCharacterMobilityTab_4      matlab.ui.container.Tab
        UIUser4_2                       matlab.ui.control.UIAxes
        UserPanel_5                     matlab.ui.container.Panel
        TabGroup_5                      matlab.ui.container.TabGroup
        InputParametersTab_5            matlab.ui.container.Tab
        SecondaryActivitiesPanel_5      matlab.ui.container.Panel
        LoadSpinner_4Label              matlab.ui.control.Label
        SleepSpinner_5Label             matlab.ui.control.Label
        WorkSpinner_5Label              matlab.ui.control.Label
        RelaxSpinner_5Label             matlab.ui.control.Label
        SelfDrivingSpinner_4Label       matlab.ui.control.Label
        PassengersforSleepSpinner_5Label  matlab.ui.control.Label
        PassengersforWorkSpinner_5Label  matlab.ui.control.Label
        PassengersforRelaxSpinner_5Label  matlab.ui.control.Label
        PassengersforSelfDrivingSpinner_6Label  matlab.ui.control.Label
        SelfDrivingSpinner_5            matlab.ui.control.Spinner
        RelaxSpinner_5                  matlab.ui.control.Spinner
        WorkSpinner_5                   matlab.ui.control.Spinner
        SleepSpinner_5                  matlab.ui.control.Spinner
        LoadSpinner_5                   matlab.ui.control.Spinner
        PassengersforSelfDrivingSpinner_5  matlab.ui.control.Spinner
        PassengersforRelaxSpinner_5     matlab.ui.control.Spinner
        PassengersforWorkSpinner_5      matlab.ui.control.Spinner
        PassengersforSleepSpinner_5     matlab.ui.control.Spinner
        CharacterPanel_5                matlab.ui.container.Panel
        WillingnessofMaaSSpinner_5Label  matlab.ui.control.Label
        WillingnessofMaaSSpinner_5      matlab.ui.control.Spinner
        WillingnesstoInvestSpinner_5Label  matlab.ui.control.Label
        WillingnesstoInvestSpinner_5    matlab.ui.control.Spinner
        NeedofSecuritySafetySpinner_3Label  matlab.ui.control.Label
        NeedofSecuritySafetySpinner_5   matlab.ui.control.Spinner
        MobilityBehaviorPanel_5         matlab.ui.container.Panel
        DPUrbanSpinner_5                matlab.ui.control.Spinner
        DPRuralSpinner_5                matlab.ui.control.Spinner
        DPHighwaySpinner_5Label         matlab.ui.control.Label
        DPHighwaySpinner_5              matlab.ui.control.Spinner
        DPUrbanSpinner_3Label           matlab.ui.control.Label
        DPGeneralNeedSpinner_5Label     matlab.ui.control.Label
        DPGeneralNeedSpinner_5          matlab.ui.control.Spinner
        DPRuralSpinner_3Label           matlab.ui.control.Label
        ViewSecondaryActivitiesTab_5    matlab.ui.container.Tab
        UIUser5_1                       matlab.ui.control.UIAxes
        ViewCharacterMobilityTab_5      matlab.ui.container.Tab
        UIUser5_2                       matlab.ui.control.UIAxes
        UserPanel_6                     matlab.ui.container.Panel
        TabGroup_6                      matlab.ui.container.TabGroup
        InputParametersTab_6            matlab.ui.container.Tab
        SecondaryActivitiesPanel_6      matlab.ui.container.Panel
        LoadSpinner_6Label              matlab.ui.control.Label
        SleepSpinner_6Label             matlab.ui.control.Label
        WorkSpinner_6Label              matlab.ui.control.Label
        RelaxSpinner_6Label             matlab.ui.control.Label
        SelfDrivingSpinner_5Label       matlab.ui.control.Label
        PassengersforSleepSpinner_6Label  matlab.ui.control.Label
        PassengersforWorkSpinner_6Label  matlab.ui.control.Label
        PassengersforRelaxSpinner_6Label  matlab.ui.control.Label
        PassengersforSelfDrivingSpinner_4Label  matlab.ui.control.Label
        SelfDrivingSpinner_6            matlab.ui.control.Spinner
        RelaxSpinner_6                  matlab.ui.control.Spinner
        WorkSpinner_6                   matlab.ui.control.Spinner
        SleepSpinner_6                  matlab.ui.control.Spinner
        LoadSpinner_6                   matlab.ui.control.Spinner
        PassengersforSelfDrivingSpinner_6  matlab.ui.control.Spinner
        PassengersforRelaxSpinner_6     matlab.ui.control.Spinner
        PassengersforWorkSpinner_6      matlab.ui.control.Spinner
        PassengersforSleepSpinner_6     matlab.ui.control.Spinner
        CharacterPanel_6                matlab.ui.container.Panel
        WillingnessofMaaSSpinner_6Label  matlab.ui.control.Label
        WillingnessofMaaSSpinner_6      matlab.ui.control.Spinner
        WillingnesstoInvestSpinner_6Label  matlab.ui.control.Label
        WillingnesstoInvestSpinner_6    matlab.ui.control.Spinner
        NeedofSecuritySafetySpinner_5Label  matlab.ui.control.Label
        NeedofSecuritySafetySpinner_6   matlab.ui.control.Spinner
        MobilityBehaviorPanel_6         matlab.ui.container.Panel
        DPUrbanSpinner_6                matlab.ui.control.Spinner
        DPRuralSpinner_6                matlab.ui.control.Spinner
        DPHighwaySpinner_4Label         matlab.ui.control.Label
        DPHighwaySpinner_6              matlab.ui.control.Spinner
        DPUrbanSpinner_6Label           matlab.ui.control.Label
        DPGeneralNeedSpinner_4Label     matlab.ui.control.Label
        DPGeneralNeedSpinner_6          matlab.ui.control.Spinner
        DPRuralSpinner_6Label           matlab.ui.control.Label
        ViewSecondaryActivitiesTab_6    matlab.ui.container.Tab
        UIUser6_1                       matlab.ui.control.UIAxes
        ViewCharacterMobilityTab_6      matlab.ui.container.Tab
        UIUser6_2                       matlab.ui.control.UIAxes
        MultipleUsersViewPanel          matlab.ui.container.Panel
        SecondaryActivitiesofUsersPanel  matlab.ui.container.Panel
        UIActivitiesPlot                matlab.ui.control.UIAxes
        CharacterandMobilityofUsersPanel  matlab.ui.container.Panel
        UICharMobPlot                   matlab.ui.control.UIAxes
    end

    
    %% General Information:
    
    % Designed by David Fischer, Ferdinand Schockenhoff (FTM, Techical University of Munich)
    %---------------------------------------
    % Created on May 2021
    % Last update 2021-12-02
    %---------------------------------------
    % Version: Matlab2020b
    %---------------------------------------
    % Description: GUI of the process step "User Needs"
    %---------------------------------------
    % Input: Data inserted manually by the user or if a scenario is chosen
    % by the local .mat file.
    %---------------------------------------
    % Output:'user_matrix','user_Names','user_wish','derivative_suggestion','derivative_suggestion_extend',...
    %               'user_fulfillment','user2derivative','user2derivative_extend'
    % Start of the app Vehicle Provision
    
    %initialize private variables 
    properties (Access = private)
        UserMatrix % Contains all the values of the Spinner fields
        UserNames = ["User 1";"User 2";"User 3";"User 4";"User 5";"User 6"] % Displayed default Usernames
        oldpath = addpath('99_General_Elements\') % Needed for first build up of app (TUM image)
        ColorUsers = [[0 0.4 0.74];[0 0.32 0.58];[0 0.2 0.35];[0.85 0.85 0.86];[0.61 0.62 0.62];[0.36 0.36 0.35]]; %TUM Colors for Users
    end
    
    %initialize global variables
    properties (Access = public)
%        UserMatrixPublic % Needed for importing files
%        mainFigure %AppFigure
    end
    
    methods (Access = private)
        
        %Toggle the visibility of the User-Tabgroups-----------------------
        function UserPanel_visibility(app,state1,state2,state3,state4,state5,state6)
            app.TabGroup_1.Visible = state1;
            app.TabGroup_2.Visible = state2;
            app.TabGroup_3.Visible = state3;
            app.TabGroup_4.Visible = state4;
            app.TabGroup_5.Visible = state5;
            app.TabGroup_6.Visible = state6;
        end
        %------------------------------------------------------------------
        
        %Toggle all User-Tabgroups to selected tab at once-----------------
        function UserTabSelector(app,TabName)            
            TabGroupNameVector = num2cell(zeros(1,6));
            SelectedTabVector = num2cell(zeros(1,6));
            for i=1:6
                formatSpec1 = 'TabGroup_%d';
                TabGroupName = sprintf(formatSpec1,i);
                TabGroupNameVector{i} = TabGroupName;
                
                formatSpec2 = TabName + '%d';
                SelectedTab = sprintf(formatSpec2,i);
                SelectedTabVector{i} = SelectedTab;
            end
            
            for j=1:6
                app.(TabGroupNameVector{j}).SelectedTab = app.(SelectedTabVector{j});
            end
        end
        %------------------------------------------------------------------
        
        %Collect all Input data from the edit fields-----------------------
        function collectUserNeeds(app)
            userNumber = app.NumberofUsersSpinner.Value;
            user_matrix = zeros(userNumber,16);
            
            %Generate matrix with names of edit fields
            Namensvektor = ["SelfDrivingSpinner_";"RelaxSpinner_";"WorkSpinner_";"SleepSpinner_";"LoadSpinner_";"PassengersforSelfDrivingSpinner_";...
                "PassengersforRelaxSpinner_";"PassengersforWorkSpinner_";"PassengersforSleepSpinner_";"WillingnessofMaaSSpinner_";...
                "WillingnesstoInvestSpinner_";"NeedofSecuritySafetySpinner_";"DPUrbanSpinner_";"DPRuralSpinner_";"DPHighwaySpinner_";"DPGeneralNeedSpinner_"]';
            
            name_matrix = string(zeros(userNumber,16));
            for k=1:userNumber
                for in=1:16
                    name_matrix(k,in) = Namensvektor(in) + num2str(k);
                end
            end
            
            for s=1:userNumber
                for se=1:16
                    user_matrix(s,se) = app.(name_matrix(s,se)).Value;
                end
            end
            app.UserMatrix = user_matrix;
            
        end
        %------------------------------------------------------------------
        
        %Prepare data for Spider graph-------------------------------------
        function [P,lbl,leg] = spiderDataCollector(app,startvalue,endvalue)
            user_matrix = app.UserMatrix;
            numberofUsers = app.NumberofUsersSpinner.Value;
            
            %Data from user Matrix
            P = (user_matrix(:,startvalue:endvalue))';

            %Labels for the axes
            lbl_full = {'Self-Driving'; 'Relax'; 'Work'; 'Sleep'; 'Load'; 'Passengers for Self-Driving'; sprintf('Passengers \nfor Relax'); ...
                sprintf('Passengers \nfor Work'); sprintf('Passengers \nfor Sleep'); sprintf('Willingness \n    of MaaS');...
                sprintf('Willingness \n    to Invest'); sprintf('Need for \nSecurity/Safety'); 'DP Urban'; 'DP Rural'; 'DP Highway'; 'DP General Need'};
             
            lbl = lbl_full(startvalue:endvalue,1);
            
            %Legend of the graph
            leg = cell(1, numberofUsers);
            if app.UsepredefinedScenarioCheckBox.Value == true
                nameVector = app.UserNames;
                for j=1:numberofUsers
                    leg{j}=nameVector(j);
                end
            else
                for i=1:numberofUsers
                    leg{i}=sprintf('User %d',i);
                end
            end
            
            
        end
        %------------------------------------------------------------------
        
        %Prepare data for spider graph (single view)-----------------------
        function [P,lbl,leg] = spiderDataCollectorSingle(app,startvalue,endvalue,UserNumber)
            user_matrix = app.UserMatrix(UserNumber,:);
            
            %Data from user Matrix
            P = (user_matrix(:,startvalue:endvalue))';

            %Labels for the axes
            lbl_full = {'Self-Driving'; 'Relax'; 'Work'; 'Sleep'; 'Load'; 'Passengers for Self-Driving'; sprintf('Passengers \nfor Relax'); ...
                sprintf('Passengers \nfor Work'); sprintf('Passengers \nfor Sleep'); 'Willingness of MaaS'; sprintf('Willingness \n    to Invest'); ...
                sprintf('Need for \nSecurity/Safety'); 'DP Urban'; 'DP Rural'; 'DP Highway'; 'DP General Need'};
            
            lbl = lbl_full(startvalue:endvalue,1);
            
            %Legend of the graph
            leg = "User 1";
            
        end
        %------------------------------------------------------------------
        
        %Plot function for spider graph------------------------------------
        function plotSpiderGraph(app,data,lbl,ax,leg,fontsize,fts_tm,placement,colorcode)
            % size segments and number of cases
            [r,c] = size(data);
            
            min_max_rng = [0 10];
            rng = repmat (min_max_rng, r, 1);
            
            %Clean up the initial plot
            legend(ax,'off'); reset(ax); cla(ax); hold(ax,'on'); ax.BackgroundColor = [1,1,1];
            
            % set to add plot
            set(ax,'nextplot','add');
            
            % clear figure and set limits
            set(ax,'visible','off'); set(ax,'color','w');        %makes 2D plot invisible and sets background color white
            set(ax,'xlim',placement(1,:),'ylim',placement(2,:)); axis(ax,'equal','manual') %Defines size of the plot
            
            ax.Visible = 'off'; set(ax,'color','w');
            
            % scale by range
            angw = linspace(0,2*pi,r+1)';
            % wrap data (close the last axis to the first)
            ang = angw(1:end-1); 
            angs = [ang(:); ang(1)];
            
            % make the plot
            % define the axis locations
            start_ax = [zeros(1,r)+0.25; sin(ang')+0.25];
            stop_ax = [zeros(1,r); cos(ang')];
            
            % Transformation der Daten
            start = [zeros(1,r); sin(ang')];
            A_xaxis = data .* start(2,:)';
            A_xaxis = [A_xaxis; A_xaxis(1,:)];
            A_xaxis = A_xaxis *(1/10)+0.25;
            
            stop = [zeros(1,r); cos(ang')];
            A_yaxis = data .* stop(2,:)';
            A_yaxis = [A_yaxis; A_yaxis(1,:)];
            A_yaxis = A_yaxis *(1/10);
            
            % plot the axes
            plot(ax,start_ax,stop_ax,'color','k','linestyle','-','linewidth', 1); hold(ax, 'on'); axis equal;  % open figure...
            % plot axes markers
            inc = (1/5):(1/5):1; 
            tx = 0.05 * ones(1,5);
            
            %fontsize tick marks
            fonttm = fts_tm;
            
            %fontsize legend
            fontleg = fontsize-4;
            % loop each axis ang plot the line markers and labels
            % add axes
            for ii = 1:r
            	% plot tick lines
                plot(ax,[sin(angs(ii)) * inc; sin(angs(ii+1)) * inc] +0.25, ...
            		[(cos(angs(ii)) * inc); cos(angs(ii+1)) * inc],'color','k','linewidth', 1);
                
            	% label the tick marks
            	for jj = 1:5
                    temp = text(ax,sin(angs(ii)) * inc(jj) + cos(angs(ii)) * tx(jj)+0.25, ...
            			cos(angs(ii)) * inc(jj) - sin(angs(ii)) * tx(jj), ...
            			num2str(rd(app,rng(ii,1) + inc(jj)*diff(rng(ii,:)),-2)), ...
            			'fontsize',fonttm, 'FontName', 'Arial');
            		% flip the text alignment for lower axes
            		if angs(ii) >= pi
            			set(temp,'HorizontalAlignment','right');
            		end
            	end
            	% label each axis
            	temp = text(ax,sin(angs(ii)) * 1.1 + cos(angs(ii)) * 0+0.25, cos(angs(ii)) * 1.1 - sin(angs(ii)) * 0, ...
        			char(lbl(ii,:)),'fontsize',fontsize, 'FontName', 'Arial');
            	% flip the text alignment for right side axes
            	if angs(ii) >= pi
            		set(temp,'HorizontalAlignment','right');
            	end
            end
            
            %plot the data
            o = plot(ax,A_xaxis,A_yaxis);
            
            % define data case colors
            col = app.ColorUsers;

            % define data case linestyles
            linsty = {'-'; ':'; '--'; '-'; ':'; '--'};
            
            % set color
            switch colorcode
                case 0
                    for ii=1:c
                        set(o(ii),'color',col(ii,:), 'LineWidth', 3, 'LineStyle', linsty{ii});
                    end
                case 1
                    set(o(1),'color',col(1,:), 'LineWidth', 3, 'LineStyle', linsty{1});
                case 2
                    set(o(1),'color',col(2,:), 'LineWidth', 3, 'LineStyle', linsty{2});
                case 3
                    set(o(1),'color',col(3,:), 'LineWidth', 3, 'LineStyle', linsty{3});
                case 4
                    set(o(1),'color',col(4,:), 'LineWidth', 3, 'LineStyle', linsty{4});
                case 5
                    set(o(1),'color',col(5,:), 'LineWidth', 3, 'LineStyle', linsty{5});
                case 6
                    set(o(1),'color',col(6,:), 'LineWidth', 3, 'LineStyle', linsty{6});
            end
            
            legend(o, leg, 'FontName', 'Arial', 'fontsize',fontleg);
            hold on
            close
        end
        %------------------------------------------------------------------
        
        %quick round function (to specified decimal)-----------------------
        function [v] = rd(~,v,dec)
            if nargin == 1; dec = 0; end
            
            v = v / 10^dec;
            v = round(v);
            v = v * 10^dec;
        end
        %------------------------------------------------------------------
        
        %Shortcall for single user plots-----------------------------------
        function plotSingleSpiderCall(app,UserNumber,UIaxes,GraphNumber,color)
            collectUserNeeds(app)
            if GraphNumber == 1
                [P,lbl,leg] = spiderDataCollectorSingle(app,1,9,UserNumber);
            else
                [P,lbl,leg] = spiderDataCollectorSingle(app,10,16,UserNumber);
            end
            place = [-1.1,1.1;-1.1,1.1];
            plotSpiderGraph(app,P,lbl,UIaxes,leg,12,9,place,color)
            UIaxes.Legend.Visible = "off";
        end
        %------------------------------------------------------------------
        
        %Making the chosen scenario visible--------------------------------
        function ScenarioData(app)
            scenario = app.ScenarioDropDown.Value;
            UFvalue = app.UserNeedsFulfillmentinDropDown.Value;
            app.UserNeedsFulfillmentinSpinner.Value = str2double(UFvalue);
            
            switch scenario
                case 'Family'
                    userMatrix = load("01_User Needs\Scenario_Data.mat","userMatrix_sc1");
                    userNames = load("01_User Needs\Scenario_Data.mat","userNames_sc1");
                    app.NumberofUsersSpinner.Value = 4;
                case 'Daily Grind'
                    userMatrix = load("01_User Needs\Scenario_Data.mat","userMatrix_sc2");
                    userNames = load("01_User Needs\Scenario_Data.mat","userNames_sc2");
                    app.NumberofUsersSpinner.Value = 6;
                case 'Commuter'
                    userMatrix = load("01_User Needs\Scenario_Data.mat","userMatrix_sc3");
                    userNames = load("01_User Needs\Scenario_Data.mat","userNames_sc3");
                    app.NumberofUsersSpinner.Value = 6;
            end
            %loaded data generates a 1x1 struct which includes the array!
            userNames = struct2cell(userNames);
            app.UserNames = userNames{1,1};
            userMatrix = struct2cell(userMatrix);
            app.UserMatrix = userMatrix{1,1};
        end
        %------------------------------------------------------------------

        %Fills parameters of spinners from given user Matrix---------------
        function ParameterFillUp(app)
            userNumber = app.NumberofUsersSpinner.Value;
            user_matrix = app.UserMatrix;
            
            %Generate matrix with names of edit fields
            Namensvektor = ["SelfDrivingSpinner_";"RelaxSpinner_";"WorkSpinner_";"SleepSpinner_";"LoadSpinner_";"PassengersforSelfDrivingSpinner_";...
                "PassengersforRelaxSpinner_";"PassengersforWorkSpinner_";"PassengersforSleepSpinner_";"WillingnessofMaaSSpinner_";...
                "WillingnesstoInvestSpinner_";"NeedofSecuritySafetySpinner_";"DPUrbanSpinner_";"DPRuralSpinner_";"DPHighwaySpinner_";"DPGeneralNeedSpinner_"]';

            name_matrix = string(zeros(userNumber,16));
            for k=1:userNumber
                for in=1:16
                    name_matrix(k,in) = Namensvektor(in) + num2str(k);
                end
            end
            
            for s=1:userNumber
                for se=1:16
                    app.(name_matrix(s,se)).Value = user_matrix(s,se);
                end
            end
        end
        %------------------------------------------------------------------
        
        %Enable or disable state of parameter panels-----------------------
        function SingleUserParameterEnabler(app,forename,state)            
            nameVector = num2cell(zeros(1,6));
            for i=1:6
                formatSpec1 = forename + '_%d';
                name = sprintf(formatSpec1,i);
                nameVector{i} = name;  
            end
            
            for j=1:6
                app.(nameVector{j}).Enable = state;
            end
        end
        %------------------------------------------------------------------
   
         %Shortcall for multiple users plot--------------------------------
        function plotMultipleSpiderCall(app)
            [P,lbl,leg] = spiderDataCollector(app,1,9);
            [P2,lbl2,leg2] = spiderDataCollector(app,10,16);
            place1 = [-1.5,1.5;-1.5,1.5];
            place2 = [-1.5,1.5;-1.5,1.5];
            plotSpiderGraph(app,P,lbl,app.UIActivitiesPlot,leg,16,10,place1,0)
            plotSpiderGraph(app,P2,lbl2,app.UICharMobPlot,leg2,16,10,place2,0)
        end
        %------------------------------------------------------------------
        
        %Changes the title of the user panels------------------------------
        function UsernameChanger(app,nameVector)
            userNumber = app.NumberofUsersSpinner.Value;
            for i=1:userNumber
                formatSpec1 = 'UserPanel_%d';
                name = sprintf(formatSpec1,i);
                app.(name).Title = nameVector(i);
            end
            
            if userNumber < 6
                userNumber = userNumber + 1;
                for j=userNumber:6
                    formatSpec1 = 'UserPanel_%d';
                    name = sprintf(formatSpec1,j);
                    app.(name).Title = 'No User selected';
                end
            end
        end
        %------------------------------------------------------------------
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Adding necessary folders to path
            addpath('99_General_Elements\')
            addpath(genpath('01_User Needs\'))
            addpath(genpath('02_Simulation_UN2VP\'))
            addpath(genpath('03_Vehicle Provision\'))
            %Setting up the first view
            app.MultipleUsersViewPanel.Visible = false;
            UserPanel_visibility(app,"on","off","off","off","off","off")
            app.ScenariosPanel.Enable = "off";
            UsernameChanger(app,app.UserNames)
            %Opening the window maximized
            drawnow;
            app.MainScreenUN.WindowState = 'maximized';
        end

        % Value changed function: NumberofUsersSpinner
        function NumberofUsersSpinnerValueChanged(app, event)
            value = app.NumberofUsersSpinner.Value;
            %Change the user panel names
            value2 = app.UsepredefinedScenarioCheckBox.Value;
            if value2 == false
                UsernameChanger(app,app.UserNames)
            end
            %Set up the user panel visibility
            switch value
                case 1
                    UserPanel_visibility(app,"on","off","off","off","off","off")
                case 2
                    UserPanel_visibility(app,"on","on","off","off","off","off")
                case 3
                    UserPanel_visibility(app,"on","on","on","off","off","off")
                case 4
                    UserPanel_visibility(app,"on","on","on","on","off","off")
                case 5
                    UserPanel_visibility(app,"on","on","on","on","on","off")
                case 6
                    UserPanel_visibility(app,"on","on","on","on","on","on")
            end
        end

        % Button pushed function: ShowInputParametersButton
        function ShowInputParametersButtonPushed(app, event)
            app.SingleUserViewPanel.Visible = true;
            app.MultipleUsersViewPanel.Visible = false;
            %Shows the input parameter tab
            UserTabSelector(app,"InputParametersTab_")
        end

        % Button pushed function: VisualizeSecondaryActivitiesButton
        function VisualizeSecondaryActivitiesButtonPushed(app, event)
            app.SingleUserViewPanel.Visible = true;
            app.MultipleUsersViewPanel.Visible = false;
            %Plots every single spider plot and opens tab
            value = app.NumberofUsersSpinner.Value;
            for i=1:value
                formatSpec1 = 'UIUser%d_1';
                name = sprintf(formatSpec1,i);
                plotSingleSpiderCall(app,i,app.(name),1,i)
            end
            UserTabSelector(app,"ViewSecondaryActivitiesTab_")
        end

        % Button pushed function: VisualizeCharacterMobilityButton
        function VisualizeCharacterMobilityButtonPushed(app, event)
            app.SingleUserViewPanel.Visible = true;
            app.MultipleUsersViewPanel.Visible = false;
            %Plots every single spider plot and opens tab
            value = app.NumberofUsersSpinner.Value;
            for i=1:value
                formatSpec1 = 'UIUser%d_2';
                name = sprintf(formatSpec1,i);
                plotSingleSpiderCall(app,i,app.(name),2,i)
            end
            UserTabSelector(app,"ViewCharacterMobilityTab_")
        end

        % Button pushed function: ShowallUserstogetherButton_2
        function ShowallUserstogetherButton_2Pushed(app, event)
            %set up data and plot
            collectUserNeeds(app)
            plotMultipleSpiderCall(app)
            %Change the state of the main view panels
            app.SingleUserViewPanel.Visible = false;
            app.MultipleUsersViewPanel.Visible = true;
        end

        % Selection change function: TabGroup_1
        function TabGroup_1SelectionChanged(app, event)
            %Updates and plots the graph for the selected tab
            selectedTab = app.TabGroup_1.SelectedTab;
            switch selectedTab
                case app.ViewSecondaryActivitiesTab_1
                    plotSingleSpiderCall(app,1,app.UIUser1_1,1,1)
                case app.ViewCharacterMobilityTab_1
                    plotSingleSpiderCall(app,1,app.UIUser1_2,2,1)
            end
        end

        % Selection change function: TabGroup_2
        function TabGroup_2SelectionChanged(app, event)
            %Updates and plots the graph for the selected tab
            selectedTab = app.TabGroup_2.SelectedTab;
            switch selectedTab
                case app.ViewSecondaryActivitiesTab_2
                    plotSingleSpiderCall(app,2,app.UIUser2_1,1,2)
                case app.ViewCharacterMobilityTab_2
                    plotSingleSpiderCall(app,2,app.UIUser2_2,2,2)
            end
        end

        % Selection change function: TabGroup_3
        function TabGroup_3SelectionChanged(app, event)
            %Updates and plots the graph for the selected tab
            selectedTab = app.TabGroup_3.SelectedTab;
            switch selectedTab
                case app.ViewSecondaryActivitiesTab_3
                    plotSingleSpiderCall(app,3,app.UIUser3_1,1,3)
                case app.ViewCharacterMobilityTab_3
                    plotSingleSpiderCall(app,3,app.UIUser3_2,2,3)
            end
        end

        % Selection change function: TabGroup_4
        function TabGroup_4SelectionChanged(app, event)
            %Updates and plots the graph for the selected tab
            selectedTab = app.TabGroup_4.SelectedTab;
            switch selectedTab
                case app.ViewSecondaryActivitiesTab_4
                    plotSingleSpiderCall(app,4,app.UIUser4_1,1,4)
                case app.ViewCharacterMobilityTab_4
                    plotSingleSpiderCall(app,4,app.UIUser4_2,2,4)
            end
        end

        % Selection change function: TabGroup_5
        function TabGroup_5SelectionChanged(app, event)
            %Updates and plots the graph for the selected tab
            selectedTab = app.TabGroup_5.SelectedTab;
            switch selectedTab
                case app.ViewSecondaryActivitiesTab_5
                    plotSingleSpiderCall(app,5,app.UIUser5_1,1,5)
                case app.ViewCharacterMobilityTab_5
                    plotSingleSpiderCall(app,5,app.UIUser5_2,2,5)
            end
        end

        % Selection change function: TabGroup_6
        function TabGroup_6SelectionChanged(app, event)
            %Updates and plots the graph for the selected tab
            selectedTab = app.TabGroup_6.SelectedTab;
            switch selectedTab
                case app.ViewSecondaryActivitiesTab_6
                    plotSingleSpiderCall(app,6,app.UIUser6_1,1,6)
                case app.ViewCharacterMobilityTab_6
                    plotSingleSpiderCall(app,6,app.UIUser6_2,2,6)
            end
        end

        % Value changed function: UsepredefinedScenarioCheckBox
        function UsepredefinedScenarioCheckBoxValueChanged(app, event)
            value = app.UsepredefinedScenarioCheckBox.Value;
            if value == false
                %Change the state of the scenario panel and the main
                %parameters
                app.ScenariosPanel.Enable = "off";
                app.MainParametersPanel.Enable = "On";
                %edit the UserNames and change user number
                app.UserNames = ["User 1";"User 2";"User 3";"User 4";"User 5";"User 6"];
                NumberofUsersSpinnerValueChanged(app, event)
                %Set up the remaining butons
                SingleUserParameterEnabler(app,"SecondaryActivitiesPanel","On")
                SingleUserParameterEnabler(app,"CharacterPanel","On")
                SingleUserParameterEnabler(app,"MobilityBehaviorPanel","On")
                app.SaveButton.Enable = "on";
                app.LoadButton.Enable = "on";
            else
                %Open a confirmation dialog box
                fig = app.MainScreenUN;
                selection = uiconfirm(fig,'Do you really want to open a predefined scenario? This will overwrite all current parameters.',...
                    'Confirm scenario selection','Icon','warning');
                tf = strcmp(selection,'OK');
                if tf == true
                    %Change the state of the scenario panel and the main
                    %parameters
                    app.ScenariosPanel.Enable = "on";
                    app.MainParametersPanel.Enable = "Off";
                    %Set up the remaining butons
                    SingleUserParameterEnabler(app,"SecondaryActivitiesPanel","Off")
                    SingleUserParameterEnabler(app,"CharacterPanel","Off")
                    SingleUserParameterEnabler(app,"MobilityBehaviorPanel","Off")
                    app.SaveButton.Enable = "off";
                    app.LoadButton.Enable = "off";
                    %Activate the scenario event for data
                    ScenarioDropDownValueChanged(app, event)
                else
                    app.UsepredefinedScenarioCheckBox.Value = false;
                end
            end
        end

        % Value changed function: ScenarioDropDown
        function ScenarioDropDownValueChanged(app, event)
            ScenarioData(app)
            ParameterFillUp(app)
            NumberofUsersSpinnerValueChanged(app, event)
            %only use this convertion after scenarioData was executed
            nameVector = app.UserNames;
            UsernameChanger(app,nameVector)
        end

        % Value changed function: UserNeedsFulfillmentinDropDown
        function UserNeedsFulfillmentinDropDownValueChanged(app, event)
            UFvalue = app.UserNeedsFulfillmentinDropDown.Value;
            app.UserNeedsFulfillmentinSpinner.Value = str2double(UFvalue);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            if app.MultipleUsersViewPanel.Visible == true
                %Update Multiple user view
                collectUserNeeds(app)
                plotMultipleSpiderCall(app)
            else
                %Update single user view
                value = app.NumberofUsersSpinner.Value;
                for i=1:value
                    formatSpec1 = 'UIUser%d_1';
                    name = sprintf(formatSpec1,i);
                    plotSingleSpiderCall(app,i,app.(name),1,i)
                end
                for i=1:value
                    formatSpec1 = 'UIUser%d_2';
                    name = sprintf(formatSpec1,i);
                    plotSingleSpiderCall(app,i,app.(name),2,i)
                end
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %Open a confirmation dialog box
            fig = app.MainScreenUN;
            selection = uiconfirm(fig,'Do you really want to reset all parameters?','Confirm parameter reset','Icon','warning');
            tf = strcmp(selection,'OK');
            %Execute only when the user agrees
            if tf == true
                %Activating all submenu panels
                app.MainParametersPanel.Enable = "on";
                app.DataOptionsPanel.Enable = "on";
                app.SaveButton.Enable = "on";
                app.LoadButton.Enable = "on";
                %Deactivating and reseting the Scenarios
                app.UsepredefinedScenarioCheckBox.Value = false;
                app.ScenariosPanel.Enable = "off";
                app.UserNeedsFulfillmentinDropDown.Value = '50';
                app.ScenarioDropDown.Value = 'Family';
                %Setting up the main view panel
                UserTabSelector(app,"InputParametersTab_")
                app.SingleUserViewPanel.Visible = true;
                app.MultipleUsersViewPanel.Visible = false;
                SingleUserParameterEnabler(app,"SecondaryActivitiesPanel","On")
                SingleUserParameterEnabler(app,"CharacterPanel","On")
                SingleUserParameterEnabler(app,"MobilityBehaviorPanel","On")
                UserPanel_visibility(app,"on","off","off","off","off","off")
                %reseting all main parameters and input parameters
                app.UserNeedsFulfillmentinSpinner.Value = 70;
                app.UserMatrix = zeros(6,16);
                app.NumberofUsersSpinner.Value = 6; %Setting temporary to 6 for resetting input parameters
                ParameterFillUp(app)
                app.NumberofUsersSpinner.Value = 1;
                %Setting up the name of the user panels
                nameVector = ["User 1";"User 2";"User 3";"User 4";"User 5";"User 6"];
                UsernameChanger(app,nameVector)
                app.UserNames = nameVector;
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %Open the dialog box to save .mat file
            filename = uiputfile('*.mat','myScenario','01_User Needs\UN_Saved_Scenarios\');
            if filename ~= 0
                collectUserNeeds(app)
                saveData = app.UserMatrix;
                newName = append('01_User Needs\UN_Saved_Scenarios\',filename);
                save(newName,'saveData')
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            %Open the dialog box to choose .mat file
            filename = uigetfile('01_User Needs\UN_Saved_Scenarios\','*.mat');
            if filename ~= 0
                load(filename, 'saveData');
                %Showing all Users
                [rows,~] = size(saveData);
                app.NumberofUsersSpinner.Value = rows;
                NumberofUsersSpinnerValueChanged(app, event)
                %Filling the loaded data into parameter edit fields
                app.UserMatrixPublic = saveData;
                app.UserMatrix = app.UserMatrixPublic;
                ParameterFillUp(app)
            end
        end

        % Button pushed function: ProceedandRunCalculationButton
        function ProceedandRunCalculationButtonPushed(app, event)
            %Open a confirmation dialog box
            fig = app.MainScreenUN;
            selection = uiconfirm(fig,'Do you want to proceed and start the optimization?','Confirm optimization start','Icon','question');
            tf = strcmp(selection,'OK');
            %Execute only when the user agrees
            if tf == true
                if app.UsepredefinedScenarioCheckBox.Value == true
                    %check which scenario is used
                    scenarioName = app.ScenarioDropDown.Value;
                    scenarioUF = app.UserNeedsFulfillmentinDropDown.Value;
                    pathName = append('03_Vehicle Provision\VP_Saved_Scenarios\VP ',scenarioName,' ',scenarioUF,'UF');
                    load(pathName,'user_matrix','user_Names','user_wish','derivative_suggestion','derivative_suggestion_extend',...
                        'user_fulfillment','user2derivative','user2derivative_extend');
                    VehicleProvision(user_matrix,user_Names,user_wish,derivative_suggestion,...
                            derivative_suggestion_extend,user_fulfillment,user2derivative,user2derivative_extend)
                else
                    %prepare data for simulation
                    collectUserNeeds(app)
                    user_matrix = app.UserMatrix;
                    user_wish = app.UserNeedsFulfillmentinSpinner.Value / 100;
                    figure = app.MainScreenUN;
                    user_Names = app.UserNames;
                    %run the simulation as external function
                    [derivative_suggestion,derivative_suggestion_extend,user_fulfillment,user2derivative,user2derivative_extend] ...
                        = userNeeds2vehicleProvision(user_matrix,user_wish,figure);
                    %Save the results of the simulation if the user agrees
                    selection2 = uiconfirm(figure,'Do you want to save the results?','Saving Results','Icon','question');
                    tf2 = strcmp(selection2,'OK');
                    if tf2 == true
                        filename = uiputfile('*.mat','myScenario_VP','03_Vehicle Provision\VP_Saved_Scenarios\');
                        if filename ~= 0
                            newName = append('03_Vehicle Provision\VP_Saved_Scenarios\',filename);
                            save(newName,'user_matrix','user_Names','user_wish','derivative_suggestion','derivative_suggestion_extend',...
                                'user_fulfillment','user2derivative','user2derivative_extend')
                        end
                    end
                    %open the next app (vehicle provision) and transfer data
                    selection3 = uiconfirm(figure,'Do you want to open the Vehicle Provision app?','Open Vehicle Provision app','Icon','question');
                    tf3 = strcmp(selection3,'OK');
                    if tf3 == true
                        if isempty(derivative_suggestion) == true
                            derivative_suggestion = zeros(1,18);
                            derivative_suggestion_extend = zeros(2,18);
                        end
                        VehicleProvision(user_matrix,user_Names,user_wish,derivative_suggestion,...
                            derivative_suggestion_extend,user_fulfillment,user2derivative,user2derivative_extend)
                    end
                end
            end
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            %Open a confirmation dialog box
            fig = app.MainScreenUN;
            message = sprintf(['Do you want to return to the Home screen? \n\n'...
                'Consider saving their current results. Otherwise it will be deleted.']);
            selection = uiconfirm(fig,message,'Confirm return to Home screen','Icon','question');
            tf = strcmp(selection,'OK');
            %Execute only when the user agrees
            if tf == true
                Start;
                delete(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainScreenUN = uifigure('Visible', 'off');
            app.MainScreenUN.Name = 'User-bound Mobility Needs';
            app.MainScreenUN.Icon = 'FTMLogo.PNG';
            app.HeaderAppName = uilabel(app.MainScreenUN);
            app.HeaderAppName.Text = '              Step 1.1: User-bound Mobility Needs';
            app.TUMlogo = uiimage(app.MainScreenUN);
            app.TUMlogo.ImageSource = '2015_Logo_TUM_neg_RGB.svg';
            app.HomeButton = uibutton(app.MainScreenUN, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Icon = 'Home.svg';
            app.HomeButton.Text = '';
            app.MainMenuPanel = uipanel(app.MainScreenUN);
            app.MainMenuPanel.Title = 'Main Menu';
            app.ProceedandRunCalculationButton = uibutton(app.MainMenuPanel, 'push');
            app.ProceedandRunCalculationButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedandRunCalculationButtonPushed, true);
            app.ProceedandRunCalculationButton.Tooltip = {'Proceed to GUI "Vehicle Provision" by starting the calculation of the necessary Derivatives.'};
            app.ProceedandRunCalculationButton.Text = 'Proceed and Run Calculation';
            app.MainParametersPanel = uipanel(app.MainMenuPanel);
            app.MainParametersPanel.Title = 'Main Parameters';
            app.NumberofUsersSpinnerLabel = uilabel(app.MainParametersPanel);
            app.NumberofUsersSpinnerLabel.Text = 'Number of Users';
            app.NumberofUsersSpinner = uispinner(app.MainParametersPanel);
            app.NumberofUsersSpinner.Limits = [1 6];
            app.NumberofUsersSpinner.RoundFractionalValues = 'on';
            app.NumberofUsersSpinner.ValueDisplayFormat = '%.0f';
            app.NumberofUsersSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofUsersSpinnerValueChanged, true);
            app.NumberofUsersSpinner.Tooltip = {'Number of persons who are interested in using a mobility device.'};
            app.NumberofUsersSpinner.Value = 1;
            app.UserNeedsFulfillmentinSpinnerLabel = uilabel(app.MainParametersPanel);
            app.UserNeedsFulfillmentinSpinnerLabel.Text = 'User Needs Fulfillment in %';
            app.UserNeedsFulfillmentinSpinner = uispinner(app.MainParametersPanel);
            app.UserNeedsFulfillmentinSpinner.Step = 5;
            app.UserNeedsFulfillmentinSpinner.Limits = [50 80];
            app.UserNeedsFulfillmentinSpinner.ValueDisplayFormat = '%.0f';
            app.UserNeedsFulfillmentinSpinner.Tooltip = {'Determines the degree of satisfaction of the selected User Needs by the resulting Vehicle Provision.'; 'Parameter needed as a target value for the calculation.'};
            app.UserNeedsFulfillmentinSpinner.Value = 70;
            app.DisplayOptionsPanel = uipanel(app.MainMenuPanel);
            app.DisplayOptionsPanel.Title = 'Display Options';
            app.ShowInputParametersButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.ShowInputParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ShowInputParametersButtonPushed, true);
            app.ShowInputParametersButton.Tooltip = {'Displays all input parameter tabs.'};
            app.ShowInputParametersButton.Text = 'Show Input Parameters';
            app.VisualizeSecondaryActivitiesButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.VisualizeSecondaryActivitiesButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeSecondaryActivitiesButtonPushed, true);
            app.VisualizeSecondaryActivitiesButton.Tooltip = {'Displays all Secondary Activities tabs.'};
            app.VisualizeSecondaryActivitiesButton.Text = 'Visualize Secondary Activities';
            app.VisualizeCharacterMobilityButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.VisualizeCharacterMobilityButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeCharacterMobilityButtonPushed, true);
            app.VisualizeCharacterMobilityButton.Tooltip = {'Displays all Character/Mobility tabs.'};
            app.VisualizeCharacterMobilityButton.Text = 'Visualize Character/Mobility';
            app.ShowallUserstogetherButton_2 = uibutton(app.DisplayOptionsPanel, 'push');
            app.ShowallUserstogetherButton_2.ButtonPushedFcn = createCallbackFcn(app, @ShowallUserstogetherButton_2Pushed, true);
            app.ShowallUserstogetherButton_2.Tooltip = {'Shows all Users in one spidergraph.'};
            app.ShowallUserstogetherButton_2.Text = 'Show all Users together';
            app.DataOptionsPanel = uipanel(app.MainMenuPanel);
            app.DataOptionsPanel.Title = 'Data Options';
            app.SaveButton = uibutton(app.DataOptionsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tooltip = {'Saves the current parameters into a .mat file. This file can be opened later by the Load button.'};
            app.SaveButton.Text = 'Save';
            app.ResetButton = uibutton(app.DataOptionsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tooltip = {'Resets all parameters and sets display to the start view.'};
            app.ResetButton.Text = 'Reset';
            app.LoadButton = uibutton(app.DataOptionsPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Tooltip = {'Loads saved parameters from .mat file.'; 'Notice: Only previously saved files can be openend!'};
            app.LoadButton.Text = 'Load';
            app.UpdateButton = uibutton(app.DataOptionsPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Tooltip = {'Updates all visible spidergraphs.'};
            app.UpdateButton.Text = 'Update';
            app.ScenariosPanel = uipanel(app.MainMenuPanel);
            app.ScenariosPanel.Title = 'Scenarios';
            app.ScenarioDropDownLabel = uilabel(app.ScenariosPanel);
            app.ScenarioDropDownLabel.Text = 'Scenario';
            app.ScenarioDropDown = uidropdown(app.ScenariosPanel);
            app.ScenarioDropDown.Items = {'Family', 'Daily Grind', 'Commuter'};
            app.ScenarioDropDown.ValueChangedFcn = createCallbackFcn(app, @ScenarioDropDownValueChanged, true);
            app.ScenarioDropDown.Tooltip = {'Choose from different predefined scenarios.'};
            app.ScenarioDropDown.Value = 'Family';
            app.UserNeedsFulfillmentinDropDownLabel = uilabel(app.ScenariosPanel);
            app.UserNeedsFulfillmentinDropDownLabel.Text = 'User Needs Fulfillment in %';
            app.UserNeedsFulfillmentinDropDown = uidropdown(app.ScenariosPanel);
            app.UserNeedsFulfillmentinDropDown.Items = {'50', '60', '70', '80'};
            app.UserNeedsFulfillmentinDropDown.ValueChangedFcn = createCallbackFcn(app, @UserNeedsFulfillmentinDropDownValueChanged, true);
            app.UserNeedsFulfillmentinDropDown.Tooltip = {'Determines the degree of satisfaction of the selected User Needs by the resulting Vehicle Provision.'; 'Parameter needed as a target value for the calculation.'};
            app.UserNeedsFulfillmentinDropDown.Value = '50';
            app.UsepredefinedScenarioCheckBox = uicheckbox(app.MainMenuPanel);
            app.UsepredefinedScenarioCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsepredefinedScenarioCheckBoxValueChanged, true);
            app.UsepredefinedScenarioCheckBox.Tooltip = {'Enables the choice of a predefined scenario.'};
            app.UsepredefinedScenarioCheckBox.Text = ' Use predefined Scenario?';
            app.SingleUserViewPanel = uipanel(app.MainScreenUN);
            app.SingleUserViewPanel.Title = 'Single User View';
            app.UserPanel_1 = uipanel(app.SingleUserViewPanel);
            app.UserPanel_1.Title = 'User 1';
            app.TabGroup_1 = uitabgroup(app.UserPanel_1);
            app.TabGroup_1.SelectionChangedFcn = createCallbackFcn(app, @TabGroup_1SelectionChanged, true);
            app.InputParametersTab_1 = uitab(app.TabGroup_1);
            app.InputParametersTab_1.Title = 'Input Parameters';
            app.SecondaryActivitiesPanel_1 = uipanel(app.InputParametersTab_1);
            app.SecondaryActivitiesPanel_1.Tooltip = {''};
            app.SecondaryActivitiesPanel_1.Title = 'Secondary Activities';
            app.SelfDrivingSpinner_1 = uispinner(app.SecondaryActivitiesPanel_1);
            app.SelfDrivingSpinner_1.Limits = [0 10];
            app.SelfDrivingSpinner_1.ValueDisplayFormat = '%.1f';
            app.RelaxSpinner_1 = uispinner(app.SecondaryActivitiesPanel_1);
            app.RelaxSpinner_1.Limits = [0 10];
            app.RelaxSpinner_1.ValueDisplayFormat = '%.1f';
            app.WorkSpinner_1 = uispinner(app.SecondaryActivitiesPanel_1);
            app.WorkSpinner_1.Limits = [0 10];
            app.WorkSpinner_1.ValueDisplayFormat = '%.1f';
            app.SleepSpinner_1 = uispinner(app.SecondaryActivitiesPanel_1);
            app.SleepSpinner_1.Limits = [0 10];
            app.SleepSpinner_1.ValueDisplayFormat = '%.1f';
            app.LoadSpinner_1 = uispinner(app.SecondaryActivitiesPanel_1);
            app.LoadSpinner_1.Limits = [0 10];
            app.LoadSpinner_1.ValueDisplayFormat = '%.1f';
            app.PassengersforSelfDrivingSpinner_1 = uispinner(app.SecondaryActivitiesPanel_1);
            app.PassengersforSelfDrivingSpinner_1.Limits = [0 9];
            app.PassengersforSelfDrivingSpinner_1.ValueDisplayFormat = '%.1f';
            app.PassengersforRelaxSpinner_1 = uispinner(app.SecondaryActivitiesPanel_1);
            app.PassengersforRelaxSpinner_1.Limits = [0 9];
            app.PassengersforRelaxSpinner_1.ValueDisplayFormat = '%.1f';
            app.PassengersforWorkSpinner_1 = uispinner(app.SecondaryActivitiesPanel_1);
            app.PassengersforWorkSpinner_1.Limits = [0 9];
            app.PassengersforWorkSpinner_1.ValueDisplayFormat = '%.1f';
            app.PassengersforSleepSpinner_1 = uispinner(app.SecondaryActivitiesPanel_1);
            app.PassengersforSleepSpinner_1.Limits = [0 9];
            app.PassengersforSleepSpinner_1.ValueDisplayFormat = '%.1f';
            app.LoadSpinnerLabel = uilabel(app.SecondaryActivitiesPanel_1);
            app.LoadSpinnerLabel.Text = 'Load';
            app.SleepSpinnerLabel = uilabel(app.SecondaryActivitiesPanel_1);
            app.SleepSpinnerLabel.Text = 'Sleep';
            app.WorkSpinnerLabel = uilabel(app.SecondaryActivitiesPanel_1);
            app.WorkSpinnerLabel.Text = 'Work';
            app.RelaxSpinnerLabel = uilabel(app.SecondaryActivitiesPanel_1);
            app.RelaxSpinnerLabel.Text = 'Relax';
            app.SelfDrivingSpinnerLabel = uilabel(app.SecondaryActivitiesPanel_1);
            app.SelfDrivingSpinnerLabel.Text = 'Self-Driving';
            app.PassengersforSleepSpinnerLabel = uilabel(app.SecondaryActivitiesPanel_1);
            app.PassengersforSleepSpinnerLabel.Text = 'Passengers for Sleep';
            app.PassengersforWorkSpinnerLabel = uilabel(app.SecondaryActivitiesPanel_1);
            app.PassengersforWorkSpinnerLabel.Text = 'Passengers for Work';
            app.PassengersforRelaxSpinnerLabel = uilabel(app.SecondaryActivitiesPanel_1);
            app.PassengersforRelaxSpinnerLabel.Text = 'Passengers for Relax';
            app.PassengersforSelfDrivingSpinnerLabel = uilabel(app.SecondaryActivitiesPanel_1);
            app.PassengersforSelfDrivingSpinnerLabel.Text = 'Passengers for Self-Driving';
            app.CharacterPanel_1 = uipanel(app.InputParametersTab_1);
            app.CharacterPanel_1.Tooltip = {'Character of the user concerning mobility.'; '(MaaS = Mobility as a Service)'};
            app.CharacterPanel_1.Title = 'Character';
            app.WillingnessofMaaSSpinnerLabel = uilabel(app.CharacterPanel_1);
            app.WillingnessofMaaSSpinnerLabel.Text = 'Willingness of MaaS';
            app.WillingnessofMaaSSpinner_1 = uispinner(app.CharacterPanel_1);
            app.WillingnessofMaaSSpinner_1.Limits = [0 10];
            app.WillingnessofMaaSSpinner_1.ValueDisplayFormat = '%.1f';
            app.WillingnesstoInvestSpinnerLabel = uilabel(app.CharacterPanel_1);
            app.WillingnesstoInvestSpinnerLabel.Text = 'Willingness to Invest';
            app.WillingnesstoInvestSpinner_1 = uispinner(app.CharacterPanel_1);
            app.WillingnesstoInvestSpinner_1.Limits = [0 10];
            app.WillingnesstoInvestSpinner_1.ValueDisplayFormat = '%.1f';
            app.NeedofSecuritySafetySpinnerLabel = uilabel(app.CharacterPanel_1);
            app.NeedofSecuritySafetySpinnerLabel.Text = 'Need of Security/Safety';
            app.NeedofSecuritySafetySpinner_1 = uispinner(app.CharacterPanel_1);
            app.NeedofSecuritySafetySpinner_1.Limits = [0 10];
            app.NeedofSecuritySafetySpinner_1.ValueDisplayFormat = '%.1f';
            app.MobilityBehaviorPanel_1 = uipanel(app.InputParametersTab_1);
            app.MobilityBehaviorPanel_1.Tooltip = {'Composition of the mobility parts describes the purpose of the vehicle usage.'; '(DP = Driving Profile)'};
            app.MobilityBehaviorPanel_1.Title = 'Mobility Behavior';
            app.DPUrbanSpinner_1 = uispinner(app.MobilityBehaviorPanel_1);
            app.DPUrbanSpinner_1.Limits = [0 10];
            app.DPUrbanSpinner_1.ValueDisplayFormat = '%.1f';
            app.DPUrbanSpinner_1.Tooltip = {''};
            app.DPRuralSpinner_1 = uispinner(app.MobilityBehaviorPanel_1);
            app.DPRuralSpinner_1.Limits = [0 10];
            app.DPRuralSpinner_1.ValueDisplayFormat = '%.1f';
            app.DPHighwaySpinner_1 = uispinner(app.MobilityBehaviorPanel_1);
            app.DPHighwaySpinner_1.Limits = [0 10];
            app.DPHighwaySpinner_1.ValueDisplayFormat = '%.1f';
            app.DPGeneralNeedSpinner_1 = uispinner(app.MobilityBehaviorPanel_1);
            app.DPGeneralNeedSpinner_1.Limits = [0 10];
            app.DPGeneralNeedSpinner_1.ValueDisplayFormat = '%.1f';
            app.DPHighwaySpinnerLabel = uilabel(app.MobilityBehaviorPanel_1);
            app.DPHighwaySpinnerLabel.Text = 'DP Highway';
            app.DPUrbanSpinnerLabel = uilabel(app.MobilityBehaviorPanel_1);
            app.DPUrbanSpinnerLabel.Text = 'DP Urban';
            app.DPGeneralNeedSpinnerLabel = uilabel(app.MobilityBehaviorPanel_1);
            app.DPGeneralNeedSpinnerLabel.Text = 'DP General Need';
            app.DPRuralSpinnerLabel = uilabel(app.MobilityBehaviorPanel_1);
            app.DPRuralSpinnerLabel.Text = 'DP Rural';
            app.ViewSecondaryActivitiesTab_1 = uitab(app.TabGroup_1);
            app.ViewSecondaryActivitiesTab_1.Title = 'View: Secondary Activities';
            app.UIUser1_1 = uiaxes(app.ViewSecondaryActivitiesTab_1);
            xlabel(app.UIUser1_1, 'X')
            ylabel(app.UIUser1_1, 'Y')
            zlabel(app.UIUser1_1, 'Z')
            app.UIUser1_1.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ViewCharacterMobilityTab_1 = uitab(app.TabGroup_1);
            app.ViewCharacterMobilityTab_1.Title = 'View: Character/Mobility';
            app.UIUser1_2 = uiaxes(app.ViewCharacterMobilityTab_1);
            xlabel(app.UIUser1_2, 'X')
            ylabel(app.UIUser1_2, 'Y')
            zlabel(app.UIUser1_2, 'Z')
            app.UserPanel_2 = uipanel(app.SingleUserViewPanel);
            app.UserPanel_2.Title = 'User 2';
            app.TabGroup_2 = uitabgroup(app.UserPanel_2);
            app.TabGroup_2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup_2SelectionChanged, true);
            app.InputParametersTab_2 = uitab(app.TabGroup_2);
            app.InputParametersTab_2.Title = 'Input Parameters';
            app.SecondaryActivitiesPanel_2 = uipanel(app.InputParametersTab_2);
            app.SecondaryActivitiesPanel_2.Tooltip = {'Desired activities carried out by the user during the ride and with how many other passengers.'};
            app.SecondaryActivitiesPanel_2.Title = 'Secondary Activities';
            app.SelfDrivingSpinner_2 = uispinner(app.SecondaryActivitiesPanel_2);
            app.SelfDrivingSpinner_2.Limits = [0 10];
            app.SelfDrivingSpinner_2.ValueDisplayFormat = '%.1f';
            app.RelaxSpinner_2 = uispinner(app.SecondaryActivitiesPanel_2);
            app.RelaxSpinner_2.Limits = [0 10];
            app.RelaxSpinner_2.ValueDisplayFormat = '%.1f';
            app.WorkSpinner_2 = uispinner(app.SecondaryActivitiesPanel_2);
            app.WorkSpinner_2.Limits = [0 10];
            app.WorkSpinner_2.ValueDisplayFormat = '%.1f';
            app.SleepSpinner_2 = uispinner(app.SecondaryActivitiesPanel_2);
            app.SleepSpinner_2.Limits = [0 10];
            app.SleepSpinner_2.ValueDisplayFormat = '%.1f';
            app.LoadSpinner_2 = uispinner(app.SecondaryActivitiesPanel_2);
            app.LoadSpinner_2.Limits = [0 10];
            app.LoadSpinner_2.ValueDisplayFormat = '%.1f';
            app.PassengersforSelfDrivingSpinner_2 = uispinner(app.SecondaryActivitiesPanel_2);
            app.PassengersforSelfDrivingSpinner_2.Limits = [0 9];
            app.PassengersforSelfDrivingSpinner_2.ValueDisplayFormat = '%.1f';
            app.PassengersforRelaxSpinner_2 = uispinner(app.SecondaryActivitiesPanel_2);
            app.PassengersforRelaxSpinner_2.Limits = [0 9];
            app.PassengersforRelaxSpinner_2.ValueDisplayFormat = '%.1f';
            app.PassengersforWorkSpinner_2 = uispinner(app.SecondaryActivitiesPanel_2);
            app.PassengersforWorkSpinner_2.Limits = [0 9];
            app.PassengersforWorkSpinner_2.ValueDisplayFormat = '%.1f';
            app.PassengersforSleepSpinner_2 = uispinner(app.SecondaryActivitiesPanel_2);
            app.PassengersforSleepSpinner_2.Limits = [0 9];
            app.PassengersforSleepSpinner_2.ValueDisplayFormat = '%.1f';
            app.LoadSpinner_2Label = uilabel(app.SecondaryActivitiesPanel_2);
            app.LoadSpinner_2Label.Text = 'Load';
            app.SleepSpinner_2Label = uilabel(app.SecondaryActivitiesPanel_2);
            app.SleepSpinner_2Label.Text = 'Sleep';
            app.WorkSpinner_2Label = uilabel(app.SecondaryActivitiesPanel_2);
            app.WorkSpinner_2Label.Text = 'Work';
            app.RelaxSpinner_2Label = uilabel(app.SecondaryActivitiesPanel_2);
            app.RelaxSpinner_2Label.Text = 'Relax';
            app.SelfDrivingSpinner_2Label = uilabel(app.SecondaryActivitiesPanel_2);
            app.SelfDrivingSpinner_2Label.Text = 'Self-Driving';
            app.PassengersforSleepSpinner_2Label = uilabel(app.SecondaryActivitiesPanel_2);
            app.PassengersforSleepSpinner_2Label.Text = 'Passengers for Sleep';
            app.PassengersforWorkSpinner_2Label = uilabel(app.SecondaryActivitiesPanel_2);
            app.PassengersforWorkSpinner_2Label.Text = 'Passengers for Work';
            app.PassengersforRelaxSpinner_2Label = uilabel(app.SecondaryActivitiesPanel_2);
            app.PassengersforRelaxSpinner_2Label.Text = 'Passengers for Relax';
            app.PassengersforSelfDrivingSpinner_2Label = uilabel(app.SecondaryActivitiesPanel_2);
            app.PassengersforSelfDrivingSpinner_2Label.Text = 'Passengers for Self-Driving';
            app.CharacterPanel_2 = uipanel(app.InputParametersTab_2);
            app.CharacterPanel_2.Tooltip = {'Character of the user concerning mobility.'; '(MaaS = Mobility as a Service)'};
            app.CharacterPanel_2.Title = 'Character';
            app.WillingnessofMaaSSpinner_2Label = uilabel(app.CharacterPanel_2);
            app.WillingnessofMaaSSpinner_2Label.Text = 'Willingness of MaaS';
            app.WillingnessofMaaSSpinner_2 = uispinner(app.CharacterPanel_2);
            app.WillingnessofMaaSSpinner_2.Limits = [0 10];
            app.WillingnessofMaaSSpinner_2.ValueDisplayFormat = '%.1f';
            app.WillingnesstoInvestSpinner_2Label = uilabel(app.CharacterPanel_2);
            app.WillingnesstoInvestSpinner_2Label.Text = 'Willingness to Invest';
            app.WillingnesstoInvestSpinner_2 = uispinner(app.CharacterPanel_2);
            app.WillingnesstoInvestSpinner_2.Limits = [0 10];
            app.WillingnesstoInvestSpinner_2.ValueDisplayFormat = '%.1f';
            app.NeedofSecuritySafetySpinner_4Label = uilabel(app.CharacterPanel_2);
            app.NeedofSecuritySafetySpinner_4Label.Text = 'Need of Security/Safety';
            app.NeedofSecuritySafetySpinner_2 = uispinner(app.CharacterPanel_2);
            app.NeedofSecuritySafetySpinner_2.Limits = [0 10];
            app.NeedofSecuritySafetySpinner_2.ValueDisplayFormat = '%.1f';
            app.MobilityBehaviorPanel_2 = uipanel(app.InputParametersTab_2);
            app.MobilityBehaviorPanel_2.Tooltip = {'Composition of the mobility parts describes the purpose of the vehicle usage.'; '(DP = Driving Profile)'};
            app.MobilityBehaviorPanel_2.Title = 'Mobility Behavior';
            app.DPUrbanSpinner_2 = uispinner(app.MobilityBehaviorPanel_2);
            app.DPUrbanSpinner_2.Limits = [0 10];
            app.DPUrbanSpinner_2.ValueDisplayFormat = '%.1f';
            app.DPRuralSpinner_2 = uispinner(app.MobilityBehaviorPanel_2);
            app.DPRuralSpinner_2.Limits = [0 10];
            app.DPRuralSpinner_2.ValueDisplayFormat = '%.1f';
            app.DPHighwaySpinner_2 = uispinner(app.MobilityBehaviorPanel_2);
            app.DPHighwaySpinner_2.Limits = [0 10];
            app.DPHighwaySpinner_2.ValueDisplayFormat = '%.1f';
            app.DPGeneralNeedSpinner_2 = uispinner(app.MobilityBehaviorPanel_2);
            app.DPGeneralNeedSpinner_2.Limits = [0 10];
            app.DPGeneralNeedSpinner_2.ValueDisplayFormat = '%.1f';
            app.DPHighwaySpinner_2Label = uilabel(app.MobilityBehaviorPanel_2);
            app.DPHighwaySpinner_2Label.Text = 'DP Highway';
            app.DPUrbanSpinner_4Label = uilabel(app.MobilityBehaviorPanel_2);
            app.DPUrbanSpinner_4Label.Text = 'DP Urban';
            app.DPGeneralNeedSpinner_2Label = uilabel(app.MobilityBehaviorPanel_2);
            app.DPGeneralNeedSpinner_2Label.Text = 'DP General Need';
            app.DPRuralSpinner_4Label = uilabel(app.MobilityBehaviorPanel_2);
            app.DPRuralSpinner_4Label.Text = 'DP Rural';
            app.ViewSecondaryActivitiesTab_2 = uitab(app.TabGroup_2);
            app.ViewSecondaryActivitiesTab_2.Title = 'View: Secondary Activities';
            app.UIUser2_1 = uiaxes(app.ViewSecondaryActivitiesTab_2);
            xlabel(app.UIUser2_1, 'X')
            ylabel(app.UIUser2_1, 'Y')
            zlabel(app.UIUser2_1, 'Z')
            app.ViewCharacterMobilityTab_2 = uitab(app.TabGroup_2);
            app.ViewCharacterMobilityTab_2.Title = 'View: Character/Mobility';
            app.UIUser2_2 = uiaxes(app.ViewCharacterMobilityTab_2);
            xlabel(app.UIUser2_2, 'X')
            ylabel(app.UIUser2_2, 'Y')
            zlabel(app.UIUser2_2, 'Z')
            app.UserPanel_3 = uipanel(app.SingleUserViewPanel);
            app.UserPanel_3.Title = 'User 3';
            app.TabGroup_3 = uitabgroup(app.UserPanel_3);
            app.TabGroup_3.SelectionChangedFcn = createCallbackFcn(app, @TabGroup_3SelectionChanged, true);
            app.InputParametersTab_3 = uitab(app.TabGroup_3);
            app.InputParametersTab_3.Title = 'Input Parameters';
            app.SecondaryActivitiesPanel_3 = uipanel(app.InputParametersTab_3);
            app.SecondaryActivitiesPanel_3.Tooltip = {'Desired activities carried out by the user during the ride and with how many other passengers.'};
            app.SecondaryActivitiesPanel_3.Title = 'Secondary Activities';
            app.LoadSpinner_5Label = uilabel(app.SecondaryActivitiesPanel_3);
            app.LoadSpinner_5Label.Text = 'Load';
            app.SleepSpinner_3Label = uilabel(app.SecondaryActivitiesPanel_3);
            app.SleepSpinner_3Label.Text = 'Sleep';
            app.WorkSpinner_3Label = uilabel(app.SecondaryActivitiesPanel_3);
            app.WorkSpinner_3Label.Text = 'Work';
            app.RelaxSpinner_3Label = uilabel(app.SecondaryActivitiesPanel_3);
            app.RelaxSpinner_3Label.Text = 'Relax';
            app.SelfDrivingSpinner_6Label = uilabel(app.SecondaryActivitiesPanel_3);
            app.SelfDrivingSpinner_6Label.Text = 'Self-Driving';
            app.PassengersforSleepSpinner_3Label = uilabel(app.SecondaryActivitiesPanel_3);
            app.PassengersforSleepSpinner_3Label.Text = 'Passengers for Sleep';
            app.PassengersforWorkSpinner_3Label = uilabel(app.SecondaryActivitiesPanel_3);
            app.PassengersforWorkSpinner_3Label.Text = 'Passengers for Work';
            app.PassengersforRelaxSpinner_3Label = uilabel(app.SecondaryActivitiesPanel_3);
            app.PassengersforRelaxSpinner_3Label.Text = 'Passengers for Relax';
            app.PassengersforSelfDrivingSpinner_3Label = uilabel(app.SecondaryActivitiesPanel_3);
            app.PassengersforSelfDrivingSpinner_3Label.Text = 'Passengers for Self-Driving';
            app.SelfDrivingSpinner_3 = uispinner(app.SecondaryActivitiesPanel_3);
            app.SelfDrivingSpinner_3.Limits = [0 10];
            app.SelfDrivingSpinner_3.ValueDisplayFormat = '%.1f';
            app.RelaxSpinner_3 = uispinner(app.SecondaryActivitiesPanel_3);
            app.RelaxSpinner_3.Limits = [0 10];
            app.RelaxSpinner_3.ValueDisplayFormat = '%.1f';
            app.WorkSpinner_3 = uispinner(app.SecondaryActivitiesPanel_3);
            app.WorkSpinner_3.Limits = [0 10];
            app.WorkSpinner_3.ValueDisplayFormat = '%.1f';
            app.SleepSpinner_3 = uispinner(app.SecondaryActivitiesPanel_3);
            app.SleepSpinner_3.Limits = [0 10];
            app.SleepSpinner_3.ValueDisplayFormat = '%.1f';
            app.LoadSpinner_3 = uispinner(app.SecondaryActivitiesPanel_3);
            app.LoadSpinner_3.Limits = [0 10];
            app.LoadSpinner_3.ValueDisplayFormat = '%.1f';
            app.PassengersforSelfDrivingSpinner_3 = uispinner(app.SecondaryActivitiesPanel_3);
            app.PassengersforSelfDrivingSpinner_3.Limits = [0 9];
            app.PassengersforSelfDrivingSpinner_3.ValueDisplayFormat = '%.1f';
            app.PassengersforRelaxSpinner_3 = uispinner(app.SecondaryActivitiesPanel_3);
            app.PassengersforRelaxSpinner_3.Limits = [0 9];
            app.PassengersforRelaxSpinner_3.ValueDisplayFormat = '%.1f';
            app.PassengersforWorkSpinner_3 = uispinner(app.SecondaryActivitiesPanel_3);
            app.PassengersforWorkSpinner_3.Limits = [0 9];
            app.PassengersforWorkSpinner_3.ValueDisplayFormat = '%.1f';
            app.PassengersforSleepSpinner_3 = uispinner(app.SecondaryActivitiesPanel_3);
            app.PassengersforSleepSpinner_3.Limits = [0 9];
            app.PassengersforSleepSpinner_3.ValueDisplayFormat = '%.1f';
            app.CharacterPanel_3 = uipanel(app.InputParametersTab_3);
            app.CharacterPanel_3.Tooltip = {'Character of the user concerning mobility.'; '(MaaS = Mobility as a Service)'};
            app.CharacterPanel_3.Title = 'Character';
            app.WillingnessofMaaSSpinner_3Label = uilabel(app.CharacterPanel_3);
            app.WillingnessofMaaSSpinner_3Label.Text = 'Willingness of MaaS';
            app.WillingnessofMaaSSpinner_3 = uispinner(app.CharacterPanel_3);
            app.WillingnessofMaaSSpinner_3.Limits = [0 10];
            app.WillingnessofMaaSSpinner_3.ValueDisplayFormat = '%.1f';
            app.WillingnesstoInvestSpinner_3Label = uilabel(app.CharacterPanel_3);
            app.WillingnesstoInvestSpinner_3Label.Text = 'Willingness to Invest';
            app.WillingnesstoInvestSpinner_3 = uispinner(app.CharacterPanel_3);
            app.WillingnesstoInvestSpinner_3.Limits = [0 10];
            app.WillingnesstoInvestSpinner_3.ValueDisplayFormat = '%.1f';
            app.NeedofSecuritySafetySpinner_6Label = uilabel(app.CharacterPanel_3);
            app.NeedofSecuritySafetySpinner_6Label.Text = 'Need of Security/Safety';
            app.NeedofSecuritySafetySpinner_3 = uispinner(app.CharacterPanel_3);
            app.NeedofSecuritySafetySpinner_3.Limits = [0 10];
            app.NeedofSecuritySafetySpinner_3.ValueDisplayFormat = '%.1f';
            app.MobilityBehaviorPanel_3 = uipanel(app.InputParametersTab_3);
            app.MobilityBehaviorPanel_3.Tooltip = {'Composition of the mobility parts describes the purpose of the vehicle usage.'; '(DP = Driving Profile)'};
            app.MobilityBehaviorPanel_3.Title = 'Mobility Behavior';
            app.DPHighwaySpinner_3Label = uilabel(app.MobilityBehaviorPanel_3);
            app.DPHighwaySpinner_3Label.Text = 'DP Highway';
            app.DPUrbanSpinner_5Label = uilabel(app.MobilityBehaviorPanel_3);
            app.DPUrbanSpinner_5Label.Text = 'DP Urban';
            app.DPGeneralNeedSpinner_3Label = uilabel(app.MobilityBehaviorPanel_3);
            app.DPGeneralNeedSpinner_3Label.Text = 'DP General Need';
            app.DPRuralSpinner_5Label = uilabel(app.MobilityBehaviorPanel_3);
            app.DPRuralSpinner_5Label.Text = 'DP Rural';
            app.DPUrbanSpinner_3 = uispinner(app.MobilityBehaviorPanel_3);
            app.DPUrbanSpinner_3.Limits = [0 10];
            app.DPUrbanSpinner_3.ValueDisplayFormat = '%.1f';
            app.DPRuralSpinner_3 = uispinner(app.MobilityBehaviorPanel_3);
            app.DPRuralSpinner_3.Limits = [0 10];
            app.DPRuralSpinner_3.ValueDisplayFormat = '%.1f';
            app.DPHighwaySpinner_3 = uispinner(app.MobilityBehaviorPanel_3);
            app.DPHighwaySpinner_3.Limits = [0 10];
            app.DPHighwaySpinner_3.ValueDisplayFormat = '%.1f';
            app.DPGeneralNeedSpinner_3 = uispinner(app.MobilityBehaviorPanel_3);
            app.DPGeneralNeedSpinner_3.Limits = [0 10];
            app.DPGeneralNeedSpinner_3.ValueDisplayFormat = '%.1f';
            app.ViewSecondaryActivitiesTab_3 = uitab(app.TabGroup_3);
            app.ViewSecondaryActivitiesTab_3.Title = 'View: Secondary Activities';
            app.UIUser3_1 = uiaxes(app.ViewSecondaryActivitiesTab_3);
            xlabel(app.UIUser3_1, 'X')
            ylabel(app.UIUser3_1, 'Y')
            zlabel(app.UIUser3_1, 'Z')
            app.ViewCharacterMobilityTab_3 = uitab(app.TabGroup_3);
            app.ViewCharacterMobilityTab_3.Title = 'View: Character/Mobility';
            app.UIUser3_2 = uiaxes(app.ViewCharacterMobilityTab_3);
            xlabel(app.UIUser3_2, 'X')
            ylabel(app.UIUser3_2, 'Y')
            zlabel(app.UIUser3_2, 'Z')
            app.UserPanel_4 = uipanel(app.SingleUserViewPanel);
            app.UserPanel_4.Title = 'User 4';
            app.TabGroup_4 = uitabgroup(app.UserPanel_4);
            app.TabGroup_4.SelectionChangedFcn = createCallbackFcn(app, @TabGroup_4SelectionChanged, true);
            app.InputParametersTab_4 = uitab(app.TabGroup_4);
            app.InputParametersTab_4.Title = 'Input Parameters';
            app.SecondaryActivitiesPanel_4 = uipanel(app.InputParametersTab_4);
            app.SecondaryActivitiesPanel_4.Tooltip = {'Desired activities carried out by the user during the ride and with how many other passengers.'};
            app.SecondaryActivitiesPanel_4.Title = 'Secondary Activities';
            app.LoadSpinner_3Label = uilabel(app.SecondaryActivitiesPanel_4);
            app.LoadSpinner_3Label.Text = 'Load';
            app.SleepSpinner_4Label = uilabel(app.SecondaryActivitiesPanel_4);
            app.SleepSpinner_4Label.Text = 'Sleep';
            app.WorkSpinner_4Label = uilabel(app.SecondaryActivitiesPanel_4);
            app.WorkSpinner_4Label.Text = 'Work';
            app.RelaxSpinner_4Label = uilabel(app.SecondaryActivitiesPanel_4);
            app.RelaxSpinner_4Label.Text = 'Relax';
            app.SelfDrivingSpinner_3Label = uilabel(app.SecondaryActivitiesPanel_4);
            app.SelfDrivingSpinner_3Label.Text = 'Self-Driving';
            app.PassengersforSleepSpinner_4Label = uilabel(app.SecondaryActivitiesPanel_4);
            app.PassengersforSleepSpinner_4Label.Text = 'Passengers for Sleep';
            app.PassengersforWorkSpinner_4Label = uilabel(app.SecondaryActivitiesPanel_4);
            app.PassengersforWorkSpinner_4Label.Text = 'Passengers for Work';
            app.PassengersforRelaxSpinner_4Label = uilabel(app.SecondaryActivitiesPanel_4);
            app.PassengersforRelaxSpinner_4Label.Text = 'Passengers for Relax';
            app.PassengersforSelfDrivingSpinner_5Label = uilabel(app.SecondaryActivitiesPanel_4);
            app.PassengersforSelfDrivingSpinner_5Label.Text = 'Passengers for Self-Driving';
            app.SelfDrivingSpinner_4 = uispinner(app.SecondaryActivitiesPanel_4);
            app.SelfDrivingSpinner_4.Limits = [0 10];
            app.SelfDrivingSpinner_4.ValueDisplayFormat = '%.1f';
            app.RelaxSpinner_4 = uispinner(app.SecondaryActivitiesPanel_4);
            app.RelaxSpinner_4.Limits = [0 10];
            app.RelaxSpinner_4.ValueDisplayFormat = '%.1f';
            app.WorkSpinner_4 = uispinner(app.SecondaryActivitiesPanel_4);
            app.WorkSpinner_4.Limits = [0 10];
            app.WorkSpinner_4.ValueDisplayFormat = '%.1f';
            app.SleepSpinner_4 = uispinner(app.SecondaryActivitiesPanel_4);
            app.SleepSpinner_4.Limits = [0 10];
            app.SleepSpinner_4.ValueDisplayFormat = '%.1f';
            app.LoadSpinner_4 = uispinner(app.SecondaryActivitiesPanel_4);
            app.LoadSpinner_4.Limits = [0 10];
            app.LoadSpinner_4.ValueDisplayFormat = '%.1f';
            app.PassengersforSelfDrivingSpinner_4 = uispinner(app.SecondaryActivitiesPanel_4);
            app.PassengersforSelfDrivingSpinner_4.Limits = [0 9];
            app.PassengersforSelfDrivingSpinner_4.ValueDisplayFormat = '%.1f';
            app.PassengersforRelaxSpinner_4 = uispinner(app.SecondaryActivitiesPanel_4);
            app.PassengersforRelaxSpinner_4.Limits = [0 9];
            app.PassengersforRelaxSpinner_4.ValueDisplayFormat = '%.1f';
            app.PassengersforWorkSpinner_4 = uispinner(app.SecondaryActivitiesPanel_4);
            app.PassengersforWorkSpinner_4.Limits = [0 9];
            app.PassengersforWorkSpinner_4.ValueDisplayFormat = '%.1f';
            app.PassengersforSleepSpinner_4 = uispinner(app.SecondaryActivitiesPanel_4);
            app.PassengersforSleepSpinner_4.Limits = [0 9];
            app.PassengersforSleepSpinner_4.ValueDisplayFormat = '%.1f';
            app.CharacterPanel_4 = uipanel(app.InputParametersTab_4);
            app.CharacterPanel_4.Tooltip = {'Character of the user concerning mobility.'; '(MaaS = Mobility as a Service)'};
            app.CharacterPanel_4.Title = 'Character';
            app.WillingnessofMaaSSpinner_4Label = uilabel(app.CharacterPanel_4);
            app.WillingnessofMaaSSpinner_4Label.Text = 'Willingness of MaaS';
            app.WillingnessofMaaSSpinner_4 = uispinner(app.CharacterPanel_4);
            app.WillingnessofMaaSSpinner_4.Limits = [0 10];
            app.WillingnessofMaaSSpinner_4.ValueDisplayFormat = '%.1f';
            app.WillingnesstoInvestSpinner_4Label = uilabel(app.CharacterPanel_4);
            app.WillingnesstoInvestSpinner_4Label.Text = 'Willingness to Invest';
            app.WillingnesstoInvestSpinner_4 = uispinner(app.CharacterPanel_4);
            app.WillingnesstoInvestSpinner_4.Limits = [0 10];
            app.WillingnesstoInvestSpinner_4.ValueDisplayFormat = '%.1f';
            app.NeedofSecuritySafetySpinner_2Label = uilabel(app.CharacterPanel_4);
            app.NeedofSecuritySafetySpinner_2Label.Text = 'Need of Security/Safety';
            app.NeedofSecuritySafetySpinner_4 = uispinner(app.CharacterPanel_4);
            app.NeedofSecuritySafetySpinner_4.Limits = [0 10];
            app.NeedofSecuritySafetySpinner_4.ValueDisplayFormat = '%.1f';
            app.MobilityBehaviorPanel_4 = uipanel(app.InputParametersTab_4);
            app.MobilityBehaviorPanel_4.Tooltip = {'Composition of the mobility parts describes the purpose of the vehicle usage.'; '(DP = Driving Profile)'};
            app.MobilityBehaviorPanel_4.Title = 'Mobility Behavior';
            app.DPHighwaySpinner_6Label = uilabel(app.MobilityBehaviorPanel_4);
            app.DPHighwaySpinner_6Label.Text = 'DP Highway';
            app.DPUrbanSpinner_2Label = uilabel(app.MobilityBehaviorPanel_4);
            app.DPUrbanSpinner_2Label.Text = 'DP Urban';
            app.DPGeneralNeedSpinner_6Label = uilabel(app.MobilityBehaviorPanel_4);
            app.DPGeneralNeedSpinner_6Label.Text = 'DP General Need';
            app.DPRuralSpinner_2Label = uilabel(app.MobilityBehaviorPanel_4);
            app.DPRuralSpinner_2Label.Text = 'DP Rural';
            app.DPUrbanSpinner_4 = uispinner(app.MobilityBehaviorPanel_4);
            app.DPUrbanSpinner_4.Limits = [0 10];
            app.DPUrbanSpinner_4.ValueDisplayFormat = '%.1f';
            app.DPRuralSpinner_4 = uispinner(app.MobilityBehaviorPanel_4);
            app.DPRuralSpinner_4.Limits = [0 10];
            app.DPRuralSpinner_4.ValueDisplayFormat = '%.1f';
            app.DPHighwaySpinner_4 = uispinner(app.MobilityBehaviorPanel_4);
            app.DPHighwaySpinner_4.Limits = [0 10];
            app.DPHighwaySpinner_4.ValueDisplayFormat = '%.1f';
            app.DPGeneralNeedSpinner_4 = uispinner(app.MobilityBehaviorPanel_4);
            app.DPGeneralNeedSpinner_4.Limits = [0 10];
            app.DPGeneralNeedSpinner_4.ValueDisplayFormat = '%.1f';
            app.ViewSecondaryActivitiesTab_4 = uitab(app.TabGroup_4);
            app.ViewSecondaryActivitiesTab_4.Title = 'View: Secondary Activities';
            app.UIUser4_1 = uiaxes(app.ViewSecondaryActivitiesTab_4);
            xlabel(app.UIUser4_1, 'X')
            ylabel(app.UIUser4_1, 'Y')
            zlabel(app.UIUser4_1, 'Z')
            app.ViewCharacterMobilityTab_4 = uitab(app.TabGroup_4);
            app.ViewCharacterMobilityTab_4.Title = 'View: Character/Mobility';
            app.UIUser4_2 = uiaxes(app.ViewCharacterMobilityTab_4);
            xlabel(app.UIUser4_2, 'X')
            ylabel(app.UIUser4_2, 'Y')
            zlabel(app.UIUser4_2, 'Z')
            app.UserPanel_5 = uipanel(app.SingleUserViewPanel);
            app.UserPanel_5.Title = 'User 5';
            app.TabGroup_5 = uitabgroup(app.UserPanel_5);
            app.TabGroup_5.SelectionChangedFcn = createCallbackFcn(app, @TabGroup_5SelectionChanged, true);
            app.InputParametersTab_5 = uitab(app.TabGroup_5);
            app.InputParametersTab_5.Title = 'Input Parameters';
            app.SecondaryActivitiesPanel_5 = uipanel(app.InputParametersTab_5);
            app.SecondaryActivitiesPanel_5.Tooltip = {'Desired activities carried out by the user during the ride and with how many other passengers.'};
            app.SecondaryActivitiesPanel_5.Title = 'Secondary Activities';
            app.LoadSpinner_4Label = uilabel(app.SecondaryActivitiesPanel_5);
            app.LoadSpinner_4Label.Text = 'Load';
            app.SleepSpinner_5Label = uilabel(app.SecondaryActivitiesPanel_5);
            app.SleepSpinner_5Label.Text = 'Sleep';
            app.WorkSpinner_5Label = uilabel(app.SecondaryActivitiesPanel_5);
            app.WorkSpinner_5Label.Text = 'Work';
            app.RelaxSpinner_5Label = uilabel(app.SecondaryActivitiesPanel_5);
            app.RelaxSpinner_5Label.Text = 'Relax';
            app.SelfDrivingSpinner_4Label = uilabel(app.SecondaryActivitiesPanel_5);
            app.SelfDrivingSpinner_4Label.Text = 'Self-Driving';
            app.PassengersforSleepSpinner_5Label = uilabel(app.SecondaryActivitiesPanel_5);
            app.PassengersforSleepSpinner_5Label.Text = 'Passengers for Sleep';
            app.PassengersforWorkSpinner_5Label = uilabel(app.SecondaryActivitiesPanel_5);
            app.PassengersforWorkSpinner_5Label.Text = 'Passengers for Work';
            app.PassengersforRelaxSpinner_5Label = uilabel(app.SecondaryActivitiesPanel_5);
            app.PassengersforRelaxSpinner_5Label.Text = 'Passengers for Relax';
            app.PassengersforSelfDrivingSpinner_6Label = uilabel(app.SecondaryActivitiesPanel_5);
            app.PassengersforSelfDrivingSpinner_6Label.Text = 'Passengers for Self-Driving';
            app.SelfDrivingSpinner_5 = uispinner(app.SecondaryActivitiesPanel_5);
            app.SelfDrivingSpinner_5.Limits = [0 10];
            app.SelfDrivingSpinner_5.ValueDisplayFormat = '%.1f';
            app.RelaxSpinner_5 = uispinner(app.SecondaryActivitiesPanel_5);
            app.RelaxSpinner_5.Limits = [0 10];
            app.RelaxSpinner_5.ValueDisplayFormat = '%.1f';
            app.WorkSpinner_5 = uispinner(app.SecondaryActivitiesPanel_5);
            app.WorkSpinner_5.Limits = [0 10];
            app.WorkSpinner_5.ValueDisplayFormat = '%.1f';
            app.SleepSpinner_5 = uispinner(app.SecondaryActivitiesPanel_5);
            app.SleepSpinner_5.Limits = [0 10];
            app.SleepSpinner_5.ValueDisplayFormat = '%.1f';
            app.LoadSpinner_5 = uispinner(app.SecondaryActivitiesPanel_5);
            app.LoadSpinner_5.Limits = [0 10];
            app.LoadSpinner_5.ValueDisplayFormat = '%.1f';
            app.PassengersforSelfDrivingSpinner_5 = uispinner(app.SecondaryActivitiesPanel_5);
            app.PassengersforSelfDrivingSpinner_5.Limits = [0 9];
            app.PassengersforSelfDrivingSpinner_5.ValueDisplayFormat = '%.1f';
            app.PassengersforRelaxSpinner_5 = uispinner(app.SecondaryActivitiesPanel_5);
            app.PassengersforRelaxSpinner_5.Limits = [0 9];
            app.PassengersforRelaxSpinner_5.ValueDisplayFormat = '%.1f';
            app.PassengersforWorkSpinner_5 = uispinner(app.SecondaryActivitiesPanel_5);
            app.PassengersforWorkSpinner_5.Limits = [0 9];
            app.PassengersforWorkSpinner_5.ValueDisplayFormat = '%.1f';
            app.PassengersforSleepSpinner_5 = uispinner(app.SecondaryActivitiesPanel_5);
            app.PassengersforSleepSpinner_5.Limits = [0 9];
            app.PassengersforSleepSpinner_5.ValueDisplayFormat = '%.1f';
            app.CharacterPanel_5 = uipanel(app.InputParametersTab_5);
            app.CharacterPanel_5.Tooltip = {'Character of the user concerning mobility.'; '(MaaS = Mobility as a Service)'};
            app.CharacterPanel_5.Title = 'Character';
            app.WillingnessofMaaSSpinner_5Label = uilabel(app.CharacterPanel_5);
            app.WillingnessofMaaSSpinner_5Label.Text = 'Willingness of MaaS';
            app.WillingnessofMaaSSpinner_5 = uispinner(app.CharacterPanel_5);
            app.WillingnessofMaaSSpinner_5.Limits = [0 10];
            app.WillingnessofMaaSSpinner_5.ValueDisplayFormat = '%.1f';
            app.WillingnesstoInvestSpinner_5Label = uilabel(app.CharacterPanel_5);
            app.WillingnesstoInvestSpinner_5Label.Text = 'Willingness to Invest';
            app.WillingnesstoInvestSpinner_5 = uispinner(app.CharacterPanel_5);
            app.WillingnesstoInvestSpinner_5.Limits = [0 10];
            app.WillingnesstoInvestSpinner_5.ValueDisplayFormat = '%.1f';
            app.NeedofSecuritySafetySpinner_3Label = uilabel(app.CharacterPanel_5);
            app.NeedofSecuritySafetySpinner_3Label.Text = 'Need of Security/Safety';
            app.NeedofSecuritySafetySpinner_5 = uispinner(app.CharacterPanel_5);
            app.NeedofSecuritySafetySpinner_5.Limits = [0 10];
            app.NeedofSecuritySafetySpinner_5.ValueDisplayFormat = '%.1f';
            app.MobilityBehaviorPanel_5 = uipanel(app.InputParametersTab_5);
            app.MobilityBehaviorPanel_5.Tooltip = {'Composition of the mobility parts describes the purpose of the vehicle usage.'; '(DP = Driving Profile)'};
            app.MobilityBehaviorPanel_5.Title = 'Mobility Behavior';
            app.DPUrbanSpinner_5 = uispinner(app.MobilityBehaviorPanel_5);
            app.DPUrbanSpinner_5.Limits = [0 10];
            app.DPUrbanSpinner_5.ValueDisplayFormat = '%.1f';
            app.DPRuralSpinner_5 = uispinner(app.MobilityBehaviorPanel_5);
            app.DPRuralSpinner_5.Limits = [0 10];
            app.DPRuralSpinner_5.ValueDisplayFormat = '%.1f';
            app.DPHighwaySpinner_5Label = uilabel(app.MobilityBehaviorPanel_5);
            app.DPHighwaySpinner_5Label.Text = 'DP Highway';
            app.DPHighwaySpinner_5 = uispinner(app.MobilityBehaviorPanel_5);
            app.DPHighwaySpinner_5.Limits = [0 10];
            app.DPHighwaySpinner_5.ValueDisplayFormat = '%.1f';
            app.DPUrbanSpinner_3Label = uilabel(app.MobilityBehaviorPanel_5);
            app.DPUrbanSpinner_3Label.Text = 'DP Urban';
            app.DPGeneralNeedSpinner_5Label = uilabel(app.MobilityBehaviorPanel_5);
            app.DPGeneralNeedSpinner_5Label.Text = 'DP General Need';
            app.DPGeneralNeedSpinner_5 = uispinner(app.MobilityBehaviorPanel_5);
            app.DPGeneralNeedSpinner_5.Limits = [0 10];
            app.DPGeneralNeedSpinner_5.ValueDisplayFormat = '%.1f';
            app.DPRuralSpinner_3Label = uilabel(app.MobilityBehaviorPanel_5);
            app.DPRuralSpinner_3Label.Text = 'DP Rural';
            app.ViewSecondaryActivitiesTab_5 = uitab(app.TabGroup_5);
            app.ViewSecondaryActivitiesTab_5.Title = 'View: Secondary Activities';
            app.UIUser5_1 = uiaxes(app.ViewSecondaryActivitiesTab_5);
            xlabel(app.UIUser5_1, 'X')
            ylabel(app.UIUser5_1, 'Y')
            zlabel(app.UIUser5_1, 'Z')
            app.ViewCharacterMobilityTab_5 = uitab(app.TabGroup_5);
            app.ViewCharacterMobilityTab_5.Title = 'View: Character/Mobility';
            app.UIUser5_2 = uiaxes(app.ViewCharacterMobilityTab_5);
            xlabel(app.UIUser5_2, 'X')
            ylabel(app.UIUser5_2, 'Y')
            zlabel(app.UIUser5_2, 'Z')
            app.UserPanel_6 = uipanel(app.SingleUserViewPanel);
            app.UserPanel_6.Title = 'User 6';
            app.TabGroup_6 = uitabgroup(app.UserPanel_6);
            app.TabGroup_6.SelectionChangedFcn = createCallbackFcn(app, @TabGroup_6SelectionChanged, true);
            app.InputParametersTab_6 = uitab(app.TabGroup_6);
            app.InputParametersTab_6.Title = 'Input Parameters';
            app.SecondaryActivitiesPanel_6 = uipanel(app.InputParametersTab_6);
            app.SecondaryActivitiesPanel_6.Tooltip = {'Desired activities carried out by the user during the ride and with how many other passengers.'};
            app.SecondaryActivitiesPanel_6.Title = 'Secondary Activities';
            app.LoadSpinner_6Label = uilabel(app.SecondaryActivitiesPanel_6);
            app.LoadSpinner_6Label.Text = 'Load';
            app.SleepSpinner_6Label = uilabel(app.SecondaryActivitiesPanel_6);
            app.SleepSpinner_6Label.Text = 'Sleep';
            app.WorkSpinner_6Label = uilabel(app.SecondaryActivitiesPanel_6);
            app.WorkSpinner_6Label.Text = 'Work';
            app.RelaxSpinner_6Label = uilabel(app.SecondaryActivitiesPanel_6);
            app.RelaxSpinner_6Label.Text = 'Relax';
            app.SelfDrivingSpinner_5Label = uilabel(app.SecondaryActivitiesPanel_6);
            app.SelfDrivingSpinner_5Label.Text = 'Self-Driving';
            app.PassengersforSleepSpinner_6Label = uilabel(app.SecondaryActivitiesPanel_6);
            app.PassengersforSleepSpinner_6Label.Text = 'Passengers for Sleep';
            app.PassengersforWorkSpinner_6Label = uilabel(app.SecondaryActivitiesPanel_6);
            app.PassengersforWorkSpinner_6Label.Text = 'Passengers for Work';
            app.PassengersforRelaxSpinner_6Label = uilabel(app.SecondaryActivitiesPanel_6);
            app.PassengersforRelaxSpinner_6Label.Text = 'Passengers for Relax';
            app.PassengersforSelfDrivingSpinner_4Label = uilabel(app.SecondaryActivitiesPanel_6);
            app.PassengersforSelfDrivingSpinner_4Label.Text = 'Passengers for Self-Driving';
            app.SelfDrivingSpinner_6 = uispinner(app.SecondaryActivitiesPanel_6);
            app.SelfDrivingSpinner_6.Limits = [0 10];
            app.SelfDrivingSpinner_6.ValueDisplayFormat = '%.1f';
            app.RelaxSpinner_6 = uispinner(app.SecondaryActivitiesPanel_6);
            app.RelaxSpinner_6.Limits = [0 10];
            app.RelaxSpinner_6.ValueDisplayFormat = '%.1f';
            app.WorkSpinner_6 = uispinner(app.SecondaryActivitiesPanel_6);
            app.WorkSpinner_6.Limits = [0 10];
            app.WorkSpinner_6.ValueDisplayFormat = '%.1f';
            app.SleepSpinner_6 = uispinner(app.SecondaryActivitiesPanel_6);
            app.SleepSpinner_6.Limits = [0 10];
            app.SleepSpinner_6.ValueDisplayFormat = '%.1f';
            app.LoadSpinner_6 = uispinner(app.SecondaryActivitiesPanel_6);
            app.LoadSpinner_6.Limits = [0 10];
            app.LoadSpinner_6.ValueDisplayFormat = '%.1f';
            app.PassengersforSelfDrivingSpinner_6 = uispinner(app.SecondaryActivitiesPanel_6);
            app.PassengersforSelfDrivingSpinner_6.Limits = [0 9];
            app.PassengersforSelfDrivingSpinner_6.ValueDisplayFormat = '%.1f';
            app.PassengersforRelaxSpinner_6 = uispinner(app.SecondaryActivitiesPanel_6);
            app.PassengersforRelaxSpinner_6.Limits = [0 9];
            app.PassengersforRelaxSpinner_6.ValueDisplayFormat = '%.1f';
            app.PassengersforWorkSpinner_6 = uispinner(app.SecondaryActivitiesPanel_6);
            app.PassengersforWorkSpinner_6.Limits = [0 9];
            app.PassengersforWorkSpinner_6.ValueDisplayFormat = '%.1f';
            app.PassengersforSleepSpinner_6 = uispinner(app.SecondaryActivitiesPanel_6);
            app.PassengersforSleepSpinner_6.Limits = [0 9];
            app.PassengersforSleepSpinner_6.ValueDisplayFormat = '%.1f';
            app.CharacterPanel_6 = uipanel(app.InputParametersTab_6);
            app.CharacterPanel_6.Tooltip = {'Character of the user concerning mobility.'; '(MaaS = Mobility as a Service)'};
            app.CharacterPanel_6.Title = 'Character';
            app.WillingnessofMaaSSpinner_6Label = uilabel(app.CharacterPanel_6);
            app.WillingnessofMaaSSpinner_6Label.Text = 'Willingness of MaaS';
            app.WillingnessofMaaSSpinner_6 = uispinner(app.CharacterPanel_6);
            app.WillingnessofMaaSSpinner_6.Limits = [0 10];
            app.WillingnessofMaaSSpinner_6.ValueDisplayFormat = '%.1f';
            app.WillingnesstoInvestSpinner_6Label = uilabel(app.CharacterPanel_6);
            app.WillingnesstoInvestSpinner_6Label.Text = 'Willingness to Invest';
            app.WillingnesstoInvestSpinner_6 = uispinner(app.CharacterPanel_6);
            app.WillingnesstoInvestSpinner_6.Limits = [0 10];
            app.WillingnesstoInvestSpinner_6.ValueDisplayFormat = '%.1f';
            app.NeedofSecuritySafetySpinner_5Label = uilabel(app.CharacterPanel_6);
            app.NeedofSecuritySafetySpinner_5Label.Text = 'Need of Security/Safety';
            app.NeedofSecuritySafetySpinner_6 = uispinner(app.CharacterPanel_6);
            app.NeedofSecuritySafetySpinner_6.Limits = [0 10];
            app.NeedofSecuritySafetySpinner_6.ValueDisplayFormat = '%.1f';
            app.MobilityBehaviorPanel_6 = uipanel(app.InputParametersTab_6);
            app.MobilityBehaviorPanel_6.Tooltip = {'Composition of the mobility parts describes the purpose of the vehicle usage.'; '(DP = Driving Profile)'};
            app.MobilityBehaviorPanel_6.Title = 'Mobility Behavior';
            app.DPUrbanSpinner_6 = uispinner(app.MobilityBehaviorPanel_6);
            app.DPUrbanSpinner_6.Limits = [0 10];
            app.DPUrbanSpinner_6.ValueDisplayFormat = '%.1f';
            app.DPRuralSpinner_6 = uispinner(app.MobilityBehaviorPanel_6);
            app.DPRuralSpinner_6.Limits = [0 10];
            app.DPRuralSpinner_6.ValueDisplayFormat = '%.1f';
            app.DPHighwaySpinner_4Label = uilabel(app.MobilityBehaviorPanel_6);
            app.DPHighwaySpinner_4Label.Text = 'DP Highway';
            app.DPHighwaySpinner_6 = uispinner(app.MobilityBehaviorPanel_6);
            app.DPHighwaySpinner_6.Limits = [0 10];
            app.DPHighwaySpinner_6.ValueDisplayFormat = '%.1f';
            app.DPUrbanSpinner_6Label = uilabel(app.MobilityBehaviorPanel_6);
            app.DPUrbanSpinner_6Label.Text = 'DP Urban';
            app.DPGeneralNeedSpinner_4Label = uilabel(app.MobilityBehaviorPanel_6);
            app.DPGeneralNeedSpinner_4Label.Text = 'DP General Need';
            app.DPGeneralNeedSpinner_6 = uispinner(app.MobilityBehaviorPanel_6);
            app.DPGeneralNeedSpinner_6.Limits = [0 10];
            app.DPGeneralNeedSpinner_6.ValueDisplayFormat = '%.1f';
            app.DPRuralSpinner_6Label = uilabel(app.MobilityBehaviorPanel_6);
            app.DPRuralSpinner_6Label.Text = 'DP Rural';
            app.ViewSecondaryActivitiesTab_6 = uitab(app.TabGroup_6);
            app.ViewSecondaryActivitiesTab_6.Title = 'View: Secondary Activities';
            app.UIUser6_1 = uiaxes(app.ViewSecondaryActivitiesTab_6);
            xlabel(app.UIUser6_1, 'X')
            ylabel(app.UIUser6_1, 'Y')
            zlabel(app.UIUser6_1, 'Z')
            app.ViewCharacterMobilityTab_6 = uitab(app.TabGroup_6);
            app.ViewCharacterMobilityTab_6.Title = 'View: Character/Mobility';
            app.UIUser6_2 = uiaxes(app.ViewCharacterMobilityTab_6);
            xlabel(app.UIUser6_2, 'X')
            ylabel(app.UIUser6_2, 'Y')
            zlabel(app.UIUser6_2, 'Z')
            app.MultipleUsersViewPanel = uipanel(app.MainScreenUN);
            app.MultipleUsersViewPanel.Title = 'Multiple Users View';
            app.SecondaryActivitiesofUsersPanel = uipanel(app.MultipleUsersViewPanel);
            app.SecondaryActivitiesofUsersPanel.Title = 'Secondary Activities of Users';
            app.UIActivitiesPlot = uiaxes(app.SecondaryActivitiesofUsersPanel);
            xlabel(app.UIActivitiesPlot, 'X')
            ylabel(app.UIActivitiesPlot, 'Y')
            zlabel(app.UIActivitiesPlot, 'Z')
            app.CharacterandMobilityofUsersPanel = uipanel(app.MultipleUsersViewPanel);
            app.CharacterandMobilityofUsersPanel.Tooltip = {'(MaaS = Mobility as a Service'; 'DP = Driving Profile)'};
            app.CharacterandMobilityofUsersPanel.Title = 'Character and Mobility of Users';
            app.UICharMobPlot = uiaxes(app.CharacterandMobilityofUsersPanel);
            xlabel(app.UICharMobPlot, 'X')
            ylabel(app.UICharMobPlot, 'Y')
            zlabel(app.UICharMobPlot, 'Z')
            % Show the figure after all components are created
            app.MainScreenUN.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UserNeeds

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainScreenUN)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainScreenUN)
        end
    end
end