classdef VehicleConcepts < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainScreenVC                 matlab.ui.Figure
        Step5AutonomousSharedandElectricVehicleConceptsLabel  matlab.ui.control.Label
        MainMenuPanel                matlab.ui.container.Panel
        DataOptionsPanel             matlab.ui.container.Panel
        LoadButton                   matlab.ui.control.Button
        SaveButton                   matlab.ui.control.Button
        DisplayOptionsPanel          matlab.ui.container.Panel
        ShowallVCinISOViewTopButton  matlab.ui.control.Button
        ShowallVCinSideViewButton    matlab.ui.control.Button
        ShowallVCinTopViewButton     matlab.ui.control.Button
        ShowallVCinISOViewBotButton  matlab.ui.control.Button
        ShowParameterListforallVCButton  matlab.ui.control.Button
        ShowSingleVCButton           matlab.ui.control.Button
        ShowSingleVehicleConceptsButtonGroup  matlab.ui.container.ButtonGroup
        VehicleConcept1Button        matlab.ui.control.ToggleButton
        VehicleConcept2Button        matlab.ui.control.ToggleButton
        VehicleConcept3Button        matlab.ui.control.ToggleButton
        VehicleConcept4Button        matlab.ui.control.ToggleButton
        VehicleConcept5Button        matlab.ui.control.ToggleButton
        VehicleConcept6Button        matlab.ui.control.ToggleButton
        AnalysisViewButton           matlab.ui.control.Button
        HomeButton                   matlab.ui.control.Button
        TUMlogo                      matlab.ui.control.Image
        Single_VC_Panel_1            matlab.ui.container.Panel
        TG_SV_1                      matlab.ui.container.TabGroup
        Show3DModelTab               matlab.ui.container.Tab
        Rotation_Figure_1            matlab.ui.control.UIAxes
        ShowDifferentViewsTab_1      matlab.ui.container.Tab
        ISOViewTop_Sing_1            matlab.ui.control.Image
        ISOViewBot_Sing_1            matlab.ui.control.Image
        SideView_Sing_1              matlab.ui.control.Image
        TopView_Sing_1               matlab.ui.control.Image
        ShowSingleVCinnewWindowButton_1  matlab.ui.control.Button
        Single_VC_Panel_2            matlab.ui.container.Panel
        TG_SV_2                      matlab.ui.container.TabGroup
        Show3DModelTab_2             matlab.ui.container.Tab
        Rotation_Figure_2            matlab.ui.control.UIAxes
        ShowDifferentViewsTab_2      matlab.ui.container.Tab
        ISOViewTop_Sing_2            matlab.ui.control.Image
        ISOViewBot_Sing_2            matlab.ui.control.Image
        SideView_Sing_2              matlab.ui.control.Image
        TopView_Sing_2               matlab.ui.control.Image
        ShowSingleVCinnewWindowButton_2  matlab.ui.control.Button
        Single_VC_Panel_3            matlab.ui.container.Panel
        TG_SV_3                      matlab.ui.container.TabGroup
        Show3DModelTab_3             matlab.ui.container.Tab
        Rotation_Figure_3            matlab.ui.control.UIAxes
        ShowDifferentViewsTab_3      matlab.ui.container.Tab
        ISOViewTop_Sing_3            matlab.ui.control.Image
        ISOViewBot_Sing_3            matlab.ui.control.Image
        SideView_Sing_3              matlab.ui.control.Image
        TopView_Sing_3               matlab.ui.control.Image
        ShowSingleVCinnewWindowButton_3  matlab.ui.control.Button
        Single_VC_Panel_4            matlab.ui.container.Panel
        TG_SV_4                      matlab.ui.container.TabGroup
        Show3DModelTab_4             matlab.ui.container.Tab
        Rotation_Figure_4            matlab.ui.control.UIAxes
        ShowDifferentViewsTab_4      matlab.ui.container.Tab
        ISOViewTop_Sing_4            matlab.ui.control.Image
        ISOViewBot_Sing_4            matlab.ui.control.Image
        SideView_Sing_4              matlab.ui.control.Image
        TopView_Sing_4               matlab.ui.control.Image
        ShowSingleVCinnewWindowButton_4  matlab.ui.control.Button
        Single_VC_Panel_5            matlab.ui.container.Panel
        TG_SV_5                      matlab.ui.container.TabGroup
        Show3DModelTab_5             matlab.ui.container.Tab
        Rotation_Figure_5            matlab.ui.control.UIAxes
        ShowDifferentViewsTab_5      matlab.ui.container.Tab
        ISOViewTop_Sing_5            matlab.ui.control.Image
        ISOViewBot_Sing_5            matlab.ui.control.Image
        SideView_Sing_5              matlab.ui.control.Image
        TopView_Sing_5               matlab.ui.control.Image
        ShowSingleVCinnewWindowButton_5  matlab.ui.control.Button
        Single_VC_Panel_6            matlab.ui.container.Panel
        TG_SV_6                      matlab.ui.container.TabGroup
        Show3DModelTab_6             matlab.ui.container.Tab
        Rotation_Figure_6            matlab.ui.control.UIAxes
        ShowDifferentViewsTab_6      matlab.ui.container.Tab
        ISOViewTop_Sing_6            matlab.ui.control.Image
        ISOViewBot_Sing_6            matlab.ui.control.Image
        SideView_Sing_6              matlab.ui.control.Image
        TopView_Sing_6               matlab.ui.control.Image
        ShowSingleVCinnewWindowButton_6  matlab.ui.control.Button
        All_VC_Panel                 matlab.ui.container.Panel
        TG_VC_1                      matlab.ui.container.TabGroup
        ISOViewTopTab_1              matlab.ui.container.Tab
        ISOViewTopPic_1              matlab.ui.control.Image
        ISOViewBotTab_1              matlab.ui.container.Tab
        ISOViewBotPic_1              matlab.ui.control.Image
        SideViewTab_1                matlab.ui.container.Tab
        SideViewPic_1                matlab.ui.control.Image
        TopViewTab_1                 matlab.ui.container.Tab
        TopViewPic_1                 matlab.ui.control.Image
        ParameterListTab_1           matlab.ui.container.Tab
        ParameterList_1              matlab.ui.control.TextArea
        TG_VC_2                      matlab.ui.container.TabGroup
        ISOViewTopTab_2              matlab.ui.container.Tab
        ISOViewTopPic_2              matlab.ui.control.Image
        ISOViewBotTab_2              matlab.ui.container.Tab
        ISOViewBotPic_2              matlab.ui.control.Image
        SideViewTab_2                matlab.ui.container.Tab
        SideViewPic_2                matlab.ui.control.Image
        TopViewTab_2                 matlab.ui.container.Tab
        TopViewPic_2                 matlab.ui.control.Image
        ParameterListTab_2           matlab.ui.container.Tab
        ParameterList_2              matlab.ui.control.TextArea
        TG_VC_3                      matlab.ui.container.TabGroup
        ISOViewTopTab_3              matlab.ui.container.Tab
        ISOViewTopPic_3              matlab.ui.control.Image
        ISOViewBotTab_3              matlab.ui.container.Tab
        ISOViewBotPic_3              matlab.ui.control.Image
        SideViewTab_3                matlab.ui.container.Tab
        SideViewPic_3                matlab.ui.control.Image
        TopViewTab_3                 matlab.ui.container.Tab
        TopViewPic_3                 matlab.ui.control.Image
        ParameterListTab_3           matlab.ui.container.Tab
        ParameterList_3              matlab.ui.control.TextArea
        TG_VC_4                      matlab.ui.container.TabGroup
        ISOViewTopTab_4              matlab.ui.container.Tab
        ISOViewTopPic_4              matlab.ui.control.Image
        ISOViewBotTab_4              matlab.ui.container.Tab
        ISOViewBotPic_4              matlab.ui.control.Image
        SideViewTab_4                matlab.ui.container.Tab
        SideViewPic_4                matlab.ui.control.Image
        TopViewTab_4                 matlab.ui.container.Tab
        TopViewPic_4                 matlab.ui.control.Image
        ParameterListTab_4           matlab.ui.container.Tab
        ParameterList_4              matlab.ui.control.TextArea
        TG_VC_5                      matlab.ui.container.TabGroup
        ISOViewTopTab_5              matlab.ui.container.Tab
        ISOViewTopPic_5              matlab.ui.control.Image
        ISOViewBotTab_5              matlab.ui.container.Tab
        ISOViewBotPic_5              matlab.ui.control.Image
        SideViewTab_5                matlab.ui.container.Tab
        SideViewPic_5                matlab.ui.control.Image
        TopViewTab_5                 matlab.ui.container.Tab
        TopViewPic_5                 matlab.ui.control.Image
        ParameterListTab_5           matlab.ui.container.Tab
        ParameterList_5              matlab.ui.control.TextArea
        TG_VC_6                      matlab.ui.container.TabGroup
        ISOViewTopTab_6              matlab.ui.container.Tab
        ISOViewTopPic_6              matlab.ui.control.Image
        ISOViewBotTab_6              matlab.ui.container.Tab
        ISOViewBotPic_6              matlab.ui.control.Image
        SideViewTab_6                matlab.ui.container.Tab
        SideViewPic_6                matlab.ui.control.Image
        TopViewTab_6                 matlab.ui.container.Tab
        TopViewPic_6                 matlab.ui.control.Image
        ParameterListTab_6           matlab.ui.container.Tab
        ParameterList_6              matlab.ui.control.TextArea
    end

    %% General Information:
    
    %----------------------------------------------------------------------
    % Designed by Marc Raudszus, Ferdinand Schockenhoff (FTM, Techical University of Munich)
    % Template and few parts of the code of David Fischer (FTM, Techical University of Munich)
    %---------------------------------------
    % Created on:   September 2021
    % Last update:  December 2021
    %---------------------------------------
    % Version:      Matlab2020b
    %---------------------------------------
    % Description:  GUI of the process step "Vehicle Concept Results"
    %---------------------------------------
    % Input:        - VC_result_matrix: (parameters of the Vehicle Concepts for the Vehicle Concept Results App)
    %               => cell (n x m) n = number of parameters / m = number of vehicle concepts + 1 
    %               => Currently not active, but later needed for list of parameterss in the VCR-App
    %
    %               - VC_feasible: Vector of numbers from feasible VC (feasible in AuVeCoDe)
    %---------------------------------------
    % Output:       End of tool-chain
    %---------------------------------------
    % To-Do:        - Load function
    %----------------------------------------------------------------------
    properties (Access = private)
        
        oldpath = addpath('99_General_Elements\') % Needed for first build up of app (TUM image)
        
    end
    %----------------------------------------------------------------------
    properties (Access = public)
        
        VC_result_matrix                    % Parameters of the Vehicle Concepts for the Vehicle Concept Results App
        VC_feasible                         % Vector of numbers from feasible VC (feasible in AuVeCoDe)
        VC_feasible_num                     % Amount of feasible VC
        design_parameter_matrix_VC          % Design Parameter Matrix from steps before
        CRPofVCs                            % Current Customer-relevant properties of the Vehicle Concepts
        CRP                                 % Customer-relevant properties of the Vehicle Concepts
        
    end
    %----------------------------------------------------------------------
    methods (Access = private)
        %----------------------------------------------------------------------
        % Function to display rotatable figure
        function Display_3DModel_Rotation(app,iteration_VCR)
            % Load saved figure from AuVeCoDe-Tool
            fig_3D_name = sprintf('11_Vehicle Concept\\temp\\01_3D_Model_Figures\\VehicleConcept3DModel_%0.0f.fig',iteration_VCR);
            fig_3D = openfig(fig_3D_name, 'invisible');
            ax = findobj(fig_3D,'Type','Axes');
            
            % Copy the figure into the GUI including settings
            Rotation_figure_name = sprintf('Rotation_Figure_%0.0f',iteration_VCR);
            copyobj(ax.Children, app.(Rotation_figure_name))
            
            % Figure Options
            app.(Rotation_figure_name).DataAspectRatio = [1 1 1];
            app.(Rotation_figure_name).View=[22, 18];
            
            % Title Change to current VC
            panel_name = sprintf('Single_VC_Panel_%0.0f',iteration_VCR);
            title_name = sprintf('Vehicle Concept %0.0f',app.VC_feasible(iteration_VCR));
            app.(panel_name).Title = title_name;
            
            % Delete figure
            delete(fig_3D)
        end        
        %----------------------------------------------------------------------
        % 
        %----------------------------------------------------------------------
        % Function to create the bmp-images for the required views
        function Create_View_Pictures(app,iteration_VCR)
            
            % Open the figure saved from the AuVeCoDe-Tool
            figfilename = sprintf('11_Vehicle Concept\\temp\\01_3D_Model_Figures\\VehicleConcept3DModel_%0.0f.fig',iteration_VCR);
            picfig = openfig(figfilename, 'invisible');
            
            % Modify the view of said figure
   
            % ISO view top
            view(-22, 18);
            titlename_ISOViewTop = sprintf("Vehicle Concept %0.0f: ISO-View Top",app.VC_feasible(iteration_VCR));
            title(titlename_ISOViewTop)
            set(gca,'LooseInset',get(gca,'TightInset'));
            savename_ISOViewTop = sprintf("11_Vehicle Concept\\temp\\02_Saved_Views\\ISOViewTop_%0.0f",iteration_VCR);
            saveas(picfig,savename_ISOViewTop,'bmp');
            
            % ISO view bot
            view(22, -30);
            titlename_ISOViewBot = sprintf("Vehicle Concept %0.0f: ISO-View Bot",app.VC_feasible(iteration_VCR));
            title(titlename_ISOViewBot)
            set(gca,'LooseInset',get(gca,'TightInset'));
            savename_ISOViewBot = sprintf("11_Vehicle Concept\\temp\\02_Saved_Views\\ISOViewBot_%0.0f",iteration_VCR);
            saveas(picfig,savename_ISOViewBot,'bmp');
            
            % Side view
            view(0, 0);
            titlename_SideView = sprintf("Vehicle Concept %0.0f: Side View",app.VC_feasible(iteration_VCR));
            title(titlename_SideView)
            set(gca,'LooseInset',get(gca,'TightInset'));
            savename_SideView = sprintf("11_Vehicle Concept\\temp\\02_Saved_Views\\SideView_%0.0f",iteration_VCR);
            saveas(picfig,savename_SideView,'bmp');
            
            % Top view
            view(0, 90);
            titlename_TopView = sprintf("Vehicle Concept %0.0f: Top View",app.VC_feasible(iteration_VCR));
            title(titlename_TopView)
            set(gca,'LooseInset',get(gca,'TightInset'));
            savename_TopView = sprintf("11_Vehicle Concept\\temp\\02_Saved_Views\\TopView_%0.0f",iteration_VCR);
            saveas(picfig,savename_TopView,'bmp');
     
            % Delete figure
            delete(picfig);
        end
        %----------------------------------------------------------------------
        % 
        %----------------------------------------------------------------------
        % Function to import saved bmp-views into GUI 
        function Import_Pictures(app,iteration_VCR)
            
            % Views for "All VC panel"
            ISO_top_name = sprintf('ISOViewTopPic_%0.0f',iteration_VCR);
            ISO_top_pic = sprintf('11_Vehicle Concept\\temp\\02_Saved_Views\\ISOViewTop_%0.0f.bmp',iteration_VCR);
            app.(ISO_top_name).ImageSource = imread(ISO_top_pic);
            
            ISO_bot_name = sprintf('ISOViewBotPic_%0.0f',iteration_VCR);
            ISO_bot_pic = sprintf('11_Vehicle Concept\\temp\\02_Saved_Views\\ISOViewBot_%0.0f.bmp',iteration_VCR);
            app.(ISO_bot_name).ImageSource = imread(ISO_bot_pic);
            
            Side_name = sprintf('SideViewPic_%0.0f',iteration_VCR);
            Side_pic = sprintf('11_Vehicle Concept\\temp\\02_Saved_Views\\SideView_%0.0f.bmp',iteration_VCR);
            app.(Side_name).ImageSource = imread(Side_pic);
            
            Top_name = sprintf('TopViewPic_%0.0f',iteration_VCR);
            Top_pic = sprintf('11_Vehicle Concept\\temp\\02_Saved_Views\\TopView_%0.0f.bmp',iteration_VCR);
            app.(Top_name).ImageSource = imread(Top_pic);
            
            ParameterList_name = sprintf('ParamterList_%0.0f',iteration_VCR);
            ParameterList_Content_VC_Number = sprintf('%s: %0.0f\n\n',app.VC_result_matrix{1,1},app.VC_result_matrix{1,(iteration_VCR+1)});
                        
            ParameterList_Content_Total = '';
            [num_Parameters, ~] = size(app.VC_result_matrix);
            for i_ParameterList = 3:num_Parameters % Convert to variable
                ParameterList_name = sprintf('ParameterList_%0.0f',iteration_VCR);
                ParameterList_Content = sprintf('%s     %0.2f\n',app.VC_result_matrix{i_ParameterList,1},app.VC_result_matrix{i_ParameterList,(iteration_VCR+1)});
                ParameterList_Content_Total = append(ParameterList_Content_Total,ParameterList_Content);
            end
            
            ParameterList_Total = append(ParameterList_Content_VC_Number,ParameterList_Content_Total);
            app.(ParameterList_name).Value = ParameterList_Total;            
            
            % Views for "Single VC panels"
            ISO_top_name = sprintf('ISOViewTop_Sing_%0.0f',iteration_VCR);
            ISO_top_pic = sprintf('11_Vehicle Concept\\temp\\02_Saved_Views\\ISOViewTop_%0.0f.bmp',iteration_VCR);
            app.(ISO_top_name).ImageSource = imread(ISO_top_pic);
            
            ISO_bot_name = sprintf('ISOViewBot_Sing_%0.0f',iteration_VCR);
            ISO_bot_pic = sprintf('11_Vehicle Concept\\temp\\02_Saved_Views\\ISOViewBot_%0.0f.bmp',iteration_VCR);
            app.(ISO_bot_name).ImageSource = imread(ISO_bot_pic);
            
            Side_name = sprintf('SideView_Sing_%0.0f',iteration_VCR);
            Side_pic = sprintf('11_Vehicle Concept\\temp\\02_Saved_Views\\SideView_%0.0f.bmp',iteration_VCR);
            app.(Side_name).ImageSource = imread(Side_pic);
            
            Top_name = sprintf('TopView_Sing_%0.0f',iteration_VCR);
            Top_pic = sprintf('11_Vehicle Concept\\temp\\02_Saved_Views\\TopView_%0.0f.bmp',iteration_VCR);
            app.(Top_name).ImageSource = imread(Top_pic);       
        end
        %----------------------------------------------------------------------
        % 
        %----------------------------------------------------------------------
        function standard_view(app)
            
            % Initialize Tab Group visibility for "All VC panel" and
            % initialize Vehicle Concepts Button Group
            
            % Make visible
            for i_visible_TG = 1:app.VC_feasible_num
                TG_name = sprintf('TG_VC_%0.0f',i_visible_TG);
                button_name = sprintf('VehicleConcept%0.0fButton',i_visible_TG);
                button_text = sprintf('Vehicle Concept %0.0f',app.VC_feasible(i_visible_TG));
                
                app.(TG_name).Visible = 'on';
                app.(button_name).Visible = 'on';
                app.(button_name).Text = button_text;
            end
            
            % Make not visible
            for i_not_visible_TG = (app.VC_feasible_num + 1):6
                TG_name = sprintf('TG_VC_%0.0f',i_not_visible_TG);
                button_name = sprintf('VehicleConcept%0.0fButton',i_not_visible_TG);
                
                app.(TG_name).Visible = 'off';
                app.(button_name).Visible = 'off';
            end
            
            % Create standard view
            app.Single_VC_Panel_1.Visible = 'off';
            app.Single_VC_Panel_2.Visible = 'off';
            app.Single_VC_Panel_3.Visible = 'off';
            app.Single_VC_Panel_4.Visible = 'off';
            app.Single_VC_Panel_5.Visible = 'off';
            app.Single_VC_Panel_6.Visible = 'off';
            app.ShowSingleVehicleConceptsButtonGroup.Visible = 'off';
            app.All_VC_Panel.Visible = 'on';
        end
        %----------------------------------------------------------------------
        % 
        %----------------------------------------------------------------------
        % Disable all Main Menu Icons
        function disable_all(app)
            app.ShowSingleVCButton.Enable = 'off';
            app.ShowParameterListforallVCButton.Enable = 'off';
            app.ShowallVCinISOViewBotButton.Enable = 'off';
            app.ShowallVCinTopViewButton.Enable = 'off';
            app.ShowallVCinSideViewButton.Enable = 'off';
            app.ShowallVCinISOViewTopButton.Enable = 'off';
            app.AnalysisViewButton.Enable = 'off';
            app.LoadButton.Enable = 'off';
            app.SaveButton.Enable = 'off';
            app.ShowSingleVehicleConceptsButtonGroup.Visible = 'off';
        end
        %----------------------------------------------------------------------
        % 
        %----------------------------------------------------------------------
        % Enable all Main Menu Icons
        function enable_all(app)
            app.ShowSingleVCButton.Enable = 'on';
            app.ShowParameterListforallVCButton.Enable = 'on';
            app.ShowallVCinISOViewBotButton.Enable = 'on';
            app.ShowallVCinTopViewButton.Enable = 'on';
            app.ShowallVCinSideViewButton.Enable = 'on';
            app.ShowallVCinISOViewTopButton.Enable = 'on';
            app.AnalysisViewButton.Enable = 'on';
            app.LoadButton.Enable = 'on';
            app.SaveButton.Enable = 'on';
        end
        %----------------------------------------------------------------------
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, VC_result, VC_number, input_parameter_matrix_VC, currentCRP, CustomerRelevantPropertiesVC)
            % Adding necessary folders to path
            addpath('99_General_Elements\')
            addpath(genpath('11_Vehicle Concept\'))
            
            % Check if data in the workspace is available
            dataExistCheck = exist('VC_result',"var");
            if dataExistCheck == 0
                uiconfirm(app.MainScreenVC,'App was loaded with no dataset. Please load manually a predefined dataset with the "Load" Button',...
                    'No dataset chosen','Options',{'Ok'},'Icon','warning');
                disable_all(app);
                app.LoadButton.Enable = 'on';
            else
   
                % Defining the input parameters
                app.VC_result_matrix = VC_result;
                app.VC_feasible = VC_number;
                app.design_parameter_matrix_VC = input_parameter_matrix_VC;
                app.CRPofVCs = currentCRP;
                app.CRP = CustomerRelevantPropertiesVC;
                [app.VC_feasible_num,~] = size(app.VC_feasible);
            
                % Display Standard View
                standard_view(app);
            
                % Delete existing Vehicle Concept plots
                delete('11_Vehicle Concept\temp\02_Saved_Views\*.bmp');
            
                % Display all feasible Vehicle Concepts
                for iteration_VCR = 1:app.VC_feasible_num
                    filename3Dmodel = sprintf('11_Vehicle Concept\\temp\\01_3D_Model_Figures\\VehicleConcept3DModel_%0.0f.fig',iteration_VCR);
                
                    % Check if file is existing
                    if isfile(filename3Dmodel) == 1
                    
                        % Create rotatable 3D-Model
                        Display_3DModel_Rotation(app,iteration_VCR);
                    
                        % Create views as .bmp-files
                        Create_View_Pictures(app,iteration_VCR);
                    
                        % Import created views into the GUI
                        Import_Pictures(app,iteration_VCR);
                    end
                end
            end
            
            % Opening the window maximized
            drawnow;
            app.MainScreenVC.WindowState = 'maximized';
        end

        % Button pushed function: ShowSingleVCButton
        function ShowSingleVCButtonPushed(app, event)
            % Change to other view
            ShowSingleVehicleConceptsButtonGroupSelectionChanged(app, event);
        end

        % Button pushed function: ShowallVCinISOViewTopButton
        function ShowallVCinISOViewTopButtonPushed(app, event)
            % Activate ISO-View Top tabs in all tab-groups
            for i_panel = 1:6
                single_VC_panel_name = sprintf('Single_VC_Panel_%0.0f',i_panel);
                TG_VC_panel_name = sprintf('TG_VC_%0.0f',i_panel);
                ISOViewTop_name = sprintf('ISOViewTopTab_%0.0f',i_panel);
                app.(single_VC_panel_name).Visible = 'off';
                app.(TG_VC_panel_name).SelectedTab = app.(ISOViewTop_name);
            end
            app.ShowSingleVehicleConceptsButtonGroup.Visible = 'off';
            app.All_VC_Panel.Visible = 'on';
        end

        % Button pushed function: ShowallVCinSideViewButton
        function ShowallVCinSideViewButtonPushed(app, event)
            % Activate Side-View tabs in all tab-groups
            for i_panel = 1:6
                single_VC_panel_name = sprintf('Single_VC_Panel_%0.0f',i_panel);
                TG_VC_panel_name = sprintf('TG_VC_%0.0f',i_panel);
                SideView_name = sprintf('SideViewTab_%0.0f',i_panel);
                app.(single_VC_panel_name).Visible = 'off';
                app.(TG_VC_panel_name).SelectedTab = app.(SideView_name);
            end
            app.ShowSingleVehicleConceptsButtonGroup.Visible = 'off';
            app.All_VC_Panel.Visible = 'on';
        end

        % Button pushed function: ShowallVCinTopViewButton
        function ShowallVCinTopViewButtonPushed(app, event)
            % Activate Top-View tabs in all tab-groups
            for i_panel = 1:6
                single_VC_panel_name = sprintf('Single_VC_Panel_%0.0f',i_panel);
                TG_VC_panel_name = sprintf('TG_VC_%0.0f',i_panel);
                TopView_name = sprintf('TopViewTab_%0.0f',i_panel);
                app.(single_VC_panel_name).Visible = 'off';
                app.(TG_VC_panel_name).SelectedTab = app.(TopView_name);
            end
            app.ShowSingleVehicleConceptsButtonGroup.Visible = 'off';
            app.All_VC_Panel.Visible = 'on';
        end

        % Button pushed function: ShowallVCinISOViewBotButton
        function ShowallVCinISOViewBotButtonPushed(app, event)
            % Activate ISO-View Bot tabs in all tab-groups
            for i_panel = 1:6
                single_VC_panel_name = sprintf('Single_VC_Panel_%0.0f',i_panel);
                TG_VC_panel_name = sprintf('TG_VC_%0.0f',i_panel);
                ISOViewBot_name = sprintf('ISOViewBotTab_%0.0f',i_panel);
                app.(single_VC_panel_name).Visible = 'off';
                app.(TG_VC_panel_name).SelectedTab = app.(ISOViewBot_name);
            end
            app.ShowSingleVehicleConceptsButtonGroup.Visible = 'off';
            app.All_VC_Panel.Visible = 'on';
        end

        % Button pushed function: ShowParameterListforallVCButton
        function ShowParameterListforallVCButtonPushed(app, event)
            % Activate Parameter List tabs in all tab-groups
            for i_panel = 1:6
                single_VC_panel_name = sprintf('Single_VC_Panel_%0.0f',i_panel);
                TG_VC_panel_name = sprintf('TG_VC_%0.0f',i_panel);
                ParameterList_name = sprintf('ParameterListTab_%0.0f',i_panel);
                app.(single_VC_panel_name).Visible = 'off';
                app.(TG_VC_panel_name).SelectedTab = app.(ParameterList_name);
            end
            app.ShowSingleVehicleConceptsButtonGroup.Visible = 'off';
            app.All_VC_Panel.Visible = 'on';
        end

        % Button pushed function: ShowSingleVCinnewWindowButton_1
        function ShowSingleVCinnewWindowButton_1Pushed(app, event)
            % Open new window to display rotatable 3D-Model
            Rot_Fig = openfig('11_Vehicle Concept\\temp\\01_3D_Model_Figures\\VehicleConcept3DModel_1.fig');
            Rot_Fig.Visible='on';
        end

        % Selection changed function: 
        % ShowSingleVehicleConceptsButtonGroup
        function ShowSingleVehicleConceptsButtonGroupSelectionChanged(app, event)
            % Create "single vehicle concept"-view
            selectedButton = app.ShowSingleVehicleConceptsButtonGroup.SelectedObject;
            
            % Make every single VC panel invisible
            for i_panel = 1:6
                VC_panel_name = sprintf('Single_VC_Panel_%0.0f',i_panel);
                app.(VC_panel_name).Visible = 'off';
            end
            
            % Make the selected VC panel visible
            for i_button = 1:6
                VC_button_name = sprintf('VehicleConcept%0.0fButton',i_button);
                if selectedButton == app.(VC_button_name)
                    VC_panel_name = sprintf('Single_VC_Panel_%0.0f',i_button);
                    app.(VC_panel_name).Visible = 'on';
                    break
                end
            end
            % Make "all vehicle concepts"-view invisible
            app.All_VC_Panel.Visible = 'off';
            
            % Make button group visible
            app.ShowSingleVehicleConceptsButtonGroup.Visible = 'on';
        end

        % Button pushed function: ShowSingleVCinnewWindowButton_2
        function ShowSingleVCinnewWindowButton_2Pushed(app, event)
            % Open new window to display rotatable 3D-Model
            Rot_Fig = openfig('11_Vehicle Concept\\temp\\01_3D_Model_Figures\\VehicleConcept3DModel_2.fig');
            Rot_Fig.Visible='on';
        end

        % Button pushed function: ShowSingleVCinnewWindowButton_3
        function ShowSingleVCinnewWindowButton_3Pushed(app, event)
            % Open new window to display rotatable 3D-Model
            Rot_Fig = openfig('11_Vehicle Concept\\temp\\01_3D_Model_Figures\\VehicleConcept3DModel_3.fig');
            Rot_Fig.Visible='on';
        end

        % Button pushed function: ShowSingleVCinnewWindowButton_4
        function ShowSingleVCinnewWindowButton_4Pushed(app, event)
            % Open new window to display rotatable 3D-Model
            Rot_Fig = openfig('11_Vehicle Concept\\temp\\01_3D_Model_Figures\\VehicleConcept3DModel_4.fig');
            Rot_Fig.Visible='on';
        end

        % Button pushed function: ShowSingleVCinnewWindowButton_5
        function ShowSingleVCinnewWindowButton_5Pushed(app, event)
            % Open new window to display rotatable 3D-Model
            Rot_Fig = openfig('11_Vehicle Concept\\temp\\01_3D_Model_Figures\\VehicleConcept3DModel_5.fig');
            Rot_Fig.Visible='on';
        end

        % Button pushed function: ShowSingleVCinnewWindowButton_6
        function ShowSingleVCinnewWindowButton_6Pushed(app, event)
            % Open new window to display rotatable 3D-Model
            Rot_Fig = openfig('11_Vehicle Concept\\temp\\01_3D_Model_Figures\\VehicleConcept3DModel_6.fig');
            Rot_Fig.Visible='on';
        end

        % Button pushed function: AnalysisViewButton
        function AnalysisViewButtonPushed(app, event)
            % Analysis view for easy comparison of VC
            ShowSingleVehicleConceptsButtonGroupSelectionChanged(app, event)
            
            app.TG_SV_1.SelectedTab = app.ShowDifferentViewsTab_1;
            app.TG_SV_2.SelectedTab = app.ShowDifferentViewsTab_2;
            app.TG_SV_3.SelectedTab = app.ShowDifferentViewsTab_3;
            app.TG_SV_4.SelectedTab = app.ShowDifferentViewsTab_4;
            app.TG_SV_5.SelectedTab = app.ShowDifferentViewsTab_5;
            app.TG_SV_6.SelectedTab = app.ShowDifferentViewsTab_6;
            % Optimization potential
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            %Open a confirmation dialog box
            fig = app.MainScreenVC;
            message = sprintf(['Do you want to return to the Home screen? \n\n'...
                'Consider saving their current results. Otherwise it will be deleted.']);
            selection = uiconfirm(fig,message,'Confirm return to Home screen','Icon','question');
            tf = strcmp(selection,'OK');
            %Execute only when the user agrees
            if tf == true
                Start;
                delete(app);
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Open the dialog box to choose .mat file
            folder = '11_Vehicle Concept\VC_Saved_Scenarios\';
            filename = uigetfile(folder,'*.mat');
            if filename ~= 0
                load(filename,'VC_result','VC_number','input_parameter_matrix_VC','currentCRP','CustomerRelevantPropertiesVC');
                % Defining the input parameters
                app.VC_result_matrix = VC_result;
                app.VC_feasible = VC_number;
                app.design_parameter_matrix_VC = input_parameter_matrix_VC;
                app.CRPofVCs = currentCRP;
                app.CRP = CustomerRelevantPropertiesVC;
                [app.VC_feasible_num,~] = size(app.VC_feasible);
                
                % Delete existing Vehicle Concept plots
                delete('11_Vehicle Concept\temp\01_3D_Model_Figures\*.fig');
                delete('11_Vehicle Concept\temp\02_Saved_Views\*.bmp');
                
                %Copy 3D models from VC_Saved-Scenarios to temp                
                foldername = append(folder, '3D_models\',filename(1:end-4),'\*.fig');                
                copyfile(foldername, '11_Vehicle Concept\temp\01_3D_Model_Figures');
                
                % Display Standard View
                standard_view(app);
                
                % Display all feasible Vehicle Concepts
                for iteration_VCR = 1:app.VC_feasible_num
                    filename3Dmodel = sprintf('11_Vehicle Concept\\temp\\01_3D_Model_Figures\\VehicleConcept3DModel_%0.0f.fig',iteration_VCR);
                
                    % Check if file is existing
                    if isfile(filename3Dmodel) == 1
                    
                        % Create rotatable 3D-Model
                        Display_3DModel_Rotation(app,iteration_VCR);
                    
                        % Create views as .bmp-files
                        Create_View_Pictures(app,iteration_VCR);
                    
                        % Import created views into the GUI
                        Import_Pictures(app,iteration_VCR);
                    end
                end                
            end
            
            % enable all buttons
            enable_all(app);
                
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
           % save the current Vehickle Concept
            filename = uiputfile('*.mat','myScenario_VC','11_Vehicle Concept\VC_Saved_Scenarios\');
                if filename ~= 0
                    
                    % prepare data
                    VC_result = app.VC_result_matrix;
                    VC_number = app.VC_feasible;
                    input_parameter_matrix_VC = app.design_parameter_matrix_VC;
                    currentCRP = app.CRPofVCs;
                    CustomerRelevantPropertiesVC = app.CRP;
                    % prepare path
                    newName = append('11_Vehicle Concept\VC_Saved_Scenarios\',filename);
                    save(newName,'VC_result','VC_number','input_parameter_matrix_VC','currentCRP','CustomerRelevantPropertiesVC')
                    % Pepare data and path for the saving of the figures
                    foldername = filename(1:end-4);
                    newFolder = append('11_Vehicle Concept\VC_Saved_Scenarios\3D_models\', foldername);
                    
                    % delete content or create folder for figures
                    if isfolder(newFolder)
                        delete(append(newFolder, '\*'));                                                                
                    else                            
                        mkdir(newFolder);
                    end
                    % copy figures from temp to the scenario folder
                    copyfile('11_Vehicle Concept\temp\01_3D_Model_Figures\*.fig', newFolder);                            
                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainScreenVC = uifigure('Visible', 'off');
            app.MainScreenVC.Name = 'Vehicle Concepts';
            app.MainScreenVC.Icon = 'FTMLogo.PNG';
            app.Step5AutonomousSharedandElectricVehicleConceptsLabel = uilabel(app.MainScreenVC);
            app.Step5AutonomousSharedandElectricVehicleConceptsLabel.Text = '              Step 5: Autonomous, Shared and Electric Vehicle Concepts';
            app.MainMenuPanel = uipanel(app.MainScreenVC);
            app.MainMenuPanel.Title = 'Main Menu';
            app.DataOptionsPanel = uipanel(app.MainMenuPanel);
            app.DataOptionsPanel.Title = 'Data Options';
            app.LoadButton = uibutton(app.DataOptionsPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Tooltip = {''};
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.DataOptionsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tooltip = {''};
            app.SaveButton.Text = 'Save';
            app.DisplayOptionsPanel = uipanel(app.MainMenuPanel);
            app.DisplayOptionsPanel.Title = 'Display Options';
            app.ShowallVCinISOViewTopButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.ShowallVCinISOViewTopButton.ButtonPushedFcn = createCallbackFcn(app, @ShowallVCinISOViewTopButtonPushed, true);
            app.ShowallVCinISOViewTopButton.Tooltip = {''};
            app.ShowallVCinISOViewTopButton.Text = 'Show all VC in ISO-View Top';
            app.ShowallVCinSideViewButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.ShowallVCinSideViewButton.ButtonPushedFcn = createCallbackFcn(app, @ShowallVCinSideViewButtonPushed, true);
            app.ShowallVCinSideViewButton.Tooltip = {''};
            app.ShowallVCinSideViewButton.Text = 'Show all VC in Side-View';
            app.ShowallVCinTopViewButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.ShowallVCinTopViewButton.ButtonPushedFcn = createCallbackFcn(app, @ShowallVCinTopViewButtonPushed, true);
            app.ShowallVCinTopViewButton.Tooltip = {''};
            app.ShowallVCinTopViewButton.Text = 'Show all VC in Top-View';
            app.ShowallVCinISOViewBotButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.ShowallVCinISOViewBotButton.ButtonPushedFcn = createCallbackFcn(app, @ShowallVCinISOViewBotButtonPushed, true);
            app.ShowallVCinISOViewBotButton.Tooltip = {''};
            app.ShowallVCinISOViewBotButton.Text = 'Show all VC in ISO-View Bot';
            app.ShowParameterListforallVCButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.ShowParameterListforallVCButton.ButtonPushedFcn = createCallbackFcn(app, @ShowParameterListforallVCButtonPushed, true);
            app.ShowParameterListforallVCButton.Tooltip = {''};
            app.ShowParameterListforallVCButton.Text = 'Show Parameter List for all VC';
            app.ShowSingleVCButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.ShowSingleVCButton.ButtonPushedFcn = createCallbackFcn(app, @ShowSingleVCButtonPushed, true);
            app.ShowSingleVCButton.Tooltip = {''};
            app.ShowSingleVCButton.Text = 'Show Single VC';
            app.ShowSingleVehicleConceptsButtonGroup = uibuttongroup(app.MainMenuPanel);
            app.ShowSingleVehicleConceptsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ShowSingleVehicleConceptsButtonGroupSelectionChanged, true);
            app.ShowSingleVehicleConceptsButtonGroup.Title = 'Show Single - Vehicle Concepts';
            app.VehicleConcept1Button = uitogglebutton(app.ShowSingleVehicleConceptsButtonGroup);
            app.VehicleConcept1Button.Text = 'Vehicle Concept 1';
            app.VehicleConcept1Button.Value = true;
            app.VehicleConcept2Button = uitogglebutton(app.ShowSingleVehicleConceptsButtonGroup);
            app.VehicleConcept2Button.Text = 'Vehicle Concept 2';
            app.VehicleConcept3Button = uitogglebutton(app.ShowSingleVehicleConceptsButtonGroup);
            app.VehicleConcept3Button.Text = 'Vehicle Concept 3';
            app.VehicleConcept4Button = uitogglebutton(app.ShowSingleVehicleConceptsButtonGroup);
            app.VehicleConcept4Button.Text = 'Vehicle Concept 4';
            app.VehicleConcept5Button = uitogglebutton(app.ShowSingleVehicleConceptsButtonGroup);
            app.VehicleConcept5Button.Text = 'Vehicle Concept 5';
            app.VehicleConcept6Button = uitogglebutton(app.ShowSingleVehicleConceptsButtonGroup);
            app.VehicleConcept6Button.Text = 'Vehicle Concept 6';
            app.AnalysisViewButton = uibutton(app.MainMenuPanel, 'push');
            app.AnalysisViewButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisViewButtonPushed, true);
            app.AnalysisViewButton.Tooltip = {''};
            app.AnalysisViewButton.Text = 'Analysis View';
            app.HomeButton = uibutton(app.MainScreenVC, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Icon = 'Home.svg';
            app.HomeButton.Text = '';
            app.TUMlogo = uiimage(app.MainScreenVC);
            app.TUMlogo.ImageSource = '2015_Logo_TUM_neg_RGB.svg';
            app.Single_VC_Panel_1 = uipanel(app.MainScreenVC);
            app.Single_VC_Panel_1.Title = 'Vehicle Concept 1';
            app.TG_SV_1 = uitabgroup(app.Single_VC_Panel_1);
            app.Show3DModelTab = uitab(app.TG_SV_1);
            app.Show3DModelTab.Title = 'Show 3D-Model';
            app.Rotation_Figure_1 = uiaxes(app.Show3DModelTab);
            xlabel(app.Rotation_Figure_1, 'X')
            ylabel(app.Rotation_Figure_1, 'Y')
            zlabel(app.Rotation_Figure_1, 'Z')
            app.ShowDifferentViewsTab_1 = uitab(app.TG_SV_1);
            app.ShowDifferentViewsTab_1.Title = 'Show Different Views';
            app.ISOViewTop_Sing_1 = uiimage(app.ShowDifferentViewsTab_1);
            app.ISOViewBot_Sing_1 = uiimage(app.ShowDifferentViewsTab_1);
            app.SideView_Sing_1 = uiimage(app.ShowDifferentViewsTab_1);
            app.TopView_Sing_1 = uiimage(app.ShowDifferentViewsTab_1);
            app.ShowSingleVCinnewWindowButton_1 = uibutton(app.Single_VC_Panel_1, 'push');
            app.ShowSingleVCinnewWindowButton_1.ButtonPushedFcn = createCallbackFcn(app, @ShowSingleVCinnewWindowButton_1Pushed, true);
            app.ShowSingleVCinnewWindowButton_1.Tooltip = {''};
            app.ShowSingleVCinnewWindowButton_1.Text = 'Show Single VC in new Window';
            app.Single_VC_Panel_2 = uipanel(app.MainScreenVC);
            app.Single_VC_Panel_2.Title = 'Vehicle Concept 2';
            app.TG_SV_2 = uitabgroup(app.Single_VC_Panel_2);
            app.Show3DModelTab_2 = uitab(app.TG_SV_2);
            app.Show3DModelTab_2.Title = 'Show 3D-Model';
            app.Rotation_Figure_2 = uiaxes(app.Show3DModelTab_2);
            xlabel(app.Rotation_Figure_2, 'X')
            ylabel(app.Rotation_Figure_2, 'Y')
            zlabel(app.Rotation_Figure_2, 'Z')
            app.ShowDifferentViewsTab_2 = uitab(app.TG_SV_2);
            app.ShowDifferentViewsTab_2.Title = 'Show Different Views';
            app.ISOViewTop_Sing_2 = uiimage(app.ShowDifferentViewsTab_2);
            app.ISOViewBot_Sing_2 = uiimage(app.ShowDifferentViewsTab_2);
            app.SideView_Sing_2 = uiimage(app.ShowDifferentViewsTab_2);
            app.TopView_Sing_2 = uiimage(app.ShowDifferentViewsTab_2);
            app.ShowSingleVCinnewWindowButton_2 = uibutton(app.Single_VC_Panel_2, 'push');
            app.ShowSingleVCinnewWindowButton_2.ButtonPushedFcn = createCallbackFcn(app, @ShowSingleVCinnewWindowButton_2Pushed, true);
            app.ShowSingleVCinnewWindowButton_2.Tooltip = {''};
            app.ShowSingleVCinnewWindowButton_2.Text = 'Show Single VC in new Window';
            app.Single_VC_Panel_3 = uipanel(app.MainScreenVC);
            app.Single_VC_Panel_3.Title = 'Vehicle Concept 3';
            app.TG_SV_3 = uitabgroup(app.Single_VC_Panel_3);
            app.Show3DModelTab_3 = uitab(app.TG_SV_3);
            app.Show3DModelTab_3.Title = 'Show 3D-Model';
            app.Rotation_Figure_3 = uiaxes(app.Show3DModelTab_3);
            xlabel(app.Rotation_Figure_3, 'X')
            ylabel(app.Rotation_Figure_3, 'Y')
            zlabel(app.Rotation_Figure_3, 'Z')
            app.ShowDifferentViewsTab_3 = uitab(app.TG_SV_3);
            app.ShowDifferentViewsTab_3.Title = 'Show Different Views';
            app.ISOViewTop_Sing_3 = uiimage(app.ShowDifferentViewsTab_3);
            app.ISOViewBot_Sing_3 = uiimage(app.ShowDifferentViewsTab_3);
            app.SideView_Sing_3 = uiimage(app.ShowDifferentViewsTab_3);
            app.TopView_Sing_3 = uiimage(app.ShowDifferentViewsTab_3);
            app.ShowSingleVCinnewWindowButton_3 = uibutton(app.Single_VC_Panel_3, 'push');
            app.ShowSingleVCinnewWindowButton_3.ButtonPushedFcn = createCallbackFcn(app, @ShowSingleVCinnewWindowButton_3Pushed, true);
            app.ShowSingleVCinnewWindowButton_3.Tooltip = {''};
            app.ShowSingleVCinnewWindowButton_3.Text = 'Show Single VC in new Window';
            app.Single_VC_Panel_4 = uipanel(app.MainScreenVC);
            app.Single_VC_Panel_4.Title = 'Vehicle Concept 4';
            app.TG_SV_4 = uitabgroup(app.Single_VC_Panel_4);
            app.Show3DModelTab_4 = uitab(app.TG_SV_4);
            app.Show3DModelTab_4.Title = 'Show 3D-Model';
            app.Rotation_Figure_4 = uiaxes(app.Show3DModelTab_4);
            xlabel(app.Rotation_Figure_4, 'X')
            ylabel(app.Rotation_Figure_4, 'Y')
            zlabel(app.Rotation_Figure_4, 'Z')
            app.ShowDifferentViewsTab_4 = uitab(app.TG_SV_4);
            app.ShowDifferentViewsTab_4.Title = 'Show Different Views';
            app.ISOViewTop_Sing_4 = uiimage(app.ShowDifferentViewsTab_4);
            app.ISOViewBot_Sing_4 = uiimage(app.ShowDifferentViewsTab_4);
            app.SideView_Sing_4 = uiimage(app.ShowDifferentViewsTab_4);
            app.TopView_Sing_4 = uiimage(app.ShowDifferentViewsTab_4);
            app.ShowSingleVCinnewWindowButton_4 = uibutton(app.Single_VC_Panel_4, 'push');
            app.ShowSingleVCinnewWindowButton_4.ButtonPushedFcn = createCallbackFcn(app, @ShowSingleVCinnewWindowButton_4Pushed, true);
            app.ShowSingleVCinnewWindowButton_4.Tooltip = {''};
            app.ShowSingleVCinnewWindowButton_4.Text = 'Show Single VC in new Window';
            app.Single_VC_Panel_5 = uipanel(app.MainScreenVC);
            app.Single_VC_Panel_5.Title = 'Vehicle Concept 5';
            app.TG_SV_5 = uitabgroup(app.Single_VC_Panel_5);
            app.Show3DModelTab_5 = uitab(app.TG_SV_5);
            app.Show3DModelTab_5.Title = 'Show 3D-Model';
            app.Rotation_Figure_5 = uiaxes(app.Show3DModelTab_5);
            xlabel(app.Rotation_Figure_5, 'X')
            ylabel(app.Rotation_Figure_5, 'Y')
            zlabel(app.Rotation_Figure_5, 'Z')
            app.ShowDifferentViewsTab_5 = uitab(app.TG_SV_5);
            app.ShowDifferentViewsTab_5.Title = 'Show Different Views';
            app.ISOViewTop_Sing_5 = uiimage(app.ShowDifferentViewsTab_5);
            app.ISOViewBot_Sing_5 = uiimage(app.ShowDifferentViewsTab_5);
            app.SideView_Sing_5 = uiimage(app.ShowDifferentViewsTab_5);
            app.TopView_Sing_5 = uiimage(app.ShowDifferentViewsTab_5);
            app.ShowSingleVCinnewWindowButton_5 = uibutton(app.Single_VC_Panel_5, 'push');
            app.ShowSingleVCinnewWindowButton_5.ButtonPushedFcn = createCallbackFcn(app, @ShowSingleVCinnewWindowButton_5Pushed, true);
            app.ShowSingleVCinnewWindowButton_5.Tooltip = {''};
            app.ShowSingleVCinnewWindowButton_5.Text = 'Show Single VC in new Window';
            app.Single_VC_Panel_6 = uipanel(app.MainScreenVC);
            app.Single_VC_Panel_6.Title = 'Vehicle Concept 6';
            app.TG_SV_6 = uitabgroup(app.Single_VC_Panel_6);
            app.Show3DModelTab_6 = uitab(app.TG_SV_6);
            app.Show3DModelTab_6.Title = 'Show 3D-Model';
            app.Rotation_Figure_6 = uiaxes(app.Show3DModelTab_6);
            xlabel(app.Rotation_Figure_6, 'X')
            ylabel(app.Rotation_Figure_6, 'Y')
            zlabel(app.Rotation_Figure_6, 'Z')
            app.ShowDifferentViewsTab_6 = uitab(app.TG_SV_6);
            app.ShowDifferentViewsTab_6.Title = 'Show Different Views';
            app.ISOViewTop_Sing_6 = uiimage(app.ShowDifferentViewsTab_6);
            app.ISOViewBot_Sing_6 = uiimage(app.ShowDifferentViewsTab_6);
            app.SideView_Sing_6 = uiimage(app.ShowDifferentViewsTab_6);
            app.TopView_Sing_6 = uiimage(app.ShowDifferentViewsTab_6);
            app.ShowSingleVCinnewWindowButton_6 = uibutton(app.Single_VC_Panel_6, 'push');
            app.ShowSingleVCinnewWindowButton_6.ButtonPushedFcn = createCallbackFcn(app, @ShowSingleVCinnewWindowButton_6Pushed, true);
            app.ShowSingleVCinnewWindowButton_6.Tooltip = {''};
            app.ShowSingleVCinnewWindowButton_6.Text = 'Show Single VC in new Window';
            app.All_VC_Panel = uipanel(app.MainScreenVC);
            app.All_VC_Panel.Title = 'Results for all Vehicle Concepts';
            app.TG_VC_1 = uitabgroup(app.All_VC_Panel);
            app.ISOViewTopTab_1 = uitab(app.TG_VC_1);
            app.ISOViewTopTab_1.Title = 'ISO-View Top';
            app.ISOViewTopPic_1 = uiimage(app.ISOViewTopTab_1);
            app.ISOViewBotTab_1 = uitab(app.TG_VC_1);
            app.ISOViewBotTab_1.Title = 'ISO-View Bot';
            app.ISOViewBotPic_1 = uiimage(app.ISOViewBotTab_1);
            app.SideViewTab_1 = uitab(app.TG_VC_1);
            app.SideViewTab_1.Title = 'Side-View';
            app.SideViewPic_1 = uiimage(app.SideViewTab_1);
            app.TopViewTab_1 = uitab(app.TG_VC_1);
            app.TopViewTab_1.Title = 'Top-View';
            app.TopViewPic_1 = uiimage(app.TopViewTab_1);
            app.ParameterListTab_1 = uitab(app.TG_VC_1);
            app.ParameterListTab_1.Title = 'Parameter List';
            app.ParameterList_1 = uitextarea(app.ParameterListTab_1);
            app.TG_VC_2 = uitabgroup(app.All_VC_Panel);
            app.ISOViewTopTab_2 = uitab(app.TG_VC_2);
            app.ISOViewTopTab_2.Title = 'ISO-View Top';
            app.ISOViewTopPic_2 = uiimage(app.ISOViewTopTab_2);
            app.ISOViewBotTab_2 = uitab(app.TG_VC_2);
            app.ISOViewBotTab_2.Title = 'ISO-View Bot';
            app.ISOViewBotPic_2 = uiimage(app.ISOViewBotTab_2);
            app.SideViewTab_2 = uitab(app.TG_VC_2);
            app.SideViewTab_2.Title = 'Side-View';
            app.SideViewPic_2 = uiimage(app.SideViewTab_2);
            app.TopViewTab_2 = uitab(app.TG_VC_2);
            app.TopViewTab_2.Title = 'Top-View';
            app.TopViewPic_2 = uiimage(app.TopViewTab_2);
            app.ParameterListTab_2 = uitab(app.TG_VC_2);
            app.ParameterListTab_2.Title = 'Parameter  List';
            app.ParameterList_2 = uitextarea(app.ParameterListTab_2);
            app.TG_VC_3 = uitabgroup(app.All_VC_Panel);
            app.ISOViewTopTab_3 = uitab(app.TG_VC_3);
            app.ISOViewTopTab_3.Title = 'ISO-View Top';
            app.ISOViewTopPic_3 = uiimage(app.ISOViewTopTab_3);
            app.ISOViewBotTab_3 = uitab(app.TG_VC_3);
            app.ISOViewBotTab_3.Title = 'ISO-View Bot';
            app.ISOViewBotPic_3 = uiimage(app.ISOViewBotTab_3);
            app.SideViewTab_3 = uitab(app.TG_VC_3);
            app.SideViewTab_3.Title = 'Side-View';
            app.SideViewPic_3 = uiimage(app.SideViewTab_3);
            app.TopViewTab_3 = uitab(app.TG_VC_3);
            app.TopViewTab_3.Title = 'Top-View';
            app.TopViewPic_3 = uiimage(app.TopViewTab_3);
            app.ParameterListTab_3 = uitab(app.TG_VC_3);
            app.ParameterListTab_3.Title = 'Parameter List';
            app.ParameterList_3 = uitextarea(app.ParameterListTab_3);
            app.TG_VC_4 = uitabgroup(app.All_VC_Panel);
            app.ISOViewTopTab_4 = uitab(app.TG_VC_4);
            app.ISOViewTopTab_4.Title = 'ISO-View Top';
            app.ISOViewTopPic_4 = uiimage(app.ISOViewTopTab_4);
            app.ISOViewBotTab_4 = uitab(app.TG_VC_4);
            app.ISOViewBotTab_4.Title = 'ISO-View Bot';
            app.ISOViewBotPic_4 = uiimage(app.ISOViewBotTab_4);
            app.SideViewTab_4 = uitab(app.TG_VC_4);
            app.SideViewTab_4.Title = 'Side-View';
            app.SideViewPic_4 = uiimage(app.SideViewTab_4);
            app.TopViewTab_4 = uitab(app.TG_VC_4);
            app.TopViewTab_4.Title = 'Top-View';
            app.TopViewPic_4 = uiimage(app.TopViewTab_4);
            app.ParameterListTab_4 = uitab(app.TG_VC_4);
            app.ParameterListTab_4.Title = 'Parameter List';
            app.ParameterList_4 = uitextarea(app.ParameterListTab_4);
            app.TG_VC_5 = uitabgroup(app.All_VC_Panel);
            app.ISOViewTopTab_5 = uitab(app.TG_VC_5);
            app.ISOViewTopTab_5.Title = 'ISO-View Top';
            app.ISOViewTopPic_5 = uiimage(app.ISOViewTopTab_5);
            app.ISOViewBotTab_5 = uitab(app.TG_VC_5);
            app.ISOViewBotTab_5.Title = 'ISO-View Bot';
            app.ISOViewBotPic_5 = uiimage(app.ISOViewBotTab_5);
            app.SideViewTab_5 = uitab(app.TG_VC_5);
            app.SideViewTab_5.Title = 'Side-View';
            app.SideViewPic_5 = uiimage(app.SideViewTab_5);
            app.TopViewTab_5 = uitab(app.TG_VC_5);
            app.TopViewTab_5.Title = 'Top-View';
            app.TopViewPic_5 = uiimage(app.TopViewTab_5);
            app.ParameterListTab_5 = uitab(app.TG_VC_5);
            app.ParameterListTab_5.Title = 'Parameter List';
            app.ParameterList_5 = uitextarea(app.ParameterListTab_5);
            app.TG_VC_6 = uitabgroup(app.All_VC_Panel);
            app.ISOViewTopTab_6 = uitab(app.TG_VC_6);
            app.ISOViewTopTab_6.Title = 'ISO-View Top';
            app.ISOViewTopPic_6 = uiimage(app.ISOViewTopTab_6);
            app.ISOViewBotTab_6 = uitab(app.TG_VC_6);
            app.ISOViewBotTab_6.Title = 'ISO-View Bot';
            app.ISOViewBotPic_6 = uiimage(app.ISOViewBotTab_6);
            app.SideViewTab_6 = uitab(app.TG_VC_6);
            app.SideViewTab_6.Title = 'Side-View';
            app.SideViewPic_6 = uiimage(app.SideViewTab_6);
            app.TopViewTab_6 = uitab(app.TG_VC_6);
            app.TopViewTab_6.Title = 'Top-View';
            app.TopViewPic_6 = uiimage(app.TopViewTab_6);
            app.ParameterListTab_6 = uitab(app.TG_VC_6);
            app.ParameterListTab_6.Title = 'Parameter List';
            app.ParameterList_6 = uitextarea(app.ParameterListTab_6);
            % Show the figure after all components are created
            app.MainScreenVC.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VehicleConcepts(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainScreenVC)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainScreenVC)
        end
    end
end