classdef VehicleProvision < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainScreenVP                    matlab.ui.Figure
        Step12VehicleBoundMobilityProvisionLabel  matlab.ui.control.Label
        MainMenuPanel                   matlab.ui.container.Panel
        DataOptionsPanel                matlab.ui.container.Panel
        LoadButton                      matlab.ui.control.Button
        DisplayOptionsPanel             matlab.ui.container.Panel
        ShowDerivativesButton           matlab.ui.control.Button
        ShowDerivativesforUsersButton   matlab.ui.control.Button
        ShowUsersperDerivativesButton   matlab.ui.control.Button
        CreateCustomViewButton          matlab.ui.control.Button
        ProceedandGenerateCustomerrelevantPropertiesButton  matlab.ui.control.Button
        AddadditionalDerivativesCheckBox  matlab.ui.control.CheckBox
        SpidergraphSelectionButtonGroup  matlab.ui.container.ButtonGroup
        SecondaryActivitiesButton       matlab.ui.control.ToggleButton
        CharacterMobilityButton         matlab.ui.control.ToggleButton
        Onlyshowing14DerivativesoutofXXLabel  matlab.ui.control.Label
        TUMlogo                         matlab.ui.control.Image
        HomeButton                      matlab.ui.control.Button
        CustomViewPanel                 matlab.ui.container.Panel
        SecondaryActivitiesPanel        matlab.ui.container.Panel
        SecondaryActivitiesCustom       matlab.ui.control.UIAxes
        CharacterMobilityPanel          matlab.ui.container.Panel
        CharacterMobilityCustom         matlab.ui.control.UIAxes
        DerivativesPanel                matlab.ui.container.Panel
        DerivativeLamp1_3               matlab.ui.control.Lamp
        DerivativeLamp2_3               matlab.ui.control.Lamp
        DerivativeLamp3_3               matlab.ui.control.Lamp
        DerivativeLamp4_3               matlab.ui.control.Lamp
        DerivativeLamp5_3               matlab.ui.control.Lamp
        DerivativeLamp6_3               matlab.ui.control.Lamp
        DerivativeLamp7_3               matlab.ui.control.Lamp
        DerivativeLamp8_3               matlab.ui.control.Lamp
        DerivativeLamp9_3               matlab.ui.control.Lamp
        DerivativeLamp10_3              matlab.ui.control.Lamp
        DerivativeLamp11_3              matlab.ui.control.Lamp
        DerivativeLamp12_3              matlab.ui.control.Lamp
        DerivativeLamp13_3              matlab.ui.control.Lamp
        DerivativeLamp14_3              matlab.ui.control.Lamp
        Derivative1LampLabel_3          matlab.ui.control.Label
        Derivative8LampLabel_3          matlab.ui.control.Label
        Derivative2LampLabel_3          matlab.ui.control.Label
        Derivative9LampLabel_3          matlab.ui.control.Label
        Derivative3LampLabel_3          matlab.ui.control.Label
        Derivative10LampLabel_3         matlab.ui.control.Label
        Derivative4LampLabel_3          matlab.ui.control.Label
        Derivative11LampLabel_3         matlab.ui.control.Label
        Derivative5LampLabel_3          matlab.ui.control.Label
        Derivative12LampLabel_3         matlab.ui.control.Label
        Derivative6LampLabel_3          matlab.ui.control.Label
        Derivative13LampLabel_3         matlab.ui.control.Label
        Derivative7LampLabel_3          matlab.ui.control.Label
        Derivative14LampLabel_3         matlab.ui.control.Label
        CheckBox1                       matlab.ui.control.CheckBox
        CheckBox2                       matlab.ui.control.CheckBox
        CheckBox3                       matlab.ui.control.CheckBox
        CheckBox4                       matlab.ui.control.CheckBox
        CheckBox5                       matlab.ui.control.CheckBox
        CheckBox6                       matlab.ui.control.CheckBox
        CheckBox7                       matlab.ui.control.CheckBox
        CheckBox8                       matlab.ui.control.CheckBox
        CheckBox9                       matlab.ui.control.CheckBox
        CheckBox10                      matlab.ui.control.CheckBox
        CheckBox11                      matlab.ui.control.CheckBox
        CheckBox12                      matlab.ui.control.CheckBox
        CheckBox13                      matlab.ui.control.CheckBox
        CheckBox14                      matlab.ui.control.CheckBox
        UsersPanel                      matlab.ui.container.Panel
        User1Label_2                    matlab.ui.control.Label
        User4Label_2                    matlab.ui.control.Label
        User2Label_2                    matlab.ui.control.Label
        User5Label_2                    matlab.ui.control.Label
        User3Label_2                    matlab.ui.control.Label
        User6Label_2                    matlab.ui.control.Label
        CheckBox1_2                     matlab.ui.control.CheckBox
        CheckBox2_2                     matlab.ui.control.CheckBox
        CheckBox3_2                     matlab.ui.control.CheckBox
        CheckBox4_2                     matlab.ui.control.CheckBox
        CheckBox5_2                     matlab.ui.control.CheckBox
        CheckBox6_2                     matlab.ui.control.CheckBox
        UserLamp1                       matlab.ui.control.Lamp
        UserLamp2                       matlab.ui.control.Lamp
        UserLamp3                       matlab.ui.control.Lamp
        UserLamp4                       matlab.ui.control.Lamp
        UserLamp5                       matlab.ui.control.Lamp
        UserLamp6                       matlab.ui.control.Lamp
        UpdateViewButton                matlab.ui.control.Button
        TickAllButton                   matlab.ui.control.Button
        UsersperDerivativesPanel        matlab.ui.container.Panel
        UsersPanel_2                    matlab.ui.container.Panel
        User1Label_3                    matlab.ui.control.Label
        UserLamp1_2                     matlab.ui.control.Lamp
        User2Label_3                    matlab.ui.control.Label
        UserLamp2_2                     matlab.ui.control.Lamp
        User3Label_3                    matlab.ui.control.Label
        UserLamp3_2                     matlab.ui.control.Lamp
        User4Label_3                    matlab.ui.control.Label
        UserLamp4_2                     matlab.ui.control.Lamp
        User5Label_3                    matlab.ui.control.Label
        UserLamp5_2                     matlab.ui.control.Lamp
        User6Label_3                    matlab.ui.control.Label
        UserLamp6_2                     matlab.ui.control.Lamp
        DerivativeSelectionButtonGroup  matlab.ui.container.ButtonGroup
        V1_6                            matlab.ui.control.ToggleButton
        V7_12                           matlab.ui.control.ToggleButton
        V13_14                          matlab.ui.control.ToggleButton
        PanelUpD1                       matlab.ui.container.Panel
        SpiderUpD1                      matlab.ui.control.UIAxes
        PanelUpD2                       matlab.ui.container.Panel
        SpiderUpD2                      matlab.ui.control.UIAxes
        PanelUpD3                       matlab.ui.container.Panel
        SpiderUpD3                      matlab.ui.control.UIAxes
        PanelUpD4                       matlab.ui.container.Panel
        SpiderUpD4                      matlab.ui.control.UIAxes
        PanelUpD5                       matlab.ui.container.Panel
        SpiderUpD5                      matlab.ui.control.UIAxes
        PanelUpD6                       matlab.ui.container.Panel
        SpiderUpD6                      matlab.ui.control.UIAxes
        DerivativesforUsersPanel        matlab.ui.container.Panel
        PanelV4U1                       matlab.ui.container.Panel
        SpiderV4U1                      matlab.ui.control.UIAxes
        PanelV4U2                       matlab.ui.container.Panel
        SpiderV4U2                      matlab.ui.control.UIAxes
        PanelV4U3                       matlab.ui.container.Panel
        SpiderV4U3                      matlab.ui.control.UIAxes
        PanelV4U4                       matlab.ui.container.Panel
        SpiderV4U4                      matlab.ui.control.UIAxes
        PanelV4U5                       matlab.ui.container.Panel
        SpiderV4U5                      matlab.ui.control.UIAxes
        PanelV4U6                       matlab.ui.container.Panel
        SpiderV4U6                      matlab.ui.control.UIAxes
        DerivativetypesPanel            matlab.ui.container.Panel
        DerivativeType1_2               matlab.ui.control.EditField
        DerivativeType2_2               matlab.ui.control.EditField
        DerivativeType3_2               matlab.ui.control.EditField
        DerivativeType4_2               matlab.ui.control.EditField
        DerivativeType5_2               matlab.ui.control.EditField
        DerivativeType6_2               matlab.ui.control.EditField
        DerivativeType7_2               matlab.ui.control.EditField
        DerivativeType8_2               matlab.ui.control.EditField
        DerivativeType9_2               matlab.ui.control.EditField
        DerivativeType10_2              matlab.ui.control.EditField
        DerivativeType11_2              matlab.ui.control.EditField
        DerivativeType12_2              matlab.ui.control.EditField
        DerivativeType13_2              matlab.ui.control.EditField
        DerivativeType14_2              matlab.ui.control.EditField
        Derivative1LampLabel            matlab.ui.control.Label
        Derivative8LampLabel            matlab.ui.control.Label
        Derivative2LampLabel            matlab.ui.control.Label
        Derivative9LampLabel            matlab.ui.control.Label
        Derivative3LampLabel            matlab.ui.control.Label
        Derivative10LampLabel           matlab.ui.control.Label
        Derivative4LampLabel            matlab.ui.control.Label
        Derivative11LampLabel           matlab.ui.control.Label
        Derivative5LampLabel            matlab.ui.control.Label
        Derivative12LampLabel           matlab.ui.control.Label
        Derivative6LampLabel            matlab.ui.control.Label
        Derivative13LampLabel           matlab.ui.control.Label
        Derivative7LampLabel            matlab.ui.control.Label
        Derivative14LampLabel           matlab.ui.control.Label
        DerivativeLamp1_2               matlab.ui.control.Lamp
        DerivativeLamp2_2               matlab.ui.control.Lamp
        DerivativeLamp3_2               matlab.ui.control.Lamp
        DerivativeLamp4_2               matlab.ui.control.Lamp
        DerivativeLamp5_2               matlab.ui.control.Lamp
        DerivativeLamp6_2               matlab.ui.control.Lamp
        DerivativeLamp7_2               matlab.ui.control.Lamp
        DerivativeLamp8_2               matlab.ui.control.Lamp
        DerivativeLamp9_2               matlab.ui.control.Lamp
        DerivativeLamp10_2              matlab.ui.control.Lamp
        DerivativeLamp11_2              matlab.ui.control.Lamp
        DerivativeLamp12_2              matlab.ui.control.Lamp
        DerivativeLamp13_2              matlab.ui.control.Lamp
        DerivativeLamp14_2              matlab.ui.control.Lamp
        ProvidedDerivativesPanel        matlab.ui.container.Panel
        ProvidedSecondaryActivitiesoftheDerivativesPanel  matlab.ui.container.Panel
        VehiclesSecondaryActivities     matlab.ui.control.UIAxes
        ProvidedCharacterandMobilityBehavioroftheDerivativesPanel  matlab.ui.container.Panel
        VehiclesCharacterMobility       matlab.ui.control.UIAxes
        DerivativetypesPanel_2          matlab.ui.container.Panel
        DerivativeType1                 matlab.ui.control.EditField
        DerivativeType2                 matlab.ui.control.EditField
        DerivativeType3                 matlab.ui.control.EditField
        DerivativeType4                 matlab.ui.control.EditField
        DerivativeType5                 matlab.ui.control.EditField
        DerivativeType6                 matlab.ui.control.EditField
        DerivativeType7                 matlab.ui.control.EditField
        DerivativeType8                 matlab.ui.control.EditField
        DerivativeType9                 matlab.ui.control.EditField
        DerivativeType10                matlab.ui.control.EditField
        DerivativeType11                matlab.ui.control.EditField
        DerivativeType12                matlab.ui.control.EditField
        DerivativeType13                matlab.ui.control.EditField
        DerivativeType14                matlab.ui.control.EditField
        Derivative1LampLabel_2          matlab.ui.control.Label
        Derivative8LampLabel_2          matlab.ui.control.Label
        Derivative2LampLabel_2          matlab.ui.control.Label
        Derivative9LampLabel_2          matlab.ui.control.Label
        Derivative3LampLabel_2          matlab.ui.control.Label
        Derivative10LampLabel_2         matlab.ui.control.Label
        Derivative4LampLabel_2          matlab.ui.control.Label
        Derivative11LampLabel_2         matlab.ui.control.Label
        Derivative5LampLabel_2          matlab.ui.control.Label
        Derivative12LampLabel_2         matlab.ui.control.Label
        Derivative6LampLabel_2          matlab.ui.control.Label
        Derivative13LampLabel_2         matlab.ui.control.Label
        Derivative7LampLabel_2          matlab.ui.control.Label
        Derivative14LampLabel_2         matlab.ui.control.Label
        DerivativeLamp1                 matlab.ui.control.Lamp
        DerivativeLamp2                 matlab.ui.control.Lamp
        DerivativeLamp3                 matlab.ui.control.Lamp
        DerivativeLamp4                 matlab.ui.control.Lamp
        DerivativeLamp5                 matlab.ui.control.Lamp
        DerivativeLamp6                 matlab.ui.control.Lamp
        DerivativeLamp7                 matlab.ui.control.Lamp
        DerivativeLamp8                 matlab.ui.control.Lamp
        DerivativeLamp9                 matlab.ui.control.Lamp
        DerivativeLamp10                matlab.ui.control.Lamp
        DerivativeLamp11                matlab.ui.control.Lamp
        DerivativeLamp12                matlab.ui.control.Lamp
        DerivativeLamp13                matlab.ui.control.Lamp
        DerivativeLamp14                matlab.ui.control.Lamp
    end

    %% General Information:
    
    % Designed by David Fischer, Ferdinand Schockenhoff (FTM, Techical University of Munich)
    %---------------------------------------
    % Created on June 2021
    % last update: 2021-12-02
    %---------------------------------------
    % Version: Matlab2020b
    %---------------------------------------
    % Description: GUI of the process step "Vehicle Provision"
    %---------------------------------------
    % Input: 'user_matrix','user_Names','user_wish','derivative_suggestion','derivative_suggestion_extend',...
    %               'user_fulfillment','user2derivative','user2derivative_extend'
    %---------------------------------------
    % Output: 'derivativesCRP','derivativeSuggestionCRP'
    % Start of the app CrProperties
    
    properties (Access = private)
        oldpath = addpath('99_General_Elements\') % Needed for first build up of app (TUM image)
        
        %All these variables are imported from the app userNeeds
        userMatrix;                     %contains the collected needs of every user
        userNames;                      %vector with names of the users
        userWish;                       %transfer the percentage (0-100) app.PercentageofUserNeedsFulfillmentEditField.Value in a number (0-1)
        derivativeSuggestion = zeros(1,18);           %derivative to satisfy the users' needs
        derivativeSuggestionExtend;     %extended number to satisfy the users' needs and vary the mobility needs
        userFulfillment;                %reached users_ fulfillement with suggested derivatives
        user2Derivative;                %Which derivative satisfies which need of which user
        user2derivativeExtend;          %Which derivative satisfies which need of which user in the extended number of derivatives
        
        %Additional variables
        derivativeSuggestionBackup      %Saves the initial derivativeSuggestion
        user2DerivativeBackup;          %Saves the initial user2Drivative
        derivativeSuggestionOverflow    %Saves the derivatives that exceed the maximum display number
        user2DerivativeOverflow         %Saves the mapping of the users 2 derivatives for exceeding derivatives
        ColorDerivatives = [[249 186 0]./255; [0 124 48]./255;  [105 8 90]./255; [227 114 34]./255; [162 173 0]./255; [152 198 234]./255; [218 215 203]./255;...
                 [255 220 0]./255; [103 154 29]./255; [156 13 22]./255; [214 76 19]./255; [50 119 138]./255; [100 160 200]./255; [15 27 9]./255;]; %TUM Colors for Derivatives
        ColorUsers = [[0 0.4 0.74];[0 0.32 0.58];[0 0.2 0.35];[0.85 0.85 0.86];[0.61 0.62 0.62];[0.36 0.36 0.35]]; %TUM Colors for Users
    end
    
    methods (Access = private)
        
        %__________________________________________________________________
        %Determines the edit field value and color for the derivative type
        function derivativeTypeEvaluation(app,derivativeSuggestion,derivativeNumber,editFieldName,lampName)
            name = sprintf(editFieldName,derivativeNumber);
            if derivativeSuggestion(derivativeNumber,18) == 1
                app.(name).Value = "Private";
            elseif derivativeSuggestion(derivativeNumber,18) == 2
                app.(name).Value = "Taxi";
            elseif derivativeSuggestion(derivativeNumber,18) == 3
                app.(name).Value = "Shuttle";
            else
                app.(name).Value = "not needed";
            end
            %setting the edit field color to match the line color
            derivativesCol = app.ColorDerivatives;
            lamp = sprintf(lampName,derivativeNumber); 
            app.(lamp).Color = derivativesCol(derivativeNumber,:);
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Sets the name and lamp color for not used elements
        function derivativeTypeFiller(app,derivativeSuggestion,editFieldName,lampName)
            [numberDerivatives,~] = size(derivativeSuggestion);
            if numberDerivatives < 14
                newNumber = numberDerivatives+1;
                for i=newNumber:14
                    fieldName = sprintf(editFieldName,i);
                    app.(fieldName).Value = "not needed";
                    lamp = sprintf(lampName,i);
                    app.(lamp).Color = 'white';
                end
            end    
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Setting up the data for Users per Derivative plot
        function [dataSec,lblSec,dataCharMob,lblCharMob,figure,leg] = UsersPerVehiclePlotsData(app,userMatrix,...
                derivativeSuggestion,derivativeNumber,figureNumber)
        
            %Concatenating user matrix data and derivative suggestion data
            %Looking for the necessary derivatives for the user
            logicalArray = app.user2Derivative == derivativeNumber;
            logicalVector = sum(logicalArray,2);
            nonZeroElementsNumber = nnz(logicalVector);
            
            %Data for Secondary Activities
            dataDSecDS = derivativeSuggestion(derivativeNumber,2:7);
            dataDSecDS(1,7:9) = derivativeSuggestion(derivativeNumber,7); %Data from the derivative
            counter = 0;
            dataDSecUM = zeros(nonZeroElementsNumber,9);
            for int=1:size(logicalVector,1)
                if logicalVector(int) ~= 0
                    counter = counter + 1;
                    dataDSecUM(counter,:) = userMatrix(int,1:9);
                end
            end
            dataSec = [dataDSecUM;dataDSecDS]';
            
            %Data for Character and Mobility
            dataDCharMobDS = derivativeSuggestion(derivativeNumber,11:16);
            counter = 0;
            dataDCharMobUM = zeros(nonZeroElementsNumber,6);
            for int=1:size(logicalVector,1)
                if logicalVector(int) ~= 0
                    counter = counter + 1;
                    dataDCharMobUM(counter,:) = userMatrix(int,11:16);
                end
            end
            dataCharMob = [dataDCharMobUM;dataDCharMobDS]';
            
            %Label for Secondary Activities
            lblSec = {'Self-Driving'; 'Relax'; 'Work'; 'Sleep'; 'Load'; 'Passengers for Self-Driving'; sprintf('Passengers \nfor Relax'); ...
                sprintf('Passengers \nfor Work'); sprintf('Passengers \nfor Sleep')};
            
            %Label for Character/Mobility
            lblCharMob = {sprintf('Willingness to Invest'); sprintf('Need of \n   Security/Safety'); 'DP Urban'; 'DP Rural'; 'DP Highway'; 'DP General Need'};
            
            %Legend and figure is same for both graphs
            figureName = sprintf('SpiderUpD%d',figureNumber);
            figure = app.(figureName);
            
            leg = cell(1, nonZeroElementsNumber+1);
            leg{1} = sprintf('Derivative %d',derivativeNumber);
            for i=2:nonZeroElementsNumber+1
                k = i-1;
                leg{i}=sprintf('Derivative %d',k);
            end
        end
        %__________________________________________________________________

        %__________________________________________________________________
        %Setting up the data for Derivatives for User plot
        function [dataSec,lblSec,dataCharMob,lblCharMob,figure,leg] = vehicle4UserPlotsData(app,userMatrix,derivativeSuggestion,user2Derivative,UserNumber)
            %%Concatenating user matrix data and derivative suggestion data
            %Looking for the used derivatives
            usedDerivatives = unique(user2Derivative(UserNumber,:)); 
            if usedDerivatives(1) == 0  %Avoiding "0" error
                usedDerivatives = usedDerivatives(2:end);
            end
            [~,numberUsedDerivatives] = size(usedDerivatives);
            
            %Data for Secondary Activities
            dataSecUM = userMatrix(UserNumber,1:9); %Data from the user
            dataSecDSfull = derivativeSuggestion(:,2:7); %Full data from the derivatives for the user
            datasecDSP = derivativeSuggestion(:,7);
            dataSecDSfull = [dataSecDSfull,datasecDSP,datasecDSP,datasecDSP]; %Adding the passengers
            dataSecDS = zeros(numberUsedDerivatives,9); 
            for j=1:numberUsedDerivatives %Selecting only the necessary derivatives
                dataSecDS(j,:) = dataSecDSfull(usedDerivatives(j),:);
            end
            dataSec = [dataSecUM;dataSecDS]';
            
            %Data for Character and Mobility
            dataCharMobUM = userMatrix(UserNumber,11:16);
            dataCharMobDSfull = derivativeSuggestion(:,11:16);
            dataCharMobDS = zeros(numberUsedDerivatives,6);
            for j=1:numberUsedDerivatives
                dataCharMobDS(j,:) = dataCharMobDSfull(usedDerivatives(j),:);
            end
            dataCharMob = [dataCharMobUM;dataCharMobDS]';
            
            %Label for Secondary Activities
            lblSec = {'Self-Driving'; 'Relax'; 'Work'; 'Sleep'; 'Load'; 'Passengers for Self-Driving'; sprintf('Passengers \nfor Relax'); ...
                sprintf('Passengers \nfor Work'); sprintf('Passengers \nfor Sleep')};
            
            %Label for Character/Mobility
            lblCharMob = {sprintf('Willingness to Invest'); sprintf('Need of \n   Security/Safety'); 'DP Urban'; 'DP Rural'; 'DP Highway'; 'DP General Need'};
            
            %Legend and figure is same for both graphs
            figureName = sprintf('SpiderV4U%d',UserNumber);
            figure = app.(figureName);
            
            leg = cell(1, numberUsedDerivatives+1);
            leg{1} = sprintf('User %d',UserNumber);
            for i=2:numberUsedDerivatives+1
                k = i-1;
                leg{i}=sprintf('Derivative %d',k);
            end
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Setting up the data for vehicle secondary activities plot
        function [data1,figure1,lbl1,data2,figure2,lbl2,leg] = vehiclePlotsData(app,derivativeSuggestion)
            [numberDerivatives,~] = size(derivativeSuggestion);
            
            %Data for Secondary Activities
            data1 = (derivativeSuggestion(:,2:7))';
            figure1 = app.VehiclesSecondaryActivities;
            lbl1 = {'Self-Driving';'Relax';'Work';'Sleep';'Load';'People'};
            
            %Data for Character and Mobility
            data2 = (derivativeSuggestion(:,11:16))';
            figure2 = app.VehiclesCharacterMobility;
            lbl2 = {'Willingness to Invest';'Need of Security/Safety';'DP Urban';'DP Rural';'DP Highway';sprintf('DP General \nNeed')};
            
            %Legend is same for both graphs
            leg = cell(1, numberDerivatives);
            for i=1:numberDerivatives
                leg{i}=sprintf('Derivative %d',i);
            end
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Plot function for spider graph
        function plotSpiderGraph(app,data,lbl,ax,leg,fontsize,fts_tm,placement,colorcode,usersColorNumber,derivativesColorNumber)
            % size segments and number of cases
            [r,c] = size(data);
            
            min_max_rng = [0 10];
            rng = repmat (min_max_rng, r, 1);
            
            %Clean up the initial plot
            legend(ax,'off'); reset(ax); cla(ax); hold(ax,'on'); ax.BackgroundColor = [1,1,1];
            
            % set to add plot
            set(ax,'nextplot','add');
            
            % clear figure and set limits
            set(ax,'visible','off'); set(ax,'color','w');        %makes 2D plot invisible and sets background color white
            set(ax,'xlim',placement(1,:),'ylim',placement(2,:)); axis(ax,'equal','manual') %Defines size of the plot
            
            ax.Visible = 'off'; set(ax,'color','w');
            
            % scale by range
            angw = linspace(0,2*pi,r+1)';
            % wrap data (close the last axis to the first)
            ang = angw(1:end-1); 
            angs = [ang(:); ang(1)];
            
            % make the plot
            % define the axis locations
            start_ax = [zeros(1,r)+0.25; sin(ang')+0.25];
            stop_ax = [zeros(1,r); cos(ang')];
            
            % Transformation of data
            start = [zeros(1,r); sin(ang')];
            A_xaxis = data .* start(2,:)';
            A_xaxis = [A_xaxis; A_xaxis(1,:)];
            A_xaxis = A_xaxis *(1/10)+0.25;
            
            stop = [zeros(1,r); cos(ang')];
            A_yaxis = data .* stop(2,:)';
            A_yaxis = [A_yaxis; A_yaxis(1,:)];
            A_yaxis = A_yaxis *(1/10);
            
            % plot the axes
            plot(ax,start_ax,stop_ax,'color','k','linestyle','-','linewidth', 1); hold(ax, 'on'); axis equal; % open figure...
            
            % plot axes markers
            inc = (1/5):(1/5):1; 
            tx = 0.05 * ones(1,5);
            
            %fontsize tick marks
            fonttm = fts_tm;
            
            %fontsize legend
            fontleg = fontsize-4;
            % loop each axis ang plot the line markers and labels
            % add axes
            for ii = 1:r
            	% plot tick lines
                plot(ax,[sin(angs(ii)) * inc; sin(angs(ii+1)) * inc] +0.25, ...
            		[(cos(angs(ii)) * inc); cos(angs(ii+1)) * inc],'color','k','linewidth', 0.75);
                
            	% label the tick marks
            	for jj = 1:5
                    temp = text(ax,sin(angs(ii)) * inc(jj) + cos(angs(ii)) * tx(jj)+0.25, ...
            			cos(angs(ii)) * inc(jj) - sin(angs(ii)) * tx(jj), ...
            			num2str(rd(app,rng(ii,1) + inc(jj)*diff(rng(ii,:)),-2)), ...
            			'fontsize',fonttm, 'FontName', 'Arial');
            		% flip the text alignment for lower axes
            		if angs(ii) >= pi
            			set(temp,'HorizontalAlignment','right');
            		end
            	end
            	% label each axis
            	temp = text(ax,sin(angs(ii)) * 1.1 + cos(angs(ii)) * 0+0.25, cos(angs(ii)) * 1.1 - sin(angs(ii)) * 0, ...
        			char(lbl(ii,:)),'fontsize',fontsize, 'FontName', 'Arial');
            	% flip the text alignment for right side axes
            	if angs(ii) >= pi
            		set(temp,'HorizontalAlignment','right');
            	end
            end
            
            %plot the data
            o = plot(ax,A_xaxis,A_yaxis);
            
            % define data case colors
            derivativesCol = app.ColorDerivatives;
            usersCol = app.ColorUsers;
            
            % define data case linestyles
            usersLinsty = {'-'; '-'; '-'; '-'; '-'; '-'};
            derivativesLinsty = {'-.'; ':'; ':'; ':'; ':'; ':';':'; ':'; ':'; ':'; ':'; ':';':'; ':'};
            
            % set color
            switch colorcode
                %Case: Show Derivatives
                case 0 
                    for ii=1:c
                        set(o(ii),'color',derivativesCol(ii,:), 'LineWidth', 3, 'LineStyle', derivativesLinsty{1});
                    end
                %Case: Derivatives for Users
                case 1
                    %Looking for the used derivatives
                    usedDerivatives = unique(app.user2Derivative(usersColorNumber,:));
                    if usedDerivatives(1) == 0  %Avoiding "0" error
                        usedDerivatives = usedDerivatives(2:end);
                    end
                    [~,numberUsedDerivatives] = size(usedDerivatives);
                    %Setting the right color
                    set(o(1),'color',usersCol(usersColorNumber,:), 'LineWidth', 3, 'LineStyle', usersLinsty{1});
                    for ii=1:numberUsedDerivatives
                        set(o(ii+1),'color',derivativesCol(usedDerivatives(ii),:), 'LineWidth', 2, 'LineStyle', derivativesLinsty{1});
                    end
                %Case: Users per Derivatives
                case 2
                    %Looking for the necessary derivatives for the user
                    logicalArray = app.user2Derivative == derivativesColorNumber;
                    logicalVector = sum(logicalArray,2);
                    numberUsersPerDerivative = nnz(logicalVector);
                    counter = 0;
                    userNumbers = zeros(numberUsersPerDerivative,1);
                    for int=1:size(logicalVector,1)
                        if logicalVector(int) ~= 0
                            counter = counter+1;
                            userNumbers(counter) = int;
                        end
                    end
                    set(o(numberUsersPerDerivative+1),'color',derivativesCol(derivativesColorNumber,:), 'LineWidth', 3, 'LineStyle', derivativesLinsty{1});
                    for ii=1:numberUsersPerDerivative
                        set(o(ii),'color',usersCol(userNumbers(ii),:), 'LineWidth', 2, 'LineStyle', usersLinsty{1});
                    end
                case 3
                    %Setting the colors of the Users
                    [userNumber,~] = size(app.userMatrix);
                    tickedUsers = zeros(nnz(usersColorNumber),1);
                    flag = 1;
                    for i=1:userNumber
                        if usersColorNumber(i) == 1
                            tickedUsers(flag) = i;
                            flag = flag + 1;
                        end
                    end
                    for ii=1:nnz(usersColorNumber)
                        set(o(ii),'color',usersCol(tickedUsers(ii),:), 'LineWidth', 3, 'LineStyle', usersLinsty{1});
                    end
                    %Setting the colors of the derivatives
                    [numberDerivatives,~] = size(app.derivativeSuggestion);
                    tickedDerivatives = zeros(nnz(derivativesColorNumber),1);
                    flag2 = 1;
                    for i=1:numberDerivatives
                        if derivativesColorNumber(i) == 1
                            tickedDerivatives(flag2) = i;
                            flag2 = flag2 + 1;
                        end
                    end
                    newStart = nnz(usersColorNumber)+1;
                    newEnd = newStart + nnz(derivativesColorNumber)-1;
                    flag3 = 1;
                    for ii=newStart:newEnd
                        set(o(ii),'color',derivativesCol(tickedDerivatives(flag3),:), 'LineWidth', 3, 'LineStyle', derivativesLinsty{1});
                        flag3 = flag3 + 1;
                    end
            end
            
            legend(o, leg,'Location','south','Orientation',"horizontal",'NumColumns',4,'FontName', 'Arial', 'fontsize',fontleg);
            hold on
            close
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %quick round function (to specified decimal)
        function [v] = rd(~,v,dec)
            if nargin == 1; dec = 0; end
            v = v / 10^dec;
            v = round(v);
            v = v * 10^dec;
        end
        %__________________________________________________________________

        %__________________________________________________________________
        %Activate the right graphs in Vehicles for Users view
        function ShowVehiclesperUserSpidergraphCall(app)
            selectedButton = app.SpidergraphSelectionButtonGroup.SelectedObject;
            if selectedButton == app.SecondaryActivitiesButton
                vehicles4UsersPlotChortcall(app,"Secondary Activities")
            elseif selectedButton == app.CharacterMobilityButton
                vehicles4UsersPlotChortcall(app,"Character/Mobility")
            end
            D4UVisibilityChanger(app)
            derivativesLegendEnabler(app,'DerivativeLamp%d_2','Derivative%dLampLabel','DerivativeType%d_2')
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Plotting the right graphs depending on userNumber and SpidergraphType
        function vehicles4UsersPlotChortcall(app,spidergraphType)
            numberUsers = size(app.userMatrix);
            for i=1:numberUsers
                %Changing the name of the panel title
                PanelV4UTitleChanger(app,spidergraphType,i)
                %Plotting the graphs
                spidergraphTypePlotV4U(app,spidergraphType,i)
            end
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Plotting spidergraph depending on the graph type choice
        function spidergraphTypePlotV4U(app,spidergraphType,userNumber)
            placement = [-1.1,1.1;-1.1,1.1];
            if spidergraphType == "Secondary Activities"
                [dataSec,lblSec,~,~,figure,leg] = vehicle4UserPlotsData(app,app.userMatrix,app.derivativeSuggestion,app.user2Derivative,userNumber);
                plotSpiderGraph(app,dataSec,lblSec,figure,leg,12,9,placement,1,userNumber,[])
                figure.Legend.Visible = 'off';
            elseif spidergraphType == "Character/Mobility"
                [~,~,dataCharMob,lblCharMob,figure,leg] = vehicle4UserPlotsData(app,app.userMatrix,app.derivativeSuggestion,app.user2Derivative,userNumber);
                plotSpiderGraph(app,dataCharMob,lblCharMob,figure,leg,12,9,placement,1,userNumber,[])
                figure.Legend.Visible = 'off';
            end
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Changing the title of the panels in Vehicles for Users view
        function PanelV4UTitleChanger(app,spidergraphType,panelNumber)
            %Defining the panelnames to control the panels
            panelName = sprintf('PanelV4U%d',panelNumber);
            %Preparing the names for the derivatives
            usedDerivatives = unique(app.user2Derivative(panelNumber,:));
            if usedDerivatives(1) == 0  %Avoiding "0" error
                usedDerivatives = usedDerivatives(2:end);
            end
            [~,numberUsedDerivatives] = size(usedDerivatives);
            derivativeNames = convertCharsToStrings(num2str(usedDerivatives(1)));
            for i=2:numberUsedDerivatives
                additionalName = convertCharsToStrings(num2str(usedDerivatives(i)));
                derivativeNames = append(derivativeNames,', ',additionalName);
            end
            %Set the panel titles and the main view title
            app.(panelName).Title = append('Derivative(s) ',derivativeNames,' for ',app.userNames(panelNumber));
            if spidergraphType == "Secondary Activities"
                app.DerivativesforUsersPanel.Title = "Derivatives for Users - Secondary Activities";
            elseif spidergraphType == "Character/Mobility"
                app.DerivativesforUsersPanel.Title = "Derivatives for Users - Character/Mobility";
            end
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Activate the right graphs in Users per Vehicle view
        function ShowUsersperVehicleSpidergraphCall(app)
            selectedButton = app.SpidergraphSelectionButtonGroup.SelectedObject;
            if selectedButton == app.SecondaryActivitiesButton
                usersPerVehiclePlotShortcall(app,"Secondary Activities")
            elseif selectedButton == app.CharacterMobilityButton
                usersPerVehiclePlotShortcall(app,"Character/Mobility")
            end
            UpDVisibilityChanger(app)
            userLegendEnabler(app)
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Plotting the right graphs depending on derivativeNumber and SpidergraphType
        function usersPerVehiclePlotShortcall(app,spidergraphType)
            [numberDerivatives,~] = size(app.derivativeSuggestion);
            selectedButton = app.DerivativeSelectionButtonGroup.SelectedObject;
            if selectedButton == app.V1_6
                if numberDerivatives < 7
                    drawnumber = numberDerivatives;
                else
                    drawnumber = 6;
                end
                for k=1:drawnumber
                    %Changing the name of the panel title
                    PanelUpDTitleChanger(app,spidergraphType,k,k)
                    %Plotting the graphs
                    spidergraphTypePlotUpV(app,spidergraphType,k,k)
                end
            elseif selectedButton == app.V7_12
                if numberDerivatives > 6 && numberDerivatives < 13
                    drawnumber = numberDerivatives;
                else
                    drawnumber = 12;
                end
                for k=7:drawnumber
                    reducedNumber = k-6;
                    %Changing the name of the panel title
                    PanelUpDTitleChanger(app,spidergraphType,reducedNumber,k)
                    %Plotting the graphs
                    spidergraphTypePlotUpV(app,spidergraphType,k,reducedNumber)
                end
            elseif selectedButton == app.V13_14
                if numberDerivatives > 12 && numberDerivatives < 19
                    drawnumber = numberDerivatives;
                else
                    drawnumber = 18;
                end
                for k=13:drawnumber
                    reducedNumber = k-12;
                    %Changing the name of the panel title
                    PanelUpDTitleChanger(app,spidergraphType,reducedNumber,k)
                    %Plotting the graphs
                    spidergraphTypePlotUpV(app,spidergraphType,k,reducedNumber)
                end
            end
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Plotting spidergraph depending on the graph type choice
        function spidergraphTypePlotUpV(app,spidergraphType,derivativeNumber,figureNumber)
            placement = [-1.1,1.1;-1.1,1.1];
            if spidergraphType == "Secondary Activities"
                [dataSec,lblSec,~,~,figure,leg] = UsersPerVehiclePlotsData(app,app.userMatrix,app.derivativeSuggestion,derivativeNumber,figureNumber);
                plotSpiderGraph(app,dataSec,lblSec,figure,leg,12,9,placement,2,[],derivativeNumber)
                figure.Legend.Visible = 'off';
            elseif spidergraphType == "Character/Mobility"
                [~,~,dataCharMob,lblCharMob,figure,leg] = UsersPerVehiclePlotsData(app,app.userMatrix,app.derivativeSuggestion,derivativeNumber,figureNumber);
                plotSpiderGraph(app,dataCharMob,lblCharMob,figure,leg,12,9,placement,2,[],derivativeNumber)
                figure.Legend.Visible = 'off';
            end
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Changing the title of the panels in User per Vehicle view
        function PanelUpDTitleChanger(app,spidergraphType,panelNumber,derivativeNumber)
            panelName = sprintf('PanelUpD%d',panelNumber);
            app.(panelName).Title = sprintf('Users per Derivative %d',derivativeNumber);
            if spidergraphType == "Secondary Activities"
                app.UsersperDerivativesPanel.Title = "Users per Derivatives - Secondary Activities";
            elseif spidergraphType == "Character/Mobility"
                app.UsersperDerivativesPanel.Title = "Users per Derivatives - Character/Mobility";
            end
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Automatically changes graph visibility depending on user Number 
        function D4UVisibilityChanger(app)
            [numberUsers,~] = size(app.userMatrix);
            for i=1:numberUsers
                graphName = sprintf('PanelV4U%d',i);
                app.(graphName).Visible = 'on';
            end
            if numberUsers < 6
                extendedNumber = numberUsers + 1;
                for k=extendedNumber:6
                    graphName = sprintf('PanelV4U%d',k);
                    app.(graphName).Visible = 'off';
                end
            end
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Automatically changes graph visibility depending on derivative number
        function UpDVisibilityChanger(app)
            [numberDerivatives,~] = size(app.derivativeSuggestion);
            selectedButton = app.DerivativeSelectionButtonGroup.SelectedObject;
            if selectedButton == app.V1_6
                if numberDerivatives < 7
                    correctedNumber = numberDerivatives;
                else
                    correctedNumber = 6;
                end
            elseif selectedButton == app.V7_12
                if numberDerivatives > 6 && numberDerivatives < 13
                    correctedNumber = numberDerivatives - 6;
                else
                    correctedNumber = 6;
                end
            elseif selectedButton == app.V13_14
                if numberDerivatives > 12 && numberDerivatives < 19
                    correctedNumber = numberDerivatives - 12;
                else
                    correctedNumber = 6;
                end
            end
            
            for i=1:correctedNumber
                graphName = sprintf('PanelUpD%d',i);
                app.(graphName).Visible = 'on';
            end
            
            if correctedNumber < 6
                extendedNumber = correctedNumber + 1;
                for k=extendedNumber:6
                    graphName = sprintf('PanelUpD%d',k);
                    app.(graphName).Visible = 'off';
                end
            end
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Determinates the visible legend entries for UpD
        function userLegendEnabler(app)
            [userNumber,~] = size(app.userMatrix);
            for i=1:userNumber
                fieldName = sprintf('UserLamp%d_2',i);
                app.(fieldName).Visible = 'on';
                labelName = sprintf('User%dLabel_3',i);
                app.(labelName).Visible = 'on';
                app.(labelName).Text = app.userNames(i);
            end
            if userNumber < 6
                extendedNumber = userNumber + 1;
                for k=extendedNumber:6
                    fieldName = sprintf('UserLamp%d_2',k);
                    app.(fieldName).Visible = 'off';
                    labelName = sprintf('User%dLabel_3',k);
                    app.(labelName).Visible = 'off';
                end
            end
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Determinates the visible legend entries for Derivative Provision
        %and V4U
        function derivativesLegendEnabler(app,lampNames,labelNames,fieldNames)
            [derivativeNumber,~] = size(app.derivativeSuggestion);
            for i=1:derivativeNumber
                lampName = sprintf(lampNames,i);
                app.(lampName).Visible = 'on';
                labelName = sprintf(labelNames,i);
                app.(labelName).Visible = 'on';
                fieldName = sprintf(fieldNames,i);
                app.(fieldName).Visible = 'on';
            end
            if derivativeNumber < 14
                extendedNumber = derivativeNumber + 1;
                for k=extendedNumber:14
                    lampName = sprintf(lampNames,k);
                    app.(lampName).Visible = 'off';
                    labelName = sprintf(labelNames,k);
                    app.(labelName).Visible = 'off';
                    fieldName = sprintf(fieldNames,k);
                    app.(fieldName).Visible = 'off';
                end
            end
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Determinates the visible Users/Derivatives for custom view
        function customViewBoxesEnabler(app,lampname,labelname,checkboxname,maxfields,threshold)
            %Enable the user check boxes
            for i=1:maxfields
                fieldName = sprintf(lampname,i);
                app.(fieldName).Visible = 'on';
                labelName = sprintf(labelname,i);
                app.(labelName).Visible = 'on';
                checkBoxName = sprintf(checkboxname,i);
                app.(checkBoxName).Visible = 'on';
                app.(checkBoxName).Enable = 'on';
            end
            if maxfields < threshold
                extendedNumber = maxfields + 1;
                for k=extendedNumber:threshold
                    fieldName = sprintf(lampname,k);
                    app.(fieldName).Visible = 'off';
                    labelName = sprintf(labelname,k);
                    app.(labelName).Visible = 'off';
                    checkBoxName = sprintf(checkboxname,k);
                    app.(checkBoxName).Visible = 'off';
                    app.(checkBoxName).Enable = 'off';
                end
            end
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Determinates the names of the users in the custom view
        function customViewUserNames(app)
            [userNumber,~] = size(app.userMatrix);
            for i=1:userNumber
                labelName = sprintf('User%dLabel_2',i);
                app.(labelName).Text = app.userNames(i);
                app.(labelName).Tooltip = app.userNames(i);
            end
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Determinates the visible Users/Derivatives for custom view
        function [tickMatrixUsers,tickMatrixDerivatives] = checkBoxChecker(app)
            %Check the ticked Users
            [userNumber,~] = size(app.userMatrix);
            tickMatrixUsers = zeros(userNumber,1);
            for i=1:userNumber
                checkBoxName = sprintf('CheckBox%d_2',i);
                if app.(checkBoxName).Value == true
                    tickMatrixUsers(i) = 1;
                else
                    tickMatrixUsers(i) = 0;
                end
            end
            [numberDerivatives,~] = size(app.derivativeSuggestion);
            tickMatrixDerivatives = zeros(numberDerivatives,1);
            for i=1:numberDerivatives
                checkBoxName = sprintf('CheckBox%d',i);
                if app.(checkBoxName).Value == true
                    tickMatrixDerivatives(i) = 1;
                else
                    tickMatrixDerivatives(i) = 0;
                end
            end
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Setting up the data for Custom view
        function [dataSec,lblSec,figureSec,dataCharMob,lblCharMob,figureCharMob,leg] = customViewData(app,tickMatrixUsers,tickMatrixDerivatives)
            %%Concatenating user matrix data and derivative suggestion data
            [userNumber,~] = size(app.userMatrix);
            [numberDerivatives,~] = size(app.derivativeSuggestion);
            %Data for Secondary Activities Users
            dataSecU = zeros(nnz(tickMatrixUsers),9);
            flag = 1;
            for i=1:userNumber
                if tickMatrixUsers(i) == 1
                    dataSecU(flag,:) = app.userMatrix(i,1:9);
                    flag = flag + 1;
                end
            end
            %Data for Secondary Activities Derivatives
            dataSecD = zeros(nnz(tickMatrixDerivatives),9);
            flag2 = 1;
            for k=1:numberDerivatives
                if tickMatrixDerivatives(k) == 1
                    dataSecD(flag2,1:6) = app.derivativeSuggestion(k,2:7);
                    flag2 = flag2 + 1;
                end
            end
            dataSecD(:,7:9) = [dataSecD(:,6),dataSecD(:,6),dataSecD(:,6)]; %Adding the passengers for the view compatibility with UM
            dataSec = [dataSecU;dataSecD]';
            
            %%Label for Secondary Activities
            lblSec = {'Self-Driving'; 'Relax'; 'Work'; 'Sleep'; 'Load'; 'Passengers for Self-Driving'; sprintf('Passengers \nfor Relax'); ...
                sprintf('Passengers \nfor Work'); sprintf('Passengers \nfor Sleep')};
            
            %%Figure or Secondary Activities
            figureSec = app.SecondaryActivitiesCustom;
            
            %%Data for Character and Mobility
            %Data for Character/Mobility Users
            dataCharMobU = zeros(nnz(tickMatrixUsers),6);
            flag = 1;
            for i=1:userNumber
                if tickMatrixUsers(i) == 1
                    dataCharMobU(flag,:) = app.userMatrix(i,11:16);
                    flag = flag + 1;
                end
            end
            %Data for Character/Mobility Derivatives
            dataCharMobD = zeros(nnz(tickMatrixDerivatives),6);
            flag2 = 1;
            for k=1:numberDerivatives
                if tickMatrixDerivatives(k) == 1
                    dataCharMobD(flag2,:) = app.derivativeSuggestion(k,11:16);
                    flag2 = flag2 + 1;
                end
            end
            dataCharMob = [dataCharMobU;dataCharMobD]';

            %%Label for Character/Mobility
            lblCharMob = {sprintf('Willingness to Invest'); sprintf('Need of \n   Security/Safety'); 'DP Urban'; 'DP Rural'; 'DP Highway'; 'DP General Need'};
            
            %%Figure or Secondary Activities
            figureCharMob = app.CharacterMobilityCustom;
            
            %%Legend entries (not needed)
            entriesNumber = flag + flag2 -2;
            leg = cell(1, entriesNumber);
            leg{1} = 'Placeholder';
            for i=2:entriesNumber
                k = i-1;
                leg{i}=sprintf('Placeholder %d',k);
            end
        end
        %__________________________________________________________________
        
        %__________________________________________________________________
        %Evaluating the color of the lamps
        function colorEvaluator(app,fieldType,number,LampName,checkboxName)
            value = app.(checkboxName).Value;
            derivativesCol = app.ColorDerivatives;
            usersCol = app.ColorUsers;
            if value == true
                %Evaluate color
                if fieldType == "User"
                    app.(LampName).Color = usersCol(number,:);
                else
                    app.(LampName).Color = derivativesCol(number,:);
                end
            else
                app.(LampName).Color = 'white';
            end
        end
        %__________________________________________________________________
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, userMatrix, userNames, userWish, derivativeSuggestion, derivativeSuggestionExtend, userFulfillment, user2derivative, user2derivativeExtend)
            %Adding necessary folders to path
            addpath('99_General_Elements\')
            addpath(genpath('03_Vehicle Provision\'))
            addpath(genpath('04_Simulation_VP2CRP\'))
            addpath(genpath('05_Customer-relevant Properties'))
            
            %Check if data in the workspace is available
            dataExistCheck = exist('derivativeSuggestion',"var");
            if dataExistCheck == 0
                app.DisplayOptionsPanel.Enable = 'off';
                app.SpidergraphSelectionButtonGroup.Enable = 'off';
                app.AddadditionalDerivativesCheckBox.Enable = 'off';
                app.ProceedandGenerateCustomerrelevantPropertiesButton.Enable = 'off';
                app.ProvidedDerivativesPanel.Enable = 'off';
                app.Onlyshowing14DerivativesoutofXXLabel.Visible = 'off';
                uiconfirm(app.MainScreenVP,'App was loaded with no dataset. Please load manually a predefined dataset with the "Load" Button',...
                    'No dataset chosen','Options',{'Ok'},'Icon','warning');
            else
                %Defining the input parameters
                app.userMatrix = userMatrix;
                app.userNames = userNames;
                app.userWish = userWish;
                app.derivativeSuggestion = derivativeSuggestion;
                app.derivativeSuggestionExtend = derivativeSuggestionExtend;
                app.userFulfillment = userFulfillment;
                app.user2Derivative = user2derivative;
                app.user2derivativeExtend = user2derivativeExtend;
                %Check for more than 14 derivatives
                [derivativeNumber,~] = size(app.derivativeSuggestion);
                fig = app.MainScreenVP;
                if derivativeNumber > 14
                    uiconfirm(fig,['The number of suggested Derivatives exceeds the possible display options (14 Derivatives).' ...
                        'The Derivatives will therefore be limited to the first 14 Derivatives.'],'Derivative Overflow','Options',{'Ok'},'Icon','warning');
                    app.derivativeSuggestionOverflow = app.derivativeSuggestion(15:derivativeNumber,:);
                    app.derivativeSuggestion = app.derivativeSuggestion(1:14,:);
                    app.user2DerivativeOverflow = app.user2Derivative;
                    app.user2Derivative(app.user2Derivative > 14) = 0;
                    %Enabling the derivative number message
                    app.Onlyshowing14DerivativesoutofXXLabel.Text = append('Only Showing 14 Derivatives out of ',num2str(derivativeNumber));
                    app.Onlyshowing14DerivativesoutofXXLabel.Visible = 'on';
                else
                    app.Onlyshowing14DerivativesoutofXXLabel.Visible = 'off';
                end
                
                %Show the first view
                ShowDerivativesButtonPushed(app)
            end
            
            %Set up the first view
            app.ProvidedDerivativesPanel.Visible = 'on';
            app.DerivativesforUsersPanel.Visible = 'off';
            app.UsersperDerivativesPanel.Visible = 'off';
            app.CustomViewPanel.Visible = 'off';
            app.SpidergraphSelectionButtonGroup.Enable = 'off';
            
            %Opening the window maximized
            drawnow;
            app.MainScreenVP.WindowState = 'maximized';
        end

        % Button pushed function: ShowDerivativesButton
        function ShowDerivativesButtonPushed(app, event)
            %Activating the view window
            app.ProvidedDerivativesPanel.Visible = 'on';
            app.DerivativesforUsersPanel.Visible = 'off';
            app.UsersperDerivativesPanel.Visible = 'off';
            app.CustomViewPanel.Visible = 'off';
            app.SpidergraphSelectionButtonGroup.Enable = 'off';
            
            %Changing the type names of the derivatives
            [derivativeNumber,~] = size(app.derivativeSuggestion);
            for i=1:derivativeNumber
                derivativeTypeEvaluation(app,app.derivativeSuggestion,i,'DerivativeType%d','DerivativeLamp%d')
            end
            derivativeTypeFiller(app,app.derivativeSuggestion,'DerivativeType%d','DerivativeLamp%d')
            derivativesLegendEnabler(app,'DerivativeLamp%d','Derivative%dLampLabel_2','DerivativeType%d')
            
            %Plotting the spider graphs
            [data1,figure1,lbl1,data2,figure2,lbl2,leg] = vehiclePlotsData(app,app.derivativeSuggestion);
            placement = [-1.4,1.4;-1.4,1.4];
            plotSpiderGraph(app,data1,lbl1,figure1,leg,16,10,placement,0,[],[])
            figure1.Legend.Visible = 'off';
            plotSpiderGraph(app,data2,lbl2,figure2,leg,16,10,placement,0,[],[])
            figure2.Legend.Visible = 'off';
        end

        % Button pushed function: ShowDerivativesforUsersButton
        function ShowDerivativesforUsersButtonPushed(app, event)
            %Activating the view window
            app.ProvidedDerivativesPanel.Visible = 'off';
            app.DerivativesforUsersPanel.Visible = 'on';
            app.UsersperDerivativesPanel.Visible = 'off';
            app.CustomViewPanel.Visible = 'off';
            app.SpidergraphSelectionButtonGroup.Enable = 'on';
            
            %Changing the type names of the derivatives
            [derivativeNumber,~] = size(app.derivativeSuggestion);
            for i=1:derivativeNumber
                derivativeTypeEvaluation(app,app.derivativeSuggestion,i,'DerivativeType%d_2','DerivativeLamp%d_2')
            end
            derivativeTypeFiller(app,app.derivativeSuggestion,'DerivativeType%d_2','DerivativeLamp%d_2')
            
            %Plotting the spider graphs
            ShowVehiclesperUserSpidergraphCall(app)
            
        end

        % Button pushed function: ShowUsersperDerivativesButton
        function ShowUsersperDerivativesButtonPushed(app, event)
            %Activating the view window
            app.ProvidedDerivativesPanel.Visible = 'off';
            app.DerivativesforUsersPanel.Visible = 'off';
            app.UsersperDerivativesPanel.Visible = 'on';
            app.CustomViewPanel.Visible = 'off';
            app.SpidergraphSelectionButtonGroup.Enable = 'on';
            
            %Enabling the right spider selection buttons
            [derivativeNumber,~] = size(app.derivativeSuggestion);
            if derivativeNumber < 7
                app.V7_12.Enable = 'off';
                app.V13_14.Enable = 'off';
            elseif derivativeNumber > 6 && derivativeNumber < 13
                app.V7_12.Enable = 'on';
                app.V13_14.Enable = 'off';
            elseif derivativeNumber > 12 && derivativeNumber < 19
                app.V7_12.Enable = 'on';
                app.V13_14.Enable = 'on';
            end
            
            %Plotting the spider graphs
            ShowUsersperVehicleSpidergraphCall(app)
            
        end

        % Button pushed function: CreateCustomViewButton
        function CreateCustomViewButtonPushed(app, event)
            %Activating the view window
            app.ProvidedDerivativesPanel.Visible = 'off';
            app.DerivativesforUsersPanel.Visible = 'off';
            app.UsersperDerivativesPanel.Visible = 'off';
            app.CustomViewPanel.Visible = 'on';
            app.SpidergraphSelectionButtonGroup.Enable = 'off';
            
            %Enable only the valid Checkboxes
            [userNumber,~] = size(app.userMatrix);
            customViewBoxesEnabler(app,'UserLamp%d','User%dLabel_2','CheckBox%d_2',userNumber,6)
            [numberDerivatives,~] = size(app.derivativeSuggestion);
            customViewBoxesEnabler(app,'DerivativeLamp%d_3','Derivative%dLampLabel_3','CheckBox%d',numberDerivatives,14)
            customViewUserNames(app)
            
        end

        % Value changed function: CheckBox1, CheckBox10, 
        % CheckBox11, CheckBox12, CheckBox13, CheckBox14, 
        % CheckBox2, CheckBox3, CheckBox4, CheckBox5, CheckBox6, 
        % CheckBox7, CheckBox8, CheckBox9
        function CheckBox1ValueChanged(app, event)
            [derivativeNumber,~] = size(app.derivativeSuggestion);
            for i=1:derivativeNumber
                lampName = sprintf('DerivativeLamp%d_3',i);
                checkboxName = sprintf('CheckBox%d',i);
                colorEvaluator(app,"Derivative",i,lampName,checkboxName)
            end
        end

        % Value changed function: CheckBox1_2, CheckBox2_2, 
        % CheckBox3_2, CheckBox4_2, CheckBox5_2, CheckBox6_2
        function CheckBox1_2ValueChanged(app, event)
            [userNumber,~] = size(app.userMatrix);
            for i=1:userNumber
                lampName = sprintf('UserLamp%d',i);
                checkboxName = sprintf('CheckBox%d_2',i);
                colorEvaluator(app,"User",i,lampName,checkboxName)
            end
        end

        % Button pushed function: TickAllButton
        function TickAllButtonPushed(app, event)
            [tickMatrixUsers,tickMatrixDerivatives] = checkBoxChecker(app);
            nonTickedUsers = nnz(~tickMatrixUsers);
            nonTickedDerivatives = nnz(~tickMatrixDerivatives);
            
            if nonTickedUsers==0 && nonTickedDerivatives==0
                %Setting all Derivatives false
                [derivativeNumber,~] = size(app.derivativeSuggestion);
                for i=1:derivativeNumber
                    boxName = sprintf('CheckBox%d',i);
                    app.(boxName).Value = false;
                    lampName = sprintf('DerivativeLamp%d_3',i);
                    colorEvaluator(app,"Derivative",i,lampName,boxName)
                end
                
                %Setting all Users false
                [userNumber,~] = size(app.userMatrix);
                for i=1:userNumber
                    checkboxName = sprintf('CheckBox%d_2',i);
                    app.(checkboxName).Value = false;
                    lampName = sprintf('UserLamp%d',i);
                    colorEvaluator(app,"User",i,lampName,checkboxName)
                end
            else
                %Setting all Derivatives true
                [derivativeNumber,~] = size(app.derivativeSuggestion);
                for i=1:derivativeNumber
                    boxName = sprintf('CheckBox%d',i);
                    app.(boxName).Value = true;
                    lampName = sprintf('DerivativeLamp%d_3',i);
                    colorEvaluator(app,"Derivative",i,lampName,boxName)
                end
                
                %Setting all Users true
                [userNumber,~] = size(app.userMatrix);
                for i=1:userNumber
                    checkboxName = sprintf('CheckBox%d_2',i);
                    app.(checkboxName).Value = true;
                    lampName = sprintf('UserLamp%d',i);
                    colorEvaluator(app,"User",i,lampName,checkboxName)
                end
            end
            
        end

        % Button pushed function: UpdateViewButton
        function UpdateViewButtonPushed(app, event)
            %Get the information about the ticked Checkboxes
            [tickMatrixUsers,tickMatrixDerivatives] = checkBoxChecker(app);
            [userNumber,~] = size(app.userMatrix);
            [numberDerivatives,~] = size(app.derivativeSuggestion);
            %Get data from only the ticked plots
            [dataSec,lblSec,figureSec,dataCharMob,lblCharMob,figureCharMob,leg] = customViewData(app,tickMatrixUsers,tickMatrixDerivatives);
            %Plot only the ticked elements
            placement = [-1.3,1.3;-1.3,1.3];
            tickTruthUM = sum(tickMatrixUsers == zeros(userNumber,1)); %Number of not ticked elements
            tickTruthD = sum(tickMatrixDerivatives == zeros(numberDerivatives,1)); %Number of not ticked elements
            if tickTruthUM == userNumber && tickTruthD == numberDerivatives %Catch the case that nothing is ticked
                dataSec = zeros(9,1);
                dataCharMob = zeros(6,1);
            end
            plotSpiderGraph(app,dataSec,lblSec,figureSec,leg,16,10,placement,3,tickMatrixUsers,tickMatrixDerivatives)
            figureSec.Legend.Visible = 'off';
            plotSpiderGraph(app,dataCharMob,lblCharMob,figureCharMob,leg,16,10,placement,3,tickMatrixUsers,tickMatrixDerivatives)
            figureCharMob.Legend.Visible = 'off';
        end

        % Selection changed function: 
        % SpidergraphSelectionButtonGroup
        function SpidergraphSelectionButtonGroupSelectionChanged(app, event)
            if app.UsersperDerivativesPanel.Visible == true
                ShowUsersperVehicleSpidergraphCall(app)
            elseif app.DerivativesforUsersPanel.Visible == true
                ShowVehiclesperUserSpidergraphCall(app)
                %Changing the type names of the derivatives
                [derivativeNumber,~] = size(app.derivativeSuggestion);
                for i=1:derivativeNumber
                    derivativeTypeEvaluation(app,app.derivativeSuggestion,i,'DerivativeType%d_2','DerivativeLamp%d_2')
                end
                derivativeTypeFiller(app,app.derivativeSuggestion,'DerivativeType%d_2','DerivativeLamp%d_2')
            end
        end

        % Selection changed function: DerivativeSelectionButtonGroup
        function DerivativeSelectionButtonGroupSelectionChanged(app, event)
            ShowUsersperVehicleSpidergraphCall(app)
        end

        % Value changed function: AddadditionalDerivativesCheckBox
        function AddadditionalDerivativesCheckBoxValueChanged(app, event)
            %Changing the dataset
            value = app.AddadditionalDerivativesCheckBox.Value;
            if value == true
                app.derivativeSuggestionBackup = app.derivativeSuggestion;
                app.derivativeSuggestion = app.derivativeSuggestionExtend;
                app.user2DerivativeBackup = app.user2Derivative;
                app.user2Derivative = app.user2derivativeExtend;
                %Check for more than 14 derivatives
                [derivativeNumber,~] = size(app.derivativeSuggestion);
                fig = app.MainScreenVP;
                if derivativeNumber > 14
                    uiconfirm(fig,['The number of suggested derivatives exceeds the possible display options (14 derivatives).' ...
                        'The derivatives will therefore be limited to the first 14 derivatives.'],'Derivative Overflow','Options',{'Ok'},'Icon','warning');
                    app.derivativeSuggestionOverflow = app.derivativeSuggestion(15:derivativeNumber,:);
                    app.derivativeSuggestion = app.derivativeSuggestion(1:14,:);
                    app.user2DerivativeOverflow = app.user2Derivative;
                    app.user2Derivative(app.user2Derivative > 14) = 0;
                    %Enabling the derivative number message
                    app.Onlyshowing14DerivativesoutofXXLabel.Text = append('Only Showing 14 Derivatives out of ',num2str(derivativeNumber));
                    app.Onlyshowing14DerivativesoutofXXLabel.Visible = 'on';
                else
                    app.Onlyshowing14DerivativesoutofXXLabel.Visible = 'off';
                end
            else
                app.derivativeSuggestion = app.derivativeSuggestionBackup;
                app.user2Derivative = app.user2DerivativeBackup;
                %Check for more than 14 derivatives
                [derivativeNumber,~] = size(app.derivativeSuggestion);
                fig = app.MainScreenVP;
                if derivativeNumber > 14
                    uiconfirm(fig,['The number of suggested derivatives exceeds the possible display options (14 derivatives).' ...
                        'The derivatives will therefore be limited to the first 14 derivatives.'],'Derivative Overflow','Options',{'Ok'},'Icon','warning');
                    app.derivativeSuggestionOverflow = app.derivativeSuggestion(15:derivativeNumber,:);
                    app.derivativeSuggestion = app.derivativeSuggestion(1:14,:);
                    app.user2DerivativeOverflow = app.user2Derivative;
                    app.user2Derivative(app.user2Derivative > 14) = 0;
                    %Enabling the derivative number message
                    app.Onlyshowing14DerivativesoutofXXLabel.Text = append('Only Showing 14 Derivatives out of ',num2str(derivativeNumber));
                    app.Onlyshowing14DerivativesoutofXXLabel.Visible = 'on';
                else
                    app.Onlyshowing14DerivativesoutofXXLabel.Visible = 'off';
                end
            end
            %Updating the view
            if app.ProvidedDerivativesPanel.Visible == true
                ShowDerivativesButtonPushed(app, event)
            elseif app.DerivativesforUsersPanel.Visible == true
                ShowDerivativesforUsersButtonPushed(app, event)
            elseif app.UsersperDerivativesPanel.Visible == true
                ShowUsersperDerivativesButtonPushed(app, event)
            elseif app.CustomViewPanel.Visible == true
                CreateCustomViewButtonPushed(app, event)
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            %Open the dialog box to choose .mat file
            filename = uigetfile('03_Vehicle Provision\VP_Saved_Scenarios\','*.mat');
            if filename ~= 0
                load(filename,'user_matrix','user_Names','user_wish','derivative_suggestion','derivative_suggestion_extend',...
                        'user_fulfillment','user2derivative','user2derivative_extend');
                %Defining the input parameters
                app.userMatrix = user_matrix;
                app.userNames = user_Names;
                app.userWish = user_wish;
                app.derivativeSuggestion = derivative_suggestion;
                app.derivativeSuggestionExtend = derivative_suggestion_extend;
                app.userFulfillment = user_fulfillment;
                app.user2Derivative = user2derivative;
                app.user2derivativeExtend = user2derivative_extend;
                %Check for more than 14 derivatives
                [derivativeNumber,~] = size(app.derivativeSuggestion);
                fig = app.MainScreenVP;
                if derivativeNumber > 14
                    uiconfirm(fig,['The number of suggested Derivatives exceeds the possible display options (14 Derivatives).' ...
                        'The Derivatives will therefore be limited to the first 14 Derivatives.'],'Derivative Overflow','Options',{'Ok'},'Icon','warning');
                    app.derivativeSuggestionOverflow = app.derivativeSuggestion(15:derivativeNumber,:);
                    app.derivativeSuggestion = app.derivativeSuggestion(1:14,:);
                    app.user2DerivativeOverflow = app.user2Derivative;
                    app.user2Derivative(app.user2Derivative > 14) = 0;
                    %Enabling the derivative number message
                    app.Onlyshowing14DerivativesoutofXXLabel.Text = append('Only Showing 14 Derivatives out of ',num2str(derivativeNumber));
                    app.Onlyshowing14DerivativesoutofXXLabel.Visible = 'on';
                else
                    app.Onlyshowing14DerivativesoutofXXLabel.Visible = 'off';
                end
                %Enabling the windows
                app.DisplayOptionsPanel.Enable = 'on';
                app.SpidergraphSelectionButtonGroup.Enable = 'on';
                app.AddadditionalDerivativesCheckBox.Enable = 'on';
                app.ProceedandGenerateCustomerrelevantPropertiesButton.Enable = 'on';
                app.ProvidedDerivativesPanel.Enable = 'on';
                %Preparing the view
                app.DerivativeSelectionButtonGroup.SelectedObject = app.V1_6;
                app.SpidergraphSelectionButtonGroup.SelectedObject = app.SecondaryActivitiesButton;
                app.AddadditionalDerivativesCheckBox.Value = false;
                %Showing the data
                ShowDerivativesButtonPushed(app, event)
            end
        end

        % Button pushed function: 
        % ProceedandGenerateCustomerrelevantPropertiesButton
        function ProceedandGenerateCustomerrelevantPropertiesButtonPushed(app, event)
            %Open a confirmation dialog box
            fig = app.MainScreenVP;
            selection = uiconfirm(fig,'Do you want to proceed and start the calculation?','Confirm calculation start','Icon','question');
            tf = strcmp(selection,'OK');
            %Execute only when the user agrees
            if tf == true
                [derivativeNumber,~] = size(app.derivativeSuggestion);
                %run the simulation as external function
                d = uiprogressdlg(fig,'Title','Please Wait - Calculation is running','Message',sprintf('Starting the calculation'));
                pause(1)
                optiOutput = zeros(derivativeNumber,28);
                for i = 1:derivativeNumber
                    %Updating processing window
                    d.Message = sprintf('Fuzzy Logic: \nDeterminating Customer-relevant Properties for Derivative %d',i);
                    optiInput = app.derivativeSuggestion(i,:);
                    optiOutput(i,:) = read_fuzzy_CRP(optiInput);
                    pause(2)
                    d.Message = sprintf('Customer-relevant Properties for Derivative %d determined',i);
                    d.Value = i/derivativeNumber;
                    pause(1)
                end
                
                %Scaling of the optimized derivatives
                optiOutput = (optiOutput.*0.6) + 4;
                derivativesCRP = optiOutput;
                
                %Adding number of Passengers wiht its correlation function
                passengerseats= app.derivativeSuggestion(:,7); k = 1.0463; x_0 = 4.5096;
                derivativesCRP(:,29) = (6./(1+exp(-k*(passengerseats-x_0))))+4;
                
                %Sorting the columns for grouping the CRPs
                Group1VD = derivativesCRP(:,1:4);
                Group2Erg = [derivativesCRP(:,8:12),derivativesCRP(:,24)];
                Group3Sec = [derivativesCRP(:,6),derivativesCRP(:,13),derivativesCRP(:,16),derivativesCRP(:,25:26)];
                Group4Day = [derivativesCRP(:,5),derivativesCRP(:,7),derivativesCRP(:,23),derivativesCRP(:,27:29)];
                Group5DS = derivativesCRP(:,17:20);
                Group6Lux = [derivativesCRP(:,14:15),derivativesCRP(:,22)];
                derivativesCRP = [Group1VD,Group2Erg,Group3Sec,Group4Day,Group5DS,Group6Lux];
                derivativeSuggestionCRP = app.derivativeSuggestion;
                
                %Preparing vector with types of derivatives
                typesNumbers = app.derivativeSuggestion(:,18);
                typeVector = string(zeros(1,derivativeNumber));
                for i=1:derivativeNumber
                    if typesNumbers(i,1) == 1
                        typeVector(i) = "Private";
                    elseif typesNumbers(i,1) == 2
                        typeVector(i) = "Taxi";
                    else
                        typeVector(i) = "Shuttle";
                    end
                end
                
                %Save the results of the simulation if the user agrees
                selection2 = uiconfirm(fig,'Do you want to save the results?','Saving Results','Icon','question');
                tf2 = strcmp(selection2,'OK');
                if tf2 == true
                    filename = uiputfile('*.mat','myScenario_CRP','05_Customer-relevant Properties\CRP_Saved_Scenarios\');
                    if filename ~= 0
                        newName = append('05_Customer-relevant Properties\CRP_Saved_Scenarios\',filename);
                        save(newName,'derivativesCRP','derivativeSuggestionCRP','typeVector')
                    end
                end
                
                %open the next app (CR Properties) and transfer data
                selection3 = uiconfirm(fig,'Do you want to open the Customer-relevant Properties app?','Open Customer-relevant Properties app','Icon','question');
                tf3 = strcmp(selection3,'OK');
                if tf3 == true
                    CustomerRelevantProperties(derivativesCRP,derivativeSuggestionCRP,typeVector)
                end
            end
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            %Open a confirmation dialog box
            fig = app.MainScreenVP;
            message = sprintf(['Do you want to return to the Home screen? \n\n'...
                'Consider saving their current results. Otherwise it will be deleted.']);
            selection = uiconfirm(fig,message,'Confirm return to Home screen','Icon','question');
            tf = strcmp(selection,'OK');
            %Execute only when the user agrees
            if tf == true
                Start;
                delete(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainScreenVP = uifigure('Visible', 'off');
            app.MainScreenVP.Name = 'Vehicle-Bound Mobility Provision';
            app.MainScreenVP.Icon = 'FTMLogo.PNG';
            app.Step12VehicleBoundMobilityProvisionLabel = uilabel(app.MainScreenVP);
            app.Step12VehicleBoundMobilityProvisionLabel.Text = '              Step 1.2: Vehicle-Bound Mobility Provision';
            app.MainMenuPanel = uipanel(app.MainScreenVP);
            app.MainMenuPanel.Title = 'Main Menu';
            app.DataOptionsPanel = uipanel(app.MainMenuPanel);
            app.DataOptionsPanel.Title = 'Data Options';
            app.LoadButton = uibutton(app.DataOptionsPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Tooltip = {'Loads saved calculation from .mat file.'; 'Notice: Only previously saved files can be openend!'};
            app.LoadButton.Text = 'Load';
            app.DisplayOptionsPanel = uipanel(app.MainMenuPanel);
            app.DisplayOptionsPanel.Title = 'Display Options';
            app.ShowDerivativesButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.ShowDerivativesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowDerivativesButtonPushed, true);
            app.ShowDerivativesButton.Tooltip = {'Displays all provided Derivatives.'};
            app.ShowDerivativesButton.Text = 'Show Derivatives';
            app.ShowDerivativesforUsersButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.ShowDerivativesforUsersButton.ButtonPushedFcn = createCallbackFcn(app, @ShowDerivativesforUsersButtonPushed, true);
            app.ShowDerivativesforUsersButton.Tooltip = {'Displays the suggested Derivatives for each User.'};
            app.ShowDerivativesforUsersButton.Text = 'Show Derivatives for Users';
            app.ShowUsersperDerivativesButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.ShowUsersperDerivativesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowUsersperDerivativesButtonPushed, true);
            app.ShowUsersperDerivativesButton.Tooltip = {'Displays the Users for each used Derivative.'};
            app.ShowUsersperDerivativesButton.Text = 'Show Users per Derivatives';
            app.CreateCustomViewButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.CreateCustomViewButton.ButtonPushedFcn = createCallbackFcn(app, @CreateCustomViewButtonPushed, true);
            app.CreateCustomViewButton.Tooltip = {'Create a custom view with chosen Derivatives and Users.'};
            app.CreateCustomViewButton.Text = 'Create Custom View';
            app.ProceedandGenerateCustomerrelevantPropertiesButton = uibutton(app.MainMenuPanel, 'push');
            app.ProceedandGenerateCustomerrelevantPropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedandGenerateCustomerrelevantPropertiesButtonPushed, true);
            app.ProceedandGenerateCustomerrelevantPropertiesButton.Tooltip = {'Proceed to GUI "Customer-Relevant Properties" by starting the calculation of the Customer-Relevant Properties for each Derivative.'};
            app.ProceedandGenerateCustomerrelevantPropertiesButton.Text = {'Proceed and Generate '; 'Customer-relevant Properties'};
            app.AddadditionalDerivativesCheckBox = uicheckbox(app.MainMenuPanel);
            app.AddadditionalDerivativesCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddadditionalDerivativesCheckBoxValueChanged, true);
            app.AddadditionalDerivativesCheckBox.Tooltip = {'Enables more suggested Derivatives.'};
            app.AddadditionalDerivativesCheckBox.Text = ' Add additional Derivatives?';
            app.SpidergraphSelectionButtonGroup = uibuttongroup(app.MainMenuPanel);
            app.SpidergraphSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SpidergraphSelectionButtonGroupSelectionChanged, true);
            app.SpidergraphSelectionButtonGroup.Title = 'Spidergraph Selection';
            app.SecondaryActivitiesButton = uitogglebutton(app.SpidergraphSelectionButtonGroup);
            app.SecondaryActivitiesButton.Tooltip = {'Displays the Secondary Activities parameters.'};
            app.SecondaryActivitiesButton.Text = 'Secondary Activities';
            app.SecondaryActivitiesButton.Value = true;
            app.CharacterMobilityButton = uitogglebutton(app.SpidergraphSelectionButtonGroup);
            app.CharacterMobilityButton.Tooltip = {'Displays the Character/Mobility parameters.'};
            app.CharacterMobilityButton.Text = 'Character/Mobility';
            app.Onlyshowing14DerivativesoutofXXLabel = uilabel(app.MainMenuPanel);
            app.Onlyshowing14DerivativesoutofXXLabel.Tooltip = {'Number of suggested Derivatives exceeds the display limits (14). Therefore only the first 14 Derivatives are shown.'};
            app.Onlyshowing14DerivativesoutofXXLabel.Text = 'Only showing 14 Derivatives out of XX';
            app.TUMlogo = uiimage(app.MainScreenVP);
            app.TUMlogo.ImageSource = '2015_Logo_TUM_neg_RGB.svg';
            app.HomeButton = uibutton(app.MainScreenVP, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Icon = 'Home.svg';
            app.HomeButton.Text = '';
            app.CustomViewPanel = uipanel(app.MainScreenVP);
            app.CustomViewPanel.Title = 'Custom View';
            app.SecondaryActivitiesPanel = uipanel(app.CustomViewPanel);
            app.SecondaryActivitiesPanel.Title = 'Secondary Activities';
            app.SecondaryActivitiesCustom = uiaxes(app.SecondaryActivitiesPanel);
            xlabel(app.SecondaryActivitiesCustom, 'X')
            ylabel(app.SecondaryActivitiesCustom, 'Y')
            zlabel(app.SecondaryActivitiesCustom, 'Z')
            app.CharacterMobilityPanel = uipanel(app.CustomViewPanel);
            app.CharacterMobilityPanel.Title = 'Character/Mobility';
            app.CharacterMobilityCustom = uiaxes(app.CharacterMobilityPanel);
            xlabel(app.CharacterMobilityCustom, 'X')
            ylabel(app.CharacterMobilityCustom, 'Y')
            zlabel(app.CharacterMobilityCustom, 'Z')
            app.DerivativesPanel = uipanel(app.CustomViewPanel);
            app.DerivativesPanel.Tooltip = {'Choose the Derivatives you want to view.'};
            app.DerivativesPanel.Title = 'Derivatives';
            app.DerivativeLamp1_3 = uilamp(app.DerivativesPanel);
            app.DerivativeLamp2_3 = uilamp(app.DerivativesPanel);
            app.DerivativeLamp3_3 = uilamp(app.DerivativesPanel);
            app.DerivativeLamp4_3 = uilamp(app.DerivativesPanel);
            app.DerivativeLamp5_3 = uilamp(app.DerivativesPanel);
            app.DerivativeLamp6_3 = uilamp(app.DerivativesPanel);
            app.DerivativeLamp7_3 = uilamp(app.DerivativesPanel);
            app.DerivativeLamp8_3 = uilamp(app.DerivativesPanel);
            app.DerivativeLamp9_3 = uilamp(app.DerivativesPanel);
            app.DerivativeLamp10_3 = uilamp(app.DerivativesPanel);
            app.DerivativeLamp11_3 = uilamp(app.DerivativesPanel);
            app.DerivativeLamp12_3 = uilamp(app.DerivativesPanel);
            app.DerivativeLamp13_3 = uilamp(app.DerivativesPanel);
            app.DerivativeLamp14_3 = uilamp(app.DerivativesPanel);
            app.Derivative1LampLabel_3 = uilabel(app.DerivativesPanel);
            app.Derivative1LampLabel_3.Text = 'Derivative 1';
            app.Derivative8LampLabel_3 = uilabel(app.DerivativesPanel);
            app.Derivative8LampLabel_3.Text = 'Derivative 8';
            app.Derivative2LampLabel_3 = uilabel(app.DerivativesPanel);
            app.Derivative2LampLabel_3.Text = 'Derivative 2';
            app.Derivative9LampLabel_3 = uilabel(app.DerivativesPanel);
            app.Derivative9LampLabel_3.Text = 'Derivative 9';
            app.Derivative3LampLabel_3 = uilabel(app.DerivativesPanel);
            app.Derivative3LampLabel_3.Text = 'Derivative 3';
            app.Derivative10LampLabel_3 = uilabel(app.DerivativesPanel);
            app.Derivative10LampLabel_3.Text = 'Derivative 10';
            app.Derivative4LampLabel_3 = uilabel(app.DerivativesPanel);
            app.Derivative4LampLabel_3.Text = 'Derivative 4';
            app.Derivative11LampLabel_3 = uilabel(app.DerivativesPanel);
            app.Derivative11LampLabel_3.Text = 'Derivative 11';
            app.Derivative5LampLabel_3 = uilabel(app.DerivativesPanel);
            app.Derivative5LampLabel_3.Text = 'Derivative 5';
            app.Derivative12LampLabel_3 = uilabel(app.DerivativesPanel);
            app.Derivative12LampLabel_3.Text = 'Derivative 12';
            app.Derivative6LampLabel_3 = uilabel(app.DerivativesPanel);
            app.Derivative6LampLabel_3.Text = 'Derivative 6';
            app.Derivative13LampLabel_3 = uilabel(app.DerivativesPanel);
            app.Derivative13LampLabel_3.Text = 'Derivative 13';
            app.Derivative7LampLabel_3 = uilabel(app.DerivativesPanel);
            app.Derivative7LampLabel_3.Text = 'Derivative 7';
            app.Derivative14LampLabel_3 = uilabel(app.DerivativesPanel);
            app.Derivative14LampLabel_3.Text = 'Derivative 14';
            app.CheckBox1 = uicheckbox(app.DerivativesPanel);
            app.CheckBox1.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox1.Text = '';
            app.CheckBox2 = uicheckbox(app.DerivativesPanel);
            app.CheckBox2.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox2.Text = '';
            app.CheckBox3 = uicheckbox(app.DerivativesPanel);
            app.CheckBox3.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox3.Text = '';
            app.CheckBox4 = uicheckbox(app.DerivativesPanel);
            app.CheckBox4.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox4.Text = '';
            app.CheckBox5 = uicheckbox(app.DerivativesPanel);
            app.CheckBox5.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox5.Text = '';
            app.CheckBox6 = uicheckbox(app.DerivativesPanel);
            app.CheckBox6.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox6.Text = '';
            app.CheckBox7 = uicheckbox(app.DerivativesPanel);
            app.CheckBox7.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox7.Text = '';
            app.CheckBox8 = uicheckbox(app.DerivativesPanel);
            app.CheckBox8.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox8.Text = '';
            app.CheckBox9 = uicheckbox(app.DerivativesPanel);
            app.CheckBox9.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox9.Text = '';
            app.CheckBox10 = uicheckbox(app.DerivativesPanel);
            app.CheckBox10.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox10.Text = '';
            app.CheckBox11 = uicheckbox(app.DerivativesPanel);
            app.CheckBox11.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox11.Text = '';
            app.CheckBox12 = uicheckbox(app.DerivativesPanel);
            app.CheckBox12.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox12.Text = '';
            app.CheckBox13 = uicheckbox(app.DerivativesPanel);
            app.CheckBox13.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox13.Text = '';
            app.CheckBox14 = uicheckbox(app.DerivativesPanel);
            app.CheckBox14.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox14.Text = '';
            app.UsersPanel = uipanel(app.CustomViewPanel);
            app.UsersPanel.Tooltip = {'Choose the users you want to view.'};
            app.UsersPanel.Title = 'Users';
            app.User1Label_2 = uilabel(app.UsersPanel);
            app.User1Label_2.Text = 'User 1';
            app.User4Label_2 = uilabel(app.UsersPanel);
            app.User4Label_2.Text = 'User 4';
            app.User2Label_2 = uilabel(app.UsersPanel);
            app.User2Label_2.Text = 'User 2';
            app.User5Label_2 = uilabel(app.UsersPanel);
            app.User5Label_2.Text = 'User 5';
            app.User3Label_2 = uilabel(app.UsersPanel);
            app.User3Label_2.Text = 'User 3';
            app.User6Label_2 = uilabel(app.UsersPanel);
            app.User6Label_2.Text = 'User 6';
            app.CheckBox1_2 = uicheckbox(app.UsersPanel);
            app.CheckBox1_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox1_2ValueChanged, true);
            app.CheckBox1_2.Text = '';
            app.CheckBox2_2 = uicheckbox(app.UsersPanel);
            app.CheckBox2_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox1_2ValueChanged, true);
            app.CheckBox2_2.Text = '';
            app.CheckBox3_2 = uicheckbox(app.UsersPanel);
            app.CheckBox3_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox1_2ValueChanged, true);
            app.CheckBox3_2.Text = '';
            app.CheckBox4_2 = uicheckbox(app.UsersPanel);
            app.CheckBox4_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox1_2ValueChanged, true);
            app.CheckBox4_2.Text = '';
            app.CheckBox5_2 = uicheckbox(app.UsersPanel);
            app.CheckBox5_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox1_2ValueChanged, true);
            app.CheckBox5_2.Text = '';
            app.CheckBox6_2 = uicheckbox(app.UsersPanel);
            app.CheckBox6_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox1_2ValueChanged, true);
            app.CheckBox6_2.Text = '';
            app.UserLamp1 = uilamp(app.UsersPanel);
            app.UserLamp2 = uilamp(app.UsersPanel);
            app.UserLamp3 = uilamp(app.UsersPanel);
            app.UserLamp4 = uilamp(app.UsersPanel);
            app.UserLamp5 = uilamp(app.UsersPanel);
            app.UserLamp6 = uilamp(app.UsersPanel);
            app.UpdateViewButton = uibutton(app.CustomViewPanel, 'push');
            app.UpdateViewButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateViewButtonPushed, true);
            app.UpdateViewButton.Tooltip = {'Updates the custom view.'};
            app.UpdateViewButton.Text = 'Update View';
            app.TickAllButton = uibutton(app.CustomViewPanel, 'push');
            app.TickAllButton.ButtonPushedFcn = createCallbackFcn(app, @TickAllButtonPushed, true);
            app.TickAllButton.Tooltip = {'Ticks all available Derivatives and Users.'};
            app.TickAllButton.Text = 'Tick All';
            app.UsersperDerivativesPanel = uipanel(app.MainScreenVP);
            app.UsersperDerivativesPanel.Title = 'Users per Derivatives';
            app.UsersPanel_2 = uipanel(app.UsersperDerivativesPanel);
            app.UsersPanel_2.Title = 'Users';
            app.User1Label_3 = uilabel(app.UsersPanel_2);
            app.User1Label_3.Text = 'User 1';
            app.UserLamp1_2 = uilamp(app.UsersPanel_2);
            app.User2Label_3 = uilabel(app.UsersPanel_2);
            app.User2Label_3.Text = 'User 2';
            app.UserLamp2_2 = uilamp(app.UsersPanel_2);
            app.User3Label_3 = uilabel(app.UsersPanel_2);
            app.User3Label_3.Text = 'User 3';
            app.UserLamp3_2 = uilamp(app.UsersPanel_2);
            app.User4Label_3 = uilabel(app.UsersPanel_2);
            app.User4Label_3.Text = 'User 4';
            app.UserLamp4_2 = uilamp(app.UsersPanel_2);
            app.User5Label_3 = uilabel(app.UsersPanel_2);
            app.User5Label_3.Text = 'User 5';
            app.UserLamp5_2 = uilamp(app.UsersPanel_2);
            app.User6Label_3 = uilabel(app.UsersPanel_2);
            app.User6Label_3.Text = 'User 6';
            app.UserLamp6_2 = uilamp(app.UsersPanel_2);
            app.DerivativeSelectionButtonGroup = uibuttongroup(app.UsersperDerivativesPanel);
            app.DerivativeSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DerivativeSelectionButtonGroupSelectionChanged, true);
            app.DerivativeSelectionButtonGroup.Tooltip = {'Select the visible Derivatives.'};
            app.DerivativeSelectionButtonGroup.Title = 'Derivative Selection';
            app.V1_6 = uitogglebutton(app.DerivativeSelectionButtonGroup);
            app.V1_6.Text = '1-6';
            app.V1_6.Value = true;
            app.V7_12 = uitogglebutton(app.DerivativeSelectionButtonGroup);
            app.V7_12.Text = '7-12';
            app.V13_14 = uitogglebutton(app.DerivativeSelectionButtonGroup);
            app.V13_14.Text = '13-14';
            app.PanelUpD1 = uipanel(app.UsersperDerivativesPanel);
            app.PanelUpD1.Title = 'Users per Derivative 1';
            app.SpiderUpD1 = uiaxes(app.PanelUpD1);
            xlabel(app.SpiderUpD1, 'X')
            ylabel(app.SpiderUpD1, 'Y')
            zlabel(app.SpiderUpD1, 'Z')
            app.PanelUpD2 = uipanel(app.UsersperDerivativesPanel);
            app.PanelUpD2.Title = 'Users per Derivative 2';
            app.SpiderUpD2 = uiaxes(app.PanelUpD2);
            xlabel(app.SpiderUpD2, 'X')
            ylabel(app.SpiderUpD2, 'Y')
            zlabel(app.SpiderUpD2, 'Z')
            app.PanelUpD3 = uipanel(app.UsersperDerivativesPanel);
            app.PanelUpD3.Title = 'Users per Derivative 3';
            app.SpiderUpD3 = uiaxes(app.PanelUpD3);
            xlabel(app.SpiderUpD3, 'X')
            ylabel(app.SpiderUpD3, 'Y')
            zlabel(app.SpiderUpD3, 'Z')
            app.PanelUpD4 = uipanel(app.UsersperDerivativesPanel);
            app.PanelUpD4.Title = 'Users per Derivative 4';
            app.SpiderUpD4 = uiaxes(app.PanelUpD4);
            xlabel(app.SpiderUpD4, 'X')
            ylabel(app.SpiderUpD4, 'Y')
            zlabel(app.SpiderUpD4, 'Z')
            app.PanelUpD5 = uipanel(app.UsersperDerivativesPanel);
            app.PanelUpD5.Title = 'Users per Derivative 5';
            app.SpiderUpD5 = uiaxes(app.PanelUpD5);
            xlabel(app.SpiderUpD5, 'X')
            ylabel(app.SpiderUpD5, 'Y')
            zlabel(app.SpiderUpD5, 'Z')
            app.PanelUpD6 = uipanel(app.UsersperDerivativesPanel);
            app.PanelUpD6.Title = 'Users per Derivative 6';
            app.SpiderUpD6 = uiaxes(app.PanelUpD6);
            xlabel(app.SpiderUpD6, 'X')
            ylabel(app.SpiderUpD6, 'Y')
            zlabel(app.SpiderUpD6, 'Z')
            app.DerivativesforUsersPanel = uipanel(app.MainScreenVP);
            app.DerivativesforUsersPanel.Title = 'Derivatives for Users';
            app.PanelV4U1 = uipanel(app.DerivativesforUsersPanel);
            app.PanelV4U1.Title = 'Derivatives for User 1';
            app.SpiderV4U1 = uiaxes(app.PanelV4U1);
            xlabel(app.SpiderV4U1, 'X')
            ylabel(app.SpiderV4U1, 'Y')
            zlabel(app.SpiderV4U1, 'Z')
            app.PanelV4U2 = uipanel(app.DerivativesforUsersPanel);
            app.PanelV4U2.Title = 'Derivatives for User 2';
            app.SpiderV4U2 = uiaxes(app.PanelV4U2);
            xlabel(app.SpiderV4U2, 'X')
            ylabel(app.SpiderV4U2, 'Y')
            zlabel(app.SpiderV4U2, 'Z')
            app.PanelV4U3 = uipanel(app.DerivativesforUsersPanel);
            app.PanelV4U3.Title = 'Derivatives for User 3';
            app.SpiderV4U3 = uiaxes(app.PanelV4U3);
            xlabel(app.SpiderV4U3, 'X')
            ylabel(app.SpiderV4U3, 'Y')
            zlabel(app.SpiderV4U3, 'Z')
            app.PanelV4U4 = uipanel(app.DerivativesforUsersPanel);
            app.PanelV4U4.Title = 'Derivatives for User 4';
            app.SpiderV4U4 = uiaxes(app.PanelV4U4);
            xlabel(app.SpiderV4U4, 'X')
            ylabel(app.SpiderV4U4, 'Y')
            zlabel(app.SpiderV4U4, 'Z')
            app.PanelV4U5 = uipanel(app.DerivativesforUsersPanel);
            app.PanelV4U5.Title = 'Derivatives for User 5';
            app.SpiderV4U5 = uiaxes(app.PanelV4U5);
            xlabel(app.SpiderV4U5, 'X')
            ylabel(app.SpiderV4U5, 'Y')
            zlabel(app.SpiderV4U5, 'Z')
            app.PanelV4U6 = uipanel(app.DerivativesforUsersPanel);
            app.PanelV4U6.Title = 'Derivatives for User 6';
            app.SpiderV4U6 = uiaxes(app.PanelV4U6);
            xlabel(app.SpiderV4U6, 'X')
            ylabel(app.SpiderV4U6, 'Y')
            zlabel(app.SpiderV4U6, 'Z')
            app.DerivativetypesPanel = uipanel(app.DerivativesforUsersPanel);
            app.DerivativetypesPanel.Title = 'Derivative types';
            app.DerivativeType1_2 = uieditfield(app.DerivativetypesPanel, 'text');
            app.DerivativeType1_2.Editable = 'off';
            app.DerivativeType1_2.Value = 'not needed';
            app.DerivativeType2_2 = uieditfield(app.DerivativetypesPanel, 'text');
            app.DerivativeType2_2.Editable = 'off';
            app.DerivativeType2_2.Value = 'not needed';
            app.DerivativeType3_2 = uieditfield(app.DerivativetypesPanel, 'text');
            app.DerivativeType3_2.Editable = 'off';
            app.DerivativeType3_2.Value = 'not needed';
            app.DerivativeType4_2 = uieditfield(app.DerivativetypesPanel, 'text');
            app.DerivativeType4_2.Editable = 'off';
            app.DerivativeType4_2.Value = 'not needed';
            app.DerivativeType5_2 = uieditfield(app.DerivativetypesPanel, 'text');
            app.DerivativeType5_2.Editable = 'off';
            app.DerivativeType5_2.Value = 'not needed';
            app.DerivativeType6_2 = uieditfield(app.DerivativetypesPanel, 'text');
            app.DerivativeType6_2.Editable = 'off';
            app.DerivativeType6_2.Value = 'not needed';
            app.DerivativeType7_2 = uieditfield(app.DerivativetypesPanel, 'text');
            app.DerivativeType7_2.Editable = 'off';
            app.DerivativeType7_2.Value = 'not needed';
            app.DerivativeType8_2 = uieditfield(app.DerivativetypesPanel, 'text');
            app.DerivativeType8_2.Editable = 'off';
            app.DerivativeType8_2.Value = 'not needed';
            app.DerivativeType9_2 = uieditfield(app.DerivativetypesPanel, 'text');
            app.DerivativeType9_2.Editable = 'off';
            app.DerivativeType9_2.Value = 'not needed';
            app.DerivativeType10_2 = uieditfield(app.DerivativetypesPanel, 'text');
            app.DerivativeType10_2.Editable = 'off';
            app.DerivativeType10_2.Value = 'not needed';
            app.DerivativeType11_2 = uieditfield(app.DerivativetypesPanel, 'text');
            app.DerivativeType11_2.Editable = 'off';
            app.DerivativeType11_2.Value = 'not needed';
            app.DerivativeType12_2 = uieditfield(app.DerivativetypesPanel, 'text');
            app.DerivativeType12_2.Editable = 'off';
            app.DerivativeType12_2.Value = 'not needed';
            app.DerivativeType13_2 = uieditfield(app.DerivativetypesPanel, 'text');
            app.DerivativeType13_2.Editable = 'off';
            app.DerivativeType13_2.Value = 'not needed';
            app.DerivativeType14_2 = uieditfield(app.DerivativetypesPanel, 'text');
            app.DerivativeType14_2.Editable = 'off';
            app.DerivativeType14_2.Value = 'not needed';
            app.Derivative1LampLabel = uilabel(app.DerivativetypesPanel);
            app.Derivative1LampLabel.Text = 'Derivative 1';
            app.Derivative8LampLabel = uilabel(app.DerivativetypesPanel);
            app.Derivative8LampLabel.Text = 'Derivative 8';
            app.Derivative2LampLabel = uilabel(app.DerivativetypesPanel);
            app.Derivative2LampLabel.Text = 'Derivative 2';
            app.Derivative9LampLabel = uilabel(app.DerivativetypesPanel);
            app.Derivative9LampLabel.Text = 'Derivative 9';
            app.Derivative3LampLabel = uilabel(app.DerivativetypesPanel);
            app.Derivative3LampLabel.Text = 'Derivative 3';
            app.Derivative10LampLabel = uilabel(app.DerivativetypesPanel);
            app.Derivative10LampLabel.Text = 'Derivative 10';
            app.Derivative4LampLabel = uilabel(app.DerivativetypesPanel);
            app.Derivative4LampLabel.Text = 'Derivative 4';
            app.Derivative11LampLabel = uilabel(app.DerivativetypesPanel);
            app.Derivative11LampLabel.Text = 'Derivative 11';
            app.Derivative5LampLabel = uilabel(app.DerivativetypesPanel);
            app.Derivative5LampLabel.Text = 'Derivative 5';
            app.Derivative12LampLabel = uilabel(app.DerivativetypesPanel);
            app.Derivative12LampLabel.Text = 'Derivative 12';
            app.Derivative6LampLabel = uilabel(app.DerivativetypesPanel);
            app.Derivative6LampLabel.Text = 'Derivative 6';
            app.Derivative13LampLabel = uilabel(app.DerivativetypesPanel);
            app.Derivative13LampLabel.Text = 'Derivative 13';
            app.Derivative7LampLabel = uilabel(app.DerivativetypesPanel);
            app.Derivative7LampLabel.Text = 'Derivative 7';
            app.Derivative14LampLabel = uilabel(app.DerivativetypesPanel);
            app.Derivative14LampLabel.Text = 'Derivative 14';
            app.DerivativeLamp1_2 = uilamp(app.DerivativetypesPanel);
            app.DerivativeLamp2_2 = uilamp(app.DerivativetypesPanel);
            app.DerivativeLamp3_2 = uilamp(app.DerivativetypesPanel);
            app.DerivativeLamp4_2 = uilamp(app.DerivativetypesPanel);
            app.DerivativeLamp5_2 = uilamp(app.DerivativetypesPanel);
            app.DerivativeLamp6_2 = uilamp(app.DerivativetypesPanel);
            app.DerivativeLamp7_2 = uilamp(app.DerivativetypesPanel);
            app.DerivativeLamp8_2 = uilamp(app.DerivativetypesPanel);
            app.DerivativeLamp9_2 = uilamp(app.DerivativetypesPanel);
            app.DerivativeLamp10_2 = uilamp(app.DerivativetypesPanel);
            app.DerivativeLamp11_2 = uilamp(app.DerivativetypesPanel);
            app.DerivativeLamp12_2 = uilamp(app.DerivativetypesPanel);
            app.DerivativeLamp13_2 = uilamp(app.DerivativetypesPanel);
            app.DerivativeLamp14_2 = uilamp(app.DerivativetypesPanel);
            app.ProvidedDerivativesPanel = uipanel(app.MainScreenVP);
            app.ProvidedDerivativesPanel.Title = 'Provided Derivatives';
            app.ProvidedSecondaryActivitiesoftheDerivativesPanel = uipanel(app.ProvidedDerivativesPanel);
            app.ProvidedSecondaryActivitiesoftheDerivativesPanel.Title = 'Provided Secondary Activities of the Derivatives';
            app.VehiclesSecondaryActivities = uiaxes(app.ProvidedSecondaryActivitiesoftheDerivativesPanel);
            xlabel(app.VehiclesSecondaryActivities, 'X')
            ylabel(app.VehiclesSecondaryActivities, 'Y')
            zlabel(app.VehiclesSecondaryActivities, 'Z')
            app.ProvidedCharacterandMobilityBehavioroftheDerivativesPanel = uipanel(app.ProvidedDerivativesPanel);
            app.ProvidedCharacterandMobilityBehavioroftheDerivativesPanel.Title = 'Provided Character and Mobility Behavior of the Derivatives';
            app.VehiclesCharacterMobility = uiaxes(app.ProvidedCharacterandMobilityBehavioroftheDerivativesPanel);
            xlabel(app.VehiclesCharacterMobility, 'X')
            ylabel(app.VehiclesCharacterMobility, 'Y')
            zlabel(app.VehiclesCharacterMobility, 'Z')
            app.DerivativetypesPanel_2 = uipanel(app.ProvidedDerivativesPanel);
            app.DerivativetypesPanel_2.Title = 'Derivative types';
            app.DerivativeType1 = uieditfield(app.DerivativetypesPanel_2, 'text');
            app.DerivativeType1.Editable = 'off';
            app.DerivativeType1.Value = 'not needed';
            app.DerivativeType2 = uieditfield(app.DerivativetypesPanel_2, 'text');
            app.DerivativeType2.Editable = 'off';
            app.DerivativeType2.Value = 'not needed';
            app.DerivativeType3 = uieditfield(app.DerivativetypesPanel_2, 'text');
            app.DerivativeType3.Editable = 'off';
            app.DerivativeType3.Value = 'not needed';
            app.DerivativeType4 = uieditfield(app.DerivativetypesPanel_2, 'text');
            app.DerivativeType4.Editable = 'off';
            app.DerivativeType4.Value = 'not needed';
            app.DerivativeType5 = uieditfield(app.DerivativetypesPanel_2, 'text');
            app.DerivativeType5.Editable = 'off';
            app.DerivativeType5.Value = 'not needed';
            app.DerivativeType6 = uieditfield(app.DerivativetypesPanel_2, 'text');
            app.DerivativeType6.Editable = 'off';
            app.DerivativeType6.Value = 'not needed';
            app.DerivativeType7 = uieditfield(app.DerivativetypesPanel_2, 'text');
            app.DerivativeType7.Editable = 'off';
            app.DerivativeType7.Value = 'not needed';
            app.DerivativeType8 = uieditfield(app.DerivativetypesPanel_2, 'text');
            app.DerivativeType8.Editable = 'off';
            app.DerivativeType8.Value = 'not needed';
            app.DerivativeType9 = uieditfield(app.DerivativetypesPanel_2, 'text');
            app.DerivativeType9.Editable = 'off';
            app.DerivativeType9.Value = 'not needed';
            app.DerivativeType10 = uieditfield(app.DerivativetypesPanel_2, 'text');
            app.DerivativeType10.Editable = 'off';
            app.DerivativeType10.Value = 'not needed';
            app.DerivativeType11 = uieditfield(app.DerivativetypesPanel_2, 'text');
            app.DerivativeType11.Editable = 'off';
            app.DerivativeType11.Value = 'not needed';
            app.DerivativeType12 = uieditfield(app.DerivativetypesPanel_2, 'text');
            app.DerivativeType12.Editable = 'off';
            app.DerivativeType12.Value = 'not needed';
            app.DerivativeType13 = uieditfield(app.DerivativetypesPanel_2, 'text');
            app.DerivativeType13.Editable = 'off';
            app.DerivativeType13.Value = 'not needed';
            app.DerivativeType14 = uieditfield(app.DerivativetypesPanel_2, 'text');
            app.DerivativeType14.Editable = 'off';
            app.DerivativeType14.Value = 'not needed';
            app.Derivative1LampLabel_2 = uilabel(app.DerivativetypesPanel_2);
            app.Derivative1LampLabel_2.Text = 'Derivative 1';
            app.Derivative8LampLabel_2 = uilabel(app.DerivativetypesPanel_2);
            app.Derivative8LampLabel_2.Text = 'Derivative 8';
            app.Derivative2LampLabel_2 = uilabel(app.DerivativetypesPanel_2);
            app.Derivative2LampLabel_2.Text = 'Derivative 2';
            app.Derivative9LampLabel_2 = uilabel(app.DerivativetypesPanel_2);
            app.Derivative9LampLabel_2.Text = 'Derivative 9';
            app.Derivative3LampLabel_2 = uilabel(app.DerivativetypesPanel_2);
            app.Derivative3LampLabel_2.Text = 'Derivative 3';
            app.Derivative10LampLabel_2 = uilabel(app.DerivativetypesPanel_2);
            app.Derivative10LampLabel_2.Text = 'Derivative 10';
            app.Derivative4LampLabel_2 = uilabel(app.DerivativetypesPanel_2);
            app.Derivative4LampLabel_2.Text = 'Derivative 4';
            app.Derivative11LampLabel_2 = uilabel(app.DerivativetypesPanel_2);
            app.Derivative11LampLabel_2.Text = 'Derivative 11';
            app.Derivative5LampLabel_2 = uilabel(app.DerivativetypesPanel_2);
            app.Derivative5LampLabel_2.Text = 'Derivative 5';
            app.Derivative12LampLabel_2 = uilabel(app.DerivativetypesPanel_2);
            app.Derivative12LampLabel_2.Text = 'Derivative 12';
            app.Derivative6LampLabel_2 = uilabel(app.DerivativetypesPanel_2);
            app.Derivative6LampLabel_2.Text = 'Derivative 6';
            app.Derivative13LampLabel_2 = uilabel(app.DerivativetypesPanel_2);
            app.Derivative13LampLabel_2.Text = 'Derivative 13';
            app.Derivative7LampLabel_2 = uilabel(app.DerivativetypesPanel_2);
            app.Derivative7LampLabel_2.Text = 'Derivative 7';
            app.Derivative14LampLabel_2 = uilabel(app.DerivativetypesPanel_2);
            app.Derivative14LampLabel_2.Text = 'Derivative 14';
            app.DerivativeLamp1 = uilamp(app.DerivativetypesPanel_2);
            app.DerivativeLamp2 = uilamp(app.DerivativetypesPanel_2);
            app.DerivativeLamp3 = uilamp(app.DerivativetypesPanel_2);
            app.DerivativeLamp4 = uilamp(app.DerivativetypesPanel_2);
            app.DerivativeLamp5 = uilamp(app.DerivativetypesPanel_2);
            app.DerivativeLamp6 = uilamp(app.DerivativetypesPanel_2);
            app.DerivativeLamp7 = uilamp(app.DerivativetypesPanel_2);
            app.DerivativeLamp8 = uilamp(app.DerivativetypesPanel_2);
            app.DerivativeLamp9 = uilamp(app.DerivativetypesPanel_2);
            app.DerivativeLamp10 = uilamp(app.DerivativetypesPanel_2);
            app.DerivativeLamp11 = uilamp(app.DerivativetypesPanel_2);
            app.DerivativeLamp12 = uilamp(app.DerivativetypesPanel_2);
            app.DerivativeLamp13 = uilamp(app.DerivativetypesPanel_2);
            app.DerivativeLamp14 = uilamp(app.DerivativetypesPanel_2);
            % Show the figure after all components are created
            app.MainScreenVP.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VehicleProvision(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainScreenVP)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainScreenVP)
        end
    end
end